classdef Segmentation3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImagesegmentationUIFigure       matlab.ui.Figure
        Label2_8                        matlab.ui.control.Label
        SortpersizeRandomizeconnectivityLabel  matlab.ui.control.Label
        ToDoCalculationsgreylevelCalculationssegmenteddataLabel  matlab.ui.control.Label
        MainTabGroup                    matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ImageswillbesavedinSavefolderFilenameWorkflownameLabel  matlab.ui.control.Label
        DatatypeDropDown                matlab.ui.control.DropDown
        DatatypeDropDownLabel           matlab.ui.control.Label
        ThenimportfilesLabel            matlab.ui.control.Label
        SelectyouroptionsLabel          matlab.ui.control.Label
        Files_UITable                   matlab.ui.control.Table
        Donotloadallfiles_CheckBox      matlab.ui.control.CheckBox
        Importtif_folder_Button         matlab.ui.control.Button
        Savefolder_Label                matlab.ui.control.Label
        SelectSavefolder_Button         matlab.ui.control.Button
        BydefaultthesavingfolderisthesameastheimportfolderLabel  matlab.ui.control.Label
        ImportLabel_2                   matlab.ui.control.Label
        Label_path                      matlab.ui.control.Label
        PathLabel                       matlab.ui.control.Label
        NodataloadedLabel               matlab.ui.control.Label
        ImportLabel                     matlab.ui.control.Label
        StatesTab                       matlab.ui.container.Tab
        GridLayout_States               matlab.ui.container.GridLayout
        NumberofrowsEditField           matlab.ui.control.NumericEditField
        NumberofrowsEditFieldLabel      matlab.ui.control.Label
        ShowonlystateswithacustomnameCheckBox  matlab.ui.control.CheckBox
        MosaicaxisDropDown              matlab.ui.control.DropDown
        MosaicaxisDropDownLabel         matlab.ui.control.Label
        InsertfilenameincustomnameCheckBox  matlab.ui.control.CheckBox
        Mosaic_FromworkflowEditField    matlab.ui.control.NumericEditField
        FromworkflowEditField_2Label    matlab.ui.control.Label
        Mosaic_toworkflowEditField      matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        Savechoice_DropDown             matlab.ui.control.DropDown
        Workflow_FontsizeEditField      matlab.ui.control.NumericEditField
        Subtitle_FontsizeEditField      matlab.ui.control.NumericEditField
        Title_FontsizeEditField         matlab.ui.control.NumericEditField
        FontsizeEditFieldLabel          matlab.ui.control.Label
        DoubleclickinthetabletovisualizeavolumeLabel  matlab.ui.control.Label
        Delete_WorkflowButton           matlab.ui.control.Button
        SelectactivefileDropDown        matlab.ui.control.DropDown
        SelectactivefileDropDownLabel   matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        MemorydepthEditField            matlab.ui.control.NumericEditField
        MemorydepthEditFieldLabel       matlab.ui.control.Label
        States_UITable                  matlab.ui.control.Table
        ImagemosaicofallstatesinmemoryButton  matlab.ui.control.Button
        FromstateDropDown               matlab.ui.control.DropDown
        FromstateLabel                  matlab.ui.control.Label
        FromexistingworkflowDropDown    matlab.ui.control.DropDown
        FromexistingworkflowDropDownLabel  matlab.ui.control.Label
        RenamecurrentworkflowoptionalEditField  matlab.ui.control.EditField
        RenamecurrentworkflowoptionalEditFieldLabel  matlab.ui.control.Label
        CreateanewworkflowButton        matlab.ui.control.Button
        SelectactiveworkflowDropDown    matlab.ui.control.DropDown
        SelectactiveworkflowDropDownLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ActionsTab                      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        RegionofInterestTab             matlab.ui.container.Tab
        ROI_GridLayout                  matlab.ui.container.GridLayout
        DeltaROIformacroCheckBox        matlab.ui.control.CheckBox
        ROI_Rotationmaxdeg_y_EditField  matlab.ui.control.NumericEditField
        ROI_Rotationmaxdeg_z_EditField  matlab.ui.control.NumericEditField
        ROI_Buffer_axe3_EditField       matlab.ui.control.NumericEditField
        ROI_Buffer_axe2_EditField       matlab.ui.control.NumericEditField
        ROI_Rotationmaxdeg_x_EditField  matlab.ui.control.NumericEditField
        Rotationmaxdegaxe12and3Label    matlab.ui.control.Label
        ROI_ParametersDropDown          matlab.ui.control.DropDown
        ForLabel                        matlab.ui.control.Label
        ROI_Buffer_axe1_EditField       matlab.ui.control.NumericEditField
        Bufferaxe12and3Label            matlab.ui.control.Label
        ROI_FromstateDropDown           matlab.ui.control.DropDown
        FromstateDropDown_3Label_3      matlab.ui.control.Label
        ROI_FromworkflowDropDown        matlab.ui.control.DropDown
        FromworkflowDropDownLabel_6     matlab.ui.control.Label
        ROI_FromfileDropDown            matlab.ui.control.DropDown
        FromfileDropDownLabel_6         matlab.ui.control.Label
        ROI_BackgroundlabelEditField    matlab.ui.control.NumericEditField
        BackgroundlabelEditFieldLabel   matlab.ui.control.Label
        ROI_SelectbackgroundDropDown    matlab.ui.control.DropDown
        SelectbackgroundDropDown_2Label_2  matlab.ui.control.Label
        ROI_background_DoButton         matlab.ui.control.Button
        ROI_DropDown                    matlab.ui.control.DropDown
        LargestROIwithoutbackgroundLabel  matlab.ui.control.Label
        SlicevisualizationPanel         matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        LabelcolorColorPicker           matlab.ui.control.ColorPicker
        LabelcolorColorPickerLabel      matlab.ui.control.Label
        LabelsizeEditField              matlab.ui.control.NumericEditField
        LabelsizeEditFieldLabel         matlab.ui.control.Label
        ShowlabelsegmentedimageonlyCheckBox  matlab.ui.control.CheckBox
        ROI_StateLabel                  matlab.ui.control.Label
        ROI_StateMinusButton            matlab.ui.control.Button
        ROI_StatePlusButton             matlab.ui.control.Button
        ROI_plus_Button                 matlab.ui.control.Button
        ROI_minus_Button                matlab.ui.control.Button
        ROI_SliceEditField              matlab.ui.control.NumericEditField
        SliceEditFieldLabel             matlab.ui.control.Label
        ROI_ColormapDropDown            matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        ROI_visu_AxisDropDown           matlab.ui.control.DropDown
        AxisDropDownLabel               matlab.ui.control.Label
        ROI_visu_SliceSlider            matlab.ui.control.Slider
        ROI_UIAxes                      matlab.ui.control.UIAxes
        AngledegreesSpinner             matlab.ui.control.Spinner
        AngledegreesSpinnerLabel        matlab.ui.control.Label
        Apply1beforerotationCheckBox    matlab.ui.control.CheckBox
        Rotation_DoButton               matlab.ui.control.Button
        AxisofrotationDropDown          matlab.ui.control.DropDown
        AxisofrotationDropDownLabel     matlab.ui.control.Label
        RotationLabel                   matlab.ui.control.Label
        Flipswap_DropDown               matlab.ui.control.DropDown
        Flipswap_DoButton               matlab.ui.control.Button
        FliporswapaxisLabel             matlab.ui.control.Label
        Autosetcropping_downscalingCheckBox  matlab.ui.control.CheckBox
        FindparametersButton            matlab.ui.control.Button
        DownscalingfactorEditField      matlab.ui.control.NumericEditField
        DownscalingfactorEditFieldLabel  matlab.ui.control.Label
        AutoreshapeCheckBox             matlab.ui.control.CheckBox
        ROI_StatutIdleLabel             matlab.ui.control.Label
        RelativedimensionCheckBox       matlab.ui.control.CheckBox
        ROI_crop_DoButton               matlab.ui.control.Button
        ROI_crop_UITable                matlab.ui.control.Table
        CroppingLabel                   matlab.ui.control.Label
        ScalingTab                      matlab.ui.container.Tab
        Scaling_GridLayout              matlab.ui.container.GridLayout
        upscaling1downscalingLabel_2    matlab.ui.control.Label
        Anisotropicscaling_UITable      matlab.ui.control.Table
        UsefultocorrectaFIBSEM3DimagingwithananisotropicvoxelsizeLabel  matlab.ui.control.Label
        AnisotropicScaling_DoButton     matlab.ui.control.Button
        AnisotropicscalingLabel         matlab.ui.control.Label
        OnlyusedforsegmentedvolumesLabel  matlab.ui.control.Label
        Scaling_BackgroundlabelEditField  matlab.ui.control.NumericEditField
        BackgroundlabelEditField_2Label  matlab.ui.control.Label
        Scaling_StatutIdleLabel         matlab.ui.control.Label
        Scaling_DoButton                matlab.ui.control.Button
        upscaling1downscalingLabel      matlab.ui.control.Label
        ScalingfactorEditField          matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        IsotropicscalingLabel           matlab.ui.control.Label
        ContrastcorrectionTab           matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        RangeandsaturationTab           matlab.ui.container.Tab
        Contrast1_GridLayout10          matlab.ui.container.GridLayout
        Highlow_filterButton            matlab.ui.control.Button
        ForallvoxelsDropDown_2          matlab.ui.control.DropDown
        ForallvoxelsDropDown_2Label     matlab.ui.control.Label
        setthemtoEditField_2            matlab.ui.control.NumericEditField
        setthemtoEditFieldLabel_2       matlab.ui.control.Label
        Filterthreshold_EditField_2     matlab.ui.control.NumericEditField
        HighandlowfilterLabel_2         matlab.ui.control.Label
        Egsegmentedimage012becomeLabel  matlab.ui.control.Label
        UseFullRange_DoButton           matlab.ui.control.Button
        ScaletofulldatarangeLabel       matlab.ui.control.Label
        Rescalinglinearly_DoButton      matlab.ui.control.Button
        RescalelinearlyusingnvaluesEditField  matlab.ui.control.NumericEditField
        RescalelinearlyusingnvaluesEditFieldLabel  matlab.ui.control.Label
        Contrast1_StatutIdleLabel       matlab.ui.control.Label
        LinearrescalingLabel            matlab.ui.control.Label
        Saturation_DoButton             matlab.ui.control.Button
        Saturation_above_EditField      matlab.ui.control.NumericEditField
        VolumeconditionLabel            matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Saturation_below_EditField      matlab.ui.control.NumericEditField
        VolumeconditionLabel_2          matlab.ui.control.Label
        SaturationLabel                 matlab.ui.control.Label
        HistogramequalizationTab        matlab.ui.container.Tab
        Contrast2_GridLayout10          matlab.ui.container.GridLayout
        CLAHE_AlphaEditField            matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        CLAHE_DistributionDropDown      matlab.ui.control.DropDown
        DistributionDropDownLabel       matlab.ui.control.Label
        CLAHE_RangeDropDown             matlab.ui.control.DropDown
        RangeDropDownLabel              matlab.ui.control.Label
        CLAHE_NbinEditField             matlab.ui.control.NumericEditField
        Label_25                        matlab.ui.control.Label
        CLAHE_CliplimitEditField        matlab.ui.control.NumericEditField
        Label_24                        matlab.ui.control.Label
        CLAHE_NtileBEditField           matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        CLAHE_NtileAEditField           matlab.ui.control.NumericEditField
        CLAHE_DoButton                  matlab.ui.control.Button
        ContrastlimitedadaptativehistogramequalizationCLAHELabel  matlab.ui.control.Label
        ContrastEq_perslice_CheckBox    matlab.ui.control.CheckBox
        Contrast2_StatutIdleLabel       matlab.ui.control.Label
        ContrastEqualization_DoButton   matlab.ui.control.Button
        hgramEditField                  matlab.ui.control.EditField
        hgramEditFieldLabel             matlab.ui.control.Label
        rationbinsEditField             matlab.ui.control.NumericEditField
        rationbinsEditFieldLabel        matlab.ui.control.Label
        RelativenbinsCheckBox           matlab.ui.control.CheckBox
        nbinsEditField                  matlab.ui.control.NumericEditField
        nbinsEditFieldLabel             matlab.ui.control.Label
        ContrastEq_MethodDropDown       matlab.ui.control.DropDown
        MethodDropDownLabel_4           matlab.ui.control.Label
        HistogramequalizationLabel      matlab.ui.control.Label
        FilterTab                       matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        OngreylevelorchannelsTab        matlab.ui.container.Tab
        GLfilter_GridLayout             matlab.ui.container.GridLayout
        SuperPixel_RefineCompactnessCheckBox  matlab.ui.control.CheckBox
        InitialcompactnessDropDown      matlab.ui.control.DropDown
        InitialcompactnessDropDownLabel  matlab.ui.control.Label
        InitialcompactnessEditField     matlab.ui.control.NumericEditField
        SuperPixel_NumberofiterationsEditField  matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel_2  matlab.ui.control.Label
        SuperpixelNumChoice_EditField   matlab.ui.control.DropDown
        NumberofDropDownLabel           matlab.ui.control.Label
        SuperpixelNum_EditField         matlab.ui.control.NumericEditField
        Superpixel_DoButton             matlab.ui.control.Button
        SuperpixelsLabel                matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label2_2                        matlab.ui.control.Label
        FilterVal_ScalewithdimensionCheckBox  matlab.ui.control.CheckBox
        FilterVal_BackgroundEditField   matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel_3      matlab.ui.control.Label
        Filter_Value_DoButton           matlab.ui.control.Button
        FilterValue_EditField           matlab.ui.control.NumericEditField
        IdentifypixelsDropDown          matlab.ui.control.DropDown
        IdentifypixelsDropDownLabel     matlab.ui.control.Label
        ValuefilterLabel                matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        ShowbitlevelCheckBox            matlab.ui.control.CheckBox
        KeeplastxbitlevelEditField      matlab.ui.control.NumericEditField
        KeeplastxbitlevelEditFieldLabel  matlab.ui.control.Label
        Bitlevel_DoButton               matlab.ui.control.Button
        BitlevelLabel                   matlab.ui.control.Label
        GLFilter_PreviewCheckBox        matlab.ui.control.CheckBox
        Anisotropic_GradientthresholdEditField  matlab.ui.control.NumericEditField
        GradientthresholdEditFieldLabel  matlab.ui.control.Label
        Anisotropic_NumberofiterationsEditField  matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        Anisotropic_EstimategradientCheckBox  matlab.ui.control.CheckBox
        Anisotropy_ConnectivityDropDown  matlab.ui.control.DropDown
        ConnectivityDropDownLabel       matlab.ui.control.Label
        Anisotropy_ConductionmethodDropDown  matlab.ui.control.DropDown
        ConductionmethodDropDownLabel   matlab.ui.control.Label
        Anisotropic_ApplyDropDown       matlab.ui.control.DropDown
        ApplyDropDownLabel              matlab.ui.control.Label
        Anisotropic_DoButton            matlab.ui.control.Button
        AnisotropicdiffusionfilterLabel  matlab.ui.control.Label
        Filter_SigmaEditField           matlab.ui.control.NumericEditField
        FiltersizeLabel_2               matlab.ui.control.Label
        GaussianFilter_Domain_DropDown  matlab.ui.control.DropDown
        DomainLabel                     matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        Filter_PaddingDropDown          matlab.ui.control.DropDown
        PaddingDropDownLabel            matlab.ui.control.Label
        Filter_DoButton                 matlab.ui.control.Button
        Filter_RangeEditField           matlab.ui.control.NumericEditField
        FiltersizeLabel                 matlab.ui.control.Label
        AveragingLabel                  matlab.ui.control.Label
        FilterGL_StatutIdleLabel        matlab.ui.control.Label
        NLMF_DoButton                   matlab.ui.control.Button
        NLMF_ComparisonwindowsizeEditField  matlab.ui.control.NumericEditField
        ComparisonwindowsizeEditFieldLabel  matlab.ui.control.Label
        NLMF_SearchwindowsizeEditField  matlab.ui.control.NumericEditField
        SearchwindowsizeEditFieldLabel  matlab.ui.control.Label
        NLMF_DegreeofsmoothingEditField  matlab.ui.control.NumericEditField
        DegreeofsmoothingEditFieldLabel  matlab.ui.control.Label
        NLMF_EstimatedegreeofsmoothingCheckBox  matlab.ui.control.CheckBox
        NonlocalmeansfilterLabel        matlab.ui.control.Label
        OnsegmenteddataTab              matlab.ui.container.Tab
        SEGfilter_GridLayout            matlab.ui.container.GridLayout
        Hyperlink4                      matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Image4                          matlab.ui.control.Image
        Thinlayerartifact_DoButton      matlab.ui.control.Button
        WidththresholdEditField         matlab.ui.control.NumericEditField
        WidththresholdEditFieldLabel    matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        FilterSEG_StatutIdleLabel       matlab.ui.control.Label
        ErosiondilationTab              matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        GlobalTab                       matlab.ui.container.Tab
        GlobalErosionDilation_GridLayout  matlab.ui.container.GridLayout
        GlobalBackgroundlabelEditField  matlab.ui.control.NumericEditField
        BackgroundlabelEditField_4Label  matlab.ui.control.Label
        GlobalLabeltoapplyEditField     matlab.ui.control.NumericEditField
        LabeltoapplyEditFieldLabel      matlab.ui.control.Label
        AddzeroesonedgeCheckBox         matlab.ui.control.CheckBox
        Image3                          matlab.ui.control.Image
        Setto0nottoperformdilationLabel  matlab.ui.control.Label
        Setto0nottoperformerosionLabel  matlab.ui.control.Label
        GlobalErosionDilation_Button    matlab.ui.control.Button
        DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2  matlab.ui.control.CheckBox
        GlabolaErosionDilation_ApplyonDropDown  matlab.ui.control.DropDown
        ApplyonDropDownLabel_2          matlab.ui.control.Label
        GlobalErosionDilation_NumberofiterationEditField  matlab.ui.control.NumericEditField
        NumberofiterationEditFieldLabel_2  matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        GlobalErosionDilation_DistancedefinitionDropDown  matlab.ui.control.DropDown
        DistancedefinitionDropDown_2Label  matlab.ui.control.Label
        GlobalDilationdistanceEditField  matlab.ui.control.NumericEditField
        DilationdistanceEditField_2Label  matlab.ui.control.Label
        GlobalErosiondistanceEditField  matlab.ui.control.NumericEditField
        ErosiondistanceEditField_2Label  matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        GlobalErosionDilation_OrderDropDown  matlab.ui.control.DropDown
        OrderDropDownLabel_3            matlab.ui.control.Label
        GlobalmorphologyopeningorclosingLabel  matlab.ui.control.Label
        GlobalErosionDilation_StatutIdleLabel  matlab.ui.control.Label
        LocalerosionTab                 matlab.ui.container.Tab
        LocalErosion_GridLayout         matlab.ui.container.GridLayout
        Hyperlink6                      matlab.ui.control.Hyperlink
        Image2                          matlab.ui.control.Image
        Label_34                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Ifcheckedimagewiththelowest1metricwillbekeptLabel  matlab.ui.control.Label
        LocalerosionKeepbest_CheckBox   matlab.ui.control.CheckBox
        CalculatedattheedgeslocallywithinwindowrangeLabel  matlab.ui.control.Label
        Localerosion_FilenameEditField  matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        Localerosion_NofolderLabel      matlab.ui.control.Label
        Localerosion_SavefolderButton   matlab.ui.control.Button
        Localerosion_DoButton           matlab.ui.control.Button
        Localerosion_FramerateEditField  matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        Localerosion_VideoCheckBox      matlab.ui.control.CheckBox
        VoxelsattheedgeswithmetricmaxLabel  matlab.ui.control.Label
        ErosionmagnitudeEditField       matlab.ui.control.NumericEditField
        ErosionmagnitudeEditFieldLabel  matlab.ui.control.Label
        Localerosion_max1metricgoesbelowEditField  matlab.ui.control.NumericEditField
        max1metricgoesbelowEditFieldLabel  matlab.ui.control.Label
        metricbelowthisvaluewillbesetto0Label  matlab.ui.control.Label
        ThresholdnumericallerrorEditField  matlab.ui.control.NumericEditField
        ThresholdnumericallerrorEditFieldLabel  matlab.ui.control.Label
        ConcavitymetricDropDown         matlab.ui.control.DropDown
        ConcavitymetricDropDownLabel    matlab.ui.control.Label
        Erodeupto10ofthephaseLabel      matlab.ui.control.Label
        RatioofinitialvolumefractionEditField  matlab.ui.control.NumericEditField
        RatioofinitialvolumefractionEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofiterationEditField  matlab.ui.control.NumericEditField
        MaximumnumberofiterationEditFieldLabel  matlab.ui.control.Label
        StoppingconditionORLabel        matlab.ui.control.Label
        Localerosion_DistancemethodDropDown  matlab.ui.control.DropDown
        DistancemethodDropDownLabel     matlab.ui.control.Label
        Localerosion_DistanceEditField  matlab.ui.control.NumericEditField
        DistanceEditFieldLabel          matlab.ui.control.Label
        Localerosion_surfacesimplification_CheckBox  matlab.ui.control.CheckBox
        Localerosion_WindowrangeEditField  matlab.ui.control.NumericEditField
        WindowrangeEditFieldLabel       matlab.ui.control.Label
        ConcavitybasedLocalerosionLabel  matlab.ui.control.Label
        LocalErosionDilation_StatutIdleLabel  matlab.ui.control.Label
        LocaldilationTab                matlab.ui.container.Tab
        LocalDilation_GridLayout        matlab.ui.container.GridLayout
        LocalDilation_ApplyDropDown     matlab.ui.control.DropDown
        ApplyDropDownLabel_2            matlab.ui.control.Label
        Label2_6                        matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Hyperlink6_2                    matlab.ui.control.Hyperlink
        Localdilation_PersliceDropDown  matlab.ui.control.DropDown
        Localdialtion_Perslice3DonyzdirectionCheckBox  matlab.ui.control.CheckBox
        ClicktoenlargepicturesLabel     matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Localdilation_RemoveotherlabelsCheckBox  matlab.ui.control.CheckBox
        Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox  matlab.ui.control.CheckBox
        Localdilation_RoundDmap_CheckBox  matlab.ui.control.CheckBox
        Localdilation_ShowdiameteronlyforvisualizationCheckBox  matlab.ui.control.CheckBox
        ClicktoinitializeUILabel        matlab.ui.control.Label
        Localdilation_Sphere_DoButton   matlab.ui.control.Button
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        Localdilation_BackgroundlabelDropDown  matlab.ui.control.DropDown
        BackgroundlabelDropDownLabel_3  matlab.ui.control.Label
        Localdilation_DilateDropDown    matlab.ui.control.DropDown
        DilateDropDownLabel             matlab.ui.control.Label
        Localdilation_LabeltodilateDropDown  matlab.ui.control.DropDown
        LabeltodilateDropDownLabel      matlab.ui.control.Label
        LocalDilation_StatutIdleLabel   matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        LocalDilation_Shell_DoButton    matlab.ui.control.Button
        LocaldilationparticleconcaveshellsLabel  matlab.ui.control.Label
        SpherebasedlocaldilationLabel_2  matlab.ui.control.Label
        SegmentationTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        SemanticsegmentationTab         matlab.ui.container.Tab
        Semanticsegmentation_GridLayout  matlab.ui.container.GridLayout
        VisualizethresholdsButton       matlab.ui.control.Button
        Morethan2phasesYoumayneedtocorrectartifactActionsLabel  matlab.ui.control.Label
        LocalSeg_NumberofcontrolpointEditField  matlab.ui.control.NumericEditField
        NumberofcontrolpointsLabel      matlab.ui.control.Label
        LocalSeg_UITable                matlab.ui.control.Table
        LocalSegmentation_Method_DropDown  matlab.ui.control.DropDown
        OptionDropDownLabel             matlab.ui.control.Label
        Seg_UnitEditField               matlab.ui.control.EditField
        UnitEditFieldLabel_2            matlab.ui.control.Label
        Seg_VoxelsizeEditField          matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel_2       matlab.ui.control.Label
        SavethresholdprofileCheckBox    matlab.ui.control.CheckBox
        PlotthresholdprofileCheckBox    matlab.ui.control.CheckBox
        MovingaveragefilterrangeEditField  matlab.ui.control.NumericEditField
        MovingaveragefilterrangeEditFieldLabel  matlab.ui.control.Label
        TranslatethresholdprofileCheckBox  matlab.ui.control.CheckBox
        LocalSegmentation_Axe_DropDown  matlab.ui.control.DropDown
        Segthreshold_DoButton           matlab.ui.control.Button
        Method2localthresholdLabel      matlab.ui.control.Label
        BackgroundoptionPanel           matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Seg_FromstateDropDown           matlab.ui.control.DropDown
        FromstateDropDown_3Label_2      matlab.ui.control.Label
        Seg_FromworkflowDropDown        matlab.ui.control.DropDown
        FromworkflowDropDownLabel_5     matlab.ui.control.Label
        Seg_FromfileDropDown            matlab.ui.control.DropDown
        FromfileDropDownLabel_5         matlab.ui.control.Label
        Seg_BackgroundlabelEditField    matlab.ui.control.NumericEditField
        BackgroundlabelEditField_3Label  matlab.ui.control.Label
        Seg_SelectbackgroundDropDown    matlab.ui.control.DropDown
        SelectbackgroundDropDown_2Label  matlab.ui.control.Label
        Seg_ExcludebackgroundfromthecalculationCheckBox  matlab.ui.control.CheckBox
        GotocomparisonstabtochecksegmentationresultLabel  matlab.ui.control.Label
        Seg_Slider_Button               matlab.ui.control.Button
        GlobalSeg_StatutIdleLabel       matlab.ui.control.Label
        Globalthreshold_DoButton        matlab.ui.control.Button
        Segmentation_expectedvf_UITable  matlab.ui.control.Table
        Globalsegmentation_UITable      matlab.ui.control.Table
        Global_segmentation_MethodDropDown  matlab.ui.control.DropDown
        OptionLabel                     matlab.ui.control.Label
        FirstchoosethenumberofphasesincludingbackgroundLabel  matlab.ui.control.Label
        Method1globalthresholdLabel     matlab.ui.control.Label
        Seg_Numberofphases_editfield    matlab.ui.control.NumericEditField
        NumberofphasesincludingporebutnotbackgroundifanyLabel  matlab.ui.control.Label
        InstancesegmentationTab         matlab.ui.container.Tab
        Instancesegmentation_GridLayout  matlab.ui.container.GridLayout
        PostprocessingPanel             matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        RandomizeinstancelabelaftersegmentationCheckBox  matlab.ui.control.CheckBox
        SpherefilteroversegmentationcorrectionCheckBox  matlab.ui.control.CheckBox
        MethodspecificparametersPanel   matlab.ui.container.Panel
        InstanceParameterMethod_GridLayout  matlab.ui.container.GridLayout
        RepulsiveforcewindowrangeEditField  matlab.ui.control.NumericEditField
        RepulsiveforcewindowrangeEditFieldLabel  matlab.ui.control.Label
        ExponentEditField               matlab.ui.control.NumericEditField
        ExponentEditFieldLabel          matlab.ui.control.Label
        PCRFonlyparametersLabel         matlab.ui.control.Label
        PreerosiontoimprovedetectionPanel  matlab.ui.container.Panel
        Preerosion_GridLayout           matlab.ui.container.GridLayout
        PreerosionCheckBox              matlab.ui.control.CheckBox
        InstanceSeg_ShowerosionCheckBox  matlab.ui.control.CheckBox
        InstanceSeg_OrderDropDown       matlab.ui.control.DropDown
        OrderDropDownLabel              matlab.ui.control.Label
        InstanceSeg_ErosiondistanceEditField  matlab.ui.control.NumericEditField
        ErosiondistanceEditFieldLabel   matlab.ui.control.Label
        ReducecalculationtimePanel      matlab.ui.container.Panel
        ReduceCalcTime_GridLayout       matlab.ui.container.GridLayout
        InstanceSeg_ShowDownscalingCheckBox  matlab.ui.control.CheckBox
        Hyperlink7                      matlab.ui.control.Hyperlink
        HelpButton                      matlab.ui.control.Button
        Downscaling_TargetnumberofvoxelinmillionsEditField  matlab.ui.control.NumericEditField
        TargetnumberofvoxelinmillionsLabel  matlab.ui.control.Label
        OverlappinglengthDropDown       matlab.ui.control.DropDown
        OverlappinglengthDropDownLabel  matlab.ui.control.Label
        OverlappingpartlengthEditField  matlab.ui.control.NumericEditField
        OverlappingpartlengthEditFieldLabel  matlab.ui.control.Label
        CropvolumesifneededandDropDown  matlab.ui.control.DropDown
        CropvolumesifneededandDropDownLabel  matlab.ui.control.Label
        InstanceSeg_DownscalingfactorEditField  matlab.ui.control.NumericEditField
        DownscalingfactorEditField_2Label  matlab.ui.control.Label
        DownscalingfactorDropDown       matlab.ui.control.DropDown
        downscalingupscalingschemeCheckBox  matlab.ui.control.CheckBox
        ByParts_TargetnumberofvoxelinmillionsEditField  matlab.ui.control.NumericEditField
        TargetnumberofvoxelinmillionsEditFieldLabel_2  matlab.ui.control.Label
        NumberofpartsEditField          matlab.ui.control.NumericEditField
        NumberofpartsEditFieldLabel     matlab.ui.control.Label
        NumberofpartsDropDown           matlab.ui.control.DropDown
        SegmentingbypartsCheckBox       matlab.ui.control.CheckBox
        Label2_5                        matlab.ui.control.Label
        AdditionaloptionsLabel          matlab.ui.control.Label
        Label2_4                        matlab.ui.control.Label
        InstanceSeg_VerboseCheckBox     matlab.ui.control.CheckBox
        Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox  matlab.ui.control.CheckBox
        Hyperlink5                      matlab.ui.control.Hyperlink
        InstancelabelEditField          matlab.ui.control.NumericEditField
        InstancelabelEditFieldLabel     matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        InstanceSeg_StatutIdleLabel     matlab.ui.control.Label
        InstanceSeg_DoButton            matlab.ui.control.Button
        Hyperlink_5                     matlab.ui.control.Hyperlink
        InstanceSeg_MethodDropDown      matlab.ui.control.DropDown
        MethodDropDownLabel_2           matlab.ui.control.Label
        FirstchoosethenumberofphasesincludingbackgroundLabel_3  matlab.ui.control.Label
        ChannelsTab                     matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        PixelwiseTab                    matlab.ui.container.Tab
        Channels_Gridlayout_2           matlab.ui.container.GridLayout
        ForsegmentedsemanticimagesPanel  matlab.ui.container.Panel
        GridLayout_Channel2             matlab.ui.container.GridLayout
        Hyperlink9                      matlab.ui.control.Hyperlink
        Hyperlink8                      matlab.ui.control.Hyperlink
        Image_cPSD_EDM                  matlab.ui.control.Image
        CpsdEDM_roundnumberofdigits_EditField  matlab.ui.control.NumericEditField
        NumberofdigitsEditFieldLabel    matlab.ui.control.Label
        ExcludedlabelsEditField         matlab.ui.control.EditField
        ExcludedlabelsEditFieldLabel    matlab.ui.control.Label
        Channel_LabelEditField_2        matlab.ui.control.NumericEditField
        LabelEditFieldLabel_2           matlab.ui.control.Label
        ChannelLabel_ForDropDown_2      matlab.ui.control.DropDown
        ForDropDownLabel_2              matlab.ui.control.Label
        Size_RoundresultCheckBox_2      matlab.ui.control.CheckBox
        Size_DistanceDropDown_2         matlab.ui.control.DropDown
        DistanceDropDownLabel_2         matlab.ui.control.Label
        Size_MericDropDown_2            matlab.ui.control.DropDown
        MetricLabel                     matlab.ui.control.Label
        Channel_pixelsize_Button_2      matlab.ui.control.Button
        SizeLabel_2                     matlab.ui.control.Label
        ForgreylevelorchannelimagesPanel  matlab.ui.container.Panel
        GridLayout_Channel1             matlab.ui.container.GridLayout
        Channel_GraidientMethodDropDown_2  matlab.ui.control.DropDown
        MethodDropDownLabel_5           matlab.ui.control.Label
        Channel_Gradientmagnitude_DoButton_2  matlab.ui.control.Button
        GradientmagnitudeLabel_2        matlab.ui.control.Label
        Channel_std_RangeEditField_2    matlab.ui.control.NumericEditField
        RangeEditFieldLabel_2           matlab.ui.control.Label
        Channel_Std_StructuringelementDropDown_2  matlab.ui.control.DropDown
        StructuringelementDropDownLabel_2  matlab.ui.control.Label
        Texture_MetricDropDown_2        matlab.ui.control.DropDown
        MetricDropDownLabel_3           matlab.ui.control.Label
        Channel_Std_DoButton_2          matlab.ui.control.Button
        TextureLabel_2                  matlab.ui.control.Label
        Channel_StatutIdleLabel_2       matlab.ui.control.Label
        LabelwiseTab                    matlab.ui.container.Tab
        GridLayout_Channel3             matlab.ui.container.GridLayout
        ExcludedlabelsEditField_2       matlab.ui.control.EditField
        ExcludedlabelsEditField_2Label  matlab.ui.control.Label
        SolidityvolumeconvexvolumeAspectratiominLxLyLzmaxLxLyLzLabel  matlab.ui.control.Label
        Channel_ConverttointegerCheckBox  matlab.ui.control.CheckBox
        Channel_PrecisionEditField      matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel         matlab.ui.control.Label
        Channel_StatutIdleLabel_3       matlab.ui.control.Label
        Channel_LabelEditField          matlab.ui.control.NumericEditField
        LabelEditFieldLabel             matlab.ui.control.Label
        ChannelLabel_ForDropDown        matlab.ui.control.DropDown
        ForDropDownLabel                matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Shape_MetricDropDown_3          matlab.ui.control.DropDown
        MetricDropDownLabel_5           matlab.ui.control.Label
        ChannelShape_DoButton_3         matlab.ui.control.Button
        Labelshape                      matlab.ui.control.Label
        ChannelSize_DoButton_3          matlab.ui.control.Button
        Labelsize                       matlab.ui.control.Label
        ConnectivityTab                 matlab.ui.container.Tab
        TabGroup9                       matlab.ui.container.TabGroup
        ClustersandconnectivitytypeTab  matlab.ui.container.Tab
        Clusters_GridLayout             matlab.ui.container.GridLayout
        CustomruleclustersmustverifyDropDown  matlab.ui.control.DropDown
        CustomruleclustersmustverifyDropDownLabel  matlab.ui.control.Label
        Custom_Connectivity_UITable_2   matlab.ui.control.Table
        ClustersmusttouchDropDown       matlab.ui.control.DropDown
        ClustersmusttouchDropDownLabel  matlab.ui.control.Label
        alongaxeDropDown                matlab.ui.control.DropDown
        alongaxeDropDownLabel           matlab.ui.control.Label
        CalculateconnectivityforalllabelsexceptLabel  matlab.ui.control.Label
        LabelConnectivity_DoButton      matlab.ui.control.Button
        Image_connectivity_definition   matlab.ui.control.Image
        Cluster_OuptutdescriptionLabel_2  matlab.ui.control.Label
        Connectivity_DefintionDropDown  matlab.ui.control.DropDown
        DefintionDropDownLabel          matlab.ui.control.Label
        Cluster_OuptutdescriptionLabel  matlab.ui.control.Label
        SharedparametersPanel           matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        Image_connectivity_background_2  matlab.ui.control.Image
        Clusters_Background_EditField   matlab.ui.control.NumericEditField
        BackgroundlabelEditField_5Label_3  matlab.ui.control.Label
        Cluster_BackgroundisdomainsedgesCheckBox  matlab.ui.control.CheckBox
        Cluster_complimentarylabelEditField  matlab.ui.control.NumericEditField
        ComplementarylabelLabel_2       matlab.ui.control.Label
        ThreeDconnectivitiesDropDown_2  matlab.ui.control.DropDown
        DconnectivitiesLabel_2          matlab.ui.control.Label
        TwoDconnectivitiesDropDown_2    matlab.ui.control.DropDown
        DconnectivitiesDropDownLabel_2  matlab.ui.control.Label
        ForlabelEditField               matlab.ui.control.NumericEditField
        ForlabelEditFieldLabel          matlab.ui.control.Label
        CalculateClusters_DoButton      matlab.ui.control.Button
        CalculateclustersLabel_3        matlab.ui.control.Label
        Labelconnectivity_2             matlab.ui.control.Label
        Connectivity_StatutIdleLabel_3  matlab.ui.control.Label
        ClusterinteractionsTab          matlab.ui.container.Tab
        ClusterInteractions_GridLayout  matlab.ui.container.GridLayout
        Surrounding_DoButton            matlab.ui.control.Button
        thenassigntolabelEditField      matlab.ui.control.NumericEditField
        thenassigntolabelEditField_2Label  matlab.ui.control.Label
        morethanratioofinterfaceEditField  matlab.ui.control.NumericEditField
        morethanratioofinterfaceEditField_2Label  matlab.ui.control.Label
        SurroundingLabel_EditField      matlab.ui.control.NumericEditField
        IflabelEditFieldLabel_2         matlab.ui.control.Label
        issurroundedbylabelEditField    matlab.ui.control.NumericEditField
        issurroundedbylabelEditField_2Label  matlab.ui.control.Label
        Surrounding_WindowradiusEditField  matlab.ui.control.NumericEditField
        WindowradiusEditFieldLabel_2    matlab.ui.control.Label
        WillcheckclustercenteredinthewindowrangeLabel  matlab.ui.control.Label
        Removetruncated_DoButton        matlab.ui.control.Button
        RemovetruncatedlabelsLabel      matlab.ui.control.Label
        Cluster_separation_PleasewaitLabel  matlab.ui.control.Label
        Separate_labels_Image           matlab.ui.control.Image
        SeparateCluster_UITable         matlab.ui.control.Table
        ClusterSeparation_PlotdistributionButton  matlab.ui.control.Button
        EquiprobabilityCheckBox_2       matlab.ui.control.CheckBox
        MaxvariationfrommeanEditField   matlab.ui.control.NumericEditField
        MaxvariationfrommeanEditFieldLabel  matlab.ui.control.Label
        DistributionstddeviationEditField  matlab.ui.control.NumericEditField
        DistributionsigmaLabel          matlab.ui.control.Label
        MeanwidthEditField_2            matlab.ui.control.NumericEditField
        MeanwidthEditField_2Label       matlab.ui.control.Label
        RatioofseparatedlabelsEditField  matlab.ui.control.NumericEditField
        RatioofseparatedlabelsEditFieldLabel  matlab.ui.control.Label
        Separatelabels_ChoiceDropDown_2  matlab.ui.control.DropDown
        ChoiceDropDownLabel_4           matlab.ui.control.Label
        Removetruncated_DoButton_2      matlab.ui.control.Button
        AddthinseparationlayerbetweennonbackgroundlabelsincontactLabel  matlab.ui.control.Label
        SharedparametersexceptforreassignbasedonsurroundingPanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Image_connectivity_background   matlab.ui.control.Image
        ClusterInteractions_Background_EditField  matlab.ui.control.NumericEditField
        BackgroundlabelEditField_5Label_2  matlab.ui.control.Label
        ClusterInerationcs_BackgroundisdomainsedgesCheckBox  matlab.ui.control.CheckBox
        ClusterInteractions_complimentarylabelEditField  matlab.ui.control.NumericEditField
        ComplementarylabelLabel         matlab.ui.control.Label
        OtherwiseassigntoEditField      matlab.ui.control.NumericEditField
        OtherwiseassigntoEditFieldLabel  matlab.ui.control.Label
        RemoveIncludedLabels_DoButton   matlab.ui.control.Button
        AssigntosurroundinglabelCheckBox  matlab.ui.control.CheckBox
        RemovelabelsincludedintootherlabelsLabel  matlab.ui.control.Label
        ReassignlabelsbasedonsurroundingLabel  matlab.ui.control.Label
        Connectivity_StatutIdleLabel_2  matlab.ui.control.Label
        AssembleclustersTab             matlab.ui.container.Tab
        Pulverize_GridLayout            matlab.ui.container.GridLayout
        Connectivity_StatutIdleLabel_4  matlab.ui.control.Label
        Label2_7                        matlab.ui.control.Label
        SeeErosiondilationlocaldilationLabel  matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        MergePulverizedParts_DoButton   matlab.ui.control.Button
        FillingoverwritteotherlabelsCheckBox  matlab.ui.control.CheckBox
        MergeSLSDiameterEditField       matlab.ui.control.NumericEditField
        DiameterEditField_2Label        matlab.ui.control.Label
        VoidfillingmethodDropDown       matlab.ui.control.DropDown
        VoidfillingmethodDropDownLabel  matlab.ui.control.Label
        eg417127Label                   matlab.ui.control.Label
        ListoflabelstomergeEditField    matlab.ui.control.EditField
        ListoflabelstomergeEditFieldLabel  matlab.ui.control.Label
        AssemblelabelsandfillvoidinbetweenLabel  matlab.ui.control.Label
        ReassignTab                     matlab.ui.container.Tab
        Randomize_GridLayout            matlab.ui.container.GridLayout
        ButdonotmodifysomelabelsCheckBox_2  matlab.ui.control.CheckBox
        Sort_Constant_EditField         matlab.ui.control.EditField
        Randomize_Constant_EditField    matlab.ui.control.EditField
        ButdonotmodifysomelabelsCheckBox  matlab.ui.control.CheckBox
        IndexSubstitution_y_EditField   matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        IndexSubstitution_x_EditField   matlab.ui.control.NumericEditField
        xEditFieldLabel_2               matlab.ui.control.Label
        IndexSubstitution_DoButton      matlab.ui.control.Button
        SubstitutionChoice_DropDown     matlab.ui.control.DropDown
        IndexbasedsubstitutionLabel     matlab.ui.control.Label
        RemoveGapNumering_DoButton      matlab.ui.control.Button
        Removegapinlabelnumberingeg03711becomes0123Label  matlab.ui.control.Label
        Rnd_Checkuptoadistance_EditField  matlab.ui.control.NumericEditField
        CheckuptoadistanceofxvoxelsLabel  matlab.ui.control.Label
        RandomizeDistance_CheckBox      matlab.ui.control.CheckBox
        rounddigitsEditField            matlab.ui.control.NumericEditField
        digitsEditFieldLabel            matlab.ui.control.Label
        Binarize_x_EditField            matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        BinarizeChoice_DropDown         matlab.ui.control.DropDown
        SortInstance_DoButton           matlab.ui.control.Button
        Arithmetic_DropDown             matlab.ui.control.DropDown
        Sortchoice_DropDown             matlab.ui.control.DropDown
        SortlabelspervalueLabel         matlab.ui.control.Label
        Absolute_DoButton               matlab.ui.control.Button
        Round_DoButton                  matlab.ui.control.Button
        AbsoluteLabel                   matlab.ui.control.Label
        RoundtoLabel                    matlab.ui.control.Label
        Arithmetic_DoButton             matlab.ui.control.Button
        Arithmetic_EditField            matlab.ui.control.NumericEditField
        ArithmeticLabel                 matlab.ui.control.Label
        Binarize_DoButton               matlab.ui.control.Button
        BinarizeLabel                   matlab.ui.control.Label
        Reassign_DoButton               matlab.ui.control.Button
        Reassign_UITable                matlab.ui.control.Table
        ManualreassignmentLabel         matlab.ui.control.Label
        Reassign_StatutIdleLabel        matlab.ui.control.Label
        Not_DoButton                    matlab.ui.control.Button
        OppositenotLabel                matlab.ui.control.Label
        Randomize_DoButton              matlab.ui.control.Button
        Label_14                        matlab.ui.control.Label
        FormatTab                       matlab.ui.container.Tab
        DataType_GridLayout             matlab.ui.container.GridLayout
        Format_StatutIdleLabel          matlab.ui.control.Label
        ChangeDataType_DoButton         matlab.ui.control.Button
        Label2                          matlab.ui.control.Label
        ChangedatatypetoDropDown        matlab.ui.control.DropDown
        ChangedatatypetoDropDownLabel   matlab.ui.control.Label
        DatatypeLabel                   matlab.ui.control.Label
        MultiplefilesoptionTab          matlab.ui.container.Tab
        Multiplefiles_options_GridLayout  matlab.ui.container.GridLayout
        Multiplefiles_options_DropDown  matlab.ui.control.DropDown
        OptionsDropDown_2Label          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        CalculationsTab                 matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        Calc_OngreylevelTab             matlab.ui.container.Tab
        Calc_Grey_GridLayout            matlab.ui.container.GridLayout
        ImageQuality_DropDown           matlab.ui.control.DropDown
        SepCriterion_ChoiceDropDown     matlab.ui.control.DropDown
        ChoiceDropDownLabel_3           matlab.ui.control.Label
        SepCriterion_maxEditField       matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        NumberofphaseLabel              matlab.ui.control.Label
        CalcGreyLevel_StatutIdleLabel   matlab.ui.control.Label
        UsingOtsusseparabilitycriterionLabel  matlab.ui.control.Label
        AlgorithmworkonthewholeLabel    matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        Calc_MovingaveragefilterEditField  matlab.ui.control.NumericEditField
        MovingaveragefilterEditFieldLabel  matlab.ui.control.Label
        Calc_PerspectiveDropDown        matlab.ui.control.DropDown
        PerspectiveDropDownLabel        matlab.ui.control.Label
        Calc_ColrmapDropDown            matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        Calc_LinewidthEditField         matlab.ui.control.NumericEditField
        LinewidthLabel                  matlab.ui.control.Label
        Calc_AlllinesCheckBox           matlab.ui.control.CheckBox
        Calc_NlinesEditField            matlab.ui.control.NumericEditField
        NlinesLabel                     matlab.ui.control.Label
        Calc_UnitEditField              matlab.ui.control.EditField
        UnitEditFieldLabel              matlab.ui.control.Label
        Calc_VoxelsizeEditField         matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel         matlab.ui.control.Label
        BackgroundoptionPanel_2         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Background_FromstateDropDown    matlab.ui.control.DropDown
        FromworkflowDropDown_2Label_2   matlab.ui.control.Label
        Background_FromworkflowDropDown  matlab.ui.control.DropDown
        FromworkflowDropDownLabel_4     matlab.ui.control.Label
        Background_FromfileDropDown     matlab.ui.control.DropDown
        FromfileDropDownLabel_4         matlab.ui.control.Label
        CalcGrey_SelectbackgroundDropDown  matlab.ui.control.DropDown
        SelectbackgroundDropDownLabel   matlab.ui.control.Label
        BackgroundlabelEditField_2      matlab.ui.control.NumericEditField
        BackgroundlabelEditField_2Label_2  matlab.ui.control.Label
        ExcludebackgroundfromthecalculationCheckBox  matlab.ui.control.CheckBox
        Imagequality_CalculateButton    matlab.ui.control.Button
        SepCriterion_minEditField       matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        ImagequalityforsegmentationLabel  matlab.ui.control.Label
        Noiselevel_CalculateButton      matlab.ui.control.Button
        NbinsEditField                  matlab.ui.control.NumericEditField
        NbinsEditFieldLabel             matlab.ui.control.Label
        BlindnoiselevelLabel            matlab.ui.control.Label
        Greylevel_CalculateButton       matlab.ui.control.Button
        Greylevel_OptionDropDown        matlab.ui.control.DropDown
        HistogramoptionLabel            matlab.ui.control.Label
        Greylevel_CalculateDropDown     matlab.ui.control.DropDown
        CalculateDropDownLabel          matlab.ui.control.Label
        GreylevelLabel                  matlab.ui.control.Label
        Calc_OnsegmenteddataTab         matlab.ui.container.Tab
        Calc_Seg_GridLayout             matlab.ui.container.GridLayout
        CalculateandplotparticleconnectivityButton  matlab.ui.control.Button
        ParticleconnectivityLabel       matlab.ui.control.Label
        Volumefractions_CalculateandplotButton  matlab.ui.control.Button
        CalcSeg_UnitEditField           matlab.ui.control.EditField
        UnitEditFieldLabel_3            matlab.ui.control.Label
        CalcSeg_VoxelsizeEditField      matlab.ui.control.NumericEditField
        VoxelsizeEditFieldLabel_3       matlab.ui.control.Label
        BackgroundoptionforvolumefractionsPanel  matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        CalcSeg_FromstateDropDown       matlab.ui.control.DropDown
        FromstateDropDown_4Label        matlab.ui.control.Label
        CalcSeg_FromworkflowDropDown    matlab.ui.control.DropDown
        FromworkflowDropDownLabel_7     matlab.ui.control.Label
        CalcSeg_FromfileDropDown        matlab.ui.control.DropDown
        FromfileDropDownLabel_7         matlab.ui.control.Label
        CalcSeg_BackgroundlabelEditField  matlab.ui.control.NumericEditField
        BackgroundlabelEditField_3Label_3  matlab.ui.control.Label
        CalcSeg_SelectbackgroundDropDown  matlab.ui.control.DropDown
        SelectbackgroundDropDownLabel_2  matlab.ui.control.Label
        CalcSeg_ExcludebackgroundfromthecalculationCheckBox  matlab.ui.control.CheckBox
        CalcSeg_StatutIdleLabel         matlab.ui.control.Label
        FormoreadvancedoptionspleaseusethecharacterizationmoduleLabel  matlab.ui.control.Label
        VolumefractionsLabel            matlab.ui.control.Label
        CombinationsTab                 matlab.ui.container.Tab
        Combination_GridLayout          matlab.ui.container.GridLayout
        ConcatenatePanel                matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Combination_Concatenate_DoButton  matlab.ui.control.Button
        Concatenate_axisDown            matlab.ui.control.DropDown
        AfirstthenBalongaxeDropDownLabel  matlab.ui.control.Label
        AssignvoxeltonearestclusterPanel  matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        DoButton_4                      matlab.ui.control.Button
        ClustersarecalculatedonAThenLabel  matlab.ui.control.Label
        HistogramequalizationPanel      matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        MinmaxrangeotherwisedatarangeCheckBox  matlab.ui.control.CheckBox
        DoButton                        matlab.ui.control.Button
        Label_22                        matlab.ui.control.Label
        SymmetryPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Symmetry_Perslize3DonlyalongzCheckBox  matlab.ui.control.CheckBox
        Symmetry_DoButton               matlab.ui.control.Button
        CombinationSymmetry_NewstateinworkflowACheckBox  matlab.ui.control.CheckBox
        AmaterialdomainBbackgrounddomain1background0otherwiseLabel  matlab.ui.control.Label
        IndexbasedPanel                 matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        CombinationIndex_NewstateinworkflowACheckBox  matlab.ui.control.CheckBox
        Combination_index_DoButton      matlab.ui.control.Button
        Combination_Index_LabelXEditField  matlab.ui.control.NumericEditField
        LabelXEditFieldLabel            matlab.ui.control.Label
        Combination_Index_LabelYEditField  matlab.ui.control.NumericEditField
        LabelYEditFieldLabel            matlab.ui.control.Label
        Combination_Index_ChoiceDropDown  matlab.ui.control.DropDown
        ChoiceDropDownLabel             matlab.ui.control.Label
        Combination_ArithmeticPanel     matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Plus_AmaxBCheckBox              matlab.ui.control.CheckBox
        Difference_DropDown             matlab.ui.control.DropDown
        DifferenceButton                matlab.ui.control.Button
        AxBonlycheckformissingvoxelsCheckBox  matlab.ui.control.CheckBox
        Combination_multiply_DoButton   matlab.ui.control.Button
        Combination_plus_DoButton       matlab.ui.control.Button
        MergelabelsofStateAtolabelsofStateBPanel  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        Combination_merge_DoButton      matlab.ui.control.Button
        Mergeonlyifitincreasecircularity2DonlybetaCheckBox  matlab.ui.control.CheckBox
        ThresholdratioEditField         matlab.ui.control.NumericEditField
        ThresholdratioEditFieldLabel    matlab.ui.control.Label
        Combination_StatutIdleLabel     matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CombinationB_FromfileDropDown   matlab.ui.control.DropDown
        FromfileDropDownLabel           matlab.ui.control.Label
        CombinationA_FromfileDropDown   matlab.ui.control.DropDown
        FromfileLabel                   matlab.ui.control.Label
        ChoosetheoperationtoperformLabel  matlab.ui.control.Label
        CombinationB_DimensionLabel     matlab.ui.control.Label
        CombinationA_DimensionLabel     matlab.ui.control.Label
        StateBLabel                     matlab.ui.control.Label
        CombinationB_FromstateDropDown  matlab.ui.control.DropDown
        FromstateDropDown_3Label        matlab.ui.control.Label
        CombinationA_FromstateDropDown  matlab.ui.control.DropDown
        FromstateDropDown_2Label        matlab.ui.control.Label
        CombinationB_FromworkflowDropDown  matlab.ui.control.DropDown
        FromworkflowDropDown_2Label     matlab.ui.control.Label
        CombinationA_FromworkflowDropDown  matlab.ui.control.DropDown
        FromworkflowDropDownLabel_2     matlab.ui.control.Label
        StateALabel                     matlab.ui.control.Label
        SelecttwostatesAandBfromthesameordifferentworkflowsLabel  matlab.ui.control.Label
        ComparisonsTab                  matlab.ui.container.Tab
        Comparison_GridLayout           matlab.ui.container.GridLayout
        Comparison_CleartableButton     matlab.ui.control.Button
        Comparison_VisualizeButton      matlab.ui.control.Button
        ComparisontypeDropDown          matlab.ui.control.DropDown
        ComparisontypeDropDownLabel     matlab.ui.control.Label
        SelectthetypeofcomparisonandvisualizeitLabel  matlab.ui.control.Label
        SelectwhichfilestocompareLabel  matlab.ui.control.Label
        Comparison_UITable              matlab.ui.control.Table
        Comparison_AddfileButton        matlab.ui.control.Button
        Comparison_FromstateDropDown    matlab.ui.control.DropDown
        FromstateDropDown_2Label_2      matlab.ui.control.Label
        Comparison_FromworkflowDropDown  matlab.ui.control.DropDown
        FromworkflowDropDownLabel_3     matlab.ui.control.Label
        Comparison_FromfileDropDown     matlab.ui.control.DropDown
        FromfileDropDownLabel_3         matlab.ui.control.Label
        SelectfilesLabel                matlab.ui.control.Label
        MacroTab                        matlab.ui.container.Tab
        Macro_GridLayout                matlab.ui.container.GridLayout
        MacroWarningLabel               matlab.ui.control.Label
        EditmacroadvancedusersButton    matlab.ui.control.Button
        Macro_currentcalc_naLabel       matlab.ui.control.Label
        Macro_CurrentcalculationLabel   matlab.ui.control.Label
        Macro_CalculationmodeLabel      matlab.ui.control.Label
        Macro_AddacalculationButton     matlab.ui.control.StateButton
        MemorydepthEditField_2          matlab.ui.control.NumericEditField
        MemorydepthEditField_2Label     matlab.ui.control.Label
        Macro_IdleLabel                 matlab.ui.control.Label
        Macro_currentaction_name_Label  matlab.ui.control.Label
        Macro_currentifle_name_Label    matlab.ui.control.Label
        Macro_CurrentactionLabel        matlab.ui.control.Label
        Macro_CurrentfileLabel          matlab.ui.control.Label
        CombinationoperationsfromdifferentfilesinamacroLabel  matlab.ui.control.Label
        Macro_SavDropDown               matlab.ui.control.DropDown
        SaveLabel                       matlab.ui.control.Label
        Macro_ToOptionsDropDown         matlab.ui.control.DropDown
        ToLabel                         matlab.ui.control.Label
        Macro_TofileDropDown            matlab.ui.control.DropDown
        TofileDropDownLabel             matlab.ui.control.Label
        Macro_FromfileDropDown          matlab.ui.control.DropDown
        FromfileDropDownLabel_2         matlab.ui.control.Label
        NumberofstatesthatwillbecreatedwiththemacronaLabel  matlab.ui.control.Label
        Macro_TostateDropDown           matlab.ui.control.DropDown
        TostateDropDownLabel            matlab.ui.control.Label
        Macro_ToworkflowDropDown        matlab.ui.control.DropDown
        ToworkflowDropDownLabel         matlab.ui.control.Label
        ApplymacroButton                matlab.ui.control.Button
        NomacroloadedLabel              matlab.ui.control.Label
        ClicktoimportmacroButton        matlab.ui.control.Button
        Option2loadamacroLabel          matlab.ui.control.Label
        MacroNameEditField              matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        SavemacroButton                 matlab.ui.control.Button
        ClearmacroButton                matlab.ui.control.Button
        Macro_UITable                   matlab.ui.control.Table
        Macro_EndatstateDropDown        matlab.ui.control.DropDown
        EndatstateDropDownLabel         matlab.ui.control.Label
        Macro_StartatstateDropDown      matlab.ui.control.DropDown
        StartatstateDropDownLabel       matlab.ui.control.Label
        Macro_FromworkflowDropDown      matlab.ui.control.DropDown
        FromworkflowDropDownLabel       matlab.ui.control.Label
        Macro_AddastepButton            matlab.ui.control.Button
        Option1createamacroLabel        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        VisualizationTextArea           matlab.ui.control.TextArea
        VisualizationTextAreaLabel      matlab.ui.control.Label
        DatatypeTextArea                matlab.ui.control.TextArea
        DatatypeTextAreaLabel           matlab.ui.control.Label
        HowtoTextArea                   matlab.ui.control.TextArea
        HowtoTextAreaLabel              matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Label_13                        matlab.ui.control.Label
        StatesTextArea                  matlab.ui.control.TextArea
        StatesTextAreaLabel             matlab.ui.control.Label
        OperationsTextArea              matlab.ui.control.TextArea
        OperationsTextAreaLabel         matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Label_40                        matlab.ui.control.Label
        IfyouuselocalerositionorlocaldilationActionsLabel_2  matlab.ui.control.Label
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Label_39                        matlab.ui.control.Label
        IfyouuselocalerositionorlocaldilationActionsLabel  matlab.ui.control.Label
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        matboxversion = '2.0b Beta July 2025';
        
        importpath = [];
        savefolder = [];
        file = [];
        workflownames = [];
        active_f = [];
        active_w = [];
        active_s = [];
        current_channel = [];
        
        Import_PathName = [];
        Import_FileName = [];

        % Actions
        act = [];
        par = [];
        t =[];

        % Comparison
        comp_index = [];

        % Macro
        macro_fct = [];
        macro_p = [];
        macro_fctname = [];
        n_calculation = 0;
        macro_calculation = [];
        macro_act = [];
        macro_cal = [];

        change_quickstate = [];
        previous_sz = [];

        fontname = 'Times new roman';
    end
    
    methods (Access = private)
        
        
        function [] = Update_newactive_state(app,propagate)
            % Select correct table
            app.States_UITable.Data = app.file(app.active_f).workflow(app.active_w).statetable;

            % Deactivate
            s = uistyle('BackgroundColor',[0.95 0.95 0.95]);
            app.States_UITable.Data(app.active_s,end) = {false};
            addStyle(app.States_UITable,s,'row',app.active_s);
            
            % Update active state
            app.active_s = app.active_s+1;
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;

            % Overwritte subsequence states, if any
            [n_state,~] = size(app.States_UITable.Data);
            if n_state>=app.active_s
                for k=n_state:-1:app.active_s+1
                    app.file(app.active_f).workflow(app.active_w).state(k).M = [];
                end
                tmp = app.States_UITable.Data;
                tmp(app.active_s:end,:)=[];
                app.States_UITable.Data=tmp;           
            end     

            % Update States tab
            sz = size(M);
            dimension = length(sz);
            if dimension == 2
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
            else
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
            end
            r = {[num2str(min(min(min(M)))) '-'  num2str(max(max(max(M))))]};
            tmp = whos('M');
            m = {[tmp.class ', ' num2str(tmp.bytes*9.53674e-7,'%1.1f') 'MB']};

            % Data
            data_type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
            if strcmp(data_type,'Grey level') || strcmp(data_type,'Channel')
                vf = {'n/a'};
                ni = {'n/a'};
            elseif strcmp(data_type,'Segmented (phase)')
                ni = {'n/a'};
                [~,str] = app.Segmentation_update_volumefraction(M, false);
                vf = {str};
            elseif strcmp(data_type,'Segmented (instance)')
                vf = {'n/a'};
                ni = {num2str(length(unique(M)-1),'%i')};
            end

            app.States_UITable.Data = [app.States_UITable.Data; [{num2str(app.active_s)} {app.act} {app.par} {''} {false} {''} {[num2str(app.t,'%1.1f') 's']} {data_type} r vf ni s m {false} {true}]];
            s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
            addStyle(app.States_UITable,s,'row',app.active_s);
            [n_state,~] = size(app.States_UITable.Data);

            % Memory depth
            if n_state>1
                s_notinmemory = uistyle('BackgroundColor',[0.95 0.95 0.95]);
                s_inmemory = uistyle('BackgroundColor',[0.3290    0.7130    1.0000]);
                for k=1:1:n_state
                    if k~=app.active_s
                        if isempty( app.file(app.active_f).workflow(app.active_w).state(k).M)
                            addStyle(app.States_UITable,s_notinmemory,'row',k);
                            app.States_UITable.Data(k,end-2) = {'No'};
                        else
                            if k>=n_state-app.MemorydepthEditField.Value || cell2mat(app.States_UITable.Data(k,end-1))
                                addStyle(app.States_UITable,s_inmemory,'row',k);
                            else
                                app.file(app.active_f).workflow(app.active_w).state(k).M = [];
                                addStyle(app.States_UITable,s_notinmemory,'row',k);
                                app.States_UITable.Data(k,end-2) = {'No'};
                            end
                        end
                    end
                end
            end

            app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;

            % % Update from state possible
            % State tab
            from_w = app.FromexistingworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(app.active_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(app.active_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.FromstateDropDown.Items = tmp;
            app.FromstateDropDown.Value = app.FromstateDropDown.Items(1);  
            % Propagate
            if propagate
                % % Combination tab
                app.CombinationA_FromfileDropDownValueChanged;
                app.CombinationB_FromfileDropDownValueChanged;
                % Macro tab
                app.Macro_TofileDropDownValueChanged;
            end  

            app.Update_tables_dimensions

            n_workflow = length(app.SelectactiveworkflowDropDown.Items);
            app.Mosaic_FromworkflowEditField.Value = 1;
            app.Mosaic_toworkflowEditField.Value = n_workflow;            
        end
        
        
        function [] = Update_tables_dimensions(app)
            sz = size( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);
            dimension = length(sz);
            if app.RelativedimensionCheckBox.Value
                tmp = table([1:1:dimension]',sz',zeros(dimension,1),ones(dimension,1),ones(dimension,1));
            else
                tmp = table([1:1:dimension]',sz',ones(dimension,1),sz',sz');
            end
            app.ROI_crop_UITable.Data=tmp;   

            if dimension==2
                app.Flipswap_DropDown.Items = {'Flip axis 1' 'Flip axis 2' 'Swap axis 1 with axis 2'};
                app.AxisofrotationDropDown.Items = {'Axis 3'};
                app.ROI_visu_AxisDropDown.Items = {'3'};
                app.ROI_visu_SliceSlider.Enable = 'off'; app.ROI_visu_SliceSlider.Visible = 'off';
                app.ROI_SliceEditField.Enable = 'off'; app.ROI_minus_Button.Enable = 'off'; app.ROI_plus_Button.Enable = 'off';
                app.Anisotropicscaling_UITable.Data = [[1;2] [1;1]];
                app.Anisotropic_ApplyDropDown.Items = {'Single slice'}; app.Anisotropic_ApplyDropDownValueChanged;
                app.ROI_Buffer_axe3_EditField.Enable = 'off';
                app.ROI_Rotationmaxdeg_x_EditField.Enable = 'off'; app.ROI_Rotationmaxdeg_y_EditField.Enable = 'off';
                app.ROI_UIAxes.XLabel.String = 'Axe 2';
                app.ROI_UIAxes.YLabel.String = 'Axe 1';
            else
                app.Flipswap_DropDown.Items = {'Flip axis 1' 'Flip axis 2' 'Flip axis 3' 'Swap axis 1 with axis 2' 'Swap axis 1 with axis 3' 'Swap axis 2 with axis 3'};
                app.AxisofrotationDropDown.Items = {'Axis 1' 'Axis 2' 'Axis 3'};
                if isempty(app.ROI_visu_AxisDropDown.Items) || length(app.ROI_visu_AxisDropDown.Items)==1
                    app.ROI_visu_AxisDropDown.Items = {'1' '2' '3'};
                    app.ROI_visu_AxisDropDown.Value = app.ROI_visu_AxisDropDown.Items(3);
                end
                app.ROI_visu_SliceSlider.Enable = 'on'; app.ROI_visu_SliceSlider.Visible = 'on';
                app.ROI_SliceEditField.Enable = 'on'; app.ROI_minus_Button.Enable = 'on'; app.ROI_plus_Button.Enable = 'on';
                app.Anisotropicscaling_UITable.Data = [[1;2;3] [1;1;1]];
                app.Anisotropic_ApplyDropDown.Items = {'Full volume' 'Slice per slice, along axis 1' 'Slice per slice, along axis 2' 'Slice per slice, along axis 3'};
                app.Anisotropic_ApplyDropDownValueChanged;
                app.ROI_Buffer_axe3_EditField.Enable = 'on';
                app.ROI_Rotationmaxdeg_x_EditField.Enable = 'on'; app.ROI_Rotationmaxdeg_y_EditField.Enable = 'on';
            end
            app.ROI_visu_AxisDropDownValueChanged;

        end
        
        function [A_file, A_workflow, A_state, B_file, B_workflow, B_state] = Check_dimension_combinations(app)
            A_file = app.CombinationA_FromfileDropDown.ValueIndex;
            A_workflow = app.CombinationA_FromworkflowDropDown.ValueIndex;
            A_state = [];
            if ~isempty(app.CombinationA_FromstateDropDown.Value)
                A_state = str2num(app.CombinationA_FromstateDropDown.Value);
                szA = size( app.file(A_file).workflow(A_workflow).state(A_state).M );
                if length(szA) == 2
                    app.CombinationA_DimensionLabel.Text = {['Size: ' num2str(szA(1),'%i') ' x ' num2str(szA(2),'%i')]};
                else
                    app.CombinationA_DimensionLabel.Text = {['Size: ' num2str(szA(1),'%i') ' x ' num2str(szA(2),'%i') ' x ' num2str(szA(3),'%i')]};
                end
            end

            B_file = app.CombinationB_FromfileDropDown.ValueIndex;
            B_workflow = app.CombinationB_FromworkflowDropDown.ValueIndex;
            B_state = [];
            if ~isempty(app.CombinationB_FromstateDropDown.Value)
                B_state = str2num(app.CombinationB_FromstateDropDown.Value);
                szB = size( app.file(B_file).workflow(B_workflow).state(B_state).M );
                if length(szB) == 2
                    app.CombinationB_DimensionLabel.Text = {['Size: ' num2str(szB(1),'%i') ' x ' num2str(szB(2),'%i')]};
                else
                    app.CombinationB_DimensionLabel.Text = {['Size: ' num2str(szB(1),'%i') ' x ' num2str(szB(2),'%i') ' x ' num2str(szB(3),'%i')]};
                end
            end

            if ~isempty(A_state) && ~isempty(B_state)
                if length(szA)==length(szB) && sum(szA==szB)==length(szA)
                    app.CombinationA_DimensionLabel.FontColor = 'k'; app.CombinationB_DimensionLabel.FontColor = 'k';
                    app.Combination_ArithmeticPanel.Enable = 'on';
                    app.MergelabelsofStateAtolabelsofStateBPanel.Enable = 'on';
                    app.IndexbasedPanel.Enable = 'on';
                    app.SymmetryPanel.Enable = 'on';
                    app.HistogramequalizationPanel.Enable = 'on';
                    app.AssignvoxeltonearestclusterPanel.Enable = 'on';
                    app.ConcatenatePanel.Enable = 'on';
                else
                    app.CombinationA_DimensionLabel.FontColor = 'r'; app.CombinationB_DimensionLabel.FontColor = 'r';
                    app.Combination_ArithmeticPanel.Enable = 'off';
                    app.MergelabelsofStateAtolabelsofStateBPanel.Enable = 'off';
                    app.IndexbasedPanel.Enable = 'off';
                    app.SymmetryPanel.Enable = 'off';
                    app.HistogramequalizationPanel.Enable = 'on';
                    app.AssignvoxeltonearestclusterPanel.Enable = 'off';
                    app.ConcatenatePanel.Enable = 'on';
                end
            else
                app.Combination_ArithmeticPanel.Enable = 'off';
                app.MergelabelsofStateAtolabelsofStateBPanel.Enable = 'off';
                app.IndexbasedPanel.Enable = 'off';
                app.SymmetryPanel.Enable = 'off';
                app.HistogramequalizationPanel.Enable = 'off';
                app.AssignvoxeltonearestclusterPanel.Enable = 'off';
                app.ConcatenatePanel.Enable = 'off';                
            end
        end

        
        function [fileA,fileB] = Check_synchronicity(app)
            if strcmp(app.Multiplefiles_options_DropDown.Value,'On all files, on the same workflow index and on the same active state index')
                n_file = length(app.SelectactivefileDropDown.Items);
                for kfile = 1:1:n_file
                    if length(app.file(kfile).workflow) < app.active_w ||  length(app.file(kfile).workflow(app.active_w).state)<app.active_s || isempty( app.file(kfile).workflow(app.active_w).state(app.active_s).M  )
                        f = warndlg('Files are not synchronized!','Warning');
                        fileA = [];
                        fileB = [];
                        return
                    end
                    fileA = 1;
                    fileB = n_file;
                end
            else
                fileA = app.active_f;
                fileB = app.active_f;
            end  
        end
        
        function [vf,str] = Segmentation_update_volumefraction(app,M,excludebackground)
            labels = unique(M);
            nvoxel = numel(M);
            nlabel = length(labels);
            vf = zeros(nlabel,3);
            str = [];
            for k=1:1:nlabel
                vf(k,1) = labels(k);
                vf(k,2) = sum(sum(sum( M==vf(k,1) )))/nvoxel;
                str = [str num2str(vf(k,1),'%i') '=' num2str(vf(k,2),'%1.3f') ' '];
            end

            if excludebackground
                str = [str, ', and excluding background: '];
                labels(labels==0)=[];
                nvoxel = numel(M) - sum(sum(sum(M==0)));
                s = 1 - vf(1,2);
                for k=2:1:nlabel
                    vf(k,3) = vf(k,2)/s;
                    str = [str num2str(vf(k,1),'%i') '=' num2str(vf(k,3),'%1.3f') ' '];
                end
            end
        end

        
        function [] = Update_ROI_view(app)
            axe_view = str2num(app.ROI_visu_AxisDropDown.Value);
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            sz = size(M);
            dimension = length(sz);
            if dimension == 3
                z = round(app.ROI_visu_SliceSlider.Value);
                if axe_view==1
                    M = squeeze(M(z,:,:));
                    imagesc(app.ROI_UIAxes, M);
                elseif axe_view==2
                    M = squeeze(M(:,z,:));
                    imagesc(app.ROI_UIAxes, M);
                elseif axe_view==3
                    imagesc(app.ROI_UIAxes, M(:,:,z));
                end
            else
                imagesc(app.ROI_UIAxes, M);
            end

            colormap(app.ROI_UIAxes,app.ROI_ColormapDropDown.Value)
            axis(app.ROI_UIAxes,'equal');
            axis(app.ROI_UIAxes,'tight');


            if app.ShowlabelsegmentedimageonlyCheckBox.Value
                if dimension == 3 && axe_view==3
                    M = M(:,:,z);
                end
                sz_slice = size(M);
                unis = unique(M);
                unis(unis==0)=[]; % Remove background
                n_label = length(unis);
                for k=1:n_label
                    idx = find(M==unis(k));
                    [IX,IY] = ind2sub(sz_slice,idx);
                    hText = text(app.ROI_UIAxes,mean(IY),mean(IX),num2str(unis(k)),'Color',app.LabelcolorColorPicker.Value,'FontSize',app.LabelsizeEditField.Value);
                end
            end

            % Plot ROI line
            x0 = app.ROI_crop_UITable.Data(:,3).Variables;
            x1 = app.ROI_crop_UITable.Data(:,4).Variables;
            rel = app.RelativedimensionCheckBox.Value;
            if rel
                a = (sz-1)./(1-0);
                b = 1;
                x0 = round(a'.*x0 + b');
                x1 = round(a'.*x1 + b');
            end
            if sum(x1==sz')~=length(x1) || max(x0)~=1 % Only plot if cropping parameters
                hold(app.ROI_UIAxes,'on');
                if axe_view==3 && (x0(2)>1 || x1(2)<sz(2) || x0(1)>1 || x1(1)<sz(1))
                    plot(app.ROI_UIAxes,[x0(2)-0.5 x1(2)+0.5],[x0(1)-0.5 x0(1)-0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(2)-0.5 x0(2)-0.5],[x0(1)-0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x1(2)+0.5 x1(2)+0.5],[x0(1)-0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(2)-0.5 x1(2)+0.5],[x1(1)+0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                elseif axe_view==2 && (x0(3)>1 || x1(3)<sz(3) || x0(1)>1 || x1(1)<sz(1))
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x1(3)+0.5],[x0(1)-0.5 x0(1)-0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x0(3)-0.5],[x0(1)-0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x1(3)+0.5 x1(3)+0.5],[x0(1)-0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x1(3)+0.5],[x1(1)+0.5 x1(1)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                elseif axe_view==1 && (x0(3)>1 || x1(3)<sz(3) || x0(2)>1 || x1(2)<sz(2))
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x1(3)+0.5],[x0(2)-0.5 x0(2)-0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x0(3)-0.5],[x0(2)-0.5 x1(2)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x1(3)+0.5 x1(3)+0.5],[x0(2)-0.5 x1(2)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                    plot(app.ROI_UIAxes,[x0(3)-0.5 x1(3)+0.5],[x1(2)+0.5 x1(2)+0.5 ],'LineWidth',2,'LineStyle',"--",'Color','r');
                end
                hold(app.ROI_UIAxes,'off');

            end

            % Plot rotation lines
            angle_deg = app.AngledegreesSpinner.Value;
            if angle_deg~=0
                axis_rotation = app.AxisofrotationDropDown.Value;
                axe_view = str2num(app.ROI_visu_AxisDropDown.Value);
                nline=32;
                hold(app.ROI_UIAxes,'on');
                if strcmp(axis_rotation,'Axis 1') && axe_view==1
                    xlimsav=[1 sz(3)]; ylimsav=[1 sz(2)];
                    y0s=unique([linspace(-3*sz(2),1,nline+2) linspace(1,3*sz(2),nline+2)]);
                    for kline=1:1:length(y0s)
                        y0 = round(y0s(kline));
                        y1 = y0 - tan(deg2rad(angle_deg))*(sz(3)-1);
                        plot(app.ROI_UIAxes,[1 sz(3)],[y0 y1],'LineWidth',2,'LineStyle',"--",'Color','r');
                    end
                    xlim(app.ROI_UIAxes,xlimsav);
                    ylim(app.ROI_UIAxes,ylimsav);

                elseif strcmp(axis_rotation,'Axis 2') && axe_view==2
                    xlimsav=[1 sz(3)]; ylimsav=[1 sz(1)];
                    y0s=unique([linspace(-3*sz(1),1,nline+2) linspace(1,3*sz(1),nline+2)]);
                    for kline=1:1:length(y0s)
                        y0 = round(y0s(kline));
                        y1 = y0 - tan(deg2rad(angle_deg))*(sz(3)-1);
                        plot(app.ROI_UIAxes,[1 sz(3)],[y0 y1],'LineWidth',2,'LineStyle',"--",'Color','r');
                    end
                    xlim(app.ROI_UIAxes,xlimsav);
                    ylim(app.ROI_UIAxes,ylimsav);

                elseif strcmp(axis_rotation,'Axis 3') && axe_view==3
                    xlimsav=[1 sz(2)]; ylimsav=[1 sz(1)];
                    y0s=unique([linspace(-3*sz(1),1,nline+2) linspace(1,3*sz(1),nline+2)]);
                    for kline=1:1:length(y0s)
                        y0 = round(y0s(kline));
                        y1 = y0 - tan(deg2rad(angle_deg))*(sz(2)-1);
                        plot(app.ROI_UIAxes,[1 sz(2)],[y0 y1],'LineWidth',2,'LineStyle',"--",'Color','r');
                    end
                    xlim(app.ROI_UIAxes,xlimsav);
                    ylim(app.ROI_UIAxes,ylimsav);
                end
                hold(app.ROI_UIAxes,'off');
            end


        end
        
        function [] = macro_add_calculation(app)
            app.n_calculation = app.n_calculation + 1;
            app.macro_calculation(app.n_calculation).macro_fct = app.macro_fct;
            app.macro_calculation(app.n_calculation).macro_p = app.macro_p;
            app.macro_calculation(app.n_calculation).macro_fctname = app.macro_fctname;

            if ~isempty(app.Macro_UITable.Data)
                [step,~] = size(app.Macro_UITable.Data);
            else
                step = 0;
            end
            step = step+1;
            newline = [{[num2str(step,'%i') ', ' app.macro_fctname]}, {'n/a'}, {'n/a'}, {'n/a'}, {'n/a'}];
            app.Macro_UITable.Data = [app.Macro_UITable.Data; newline];
            app.ClearmacroButton.Enable = 'on';
            app.SavemacroButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable UI for all elements except for the Import tab
            children = get(app.GridLayout_States,'Children');
            set(children,'Enable','off');
            children = get(app.Macro_GridLayout,'Children');
            set(children,'Enable','off');        

            children = get(app.ROI_GridLayout,'Children');
            %set(children,'Enable','off');                 
            child_handles = allchild(app.ROI_GridLayout); % Get all children
            [n_children,~]=size(child_handles);
            for k=1:1:n_children
                if isprop(child_handles(k),'Enable')
                    child_handles(k).Enable='off';
                else
                    child_handles(k).Visible='off';
                end
            end


            children = get(app.GLfilter_GridLayout,'Children');
            set(children,'Enable','off');  
            children = get(app.SEGfilter_GridLayout,'Children');
            set(children,'Enable','off');  

            children = get(app.Semanticsegmentation_GridLayout,'Children');
            set(children,'Enable','off');   
            children = get(app.Instancesegmentation_GridLayout,'Children');
            set(children,'Enable','off'); 
            children = get(app.Randomize_GridLayout,'Children');
            set(children,'Enable','off');            
            children = get(app.GlobalErosionDilation_GridLayout,'Children');
            set(children,'Enable','off'); 
            children = get(app.LocalErosion_GridLayout,'Children');
            set(children,'Enable','off');       
            children = get(app.LocalDilation_GridLayout,'Children');
            set(children,'Enable','off');              
            children = get(app.GridLayout_Channel1,'Children');
            set(children,'Enable','off'); 
            children = get(app.GridLayout_Channel2,'Children');
            set(children,'Enable','off'); 
            children = get(app.GridLayout_Channel3,'Children');
            set(children,'Enable','off');             
            children = get(app.Contrast1_GridLayout10,'Children');
            set(children,'Enable','off');     
            children = get(app.Contrast2_GridLayout10,'Children');
            set(children,'Enable','off');      
            children = get(app.Scaling_GridLayout,'Children');
            set(children,'Enable','off');  

            children = get(app.Multiplefiles_options_GridLayout,'Children');
            set(children,'Enable','off');  

            children = get(app.Combination_GridLayout,'Children');
            set(children,'Enable','off');    

            children = get(app.Comparison_GridLayout,'Children');
            set(children,'Enable','off');     

            children = get(app.Calc_Grey_GridLayout,'Children');
            set(children,'Enable','off');      
            children = get(app.Calc_Seg_GridLayout,'Children');
            set(children,'Enable','off');     

            children = get(app.Clusters_GridLayout,'Children');
            set(children,'Enable','off');                
            children = get(app.ClusterInteractions_GridLayout,'Children');
            set(children,'Enable','off');   
            children = get(app.Pulverize_GridLayout,'Children');
            set(children,'Enable','off');   
                       
            children = get(app.DataType_GridLayout,'Children');
            set(children,'Enable','off');  

            app.ROI_ColormapDropDown.Items = {'gray' 'bone' 'copper' 'turbo' 'parula'};
            app.Calc_ColrmapDropDown.Items = {'turbo' 'parula' 'cool' 'copper'};

            app.Custom_Connectivity_UITable_2.Data = [{'first slice';'last slice'} {false; false} {false; false} {true; true}];            
        end

        % Button pushed function: SelectSavefolder_Button
        function SelectSavefolder_ButtonPushed(app, event)
            selpath = uigetdir(app.Savefolder_Label.Text,'Select save folder');
            if selpath~=0
                app.savefolder=selpath;
                app.Savefolder_Label.Text = app.savefolder;                
            end
        end

        % Cell edit callback: States_UITable
        function States_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            [~,ncol] = size(app.States_UITable.Data);
            if indices(2)==ncol % Active state
                [n_state,~] = size(app.States_UITable.Data);
                n_workflow = length(app.file(app.active_f).workflow);
                if n_state==1 && n_workflow==1
                    app.States_UITable.Data(1,end) = {true};
                else
                    if ~isempty( app.file(app.active_f).workflow(app.active_w).state(indices(1)).M )
                        % De-activate
                        s_inmemory = uistyle('BackgroundColor',[0.3290    0.7130    1.0000]);
                        app.States_UITable.Data(app.active_s,end) = {false};
                        addStyle(app.States_UITable,s_inmemory,'row',app.active_s);

                        % Activate
                        app.active_s = indices(1);
                        s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                        app.States_UITable.Data(app.active_s,end) = {true};
                        addStyle(app.States_UITable,s,'row',app.active_s);
                    else
                        app.States_UITable.Data(indices(1),end) = {~newData};
                    end        
                end
                app.Update_tables_dimensions;

            elseif indices(2)==ncol-1 % Keep in memory
                if newData % Was false
                    if isempty( app.file(app.active_f).workflow(app.active_w).state(indices(1)).M )
                        app.States_UITable.Data(indices(1),ncol-1) = {false};
                    end
                end
            end
            app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;

            tmp = app.file(app.active_f).workflow(app.active_w).statetable;
            app.active_s = find(double(cell2mat(tmp(:,end))));   
        end

        % Value changed function: SelectactiveworkflowDropDown
        function SelectactiveworkflowDropDownValueChanged(app, event)
            app.active_w = app.SelectactiveworkflowDropDown.ValueIndex;
            tmp = app.file(app.active_f).workflow(app.active_w).statetable;
            app.active_s = find(double(cell2mat(tmp(:,end))));   
           
            app.States_UITable.Data = app.file(app.active_f).workflow(app.active_w).statetable;
            [n_state,~] = size(app.States_UITable.Data);
            for k=1:1:n_state
                if k==app.active_s
                    s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                elseif ~isempty( app.file(app.active_f).workflow(app.active_w).state(k).M)
                    s = uistyle('BackgroundColor',[0.3290    0.7130    1.0000]);
                else
                    s = uistyle('BackgroundColor',[0.95 0.95 0.95]);
                end
                addStyle(app.States_UITable,s,'row',k);
            end

            if app.active_w>1
                app.Delete_WorkflowButton.Enable = 'on';
            else
                app.Delete_WorkflowButton.Enable = 'off';
            end

            app.Update_tables_dimensions;
            app.ROI_StateLabel.Text = ['State #' num2str(app.active_s) ': ' char(tmp(app.active_s,2))];
        end

        % Value changed function: RenamecurrentworkflowoptionalEditField
        function RenamecurrentworkflowoptionalEditFieldValueChanged(app, event)
            value = app.RenamecurrentworkflowoptionalEditField.Value;
            if sum(ismember(app.FromexistingworkflowDropDown.Items,value))
                f = warndlg('Name already selected! Choose another one','Warning');
            else
                app.SelectactiveworkflowDropDown.Items(app.active_w) = {value};
                app.SelectactiveworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Items(app.active_w);
                app.workflownames(app.active_f).names(app.active_w) = {value};

                % Rename in other popup menu
                app.FromexistingworkflowDropDown.Items = app.workflownames(app.active_f).names;
                app.CombinationA_FromfileDropDownValueChanged;
                app.CombinationB_FromfileDropDownValueChanged;
                app.Macro_FromfileDropDownValueChanged;
                app.Macro_TofileDropDownValueChanged;
            end
        end

        % Button pushed function: CreateanewworkflowButton
        function CreateanewworkflowButtonPushed(app, event)
            tic
            from_w = app.FromexistingworkflowDropDown.ValueIndex;
            from_s = str2num(char(app.FromstateDropDown.Value));
            workflow_name = char(app.FromexistingworkflowDropDown.Items(from_w));
            app.active_w = length(app.file(app.active_f).workflow)+1;
            app.active_s = 1;
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = app.file(app.active_f).workflow(from_w).state(from_s).M;
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = app.file(app.active_f).workflow(from_w).state(from_s).type;
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Create new workflow';
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = [from_w from_s];            
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Create new workflow';
            app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = ['From ' workflow_name ', state# ' num2str(from_s,'%i')];                

            % Update States tab
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            sz = size(M);
            dimension = length(sz);
            if dimension == 2
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
            else
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
            end
            r = {[num2str(min(min(min(M)))) '-'  num2str(max(max(max(M))))]};
            tmp = whos('M');
            m = {[tmp.class ', ' num2str(tmp.bytes*9.53674e-7,'%1.1f') 'MB']};
            
            % Data
            data_type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
            if strcmp(data_type,'Grey level') || strcmp(data_type,'Channel')
                vf = {'n/a'};
                ni = {'n/a'};
            elseif strcmp(data_type,'Segmented (phase)')
                ni = {'n/a'};
                [~,str] = app.Segmentation_update_volumefraction(M, false);
                vf = {str};
            elseif strcmp(data_type,'Segmented (instance)')
                vf = {'n/a'};
                ni = {num2str(length(unique(M)-1),'%i')};
            end

            app.States_UITable.Data = [{num2str(app.active_s)} {'Create new workflow'} {['From ' workflow_name ', state# ' num2str(from_s,'%i')]} {''} {false} {''} {[num2str(toc,'%1.1f') 's']} {data_type} r vf ni s m {false} {true}];
            s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
            addStyle(app.States_UITable,s,'row',app.active_s);

            app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;

            % Workflow popup choice update
            workflow_name = ['Workflow' num2str(app.active_w,'%i')];
            while sum(ismember(app.FromexistingworkflowDropDown.Items,workflow_name))
                workflow_name = [workflow_name '_bis'];
            end
            app.workflownames(app.active_f).names(app.active_w) = {workflow_name};      
            
            % Propagate
            app.SelectactiveworkflowDropDown.Items = app.workflownames(app.active_f).names;
            app.SelectactiveworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Items(end);
            app.SelectactiveworkflowDropDownValueChanged;
            
            app.FromexistingworkflowDropDown.Items = app.SelectactiveworkflowDropDown.Items;

            app.CombinationA_FromfileDropDownValueChanged;
            app.CombinationB_FromfileDropDownValueChanged;
            app.Macro_FromfileDropDownValueChanged;
            app.Macro_TofileDropDownValueChanged;
        end

        % Double-clicked callback: States_UITable
        function States_UITableDoubleClicked(app, event)
            doubleclikcedstate = event.InteractionInformation.DisplayRow;
            voxelsize = 1;
            voxelsize_unitname = '';
            arrayunit = [];
            if isempty(char(app.States_UITable.Data(doubleclikcedstate,4)))
                name = [char(app.SelectactivefileDropDown.Value) ', ' char(app.SelectactiveworkflowDropDown.Value) ', state#' num2str(cell2mat(app.States_UITable.Data(doubleclikcedstate,1))) ': ' char(app.States_UITable.Data(doubleclikcedstate,2))];
            else
                name = char(app.States_UITable.Data(doubleclikcedstate,4));
                if app.InsertfilenameincustomnameCheckBox.Value
                    name = [app.SelectactivefileDropDown.Value '_' name];
                end
            end
            if ~isempty(doubleclikcedstate) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(doubleclikcedstate).M)
                size( app.file(app.active_f).workflow(app.active_w).state(doubleclikcedstate).M  )
                microstructure_visualization_slices(app.file(app.active_f).workflow(app.active_w).state(doubleclikcedstate).M, name, voxelsize, voxelsize_unitname, arrayunit);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Savechoice_Items = app.Savechoice_DropDown.Items;
            Savechoice_Value = app.Savechoice_DropDown.Value;
            app.Savechoice_DropDown.Items = {'Please wait...'}; app.Savechoice_DropDown.Value = 'Please wait...';
            app.Savechoice_DropDown.Enable = 'off';
            app.SaveButton.Enable = 'off';
            pause(0.1);
            
            Table_info_data = {'Date', convertCharsToStrings(char(datetime('now','TimeZone','local','Format','eeee, HH:mm:ss Z, MMMM d, y')));
                'Username', convertCharsToStrings(getenv('USERNAME'));
                'Computer', convertCharsToStrings(getenv('COMPUTERNAME'));
                'OS',convertCharsToStrings(getenv('OS'));
                'MATLAB version',convertCharsToStrings(version);
                'MATBOX version',convertCharsToStrings(app.matboxversion);};
            Table_info = table(Table_info_data(:,1),Table_info_data(:,2),...
                'VariableNames',{'Information','Value'});

            variablenames = app.States_UITable.ColumnName; 
            clear DATA_writetable

            if strcmp(Savechoice_Value,'Save active state')
                % Save file
                workflowname = ['WF' num2str(app.active_w) '_' char(app.SelectactiveworkflowDropDown.Value)];         
                if isempty(char( app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,4) ))
                    statename = ['S' num2str(app.active_s) '_' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
                else
                    statename = char( app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,4) );
                    if app.InsertfilenameincustomnameCheckBox.Value
                        statename = [app.SelectactivefileDropDown.Value '_' statename];
                    end
                end
                fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value),workflowname,[statename '.tif']);
                [filepath,~,~] = fileparts(fullpath);
                if ~exist(filepath,'dir')
                    mkdir(filepath);
                end
                function_save_tif(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M, fullpath);

                % Save parameters
                n_workflow = length(app.file(app.active_f).workflow);
                for sheetno=1:1:n_workflow
                    DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(app.active_f).names(sheetno))];
                    T = cell2table(app.file(app.active_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                    DATA_writetable.sheet(sheetno).table=T;
                end
                DATA_writetable.sheet(sheetno+1).name='Info';
                DATA_writetable.sheet(sheetno+1).table=Table_info;
                Function_Writetable(filepath,statename,DATA_writetable)

            elseif strcmp(Savechoice_Value,'Save all states from active workflow')
                workflowname = ['WF' num2str(app.active_w) '_' char(app.SelectactiveworkflowDropDown.Value)];
                [n_state,~] = size( app.file(app.active_f).workflow(app.active_w).statetable );
                % Save tif
                for k_s=1:1:n_state
                    if isempty(char( app.file(app.active_f).workflow(app.active_w).statetable(k_s,4) ))
                        statename = ['S' num2str(k_s) '_' char(app.file(app.active_f).workflow(app.active_w).statetable(k_s,2))];
                    else
                        statename = char( app.file(app.active_f).workflow(app.active_w).statetable(k_s,4) );
                        if app.InsertfilenameincustomnameCheckBox.Value
                            statename = [app.SelectactivefileDropDown.Value '_' statename];
                        end
                    end
                    fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value),workflowname,[statename '.tif']);
                    [filepath,~,~] = fileparts(fullpath);
                    if ~exist(filepath,'dir')
                        mkdir(filepath);
                    end
                    % Save file
                    function_save_tif(app.file(app.active_f).workflow(app.active_w).state(k_s).M, fullpath);
                end

                % Save parameters
                n_workflow = length(app.file(app.active_f).workflow);
                for sheetno=1:1:n_workflow
                    DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(app.active_f).names(sheetno))];
                    T = cell2table(app.file(app.active_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                    DATA_writetable.sheet(sheetno).table=T;
                end
                DATA_writetable.sheet(sheetno+1).name='Info';
                DATA_writetable.sheet(sheetno+1).table=Table_info;
                folderpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value),workflowname);
                Function_Writetable(folderpath,'Parameters',DATA_writetable)


            elseif strcmp(Savechoice_Value,'Save all states from active file')
                n_workflow = length(app.file(app.active_f).workflow);
                for k_w=1:1:n_workflow
                    workflowname = ['WF' num2str(k_w) '_' char(app.workflownames(app.active_f).names(k_w))];
                    [n_state,~] = size( app.file(app.active_f).workflow(k_w).statetable );
                    % Save tif
                    for k_s=1:1:n_state
                        if isempty(char( app.file(app.active_f).workflow(k_w).statetable(k_s,4) ))
                            statename = ['S' num2str(k_s) '_' char(app.file(app.active_f).workflow(k_w).statetable(k_s,2))];
                        else
                            statename = char( app.file(app.active_f).workflow(k_w).statetable(k_s,4) );
                            if app.InsertfilenameincustomnameCheckBox.Value
                                statename = [app.SelectactivefileDropDown.Value '_' statename];
                            end
                        end
                        fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value),workflowname,[statename '.tif']);
                        [filepath,~,~] = fileparts(fullpath);
                        if ~exist(filepath,'dir')
                            mkdir(filepath);
                        end
                        % Save file
                        function_save_tif(app.file(app.active_f).workflow(k_w).state(k_s).M, fullpath);
                    end
                end

                % Save parameters
                n_workflow = length(app.file(app.active_f).workflow);
                for sheetno=1:1:n_workflow
                    DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(app.active_f).names(sheetno))];
                    T = cell2table(app.file(app.active_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                    DATA_writetable.sheet(sheetno).table=T;
                end
                DATA_writetable.sheet(sheetno+1).name='Info';
                DATA_writetable.sheet(sheetno+1).table=Table_info;
                folderpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value));
                Function_Writetable(folderpath,'Parameters',DATA_writetable)

            elseif strcmp(Savechoice_Value,'Save only final state for each file')
                n_file = length(app.SelectactivefileDropDown.Items);
                for k_f = 1:1:n_file
                    k_w = length(app.file(k_f).workflow);
                        workflowname = ['WF' num2str(k_w) '_' char(app.workflownames(k_f).names(k_w))];
                        [k_s,~] = size( app.file(k_f).workflow(k_w).statetable );
                        % Save tif
                        if isempty(char( app.file(k_f).workflow(k_w).statetable(k_s,4) ))
                            statename = ['S' num2str(k_s) '_' char(app.file(k_f).workflow(k_w).statetable(k_s,2))];
                        else
                            statename = char( app.file(k_f).workflow(k_w).statetable(k_s,4) );
                            if app.InsertfilenameincustomnameCheckBox.Value
                                statename = [char(app.SelectactivefileDropDown.Items(k_f)) '_' statename];
                            end

                        end
                        fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)),workflowname,[statename '.tif']);
                        [filepath,~,~] = fileparts(fullpath);
                        if ~exist(filepath,'dir')
                            mkdir(filepath);
                        end
                        % Save file
                        function_save_tif(app.file(k_f).workflow(k_w).state(k_s).M, fullpath);

                    % Save parameters
                    n_workflow = length(app.file(k_f).workflow);
                    for sheetno=1:1:n_workflow
                        DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(k_f).names(sheetno))];
                        T = cell2table(app.file(k_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                        DATA_writetable.sheet(sheetno).table=T;
                    end
                    DATA_writetable.sheet(sheetno+1).name='Info';
                    DATA_writetable.sheet(sheetno+1).table=Table_info;
                    folderpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)));
                    Function_Writetable(folderpath,'Parameters',DATA_writetable)
                end

            elseif strcmp(Savechoice_Value,'Save all states from all files')
                n_file = length(app.SelectactivefileDropDown.Items);
                for k_f = 1:1:n_file
                    n_workflow = length(app.file(k_f).workflow);
                    for k_w=1:1:n_workflow
                        workflowname = ['WF' num2str(k_w) '_' char(app.workflownames(k_f).names(k_w))];
                        [n_state,~] = size( app.file(k_f).workflow(k_w).statetable );
                        % Save tif
                        for k_s=1:1:n_state
                            if isempty(char( app.file(k_f).workflow(k_w).statetable(k_s,4) ))
                                statename = ['S' num2str(k_s) '_' char(app.file(k_f).workflow(k_w).statetable(k_s,2))];
                            else
                                statename = char( app.file(k_f).workflow(k_w).statetable(k_s,4) );
                                if app.InsertfilenameincustomnameCheckBox.Value
                                    statename = [char(app.SelectactivefileDropDown.Items(k_f)) '_' statename];
                                end
                            end
                            fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)),workflowname,[statename '.tif']);
                            [filepath,~,~] = fileparts(fullpath);
                            if ~exist(filepath,'dir')
                                mkdir(filepath);
                            end
                            % Save file
                            function_save_tif(app.file(k_f).workflow(k_w).state(k_s).M, fullpath);
                        end
                    end

                    % Save parameters
                    n_workflow = length(app.file(k_f).workflow);
                    for sheetno=1:1:n_workflow
                        DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(k_f).names(sheetno))];
                        T = cell2table(app.file(k_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                        DATA_writetable.sheet(sheetno).table=T;
                    end
                    DATA_writetable.sheet(sheetno+1).name='Info';
                    DATA_writetable.sheet(sheetno+1).table=Table_info;
                    folderpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)));
                    Function_Writetable(folderpath,'Parameters',DATA_writetable)
                end

            elseif strcmp(Savechoice_Value,'Save all marked states')

                n_file = length(app.SelectactivefileDropDown.Items);
                for k_f = 1:1:n_file
                    n_workflow = length(app.file(k_f).workflow);
                    for k_w=1:1:n_workflow
                        workflowname = ['WF' num2str(k_w) '_' char(app.workflownames(k_f).names(k_w))];
                        [n_state,~] = size( app.file(k_f).workflow(k_w).statetable );
                        % Save tif
                        for k_s=1:1:n_state
                            if cell2mat(app.file(k_f).workflow(k_w).statetable(k_s,5))
                                if isempty(char( app.file(k_f).workflow(k_w).statetable(k_s,4) ))
                                    statename = ['S' num2str(k_s) '_' char(app.file(k_f).workflow(k_w).statetable(k_s,2))];
                                else
                                    statename = char( app.file(k_f).workflow(k_w).statetable(k_s,4) );
                                    if app.InsertfilenameincustomnameCheckBox.Value
                                        statename = [char(app.SelectactivefileDropDown.Items(k_f)) '_' statename];
                                    end
                                end
                                fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)),workflowname,[statename '.tif']);
                                [filepath,~,~] = fileparts(fullpath);
                                if ~exist(filepath,'dir')
                                    mkdir(filepath);
                                end
                                % Save file
                                function_save_tif(app.file(k_f).workflow(k_w).state(k_s).M, fullpath);
                            end
                        end
                    end

                    % Save parameters
                    n_workflow = length(app.file(k_f).workflow);
                    for sheetno=1:1:n_workflow
                        DATA_writetable.sheet(sheetno).name=['WF' num2str(sheetno) '_' char(app.workflownames(k_f).names(sheetno))];
                        T = cell2table(app.file(k_f).workflow(sheetno).statetable(:,1:12),"VariableNames",variablenames(1:12));
                        DATA_writetable.sheet(sheetno).table=T;
                    end
                    DATA_writetable.sheet(sheetno+1).name='Info';
                    DATA_writetable.sheet(sheetno+1).table=Table_info;
                    folderpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Items(k_f)));
                    Function_Writetable(folderpath,'Parameters',DATA_writetable)
                end


            end

            app.Savechoice_DropDown.Items = Savechoice_Items;
            app.Savechoice_DropDown.Value = Savechoice_Value;
            app.Savechoice_DropDown.Enable = 'on';
            app.SaveButton.Enable = 'on';
        end

        % Clicked callback: States_UITable
        function States_UITableClicked(app, event)
            % app.selected_s = event.InteractionInformation.DisplayRow;
            % if ~isempty(app.selected_s)
            %     app.SaveButton.Enable = 'on';
            % else
            %     app.SaveButton.Enable = 'off';
            % end
        end

        % Value changed function: FromexistingworkflowDropDown
        function FromexistingworkflowDropDownValueChanged(app, event)
            from_w = app.FromexistingworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(app.active_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(app.active_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.FromstateDropDown.Items = tmp;
            app.FromstateDropDown.Value = app.FromstateDropDown.Items(1);
        end

        % Callback function: Macro_FromworkflowDropDown, 
        % ...and 1 other component
        function Macro_FromworkflowDropDownValueChanged(app, event)
            macro_file = app.Macro_FromfileDropDown.ValueIndex;
            macro_workflow = app.Macro_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(macro_file).workflow(macro_workflow).statetable);
            tmp = [];
            kstart = 1;
            if macro_workflow==1
                kstart=2;
            end
            if kstart<=n_state
                for k=kstart:1:n_state
                    tmp = [tmp; {num2str(k,'%i')}];
                end
                app.Macro_StartatstateDropDown.Items = tmp;
                app.Macro_StartatstateDropDown.Value = app.Macro_StartatstateDropDown.Items(1);
                app.Macro_EndatstateDropDown.Items = tmp;
                app.Macro_EndatstateDropDown.Value = app.Macro_EndatstateDropDown.Items(end);
                app.Macro_StartatstateDropDown.Enable = 'on';
                app.Macro_EndatstateDropDown.Enable = 'on';
                app.Macro_AddastepButton.Enable = 'on';
            else
                app.Macro_StartatstateDropDown.Enable = 'off';
                app.Macro_EndatstateDropDown.Enable = 'off';
                app.Macro_AddastepButton.Enable = 'off';
            end

            if ~isempty(app.Macro_UITable.Data)
                app.ClearmacroButton.Enable = 'on';
                app.SavemacroButton.Enable = 'on';
            else
                app.ClearmacroButton.Enable = 'off';
                app.SavemacroButton.Enable = 'off';               
            end
        end

        % Value changed function: Macro_StartatstateDropDown
        function Macro_StartatstateDropDownValueChanged(app, event)
            valueA = app.Macro_StartatstateDropDown.Value;   
            valueB = app.Macro_EndatstateDropDown.Value; 
            if valueA>valueB
                app.Macro_StartatstateDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: Macro_EndatstateDropDown
        function Macro_EndatstateDropDownValueChanged(app, event)
            valueA = app.Macro_StartatstateDropDown.Value;   
            valueB = app.Macro_EndatstateDropDown.Value; 
            if valueA>valueB
                app.Macro_EndatstateDropDown.Value = event.PreviousValue;
            end            
        end

        % Button pushed function: Macro_AddastepButton
        function Macro_AddastepButtonPushed(app, event)
            if ~isempty(app.Macro_UITable.Data)
                [step,~] = size(app.Macro_UITable.Data);
            else
                step = 0;
            end
            step = step+1;
            selected_f = app.Macro_FromfileDropDown.ValueIndex;
            selected_w = app.Macro_FromworkflowDropDown.ValueIndex;
            statestart = app.Macro_StartatstateDropDown.Value;
            stateend = app.Macro_EndatstateDropDown.Value;
            newline = [{num2str(step,'%i')}, {num2str(selected_f,'%i')}, {num2str(selected_w,'%i')}, {num2str(statestart,'%i')}, {num2str(stateend,'%i')}];
            app.Macro_UITable.Data = [app.Macro_UITable.Data; newline];
            app.ClearmacroButton.Enable = 'on';
            app.SavemacroButton.Enable = 'on';
        end

        % Button pushed function: ClearmacroButton
        function ClearmacroButtonPushed(app, event)
            app.Macro_UITable.Data = [];
            app.Macro_FromworkflowDropDownValueChanged;
            app.n_calculation = 0;
            app.macro_calculation = [];            
        end

        % Button pushed function: SavemacroButton
        function SavemacroButtonPushed(app, event)
            [nline,~] = size(app.Macro_UITable.Data);
            m_act = 0; macro_actions = [];
            m_cal = 0; macro_calculations = [];
            macro_actions.insertfilename = app.InsertfilenameincustomnameCheckBox.Value;
            for kline=1:1:nline
                if ~isempty(str2num(cell2mat(app.Macro_UITable.Data(kline,2)))) % Actions
                    selected_f = str2num(cell2mat(app.Macro_UITable.Data(kline,2)));
                    selected_w = str2num(cell2mat(app.Macro_UITable.Data(kline,3)));
                    statestart = str2num(cell2mat(app.Macro_UITable.Data(kline,4)));
                    stateend = str2num(cell2mat(app.Macro_UITable.Data(kline,5)));
                    for kstate=statestart:1:stateend
                        m_act=m_act+1;
                        if m_act==1
                            workflow_and_states = [selected_w kstate];
                        else
                            workflow_and_states = [workflow_and_states; [selected_w kstate]];
                        end
                        macro_actions(m_act).fct =  app.file(selected_f).workflow(selected_w).state(kstate).fct;
                        macro_actions(m_act).isfct =  app.file(selected_f).workflow(selected_w).state(kstate).isfct;
                        macro_actions(m_act).markedforsave =  cell2mat(app.file(selected_f).workflow(selected_w).statetable(kstate,5));
                        macro_actions(m_act).customname =  char(app.file(selected_f).workflow(selected_w).statetable(kstate,4));       
                        if isfield(app.file(selected_f).workflow(selected_w).state(kstate).p,'Background')
                            if numel(app.file(selected_f).workflow(selected_w).state(kstate).p.Background) > 1000 % volume
                                app.file(selected_f).workflow(selected_w).state(kstate).p.Background = [];
                            end
                        end      
                        macro_actions(m_act).p = app.file(selected_f).workflow(selected_w).state(kstate).p;
                        macro_actions(m_act).action =  app.file(selected_f).workflow(selected_w).state(kstate).action;
                        macro_actions(m_act).par_verbose = app.file(selected_f).workflow(selected_w).state(kstate).par_verbose;
                    end

                else % Calculations
                    m_cal=m_cal+1;
                    macro_calculations(m_cal).macro_fct = app.macro_calculation(m_cal).macro_fct;
                    macro_calculations(m_cal).macro_fctname = app.macro_calculation(m_cal).macro_fctname;
                    macro_calculations(m_cal).macro_p = app.macro_calculation(m_cal).macro_p;
                    macro_calculations(m_cal).after_step = m_act;
                end

            end
            %macro_actions(1).matbox_version = app.Localversion.Text;
            macro_actions(1).workflow_and_states = workflow_and_states;
            f=fullfile(app.savefolder,[app.MacroNameEditField.Value '.mat']);
            save(f,'macro_actions','macro_calculations');
        end

        % Button pushed function: ROI_crop_DoButton
        function ROI_crop_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.x0 = app.ROI_crop_UITable.Data(:,3).Variables;
            p.x1 = app.ROI_crop_UITable.Data(:,4).Variables;
            p.rel = app.RelativedimensionCheckBox.Value;
            p.autoreshape = app.AutoreshapeCheckBox.Value;
            p.autoset_tomatch_downscaling = app.Autosetcropping_downscalingCheckBox.Value;
            p.downscaling_factor = app.DownscalingfactorEditField.Value;
            p.DeltaROI = app.DeltaROIformacroCheckBox.Value;
            if p.DeltaROI
                p.delta_end =  app.ROI_crop_UITable.Data(:,2).Variables - app.ROI_crop_UITable.Data(:,4).Variables;
            end
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.ROI_StatutIdleLabel.Text = ['Please wait... Action: ROI (cropping) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,p] = fct_Crop(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_Crop';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'ROI (cropping)';
                if p.rel
                    if length(p.x0)==2
                        app.par = ['x from ' num2str(p.x0(1),'%1.3f') ' to ' num2str(p.x1(1),'%1.3f') ', y from ' num2str(p.x0(2),'%1.3f') ' to ' num2str(p.x1(2),'%1.3f')] ;
                    else
                        app.par = ['x from ' num2str(p.x0(1),'%1.3f') ' to ' num2str(p.x1(1),'%1.3f') ', y from ' num2str(p.x0(2),'%1.3f') ' to ' num2str(p.x1(2),'%1.3f') ', z from ' num2str(p.x0(3),'%1.3f') ' to ' num2str(p.x1(3),'%1.3f')] ;
                    end
                else
                    if length(p.x0)==2
                        app.par = ['x from ' num2str(p.x0(1),'%i') ' to ' num2str(p.x1(1),'%i') ', y from ' num2str(p.x0(2),'%i') ' to ' num2str(p.x1(2),'%i')] ;
                    else
                        app.par = ['x from ' num2str(p.x0(1),'%i') ' to ' num2str(p.x1(1),'%i') ', y from ' num2str(p.x0(2),'%i') ' to ' num2str(p.x1(2),'%i') ', z from ' num2str(p.x0(3),'%i') ' to ' num2str(p.x1(3),'%i')] ;
                    end
                end
                if p.DeltaROI
                    app.par = [app.par ', Delta ROI: ' num2str(p.delta_end')];
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.ROI_StatutIdleLabel.Text = 'Finished!';
        end

        % Cell edit callback: ROI_crop_UITable
        function ROI_crop_UITableCellEdit(app, event)
            indices = event.Indices;
            oldData = event.PreviousData;
            newData = event.NewData;
            sz = size( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M );
            if app.RelativedimensionCheckBox.Value
                if ~isnan(newData)
                    x0 = app.ROI_crop_UITable.Data(:,3).Variables;
                    x1 = app.ROI_crop_UITable.Data(:,4).Variables;
                    if min(x1-x0)<0 || sum(x1>1) || sum(x0<0)
                        app.ROI_crop_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                else
                    app.ROI_crop_UITable.Data(indices(1),indices(2)) = {oldData};
                end
                x0 = app.ROI_crop_UITable.Data(:,3).Variables;
                x1 = app.ROI_crop_UITable.Data(:,4).Variables;
                dx = x1-x0;
            else
                if ~isnan(newData) && newData==round(newData)
                    x0 = app.ROI_crop_UITable.Data(:,3).Variables;
                    x1 = app.ROI_crop_UITable.Data(:,4).Variables;
                    if min(x1-x0)<0 || sum(x1>sz') || sum(x0<=0)
                        app.ROI_crop_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                else
                    app.ROI_crop_UITable.Data(indices(1),indices(2)) = {oldData};
                end
                x0 = app.ROI_crop_UITable.Data(:,3).Variables;
                x1 = app.ROI_crop_UITable.Data(:,4).Variables;
                dx = x1-x0+1;
            end
            app.ROI_crop_UITable.Data(:,5).Variables = dx;
            app.Update_ROI_view;
        end

        % Value changed function: CombinationA_FromstateDropDown, 
        % ...and 1 other component
        function CombinationA_FromstateDropDownValueChanged(app, event)
            [~,~,~,~,~,~] = app.Check_dimension_combinations;            
        end

        % Callback function: CombinationA_FromworkflowDropDown, 
        % ...and 3 other components
        function CombinationA_FromworkflowDropDownValueChanged(app, event)
            from_f = app.CombinationA_FromfileDropDown.ValueIndex;
            from_w = app.CombinationA_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.CombinationA_FromstateDropDown.Items = tmp;
            app.CombinationA_FromstateDropDown.Value = app.CombinationA_FromstateDropDown.Items(1);

            from_f = app.CombinationB_FromfileDropDown.ValueIndex;
            from_w = app.CombinationB_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.CombinationB_FromstateDropDown.Items = tmp;
            app.CombinationB_FromstateDropDown.Value = app.CombinationB_FromstateDropDown.Items(1); 

            [~,~,~,~,~,~] = app.Check_dimension_combinations;
        end

        % Callback function
        function CombinationB_FromworkflowDropDownValueChanged(app, event)
            from_f = app.CombinationB_FromfileDropDown.ValueIndex;
            from_w = app.CombinationB_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.CombinationB_FromstateDropDown.Items = tmp;
            app.CombinationB_FromstateDropDown.Value = app.CombinationB_FromstateDropDown.Items(1); 
            [~,~,~,~,~,~] = app.Check_dimension_combinations;
        end

        % Button pushed function: Combination_Concatenate_DoButton, 
        % ...and 8 other components
        function Combination_plus_ButtonPushed(app, event)
            [A_file, A_workflow, A_state, B_file, B_workflow,B_state] = app.Check_dimension_combinations;
            A_file_name = char(app.CombinationA_FromfileDropDown.Items(A_file));
            B_file_name = char(app.CombinationB_FromfileDropDown.Items(B_file));
            A_workflow_name = char(app.FromexistingworkflowDropDown.Items(A_workflow));
            B_workflow_name = char(app.FromexistingworkflowDropDown.Items(B_workflow));
            
            if (strcmp(event.Source.Tag,'Index') && app.CombinationIndex_NewstateinworkflowACheckBox.Value) || (strcmp(event.Source.Tag,'Symmetry') && app.CombinationSymmetry_NewstateinworkflowACheckBox.Value)  % Result saved in workflow of file A
                app.active_f = A_file;
                app.active_w = A_workflow;
                [n_state,~] = size(app.file(app.active_f).workflow(app.active_w).statetable);
                app.active_s = n_state;
            else % Result will be saved in a new workflow
                if A_file==B_file
                    app.active_f = B_file;
                    app.active_w = length(app.file(B_file).workflow)+1;
                else
                    app.active_f = length(app.SelectactivefileDropDown.Items)+1;
                    app.active_w  = 1;
                end
                app.active_s = 1;
            end

            str_para = ['A: from ' A_file_name ', ' A_workflow_name ', state# ' num2str(A_state,'%i') ', and B: from ' B_file_name ', ' B_workflow_name ', state# ' num2str(B_state,'%i')];
           
            % % COMBINATION
            tic
            if strcmp(event.Source.Tag,'Addition')
                app.Combination_StatutIdleLabel.Text = 'Combination: addition... please wait'; pause(0.1);
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                p.Ma_type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                p.Mb_type = app.file(B_file).workflow(B_workflow).state(B_state).type;
                p.plus = app.Plus_AmaxBCheckBox.Value;                

                [M,newtype] = fct_combination_addition(Ma,Mb,p);

                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = newtype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (addition)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (addition)';
                if p.plus
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = [str_para ', new=A+maxb(B)'];
                else
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = str_para;
                end

            elseif strcmp(event.Source.Tag,'Difference')
                app.Combination_StatutIdleLabel.Text = 'Combination: difference... please wait'; pause(0.1);
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                p.Ma_type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                p.Mb_type = app.file(B_file).workflow(B_workflow).state(B_state).type;
                p.difference = app.Difference_DropDown.Value;                

                [M,newtype] = fct_combination_difference(Ma,Mb,p);

                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = newtype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (difference)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (difference)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = [str_para ', ' p.difference];                

            elseif strcmp(event.Source.Tag,'Multiplication')
                app.Combination_StatutIdleLabel.Text = 'Combination: multiplication... please wait'; pause(0.1);
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                p.Ma_type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                p.Mb_type = app.file(B_file).workflow(B_workflow).state(B_state).type;
                p.checkmissingvoxels = app.AxBonlycheckformissingvoxelsCheckBox.Value;

                [M,newtype] = fct_combination_multiplication(Ma,Mb,p);
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = newtype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (multiplication)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (multiplication)';
                if p.checkmissingvoxels
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = [str_para ', check missing voxels: true'];
                else
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = str_para;
                end

            elseif strcmp(event.Source.Tag,'Merge')
                app.Combination_StatutIdleLabel.Text = 'Combination: merge... please wait'; pause(0.1);
                % Parameters
                p.threshold = app.ThresholdratioEditField.Value;
                p.check_circularity = app.Mergeonlyifitincreasecircularity2DonlybetaCheckBox.Value;
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                M = fct_combination_merge(Ma,Mb,p);               
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (merge)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (merge)';
                if p.check_circularity
                    checkpar = 'true';
                else
                    checkpar = 'false';
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = [str_para ', threshold=' num2str(p.threshold) ', check circularity: ' checkpar];
            
            elseif strcmp(event.Source.Tag,'Assign_to_nearest_cluster')
                app.Combination_StatutIdleLabel.Text = 'Combination: assign voxel to nearest cluster... please wait'; pause(0.1);
                % Parameters
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                [M,newtype] = Assign_voxel_tonearest_cluster(Ma,Mb);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = newtype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (assign to nearest cluster)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (assign to nearest cluster)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = str_para;
            
            elseif strcmp(event.Source.Tag,'Index')            
                app.Combination_StatutIdleLabel.Text = 'Combination: index based... please wait'; pause(0.1);
                % Parameters
                p.choice = char(app.Combination_Index_ChoiceDropDown.Value);
                p.labelX = app.Combination_Index_LabelXEditField.Value;
                p.labelY = app.Combination_Index_LabelYEditField.Value;
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                M = Ma;
                if strcmp(p.choice,'A(B==X)=Y')
                    M(Mb==p.labelX)=p.labelY;
                    str_para = [str_para ', new=A, A(B==' num2str(p.labelX,'%i') ')=' num2str(p.labelY,'%i')];
                elseif strcmp(p.choice,'A(B~=X)=Y')
                    M(Mb~=p.labelX)=p.labelY;
                    str_para = [str_para ', new=A, A(B~=' num2str(p.labelX,'%i') ')=' num2str(p.labelY,'%i')];
                end
                [M] = fct_intconvert(M);
                
                if app.CombinationIndex_NewstateinworkflowACheckBox.Value
                    tmp_active_s = app.active_s+1;
                else
                    tmp_active_s = app.active_s;
                end
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).fct = 'Combination (index)';
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).p = p;
                app.act = 'Combination (index)';
                app.par = str_para;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).action = 'Combination (index)';
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).par_verbose = str_para;

            elseif strcmp(event.Source.Tag,'Symmetry')
                app.Combination_StatutIdleLabel.Text = 'Combination: symmetry... please wait'; pause(0.1);
                % Parameters
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                p.perslice = app.Symmetry_Perslize3DonlyalongzCheckBox.Value;
                M = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                p.Background = double(app.file(B_file).workflow(B_workflow).state(B_state).M);
                [M] = fct_symmetry(M,p);
                if app.CombinationSymmetry_NewstateinworkflowACheckBox.Value
                    tmp_active_s = app.active_s+1;
                else
                    tmp_active_s = app.active_s;
                end
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).fct = 'Combination (symmetry)';
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).p = p;
                app.act = 'Combination (symmetry)';
                app.par = [str_para ', A: domain, B: background'];
                if p.perslice
                    app.par = [app.par ', applied per slice'];
                else
                    app.par = [app.par ', applied on whole volume'];
                end

                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).action = 'Combination (symmetry)';
                app.file(app.active_f).workflow(app.active_w).state(tmp_active_s).par_verbose = app.par;

            elseif strcmp(event.Source.Tag,'Histogram')
                app.Combination_StatutIdleLabel.Text = 'Combination: histogram equalization,... please wait'; pause(0.1);
                % Parameters
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                M = app.file(A_file).workflow(A_workflow).state(A_state).M;
                p.M2 = app.file(B_file).workflow(B_workflow).state(B_state).M;
                p.scalerange = app.MinmaxrangeotherwisedatarangeCheckBox.Value;
                p.nbin = []; p.hgram = [];

                [M] = fct_histequalization(M,p);

                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (histogram equalization)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.act = 'Combination (histogram equalization)';
                app.par = [str_para ', B: volume with target histogram'];
                if p.scalerange
                    app.par = [app.par ', min-max histogram range'];
                else
                    app.par = [app.par ', data histogram range'];
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (histogram equalization)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = app.par;


            elseif strcmp(event.Source.Tag,'Concatenate')
                app.Combination_StatutIdleLabel.Text = 'Combination: concatenation,... please wait'; pause(0.1);
                % Parameters
                p.axe = str2double(char(app.Concatenate_axisDown.Value));
                p.states = [A_file A_workflow A_state B_file B_workflow B_state];
                p.Ma_type = app.file(A_file).workflow(A_workflow).state(A_state).type;
                p.Mb_type = app.file(B_file).workflow(B_workflow).state(B_state).type;                
                Ma = double(app.file(A_file).workflow(A_workflow).state(A_state).M);
                Mb = double(app.file(B_file).workflow(B_workflow).state(B_state).M);

                [M,newtype] = fct_combination_concatenate(Ma,Mb,p);

                app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = newtype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Combination (concatenate)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Combination (concatenate)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = [str_para ', along axis ' num2str(p.axe)];
            end            
            % % END

            if  (strcmp(event.Source.Tag,'Index') && app.CombinationIndex_NewstateinworkflowACheckBox.Value) || (strcmp(event.Source.Tag,'Symmetry') && app.CombinationSymmetry_NewstateinworkflowACheckBox.Value)
                app.Update_newactive_state(true);
                app.Combination_StatutIdleLabel.Text = 'Finished!';
            else
                % Update States tab
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;

                sz = size(M);
                dimension = length(sz);
                if dimension == 2
                    s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
                else
                    s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
                end
                r = {[num2str(min(min(min(M)))) '-'  num2str(max(max(max(M))))]};
                tmp = whos('M');
                m = {[tmp.class ', ' num2str(tmp.bytes*9.53674e-7,'%1.1f') 'MB']};

                % Data
                data_type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                if strcmp(data_type,'Grey level') || strcmp(data_type,'Channel')
                    vf = {'n/a'};
                    ni = {'n/a'};
                elseif strcmp(data_type,'Segmented (phase)')
                    ni = {'n/a'};
                    [~,str] = app.Segmentation_update_volumefraction(M, false);
                    vf = {str};
                elseif strcmp(data_type,'Segmented (instance)')
                    vf = {'n/a'};
                    ni = {num2str(length(unique(M)-1),'%i')};
                end

                app.States_UITable.Data = [{num2str(app.active_s)} {app.file(app.active_f).workflow(app.active_w).state(app.active_s).action} {app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose} {''} {false} {''} {[num2str(toc,'%1.1f') 's']} {data_type} r vf ni s m {false} {true}];
                s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                addStyle(app.States_UITable,s,'row',app.active_s);

                app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;

                app.workflownames(app.active_f).names(app.active_w) = {['Workflow' num2str(app.active_w,'%i')]};

                if A_file~=B_file
                    app.SelectactivefileDropDown.Items(app.active_f) = {[A_file_name ' and ' B_file_name]};
                    app.CombinationA_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                    app.CombinationB_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                    app.Macro_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                    app.Macro_TofileDropDown.Items = app.SelectactivefileDropDown.Items;
                end
                app.SelectactivefileDropDown.Value = app.SelectactivefileDropDown.Items(app.active_f);
                app.SelectactivefileDropDownValueChanged;

                app.Combination_StatutIdleLabel.Text = 'Finished!';

                % Propagate
                app.CombinationA_FromfileDropDownValueChanged;
                app.CombinationB_FromfileDropDownValueChanged;
                app.Macro_FromfileDropDownValueChanged;
                app.Macro_TofileDropDownValueChanged;
            end
        end

        % Button down function: CombinationsTab
        function CombinationsTabButtonDown(app, event)
            [~,~,~,~,~,~] = app.Check_dimension_combinations;
        end

        % Button pushed function: ClicktoimportmacroButton
        function ClicktoimportmacroButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select a macro previsouly created with this application';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.mat','Mat file (*.mat)'},str_dialogbox);
            else
                [FileName,PathName,~] = uigetfile({'*.mat','Mat file (*.mat)'},str_dialogbox,app.importpath);
            end
            app.MacroWarningLabel.Visible = 'off';

            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.macro_act = [];
                app.macro_cal = [];
                set(app.NomacroloadedLabel,'FontColor','k','Text','No macro loaded');
                app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Text = 'Number of states that will be created with the macro: n/a';
                app.ApplymacroButton.Enable = "off";
                app.EditmacroadvancedusersButton.Enable = "off";
            else
                tmp = load([PathName FileName]);
                if isstruct(tmp) && isfield(tmp,'macro_actions') && isfield(tmp,'macro_calculations')
                    app.macro_act = tmp.macro_actions;
                    app.macro_cal = tmp.macro_calculations;
                    ids = app.macro_act.workflow_and_states;
                    [n_step,~] = size(ids);
                    app.MemorydepthEditField_2.Value = n_step+1;
                    app.MemorydepthEditField.Value = n_step+1;
                    app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Text = ['Number of states that will be created with the macro: ' num2str(n_step,'%i')];
                    set(app.NomacroloadedLabel,'FontColor','k','Text',FileName);
                    app.ApplymacroButton.Enable = "on";
                    app.EditmacroadvancedusersButton.Enable = "on";
                    if ~app.Donotloadallfiles_CheckBox.Value
                        app.Macro_TofileDropDownValueChanged;
                    end

                    
                    if ~isfield(app.macro_act(1),'matbox_version') || ~strcmp(app.macro_act(1).matbox_version, app.Localversion.Text)
                        app.MacroWarningLabel.Visible = 'on';
                        if ~isfield(app.macro_act(1),'matbox_version')
                            app.MacroWarningLabel.Text = 'Warning: macro has been crated with an older version of MATBOX and may not work on current version!';
                        else
                            app.MacroWarningLabel.Text = ['Warning: macro has been crated with an older version of MATBOX (' app.macro_act(1).matbox_version ') and may not work on current version!'];
                        end
                        app.MacroWarningLabel.Tooltip = app.MacroWarningLabel.Text;
                    end

                else
                    set(app.NomacroloadedLabel,'FontColor','r','Text','Not a macro!');
                    app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Text = 'Number of states that will be created with the macro: n/a';
                    app.macro_act = [];
                    app.macro_cal = [];
                    app.ApplymacroButton.Enable = "off";
                    app.EditmacroadvancedusersButton.Enable = "off";
                end
            end
        end

        % Callback function: Macro_ToworkflowDropDown, 
        % ...and 1 other component
        function ToworkflowDropDownValueChanged(app, event)
            if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to one file')
                to_f = app.Macro_TofileDropDown.ValueIndex;
                to_w = app.Macro_ToworkflowDropDown.ValueIndex;
                [n_state,~] = size(app.file(to_f).workflow(to_w).statetable);
                tmp = [];
                for k=1:1:n_state
                    if ~isempty( app.file(to_f).workflow(to_w).state(k).M)
                        tmp = [tmp; {num2str(k,'%i')}];
                    end
                end

            elseif strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files from same workflow and same state')
                n_file = length(app.SelectactivefileDropDown.Items);
                k_w = app.Macro_ToworkflowDropDown.ValueIndex;
                min_n_state = 1e9;
                for k_f = 1:1:n_file
                    [n_state,~] = size(app.file(k_f).workflow(k_w).statetable);
                    min_n_state = min([min_n_state n_state]);
                end
                tmp = cell(1,min_n_state);
                for k=1:1:min_n_state
                    tmp(1,k) = {num2str(k)};
                end
            end

            app.Macro_TostateDropDown.Items = tmp;
            app.Macro_TostateDropDown.Value = app.Macro_TostateDropDown.Items(1);            
        end

        % Button pushed function: ApplymacroButton
        function ApplymacroButtonPushed(app, event)
            app.Macro_IdleLabel.Text = 'Please wait...'; pause(0.1);
            n_file = length(app.SelectactivefileDropDown.Items);
            splus = 1;
            if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to one file')
                fileA = app.Macro_TofileDropDown.ValueIndex;
                fileB = app.Macro_TofileDropDown.ValueIndex;
                to_w = app.Macro_ToworkflowDropDown.ValueIndex;
                to_s = str2num(app.Macro_TostateDropDown.Value);
            elseif strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files from same workflow and same state')
                fileA = 1;
                fileB = n_file;
                to_w = app.Macro_ToworkflowDropDown.ValueIndex;
                to_s = str2num(app.Macro_TostateDropDown.Value);
                for kfile = 1:1:n_file
                    if length(app.file(kfile).workflow) < to_w ||  length(app.file(kfile).workflow(to_w).state)<to_s || isempty( app.file(kfile).workflow(to_w).state(to_s).M  )
                        f = warndlg('Files are not synchronized!','Warning');
                        return
                    end
                end
            elseif strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files (batch mode)')
                fileA = 1;
                fileB = n_file;
                to_w = 1;
                to_s = 1;
                splus = 0;
            end
            n_file_todo = fileB-fileA+1;

            count_file = 0;
            [~, workflow_macroname, ~] = fileparts(app.NomacroloadedLabel.Text);
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                app.Macro_CurrentfileLabel.Text = ['Current file: ' num2str(count_file,'%i') '/' num2str(n_file_todo,'%i')];
                app.Macro_currentifle_name_Label.Text = char(app.SelectactivefileDropDown.Items(kfile));
                pause(0.1);

                if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to one file') || strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files from same workflow and same state')
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(to_w).state(to_s).M;

                    % % Create new workflow
                    tic
                    workflow_name = char(app.workflownames(app.active_f).names(to_w));

                    n_workflow = length(app.file(app.active_f).workflow)+1;
                    app.active_w = n_workflow;
                    app.active_s = 1;

                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = app.file(app.active_f).workflow(to_w).state(to_s).type;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).fct = 'Create new workflow';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).isfct = false;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).p = [to_w to_s];
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).action = 'Create new workflow';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).par_verbose = ['From ' workflow_name ', state# ' num2str(to_s,'%i')];

                    % Update States tab
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    sz = size(M);
                    dimension = length(sz);
                    if dimension == 2
                        s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
                    else
                        s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
                    end
                    r = {[num2str(min(min(min(M)))) '-'  num2str(max(max(max(M))))]};
                    tmp = whos('M');
                    m = {[tmp.class ', ' num2str(tmp.bytes*9.53674e-7,'%1.1f') 'MB']};

                    % Data
                    data_type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    if strcmp(data_type,'Grey level') || strcmp(data_type,'Channel')
                        vf = {'n/a'};
                        ni = {'n/a'};
                    elseif strcmp(data_type,'Segmented (phase)')
                        ni = {'n/a'};
                        [~,str] = app.Segmentation_update_volumefraction(M, false);
                        vf = {str};
                    elseif strcmp(data_type,'Segmented (instance)')
                        vf = {'n/a'};
                        ni = {num2str(length(unique(M)-1),'%i')};
                    end     

                    app.States_UITable.Data = [{num2str(app.active_s)} {'Create new workflow'} {['From ' workflow_name ', state# ' num2str(to_s,'%i')]} {''} {false} {''} {[num2str(toc,'%1.1f') 's']} {data_type} r vf ni s m {false} {true}];
                    s = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                    addStyle(app.States_UITable,s,'row',app.active_s);

                    app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;

                    % Workflow popup choice update
                    while sum(ismember(app.workflownames(app.active_f).names,workflow_macroname))
                        workflow_macroname = [workflow_macroname '_bis'];
                    end
                    app.workflownames(app.active_f).names(app.active_w) = {workflow_macroname};

                    % Propagate
                    app.SelectactiveworkflowDropDown.Items = app.workflownames(app.active_f).names;
                    app.SelectactiveworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Items(end);
                    app.SelectactiveworkflowDropDownValueChanged;

                    app.FromexistingworkflowDropDown.Items = app.SelectactiveworkflowDropDown.Items;

                    app.Macro_FromworkflowDropDown.Items = app.FromexistingworkflowDropDown.Items;
                    app.CombinationA_FromworkflowDropDown.Items = app.FromexistingworkflowDropDown.Items;
                    app.CombinationB_FromworkflowDropDown.Items = app.FromexistingworkflowDropDown.Items;
                    app.Macro_ToworkflowDropDown.Items = app.FromexistingworkflowDropDown.Items;

                elseif strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files (batch mode)')
                    % Load file
                    [M, ~] = function_load_tif([app.Import_PathName char(app.Import_FileName(kfile))], 'double' ); % Load new volume
                    [M] = fct_intconvert(M);
                    app.active_f = 1;
                    app.active_w = 1;
                    app.active_s = 1;
                    app.file = []; % clear memory
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = app.DatatypeDropDown.Value;
                end

                % % Macro
                ids = app.macro_act.workflow_and_states;
                [n_step,~] = size(ids);
                if ~isempty(app.macro_cal)
                    calculations_occured_after = [];
                    n_calc = length(app.macro_cal);
                    for kc = 1:1:n_calc
                        calculations_occured_after(kc) = app.macro_cal(kc).after_step;
                    end
                else
                    n_calc = 0;
                end

                % Calculation before any actions
                if ~isempty(app.macro_cal) && calculations_occured_after(1)==0
                    idc = find(calculations_occured_after==0);
                    for kc = 1:1:length(idc)
                        app.Macro_CurrentcalculationLabel.Text = ['Current calculation: ' num2str(kc,'%i') '/' num2str(length(idc),'%i')];
                        app.Macro_currentcalc_naLabel.Text = app.macro_cal(idc(kc)).macro_fctname; pause(0.1);
                        app.macro_cal(idc(kc)).macro_p.fullpath = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname, ['S' num2str(splus,'%i') ' ' app.macro_cal(idc(kc)).macro_fctname]);
                        app.macro_cal(idc(kc)).macro_p.filename = app.Macro_currentifle_name_Label.Text;
                        app.macro_cal(idc(kc)).macro_p.sub = [workflow_macroname ', S#' num2str(splus,'%i')'];
                        fctname_calc = app.macro_cal(idc(kc)).macro_fct; 
                        feval(fctname_calc,M,app.macro_cal(idc(kc)).macro_p);                        
                    end
                end

                for kstep = 1:1:n_step
                    app.Macro_CurrentactionLabel.Text = ['Current action: ' num2str(kstep,'%i') '/' num2str(n_step,'%i')];
                    app.Macro_currentaction_name_Label.Text = app.macro_act(kstep).action;
                    app.Macro_CurrentcalculationLabel.Text = 'Current calculation:';
                    app.Macro_currentcalc_naLabel.Text = 'n/a';
                    pause(0.1);

                    tic
                    fctname = app.macro_act(kstep).fct;
                    if app.macro_act(kstep).isfct
                        if strcmp(fctname,'fct_concavityBased_LocalizedErosion') && app.macro_act(kstep).p.video
                            app.macro_act(kstep).p.savefolder = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname, ['S' num2str(kstep+splus) ' ConcavityBased LocalizedErosion']);
                            app.macro_act(kstep).p.filename = ['S' num2str(kstep+splus) ' ConcavityBased LocalizedErosion video'];
                        end
                        if (strcmp(fctname,'fct_globalthresholding') || strcmp(fctname,'fct_localthresholding'))  && app.macro_act(kstep).p.excludebackground && strcmp(app.macro_act(kstep).p.selectbackground,'From state')
                            idA = find( sum(ids == app.macro_act(kstep).p.backgroundstates(2:3),2)==2 );
                            app.macro_act(kstep).p.background_volume = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                        end


                        [M,newtype,~] = feval(fctname,M,app.macro_act(kstep).p);
                        if strcmp(newtype,'same')
                            newtype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                        end
                    else
                        if strcmp(fctname,'Create new workflow')
                            if sum(app.macro_act(kstep).p)==2 % [1 1]
                                id = 0;
                            else
                                id = find( sum(ids == app.macro_act(kstep).p,2)==2 );
                            end
                            if isempty(id)
                                id = 0;
                            end
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(id+1).M) % +1 as 1 is out of macro create new workflow
                                M = app.file(app.active_f).workflow(app.active_w).state(id+1).M;
                                newtype = app.file(app.active_f).workflow(app.active_w).state(id+1).type;
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end
                        elseif strcmp(fctname,'Combination (addition)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                p.states = [app.active_f app.active_w idA+1 app.active_f app.active_w idB+1];
                                p.Ma_type = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                p.Mb_type = app.file(app.active_f).workflow(app.active_w).state(idB+1).type;
                                p.plus = app.macro_act(kstep).p.plus;
                                [M,newtype] = fct_combination_addition(Ma,Mb,p);
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end   

                        elseif strcmp(fctname,'Combination (difference)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                p.states = [app.active_f app.active_w idA+1 app.active_f app.active_w idB+1];
                                p.Ma_type = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                p.Mb_type = app.file(app.active_f).workflow(app.active_w).state(idB+1).type;
                                p.difference = app.macro_act(kstep).p.difference;
                                [M,newtype] = fct_combination_difference(Ma,Mb,p);
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end   

                        elseif strcmp(fctname,'Combination (multiplication)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                p.states = [app.active_f app.active_w idA+1 app.active_f app.active_w idB+1];
                                p.Ma_type = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                p.Mb_type = app.file(app.active_f).workflow(app.active_w).state(idB+1).type;
                                p.checkmissingvoxels = app.macro_act(kstep).p.checkmissingvoxels;
                                [M,newtype] = fct_combination_multiplication(Ma,Mb,p);
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end

                        elseif strcmp(fctname,'Combination (merge)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                [M] = fct_combination_merge(Ma,Mb,app.macro_act(kstep).p);  
                                newtype = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end  


                        elseif strcmp(fctname,'Combination (assign to nearest cluster)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                [M,newtype] = Assign_voxel_tonearest_cluster(Ma,Mb);  
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end    
                           
                        elseif strcmp(fctname,'Combination (index)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                M = Ma;
                                if strcmp(app.macro_act(kstep).p.choice,'A(B==X)=Y')
                                    M(Mb==app.macro_act(kstep).p.labelX)=app.macro_act(kstep).p.labelY;
                                    %str_para = ['new=A, A(B==' num2str(app.macro_act(kstep).p.labelX,'%i') ')=' num2str(app.macro_act(kstep).p.labelY,'%i')];
                                elseif strcmp(app.macro_act(kstep).p.choice,'A(B~=X)=Y')
                                    M(Mb~=app.macro_act(kstep).p.labelX)=app.macro_act(kstep).p.labelY;
                                    %str_para = ['new=A, A(B~=' num2str(app.macro_act(kstep).p.labelX,'%i') ')=' num2str(app.macro_act(kstep).p.labelY,'%i')];
                                end
                                [M] = fct_intconvert(M);
                                newtype = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end  

                        elseif strcmp(fctname,'Combination (symmetry)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 || isempty(idA) % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 || isempty(idB) % [1 1]
                                idB=0;
                            end 
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                M = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                p = app.macro_act(kstep).p;
                                p.Background = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                [M] = fct_symmetry(M,p);
                                newtype = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                %str_para = 'n/a';
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end 

                        elseif strcmp(fctname,'Combination (histogram equalization)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end 
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                M = app.file(app.active_f).workflow(app.active_w).state(idA+1).M;
                                p.M2 = app.file(app.active_f).workflow(app.active_w).state(idB+1).M;
                                p.bin = []; p.hgram = [];
                                p.scalerange = app.macro_act(kstep).p.scalerange;
                                [M] = fct_histequalization(M,p);
                                newtype = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                %str_para = 'n/a';
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end 

                    elseif strcmp(fctname,'Combination (concatenate)')
                            states = app.macro_act(kstep).p.states;
                            % [FileA, WorkflowA, StateA , FileB, WorkflowB, StateB]. We only check workflow and state.
                            % Combination operations used for macro should be applied on same file for coherence.                            
                            idA = find( sum(ids == states(2:3),2)==2 );
                            idB = find( sum(ids == states(5:6),2)==2 );
                            if sum(states(2:3))==2 % [1 1]
                                idA=0;
                            end
                            if sum(states(5:6))==2 % [1 1]
                                idB=0;
                            end  
                            if ~isempty(app.file(app.active_f).workflow(app.active_w).state(idA+1).M) && ~isempty(app.file(app.active_f).workflow(app.active_w).state(idB+1).M)
                                Ma = double(app.file(app.active_f).workflow(app.active_w).state(idA+1).M);
                                Mb = double(app.file(app.active_f).workflow(app.active_w).state(idB+1).M);
                                p.states = [app.active_f app.active_w idA+1 app.active_f app.active_w idB+1];
                                p.Ma_type = app.file(app.active_f).workflow(app.active_w).state(idA+1).type;
                                p.Mb_type = app.file(app.active_f).workflow(app.active_w).state(idB+1).type;
                                p.axe = app.macro_act(kstep).p.axe;
                                [M,newtype] = fct_combination_concatenate(Ma,Mb,p);   
                            else
                                f = warndlg('State not in memory! Increase Memory depth','Warning');
                                break
                            end  
                            
                        end     
                        
                    end

                    if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to one file') || strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files from same workflow and same state')

                        % Update table
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = app.macro_act(kstep).p;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = fctname;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = app.macro_act(kstep).isfct;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                        app.act = ['[W' num2str(ids(kstep,1),'%i') ',S' num2str(ids(kstep,2),'%i') '] ' app.macro_act(kstep).action];
                        app.par = app.macro_act(kstep).par_verbose;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                        app.t = toc;
                        app.Update_newactive_state(false);

                    elseif strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files (batch mode)')
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                        app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                        app.active_s = app.active_s+1;
                        % if kstep==n_step || strcmp(app.Macro_SavDropDown.Value,'Save all states for all files')
                        %     statename = ['S' num2str(kstep) '_' fctname];
                        %     fullpath = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname,[statename '.tif']);
                        %     [filepath,~,~] = fileparts(fullpath);
                        %     if ~exist(filepath,'dir')
                        %         mkdir(filepath);
                        %     end
                        %     % Save file
                        %     function_save_tif(M, fullpath);
                        % end
                    end

                    if ~strcmp(app.Macro_SavDropDown.Value, 'Do not save automatically')
                        tosave = false;
                        if strcmp(app.Macro_SavDropDown.Value,'Save all states for all files')
                            tosave = true;
                        elseif strcmp(app.Macro_SavDropDown.Value,'Save only final state for each file')
                            if kstep==n_step
                                tosave = true;
                            end
                        elseif strcmp(app.Macro_SavDropDown.Value,'Save all marked states')
                            if app.macro_act(kstep).markedforsave
                                tosave = true;
                            end
                        end

                        if tosave
                            if isempty(app.macro_act(kstep).customname)
                                s_save = 1;
                                if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files (batch mode)')
                                    s_save = 0;
                                end
                                statename = ['S' num2str(kstep+s_save) '_' fctname];
                            else
                                statename = app.macro_act(kstep).customname;
                                if app.macro_act(1).insertfilename
                                    statename = [char(app.SelectactivefileDropDown.Items(kfile)) '_' statename];
                                end
                            end

                            fullpath = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname,[statename '.tif']);
                            [filepath,~,~] = fileparts(fullpath);
                            if ~exist(filepath,'dir')
                                mkdir(filepath);
                            end
                            % Save file
                            function_save_tif(M, fullpath);
                        end

                        % if kstep==n_step || strcmp(app.Macro_SavDropDown.Value,'Save all states for all files')
                        %     s_save = 1;
                        %     if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files (batch mode)')
                        %         s_save = 0;
                        %     end      
                        %     statename = ['S' num2str(kstep+s_save) '_' fctname];
                        %     fullpath = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname,[statename '.tif']);
                        %     [filepath,~,~] = fileparts(fullpath);
                        %     if ~exist(filepath,'dir')
                        %         mkdir(filepath);
                        %     end
                        %     % Save file
                        %     function_save_tif(M, fullpath);
                        % end
                    end

                    % Calculation after an action
                    if ~isempty(app.macro_cal)
                        idc = find(calculations_occured_after==kstep);
                        if ~isempty(idc)
                            for kc = 1:1:length(idc)
                                app.Macro_CurrentcalculationLabel.Text = ['Current calculation: ' num2str(kc,'%i') '/' num2str(length(idc),'%i')];
                                app.Macro_currentcalc_naLabel.Text = app.macro_cal(idc(kc)).macro_fctname;
                                pause(0.1);
                                app.macro_cal(idc(kc)).macro_p.fullpath = fullfile(app.savefolder,app.Macro_currentifle_name_Label.Text,workflow_macroname, ['S' num2str(kstep+splus) ' ' app.macro_cal(idc(kc)).macro_fctname]);
                                app.macro_cal(idc(kc)).macro_p.filename = app.Macro_currentifle_name_Label.Text;
                                app.macro_cal(idc(kc)).macro_p.sub = [workflow_macroname ', S#' num2str(kstep+splus) ': ' app.macro_act(kstep).action;];
                                fctname_calc = app.macro_cal(idc(kc)).macro_fct;
                                feval(fctname_calc,M,app.macro_cal(idc(kc)).macro_p);
                            end
                        end
                    end

                end

                app.Macro_CurrentactionLabel.Text = 'Current action:';
                app.Macro_currentaction_name_Label.Text = 'n/a';
                app.Macro_CurrentcalculationLabel.Text = 'Current calculation:';
                app.Macro_currentcalc_naLabel.Text  = 'n/a';

            end
            app.Macro_IdleLabel.Text = 'Finished!';
            app.Macro_CurrentfileLabel.Text = 'Current file:';
            app.Macro_currentifle_name_Label.Text = 'n/a';

            if strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to one file') || strcmp(app.Macro_ToOptionsDropDown.Value,'Apply to all files from same workflow and same state')
                app.Update_tables_dimensions;
                app.CombinationA_FromfileDropDownValueChanged;
                app.CombinationB_FromfileDropDownValueChanged;
                app.Macro_ToOptionsDropDownValueChanged;
            end

        end

        % Button pushed function: Importtif_folder_Button
        function Importtif_folder_ButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select one or several 2D images or 3D volumes from the same folder';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,'MultiSelect','on');
            else
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,app.importpath,'MultiSelect','on');
            end

            app.active_f = 1;
            app.active_w = 1;
            app.active_s = 1;
            app.ClearmacroButton;
            datatype = class(FileName);
            if strcmp(datatype,'double')
                % User clicked cancel button or closed the dialog box
                app.file = [];
                app.workflownames = [];
                app.States_UITable.Data = [];
                set(app.NodataloadedLabel,'FontColor','k','Text','No data loaded');
                app.Files_UITable.Data = [];
                app.Label_path.Text = 'n/a';
                app.SelectSavefolder_Button.Enable = "off"; app.Savefolder_Label.Text = 'None selected'; app.savefolder = [];
                app.SelectactivefileDropDown.Items = {};
                app.startupFcn; % Reset UI
            else
                if strcmp(datatype,'char')
                    FileName = {FileName};
                end
                n_file = length(FileName);
                app.file = []; % reset first
                app.workflownames = [];
                app.SelectactivefileDropDown.Items = {};
                tabledata = cell(n_file,4);
                app.Label_path.Text = PathName;
                for kfile = 1:1:n_file
                    set(app.NodataloadedLabel,'FontColor','k','Text',['Loading file #' num2str(kfile) '/' num2str(n_file) ', please wait...']); pause(0.01);
                    tic
                    [M, outcome] = function_load_tif([PathName char(FileName(kfile))], 'double' ); % Load new volume
                    importtime = toc;
                    if outcome % Success to import
                        app.importpath = PathName;
                        app.active_f = kfile;
                        set(app.NodataloadedLabel,'FontColor','k','Text','Import successful');  pause(0.01);
                        [M] = fct_intconvert(M);
                        sz = size(M);
                        dimension = length(sz);
                        if dimension == 2
                            Numbervoxels = {[num2str(sz(1),'%i') ' x ' num2str(sz(2),'%i') ' = ' num2str(numel(M),'%i')]};
                            s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
                        else
                            Numbervoxels = {[num2str(sz(1),'%i') ' x ' num2str(sz(2),'%i') ' x ' num2str(sz(3),'%i') ' = ' num2str(numel(M),'%i')]};
                            s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
                        end
                        
                        % Table
                        tabledata(kfile,1) = {kfile};
                        tabledata(kfile,2) = {char(FileName(kfile))};
                        tabledata(kfile,3) = {dimension};
                        tabledata(kfile,4) = Numbervoxels;
                        
                        % Update States tab
                        [~, baseFileNameNoExt, ~] = fileparts(char(FileName(kfile)));
                        if ~app.Donotloadallfiles_CheckBox.Value
                            app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = M;
                            tmp = whos('M');
                            m = {[tmp.class ', ' num2str(tmp.bytes*9.53674e-7,'%1.1f') 'MB']};
                            a = {true};
                            st = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                        else % They will be used only with a macro sequentially
                            app.Import_PathName = PathName;
                            app.Import_FileName = FileName;
                            app.file(app.active_f).workflow(app.active_w).state(app.active_s).M = [];
                            m = {'No'};
                            a = {false};
                            st = uistyle('BackgroundColor',[0.95    0.95    0.95]);
                        end

                        % Data
                        data_type = app.DatatypeDropDown.Value;
                        r = {[num2str(min(min(min(M)))) '-'  num2str(max(max(max(M))))]};
                        if strcmp(data_type,'Grey level') || strcmp(data_type,'Channel')
                            app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = 'Grey level';
                            vf = {'n/a'};
                            ni = {'n/a'};
                        elseif strcmp(data_type,'Segmented (phase)')
                            app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = 'Segmented (phase)';
                            ni = {'n/a'};
                            [~,str] = app.Segmentation_update_volumefraction(M, false);
                            vf = {str};
                        elseif strcmp(data_type,'Segmented (instance)')
                            app.file(app.active_f).workflow(app.active_w).state(app.active_s).type = 'Segmented (instance)';
                            vf = {'n/a'};
                            ni = {num2str(length(unique(M)-1),'%i')};
                        end

                        app.States_UITable.Data = [{'1'} {'Import'} {fullfile(PathName,char(FileName(kfile)))} {''} {false} {''} {[num2str(importtime,'%1.1f') 's']} {data_type} r vf ni s m {false} a];
                        addStyle(app.States_UITable,st,'row',1);
                  
                        app.file(app.active_f).workflow(app.active_w).statetable = app.States_UITable.Data;
                        app.workflownames(app.active_f).names = {'Workflow1'};

                        app.SelectactivefileDropDown.Items = [app.SelectactivefileDropDown.Items {baseFileNameNoExt}];
                        app.SelectactivefileDropDown.Value = app.SelectactivefileDropDown.Items(end);                        

                        app.Files_UITable.Data = tabledata(1:kfile,:); pause(0.1);
                    else
                        if n_file==1
                            set(app.NodataloadedLabel,'FontColor','r','Text','Failed to load the volume!');
                        else
                            set(app.NodataloadedLabel,'FontColor','r','Text',['Failed to load ' char(FileName(kfile)) '!']);
                        end
                        app.file = [];
                        app.workflownames = [];
                        app.States_UITable.Data = [];
                        app.Files_UITable.Data = [];
                        app.Label_path.Text = 'n/a';
                        app.SelectSavefolder_Button.Enable = "off"; app.Savefolder_Label.Text = 'None selected'; app.savefolder = [];
                        app.SelectactivefileDropDown.Items = {};
                        app.startupFcn; % Reset UI
                        break
                    end   
                end

                if outcome
                    if ~app.Donotloadallfiles_CheckBox.Value

                        % Update UI
                        % % Initialize popup (States)
                        app.SelectactivefileDropDownValueChanged; % Propagate
                        app.SelectSavefolder_Button.Enable = "on"; app.Savefolder_Label.Text = PathName; app.savefolder = PathName;

                        % % Initialize popup (combinations)
                        app.CombinationA_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                        app.CombinationA_FromfileDropDown.Value = app.CombinationA_FromfileDropDown.Items(1);
                        app.CombinationB_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                        app.CombinationB_FromfileDropDown.Value = app.CombinationB_FromfileDropDown.Items(1);

                        app.CombinationA_FromworkflowDropDown.Items = app.workflownames(1).names;
                        app.CombinationA_FromworkflowDropDown.Value = app.CombinationA_FromworkflowDropDown.Items(1); 
                        app.CombinationB_FromworkflowDropDown.Items = app.workflownames(1).names;
                        app.CombinationB_FromworkflowDropDown.Value = app.CombinationB_FromworkflowDropDown.Items(1);   
                        app.CombinationA_FromworkflowDropDownValueChanged;  % Propagate 
                        app.CombinationB_FromworkflowDropDownValueChanged; 
                        %app.CombinationA_FromfileDropDownValueChanged;
                        %app.CombinationB_FromfileDropDownValueChanged;                       

                        % % Initialize popup (macro)
                        app.Macro_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                        app.Macro_FromfileDropDown.Value = app.Macro_FromfileDropDown.Items(1);
                        app.Macro_FromfileDropDownValueChanged; % Propagate

                        app.Macro_TofileDropDown.Items = app.SelectactivefileDropDown.Items;
                        app.Macro_TofileDropDown.Value = app.Macro_TofileDropDown.Items(1);
                        app.Macro_TofileDropDownValueChanged; % Propagate

                        if n_file ==1
                            app.Multiplefiles_options_DropDown.Enable = 'off';
                            app.Multiplefiles_options_DropDown.Value = app.Multiplefiles_options_DropDown.Items(1);
                        else
                            app.Multiplefiles_options_DropDown.Enable = 'on';
                        end

                        % % Activate UI
                        children = get(app.GridLayout_States,'Children');
                        set(children,'Enable','on');
                        app.Delete_WorkflowButton.Enable = 'off';
                        app.ShowonlystateswithacustomnameCheckBoxValueChanged;
                        children = get(app.Macro_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.ApplymacroButton.Enable = "off";
                        app.EditmacroadvancedusersButton.Enable = "off";
                        app.Macro_FromworkflowDropDownValueChanged;
                        app.Macro_ToOptionsDropDown.Items = {'Apply to one file' 'Apply to all files from same workflow and same state'};
                        app.Macro_ToOptionsDropDown.Value = app.Macro_ToOptionsDropDown.Items(1);

                        children = get(app.ROI_GridLayout,'Children');
                        %set(children,'Enable','on');
                        child_handles = allchild(app.ROI_GridLayout); % Get all children
                        [n_children,~]=size(child_handles);
                        for k=1:1:n_children
                            if isprop(child_handles(k),'Enable')
                                child_handles(k).Enable='on';
                            else
                                child_handles(k).Visible='on';
                            end
                        end
                        app.ROI_SelectbackgroundDropDownValueChanged;
                        app.ROI_ParametersDropDownValueChanged;

                        children = get(app.GLfilter_GridLayout,'Children');
                        set(children,'Enable','on');  
                        app.Anisotropic_EstimategradientCheckBoxValueChanged;
                        app.Anisotropic_ApplyDropDownValueChanged;
                        app.InitialcompactnessDropDownValueChanged;
                        children = get(app.SEGfilter_GridLayout,'Children');
                        set(children,'Enable','on');
                        children = get(app.Semanticsegmentation_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.SemanticsegmentationTabButtonDown;
                        app.Seg_ExcludebackgroundfromthecalculationCheckBoxValueChanged;
                        app.PlotthresholdprofileCheckBoxValueChanged;
                        app.LocalSegmentation_Method_DropDownValueChanged;
                        app.Seg_Numberofphases_editfieldValueChanged;
                        children = get(app.Instancesegmentation_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.InstanceSeg_MethodDropDownValueChanged;
                        app.SegmentingbypartsCheckBoxValueChanged;
                        app.downscalingupscalingschemeCheckBoxValueChanged;
                        app.PreerosionCheckBoxValueChanged;
                        children = get(app.Randomize_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.RandomizeDistance_CheckBoxValueChanged;
                        app.SubstitutionChoice_DropDownValueChanged;
                        app.ButdonotmodifysomelabelsCheckBoxValueChanged;
                        app.ButdonotmodifysomelabelsCheckBoxValueChanged;
                        app.ButdonotmodifysomelabelsCheckBox_2ValueChanged;
                        children = get(app.GlobalErosionDilation_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.GlabolaErosionDilation_ApplyonDropDownValueChanged;
                        children = get(app.LocalErosion_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.Localerosion_surfacesimplification_CheckBoxValueChanged;
                        app.Localerosion_VideoCheckBoxValueChanged;
                        children = get(app.LocalDilation_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.Localdialtion_Perslice3DonyzdirectionCheckBoxValueChanged;

                        children = get(app.GridLayout_Channel1,'Children');
                        set(children,'Enable','on');
                        children = get(app.GridLayout_Channel2,'Children');
                        set(children,'Enable','on');
                        children = get(app.GridLayout_Channel3,'Children');
                        set(children,'Enable','on');                        
                        app.ChannelLabel_ForDropDownValueChanged;
                        app.Channel_ConverttointegerCheckBoxValueChanged;
                        app.ChannelLabel_ForDropDown_2ValueChanged;
                        app.Size_MericDropDown_2ValueChanged;

                        children = get(app.Contrast1_GridLayout10,'Children');
                        set(children,'Enable','on');       
                        children = get(app.Contrast2_GridLayout10,'Children');
                        set(children,'Enable','on');   
                        app.ContrastEq_MethodDropDownValueChanged;
                        app.CLAHE_DistributionDropDownValueChanged;
                        children = get(app.Scaling_GridLayout,'Children');
                        set(children,'Enable','on');

                        children = get(app.Multiplefiles_options_GridLayout,'Children');
                        set(children,'Enable','on');
                        children = get(app.Combination_GridLayout,'Children');
                        set(children,'Enable','on');
                        children = get(app.Comparison_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.Comparison_VisualizeButton.Enable = 'off';
                        children = get(app.Calc_Grey_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.ExcludebackgroundfromthecalculationCheckBoxValueChanged;
                        children = get(app.Calc_Seg_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.Greylevel_CalculateDropDownValueChanged;
                        app.SepCriterion_ChoiceDropDownValueChanged;
                        app.CalcSeg_ExcludebackgroundfromthecalculationCheckBoxValueChanged;
                        children = get(app.Clusters_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.Cluster_BackgroundisdomainsedgesCheckBoxValueChanged;
                        app.Connectivity_DefintionDropDownValueChanged;
                        children = get(app.ClusterInteractions_GridLayout,'Children');
                        set(children,'Enable','on');
                        app.ClusterInerationcs_BackgroundisdomainsedgesCheckBoxValueChanged;
                        app.AssigntosurroundinglabelCheckBoxValueChanged;
                        app.Separatelabels_ChoiceDropDown_2ValueChanged;
                        app.EquiprobabilityCheckBox_2ValueChanged;
                        children = get(app.Pulverize_GridLayout,'Children');
                        set(children,'Enable','on');                        
                        app.VoidfillingmethodDropDownValueChanged;      
                        children = get(app.DataType_GridLayout,'Children');
                        set(children,'Enable','on');    

                    else
                        app.SelectSavefolder_Button.Enable = "on"; app.Savefolder_Label.Text = PathName; app.savefolder = PathName;
                        app.Label_4.Enable = "on";
                        app.Option2loadamacroLabel.Enable = "on";
                        app.ClicktoimportmacroButton.Enable = "on";
                        app.NomacroloadedLabel.Enable = "on";
                        app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Enable = "on";
                        app.Macro_ToOptionsDropDown.Items = {'Apply to all files (batch mode)'};
                        app.Macro_IdleLabel.Enable = 'on';
                        app.Macro_CurrentfileLabel.Enable = 'on';
                        app.Macro_currentifle_name_Label.Enable = 'on';
                        app.Macro_CurrentactionLabel.Enable = 'on';
                        app.Macro_currentaction_name_Label.Enable = 'on';
                        app.Macro_CurrentcalculationLabel.Enable = 'on';
                        app.Macro_currentcalc_naLabel.Enable = 'on';                        
                        app.MemorydepthEditField_2.Enable = 'on';
                        app.MemorydepthEditField_2Label.Enable = 'on';
                        app.MainTabGroup.SelectedTab = app.MacroTab;
                    end
                    app.Donotloadallfiles_CheckBox.Enable = 'off';
                    app.Macro_ToOptionsDropDownValueChanged;
                    app.Localerosion_NofolderLabel.Text = app.savefolder;
                    app.Localerosion_NofolderLabel.Tooltip = app.Localerosion_NofolderLabel.Text;                    
                end

            end 
        end

        % Value changed function: SelectactivefileDropDown
        function SelectactivefileDropDownValueChanged(app, event)
            app.active_f = app.SelectactivefileDropDown.ValueIndex;
            % Propagate
            app.SelectactiveworkflowDropDown.Items = app.workflownames(app.active_f).names;
            app.SelectactiveworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Items(end);
            app.SelectactiveworkflowDropDownValueChanged; 
            app.FromexistingworkflowDropDown.Items = app.SelectactiveworkflowDropDown.Items;
            app.FromexistingworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Value;
            app.FromexistingworkflowDropDownValueChanged;      

            n_workflow = length(app.SelectactiveworkflowDropDown.Items);
            app.Mosaic_FromworkflowEditField.Value = 1;
            app.Mosaic_toworkflowEditField.Value = n_workflow;
        end

        % Value changed function: RelativedimensionCheckBox
        function RelativedimensionCheckBoxValueChanged(app, event)
            value = app.RelativedimensionCheckBox.Value;
            sz = size( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M );

            x0 = app.ROI_crop_UITable.Data(:,3).Variables;
            x1 = app.ROI_crop_UITable.Data(:,4).Variables;
            if value % From abs to rel
                a = (1-0)./(sz-1);
                b = 1 - a.*sz;
            else % From rel to abs
                a = (sz-1)./(1-0);
                b = 1;
            end
            x0 = a'.*x0 + b';
            x1 = a'.*x1 + b';
            if value
                x0 = round(x0,3);
                x1 = round(x1,3);
                dx = x1-x0;
            else
                x0 = round(x0);
                x1 = round(x1);
                dx = x1-x0+1;
            end
            app.ROI_crop_UITable.Data(:,3).Variables = x0;
            app.ROI_crop_UITable.Data(:,4).Variables = x1;
            app.ROI_crop_UITable.Data(:,5).Variables = dx;  

            if app.RelativedimensionCheckBox.Value
                app.DeltaROIformacroCheckBox.Value = false;
            end
        end

        % Callback function: CombinationA_FromfileDropDown, 
        % ...and 1 other component
        function CombinationA_FromfileDropDownValueChanged(app, event)
            from_f = app.CombinationA_FromfileDropDown.ValueIndex;
            % Propagate
            app.CombinationA_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.CombinationA_FromworkflowDropDown.Value = app.CombinationA_FromworkflowDropDown.Items(end);
            app.CombinationA_FromworkflowDropDownValueChanged; 
        end

        % Callback function: CombinationB_FromfileDropDown, 
        % ...and 1 other component
        function CombinationB_FromfileDropDownValueChanged(app, event)
            from_f = app.CombinationB_FromfileDropDown.ValueIndex;
            % Propagate
            app.CombinationB_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.CombinationB_FromworkflowDropDown.Value = app.CombinationB_FromworkflowDropDown.Items(end);
            app.CombinationA_FromworkflowDropDownValueChanged;              
        end

        % Callback function: Macro_FromfileDropDown, Macro_FromfileDropDown
        function Macro_FromfileDropDownValueChanged(app, event)
            from_f = app.Macro_FromfileDropDown.ValueIndex;
            % Propagate
            app.Macro_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.Macro_FromworkflowDropDown.Value = app.Macro_FromworkflowDropDown.Items(end);
            app.Macro_FromworkflowDropDownValueChanged;                            
        end

        % Value changed function: Macro_ToOptionsDropDown
        function Macro_ToOptionsDropDownValueChanged(app, event)
            if strcmp(char(app.Macro_ToOptionsDropDown.Value),'Apply to one file')
                app.Macro_TofileDropDown.Enable = 'on';
                app.Macro_ToworkflowDropDown.Enable = 'on';
                app.Macro_TostateDropDown.Enable = 'on';
                app.Macro_TofileDropDownValueChanged;
            elseif strcmp(char(app.Macro_ToOptionsDropDown.Value),'Apply to all files from same workflow and same state')
                app.Macro_TofileDropDown.Enable = 'off';
                app.Macro_ToworkflowDropDown.Enable = 'on';
                
                n_file = length(app.SelectactivefileDropDown.Items);
                min_n_workflow = 1e9;
                for k_f = 1:1:n_file
                    n_workflow = length(app.file(k_f).workflow);
                    min_n_workflow = min([min_n_workflow n_workflow]);                    
                end
                tmp = cell(1,min_n_workflow);
                for k=1:1:min_n_workflow
                    tmp(1,k) = {num2str(k)};
                end
                app.Macro_ToworkflowDropDown.Items = tmp;
                app.ToworkflowDropDownValueChanged;
            else
                app.Macro_TofileDropDown.Enable = 'off';
                app.Macro_ToworkflowDropDown.Enable = 'off';
                app.Macro_TostateDropDown.Enable = 'off';                
            end            
        end

        % Callback function: Macro_TofileDropDown, Macro_TofileDropDown
        function Macro_TofileDropDownValueChanged(app, event)
            macro_file = app.Macro_TofileDropDown.ValueIndex;
            % Propagate
            app.Macro_ToworkflowDropDown.Items = app.workflownames(macro_file).names;
            app.Macro_ToworkflowDropDown.Value = app.Macro_ToworkflowDropDown.Items(end);
            app.ToworkflowDropDownValueChanged; 
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url) 

            app.SaveButton.Enable = 'on';
            app.Savechoice_DropDown.Enable = 'on';
            app.Savechoice_DropDown.Items = {'Save active state';'Save all states from active workflow';'Save all states from active file';'Save only final state for each file';'Save all marked states';'Save all states from all files'};
        end

        % Value changed function: MemorydepthEditField, 
        % ...and 1 other component
        function MemorydepthEditField_2ValueChanged(app, event)
            app.MemorydepthEditField.Value = event.Value;
            app.MemorydepthEditField_2.Value = event.Value;            
        end

        % Button pushed function: ImagemosaicofallstatesinmemoryButton
        function ImagemosaicofallstatesinmemoryButtonPushed(app, event)
            n_file = length(app.SelectactivefileDropDown.Items);
            scrsz = get(0,'ScreenSize'); % Screen resolution

            fontsize_title = app.Title_FontsizeEditField.Value;
            fontsize_subtitle = app.Subtitle_FontsizeEditField.Value;
            fontsize_workflowname = app.Workflow_FontsizeEditField.Value;

            for k_file = 1:1:n_file
                n_workflow = length(app.file(k_file).workflow);
                workflow_start = app.Mosaic_FromworkflowEditField.Value;
                workflow_end = app.Mosaic_toworkflowEditField.Value;
                n_workflow_display = workflow_end-workflow_start+1;
                n_statemax = 0;
                for k_w = 1:1:n_workflow
                    if k_w>=workflow_start && k_w<=workflow_end
                        [n_state,~] = size(app.file(k_file).workflow(k_w).statetable);
                        n_statemax = max([n_statemax n_state]); 
                    end
                end

                n_statecustomname = 0;
                for k_w = 1:1:n_workflow
                    [n_state,~] = size(app.file(k_file).workflow(k_w).statetable);
                    for k_s = 1:1:n_state
                        if ~isempty(char(app.file(k_file).workflow(k_w).statetable(k_s,4)))
                            n_statecustomname = n_statecustomname + 1;
                        end
                    end
                end

                Fig = figure;
                Fig.Name= char(app.SelectactivefileDropDown.Items(k_file));
                Fig.Color='white'; % Background colour
                set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]); % Full screen figure

                tmp=app.ShowonlystateswithacustomnameCheckBox.Value;
                if tmp && n_statecustomname>0
                    nrow = app.NumberofrowsEditField.Value;
                    ncol = ceil(n_statecustomname/nrow);
                    tiles = tiledlayout(nrow,ncol);
                    n_tile = 0;
                    for k_w = 1:1:n_workflow
                        [n_state,~] = size(app.file(k_file).workflow(k_w).statetable);
                        for k_s = 1:1:n_state
                            if ~isempty(char(app.file(k_file).workflow(k_w).statetable(k_s,4)))
                                n_tile = n_tile +1;
                                ax = nexttile(n_tile);
                                axis equal; axis tight;
                                if ~isempty( app.file(k_file).workflow(k_w).state(k_s).M  )
                                    M = app.file(k_file).workflow(k_w).state(k_s).M;
                                    sz = size(M);
                                    if length(sz)==3
                                        dir = str2double(char(app.MosaicaxisDropDown.Value));
                                        if dir == 1
                                            p.action = 'Swap axis 1 with axis 3';
                                            [M,~,~] = fct_flipswap(M,p);
                                        elseif dir == 2
                                            p.action = 'Swap axis 2 with axis 3';
                                            [M,~,~] = fct_flipswap(M,p);
                                        end
                                        imagesc(M(:,:,round(sz(3)/2)));
                                    else
                                        imagesc(M);
                                    end
                                    axis equal; axis tight;
                                    if strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Grey level')
                                        colormap(ax,'copper');
                                    elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Segmented (phase)')
                                        colormap(ax,'gray');
                                    elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Segmented (instance)')
                                        n=length(unique(M));
                                        custom_turbo = turbo(n);
                                        custom_turbo(1,:) = [1.0 1.0 1.0]; % White background
                                        colormap(ax,custom_turbo);
                                    elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Channel')
                                        colormap(ax,'turbo');
                                    end
                                end
                                set(gca,'XTick',[], 'YTick', [])
                                str_title = char(app.file(k_file).workflow(k_w).statetable(k_s,4));
                                if app.InsertfilenameincustomnameCheckBox.Value
                                    str_title = [char(app.SelectactivefileDropDown.Items(k_file)) '_' str_title];
                                end
                                str_title = strrep(str_title,'_',' ');
                                if length(str_title) > 25
                                    kstr = strfind(str_title,' ');
                                    if ~isempty(kstr)
                                        tmp = abs(length(str_title)/2 - kstr);
                                        ifcut = find(tmp==min(tmp));
                                        str_title = {str_title(1:kstr(ifcut)-1),str_title(kstr(ifcut)+1:end)};
                                    end
                                end
                                %h=title([char(app.file(k_file).workflow(k_w).statetable(k_s,2))]);
                                h=title(str_title);
                                h.FontSize = fontsize_title;
                                h.FontName = app.fontname;


                            end
                        end
                    end

                else
                    if n_workflow_display>=1 && n_statemax>0
                        tiles = tiledlayout(n_workflow_display,n_statemax);
                        k_wdisplay = 0;
                        for k_w = 1:1:n_workflow
                            if k_w>=workflow_start && k_w<=workflow_end
                                [n_state,~] = size(app.file(k_file).workflow(k_w).statetable);
                                k_wdisplay = k_wdisplay+1;
                                for k_s = 1:1:n_state
                                    if k_s<=length(app.file(k_file).workflow(k_w).state)
                                        n_tile = (k_wdisplay-1)*n_statemax + k_s;
                                        ax = nexttile(n_tile);
                                        axis equal; axis tight;
                                        if ~isempty( app.file(k_file).workflow(k_w).state(k_s).M  )
                                            M = app.file(k_file).workflow(k_w).state(k_s).M;
                                            sz = size(M);
                                            if length(sz)==3
                                                dir = str2double(char(app.MosaicaxisDropDown.Value));
                                                if dir == 1
                                                    p.action = 'Swap axis 1 with axis 3';
                                                    [M,~,~] = fct_flipswap(M,p);
                                                elseif dir == 2
                                                    p.action = 'Swap axis 2 with axis 3';
                                                    [M,~,~] = fct_flipswap(M,p);
                                                end
                                                imagesc(M(:,:,round(sz(3)/2)));
                                            else
                                                imagesc(M);
                                            end
                                            axis equal; axis tight;
                                            if strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Grey level')
                                                colormap(ax,'copper');
                                            elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Segmented (phase)')
                                                colormap(ax,'gray');
                                            elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Segmented (instance)')
                                                n=length(unique(M));
                                                custom_turbo = turbo(n);
                                                custom_turbo(1,:) = [1.0 1.0 1.0]; % White background
                                                colormap(ax,custom_turbo);
                                            elseif strcmp(app.file(k_file).workflow(k_w).state(k_s).type,'Channel')
                                                colormap(ax,'turbo');
                                            end
                                        end
                                        set(gca,'XTick',[], 'YTick', [])
                                        %h=title(['State#' num2str(k_s,'%i') ', ' char(app.file(k_file).workflow(k_w).statetable(k_s,2))]);

                                        if isempty(char(app.file(k_file).workflow(k_w).statetable(k_s,4)))
                                            str_title = char(app.file(k_file).workflow(k_w).statetable(k_s,2));
                                        else
                                            str_title = char(app.file(k_file).workflow(k_w).statetable(k_s,4));
                                            if app.InsertfilenameincustomnameCheckBox.Value
                                                str_title = [char(app.SelectactivefileDropDown.Items(k_file)) '_' str_title];
                                            end
                                        end
                                        str_title = strrep(str_title,'_',' ');
                                        if length(str_title) > 25
                                            kstr = strfind(str_title,' ');
                                            if ~isempty(kstr)
                                                tmp = abs(length(str_title)/2 - kstr);
                                                ifcut = find(tmp==min(tmp));
                                                str_title = {str_title(1:kstr(ifcut)-1),str_title(kstr(ifcut)+1:end)};
                                            end
                                        end
                                        %h=title([char(app.file(k_file).workflow(k_w).statetable(k_s,2))]);
                                        h=title(str_title);
                                        h.FontSize = fontsize_title;
                                        h.FontName = app.fontname;

                                        if contains( char(app.file(k_file).workflow(k_w).statetable(k_s,2)),'Create new workflow') || contains( char(app.file(k_file).workflow(k_w).statetable(k_s,2)),'Combination')
                                            subname = char(app.file(k_file).workflow(k_w).statetable(k_s,3));
                                            subname = strrep(subname,'_',' ');
                                            kstr = strfind(subname,'and B:');
                                            if isempty(kstr)
                                                subtitle(subname,'Fontsize',fontsize_subtitle,'FontName',app.fontname);
                                            else
                                                subtitle({subname(1:kstr(1)-1),subname(kstr(1):end)},'Fontsize',fontsize_subtitle,'FontName',app.fontname);
                                            end
                                        end
                                    end
                                    if k_s==1
                                        yaxis_name = char(app.workflownames(k_file).names(k_w));
                                        yaxis_name = strrep(yaxis_name,'_',' ');
                                        if length(yaxis_name) > 25
                                            kstr = strfind(yaxis_name,' ');
                                            if ~isempty(kstr)
                                                tmp = abs(length(yaxis_name)/2 - kstr);
                                                ifcut = find(tmp==min(tmp));
                                                yaxis_name = {yaxis_name(1:kstr(ifcut)-1),yaxis_name(kstr(ifcut)+1:end)};
                                            end
                                        end
                                        h=ylabel(yaxis_name);
                                        h.FontSize = fontsize_workflowname;
                                        h.FontName = app.fontname;
                                    end
                                end
                            end
                        end
 
                    end
                end
                figname = Fig.Name;
                figname = strrep(figname,'_',' ');
                %sgtitle(figname,'Fontsize',14)
                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';
            end


        end

        % Button pushed function: FindparametersButton
        function FindparametersButtonPushed(app, event)
            downscaling_factor = app.DownscalingfactorEditField.Value;
            if downscaling_factor>1
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                dimension = length(sz);
                cropxyz = zeros(dimension,1);
                for k=1:1:dimension
                    tmp = [1:1:sz(k)];
                    tmp2 = mod(tmp,downscaling_factor);
                    idx = max(find(tmp2==0));
                    cropxyz(k) = length(tmp)-idx;
                end
                app.ROI_crop_UITable.Data(:,3).Variables = ones(dimension,1);
                app.ROI_crop_UITable.Data(:,4).Variables = sz'-cropxyz;
                if app.RelativedimensionCheckBox.Value
                    app.RelativedimensionCheckBoxValueChanged
                end
            end
        end

        % Value changed function: NLMF_ComparisonwindowsizeEditField, 
        % ...and 1 other component
        function NLMF_SearchwindowsizeEditFieldValueChanged(app, event)
            Searchwindowssize = app.NLMF_SearchwindowsizeEditField.Value;
            ComparisonWindowSize = app.NLMF_ComparisonwindowsizeEditField.Value;
            if rem(Searchwindowssize, 2)==0
                Searchwindowssize=Searchwindowssize+1;
            end
            if rem(ComparisonWindowSize, 2)==0
                ComparisonWindowSize=ComparisonWindowSize+1;
            end            
            if Searchwindowssize<ComparisonWindowSize
                s=Searchwindowssize; Searchwindowssize=ComparisonWindowSize; ComparisonWindowSize=s;
            end
            app.NLMF_SearchwindowsizeEditField.Value = Searchwindowssize;
            app.NLMF_ComparisonwindowsizeEditField.Value = ComparisonWindowSize;   
        end

        % Button pushed function: NLMF_DoButton
        function NLMF_DoButtonPushed(app, event)
            % Parameters
            p.degreeofsmoothing = app.NLMF_DegreeofsmoothingEditField.Value;
            p.searchwindowssize = app.NLMF_SearchwindowsizeEditField.Value;
            p.comparizonwindowssize = app.NLMF_ComparisonwindowsizeEditField.Value;
            p.estimatedegreeofsmoothing = app.NLMF_EstimatedegreeofsmoothingCheckBox.Value;
            
            if app.GLFilter_PreviewCheckBox.Value
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                [Mfiltered,~,~] = fct_NLMF(M,p);
                Fig = figure;
                Fig.Color = 'w';
                Fig.Name = 'Non-local means filter preview on middle slice';
                montage({M,Mfiltered});
                clim([min(min(M)) max(max(M))]);
            else

                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (non-local means filter) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,~,estDos] = fct_NLMF(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_NLMF';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = 'Filtering (non-local mean filter)';

                    if p.estimatedegreeofsmoothing
                        app.par = ['Auto estimation of degree of smoothing: ' num2str(estDos,'%1.3f') ', search window size: ' num2str(p.searchwindowssize,'%i') ', comparison window size: ' num2str(p.comparizonwindowssize,'%i')] ;
                    else
                        app.par = ['Degree of smoothing: ' num2str(p.degreeofsmoothing,'%1.3f') ', search window size: ' num2str(p.searchwindowssize,'%i') ', comparison window size: ' num2str(p.comparizonwindowssize,'%i')] ;
                    end

                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = 'Finished!';
            end

        end

        % Button down function: SemanticsegmentationTab
        function SemanticsegmentationTabButtonDown(app, event)
            % Update table
            if strcmp(app.Segmentation_expectedvf_UITable.Enable,'on')
                numberphase = app.Seg_Numberofphases_editfield.Value;
                tmp=zeros(numberphase,4);
                tmp(:,1) = [1:1:numberphase]';
                tmp(:,2) = [1:1:numberphase]'-1;
                tmp(:,3) = ones(numberphase,1)*1/numberphase;
                app.Segmentation_expectedvf_UITable.Data = table(tmp(:,1),tmp(:,2),tmp(:,3));

                min_ = double( min(min(min( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M ))));
                max_ = double( max(max(max( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M ))));
                if max_>1
                    tmpthrehsold = round(linspace(min_,max_,numberphase+1));
                else
                    tmpthrehsold = linspace(min_,max_,numberphase+1);
                end
                tmp=zeros(numberphase,3);
                tmp(:,1) = [1:1:numberphase]';
                tmp(:,2) = [1:1:numberphase]'-1;
                tmp(:,3) = tmpthrehsold(2:end)';
                app.Globalsegmentation_UITable.Data = table(tmp(:,1),tmp(:,2),tmp(:,3));     

                app.LocalSegmentation_Method_DropDownValueChanged;
            end
        end

        % Cell edit callback: Segmentation_expectedvf_UITable
        function Segmentation_expectedvf_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
            expectedvf = app.Segmentation_expectedvf_UITable.Data(:,3).Variables;
            if indices(2)==2 % Label
                if isnan(newData) || newData<0 || newData~=round(newData) || sum(ismember(labels,newData))>1
                    app.Segmentation_expectedvf_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            elseif indices(2)==3 % Expected volume fractions
                if isnan(newData) || newData<0 || newData>1
                    app.Segmentation_expectedvf_UITable.Data(indices(1),indices(2)) = {oldData};
                else
                    tmp = 1-sum(expectedvf);
                    if indices(1)>1
                        app.Segmentation_expectedvf_UITable.Data(1,3).Variables = app.Segmentation_expectedvf_UITable.Data(1,3).Variables+tmp;
                    else
                        app.Segmentation_expectedvf_UITable.Data(end,3).Variables = app.Segmentation_expectedvf_UITable.Data(end,3).Variables+tmp;
                    end
                end
            end
            app.Globalsegmentation_UITable.Data(:,2).Variables = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
        end

        % Value changed function: Seg_Numberofphases_editfield
        function Seg_Numberofphases_editfieldValueChanged(app, event)
            app.SemanticsegmentationTabButtonDown;
            app.LocalSegmentation_Method_DropDownValueChanged;
            if app.Seg_Numberofphases_editfield.Value > 2
                app.Morethan2phasesYoumayneedtocorrectartifactActionsLabel.Visible = 'on';
            else
                app.Morethan2phasesYoumayneedtocorrectartifactActionsLabel.Visible = 'off';
            end
        end

        % Value changed function: Global_segmentation_MethodDropDown
        function Global_segmentation_MethodDropDownValueChanged(app, event)
            value = app.Global_segmentation_MethodDropDown.Value;
            if strcmp(value,'Manual')
                app.Globalsegmentation_UITable.ColumnEditable = [false false true];
            else
                app.Globalsegmentation_UITable.ColumnEditable = [false false false];
            end            
        end

        % Button pushed function: Globalthreshold_DoButton
        function Globalthreshold_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.method = app.Global_segmentation_MethodDropDown.Value;
            p.numberofphase = app.Seg_Numberofphases_editfield.Value;
            p.labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
            p.expectedvf = app.Segmentation_expectedvf_UITable.Data(:,3).Variables;
            p.thresholds = app.Globalsegmentation_UITable.Data(:,3).Variables;

            p.excludebackground = app.Seg_ExcludebackgroundfromthecalculationCheckBox.Value;
            if p.excludebackground
                p.selectbackground = app.Seg_SelectbackgroundDropDown.Value;
                if strcmp(p.selectbackground,'From label')
                    p.background_label = app.Seg_BackgroundlabelEditField.Value;
                    str_back = ['from label: ' num2str(p.background_label)];
                else
                    from_f = app.Seg_FromfileDropDown.ValueIndex;
                    from_w = app.Seg_FromworkflowDropDown.ValueIndex;
                    from_s = str2num(app.Seg_FromstateDropDown.Value);
                    p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
                    p.backgroundstates = [from_f from_w from_s];   
                    str_back = ['from state: file#' num2str(from_f) ', workflow#' num2str(from_w) ', state#' num2str(from_s)];
                end
            end            

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.GlobalSeg_StatutIdleLabel.Text = ['Please wait... Action: Segmentation (global thresholding) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,thresholds] = fct_globalthresholding(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_globalthresholding';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = ['Global thresholding (' p.method '). ' num2str(p.numberofphase) ' phases'];
                app.Globalsegmentation_UITable.Data(:,3).Variables = thresholds;

                if strcmp(p.method,'Linear')
                    app.par = 'n/a';
                else
                    app.par = ['Thresholds = ' num2str(thresholds(1))];
                    for k=2:1:p.numberofphase
                        app.par = [app.par '-' num2str(thresholds(k))];
                    end
                end
                if p.excludebackground
                    app.par = [app.par ', background excluded: ' str_back];
                end
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                [~,str] = app.Segmentation_update_volumefraction(M,p.excludebackground);
                app.GlobalSeg_StatutIdleLabel.Text = ['Finished! ' str]; 
            end
        end

        % Cell edit callback: Globalsegmentation_UITable
        function Globalsegmentation_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            thresholds = app.Globalsegmentation_UITable.Data(:,3).Variables;
            if indices(2)==3 % Thresholds
                if isnan(newData) || newData<0 || sum(thresholds~=sort(thresholds)) || sum(ismember(thresholds,newData))>1
                    app.Globalsegmentation_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            end
        end

        % Value changed function: InstanceSeg_MethodDropDown
        function InstanceSeg_MethodDropDownValueChanged(app, event)
            value = app.InstanceSeg_MethodDropDown.Value;
            if strcmp(value,'Pseudo-Coulomb Repulsive Field (PCRF)')
                app.ExponentEditField.Enable = 'on';
                app.RepulsiveforcewindowrangeEditField.Enable = 'on';
                app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Enable = 'off'; app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Value = 0;
                app.InstanceSeg_VerboseCheckBox.Enable = 'on';
            elseif strcmp(value,'Watershed (custom)')
                app.ExponentEditField.Enable = 'off';
                app.RepulsiveforcewindowrangeEditField.Enable = 'off';
                app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Enable = 'on';
                app.InstanceSeg_VerboseCheckBox.Enable = 'on';
            elseif strcmp(value,'Watershed (built-in)')
                app.ExponentEditField.Enable = 'off';
                app.RepulsiveforcewindowrangeEditField.Enable = 'off';
                app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Enable = 'off'; app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Value = 0;
                app.InstanceSeg_VerboseCheckBox.Enable = 'off'; app.InstanceSeg_VerboseCheckBox.Value = 0;
            end
            app.InstanceSeg_DownscalingfactorEditFieldValueChanged;
            app.InstanceSeg_DownscalingfactorEditFieldValueChanged;
        end

        % Button pushed function: InstanceSeg_DoButton
        function InstanceSeg_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.labeltoinstance = app.InstancelabelEditField.Value;
            p.instance_method = app.InstanceSeg_MethodDropDown.Value;
            app.par = ['On label ' num2str(p.labeltoinstance) '.'];

            p.exponent_law = app.ExponentEditField.Value;
            p.max_dist = app.RepulsiveforcewindowrangeEditField.Value;
            if strcmp(p.instance_method,'Pseudo-Coulomb Repulsive Field (PCRF)')
                app.par = [app.par ' PCRF: exponent = ' num2str(p.exponent_law,'%i') ', window range = ' num2str(p.max_dist,'%i') '.'];
            end

            p.cpsd_refining = app.SpherefilteroversegmentationcorrectionCheckBox.Value;
            p.randomize = true;
            if p.cpsd_refining
                app.par = [app.par ' Oversegmentation correction (sphere filter).'];
            end
            if p.randomize
                app.par = [app.par ' Randomize label after identification.'];
            end

            p.perparts = app.SegmentingbypartsCheckBox.Value;
            p.perparts_choice = app.NumberofpartsDropDown.Value;
            p.perpart_nparts = app.NumberofpartsEditField.Value;
            p.perpart_millions = app.ByParts_TargetnumberofvoxelinmillionsEditField.Value;
            p.perpart_overlappinglength_choice = app.OverlappinglengthDropDown.Value;
            p.perpart_overlappinglength = app.OverlappingpartlengthEditField.Value;
            if p.perparts
                app.par = [app.par ' Segmentation by parts. Number of parts selection: ' p.perparts_choice '.'];
                if strcmp(p.perparts_choice,'Manual')
                    app.par = [app.par ' Number of independent parts: ' num2str( p.perpart_nparts) '.'];
                elseif strcmp(p.perparts_choice,'Auto')
                    app.par = [app.par ' target number of voxels per parts ' num2str( p.perpart_millions) ' million voxels.'];
                end
                app.par = [app. par ' Overlapping length selection: ' p.perpart_overlappinglength_choice '.'];
                if strcmp(p.perpart_overlappinglength_choice,'Manual')
                    app.par = [app. par ' Overlapping length: ' num2str(p.perpart_overlappinglength) '.'];
                end
            end

            p.downscaling = app.downscalingupscalingschemeCheckBox.Value;
            p.downscaling_choice = app.DownscalingfactorDropDown.Value;
            p.downscaling_factor = app.InstanceSeg_DownscalingfactorEditField.Value;
            p.downscaling_millions = app.Downscaling_TargetnumberofvoxelinmillionsEditField.Value;
            p.downscaling_show = app.InstanceSeg_ShowDownscalingCheckBox.Value;
            if strcmp(app.CropvolumesifneededandDropDown.Value,'extrapolate to keep same dimension')
                p.extrapolate = true;
            else
                p.extrapolate = false;
            end
            if p.downscaling
                app.par = [app.par ' Downscaling-upscaling scheme. downscaling factor selection: ' p.downscaling_choice '.'];
                if strcmp(p.downscaling_choice,'Manual')
                    app.par = [app.par ' Downscaling factor: ' num2str( p.downscaling_factor) '.'];
                elseif strcmp(p.downscaling_choice,'Auto')
                    app.par = [app.par ' target number of voxels ' num2str( p.downscaling_millions) ' million voxels.'];
                elseif strcmp(p.downscaling_choice,'Auto (and round)')
                    app.par = [app.par ' target number of voxels ' num2str( p.downscaling_millions) ' million voxels (round factor).'];                    
                end
                app.par = [app.par ' Crop volumes if needed and ' app.CropvolumesifneededandDropDown.Value];
            end

            p.erosion = app.PreerosionCheckBox.Value;
            p.erosion_distance = app.InstanceSeg_ErosiondistanceEditField.Value;
            p.erosion_display = app.InstanceSeg_ShowerosionCheckBox.Value;
            if strcmp(app.InstanceSeg_OrderDropDown.Value,'Downscaling, erosion, segmentation, upscaling')
                p.downscaling_before_erosion = true;
            elseif strcmp(app.InstanceSeg_OrderDropDown.Value,'Erosion, downscaling, segmentation, upscaling')
                p.downscaling_before_erosion = false;
            end
            if p.erosion
                app.par = [app.par ' Pre-erosion: erosion distance: ' num2str(p.erosion_distance) '.'];
                if p.erosion && p.downscaling
                    app.par = [app.par ' Order: ' app.InstanceSeg_OrderDropDown.Value '.'];
                end
            end

            p.reapply_perinstance = false;
            if p.reapply_perinstance
                app.par = [app.par ' And re-apply per instance at original resolution.'];
            end

            p.details_convergence = app.InstanceSeg_VerboseCheckBox.Value; % Verbose
            
            p.visualize_2D = app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Value; % Only for illustration
            
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.InstanceSeg_StatutIdleLabel.Text = ['Please wait... Action: Segmentation (instance) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_instancesegmentation2(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_instancesegmentation2';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                if strcmp(p.instance_method,'Pseudo-Coulomb Repulsive Field (PCRF)')
                    app.act = 'Instance segmentation: PCRF';
                else
                    app.act = ['Instance segmentation: ' p.instance_method];
                end

               
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                n_instance = length(unique(M))-1;
                app.InstanceSeg_StatutIdleLabel.Text = ['Finished! Number of instances: ' num2str(n_instance,'%i')]; 
            end  
        end

        % Value changed function: InstanceSeg_DownscalingfactorEditField, 
        % ...and 1 other component
        function InstanceSeg_DownscalingfactorEditFieldValueChanged(app, event)
            Downscaling_factor = app.InstanceSeg_DownscalingfactorEditField.Value;
            erosion_distance = app.InstanceSeg_ErosiondistanceEditField.Value;
            if Downscaling_factor==1 || erosion_distance==0
                app.InstanceSeg_OrderDropDown.Enable = 'off';
            else
                app.InstanceSeg_OrderDropDown.Enable = 'on';

            end

            if erosion_distance==0
                app.InstanceSeg_ShowerosionCheckBox.Enable = 'off';
                app.InstanceSeg_ShowerosionCheckBox.Value = false;
            else
                app.InstanceSeg_ShowerosionCheckBox.Enable = 'on';
            end
            % if Downscaling_factor==1
            %     app.ApplyperinstanceatoriginalresolutionCheckBox.Enable = 'off';
            %     app.ApplyperinstanceatoriginalresolutionCheckBox.Value = false;
            % else
            %     app.ApplyperinstanceatoriginalresolutionCheckBox.Enable = 'on';
            % end     
        end

        % Button pushed function: Randomize_DoButton
        function Randomize_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            p.keepunchangedsomelabels = app.ButdonotmodifysomelabelsCheckBox.Value;
            p.cst = app.Randomize_Constant_EditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Reassign_StatutIdleLabel.Text = ['Please wait... Action: Re-assign (randomize) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                p.basedondistance = app.RandomizeDistance_CheckBox.Value;
                p.dist = app.Rnd_Checkuptoadistance_EditField.Value;
                [M,~,~] = fct_randomize(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_randomize';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Randomize labels';

                if p.basedondistance
                    app.par = ['Based on distance, up to ' num2str(p.dist) ' voxels'];
                else
                    app.par = 'Random';
                end                
                if p.keepunchangedsomelabels
                    app.par = [app.par '. Unchanged labels: ' p.cst];
                end   

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end  
            app.Reassign_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: LocalDilation_Shell_DoButton
        function Particleconcaveshell_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.LocalDilation_StatutIdleLabel.Text = ['Please wait... Action: local dilation (concave shell) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_concaveshell(M,[]);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = [];
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_concaveshell';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Concave shell';
                app.par = 'n/a' ;

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                app.LocalDilation_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Cell edit callback: Reassign_UITable
        function Reassign_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==2 % New value
                if isnan(newData) || newData<0 || newData~=round(newData)
                    app.Reassign_UITable.Data(indices(1),indices(2)) = oldData;
                end
            end
        end

        % Button pushed function: Reassign_DoButton
        function Reassign_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.oldlabels = app.Reassign_UITable.Data(:,1);
            p.newlabels = app.Reassign_UITable.Data(:,2);

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Reassign_StatutIdleLabel.Text = ['Please wait... Action: Re-assign (manual relabelling) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_manual_reassign(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_manual_reassign';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Manual reassignment';

                app.par = ['Old labels: ' num2str(p.oldlabels') ', new labels: ' num2str(p.newlabels')] ;
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Reassign_StatutIdleLabel.Text = 'Finished!';            
        end

        % Button pushed function: Thinlayerartifact_DoButton
        function Thinlayerartifact_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.width_threshold = app.WidththresholdEditField.Value;
            p.illustrate = false;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.FilterSEG_StatutIdleLabel.Text = ['Please wait... Action: Filtering (thin layer artifact removal) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_removethinlayerartifact(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_removethinlayerartifact';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Filtering (thin layer artifact removal)';
                app.par = ['Width threshold: ' num2str(p.width_threshold)] ;

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                [~,str] = app.Segmentation_update_volumefraction(M, false);
                app.FilterSEG_StatutIdleLabel.Text = ['Finished! ' str];
            end
        end

        % Button pushed function: Delete_WorkflowButton
        function Delete_WorkflowButtonPushed(app, event)
            if app.active_w>1
                app.file(app.active_f).workflow(app.active_w)=[]; % Remove workflow
                app.workflownames(app.active_f).names(app.active_w)=[];
                app.active_w = app.active_w-1;

                % Propagate
                app.SelectactiveworkflowDropDown.Items = app.workflownames(app.active_f).names;
                app.SelectactiveworkflowDropDown.Value = app.SelectactiveworkflowDropDown.Items(app.active_w);
                app.SelectactiveworkflowDropDownValueChanged;

                app.FromexistingworkflowDropDown.Items = app.SelectactiveworkflowDropDown.Items;

                app.CombinationA_FromworkflowDropDown.Items= app.SelectactiveworkflowDropDown.Items;
                app.CombinationB_FromworkflowDropDown.Items= app.SelectactiveworkflowDropDown.Items;

                app.CombinationA_FromfileDropDownValueChanged;
                app.CombinationB_FromfileDropDownValueChanged;
                app.Macro_FromfileDropDownValueChanged;
                app.Macro_TofileDropDownValueChanged;
            end
        end

        % Button pushed function: Filter_DoButton
        function Filter_DoButtonPushed(app, event)
            % Parameters
            p.filter = app.FilterDropDown.Value;
            p.sigma = app.Filter_SigmaEditField.Value;
            p.filtersize = app.Filter_RangeEditField.Value;
            p.padding = app.Filter_PaddingDropDown.Value;
            p.domain = app.GaussianFilter_Domain_DropDown.Value;
            
            if app.GLFilter_PreviewCheckBox.Value
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                [Mfiltered,~,~] = fct_filter(M,p);
                Fig = figure;
                Fig.Color = 'w';
                Fig.Name = [p.filter ' preview on middle slice'];
                montage({M,Mfiltered});
                clim([min(min(M)) max(max(M))]);                
            else
                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (' p.filter ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,~,~] = fct_filter(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_filter';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = ['Filtering (' p.filter ')'];
                    if strcmp(p.filter,'Gaussian filter')
                        app.par = ['Sigma=' num2str(p.sigma) ' ,Filtersize=' num2str(p.filtersize,'%i') ', padding: ' p.padding ', domain: ' p.domain];
                    elseif strcmp(p.filter,'Mean filter')
                        app.par = ['Filtersize=' num2str(p.filtersize,'%i') ', padding: ' p.padding] ;
                    end

                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Value changed function: Filter_RangeEditField
        function Filter_RangeEditFieldValueChanged(app, event)
            value = app.Filter_RangeEditField.Value;
            if rem(value,2) == 0
                app.Filter_RangeEditField.Value = event.PreviousValue;                
            end            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            if strcmp(value,'Mean filter')
                app.Filter_SigmaEditField.Enable = 'off';
                app.GaussianFilter_Domain_DropDown.Enable = 'off';
            elseif strcmp(value,'Gaussian filter')
                app.Filter_SigmaEditField.Enable = 'on';
                app.GaussianFilter_Domain_DropDown.Enable = 'on';
            end
        end

        % Value changed function: Filter_SigmaEditField
        function Filter_SigmaEditFieldValueChanged(app, event)
            sigma = app.Filter_SigmaEditField.Value;
            % set default value
            app.Filter_RangeEditField.Value = 2*ceil(2*sigma)+1;            
        end

        % Button pushed function: Channel_Gradientmagnitude_DoButton_2
        function Channel_Gradientmagnitude_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.method = app.Channel_GraidientMethodDropDown_2.Value;

            app.current_channel = ['Gradient ' p.method];

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Channel_StatutIdleLabel_2.Text = ['Please wait... Action: Channel (gradient magnitude) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,p] = fct_gradmagnitude(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_gradmagnitude';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = 'Channel (gradient magnitude)';
                app.par = ['Method: ' p.method];
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Channel_StatutIdleLabel_2.Text = 'Finished!';
        end

        % Button pushed function: Channel_Std_DoButton_2
        function Channel_Std_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end        

            % Parameters
            p.Morphologicalstructuringelement = app.Channel_Std_StructuringelementDropDown_2.Value;
            p.metric = app.Texture_MetricDropDown_2.Value;
            p.range = app.Channel_std_RangeEditField_2.Value;          

            app.current_channel = p.metric;            

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Channel_StatutIdleLabel_2.Text = ['Please wait... Action: Channel (' p.metric ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_texture(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_texture';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = p.metric;
                app.par = ['Structuring element: ' p.Morphologicalstructuringelement ', range=' num2str(p.range,'%i')];
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Channel_StatutIdleLabel_2.Text = 'Finished!';            
        end

        % Button pushed function: Seg_Slider_Button
        function Seg_Slider_ButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            isgroundtruth = false;
            % Initialize a 2-phase volume
            segmentedvolume = zeros(size(M));
            dumb_threshold = round((max(max(max(M))) + min(min(min(M))))/2);
            segmentedvolume(M>dumb_threshold)=1;
            segmentedvolume = uint8(segmentedvolume);

            name = [char(app.SelectactivefileDropDown.Items(app.active_f)) ', ' char(app.workflownames(app.active_f).names(app.active_w)) ', ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            name = strrep(name,'_',' ');

            manual_segmentation = true;
            %microstructure_visualization_segmentation(M, isgroundtruth, segmentedvolume, name, 'Manual thresholding', 1, [] ,manual_segmentation);

            greyvolume(1).array = M;
            greyvolume(1).name = name;
            segmentedvolumes(1).array = segmentedvolume;
            segmentedvolumes(1).name = 'Manual segmentation';
            microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  1, [], manual_segmentation);

        end

        % Callback function: Comparison_FromfileDropDown, 
        % ...and 1 other component
        function Comparison_FromfileDropDownValueChanged(app, event)
            from_f = app.Comparison_FromfileDropDown.ValueIndex;
            % Propagate
            app.Comparison_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.Comparison_FromworkflowDropDown.Value = app.Comparison_FromworkflowDropDown.Items(end);
            app.Comparison_FromworkflowDropDownValueChanged;   
        end

        % Value changed function: Comparison_FromworkflowDropDown
        function Comparison_FromworkflowDropDownValueChanged(app, event)
            from_f = app.Comparison_FromfileDropDown.ValueIndex;
            from_w = app.Comparison_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.Comparison_FromstateDropDown.Items = tmp;
            app.Comparison_FromstateDropDown.Value = app.Comparison_FromstateDropDown.Items(1);
        end

        % Button pushed function: Comparison_AddfileButton
        function Comparison_AddfileButtonPushed(app, event)
            add_f = app.Comparison_FromfileDropDown.ValueIndex;
            add_w = app.Comparison_FromworkflowDropDown.ValueIndex;
            add_s = str2num(app.Comparison_FromstateDropDown.Value);

            filename = app.Comparison_FromfileDropDown.Value;
            workflowname = app.workflownames(add_f).names(add_w);
            sz = size(app.file(add_f).workflow(add_w).state(add_s).M);
            dimension = length(sz);
            if dimension == 2
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
            else
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
            end
            datatype = {app.file(add_f).workflow(add_w).state(add_s).type};

            tmp = [filename workflowname {num2str(add_s)} s datatype {true}];
            if isempty(app.Comparison_UITable.Data)
                app.Comparison_UITable.Data = tmp;
                app.comp_index = [add_f add_w add_s];
            else
                app.Comparison_UITable.Data = [app.Comparison_UITable.Data ;tmp];
                app.comp_index = [app.comp_index; [add_f add_w add_s]];
            end
            app.Comparison_UITableCellEdit;
        end

        % Button pushed function: Comparison_CleartableButton
        function Comparison_CleartableButtonPushed(app, event)
            app.Comparison_UITable.Data = [];
            app.comp_index = [];
            app.Comparison_VisualizeButton.Enable = 'off';
        end

        % Button pushed function: Comparison_VisualizeButton
        function Comparison_VisualizeButtonPushed(app, event)
            idx = find(cell2mat(app.Comparison_UITable.Data(:,6)));

            if strcmp(app.ComparisontypeDropDown.Value,'Mosaic')
                % Build the string require to call the visualization function
                %str = 'Microstructure_comparison_visualization_interface('; % old
                str = 'Visualization_multiple_volumes(p,';
                first_volume = true;
                for k=1:1:length(idx)
                    if first_volume
                        str = [str 'app.file(' num2str(app.comp_index(idx(k),1)) ').workflow(' num2str(app.comp_index(idx(k),2)) ').state(' num2str(app.comp_index(idx(k),3)) ').M'];
                        first_volume = false;
                    else
                        str = [str ',app.file(' num2str(app.comp_index(idx(k),1)) ').workflow(' num2str(app.comp_index(idx(k),2)) ').state(' num2str(app.comp_index(idx(k),3)) ').M'];
                    end
                    p(k).name = [char(app.Comparison_UITable.Data(idx(k))) ', ' char(app.workflownames(app.comp_index(idx(k),1)).names(app.comp_index(idx(k),2))) ', ' char(app.file(app.comp_index(idx(k),1)).workflow(app.comp_index(idx(k),2)).statetable(app.comp_index(idx(k),3),2))];
                end
                str = [str ');'];
                eval(str);
            elseif strcmp(app.ComparisontypeDropDown.Value,'Segmentation') % Table callback makes sure this case only enable if possible
                types = app.Comparison_UITable.Data(:,5);
                types = types(idx);
                compindex = app.comp_index(idx,:);

                greyidx = find(strcmp(types,'Grey level'));
                segidx = [find(strcmp(types,'Segmented (phase)')); find(strcmp(types,'Segmented (instance)'))];

                Mgrey = app.file(compindex(greyidx,1)).workflow(compindex(greyidx,2)).state(compindex(greyidx,3)).M;

                isgroundtruth = false;
                greyname = [char(app.SelectactivefileDropDown.Items(compindex(greyidx,1))) ', ' char(app.workflownames(compindex(greyidx,1)).names(compindex(greyidx,2))) ', ' char(app.file(compindex(greyidx,1)).workflow(compindex(greyidx,2)).statetable(compindex(greyidx,3),2))];
                greyname = strrep(greyname,'_',' ');

                manual_segmentation = false;


                greyvolume(1).array = Mgrey;
                greyvolume(1).name = greyname;
                if length(idx)==2
                    Mseg = app.file(compindex(segidx,1)).workflow(compindex(segidx,2)).state(compindex(segidx,3)).M;
                    segname = [char(app.SelectactivefileDropDown.Items(compindex(segidx,1))) ', ' char(app.workflownames(compindex(segidx,1)).names(compindex(segidx,2))) ', ' char(app.file(compindex(segidx,1)).workflow(compindex(segidx,2)).statetable(compindex(segidx,3),2))];
                    segname = strrep(segname,'_',' ');        
                    %microstructure_visualization_segmentation(Mgrey, isgroundtruth, Mseg, greyname, segname, 1, [],manual_segmentation);

                    segmentedvolumes(1).array = Mseg;
                    segmentedvolumes(1).name = segname;
                    microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  1, [], manual_segmentation);
                    %microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  0.319, 'um', manual_segmentation);
                    
                else
                    imds.loadallatonce = true;
                    imds.no_import_needed = true;
                    % Create imds
                    for k=1:1:length(segidx)
                        segname = [char(app.SelectactivefileDropDown.Items(compindex(segidx(k),1))) ', ' char(app.workflownames(compindex(segidx(k),1)).names(compindex(segidx(k),2))) ', ' char(app.file(compindex(segidx(k),1)).workflow(compindex(segidx(k),2)).statetable(compindex(segidx(k),3),2))];
                        segname = strrep(segname,'_',' ');
                        %imds.Files(k) = {segname};
                        %imds.Data.Files(k).M = app.file(compindex(segidx(k),1)).workflow(compindex(segidx(k),2)).state(compindex(segidx(k),3)).M;
                        segmentedvolumes(k).array = app.file(compindex(segidx(k),1)).workflow(compindex(segidx(k),2)).state(compindex(segidx(k),3)).M;;
                        segmentedvolumes(k).name = segname;                        
                    end
                    % microstructure_visualization_segmentation(Mgrey, isgroundtruth, imds, greyname, [], 1, [],manual_segmentation);
                    microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  1, [], manual_segmentation);
                    %microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  2.747, 'um', manual_segmentation);

                end

            end

        end

        % Button down function: ComparisonsTab
        function ComparisonsTabButtonDown(app, event)
            app.Comparison_FromfileDropDown.Items = app.CombinationA_FromfileDropDown.Items; % All the heavy lifting has been done already for the Combination tab
            if ~isempty(app.Comparison_FromfileDropDown.Items)
                app.Comparison_FromfileDropDown.Value = app.Comparison_FromfileDropDown.Items(1);
                app.Comparison_FromfileDropDownValueChanged;
            end
        end

        % Cell edit callback: Comparison_UITable
        function Comparison_UITableCellEdit(app, event)
            if ~isempty(app.Comparison_UITable)
                idx = find(cell2mat(app.Comparison_UITable.Data(:,6)));
                if length(idx)>=1
                    app.Comparison_VisualizeButton.Enable = 'on';
                    types = app.Comparison_UITable.Data(:,5);
                    types=types(idx);
                    sizes = app.Comparison_UITable.Data(:,4);
                    sizes=sizes(idx);
                    n_seg = sum(ismember(types,'Segmented (phase)')) + sum(ismember(types,'Segmented (instance)'));
                    n_grey = sum(ismember(types,'Grey level'));
                    n_channel = sum(ismember(types,'Channel'));
                    if n_grey == 1 && n_seg>=1 && n_channel==0 && length(unique(sizes))==1
                        app.ComparisontypeDropDown.Items = {'Mosaic' 'Segmentation'};
                    else
                        app.ComparisontypeDropDown.Items = {'Mosaic'};
                    end
                else
                    app.ComparisontypeDropDown.Items = {'Mosaic'};
                end
            end            
        end

        % Value changed function: Greylevel_CalculateDropDown
        function Greylevel_CalculateDropDownValueChanged(app, event)
            if app.Greylevel_CalculateDropDown.Enable
                value = app.Greylevel_CalculateDropDown.Value;
                if strcmp(value,'Histogram for the whole volume')
                    app.Greylevel_OptionDropDown.Enable = 'on';
                    app.Greylevel_OptionDropDown.Items = {'Bins number: auto' 'Bins number: one per grey level' 'Bins number: custom' 'Distribution function (discrete)' 'Distribution function (continuous)'};
                    app.Greylevel_OptionDropDownValueChanged;
                    app.Calc_AlllinesCheckBox.Enable = 'off'; app.Calc_NlinesEditField.Enable = 'off';
                    app.Calc_ColrmapDropDown.Enable = 'off'; app.Calc_PerspectiveDropDown.Enable = 'off';

                elseif strcmp(value,'Histogram per slice (orthogonal slices)') || strcmp(value,'Histogram per radial position')
                    app.Greylevel_OptionDropDown.Enable = 'on';
                    app.Greylevel_OptionDropDown.Items = {'Distribution function (discrete)' 'Distribution function (continuous)'};
                    app.Greylevel_OptionDropDownValueChanged;                    
                    app.NbinsEditField.Enable = 'off';
                    app.Calc_AlllinesCheckBox.Enable = 'on'; app.Calc_AlllinesCheckBoxValueChanged;
                    app.Calc_MovingaveragefilterEditField.Enable = 'on'; app.Calc_LinewidthEditField.Enable = 'on';
                    app.Calc_ColrmapDropDown.Enable = 'on'; app.Calc_PerspectiveDropDown.Enable = 'on';                    

                elseif strcmp(value,'Grey level per slice (orthogonal slices)') || strcmp(value,'Grey level per radial position')
                    app.Greylevel_OptionDropDown.Enable = 'off'; app.NbinsEditField.Enable = 'off';
                    app.Calc_AlllinesCheckBox.Enable = 'off'; app.Calc_NlinesEditField.Enable = 'off';
                    app.Calc_MovingaveragefilterEditField.Enable = 'off'; app.Calc_LinewidthEditField.Enable = 'off';
                    app.Calc_ColrmapDropDown.Enable = 'off'; app.Calc_PerspectiveDropDown.Enable = 'off';                      

                elseif strcmp(value,'2D projection maps (3D volume only)')
                    app.Greylevel_OptionDropDown.Enable = 'off'; app.NbinsEditField.Enable = 'off';     
                    app.NbinsEditField.Enable = 'off'; app.Calc_NlinesEditField.Enable = 'off';
                    app.Calc_MovingaveragefilterEditField.Enable = 'off'; app.Calc_LinewidthEditField.Enable = 'off';
                    app.Calc_ColrmapDropDown.Enable = 'on'; app.Calc_PerspectiveDropDown.Enable = 'on';
                end
            end            
        end

        % Button pushed function: Greylevel_CalculateButton
        function Greylevel_CalculateButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;

            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;            

            if strcmp(app.file(app.active_f).workflow(app.active_w).state(app.active_s).type,'Channel')
                p.xlabel = app.current_channel;
            else
                p.xlabel = 'Grey level';
            end

            p.excludebackground = app.ExcludebackgroundfromthecalculationCheckBox.Value;
            if p.excludebackground
                p.selectbackground = app.CalcGrey_SelectbackgroundDropDown.Value;
                if strcmp(p.selectbackground,'From label')
                    p.background_label = app.BackgroundlabelEditField_2.Value;
                else
                    from_f = app.Background_FromfileDropDown.ValueIndex;
                    from_w = app.Background_FromworkflowDropDown.ValueIndex;
                    from_s = str2num(app.Background_FromstateDropDown.Value);
                    p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
                end
            end

            choice = app.Greylevel_CalculateDropDown.Value;

            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = ['Please wait... Calculation: ' choice ' on file: ' char(app.SelectactivefileDropDown.Items(app.active_f))];
                pause(0.1);
            end
            if strcmp(choice,'Histogram for the whole volume')
                p.option = app.Greylevel_OptionDropDown.Value;
                p.Nbin = app.NbinsEditField.Value;
                p.movingaverage = app.Calc_MovingaveragefilterEditField.Value;
                p.linewidth = app.Calc_LinewidthEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plothistogram_wholevolume(M,p);
                else
                    app.macro_fct = 'fct_plothistogram_wholevolume';
                    app.macro_p = p;
                end
            elseif strcmp(choice,'Histogram per slice (orthogonal slices)')
                p.voxelsize = app.Calc_VoxelsizeEditField.Value;
                p.voxelunit = app.Calc_UnitEditField.Value;
                p.colormap =  app.Calc_ColrmapDropDown.Value;
                p.perspective = app.Calc_PerspectiveDropDown.Value;
                p.allines = app.Calc_AlllinesCheckBox.Value;
                p.nlines = app.Calc_NlinesEditField.Value;
                p.option = app.Greylevel_OptionDropDown.Value;
                p.movingaverage = app.Calc_MovingaveragefilterEditField.Value;
                p.linewidth = app.Calc_LinewidthEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plothistogram_perslice(M,p)
                else
                    app.macro_fct = 'fct_plothistogram_perslice';
                    app.macro_p = p;
                end
            elseif strcmp(choice,'Grey level per slice (orthogonal slices)')
                p.voxelsize = app.Calc_VoxelsizeEditField.Value;
                p.voxelunit = app.Calc_UnitEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plotgreylevel_perslice(M,p)
                else
                    app.macro_fct = 'fct_plotgreylevel_perslice';
                    app.macro_p = p;
                end
            elseif strcmp(choice,'Histogram per radial position')
                p.voxelsize = app.Calc_VoxelsizeEditField.Value;
                p.voxelunit = app.Calc_UnitEditField.Value;
                p.colormap =  app.Calc_ColrmapDropDown.Value;
                p.perspective = app.Calc_PerspectiveDropDown.Value;
                p.allines = app.Calc_AlllinesCheckBox.Value;
                p.nlines = app.Calc_NlinesEditField.Value;
                p.option = app.Greylevel_OptionDropDown.Value;
                p.movingaverage = app.Calc_MovingaveragefilterEditField.Value;
                p.linewidth = app.Calc_LinewidthEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plothistogram_perradialposition(M,p)
                else
                     app.macro_fct = 'fct_plothistogram_perradialposition';
                    app.macro_p = p;
                end
            elseif strcmp(choice,'Grey level per radial position')
                p.voxelsize = app.Calc_VoxelsizeEditField.Value;
                p.voxelunit = app.Calc_UnitEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plotgreylevel_perradialposition(M,p)
                else
                    app.macro_fct = 'fct_plotgreylevel_perradialposition';
                    app.macro_p = p;
                end
            elseif strcmp(choice,'2D projection maps (3D volume only)')
                if length(size(M))<3
                    warning('2D projection maps can only be calculated for 3D volumes');
                else
                    p.colormap =  app.Calc_ColrmapDropDown.Value;
                    p.perspective = app.Calc_PerspectiveDropDown.Value;
                    if ~app.Macro_AddacalculationButton.Value
                        fct_plotgreylevel_projection(M,p);
                    else
                    app.macro_fct = 'fct_plotgreylevel_projection';
                    app.macro_p = p;
                    end
                end
            end

            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Finished!';
            else
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Calculation saved in macro. Add another calculation or go back to the macro tab.';
                app.macro_fctname = choice;
                app.macro_add_calculation;
            end

        end

        % Button down function: Calc_OngreylevelTab
        function Calc_OngreylevelTabButtonDown(app, event)
            app.Greylevel_CalculateDropDownValueChanged;
        end

        % Value changed function: Greylevel_OptionDropDown
        function Greylevel_OptionDropDownValueChanged(app, event)
            value = app.Greylevel_OptionDropDown.Value;
            if strcmp(value,'Bins number: custom')
                app.NbinsEditField.Enable = 'on';
                app.Calc_MovingaveragefilterEditField.Enable = 'off'; app.Calc_LinewidthEditField.Enable = 'off';
            elseif strcmp(value,'Distribution function (discrete)') || strcmp(value,'Distribution function (continuous)')
                app.NbinsEditField.Enable = 'off';
                app.Calc_MovingaveragefilterEditField.Enable = 'on'; app.Calc_LinewidthEditField.Enable = 'on';
            else
                app.NbinsEditField.Enable = 'off';
                app.Calc_MovingaveragefilterEditField.Enable = 'off'; app.Calc_LinewidthEditField.Enable = 'off';
            end
        end

        % Button pushed function: Imagequality_CalculateButton
        function Imagequality_CalculateButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;

            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;            

            if strcmp(app.file(app.active_f).workflow(app.active_w).state(app.active_s).type,'Channel')
                p.xlabel = app.current_channel;
            else
                p.xlabel = 'Grey level';
            end

            p.excludebackground = app.ExcludebackgroundfromthecalculationCheckBox.Value;
            if p.excludebackground
                p.selectbackground = app.CalcGrey_SelectbackgroundDropDown.Value;
                if strcmp(p.selectbackground,'From label')
                    p.background_label = app.BackgroundlabelEditField_2.Value;
                else
                    from_f = app.Background_FromfileDropDown.ValueIndex;
                    from_w = app.Background_FromworkflowDropDown.ValueIndex;
                    from_s = str2num(app.Background_FromstateDropDown.Value);
                    p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
                end
            end

            choice = app.SepCriterion_ChoiceDropDown.Value;
            choice2 = app.ImageQuality_DropDown.Value;


            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = ['Please wait... Calculation: image quality on file: ' char(app.SelectactivefileDropDown.Items(app.active_f))];
                pause(0.1);
            end

            p.linewidth = app.Calc_LinewidthEditField.Value;
            if strcmp(choice,'Plot maximum separability critertion')
                p.n_min = app.SepCriterion_minEditField.Value;
                p.n_max = app.SepCriterion_maxEditField.Value;
                if strcmp(choice2,'Global')
                    if ~app.Macro_AddacalculationButton.Value
                        fct_plotseparability_wholevolume(M,p);
                    else
                        app.macro_fct = 'fct_plotseparability_wholevolume';
                        app.macro_p = p;
                    end
                elseif strcmp(choice2,'Local')
                    p.voxelsize = app.Calc_VoxelsizeEditField.Value;
                    p.voxelunit = app.Calc_UnitEditField.Value;
                    if ~app.Macro_AddacalculationButton.Value
                        fct_plotseparability_perslice(M,p);
                    else
                        app.macro_fct = 'fct_plotseparability_perslice';
                        app.macro_p = p;
                    end
                end
            elseif strcmp(choice,'Plot all separability criterion')
                p.n_phase = app.SepCriterion_minEditField.Value;
                if ~app.Macro_AddacalculationButton.Value
                    fct_plotallseparability_wholevolume(M,p);
                else
                    app.macro_fct = 'fct_plotallseparability_wholevolume';
                    app.macro_p = p;
                end
            end

            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Finished!';
            else
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Calculation saved in macro. Add another calculation or go back to the macro tab.';
                app.macro_fctname = choice;
                app.macro_add_calculation;
            end
        end

        % Button pushed function: Flipswap_DoButton
        function Flipswap_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.action = app.Flipswap_DropDown.Value;
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.ROI_StatutIdleLabel.Text = ['Please wait... Action: ROI (flip or swap axis) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_flipswap(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_flipswap';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = ['ROI (' p.action ')'];
                app.par = 'n/a';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.ROI_StatutIdleLabel.Text = 'Finished!';
        end

        % Button down function: RegionofInterestTab
        function RegionofInterestTabButtonDown(app, event)
            % if strcmp(app.ROI_crop_UITable.Enable,'on')
            %     sz = size(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);
            %     dimension = length(sz);
            %     if dimension==2
            %         app.Flipswap_DropDown.Items = {'Flip axis 1' 'Flip axis 2' 'Swap axis 1 with axis 2'};
            %         app.AxisofrotationDropDown.Items = {'Axis 3'};
            %         app.AxisofrotationDropDown.Items = {'3'};
            %         app.ROI_visu_SliceSlider.Enable = 'off'; app.ROI_visu_SliceSlider.Visible = 'off';
            %     else
            %         app.Flipswap_DropDown.Items = {'Flip axis 1' 'Flip axis 2' 'Flip axis 3' 'Swap axis 1 with axis 2' 'Swap axis 1 with axis 3' 'Swap axis 2 with axis 3'};
            %         app.AxisofrotationDropDown.Items = {'Axis 1' 'Axis 2' 'Axis 3'};
            %         app.AxisofrotationDropDown.Items = {'1' '2' '3'};
            %         app.ROI_visu_SliceSlider.Enable = 'on'; app.ROI_visu_SliceSlider.Visible = 'on';
            %     end
            % end
            tmp = app.States_UITable.Data;    
            if ~isempty(tmp)
                app.ROI_StateLabel.Text = ['State #' num2str(app.active_s) ': ' char(tmp(app.active_s,2))];
            end
        end

        % Button pushed function: Rotation_DoButton
        function Rotation_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.axis = app.AxisofrotationDropDown.Value;
            p.angle = app.AngledegreesSpinner.Value;
            p.addition = app.Apply1beforerotationCheckBox.Value;
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.ROI_StatutIdleLabel.Text = ['Please wait... Action: rotation on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                p.datatype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_rotation(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_rotation';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = ['ROI (rotation, ' p.axis ')'];
                app.par = ['Angle: ' num2str(p.angle,'%1.2f')] ;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.ROI_StatutIdleLabel.Text = 'Finished!';
        end

        % Value changed function: ROI_visu_SliceSlider
        function ROI_visu_SliceSliderValueChanged(app, event)
            value = app.ROI_visu_SliceSlider.Value;
            app.ROI_visu_SliceSlider.Value = round(value);
            app.ROI_SliceEditField.Value = app.ROI_visu_SliceSlider.Value; 
            app.Update_ROI_view;
        end

        % Value changed function: ROI_visu_AxisDropDown
        function ROI_visu_AxisDropDownValueChanged(app, event)
            axe_view = str2num(app.ROI_visu_AxisDropDown.Value);
            sz = size(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);

            keepsz = [];
            if app.change_quickstate
                if length(sz) == length(app.previous_sz) && min(sz==app.previous_sz)==1
                    keepsz = app.ROI_visu_SliceSlider.Value;
                end
                app.change_quickstate=false; % reset
            end

            if axe_view==1
                app.ROI_visu_SliceSlider.Limits = [1 sz(1)];
                app.ROI_visu_SliceSlider.Value = round(sz(1)/2);
                app.ROI_UIAxes.XLabel.String = 'Axe 3';
                app.ROI_UIAxes.YLabel.String = 'Axe 2';               
            elseif axe_view==2
                app.ROI_visu_SliceSlider.Limits = [1 sz(2)];
                app.ROI_visu_SliceSlider.Value = round(sz(2)/2);
                app.ROI_UIAxes.XLabel.String = 'Axe 3';
                app.ROI_UIAxes.YLabel.String = 'Axe 1';
            elseif axe_view==3 && length(sz)==3
                app.ROI_visu_SliceSlider.Limits = [1 sz(3)];
                app.ROI_visu_SliceSlider.Value = round(sz(3)/2);
                app.ROI_UIAxes.XLabel.String = 'Axe 2';
                app.ROI_UIAxes.YLabel.String = 'Axe 1';
            end  

            if keepsz
                app.ROI_visu_SliceSlider.Value = keepsz;
            end

            app.ROI_SliceEditField.Limits = app.ROI_visu_SliceSlider.Limits;            
            app.ROI_SliceEditField.Value = app.ROI_visu_SliceSlider.Value;          
            app.Update_ROI_view;
        end

        % Value changed function: ROI_ColormapDropDown
        function ROI_ColormapDropDownValueChanged(app, event)
            app.Update_ROI_view;
        end

        % Callback function
        function AngledegreesEditFieldValueChanged(app, event)
            app.Update_ROI_view;
        end

        % Value changed function: ROI_SliceEditField
        function ROI_SliceEditFieldValueChanged(app, event)
            value = app.ROI_SliceEditField.Value;
            app.ROI_visu_SliceSlider.Value = value;
            app.ROI_visu_SliceSliderValueChanged;            
        end

        % Button pushed function: ROI_minus_Button
        function ROI_minus_ButtonPushed(app, event)
            current = app.ROI_visu_SliceSlider.Value;
            new = max([1 current-1]);
            app.ROI_visu_SliceSlider.Value = new;
            app.ROI_visu_SliceSliderValueChanged;  
        end

        % Button pushed function: ROI_plus_Button
        function ROI_plus_ButtonPushed(app, event)
            current = app.ROI_visu_SliceSlider.Value;
            new = min([app.ROI_visu_SliceSlider.Limits(2) current+1]);
            app.ROI_visu_SliceSlider.Value = new;
            app.ROI_visu_SliceSliderValueChanged;              
        end

        % Value changed function: AngledegreesSpinner
        function AngledegreesSpinnerValueChanged(app, event)
            app.Update_ROI_view;
        end

        % Button pushed function: ROI_background_DoButton
        function FindparametersforbackgroundButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            if strcmp(app.ROI_DropDown.Value,'Only find parameters')
                p.apply = false;
            else
                p.apply = true;
            end
            
            p.selectbackground = app.ROI_SelectbackgroundDropDown.Value;
            if strcmp(p.selectbackground,'From label')
                p.background_label = app.ROI_BackgroundlabelEditField.Value;
            else
                from_f = app.ROI_FromfileDropDown.ValueIndex;
                from_w = app.ROI_FromworkflowDropDown.ValueIndex;
                from_s = str2num(app.ROI_FromstateDropDown.Value);
                p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
            end

            p.buffers = [app.ROI_Buffer_axe1_EditField.Value app.ROI_Buffer_axe2_EditField.Value app.ROI_Buffer_axe3_EditField.Value];
            p.rotmax_x = app.ROI_Rotationmaxdeg_x_EditField.Value;
            p.rotmax_y = app.ROI_Rotationmaxdeg_y_EditField.Value;
            p.rotmax_z = app.ROI_Rotationmaxdeg_z_EditField.Value;
            p.ops = app.ROI_ParametersDropDown.Value;
            
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.ROI_StatutIdleLabel.Text = ['Please wait... Action: ROI (largest ROI ' p.ops ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,res] = fct_largestROI(M,p);
                if p.apply
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_largestROI';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = ['ROI (largest ROI, ' p.ops ')'];
                    app.par = res.str_p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = 'Volume dependent'; % app.par;
                else
                    if strcmp(p.ops,'Cropping')
                        app.RelativedimensionCheckBox.Value = false;
                        if length(size(M))==2
                            app.ROI_crop_UITable.Data(:,3).Variables = [res.crop(1); res.crop(3)];
                            app.ROI_crop_UITable.Data(:,4).Variables = [res.crop(2); res.crop(4)];
                        else
                            app.ROI_crop_UITable.Data(:,3).Variables = [res.crop(1); res.crop(3); res.crop(5)];
                            app.ROI_crop_UITable.Data(:,4).Variables = [res.crop(2); res.crop(4); res.crop(6)];
                        end
                        app.Update_ROI_view;
                    end
                end
                app.t = toc;

                if p.apply
                    app.Update_newactive_state(true);
                    %app.Update_tables_dimensions;
                end
            end
            if p.apply
                app.ROI_StatutIdleLabel.Text = ['Finished! ' res.str_p];
            else
                app.ROI_StatutIdleLabel.Text = ['Finished! (not applied) ' res.str_p];
            end     
        end

        % Value changed function: Autosetcropping_downscalingCheckBox
        function Autosetcropping_downscalingCheckBoxValueChanged(app, event)
            %value = app.Autosetcropping_downscalingCheckBox.Value;
            %if value
            %    app.Autocropping_background_CheckBox.Value = 0;
            %end            
        end

        % Callback function
        function Autocropping_background_CheckBoxValueChanged(app, event)
            value = app.Autocropping_background_CheckBox.Value;
            if value
                app.Autosetcropping_downscalingCheckBox.Value = 0;
            end            
        end

        % Button pushed function: Saturation_DoButton
        function Saturation_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.volumethreshold_below = app.Saturation_below_EditField.Value;
            p.volumethreshold_above = app.Saturation_above_EditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast1_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (saturation) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_contrastsaturation(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_contrastsaturation';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (saturation)';
                app.par = ['Volume threshold (below): ' num2str(p.volumethreshold_below,'%1.2f') ', Volume threshold (above): ' num2str(p.volumethreshold_above,'%1.2f')] ;
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast1_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: Rescalinglinearly_DoButton
        function Rescalinglinearly_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.nvalues = app.RescalelinearlyusingnvaluesEditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast1_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (linear rescaling) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_linearrescaling(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_linearrescaling';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (linear rescaling)';
                app.par = ['Number of values: ' num2str(p.nvalues,'%i') ];
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast1_StatutIdleLabel.Text = 'Finished!';   
        end

        % Value changed function: ContrastEq_MethodDropDown
        function ContrastEq_MethodDropDownValueChanged(app, event)
            value = app.ContrastEq_MethodDropDown.Value;
            if strcmp(value,'Transforms the grayscale image so that the histogram of the output grayscale image with n bins is approximately flat')
                app.RelativenbinsCheckBox.Enable = 'on';
                app.RelativenbinsCheckBoxValueChanged;
                app.hgramEditField.Enable = 'off';
                app.ContrastEqualization_DoButton.Enable = 'on';
            elseif strcmp(value,'Transforms the grayscale image so that the histogram of the output grayscale image approximately matches the target histogram hgram')
                app.RelativenbinsCheckBox.Enable = 'off';
                app.nbinsEditField.Enable = 'off';
                app.rationbinsEditField.Enable = 'off';
                app.hgramEditField.Enable = 'on';
                app.ContrastEqualization_DoButton.Enable = 'on';
            elseif strcmp(value,'Transforms the grayscale image so that the histogram of the output grayscale image approximately matches the histogram hgram of another state')
                app.ContrastEqualization_DoButton.Enable = 'off';
                app.MainTabGroup.SelectedTab = app.CombinationsTab;
            end
        end

        % Value changed function: RelativenbinsCheckBox
        function RelativenbinsCheckBoxValueChanged(app, event)
            value = app.RelativenbinsCheckBox.Value;
            if value
                app.nbinsEditField.Enable = 'off';
                app.rationbinsEditField.Enable = 'on';
            else
                app.nbinsEditField.Enable = 'on';
                app.rationbinsEditField.Enable = 'off';
            end            
        end

        % Button pushed function: ContrastEqualization_DoButton
        function ContrastEqualization_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            value = app.ContrastEq_MethodDropDown.Value;
            p.M2 = [];
            if strcmp(value,'Transforms the grayscale image so that the histogram of the output grayscale image with n bins is approximately flat')
                p.hgram = [];
                p.nbin = app.nbinsEditField.Value;
                p.ratio_nbin = app.rationbinsEditField.Value;
                p.relative_nbin = app.RelativenbinsCheckBox.Value;
                if p.relative_nbin
                    app.par = ['Nbin = ratio, ' num2str(p.ratio_nbin,'%1.3f')];
                else
                    app.par = ['Nbin = ' num2str(p.nbin,'%i')];
                end
            elseif strcmp(value,'Transforms the grayscale image so that the histogram of the output grayscale image approximately matches the target histogram hgram')
                p.nbin = [];
                app.par = app.hgramEditField.Value;
                eval(['p.hgram=[' app.hgramEditField.Value ']']);                
            end
            p.perslice = app.ContrastEq_perslice_CheckBox.Value;
            if p.perslice
                app.par = [app.par ', applied per slice'];
            else
                app.par = [app.par ', applied on whole volume'];
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast2_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (histogram equalization) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_histequalization(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_histequalization';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (histogram equalization)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast2_StatutIdleLabel.Text = 'Finished!';
        end

        % Value changed function: hgramEditField
        function hgramEditFieldValueChanged(app, event)
            value = app.hgramEditField.Value;
            previous_value = event.PreviousValue;  
            tmp = str2num(value);
            if ~isempty(tmp)
                if tmp(1)>0 && tmp(end)>0 && length(tmp)>2
                    eval(['tmp=[' value ']']);  
                    if length(unique(diff(tmp)))>1 || min(min(min(tmp)))<0
                        app.hgramEditField.Value = previous_value;
                    end
                else
                    app.hgramEditField.Value = previous_value;
                end
            else
                app.hgramEditField.Value = previous_value;
            end
        end

        % Value changed function: CLAHE_DistributionDropDown
        function CLAHE_DistributionDropDownValueChanged(app, event)
            value = app.CLAHE_DistributionDropDown.Value;
            if strcmp(value,'uniform')
                app.CLAHE_AlphaEditField.Enable = 'off';
            else
                app.CLAHE_AlphaEditField.Enable = 'on';
            end
        end

        % Button pushed function: CLAHE_DoButton
        function CLAHE_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.NumTiles = [app.CLAHE_NtileAEditField.Value app.CLAHE_NtileBEditField.Value];
            p.ClipLimit = app.CLAHE_CliplimitEditField.Value;
            p.NBins = app.CLAHE_NbinEditField.Value;
            p.Range = app.CLAHE_RangeDropDown.Value;
            p.Distribution = app.CLAHE_DistributionDropDown.Value;
            p.Alpha = app.CLAHE_AlphaEditField.Value;
            if strcmp(p.Distribution,'uniform')
                app.par = ['NumTiles=' num2str(p.NumTiles) ', ClipLimit=' num2str(p.ClipLimit,'%1.3f') ', NBins=' num2str(p.NBins,'%i') ', Range=' p.Range ', Distribution=' p.Distribution];
            else
                app.par = ['NumTiles=' num2str(p.NumTiles) ', ClipLimit=' num2str(p.ClipLimit,'%1.3f') ', NBins=' num2str(p.NBins,'%i') ', Range=' p.Range ', Distribution=' p.Distribution ', Alpha=' num2str(p.Alpha,'%1.3f')];
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast2_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (CLAHE) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_CLAHE(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_CLAHE';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (histogram equalization)';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast2_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: Scaling_DoButton
        function Scaling_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.scaling_factor = app.ScalingfactorEditField.Value;
            p.background = app.Scaling_BackgroundlabelEditField.Value;
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Scaling_StatutIdleLabel.Text = ['Please wait... Action: isotropic scaling on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                p.datatype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                [M,~,~] = fct_isotropicscaling(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_isotropicscaling';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Isotropic scaling';
                app.par = ['Scaling factor: ' num2str(p.scaling_factor,'%1.3f')];
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Scaling_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: AnisotropicScaling_DoButton
        function AnisotropicScaling_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.scaling_factors = app.Anisotropicscaling_UITable.Data(:,2);
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Scaling_StatutIdleLabel.Text = ['Please wait... Action: anisotropic scaling on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                p.datatype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                [M,~,~] = fct_anisotropicscaling(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_anisotropicscaling';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Anisotropic scaling';
                app.par = ['Scaling factors: ' num2str((p.scaling_factors)','%1.3f')];
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Scaling_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: Absolute_DoButton, Arithmetic_DoButton, 
        % ...and 4 other components
        function Round_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            p.action = event.Source.Tag;
            app.par = 'n/a';
            if strcmp(p.action,'Arithmetic')
                p.val = app.Arithmetic_EditField.Value;
                p.ope = app.Arithmetic_DropDown.Value;
                app.par = [p.ope ' ' num2str(p.val)];
            elseif strcmp(p.action,'Binarize')
                p.choice = app.BinarizeChoice_DropDown.Value;
                app.par = p.choice;
                if ~strcmp(p.choice,'M( M ==min(M) ) = 1, 0 otherwise') && ~strcmp(p.choice,'M( M ==max(M) ) = 1, 0 otherwise')
                    p.val = app.Binarize_x_EditField.Value;
                    app.par = [app.par ', x=' num2str(p.val)];
                end
            elseif strcmp(p.action,'Substitution')
                p.choice = app.SubstitutionChoice_DropDown.Value;
                p.x = app.IndexSubstitution_x_EditField.Value;
                p.y = app.IndexSubstitution_y_EditField.Value;
                value = app.SubstitutionChoice_DropDown.Value;
                if strcmp(value,'M( M == min(M) ) = y') || strcmp(value,'M( M == max(M) ) = y') 
                    app.par = [p.choice ', y=' num2str(p.y)];
                else
                    app.par = [p.choice ', x=' num2str(p.x) ', y=' num2str(p.y)];  
                end                              
            elseif strcmp(p.action,'Round')     
                p.val = app.rounddigitsEditField.Value;
                app.par = [num2str(p.val) ' digit(s)'];
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Reassign_StatutIdleLabel.Text = ['Please wait... Action: Re-assign (' p.action ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);                

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_simplereassign(M,p); % Overkill to use a function for these simple actions, but that allows me to use feval for the macro command
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_simplereassign'; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                if strcmp(newtype,'same')
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                else
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                end
                app.act = p.action;
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end  
            app.Reassign_StatutIdleLabel.Text = 'Finished!';
        end

        % Value changed function: 
        % ExcludebackgroundfromthecalculationCheckBox
        function ExcludebackgroundfromthecalculationCheckBoxValueChanged(app, event)
            value = app.ExcludebackgroundfromthecalculationCheckBox.Value;
            app.CalcGrey_SelectbackgroundDropDown.Enable = value;
            if ~value
                app.BackgroundlabelEditField_2.Enable = 'off';
                app.Background_FromfileDropDown.Enable = 'off';
                app.Background_FromworkflowDropDown.Enable = 'off';
                app.Background_FromstateDropDown.Enable = 'off';
            else
                app.CalcGrey_SelectbackgroundDropDownValueChanged;             
            end
        end

        % Value changed function: CalcGrey_SelectbackgroundDropDown
        function CalcGrey_SelectbackgroundDropDownValueChanged(app, event)
            value = app.CalcGrey_SelectbackgroundDropDown.Value;
            if strcmp(value,'From label')
                app.BackgroundlabelEditField_2.Enable = 'on';
                app.Background_FromfileDropDown.Enable = 'off';
                app.Background_FromworkflowDropDown.Enable = 'off';
                app.Background_FromstateDropDown.Enable = 'off';                
            elseif strcmp(value,'From state')
                app.Background_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                if ~isempty(app.Background_FromfileDropDown.Items)
                    app.Background_FromfileDropDown.Value = app.Background_FromfileDropDown.Items(1);
                    app.Background_FromfileDropDownValueChanged;
                    app.BackgroundlabelEditField_2.Enable = 'off';
                    app.Background_FromfileDropDown.Enable = 'on';
                    app.Background_FromworkflowDropDown.Enable = 'on';
                    app.Background_FromstateDropDown.Enable = 'on';
                end
            end
        end

        % Callback function: Background_FromfileDropDown, 
        % ...and 1 other component
        function Background_FromfileDropDownValueChanged(app, event)
            from_f = app.Background_FromfileDropDown.ValueIndex;
            % Propagate
            app.Background_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.Background_FromworkflowDropDown.Value = app.Background_FromworkflowDropDown.Items(end);
            app.Background_FromworkflowDropDownValueChanged;   
        end

        % Value changed function: Background_FromworkflowDropDown
        function Background_FromworkflowDropDownValueChanged(app, event)
            from_f = app.Background_FromfileDropDown.ValueIndex;
            from_w = app.Background_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.Background_FromstateDropDown.Items = tmp;
            app.Background_FromstateDropDown.Value = app.Background_FromstateDropDown.Items(1);            
        end

        % Value changed function: Calc_AlllinesCheckBox
        function Calc_AlllinesCheckBoxValueChanged(app, event)
            value = app.Calc_AlllinesCheckBox.Value;
            app.Calc_NlinesEditField.Enable = ~value;
        end

        % Value changed function: 
        % Seg_ExcludebackgroundfromthecalculationCheckBox
        function Seg_ExcludebackgroundfromthecalculationCheckBoxValueChanged(app, event)
            value = app.Seg_ExcludebackgroundfromthecalculationCheckBox.Value;
            app.Seg_SelectbackgroundDropDown.Enable = value;
            if ~value
                app.Seg_BackgroundlabelEditField.Enable = 'off';
                app.Seg_FromfileDropDown.Enable = 'off';
                app.Seg_FromworkflowDropDown.Enable = 'off';
                app.Seg_FromstateDropDown.Enable = 'off';
                labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
                if min(labels)>0
                    app.Segmentation_expectedvf_UITable.Data(:,2).Variables = app.Segmentation_expectedvf_UITable.Data(:,2).Variables-min(labels);
                    app.Globalsegmentation_UITable.Data(:,2).Variables = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
                end                 
            else
                app.Seg_SelectbackgroundDropDownValueChanged;      
                labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
                if min(labels)==0
                    app.Segmentation_expectedvf_UITable.Data(:,2).Variables = app.Segmentation_expectedvf_UITable.Data(:,2).Variables+1;
                    app.Globalsegmentation_UITable.Data(:,2).Variables = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
                end                
            end
        end

        % Value changed function: Seg_SelectbackgroundDropDown
        function Seg_SelectbackgroundDropDownValueChanged(app, event)
            value = app.Seg_SelectbackgroundDropDown.Value;
            if strcmp(value,'From label')
                app.Seg_BackgroundlabelEditField.Enable = 'on';
                app.Seg_FromfileDropDown.Enable = 'off';
                app.Seg_FromworkflowDropDown.Enable = 'off';
                app.Seg_FromstateDropDown.Enable = 'off';                
            elseif strcmp(value,'From state')
                app.Seg_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                if ~isempty(app.Seg_FromfileDropDown.Items)
                    app.Seg_FromfileDropDown.Value = app.Seg_FromfileDropDown.Items(1);
                    app.Seg_FromfileDropDownValueChanged;
                    app.Seg_BackgroundlabelEditField.Enable = 'off';
                    app.Seg_FromfileDropDown.Enable = 'on';
                    app.Seg_FromworkflowDropDown.Enable = 'on';
                    app.Seg_FromstateDropDown.Enable = 'on';
                end
            end            
        end

        % Callback function: Seg_FromfileDropDown, Seg_FromfileDropDown
        function Seg_FromfileDropDownValueChanged(app, event)
            from_f = app.Seg_FromfileDropDown.ValueIndex;
            % Propagate
            app.Seg_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.Seg_FromworkflowDropDown.Value = app.Seg_FromworkflowDropDown.Items(end);
            app.Seg_FromworkflowDropDownValueChanged;   
        end

        % Value changed function: Seg_FromworkflowDropDown
        function Seg_FromworkflowDropDownValueChanged(app, event)
            from_f = app.Seg_FromfileDropDown.ValueIndex;
            from_w = app.Seg_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.Seg_FromstateDropDown.Items = tmp;
            app.Seg_FromstateDropDown.Value = app.Seg_FromstateDropDown.Items(1);   
        end

        % Button pushed function: Segthreshold_DoButton
        function Segthreshold_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.option = app.LocalSegmentation_Method_DropDown.Value;
            p.axe = app.LocalSegmentation_Axe_DropDown.Value;
            p.numberofphase = app.Seg_Numberofphases_editfield.Value;

            % Local otsu parameter
            p.labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
            p.expectedvf = app.Segmentation_expectedvf_UITable.Data(:,3).Variables;
            p.translateprofile = app.TranslatethresholdprofileCheckBox.Value;
            p.smoothrange = app.MovingaveragefilterrangeEditField.Value;
            % Local manual parameter
            p.manualsthresh = app.LocalSeg_UITable.Data;

            p.plotprofile = app.PlotthresholdprofileCheckBox.Value;
            p.voxelsize = app.Seg_VoxelsizeEditField.Value;
            p.voxelunit = app.Seg_UnitEditField.Value;

            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;                
            
            p.excludebackground = app.Seg_ExcludebackgroundfromthecalculationCheckBox.Value;
            if p.excludebackground
                p.selectbackground = app.Seg_SelectbackgroundDropDown.Value;
                if strcmp(p.selectbackground,'From label')
                    p.background_label = app.Seg_BackgroundlabelEditField.Value;
                    str_back = ['from label: ' num2str(p.background_label)];
                else
                    from_f = app.Seg_FromfileDropDown.ValueIndex;
                    from_w = app.Seg_FromworkflowDropDown.ValueIndex;
                    from_s = str2num(app.Seg_FromstateDropDown.Value);
                    p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
                    p.backgroundstates = [from_f from_w from_s];    
                    str_back = ['from state: file#' num2str(from_f) ', workflow#' num2str(from_w) ', state#' num2str(from_s)];
                end
            end            

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.GlobalSeg_StatutIdleLabel.Text = ['Please wait... Action: Segmentation (local thresholding) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,allthresholds] = fct_localthresholding(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_localthresholding';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = ['Local thresholding (' p.option '). ' num2str(p.numberofphase) ' phases'];

                app.par = p.axe;
                if strcmp(p.option,'Otsu')
                    if p.translateprofile
                        app.par = [app.par ', translate threshold profile to match expected volume fractions ('];
                        for k=1:1:p.numberofphase
                            app.par = [app.par ' Label ' num2str(p.labels(k),'%i') ' = ' num2str(p.expectedvf(k),'%1.3f')];
                        end
                        app.par = [app.par ')'];
                    end
                    if p.smoothrange~=0
                        app.par = [app.par ', with moving average profile: ' num2str(p.smoothrange,'%i')];
                    end
                elseif strcmp(p.option,'Manual')
                    app.par = [app.par ', control points: ' num2str(p.manualsthresh(:,1)') ', thresholds:'];
                    for k=1:1:p.numberofphase-1
                        app.par = [app.par ' phase# ' num2str(k) ': ' num2str(p.manualsthresh(:,k+1)')];
                    end
                    for k=1:10
                        app.par = strrep(app.par,'  ',' ');
                    end
                end

                if p.excludebackground
                    app.par = [app.par ', background excluded: ' str_back];
                end

                if app.SavethresholdprofileCheckBox.Value
                    statename = ['S' num2str(app.active_s) '_' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
                    workflowname = ['WF' num2str(app.active_w) '_' char(app.SelectactiveworkflowDropDown.Value)];
                    fullpath = fullfile(app.savefolder,char(app.SelectactivefileDropDown.Value),workflowname,statename);

                    [filepath,name,~] = fileparts(fullpath);
                    if ~exist(filepath,'dir')
                        mkdir(filepath);
                    end

                    variablenames = {'x (voxel)'};
                    for k=1:1:p.numberofphase
                        variablenames(k+1) = {['Phase#' num2str(k,'%i') ' Label=' num2str(p.labels(k),'%i')]};
                    end
                    DATA_writetable.sheet(1).name='Thresholds';
                    DATA_writetable.sheet(1).table = array2table( [allthresholds.x allthresholds.thresholds]   ,"VariableNames",variablenames);

                    if strcmp(p.option,'Otsu')
                        DATA_writetable.sheet(2).name='Thresholds (smoothed)';
                        DATA_writetable.sheet(2).table = array2table( [allthresholds.x allthresholds.thresholds_smoothed]   ,"VariableNames",variablenames);
                        DATA_writetable.sheet(3).name='Separability criterion';
                        DATA_writetable.sheet(3).table = array2table( [allthresholds.x allthresholds.best_ratio]   ,"VariableNames",{'x (voxel)' 'eta'});
                        if p.translateprofile
                            DATA_writetable.sheet(3).name='Thresholds (translated)';
                            DATA_writetable.sheet(3).table = array2table( [allthresholds.x allthresholds.translated_thresholds]   ,"VariableNames",variablenames);
                        end
                    end

                    Function_Writetable(filepath,['Thresholds ' name],DATA_writetable);
                end
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                [~,str] = app.Segmentation_update_volumefraction(M,p.excludebackground);
                app.GlobalSeg_StatutIdleLabel.Text = ['Finished! ' str]; 
            end
        end

        % Value changed function: PlotthresholdprofileCheckBox
        function PlotthresholdprofileCheckBoxValueChanged(app, event)
            value_plot = app.PlotthresholdprofileCheckBox.Value;
            %value_save = app.SavethresholdprofileCheckBox.Value;
            if value_plot
                app.Seg_VoxelsizeEditField.Enable = 'on';
                app.Seg_UnitEditField.Enable = 'on';      
                app.SavethresholdprofileCheckBox.Enable = 'on';  
            else
                app.Seg_VoxelsizeEditField.Enable = 'off';
                app.Seg_UnitEditField.Enable = 'off'; 
                app.SavethresholdprofileCheckBox.Enable = 'off';  
                app.SavethresholdprofileCheckBox.Value = 0;  
            end
        end

        % Value changed function: ROI_SelectbackgroundDropDown
        function ROI_SelectbackgroundDropDownValueChanged(app, event)
            value = app.ROI_SelectbackgroundDropDown.Value;
            if strcmp(value,'From label')
                app.ROI_BackgroundlabelEditField.Enable = 'on';
                app.ROI_FromfileDropDown.Enable = 'off';
                app.ROI_FromworkflowDropDown.Enable = 'off';
                app.ROI_FromstateDropDown.Enable = 'off';                
            elseif strcmp(value,'From state')
                app.ROI_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                if ~isempty(app.ROI_FromfileDropDown.Items)
                    app.ROI_FromfileDropDown.Value = app.ROI_FromfileDropDown.Items(1);
                    app.ROI_FromfileDropDownValueChanged;
                    app.ROI_BackgroundlabelEditField.Enable = 'off';
                    app.ROI_FromfileDropDown.Enable = 'on';
                    app.ROI_FromworkflowDropDown.Enable = 'on';
                    app.ROI_FromstateDropDown.Enable = 'on';
                end
            end
        end

        % Callback function: ROI_FromfileDropDown, ROI_FromfileDropDown
        function ROI_FromfileDropDownValueChanged(app, event)
            from_f = app.ROI_FromfileDropDown.ValueIndex;
            % Propagate
            app.ROI_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.ROI_FromworkflowDropDown.Value = app.ROI_FromworkflowDropDown.Items(end);
            app.ROI_FromworkflowDropDownValueChanged;   

        end

        % Value changed function: ROI_FromworkflowDropDown
        function ROI_FromworkflowDropDownValueChanged(app, event)
            from_f = app.ROI_FromfileDropDown.ValueIndex;
            from_w = app.ROI_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.ROI_FromstateDropDown.Items = tmp;
            app.ROI_FromstateDropDown.Value = app.ROI_FromstateDropDown.Items(1);  
        end

        % Value changed function: ROI_ParametersDropDown
        function ROI_ParametersDropDownValueChanged(app, event)
            value = app.ROI_ParametersDropDown.Value;
            dimension = length(size(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M));
            if strcmp(value,'Cropping')
                app.ROI_Rotationmaxdeg_x_EditField.Enable = 'off';
                app.ROI_Rotationmaxdeg_y_EditField.Enable = 'off';
                app.ROI_Rotationmaxdeg_z_EditField.Enable = 'off';
            else
                if dimension==2
                    app.ROI_Rotationmaxdeg_x_EditField.Enable = 'off';
                    app.ROI_Rotationmaxdeg_y_EditField.Enable = 'off';
                    app.ROI_Rotationmaxdeg_z_EditField.Enable = 'on';
                else
                    app.ROI_Rotationmaxdeg_x_EditField.Enable = 'on';
                    app.ROI_Rotationmaxdeg_y_EditField.Enable = 'on';
                    app.ROI_Rotationmaxdeg_z_EditField.Enable = 'on';
                end
            end
        end

        % Value changed function: Anisotropic_EstimategradientCheckBox
        function Anisotropic_EstimategradientCheckBoxValueChanged(app, event)
            value = app.Anisotropic_EstimategradientCheckBox.Value;
            app.Anisotropic_GradientthresholdEditField.Enable = ~value;
            app.Anisotropic_NumberofiterationsEditField.Enable = ~value;
        end

        % Button pushed function: Anisotropic_DoButton
        function Anisotropic_DoButtonPushed(app, event)
            % Parameters
            p.conduction = app.Anisotropy_ConductionmethodDropDown.Value;
            p.connectivity = app.Anisotropy_ConnectivityDropDown.Value;
            p.estimategradientiteration = app.Anisotropic_EstimategradientCheckBox.Value;
            p.number_iterations = app.Anisotropic_NumberofiterationsEditField.Value;
            p.gradthreshold = app.Anisotropic_GradientthresholdEditField.Value;

            if app.GLFilter_PreviewCheckBox.Value
                p.apply = 'Single slice';
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                [Mfiltered,~,~] = fct_anisotropicdiffusion(M,p);
                Fig = figure;
                Fig.Color = 'w';
                Fig.Name = 'Anisotropic diffusion filter preview on middle slice';
                montage({M,Mfiltered});         
                clim([min(min(M)) max(max(M))]);                
            else
                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                p.apply = app.Anisotropic_ApplyDropDown.Value;
                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (anisotropic diffusion filter) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,~,str_p] = fct_anisotropicdiffusion(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_anisotropicdiffusion';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = 'Filtering (anisotropic diffusion filter)';
                    app.par = str_p ;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Value changed function: Anisotropic_ApplyDropDown
        function Anisotropic_ApplyDropDownValueChanged(app, event)
            value = app.Anisotropic_ApplyDropDown.Value;
            if strcmp(value,'Full volume')
                app.Anisotropic_EstimategradientCheckBox.Enable = 'off';
                app.Anisotropic_EstimategradientCheckBox.Value = false;
            else
                app.Anisotropic_EstimategradientCheckBox.Enable = 'on';
            end
            app.Anisotropic_EstimategradientCheckBoxValueChanged;
        end

        % Button pushed function: Bitlevel_DoButton
        function Bitlevel_DoButtonPushed(app, event)
            % Parameters
            p.lastbit_to_keep = app.KeeplastxbitlevelEditField.Value;
            p.show_bitlevel = app.ShowbitlevelCheckBox.Value;
            
            if app.GLFilter_PreviewCheckBox.Value
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                M = double(M);
                M = round(M*255 / max(max(max(M))));
                M = uint8(M);
                [Mlastbit,~,unis] = fct_keep_last_bitlevel(M,p);
                Fig = figure;
                Fig.Color = 'w';
                Fig.Name = ['Keep last ' num2str(p.lastbit_to_keep,'%i')  'bit levels. Unique values from ' num2str(unis(1),'%i') ' to ' num2str(unis(2),'%i')];
                montage({M,Mlastbit});      
                clim([min(min(M)) max(max(M))]);                
            else
                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (keep last bit levels) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,~,~] = fct_keep_last_bitlevel(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_keep_last_bitlevel';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = 'Filtering (keep last bit levels)';
                    app.par = ['Keep last ' num2str(p.lastbit_to_keep) ' bit levels'];
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Value changed function: GlabolaErosionDilation_ApplyonDropDown
        function GlabolaErosionDilation_ApplyonDropDownValueChanged(app, event)
            value = app.GlabolaErosionDilation_ApplyonDropDown.Value;
            if strcmp(value,'A single label')
                app.GlobalLabeltoapplyEditField.Enable = 'on';
                app.GlobalBackgroundlabelEditField.Enable = 'on';
                app.DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2.Enable = 'on';
            else
                app.GlobalLabeltoapplyEditField.Enable = 'off';
                app.GlobalBackgroundlabelEditField.Enable = 'on';
                app.DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2.Enable = 'off';
            end
        end

        % Button pushed function: GlobalErosionDilation_Button
        function GlobalErosionDilation_ButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.applyon = char(app.GlabolaErosionDilation_ApplyonDropDown.Value);
            p.labeltoapply = app.GlobalLabeltoapplyEditField.Value;
            p.background = app.GlobalBackgroundlabelEditField.Value;
            p.dilationcanoverwritte = app.DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2.Value;
            p.order = char(app.GlobalErosionDilation_OrderDropDown.Value);
            p.erosiondistance = app.GlobalErosiondistanceEditField.Value;
            p.dilationdistance = app.GlobalDilationdistanceEditField.Value;
            p.distancedefinition = char(app.GlobalErosionDilation_DistancedefinitionDropDown.Value);
            p.num_iteration = app.GlobalErosionDilation_NumberofiterationEditField.Value;
            p.zeroes_on_edges = app.AddzeroesonedgeCheckBox.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.GlobalErosionDilation_StatutIdleLabel.Text = ['Please wait... Action: ' p.order ' on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_globalerosiondilation(M,p);
                if strcmp(newtype,'same')
                    newtype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_globalerosiondilation';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
              
                if p.erosiondistance==0
                    app.act = 'Global dilation';
                elseif p.dilationdistance==0
                    app.act = 'Global erosion';
                else
                    app.act = ['Global morphology ' lower(p.order)];
                end
                if strcmp(p.applyon,'A single label')
                    if p.dilationcanoverwritte
                        canoverwritte = 'true';
                    else
                        canoverwritte = 'false';
                    end
                    app.par = ['Applied to label: ' num2str(p.labeltoapply,'%i') ', background label: ' num2str(p.background,'%i') ', dilation can overwritte other non-background label: ' canoverwritte ', erosion distance: ' num2str(p.erosiondistance) ', dilation distance: ' num2str(p.dilationdistance) ', distance definition: ' p.distancedefinition] ;
                else
                    app.par = ['Applied per label, erosion distance: ' num2str(p.erosiondistance) ', dilation distance: ' num2str(p.dilationdistance) ', distance definition: ' p.distancedefinition] ;
                end
                if p.zeroes_on_edges
                    app.par = [app.par ', added zeros on edge'];
                end

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                app.GlobalErosionDilation_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Value changed function: 
        % Localerosion_surfacesimplification_CheckBox
        function Localerosion_surfacesimplification_CheckBoxValueChanged(app, event)
            value = app.Localerosion_surfacesimplification_CheckBox.Value;
            app.Localerosion_DistanceEditField.Enable = value;
            app.Localerosion_DistancemethodDropDown.Enable = value;
        end

        % Value changed function: RatioofinitialvolumefractionEditField
        function RatioofinitialvolumefractionEditFieldValueChanged(app, event)
            value = app.RatioofinitialvolumefractionEditField.Value;
            app.Erodeupto10ofthephaseLabel.Text = ['Erode up to ' num2str((1-value)*100,'%1.1f') '% of the phase'];
            
        end

        % Value changed function: Localerosion_VideoCheckBox
        function Localerosion_VideoCheckBoxValueChanged(app, event)
            value = app.Localerosion_VideoCheckBox.Value;
            app.Localerosion_FramerateEditField.Enable = value;            
            app.Localerosion_SavefolderButton.Enable = value;     
            app.Localerosion_NofolderLabel.Enable = value;     
            app.Localerosion_FilenameEditField.Enable = value;     
        end

        % Button pushed function: Localerosion_DoButton
        function Localerosion_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.metric = app.ConcavitymetricDropDown.Value;
            p.max_iter = app.MaximumnumberofiterationEditField.Value;
            p.surface_simplification = app.Localerosion_surfacesimplification_CheckBox.Value;
            p.surface_simplification_distance = app.Localerosion_DistanceEditField.Value;
            p.surface_simplification_distancemethod = app.Localerosion_DistancemethodDropDown.Value;
            p.metric_stoppingcondition = app.Localerosion_max1metricgoesbelowEditField.Value;
            p.remove_ratiomax = app.ErosionmagnitudeEditField.Value;
            p.vf_stoppingcondition = app.RatioofinitialvolumefractionEditField.Value;
            p.wndrange = app.Localerosion_WindowrangeEditField.Value;
            p.threshold_metric = app.ThresholdnumericallerrorEditField.Value;
            p.keepbest = app.LocalerosionKeepbest_CheckBox.Value;

            p.video = app.Localerosion_VideoCheckBox.Value;
            p.framerate = app.Localerosion_FramerateEditField.Value;
            p.savefolder = app.Localerosion_NofolderLabel.Text;
            p.filename = app.Localerosion_FilenameEditField.Value;
            if p.video && (isempty(p.savefolder) || isempty(p.filename))
                warning('Video will not be created: save folder and/or filename are missing');
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.LocalErosionDilation_StatutIdleLabel.Text = ['Please wait... Action: local erosion (' p.metric ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_concavityBased_LocalizedErosion(M,p);
                if strcmp(newtype,'same')
                    newtype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_concavityBased_LocalizedErosion';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = ['Local erosion (' p.metric ')'];
                app.par = ['Window range: ' num2str(p.wndrange,'%i') ', Threshold (numericall error): ' num2str(p.threshold_metric,'%1.2f')  ', Erosion magnitude: ' num2str(p.remove_ratiomax,'%1.2f') ', ratio of initial volume fraction: ' num2str(p.vf_stoppingcondition,'%1.2f') ', max iteration: ' num2str(p.max_iter,'%i') ', max(1-metric) goes below: ' num2str(p.metric_stoppingcondition,'%1.2f')];
                if p.keepbest
                    app.par = [app.par ', Result is best among all iterations : yes'];
                else
                    app.par = [app.par ', Result is best among all iterations : no'];                    
                end                
                if p.surface_simplification
                    app.par = [app.par ', surface simplification: distance: ' num2str(p.surface_simplification_distance) ', method: ' p.surface_simplification_distancemethod];
                end             
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                [~,str] = app.Segmentation_update_volumefraction(M, false);
                app.LocalErosionDilation_StatutIdleLabel.Text = ['Finished! ' str];
            end
        end

        % Button pushed function: Localerosion_SavefolderButton
        function Localerosion_SavefolderButtonPushed(app, event)
            selpath = uigetdir(pwd,'Select save folder for the video');
            if selpath==0
                app.Localerosion_NofolderLabel.Text =  app.savefolder;
            else
                app.Localerosion_NofolderLabel.Text = selpath;    
            end
            app.Localerosion_NofolderLabel.Tooltip = app.Localerosion_NofolderLabel.Text;            
        end

        % Callback function: Localdilation_DilateDropDown, 
        % ...and 1 other component
        function Localdilation_DilateDropDownValueChanged(app, event)
            value = app.Localdilation_DilateDropDown.Value;

            labels = unique(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);
            Items = cellstr(num2str(labels));
            app.Localdilation_BackgroundlabelDropDown.Items = Items;
            app.Localdilation_BackgroundlabelDropDown.Value = Items(1);
            app.Localdilation_BackgroundlabelDropDown.Enable = 'on';
            app.Localdilation_LabeltodilateDropDown.Items = Items;
            app.Localdilation_LabeltodilateDropDown.Value = Items(2);
            if strcmp(value,'One label')
                app.Localdilation_LabeltodilateDropDown.Enable = 'on';  
                app.Localdilation_RemoveotherlabelsCheckBox.Enable = 'on';
            else
                app.Localdilation_LabeltodilateDropDown.Enable = 'off';
                app.Localdilation_RemoveotherlabelsCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: Localdilation_Sphere_DoButton
        function Localdilation_Sphere_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            if isempty(app.Localdilation_LabeltodilateDropDown.Value) || isempty(app.Localdilation_BackgroundlabelDropDown.Value)
                warning('Cannot perform local dilation: missing labels');
                return
            end

            % Parameters
            p.choice = app.Localdilation_DilateDropDown.Value;
            p.labeltodilate = str2num(app.Localdilation_LabeltodilateDropDown.Value);
            p.removeotherlabel = app.Localdilation_RemoveotherlabelsCheckBox.Value;
            p.background = str2num(app.Localdilation_BackgroundlabelDropDown.Value);
            p.per_cluster = false;
            p.per_label = false;
            if strcmp(app.LocalDilation_ApplyDropDown.Value,'per cluster')
                p.per_cluster = true;
            end
            if strcmp(app.LocalDilation_ApplyDropDown.Value,'per label')
                p.per_label = true;
            end  
            p.spherediameter = app.DiameterEditField.Value;
            p.round_dmap = app.Localdilation_RoundDmap_CheckBox.Value;
            p.round_dmap_digit = 0; 
            p.extendFOV = app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Value;
            p.perslice = app.Localdialtion_Perslice3DonyzdirectionCheckBox.Value;
            p.alongaxe = app.Localdilation_PersliceDropDown.Value;
            
            if strcmp(p.choice,'One label')
                if p.labeltodilate==p.background
                    warning('Cannot perform local dilation: incorrect labels');
                    return
                end
            end

            show_diameter = app.Localdilation_ShowdiameteronlyforvisualizationCheckBox.Value;
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.LocalDilation_StatutIdleLabel.Text = ['Please wait... Action: local dilation (sphere filter) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;

                if show_diameter
                    Mbefore = M;
                end                
                [M,~,diameter] = fct_localdilation_spheresizefilter(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_localdilation_spheresizefilter';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Local dilation (sphere filter)';
                if strcmp(p.choice,'One label')
                    if p.removeotherlabel
                        str = 'yes';
                    else
                        str = 'no';
                    end
                    app.par = ['label to dilate: ' num2str(p.labeltodilate,'%i') ', remove other labels for diameter map: ' str ', background label: ' num2str(p.background,'%i') ', sphere diameter: ' num2str(p.spherediameter,'%1.1f')];
                elseif strcmp(p.choice,'All non-background labels')
                    app.par = ['background label: ' num2str(p.background,'%i') ', sphere diameter: ' num2str(p.spherediameter,'%1.1f')];
                end
                if p.per_cluster
                    str = 'yes';
                else
                    str = 'no';
                end
                app.par = [app.par ', per cluster:' str];   
                if p.perslice
                    str = ['yes, ' p.alongaxe];
                else
                    str = 'no';
                end
                app.par = [app.par ', per slice:' str];                   

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                [~,str] = app.Segmentation_update_volumefraction(M, false);
                app.LocalDilation_StatutIdleLabel.Text = ['Finished! ' str];
                
                if show_diameter
                    pp(1).name = 'Before dilation';
                    pp(2).name = 'Diameter';
                    pp(3).name = ['After dilation, ' app.par];
                    Visualization_multiple_volumes(pp,Mbefore,diameter,M);
                end
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            winopen('Local_dilation_sphere.png');
        end

        % Button pushed function: UseFullRange_DoButton
        function UseFullRange_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast1_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (use full range) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_fulldatarange(M,[]);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = [];
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_fulldatarange';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (use full range)';
                app.par = 'n/a';
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast1_StatutIdleLabel.Text = 'Finished!';   
        end

        % Button pushed function: ChangeDataType_DoButton
        function ChangeDataType_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.datatype = app.ChangedatatypetoDropDown.Value;
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Format_StatutIdleLabel.Text = ['Please wait... Action: format (data type) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_ChangeDatatype(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_ChangeDatatype';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = 'Format (data type)';
                app.par = p.datatype;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Format_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: Noiselevel_CalculateButton
        function Noiselevel_CalculateButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            
            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;            
            p.voxelsize = app.Calc_VoxelsizeEditField.Value;
            p.voxelunit = app.Calc_UnitEditField.Value;  

            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = ['Please wait... Calculation: Grey level (blind noise level) on file: ' char(app.SelectactivefileDropDown.Items(app.active_f))];
                pause(0.1);
            end            

            if ~app.Macro_AddacalculationButton.Value
                fct_noisecalculation(M,p);
            else
                app.macro_fct = 'fct_noisecalculation';
                app.macro_p = p;
            end
            
            if ~app.Macro_AddacalculationButton.Value
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Finished!';
            else
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Calculation saved in macro. Add another calculation or go back to the macro tab.';
                app.macro_fctname = 'Blind noise level';
                app.macro_add_calculation;
            end            
        end

        % Value changed function: Macro_AddacalculationButton
        function Macro_AddacalculationButtonValueChanged(app, event)
            value = app.Macro_AddacalculationButton.Value;
            if value
                app.Macro_CalculationmodeLabel.Text = 'Go to Calculations tab';
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Macro mode. Select your parameters and click on the blue button as if you would perform a calculation';
                app.CalcGreyLevel_StatutIdleLabel.FontColor = [1 0 0];
                app.CalcGrey_SelectbackgroundDropDown.Items = {'From label'};
                app.CalcSeg_StatutIdleLabel.Text = 'Macro mode. Select your parameters and click on the blue button as if you would perform a calculation';
                app.CalcSeg_StatutIdleLabel.FontColor = [1 0 0];
                app.CalcSeg_SelectbackgroundDropDown.Items = {'From label'};                
                app.Macro_AddastepButton.Enable = 'off';
            else
                app.Macro_CalculationmodeLabel.Text = '';
                app.CalcGreyLevel_StatutIdleLabel.Text = 'Statut: Idle';
                app.CalcGreyLevel_StatutIdleLabel.FontColor = [0 0 0];
                app.CalcGrey_SelectbackgroundDropDown.Items = {'From label', 'From state'};
                app.CalcSeg_StatutIdleLabel.Text = 'Statut: Idle';
                app.CalcSeg_StatutIdleLabel.FontColor = [0 0 0];
                app.CalcSeg_SelectbackgroundDropDown.Items = {'From label', 'From state'};                
                app.Macro_AddastepButton.Enable = 'on';
            end
        end

        % Value changed function: 
        % CalcSeg_ExcludebackgroundfromthecalculationCheckBox
        function CalcSeg_ExcludebackgroundfromthecalculationCheckBoxValueChanged(app, event)
            value = app.CalcSeg_ExcludebackgroundfromthecalculationCheckBox.Value;
            app.CalcSeg_SelectbackgroundDropDown.Enable = value;
            if ~value
                app.CalcSeg_BackgroundlabelEditField.Enable = 'off';
                app.CalcSeg_FromfileDropDown.Enable = 'off';
                app.CalcSeg_FromworkflowDropDown.Enable = 'off';
                app.CalcSeg_FromstateDropDown.Enable = 'off';
            else
                app.CalcSeg_SelectbackgroundDropDownValueChanged;     
            end
        end

        % Value changed function: CalcSeg_SelectbackgroundDropDown
        function CalcSeg_SelectbackgroundDropDownValueChanged(app, event)
            value = app.CalcSeg_SelectbackgroundDropDown.Value;
            if strcmp(value,'From label')
                app.CalcSeg_BackgroundlabelEditField.Enable = 'on';
                app.CalcSeg_FromfileDropDown.Enable = 'off';
                app.CalcSeg_FromworkflowDropDown.Enable = 'off';
                app.CalcSeg_FromstateDropDown.Enable = 'off';                
            elseif strcmp(value,'From state')
                app.CalcSeg_FromfileDropDown.Items = app.SelectactivefileDropDown.Items;
                if ~isempty(app.CalcSeg_FromfileDropDown.Items)
                    app.CalcSeg_FromfileDropDown.Value = app.CalcSeg_FromfileDropDown.Items(1);
                    app.Calc_FromfileDropDownValueChanged;
                    app.CalcSeg_BackgroundlabelEditField.Enable = 'off';
                    app.CalcSeg_FromfileDropDown.Enable = 'on';
                    app.CalcSeg_FromworkflowDropDown.Enable = 'on';
                    app.CalcSeg_FromstateDropDown.Enable = 'on';
                end
            end                
            
        end

        % Callback function: CalcSeg_FromfileDropDown, 
        % ...and 1 other component
        function Calc_FromfileDropDownValueChanged(app, event)
            from_f = app.CalcSeg_FromfileDropDown.ValueIndex;
            % Propagate
            app.CalcSeg_FromworkflowDropDown.Items = app.workflownames(from_f).names;
            app.CalcSeg_FromworkflowDropDown.Value = app.CalcSeg_FromworkflowDropDown.Items(end);
            app.CalcSeg_FromworkflowDropDownValueChanged;   
        end

        % Value changed function: CalcSeg_FromworkflowDropDown
        function CalcSeg_FromworkflowDropDownValueChanged(app, event)
            from_f = app.CalcSeg_FromfileDropDown.ValueIndex;
            from_w = app.CalcSeg_FromworkflowDropDown.ValueIndex;
            [n_state,~] = size(app.file(from_f).workflow(from_w).statetable);
            tmp = [];
            for k=1:1:n_state
                if ~isempty( app.file(from_f).workflow(from_w).state(k).M)
                    tmp = [tmp; {num2str(k,'%i')}];
                end
            end
            app.CalcSeg_FromstateDropDown.Items = tmp;
            app.CalcSeg_FromstateDropDown.Value = app.CalcSeg_FromstateDropDown.Items(1);   
        end

        % Button pushed function: Volumefractions_CalculateandplotButton
        function Volumefractions_CalculateandplotButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            
            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;            
            p.voxelsize = app.CalcSeg_VoxelsizeEditField.Value;
            p.voxelunit = app.CalcSeg_UnitEditField.Value;  

            p.excludebackground = app.CalcSeg_ExcludebackgroundfromthecalculationCheckBox.Value;
            if p.excludebackground
                p.selectbackground = app.CalcSeg_SelectbackgroundDropDown.Value;
                if strcmp(p.selectbackground,'From label')
                    p.background_label = app.CalcSeg_BackgroundlabelEditField.Value;
                else
                    from_f = app.CalcSeg_FromfileDropDown.ValueIndex;
                    from_w = app.CalcSeg_FromworkflowDropDown.ValueIndex;
                    from_s = str2num(app.CalcSeg_FromstateDropDown.Value);
                    p.background_volume = app.file(from_f).workflow(from_w).state(from_s).M;
                end
            end            

            if ~app.Macro_AddacalculationButton.Value
                app.CalcSeg_StatutIdleLabel.Text = ['Please wait... Calculation: volume fractions on file: ' char(app.SelectactivefileDropDown.Items(app.active_f))];
                pause(0.1);
            end            

            if ~app.Macro_AddacalculationButton.Value
                fct_volumefractions(M,p);
            else
                app.macro_fct = 'fct_volumefractions';
                app.macro_p = p;
            end
            
            if ~app.Macro_AddacalculationButton.Value
                app.CalcSeg_StatutIdleLabel.Text = 'Finished!';
            else
                app.CalcSeg_StatutIdleLabel.Text = 'Calculation saved in macro. Add another calculation or go back to the macro tab.';
                app.macro_fctname = 'Volume fractions';
                app.macro_add_calculation;
            end            
        end

        % Button pushed function: CalculateClusters_DoButton
        function CalculateCluster_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.label = app.ForlabelEditField.Value;
            p.connectivity_rule_2D = app.TwoDconnectivitiesDropDown_2.Value;
            p.connectivity_rule_3D = app.ThreeDconnectivitiesDropDown_2.Value;
            p.background_is_edges = app.Cluster_BackgroundisdomainsedgesCheckBox.Value;
            p.backgroundlabel = app.Clusters_Background_EditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_3.Text = ['Please wait... Action: connectivity (clusters): ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);
                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_calculatecluster(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_calculatecluster';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = 'Connectivity (clusters)';
                app.par = ['Label: ' num2str(p.label,'%i') ', 2D connectivities: ' p.connectivity_rule_2D ', 3D connectivities: ' p.connectivity_rule_3D];
                if ~p.background_is_edges
                    app.par = [app.par ', background is not edges and is label: ' num2str(p.backgroundlabel)];
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Connectivity_StatutIdleLabel_3.Text = 'Finished!';
        end

        % Button pushed function: SortInstance_DoButton
        function SortInstance_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            p.order = app.Sortchoice_DropDown.Value;
            p.keepunchangedsomelabels = app.ButdonotmodifysomelabelsCheckBox_2.Value;
            p.cst = app.Sort_Constant_EditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Reassign_StatutIdleLabel.Text = ['Please wait... Action: Re-assign (sort per size) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);                

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_sortpersize(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_sortpersize'; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Sort per size';
                app.par = p.order;

                if p.keepunchangedsomelabels
                    app.par = [app.par '. Unchanged labels: ' p.cst];
                end                   
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end  
            app.Reassign_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: ChannelSize_DoButton_3
        function ChannelSize_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            app.current_channel = 'Label size'; 

            % Parameters
            p.choice = app.ChannelLabel_ForDropDown.Value;
            p.label = app.Channel_LabelEditField.Value;
            p.excluded_labels = app.ExcludedlabelsEditField_2.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Channel_StatutIdleLabel_3.Text = ['Please wait... Action: calculate label size on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_calculatesize(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_calculatesize';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = 'label size';
                if strcmp(p.choice,'One label')
                    app.par = ['For label ' num2str(p.label)];
                elseif strcmp(p.choice,'All labels except some labels')
                    app.par = ['All labels except ' num2str(p.excluded_labels)];
                elseif strcmp(p.choice,'All labels')                    
                    app.par = 'For all labels';
                end               
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Channel_StatutIdleLabel_3.Text = 'Finished!';
        end

        % Button pushed function: Highlow_filterButton
        function Highlow_filterButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            % Parameters
            p.choice = app.ForallvoxelsDropDown_2.Value;
            p.threshold = app.Filterthreshold_EditField_2.Value;
            p.newval = app.setthemtoEditField_2.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Contrast1_StatutIdleLabel.Text = ['Please wait... Action: contrast correction (high-low filter) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_highlow_filter(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_highlow_filter';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Contrast correction (high-low filter)';
                app.par = [p.choice ' ' num2str(p.threshold,'%i') ' set to ' num2str(p.newval,'%i')] ;
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Contrast1_StatutIdleLabel.Text = 'Finished!';
        end

        % Callback function
        function SeparateLabel_PlotdistributionButtonPushed(app, event)
            % Read parameter table
            p.equiprobability = app.EquiprobabilityCheckBox.Value;
            p.mean_width = app.MeanwidthEditField.Value;
            p.sigma_width = app.SigmawidthEditField.Value;
            p.maxdeviation_width = app.MaxdeviationEditField.Value;
            
            Fig = figure; % Create figure
            Fig.Name= 'Distributions'; % Figure name
            Fig.Color='white'; % Background colour
            ax_ = axes('Parent',Fig);
            hold(ax_,'on'); % Active subplot
            h_title = title ('Width distribution (will be rounded to nearest even number)'); 
            xlabel('Width');
            ylabel('a.u');
            % Plot
            [x_, pdf_, ~] = normal_distributions(p.equiprobability, p.mean_width, p.sigma_width, p.maxdeviation_width);
            if p.maxdeviation_width==0
                plot(p.mean_width,1,'LineWidth',2,'Marker','o','MarkerSize',8);
            else
                if p.equiprobability
                    plot([p.mean_width-p.maxdeviation_width p.mean_width+p.maxdeviation_width],[1 1],'LineWidth',2);
                else
                    plot(x_,pdf_,'LineWidth',2);
                end
            end
            % - Grid
            grid(ax_,'on'); % Display grid
            set(ax_,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
            set(ax_,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize
            hold(ax_,'off'); % Relase figure
        end

        % Button pushed function: Channel_pixelsize_Button_2
        function Channel_pixelsize_ButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.choice = app.ChannelLabel_ForDropDown_2.Value;
            p.label = app.Channel_LabelEditField_2.Value;
            p.excluded_labels = app.ExcludedlabelsEditField.Value;
            p.metric = app.Size_MericDropDown_2.Value;
            p.round = app.Size_RoundresultCheckBox_2.Value;
            p.round_dmap_digit = app.CpsdEDM_roundnumberofdigits_EditField.Value;
            p.distance = app.Size_DistanceDropDown_2.Value;

            app.current_channel = p.metric; 

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Channel_StatutIdleLabel_2.Text = ['Please wait... Action: Channel (pixel-wise size, ' p.metric ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_pixelsize(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_pixelsize';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = p.metric;
                if strcmp(p.choice,'One label')
                    app.par = ['For label ' num2str(p.label)];
                elseif strcmp(p.choice,'All labels except background')
                    app.par = ['All labels except some labels ' num2str(p.excluded_labels)];
                elseif strcmp(p.choice,'All labels')                    
                    app.par = 'For all labels';
                end                    
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Channel_StatutIdleLabel_2.Text = 'Finished!';   
        end

        % Callback function
        function Size_MericDropDownValueChanged(app, event)
            value = app.Size_MericDropDown.Value;
            if strcmp(value,'EDM')
                app.Size_DistanceDropDown.Enable = 'on';
                app.Size_WithinlabelCheckBox.Enable = 'on';
            else
                app.Size_DistanceDropDown.Enable = 'off';
                app.Size_WithinlabelCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: ChannelShape_DoButton_3
        function ChannelShape_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.metric = app.Shape_MetricDropDown_3.Value;
            p.choice = app.ChannelLabel_ForDropDown.Value;
            p.label = app.Channel_LabelEditField.Value;
            p.excluded_labels = app.ExcludedlabelsEditField_2.Value;   
            p.convert_toint = app.Channel_ConverttointegerCheckBox.Value;
            p.precision = app.Channel_PrecisionEditField.Value;

            if strcmp(p.metric,'Circularity (2D), sphericity (3D)')
                dimension = length(size(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M));
                if dimension == 2
                    app.current_channel = 'Circularity'; 
                else
                    app.current_channel = 'Sphericity'; 
                end
            else
                app.current_channel = p.metric;
            end

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Channel_StatutIdleLabel_3.Text = ['Please wait... Action: calculate label shape (' app.current_channel ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_calculateshape(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_calculateshape';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = ['Label shape (' app.current_channel ')'];
                if strcmp(p.choice,'One label')
                    app.par = ['For label ' num2str(p.label)];
                elseif strcmp(p.choice,'All labels except some labels')
                    app.par = ['For all labels except ' num2str(p.excluded_labels)];
                elseif strcmp(p.choice,'All labels')                    
                    app.par = 'For all labels';
                end                    
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Channel_StatutIdleLabel_3.Text = 'Finished!';
        end

        % Value changed function: ChannelLabel_ForDropDown
        function ChannelLabel_ForDropDownValueChanged(app, event)
            value = app.ChannelLabel_ForDropDown.Value;
            if strcmp(value,'One label')
                app.Channel_LabelEditField.Enable = 'on';
                app.ExcludedlabelsEditField_2.Enable = 'off';
            elseif strcmp(value,'All labels except some labels')
                app.Channel_LabelEditField.Enable = 'off';
                app.ExcludedlabelsEditField_2.Enable = 'on';
            elseif strcmp(value,'All labels')
                app.Channel_LabelEditField.Enable = 'off';
                app.ExcludedlabelsEditField_2.Enable = 'off';
            end            
        end

        % Value changed function: Channel_ConverttointegerCheckBox
        function Channel_ConverttointegerCheckBoxValueChanged(app, event)
            value = app.Channel_ConverttointegerCheckBox.Value;
            app.Channel_PrecisionEditField.Enable = value;
        end

        % Value changed function: ChannelLabel_ForDropDown_2
        function ChannelLabel_ForDropDown_2ValueChanged(app, event)
            value = app.ChannelLabel_ForDropDown_2.Value;
            if strcmp(value,'One label')
                app.Channel_LabelEditField_2.Enable = 'on';
                app.ExcludedlabelsEditField.Enable = 'off';
            elseif strcmp(value,'All labels except some labels')
                app.Channel_LabelEditField_2.Enable = 'off';
                app.ExcludedlabelsEditField.Enable = 'on';
            elseif strcmp(value,'All labels')
                app.Channel_LabelEditField_2.Enable = 'off';
                app.ExcludedlabelsEditField.Enable = 'off';
            end  
        end

        % Value changed function: Size_MericDropDown_2
        function Size_MericDropDown_2ValueChanged(app, event)
            value = app.Size_MericDropDown_2.Value;
            if strcmp(value,'c-PSD')
                app.Size_DistanceDropDown_2.Enable = 'off';
            elseif strcmp(value,'EDM')
                app.Size_DistanceDropDown_2.Enable = 'on';
            end
        end

        % Value changed function: SepCriterion_ChoiceDropDown
        function SepCriterion_ChoiceDropDownValueChanged(app, event)
            value = app.SepCriterion_ChoiceDropDown.Value;
            if strcmp(value,'Plot maximum separability critertion')
                app.ImageQuality_DropDown.Items = {'Local' 'Global'};
                app.SepCriterion_maxEditField.Enable = 'on';
            elseif strcmp(value,'Plot all separability criterion')
                app.ImageQuality_DropDown.Items = {'Global'};
                app.SepCriterion_maxEditField.Enable = 'off';
                app.SepCriterion_maxEditField.Value = app.SepCriterion_minEditField.Value;
            end
        end

        % Value changed function: SepCriterion_maxEditField, 
        % ...and 1 other component
        function SepCriterion_minEditFieldValueChanged(app, event)
            choice = app.SepCriterion_ChoiceDropDown.Value;
            if strcmp(choice,'Plot all separability criterion')
                app.SepCriterion_minEditField.Value = min([app.SepCriterion_minEditField.Value 3]);
                app.SepCriterion_maxEditField.Value = app.SepCriterion_minEditField.Value;
            else
                min_ = app.SepCriterion_minEditField.Value;
                max_ = app.SepCriterion_maxEditField.Value;
                app.SepCriterion_minEditField.Value = min([min_ max_]);
                app.SepCriterion_maxEditField.Value = max([min_ max_]);                
            end
        end

        % Button pushed function: Filter_Value_DoButton
        function Filter_Value_DoButtonPushed(app, event)
            % Parameters
            p.choice = app.IdentifypixelsDropDown.Value;
            p.threshold = app.FilterValue_EditField.Value;
            p.scalewithdim = app.FilterVal_ScalewithdimensionCheckBox.Value;
            p.background = app.FilterVal_BackgroundEditField.Value;
                        
            if app.GLFilter_PreviewCheckBox.Value
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                [Mfiltered,~,~] = fct_valfilter(M,p);
                Fig = figure; Fig.Color = 'w'; Fig.Name = 'Original image';
                imagesc(M); axis equal; axis tight;  colormap turbo; colorbar;
                Fig = figure; Fig.Color = 'w'; Fig.Name = 'After value filter';
                imagesc(Mfiltered); axis equal; axis tight; colormap gray;               
            else
                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (value filter) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,newtype,~] = fct_valfilter(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_valfilter';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                    app.act = 'Value filter';
                    app.par = [p.choice ' ' num2str(p.threshold,'%1.3f')];
                    if p.scalewithdim
                        app.par = [app.par ', scaled with dimension'];
                    end
                    if p.background>=0
                        app.par = [app.par ', background ' num2str(p.background,'%i')];
                    end

                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = 'Finished!';
            end
        end

        % Button pushed function: ROI_StateMinusButton
        function ROI_StateMinusButtonPushed(app, event)
            tmp = app.States_UITable.Data;
            [n,~] = size(tmp);
            if n>1 && app.active_s>1
                % Simulate a click
                event = [];
                event.Indices = [app.active_s-1 15];
                event.NewData = true;
                app.change_quickstate = true;
                app.previous_sz = app.ROI_crop_UITable.Data(:,2).Variables';                
                app.States_UITableCellEdit(event)
                app.ROI_StateLabel.Text = ['State #' num2str(app.active_s) ': ' char(tmp(app.active_s,2))];
            end 
        end

        % Button pushed function: ROI_StatePlusButton
        function ROI_StatePlusButtonPushed(app, event)
            tmp = app.States_UITable.Data;
            [n,~] = size(tmp);
            if n>1 && app.active_s<n
                % Simulate a click
                event = [];
                event.Indices = [app.active_s+1 15];
                event.NewData = true;
                app.change_quickstate = true;
                app.previous_sz = app.ROI_crop_UITable.Data(:,2).Variables';   
                app.States_UITableCellEdit(event)
                app.ROI_StateLabel.Text = ['State #' num2str(app.active_s) ': ' char(tmp(app.active_s,2))];
            end             
        end

        % Button down function: ReassignTab
        function ReassignTabSizeChanged(app, event)
            labels = unique(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);
            app.Reassign_UITable.Data = [labels labels];
        end

        % Button pushed function: Surrounding_DoButton
        function Surrounding_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.windowradius = app.Surrounding_WindowradiusEditField.Value;
            p.label = app.SurroundingLabel_EditField.Value;
            p.surround_label = app.issurroundedbylabelEditField.Value;
            p.thresholdratio = app.morethanratioofinterfaceEditField.Value;
            p.assignto = app.thenassigntolabelEditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_2.Text = ['Please wait... Action: check surroundings on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_checksurroundings_percluster(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_checksurroundings_percluster';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'check surroundings';
                app.par = ['Window radius: ' num2str(p.windowradius) ,'. If label: ' num2str(p.label) ' is surrounded by label:' num2str(p.surround_label) ', more than (interface ratio): ' num2str(p.thresholdratio) ', then reassign to label: ' num2str(p.assignto)];               
 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Connectivity_StatutIdleLabel_2.Text = 'Finished!';
        end

        % Value changed function: Mosaic_FromworkflowEditField, 
        % ...and 1 other component
        function Mosaic_FromworkflowEditFieldValueChanged(app, event)
            from = app.Mosaic_FromworkflowEditField.Value;
            to = app.Mosaic_toworkflowEditField.Value;
            n_workflow = length(app.SelectactiveworkflowDropDown.Items);
            if from > to || to>n_workflow
                app.Mosaic_FromworkflowEditField.Value = 1;
                app.Mosaic_toworkflowEditField.Value = n_workflow;
            end            
        end

        % Value changed function: 
        % Localdialtion_Perslice3DonyzdirectionCheckBox
        function Localdialtion_Perslice3DonyzdirectionCheckBoxValueChanged(app, event)
            value = app.Localdialtion_Perslice3DonyzdirectionCheckBox.Value;
            app.Localdilation_PersliceDropDown.Enable = value;
        end

        % Value changed function: RandomizeDistance_CheckBox
        function RandomizeDistance_CheckBoxValueChanged(app, event)
            value = app.RandomizeDistance_CheckBox.Value;
            app.Rnd_Checkuptoadistance_EditField.Enable = value;
        end

        % Value changed function: LocalSegmentation_Method_DropDown
        function LocalSegmentation_Method_DropDownValueChanged(app, event)
            value = app.LocalSegmentation_Method_DropDown.Value;
            if strcmp(value,'Manual')
                app.LocalSeg_NumberofcontrolpointEditField.Enable = 'on';
                app.LocalSeg_UITable.Enable = 'on';                      
                app.TranslatethresholdprofileCheckBox.Enable = 'off';
                app.MovingaveragefilterrangeEditField.Enable = 'off';  
                app.LocalSeg_NumberofcontrolpointEditFieldValueChanged;
                app.VisualizethresholdsButton.Enable = 'on';
            elseif strcmp(value,'Otsu')
                app.LocalSeg_NumberofcontrolpointEditField.Enable = 'off';
                app.LocalSeg_UITable.Enable = 'off';     
                app.TranslatethresholdprofileCheckBox.Enable = 'on';
                app.MovingaveragefilterrangeEditField.Enable = 'on';
                app.VisualizethresholdsButton.Enable = 'off';
            end
        end

        % Value changed function: LocalSeg_NumberofcontrolpointEditField
        function LocalSeg_NumberofcontrolpointEditFieldValueChanged(app, event)
            value = app.LocalSeg_NumberofcontrolpointEditField.Value;
            nphase = app.Seg_Numberofphases_editfield.Value;
            npoint = app.LocalSeg_NumberofcontrolpointEditField.Value;
            tmp = zeros(npoint,nphase+1);

            % Control point location
            tmp(:,1) = linspace(0,1,npoint);

            min_ = double( min(min(min( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M ))));
            max_ = double( max(max(max( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M ))));
            if max_>1
                tmpthrehsold = round(linspace(min_,max_,nphase+1));
            else
                tmpthrehsold = linspace(min_,max_,nphase+1);
            end

            ColumnName = cell(1,nphase+1);
            ColumnEditable = ones(1,nphase+1);
            ColumnName(1) = {'Location'};      
            for k=2:nphase+1
                tmp(:,k) = tmpthrehsold(k);
                ColumnName(k) = {['Phase# ' num2str(k-1)]};
            end
            ColumnEditable(end) = 0;
            app.LocalSeg_UITable.ColumnName = ColumnName;
            app.LocalSeg_UITable.ColumnEditable = logical(ColumnEditable);
            app.LocalSeg_UITable.Data = tmp;
            
        end

        % Cell edit callback: LocalSeg_UITable
        function LocalSeg_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            data = app.LocalSeg_UITable.Data;
            [npos,nphase] = size(data);
            nphase = nphase-1;
            max_gl = data(1,end);
            
            if indices(2)==1 % Position
                if isempty(newData) || isnan(newData) || newData<0 || newData>1 || indices(1)==1 || indices(1)==npos
                    app.LocalSeg_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                else                     
                    pos = data(:,1);
                    if newData<=pos(indices(1)-1,1) || newData>=pos(indices(1)+1,1)
                        app.LocalSeg_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                    end
                end
            elseif indices(2)<=nphase
                if isempty(newData) || isnan(newData) || newData<0 || newData>=max_gl
                    app.LocalSeg_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                else
                    if indices(2)>2
                        prev = data(indices(1),indices(2)-1);
                        next = data(indices(1),indices(2)+1);
                        if newData<=prev || newData>=next
                            app.LocalSeg_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                        end
                    else
                        next = data(indices(1),indices(2)+1);
                        if newData>=next
                            app.LocalSeg_UITable.Data(indices(1),indices(2)) = event.PreviousData;
                        end
                    end
                end
            end            
        end

        % Button pushed function: Removetruncated_DoButton
        function Removetruncated_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.background_is_edges = app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value;
            p.backgroundlabel = app.ClusterInteractions_Background_EditField.Value;
            p.complimentarylabel = app.ClusterInteractions_complimentarylabelEditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_2.Text = ['Please wait... Action: connectivity (remove truncated labels): ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);
                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_removetruncated(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_removetruncated';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Connectivity (remove truncated labels)';
                if p.background_is_edges
                    app.par = 'background is domain edges';
                else
                    app.par = ['background is not domain edges and is label: ' num2str(p.backgroundlabel)];
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Connectivity_StatutIdleLabel_2.Text = 'Finished!';
        end

        % Value changed function: 
        % ClusterInerationcs_BackgroundisdomainsedgesCheckBox
        function ClusterInerationcs_BackgroundisdomainsedgesCheckBoxValueChanged(app, event)
            value = app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value;
            app.ClusterInteractions_Background_EditField.Enable = ~value;
            if value
                app.Image_connectivity_background.ImageSource = 'Connectivity_nobackground.png';
                app.ClusterInteractions_complimentarylabelEditField.Value = 0;
                app.ClusterInteractions_Background_EditField.Value = 0;                
            else
                app.Image_connectivity_background.ImageSource = 'Connectivity_background.png';
                app.ClusterInteractions_complimentarylabelEditField.Value = 1;
                app.ClusterInteractions_Background_EditField.Value = 0;                      
            end
            app.Separatelabels_ChoiceDropDown_2ValueChanged;
        end

        % Button pushed function: RemoveGapNumering_DoButton
        function RemoveGapNumering_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end

            p = [];
            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Reassign_StatutIdleLabel.Text = ['Please wait... Action: Re-assign (remove gap in numbering) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);                

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_remvovegapinnumbering(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_remvovegapinnumbering'; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true; 
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Remove gap in numbering';
                app.par = 'n/a';
                
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end  
            app.Reassign_StatutIdleLabel.Text = 'Finished!';
        end

        % Button pushed function: RemoveIncludedLabels_DoButton
        function RemoveIncludedLabels_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.background_is_edges = app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value;
            p.backgroundlabel = app.ClusterInteractions_Background_EditField.Value;
            p.complementarylabel = app.ClusterInteractions_complimentarylabelEditField.Value;
            p.Assigntosurrundinglabel = app.AssigntosurroundinglabelCheckBox.Value;
            p.Assigntolabel = app.OtherwiseassigntoEditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_2.Text = ['Please wait... Action: connectivity (remove included labels): ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);
                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,~,~] = fct_removeinclusion(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_removeinclusion';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Connectivity (remove included labels)';
                if p.background_is_edges
                    app.par = 'background is domain edges';
                else
                    app.par = ['background is not domain edges and is label: ' num2str(p.backgroundlabel)];
                end
                app.par = [app.par ', assign to label ' num2str(p.Assigntolabel)];
                if p.Assigntosurrundinglabel
                    app.par = [app.par ', assign to surrounding label'];
                else
                    app.par = [app.par ', assign to label ' num2str(p.Assigntolabel)];
                end

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Connectivity_StatutIdleLabel_2.Text = 'Finished!';
        end

        % Value changed function: AssigntosurroundinglabelCheckBox
        function AssigntosurroundinglabelCheckBoxValueChanged(app, event)
            value = app.AssigntosurroundinglabelCheckBox.Value;
            app.OtherwiseassigntoEditField.Enable = ~value;
        end

        % Value changed function: SubstitutionChoice_DropDown
        function SubstitutionChoice_DropDownValueChanged(app, event)
            value = app.SubstitutionChoice_DropDown.Value;
            if strcmp(value,'M( M == min(M) ) = y') || strcmp(value,'M( M == max(M) ) = y')
                app.IndexSubstitution_x_EditField.Enable = 'off';
            else
                app.IndexSubstitution_x_EditField.Enable = 'on';
            end
        end

        % Value changed function: ButdonotmodifysomelabelsCheckBox
        function ButdonotmodifysomelabelsCheckBoxValueChanged(app, event)
            value = app.ButdonotmodifysomelabelsCheckBox.Value;
            app.Randomize_Constant_EditField.Enable = value;
        end

        % Value changed function: Separatelabels_ChoiceDropDown_2
        function Separatelabels_ChoiceDropDown_2ValueChanged(app, event)
            value = app.Separatelabels_ChoiceDropDown_2.Value;
            if strcmp(value,'Separate identically all labels')
                app.Cluster_separation_PleasewaitLabel.Visible = 'off';
                app.RatioofseparatedlabelsEditField.Enable = 'off';
                app.MeanwidthEditField_2.Enable = 'on';
                app.DistributionstddeviationEditField.Enable = 'off';
                app.EquiprobabilityCheckBox_2.Enable = 'off';
                app.MaxvariationfrommeanEditField.Enable = 'off';
                app.ClusterSeparation_PlotdistributionButton.Enable = 'off';
                app.SeparateCluster_UITable.Enable = 'off';
            elseif strcmp(value,'Separate randomly labels')
                app.Cluster_separation_PleasewaitLabel.Visible = 'off';
                app.RatioofseparatedlabelsEditField.Enable = 'on';
                app.MeanwidthEditField_2.Enable = 'on';
                app.DistributionstddeviationEditField.Enable = 'on';
                app.EquiprobabilityCheckBox_2.Enable = 'on';
                app.MaxvariationfrommeanEditField.Enable = 'on';
                app.ClusterSeparation_PlotdistributionButton.Enable = 'on';
                app.SeparateCluster_UITable.Enable = 'off';
            elseif strcmp(value,'Separate specifically some labels')
               app.RatioofseparatedlabelsEditField.Enable = 'off'; 
               app.MeanwidthEditField_2.Enable = 'off';
               app.DistributionstddeviationEditField.Enable = 'off';
               app.EquiprobabilityCheckBox_2.Enable = 'off';
               app.MaxvariationfrommeanEditField.Enable = 'off';
               app.ClusterSeparation_PlotdistributionButton.Enable = 'off';

               app.Cluster_separation_PleasewaitLabel.Visible = 'on'; pause(0.1);
               unis = unique( app.file(app.active_f).workflow(app.active_w).state(app.active_s).M );
               unis(unis == app.ClusterInteractions_complimentarylabelEditField.Value) = [];
               if ~app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value
                   unis(unis == app.ClusterInteractions_Background_EditField.Value) = [];
               end
               n_unis = length(unis);

               ColumnName = cell(1,n_unis+1);
               ColumnName(1) = {'Labels'};
               widths = zeros(n_unis,n_unis)+app.MeanwidthEditField_2.Value;
               for k=1:1:n_unis
                   widths(k,k)=0;
               end
               data = double([unis widths]);

               data(:,1) = unis;
               cedit(1) = [false];
               for k=2:1:n_unis+1
                   ColumnName(k)={[num2str(unis(k-1))]};
                   cedit(k) = [true];
               end
               app.SeparateCluster_UITable.Data = array2table(data,'VariableNames',ColumnName);
               app.SeparateCluster_UITable.ColumnName = ColumnName;
               app.SeparateCluster_UITable.ColumnEditable =cedit;
               app.Cluster_separation_PleasewaitLabel.Visible = 'off';
               app.SeparateCluster_UITable.Enable = 'on';
            end                
        end

        % Value changed function: EquiprobabilityCheckBox_2
        function EquiprobabilityCheckBox_2ValueChanged(app, event)
            value = app.EquiprobabilityCheckBox_2.Value;
            app.DistributionstddeviationEditField.Enable = ~value;            
        end

        % Button pushed function: ClusterSeparation_PlotdistributionButton
        function ClusterSeparation_PlotdistributionButtonPushed(app, event)
            % Read parameter table
            p.equiprobability = app.EquiprobabilityCheckBox_2.Value;
            p.mean_width = app.MeanwidthEditField_2.Value;
            p.sigma_width = app.DistributionstddeviationEditField.Value;
            p.maxdeviation_width = app.MaxvariationfrommeanEditField.Value;
            
            Fig = figure; % Create figure
            Fig.Name= 'Distributions'; % Figure name
            Fig.Color='white'; % Background colour
            ax_ = axes('Parent',Fig);
            hold(ax_,'on'); % Active subplot
            h_title = title ('Width distribution (will be rounded to nearest even number)'); 
            xlabel('Width');
            ylabel('a.u');
            % Plot
            [x_, pdf_, ~] = normal_distributions(p.equiprobability, p.mean_width, p.sigma_width, p.maxdeviation_width);
            id0 = find(x_<0);
            if ~isempty(id0)
                x_(id0)=[];
                pdf_(id0)=[];
            end
            if p.maxdeviation_width==0
                plot(p.mean_width,1,'LineWidth',2,'Marker','o','MarkerSize',8);
            else
                if p.equiprobability
                    plot([max([p.mean_width-p.maxdeviation_width 0]) p.mean_width+p.maxdeviation_width],[1 1],'LineWidth',2);
                else
                    plot(x_,pdf_,'LineWidth',2);
                end
            end
            % - Grid
            grid(ax_,'on'); % Display grid
            set(ax_,'XMinorGrid','on','YMinorGrid','on'); % Display grid for minor thicks
            set(ax_,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize
            hold(ax_,'off'); % Relase figure
        end

        % Button pushed function: Removetruncated_DoButton_2
        function Removetruncated_DoButton_2Pushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.choice = app.Separatelabels_ChoiceDropDown_2.Value;
            p.complimentary_label = app.ClusterInteractions_complimentarylabelEditField.Value;
            p.thereisabackrgound = ~app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value;
            p.background = app.ClusterInteractions_Background_EditField.Value;
            if ~isempty(app.SeparateCluster_UITable.Data)
                p.sep_specwidth = app.SeparateCluster_UITable.Data.Variables;
            end
            p.ratioseparated = app.RatioofseparatedlabelsEditField.Value;
            p.equiprobability = app.EquiprobabilityCheckBox_2.Value;
            p.mean_width = app.MeanwidthEditField_2.Value;
            p.sigma_width = app.DistributionstddeviationEditField.Value;
            p.maxdeviation_width = app.MaxvariationfrommeanEditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_2.Text = ['Please wait... Action: connectivity (separate labels): ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);
                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                p.datatype = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                [M,~,~] = fct_separate_labels(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_separate_labels';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                app.act = 'Connectivity (separate labels)';
                if strcmp(p.choice,'Separate identically all labels')
                    app.par = ['Separation width between all labels: ' num2str(p.mean_width,'%i')];
                elseif strcmp(p.choice,'Separate specifically some labels')
                    app.par = 'Separation width between label doublets:';
                    labels = p.sep_specwidth(:,1);
                    widths = p.sep_specwidth(:,2:end);
                    sz = size(widths);
                    for r=2:1:sz(1)
                        for c=1:1:r-1
                            app.par = [app.par ' (' num2str(labels(r)) ',' num2str(labels(c)) '):' num2str(widths(r,c))];
                        end
                    end
                elseif strcmp(p.choice,'Separate randomly labels')
                    if p.equiprobability
                        app.par = ['Separation width between randomly selected labels: ratio of separation=' num2str(p.ratioseparated,'%1.3f') ', width mean=' num2str(p.mean_width,'%1.3f') ', max deviation=' num2str(p.maxdeviation_width,'%1.3f') ', with equiprobability'];
                    else
                        app.par = ['Separation width between randomly selected labels: ratio of separation=' num2str(p.ratioseparated,'%1.3f') ', width normal distribution: mean=' num2str(p.mean_width,'%1.3f') ', sigma=' num2str(p.sigma_width,'%1.3f') ', max deviation=' num2str(p.maxdeviation_width,'%1.3f')];
                    end
                end
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Connectivity_StatutIdleLabel_2.Text = 'Finished!';
        end

        % Value changed function: MeanwidthEditField_2
        function MeanwidthEditField_2ValueChanged(app, event)
            value = app.MeanwidthEditField_2.Value;
            iseven = rem(value, 2) == 0;
            if ~iseven
                app.MeanwidthEditField_2.Value = event.PreviousValue;
            end
        end

        % Cell edit callback: SeparateCluster_UITable
        function SeparateCluster_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            iseven = rem(newData, 2) == 0;
            if ~iseven || newData<0
                app.SeparateCluster_UITable.Data(indices(1),indices(2)).Variables = event.PreviousData;
            end
        end

        % Button pushed function: VisualizethresholdsButton
        function VisualizethresholdsButtonPushed(app, event)
            x_ratio = app.LocalSeg_UITable.Data(:,1);
            thresholds = app.LocalSeg_UITable.Data(:,2:end);

            dir = str2num(app.LocalSegmentation_Axe_DropDown.Value(end));
            sz = size(app.file(app.active_f).workflow(app.active_w).state(app.active_s).M);
            % Get all thresholds per slice
            xq=1:1:sz(dir);
            xs = x_ratio*sz(dir);
            xs(1) = 1 ;
            thresholds = interp1(xs,thresholds,xq,'linear');
            thresholds=[1e-9*ones(sz(dir),1) thresholds];
            thresholds(:,1) = [];

            Fig = figure;
            Fig.Color='white'; % Background colour
            ax = axes('Parent',Fig);
            hold(ax,'on');

            col = colororder;
            labels = app.Segmentation_expectedvf_UITable.Data(:,2).Variables;
            x = [1:1:sz(dir)]*app.Seg_VoxelsizeEditField.Value;
            for current_phase = 1:1:app.Seg_Numberofphases_editfield.Value
                h = plot(x,thresholds(:,current_phase),'LineWidth',2,'Color',col(current_phase,:),'DisplayName',['Phase#' num2str(current_phase,'%i') ', label=' num2str(labels(current_phase),'%i') ]);
                if sz(dir)==1
                    set(h,'Marker','o');
                end
            end
            voxelunit = app.Seg_UnitEditField.Value;
            if strcmp(voxelunit,'um')
                voxelunit = '\mum';
            end
            xlabel(['Position along axe ' num2str(dir,'%i') ' (' voxelunit ')' ]);
            ylabel('Threshold (grey level)');
            legend(ax)

            grid(ax,'on');
            axis tight;
            set(ax,'FontName','Times new roman','FontSize',12);
            title(ax,'Local thresholds','FontName','Times new roman','FontSize',14);
            hold(ax,'off');


        end

        % Value changed function: Sort_Constant_EditField
        function Sort_Constant_EditFieldValueChanged(app, event)
            value = app.Sort_Constant_EditField.Value;
            cst  = str2num(value);
            if ~isempty(value) && isempty(cst)
                app.Sort_Constant_EditField.Value = event.PreviousValue;
            elseif sum(round(cst)==cst)~=length(cst)
                app.Sort_Constant_EditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: Randomize_Constant_EditField
        function Randomize_Constant_EditFieldValueChanged(app, event)
            value = app.Randomize_Constant_EditField.Value;
            cst  = str2num(value);
            if ~isempty(value) && isempty(cst)
                app.Randomize_Constant_EditField.Value = event.PreviousValue;
            elseif sum(round(cst)==cst)~=length(cst)
                app.Randomize_Constant_EditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: ButdonotmodifysomelabelsCheckBox_2
        function ButdonotmodifysomelabelsCheckBox_2ValueChanged(app, event)
            value = app.ButdonotmodifysomelabelsCheckBox_2.Value;
            app.Sort_Constant_EditField.Enable = value;
        end

        % Value changed function: ExcludedlabelsEditField
        function ExcludedlabelsEditFieldValueChanged(app, event)
            value = app.ExcludedlabelsEditField.Value;
            cst  = str2num(value);
            if ~isempty(value) && isempty(cst)
                app.ExcludedlabelsEditField.Value = event.PreviousValue;
            elseif sum(round(cst)==cst)~=length(cst)
                app.ExcludedlabelsEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: ExcludedlabelsEditField_2
        function ExcludedlabelsEditField_2ValueChanged(app, event)
            value = app.ExcludedlabelsEditField_2.Value;
            cst  = str2num(value);
            if ~isempty(value) && isempty(cst)
                app.ExcludedlabelsEditField_2.Value = event.PreviousValue;
            elseif sum(round(cst)==cst)~=length(cst)
                app.ExcludedlabelsEditField_2.Value = event.PreviousValue;
            end
        end

        % Value changed function: BinarizeChoice_DropDown
        function BinarizeChoice_DropDownValueChanged(app, event)
            value = app.BinarizeChoice_DropDown.Value;
            if strcmp(value,'M( M ==min(M) ) = 1, 0 otherwise') || strcmp(value,'M( M ==max(M) ) = 1, 0 otherwise')
                app.Binarize_x_EditField.Enable = 'off';
            else
                app.Binarize_x_EditField.Enable = 'on';
            end
        end

        % Value changed function: DeltaROIformacroCheckBox
        function DeltaROIformacroCheckBoxValueChanged(app, event)
            if app.DeltaROIformacroCheckBox.Value
                if app.RelativedimensionCheckBox.Value
                    app.RelativedimensionCheckBox.Value = false;
                    app.RelativedimensionCheckBoxValueChanged;
                end
            end
        end

        % Value changed function: Cluster_BackgroundisdomainsedgesCheckBox
        function Cluster_BackgroundisdomainsedgesCheckBoxValueChanged(app, event)
            value = app.Cluster_BackgroundisdomainsedgesCheckBox.Value;
            app.Clusters_Background_EditField.Enable = ~value;
            if value
                app.Image_connectivity_background_2.ImageSource = 'Connectivity_nobackground2.png';
                app.Cluster_OuptutdescriptionLabel.Text = 'Output: label 0: complementary volume, then label i (i>0) = ith cluster of selected phase';
                app.Cluster_complimentarylabelEditField.Value = 0;
                app.Clusters_Background_EditField.Value = 0;
            else
                app.Image_connectivity_background_2.ImageSource = 'Connectivity_background2.png';
                app.Cluster_OuptutdescriptionLabel.Text = 'Output: label 0: background, label 1: complementary volume, then label i (i>1) = ith cluster of selected phase';
                app.Cluster_complimentarylabelEditField.Value = 1;
                app.Clusters_Background_EditField.Value = 0;                
            end
            app.Connectivity_DefintionDropDownValueChanged;
        end

        % Value changed function: Cluster_complimentarylabelEditField, 
        % ...and 2 other components
        function Connectivity_DefintionDropDownValueChanged(app, event)
            value = app.Connectivity_DefintionDropDown.Value;
            if strcmp(value,'Isotropic')
                app.ClustersmusttouchDropDown.Enable = 'off';
                app.alongaxeDropDown.Enable = 'off';
                app.CustomruleclustersmustverifyDropDown.Enable = 'off';
                app.Custom_Connectivity_UITable_2.Enable = 'off';
                if app.Cluster_BackgroundisdomainsedgesCheckBox.Value
                    app.Image_connectivity_definition.ImageSource = 'Connectivity_definition_nobackground.png';
                    app.CalculateconnectivityforalllabelsexceptLabel.Text = ['Calculate connectivity for all labels except: '  num2str(app.Cluster_complimentarylabelEditField.Value)];
                    app.Cluster_OuptutdescriptionLabel_2.Text = 'Output: label 0: complementary volume, 1: main (largest) cluster, 2: isolated clusters, 3: unknown clusters';

                else
                    app.Image_connectivity_definition.ImageSource = 'Connectivity_definition_background.png';
                    app.CalculateconnectivityforalllabelsexceptLabel.Text = ['Calculate connectivity for all labels except: ' num2str(app.Clusters_Background_EditField.Value) ' and ' num2str(app.Cluster_complimentarylabelEditField.Value)];
                    app.Cluster_OuptutdescriptionLabel_2.Text = 'Output: label 0: background, 1: complementary volume, 2: main (largest) cluster, 3: isolated clusters, 4: unknown clusters';
                end
            else
                if strcmp(value,'Anisotropic')
                    app.ClustersmusttouchDropDown.Enable = 'on';
                    app.alongaxeDropDown.Enable = 'on';
                    app.CustomruleclustersmustverifyDropDown.Enable = 'off';
                    app.Custom_Connectivity_UITable_2.Enable = 'off';
                    app.ClustersmusttouchDropDownValueChanged;
                elseif strcmp(value,'Custom')
                    app.Image_connectivity_definition.ImageSource = 'Connectivity_custom.png';
                    app.ClustersmusttouchDropDown.Enable = 'off';
                    app.alongaxeDropDown.Enable = 'off';
                    app.CustomruleclustersmustverifyDropDown.Enable = 'on';
                    app.Custom_Connectivity_UITable_2.Enable = 'on';
                end

                if app.Cluster_BackgroundisdomainsedgesCheckBox.Value
                    app.CalculateconnectivityforalllabelsexceptLabel.Text = ['Calculate connectivity for all labels except: '  num2str(app.Cluster_complimentarylabelEditField.Value)];
                    app.Cluster_OuptutdescriptionLabel_2.Text = 'Output: label 0: complementary volume, 1: connected clusters, 2: isolated clusters, 3: unknown clusters';
                else
                    app.CalculateconnectivityforalllabelsexceptLabel.Text = ['Calculate connectivity for all labels except: ' num2str(app.Clusters_Background_EditField.Value) ' and ' num2str(app.Cluster_complimentarylabelEditField.Value)];
                    app.Cluster_OuptutdescriptionLabel_2.Text = 'Output: label 0: background, 1: complementary volume, 2: connected clusters, 3: isolated clusters, 4: unknown clusters';
                end

            end


        end

        % Button pushed function: LabelConnectivity_DoButton
        function LabelConnectivity_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.definition = app.Connectivity_DefintionDropDown.Value;
            p.anisotropy_musttouch = app.ClustersmusttouchDropDown.Value;
            p.anisotropy_alongaxe = app.alongaxeDropDown.Value;
            p.custom_mustverify = app.CustomruleclustersmustverifyDropDown.Value;
            p.custom_rules = cell2mat(app.Custom_Connectivity_UITable_2.Data(:,2:end));

            p.backgroundisdomainedges = app.Cluster_BackgroundisdomainsedgesCheckBox.Value;
            p.complementarylabel = app.Cluster_complimentarylabelEditField.Value;
            p.backgroundlabel = app.Clusters_Background_EditField.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_3.Text = ['Please wait... Action: calculate label connectivity (' p.definition ') on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);

                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_calculateconnectivity2(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_calculateconnectivity2';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = ['label connectivity (' p.definition ')'];
                app.par = ['Complementary label is ' num2str(p.complementarylabel)];
                if p.backgroundisdomainedges
                    app.par = [app.par '. Background is domain edges'];               
                else
                    app.par = [app.par '. Background label is ' num2str(p.backgroundlabel)];               
                end

                if strcmp(p.definition,'Anisotropic')
                    app.par = [app.par '. Clusters must touch ' p.anisotropy_musttouch ' along axe ' p.anisotropy_alongaxe];
                elseif strcmp(p.definition,'Custom')
                    app.par = [app.par '. Clusters must verify ' p.custom_mustverify ', touch first slice: ' num2str(p.custom_rules(1,:)) ', touch last slice: ' num2str(p.custom_rules(2,:))];
                end

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
            end
            app.Connectivity_StatutIdleLabel_3.Text = 'Finished!';
        end

        % Button pushed function: EditmacroadvancedusersButton
        function EditmacroadvancedusersButtonPushed(app, event)
            Edit_segmentationmacro(app.NomacroloadedLabel.Text, app.macro_act, app.macro_cal)
        end

        % Value changed function: ClustersmusttouchDropDown
        function ClustersmusttouchDropDownValueChanged(app, event)
            value = app.ClustersmusttouchDropDown.Value;
            if strcmp(value,'first slice')
                app.Image_connectivity_definition.ImageSource = 'Connectivity_unidrirectional_first.png';

            elseif strcmp(value,'last slice')
                app.Image_connectivity_definition.ImageSource = 'Connectivity_unidrirectional_last.png';

            elseif strcmp(value,'both opposite slices')
                app.Image_connectivity_definition.ImageSource = 'Connectivity_bidrirectional.png';

            end


            app.CalculateconnectivityforalllabelsexceptLabel.Text = ['Calculate connectivity for all labels except: ' num2str(app.Clusters_Background_EditField.Value) ' and ' num2str(app.Cluster_complimentarylabelEditField.Value)];
            app.Cluster_OuptutdescriptionLabel_2.Text = 'Output: label 0: background, 1: complementary volume, 2: main (largest) cluster, 3: isolated clusters, 4: unknown clusters';

        end

        % Value changed function: SegmentingbypartsCheckBox
        function SegmentingbypartsCheckBoxValueChanged(app, event)
            value = app.SegmentingbypartsCheckBox.Value;
            if value
                app.NumberofpartsDropDown.Enable = 'on';
                app.NumberofpartsDropDownValueChanged;
                app.OverlappinglengthDropDown.Enable = 'on';
                app.OverlappinglengthDropDownValueChanged;               
            else
                app.NumberofpartsDropDown.Enable = 'off';
                app.NumberofpartsEditField.Enable = 'off';
                app.ByParts_TargetnumberofvoxelinmillionsEditField.Enable = 'off';
                app.OverlappinglengthDropDown.Enable = 'off';
                app.OverlappingpartlengthEditField.Enable = 'off';
            end            
        end

        % Value changed function: NumberofpartsEditField
        function NumberofpartsEditFieldValueChanged(app, event)
            value = app.NumberofpartsEditField.Value;
            app.NumberofpartsEditField.Tooltip = ['Number of subvolumes to segment and then re-combine: ' num2str(value) ' independants + ' num2str(value-1) ' overlappings'];
        end

        % Button pushed function: 
        % CalculateandplotparticleconnectivityButton
        function CalculateandplotparticleconnectivityButtonPushed(app, event)
            M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
            
            p.filename = char(app.SelectactivefileDropDown.Items(app.active_f));
            p.sub =  [char(app.workflownames(app.active_f).names(app.active_w)) ', state#' num2str(app.active_s,'%i') ': ' char(app.file(app.active_f).workflow(app.active_w).statetable(app.active_s,2))];
            p.filename = strrep(p.filename,'_',' ');
            p.sub = strrep(p.sub,'_',' ');
            p.fontname = app.fontname;            
            p.voxelsize = app.CalcSeg_VoxelsizeEditField.Value;
            p.voxelunit = app.CalcSeg_UnitEditField.Value;  

            if ~app.Macro_AddacalculationButton.Value
                app.CalcSeg_StatutIdleLabel.Text = ['Please wait... Calculation: particle connectivity on file: ' char(app.SelectactivefileDropDown.Items(app.active_f))];
                pause(0.1);
            end            

            if ~app.Macro_AddacalculationButton.Value
                fct_particleconnectivity(M,p);
            else
                app.macro_fct = 'fct_particleconnectivity';
                app.macro_p = p;
            end
            
            if ~app.Macro_AddacalculationButton.Value
                app.CalcSeg_StatutIdleLabel.Text = 'Finished!';
            else
                app.CalcSeg_StatutIdleLabel.Text = 'Calculation saved in macro. Add another calculation or go back to the macro tab.';
                app.macro_fctname = 'fct_particleconnectivity';
                app.macro_add_calculation;
            end    
        end

        % Value changed function: PreerosionCheckBox
        function PreerosionCheckBoxValueChanged(app, event)
            value = app.PreerosionCheckBox.Value;
            app.InstanceSeg_ErosiondistanceEditField.Enable = value;
            app.InstanceSeg_ShowerosionCheckBox.Enable = value;
            if ~value
                app.InstanceSeg_ShowerosionCheckBox.Value = false;
            end

            downscaling = app.downscalingupscalingschemeCheckBox.Value;
            if value && downscaling
                app.InstanceSeg_OrderDropDown.Enable = 'on';
            else
                app.InstanceSeg_OrderDropDown.Enable = 'off';
            end               
        end

        % Value changed function: downscalingupscalingschemeCheckBox
        function downscalingupscalingschemeCheckBoxValueChanged(app, event)
            value = app.downscalingupscalingschemeCheckBox.Value;
            app.DownscalingfactorDropDown.Enable = value;
            app.CropvolumesifneededandDropDown.Enable = value;
            app.InstanceSeg_ShowDownscalingCheckBox.Enable = value;
            if value
                app.DownscalingfactorDropDownValueChanged;                
            else
                app.InstanceSeg_DownscalingfactorEditField.Enable = 'off';
                app.Downscaling_TargetnumberofvoxelinmillionsEditField.Enable = 'off';                
            end
            app.PreerosionCheckBoxValueChanged;
        end

        % Value changed function: NumberofpartsDropDown
        function NumberofpartsDropDownValueChanged(app, event)
            value = app.NumberofpartsDropDown.Value;
            if strcmp(value,'Manual')
                app.NumberofpartsEditField.Enable = 'on';
                app.ByParts_TargetnumberofvoxelinmillionsEditField.Enable = 'off';
            elseif strcmp(value,'Auto')
                app.NumberofpartsEditField.Enable = 'off';
                app.ByParts_TargetnumberofvoxelinmillionsEditField.Enable = 'on';
            end            
        end

        % Value changed function: DownscalingfactorDropDown
        function DownscalingfactorDropDownValueChanged(app, event)
            value = app.DownscalingfactorDropDown.Value;
            if strcmp(value,'Manual')
                app.InstanceSeg_DownscalingfactorEditField.Enable = 'on';
                app.Downscaling_TargetnumberofvoxelinmillionsEditField.Enable = 'off';
            elseif strcmp(value,'Auto') || strcmp(value,'Auto (and round)')
                app.InstanceSeg_DownscalingfactorEditField.Enable = 'off';
                app.Downscaling_TargetnumberofvoxelinmillionsEditField.Enable = 'on';
            end            
        end

        % Value changed function: OverlappinglengthDropDown
        function OverlappinglengthDropDownValueChanged(app, event)
            value = app.OverlappinglengthDropDown.Value;
            if strcmp(value,'Same as independant parts')
                app.OverlappingpartlengthEditField.Enable = 'off';
            elseif strcmp(value,'Manual')
                app.OverlappingpartlengthEditField.Enable = 'on';
            end            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            winopen('SegmentationByParts.png');
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
                winopen('Local_erosion_example2.png');
        end

        % Value changed function: ShowonlystateswithacustomnameCheckBox
        function ShowonlystateswithacustomnameCheckBoxValueChanged(app, event)
            value = app.ShowonlystateswithacustomnameCheckBox.Value;
            app.NumberofrowsEditField.Enable = value;
            app.Mosaic_FromworkflowEditField.Enable = ~value;
            app.Mosaic_toworkflowEditField.Enable = ~value;  
        end

        % Value changed function: LocalDilation_ApplyDropDown
        function LocalDilation_ApplyDropDownValueChanged(app, event)
            value = app.LocalDilation_ApplyDropDown.Value;
            if strcmp(value,'per cluster') || strcmp(value,'per label')
                app.Localdilation_ShowdiameteronlyforvisualizationCheckBox.Value = false;
                app.Localdilation_ShowdiameteronlyforvisualizationCheckBox.Enable = 'off';
                app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Value = false;
                app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Enable = 'off';   
            else
                app.Localdilation_ShowdiameteronlyforvisualizationCheckBox.Enable = 'on';
                app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Enable = 'on';  
            end
        end

        % Value changed function: ShowlabelsegmentedimageonlyCheckBox
        function ShowlabelsegmentedimageonlyCheckBoxValueChanged(app, event)
            if app.ShowlabelsegmentedimageonlyCheckBox.Value
                app.LabelsizeEditField.Enable = 'on';
                app.LabelcolorColorPicker.Enable = 'on';
            else
                app.LabelsizeEditField.Enable = 'off';
                app.LabelcolorColorPicker.Enable = 'off';
            end
            app.Update_ROI_view;            
        end

        % Callback function: LabelcolorColorPicker, LabelsizeEditField
        function LabelsizeEditFieldValueChanged(app, event)
            app.Update_ROI_view;  
        end

        % Value changed function: VoidfillingmethodDropDown
        function VoidfillingmethodDropDownValueChanged(app, event)
            value = app.VoidfillingmethodDropDown.Value;
            if strcmp(value,'Sphere-based local dilation')
                app.MergeSLSDiameterEditField.Enable = 'on';
            else
                app.MergeSLSDiameterEditField.Enable = 'off';
            end
        end

        % Value changed function: ListoflabelstomergeEditField
        function ListoflabelstomergeEditFieldValueChanged(app, event)
            value = app.ListoflabelstomergeEditField.Value;
            previousvalue = event.PreviousValue;
            labels = str2num(value);
            n=length(labels);
            if ~isempty(labels) && sum(~isnan(labels))==n && sum(labels>0)==n && n==length(unique(labels)) && sum(labels==round(labels))==n
                foo=1;
            else
                app.ListoflabelstomergeEditField.Value = previousvalue;
            end            
        end

        % Button pushed function: MergePulverizedParts_DoButton
        function MergePulverizedParts_DoButtonPushed(app, event)
            [fileA, fileB] = app.Check_synchronicity;
            if isempty(fileA)
                return
            end               

            % Parameters
            p.parts = str2num(app.ListoflabelstomergeEditField.Value);
            p.method = app.VoidfillingmethodDropDown.Value;
            p.diameterthreshold = app.MergeSLSDiameterEditField.Value;
            p.overwritte = app.FillingoverwritteotherlabelsCheckBox.Value;

            count_file = 0;
            for kfile = fileA:1:fileB
                count_file = count_file+1;
                if kfile>fileA
                    app.active_s = app.active_s-1;
                end

                app.Connectivity_StatutIdleLabel_4.Text = ['Please wait... Action: connectivity (assemble): ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                pause(0.1);
                tic
                app.active_f = kfile;
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                [M,newtype,~] = fct_assemble_labels(M,p);
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_assemble_labels';
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = newtype;
                app.act = 'Connectivity (assemble)';
                if strcmp(p.method,'Sphere-based local dilation')
                    app.par = ['Labels: ' app.ListoflabelstomergeEditField.Value ', filling method: ' p.method ', diameter threshold: ' num2str(p.diameterthreshold,'%i') ', overwritte: '];
                else
                    app.par = ['Labels: ' app.ListoflabelstomergeEditField.Value ', filling method: ' p.method ', overwritte: '];
                end
                if p.overwritte
                    app.par = [app.par 'yes'];
                else
                    app.par = [app.par 'no'];
                end

                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                app.t = toc;

                app.Update_newactive_state(true);
                %app.Update_tables_dimensions;
            end
            app.Connectivity_StatutIdleLabel_4.Text = 'Finished!';            
        end

        % Button pushed function: Superpixel_DoButton
        function Superpixel_DoButtonPushed(app, event)
            % Parameters
            p.NumChoice = app.SuperpixelNumChoice_EditField.Value;
            p.num = app.SuperpixelNum_EditField.Value;
            p.NumIterations = app.SuperPixel_NumberofiterationsEditField.Value;
            p.Refine = app.SuperPixel_RefineCompactnessCheckBox.Value;
            p.Compactness_InitialValue_choice = app.InitialcompactnessDropDown.Value;
            p.Compactness_InitialValue = app.InitialcompactnessEditField.Value;
            
            if app.GLFilter_PreviewCheckBox.Value
                M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                sz = size(M);
                if length(sz)==3
                    M=M(:,:,round(sz(3)/2));
                end
                [Msuperpixel,~,numLabels] = fct_superpixels(M,p);
                Fig = figure;
                Fig.Color = 'w';
                Fig.Name = 'Superpixels preview on middle slice';
                montage({M,Msuperpixel});
                title(['Number of superpixels: ' num2str(numLabels,'%i')]);
                clim([min(min(M)) max(max(M))]);
            else

                [fileA, fileB] = app.Check_synchronicity;
                if isempty(fileA)
                    return
                end

                count_file = 0;
                for kfile = fileA:1:fileB
                    count_file = count_file+1;
                    if kfile>fileA
                        app.active_s = app.active_s-1;
                    end

                    app.FilterGL_StatutIdleLabel.Text = ['Please wait... Action: Filtering (superpixels) on file: ' num2str(count_file,'%i') '/' num2str(fileB-fileA+1,'%i') ': ' char(app.SelectactivefileDropDown.Items(kfile))];
                    pause(0.1);

                    tic
                    app.active_f = kfile;
                    M = app.file(app.active_f).workflow(app.active_w).state(app.active_s).M;
                    [M,~,numLabels] = fct_superpixels(M,p);
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).p = p;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).fct = 'fct_superpixels';
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).isfct = true;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).M = M;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).type = app.file(app.active_f).workflow(app.active_w).state(app.active_s).type;
                    app.act = 'Superpixels';

                    if strcmp(p.Compactness_InitialValue_choice,'Default')
                        app.par = ['Number of ' p.NumChoice ': ' num2str(p.num,'%i') ', number of iterations: ' num2str(p.NumIterations,'%i') ', initial compactness choice: ' p.Compactness_InitialValue_choice] ;
                    elseif strcmp(p.Compactness_InitialValue_choice,'Custom')
                        app.par = ['Number of ' p.NumChoice ': ' num2str(p.num,'%i') ', number of iterations: ' num2str(p.NumIterations,'%i') ', initial compactness choice: ' p.Compactness_InitialValue_choice ', and value:' num2str(p.Compactness_InitialValue,'%1.3f')] ;
                    end
                    if p.Refine
                        app.par = [app.par ', refine compactness.'];
                    else
                        app.par = [app.par ', do not refine compactness (constant).'];
                    end

                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).action = app.act;
                    app.file(app.active_f).workflow(app.active_w).state(app.active_s+1).par_verbose = app.par;
                    app.t = toc;

                    app.Update_newactive_state(true);
                end
                app.FilterGL_StatutIdleLabel.Text = ['Finished! Number of superpixels: ' num2str(numLabels,'%i')];
            end   
        end

        % Value changed function: InitialcompactnessDropDown
        function InitialcompactnessDropDownValueChanged(app, event)
            value = app.InitialcompactnessDropDown.Value;
            if strcmp(value,'Default')
                app.InitialcompactnessEditField.Enable = 'off';
            else
                app.InitialcompactnessEditField.Enable = 'on';
            end
        end

        % Value changed function: Size_RoundresultCheckBox_2
        function Size_RoundresultCheckBox_2ValueChanged(app, event)
            value = app.Size_RoundresultCheckBox_2.Value;
            if value
                app.CpsdEDM_roundnumberofdigits_EditField.Enable = 'on';
            else
                app.CpsdEDM_roundnumberofdigits_EditField.Enable = 'off';
            end
        end

        % Image clicked function: Image_cPSD_EDM
        function Image_cPSD_EDMClicked(app, event)
            winopen('cPSD_EDM.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImagesegmentationUIFigure = uifigure('Visible', 'off');
            app.ImagesegmentationUIFigure.Name = 'Image segmentation';
            app.ImagesegmentationUIFigure.Icon = 'Icon_ROI.png';
            app.MainTabGroup = uitabgroup(app.ImagesegmentationUIFigure);
            app.ImportTab = uitab(app.MainTabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.ImportLabel = uilabel(app.GridLayout);
            app.ImportLabel.Text = '1) Import grey-level or segmented 2D images or 3D volumes (single channel).';
            app.NodataloadedLabel = uilabel(app.GridLayout);
            app.NodataloadedLabel.Text = 'No data loaded';
            app.PathLabel = uilabel(app.GridLayout);
            app.PathLabel.Text = 'Path:';
            app.Label_path = uilabel(app.GridLayout);
            app.Label_path.Text = 'n/a';
            app.ImportLabel_2 = uilabel(app.GridLayout);
            app.ImportLabel_2.Text = '2) Choose your save folder.';
            app.BydefaultthesavingfolderisthesameastheimportfolderLabel = uilabel(app.GridLayout);
            app.BydefaultthesavingfolderisthesameastheimportfolderLabel.Text = 'By default, the saving folder is the same as the import folder.';
            app.SelectSavefolder_Button = uibutton(app.GridLayout, 'push');
            app.SelectSavefolder_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectSavefolder_ButtonPushed, true);
            app.SelectSavefolder_Button.Enable = 'off';
            app.SelectSavefolder_Button.Text = 'Click to select save folder';
            app.Savefolder_Label = uilabel(app.GridLayout);
            app.Savefolder_Label.Text = 'None selected';
            app.Importtif_folder_Button = uibutton(app.GridLayout, 'push');
            app.Importtif_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @Importtif_folder_ButtonPushed, true);
            app.Importtif_folder_Button.Tooltip = {'You can import one or several files. All files must be located in the same folder.'};
            app.Importtif_folder_Button.Text = 'Click to import one or several files';
            app.Donotloadallfiles_CheckBox = uicheckbox(app.GridLayout);
            app.Donotloadallfiles_CheckBox.Tooltip = {'Select before importing. To change this setting, you need   to restart the app.'};
            app.Donotloadallfiles_CheckBox.Text = 'Do not load all files at once (select only if you want to apply a macro sequentially to all files).';
            app.Files_UITable = uitable(app.GridLayout);
            app.Files_UITable.ColumnName = {'File#'; 'Filename'; 'Dimension'; 'Number of voxels'};
            app.Files_UITable.RowName = {};
            app.SelectyouroptionsLabel = uilabel(app.GridLayout);
            app.SelectyouroptionsLabel.Text = 'Select your options:';
            app.ThenimportfilesLabel = uilabel(app.GridLayout);
            app.ThenimportfilesLabel.Text = 'Then import files:';
            app.DatatypeDropDownLabel = uilabel(app.GridLayout);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.GridLayout);
            app.DatatypeDropDown.Items = {'Grey level', 'Segmented (phase)', 'Segmented (instance)'};
            app.DatatypeDropDown.Tooltip = {'If you are importing several files with different data type then select "grey level". You can them apply the correct format in Actions/Format.'};
            app.DatatypeDropDown.Value = 'Grey level';
            app.ImageswillbesavedinSavefolderFilenameWorkflownameLabel = uilabel(app.GridLayout);
            app.ImageswillbesavedinSavefolderFilenameWorkflownameLabel.Text = {'Images will be saved in Save folder / File name / Workflow name /'; 'Parameters (as shown in the table of ''States'' tab) will be also saved.'; 'You can save image(s) and parameter(s) in the ''States'' tab.'; ''; 'Macros will be also saved in this save folder.'};
            app.StatesTab = uitab(app.MainTabGroup);
            app.StatesTab.Title = 'States';
            app.GridLayout_States = uigridlayout(app.StatesTab);
            app.Label_2 = uilabel(app.GridLayout_States);
            app.Label_2.Text = {'By default, only one workflow is used. First workflow always starts with the import step. In the active file, you can create new wokflows initialized with any state from any other pre-existing workflow of the active file.'; 'You can later combine states from different workflows and files in the "combinations" tab.'; ''; 'Any action modifying the active state creates a new state in the active workflow of the active file and becomes the new active state. You can select in the table below the active file, then the active worflow and then the'; 'active state. All actions and calculations are performed on the active state. If you select an active state that is not the last in the active workflow and then perfom any modifying actions, all the subsequent states from'; 'the active workflow will be removed and replaced with the new active state. If you want to avoid such overwritte, you can create a new workflow instead.'; ''; 'By default, all 50 last states for each workflow are kept in memory (see "Memory depth" parameter). You can also specify some states to be always kept in memory. Only states in memory can be selected as'; 'active state or used in the "Combinations" or "Comparisons" tabs. Any operation performed in the "Combinations" tab creates a new workflow (and if combined from two states from two different files then in a new file).'; ''; 'Additionally, the table below stores the parameters used for each modifying actions and thus also serves the purpose of an history log.'};
            app.SelectactiveworkflowDropDownLabel = uilabel(app.GridLayout_States);
            app.SelectactiveworkflowDropDownLabel.Text = 'Select active workflow';
            app.SelectactiveworkflowDropDown = uidropdown(app.GridLayout_States);
            app.SelectactiveworkflowDropDown.Items = {};
            app.SelectactiveworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectactiveworkflowDropDownValueChanged, true);
            app.SelectactiveworkflowDropDown.Value = {};
            app.CreateanewworkflowButton = uibutton(app.GridLayout_States, 'push');
            app.CreateanewworkflowButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanewworkflowButtonPushed, true);
            app.CreateanewworkflowButton.Text = 'Create a new workflow';
            app.RenamecurrentworkflowoptionalEditFieldLabel = uilabel(app.GridLayout_States);
            app.RenamecurrentworkflowoptionalEditFieldLabel.Text = 'Rename  current workflow (optional)';
            app.RenamecurrentworkflowoptionalEditField = uieditfield(app.GridLayout_States, 'text');
            app.RenamecurrentworkflowoptionalEditField.ValueChangedFcn = createCallbackFcn(app, @RenamecurrentworkflowoptionalEditFieldValueChanged, true);
            app.FromexistingworkflowDropDownLabel = uilabel(app.GridLayout_States);
            app.FromexistingworkflowDropDownLabel.Text = 'From existing workflow';
            app.FromexistingworkflowDropDown = uidropdown(app.GridLayout_States);
            app.FromexistingworkflowDropDown.Items = {};
            app.FromexistingworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @FromexistingworkflowDropDownValueChanged, true);
            app.FromexistingworkflowDropDown.Value = {};
            app.FromstateLabel = uilabel(app.GridLayout_States);
            app.FromstateLabel.Text = 'From state#';
            app.FromstateDropDown = uidropdown(app.GridLayout_States);
            app.FromstateDropDown.Items = {};
            app.FromstateDropDown.Value = {};
            app.ImagemosaicofallstatesinmemoryButton = uibutton(app.GridLayout_States, 'push');
            app.ImagemosaicofallstatesinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImagemosaicofallstatesinmemoryButtonPushed, true);
            app.ImagemosaicofallstatesinmemoryButton.Tooltip = {'Create image mosaic of all states in memory. Slice in the middle of the selected axis are shown.'};
            app.ImagemosaicofallstatesinmemoryButton.Text = 'Image mosaic of all states in memory';
            app.States_UITable = uitable(app.GridLayout_States);
            app.States_UITable.ColumnName = {'State#'; 'Action'; 'Parameters'; 'Custom name'; 'Marked for save'; 'Comment'; 'Elapsed time (s)'; 'Type'; 'Range'; 'Volume fractions'; 'Number of instances'; 'Size'; 'In memory'; 'Keep in memory'; 'Active state'};
            app.States_UITable.RowName = {};
            app.States_UITable.ColumnEditable = [false false false true true true false false false false false false false true true];
            app.States_UITable.CellEditCallback = createCallbackFcn(app, @States_UITableCellEdit, true);
            app.States_UITable.DoubleClickedFcn = createCallbackFcn(app, @States_UITableDoubleClicked, true);
            app.States_UITable.ClickedFcn = createCallbackFcn(app, @States_UITableClicked, true);
            app.States_UITable.Tooltip = {'Double click on a state in memory to visualize it.'; 'Green: active state.'; 'Blue: state in memory.'; 'Grey: state not in memory.'; 'Custom name is used for figure and filename.'};
            app.MemorydepthEditFieldLabel = uilabel(app.GridLayout_States);
            app.MemorydepthEditFieldLabel.Text = 'Memory depth';
            app.MemorydepthEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.MemorydepthEditField.Limits = [1 Inf];
            app.MemorydepthEditField.RoundFractionalValues = 'on';
            app.MemorydepthEditField.ValueChangedFcn = createCallbackFcn(app, @MemorydepthEditField_2ValueChanged, true);
            app.MemorydepthEditField.Value = 50;
            app.SaveButton = uibutton(app.GridLayout_States, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SelectactivefileDropDownLabel = uilabel(app.GridLayout_States);
            app.SelectactivefileDropDownLabel.Text = 'Select active file';
            app.SelectactivefileDropDown = uidropdown(app.GridLayout_States);
            app.SelectactivefileDropDown.Items = {};
            app.SelectactivefileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectactivefileDropDownValueChanged, true);
            app.SelectactivefileDropDown.Value = {};
            app.Delete_WorkflowButton = uibutton(app.GridLayout_States, 'push');
            app.Delete_WorkflowButton.ButtonPushedFcn = createCallbackFcn(app, @Delete_WorkflowButtonPushed, true);
            app.Delete_WorkflowButton.Text = 'Delete';
            app.DoubleclickinthetabletovisualizeavolumeLabel = uilabel(app.GridLayout_States);
            app.DoubleclickinthetabletovisualizeavolumeLabel.Text = 'Double click in the table to visualize a volume';
            app.FontsizeEditFieldLabel = uilabel(app.GridLayout_States);
            app.FontsizeEditFieldLabel.Text = 'Font size';
            app.Title_FontsizeEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.Title_FontsizeEditField.Limits = [1 Inf];
            app.Title_FontsizeEditField.RoundFractionalValues = 'on';
            app.Title_FontsizeEditField.Tooltip = {'Title'};
            app.Title_FontsizeEditField.Value = 12;
            app.Subtitle_FontsizeEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.Subtitle_FontsizeEditField.Limits = [1 Inf];
            app.Subtitle_FontsizeEditField.RoundFractionalValues = 'on';
            app.Subtitle_FontsizeEditField.Tooltip = {'Subtitle'};
            app.Subtitle_FontsizeEditField.Value = 10;
            app.Workflow_FontsizeEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.Workflow_FontsizeEditField.Limits = [1 Inf];
            app.Workflow_FontsizeEditField.RoundFractionalValues = 'on';
            app.Workflow_FontsizeEditField.Tooltip = {'Workflow name'};
            app.Workflow_FontsizeEditField.Value = 12;
            app.Savechoice_DropDown = uidropdown(app.GridLayout_States);
            app.Savechoice_DropDown.Items = {'Save active state', 'Save all states from active workflow', 'Save all states from active file', 'Save only final state for each file', 'Save all marked states', 'Save all states from all files'};
            app.Savechoice_DropDown.Value = 'Save active state';
            app.toEditFieldLabel = uilabel(app.GridLayout_States);
            app.toEditFieldLabel.Text = 'to';
            app.Mosaic_toworkflowEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.Mosaic_toworkflowEditField.Limits = [1 Inf];
            app.Mosaic_toworkflowEditField.RoundFractionalValues = 'on';
            app.Mosaic_toworkflowEditField.ValueChangedFcn = createCallbackFcn(app, @Mosaic_FromworkflowEditFieldValueChanged, true);
            app.Mosaic_toworkflowEditField.Value = 1;
            app.FromworkflowEditField_2Label = uilabel(app.GridLayout_States);
            app.FromworkflowEditField_2Label.Text = 'From workflow';
            app.Mosaic_FromworkflowEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.Mosaic_FromworkflowEditField.Limits = [1 Inf];
            app.Mosaic_FromworkflowEditField.RoundFractionalValues = 'on';
            app.Mosaic_FromworkflowEditField.ValueChangedFcn = createCallbackFcn(app, @Mosaic_FromworkflowEditFieldValueChanged, true);
            app.Mosaic_FromworkflowEditField.Value = 1;
            app.InsertfilenameincustomnameCheckBox = uicheckbox(app.GridLayout_States);
            app.InsertfilenameincustomnameCheckBox.Tooltip = {'If checked, and if Custome nane is not empty, then saved filenames will be <filename>_<custom name>. Option will be used in macro as well.'};
            app.InsertfilenameincustomnameCheckBox.Text = 'Insert file name in custom name';
            app.MosaicaxisDropDownLabel = uilabel(app.GridLayout_States);
            app.MosaicaxisDropDownLabel.Text = 'Mosaic axis';
            app.MosaicaxisDropDown = uidropdown(app.GridLayout_States);
            app.MosaicaxisDropDown.Items = {'1', '2', '3'};
            app.MosaicaxisDropDown.Value = '3';
            app.ShowonlystateswithacustomnameCheckBox = uicheckbox(app.GridLayout_States);
            app.ShowonlystateswithacustomnameCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowonlystateswithacustomnameCheckBoxValueChanged, true);
            app.ShowonlystateswithacustomnameCheckBox.Text = 'Show only states with a custom name';
            app.NumberofrowsEditFieldLabel = uilabel(app.GridLayout_States);
            app.NumberofrowsEditFieldLabel.Text = 'Number of rows';
            app.NumberofrowsEditField = uieditfield(app.GridLayout_States, 'numeric');
            app.NumberofrowsEditField.Limits = [1 Inf];
            app.NumberofrowsEditField.RoundFractionalValues = 'on';
            app.NumberofrowsEditField.Value = 1;
            app.ActionsTab = uitab(app.MainTabGroup);
            app.ActionsTab.Tooltip = {''};
            app.ActionsTab.Title = 'Actions';
            app.TabGroup2 = uitabgroup(app.ActionsTab);
            app.RegionofInterestTab = uitab(app.TabGroup2);
            app.RegionofInterestTab.Title = 'Region of Interest';
            app.RegionofInterestTab.ButtonDownFcn = createCallbackFcn(app, @RegionofInterestTabButtonDown, true);
            app.ROI_GridLayout = uigridlayout(app.RegionofInterestTab);
            app.CroppingLabel = uilabel(app.ROI_GridLayout);
            app.CroppingLabel.Text = 'Cropping';
            app.ROI_crop_UITable = uitable(app.ROI_GridLayout);
            app.ROI_crop_UITable.ColumnName = {'Axe'; 'Voxels'; 'Start'; 'End'; 'Length'};
            app.ROI_crop_UITable.RowName = {};
            app.ROI_crop_UITable.ColumnEditable = [false false true true false];
            app.ROI_crop_UITable.CellEditCallback = createCallbackFcn(app, @ROI_crop_UITableCellEdit, true);
            app.ROI_crop_UITable.Tooltip = {'Modigy "Start" and "End" columns in absolute number of voxels (or relativley from 0 to 1 if "relative dimension" is checked).'};
            app.ROI_crop_DoButton = uibutton(app.ROI_GridLayout, 'push');
            app.ROI_crop_DoButton.ButtonPushedFcn = createCallbackFcn(app, @ROI_crop_DoButtonPushed, true);
            app.ROI_crop_DoButton.Text = 'Do';
            app.RelativedimensionCheckBox = uicheckbox(app.ROI_GridLayout);
            app.RelativedimensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelativedimensionCheckBoxValueChanged, true);
            app.RelativedimensionCheckBox.Text = 'Relative dimension';
            app.ROI_StatutIdleLabel = uilabel(app.ROI_GridLayout);
            app.ROI_StatutIdleLabel.Text = 'Statut: Idle';
            app.AutoreshapeCheckBox = uicheckbox(app.ROI_GridLayout);
            app.AutoreshapeCheckBox.Tooltip = {'E.g.: cropping from (50,100,75) to (50,1,75) will result in a 2D array (50,75)'};
            app.AutoreshapeCheckBox.Text = 'Auto reshape';
            app.AutoreshapeCheckBox.Value = true;
            app.DownscalingfactorEditFieldLabel = uilabel(app.ROI_GridLayout);
            app.DownscalingfactorEditFieldLabel.Text = 'Downscaling factor';
            app.DownscalingfactorEditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.DownscalingfactorEditField.Limits = [1 Inf];
            app.DownscalingfactorEditField.Value = 1;
            app.FindparametersButton = uibutton(app.ROI_GridLayout, 'push');
            app.FindparametersButton.ButtonPushedFcn = createCallbackFcn(app, @FindparametersButtonPushed, true);
            app.FindparametersButton.Tooltip = {'This will provide you with cropping parameters so that if you later downscale your image with the downscaling factor set on the left edit box, the dimensions will still be integer.'};
            app.FindparametersButton.Text = 'Find parameters ';
            app.Autosetcropping_downscalingCheckBox = uicheckbox(app.ROI_GridLayout);
            app.Autosetcropping_downscalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @Autosetcropping_downscalingCheckBoxValueChanged, true);
            app.Autosetcropping_downscalingCheckBox.Tooltip = {'The cropping parameters will be autoselected so that a downscaling with the factor set above will result in integer dimensions. Useful for macros.'};
            app.Autosetcropping_downscalingCheckBox.Text = 'Autoset parameters';
            app.FliporswapaxisLabel = uilabel(app.ROI_GridLayout);
            app.FliporswapaxisLabel.Text = 'Flip or swap axis';
            app.Flipswap_DoButton = uibutton(app.ROI_GridLayout, 'push');
            app.Flipswap_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Flipswap_DoButtonPushed, true);
            app.Flipswap_DoButton.Text = 'Do';
            app.Flipswap_DropDown = uidropdown(app.ROI_GridLayout);
            app.Flipswap_DropDown.Items = {};
            app.Flipswap_DropDown.Value = {};
            app.RotationLabel = uilabel(app.ROI_GridLayout);
            app.RotationLabel.Text = 'Rotation';
            app.AxisofrotationDropDownLabel = uilabel(app.ROI_GridLayout);
            app.AxisofrotationDropDownLabel.Text = 'Axis of rotation';
            app.AxisofrotationDropDown = uidropdown(app.ROI_GridLayout);
            app.AxisofrotationDropDown.Items = {};
            app.AxisofrotationDropDown.Value = {};
            app.Rotation_DoButton = uibutton(app.ROI_GridLayout, 'push');
            app.Rotation_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Rotation_DoButtonPushed, true);
            app.Rotation_DoButton.Text = 'Do';
            app.Apply1beforerotationCheckBox = uicheckbox(app.ROI_GridLayout);
            app.Apply1beforerotationCheckBox.Tooltip = {'So that additional background induced by the rotation will be identified with label 0.'};
            app.Apply1beforerotationCheckBox.Text = 'Apply +1 before rotation';
            app.AngledegreesSpinnerLabel = uilabel(app.ROI_GridLayout);
            app.AngledegreesSpinnerLabel.Text = 'Angle (degrees)';
            app.AngledegreesSpinner = uispinner(app.ROI_GridLayout);
            app.AngledegreesSpinner.Step = 0.5;
            app.AngledegreesSpinner.Limits = [-90 90];
            app.AngledegreesSpinner.ValueChangedFcn = createCallbackFcn(app, @AngledegreesSpinnerValueChanged, true);
            app.SlicevisualizationPanel = uipanel(app.ROI_GridLayout);
            app.SlicevisualizationPanel.Title = 'Slice visualization';
            app.GridLayout13 = uigridlayout(app.SlicevisualizationPanel);
            app.ROI_UIAxes = uiaxes(app.GridLayout13);
            app.ROI_visu_SliceSlider = uislider(app.GridLayout13);
            app.ROI_visu_SliceSlider.ValueChangedFcn = createCallbackFcn(app, @ROI_visu_SliceSliderValueChanged, true);
            app.ROI_visu_SliceSlider.Value = 50;
            app.AxisDropDownLabel = uilabel(app.GridLayout13);
            app.AxisDropDownLabel.Text = 'Axis';
            app.ROI_visu_AxisDropDown = uidropdown(app.GridLayout13);
            app.ROI_visu_AxisDropDown.Items = {};
            app.ROI_visu_AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_visu_AxisDropDownValueChanged, true);
            app.ROI_visu_AxisDropDown.Value = {};
            app.ColormapDropDownLabel = uilabel(app.GridLayout13);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ROI_ColormapDropDown = uidropdown(app.GridLayout13);
            app.ROI_ColormapDropDown.Items = {};
            app.ROI_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_ColormapDropDownValueChanged, true);
            app.ROI_ColormapDropDown.Value = {};
            app.SliceEditFieldLabel = uilabel(app.GridLayout13);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.ROI_SliceEditField = uieditfield(app.GridLayout13, 'numeric');
            app.ROI_SliceEditField.RoundFractionalValues = 'on';
            app.ROI_SliceEditField.ValueChangedFcn = createCallbackFcn(app, @ROI_SliceEditFieldValueChanged, true);
            app.ROI_minus_Button = uibutton(app.GridLayout13, 'push');
            app.ROI_minus_Button.ButtonPushedFcn = createCallbackFcn(app, @ROI_minus_ButtonPushed, true);
            app.ROI_minus_Button.Text = '-';
            app.ROI_plus_Button = uibutton(app.GridLayout13, 'push');
            app.ROI_plus_Button.ButtonPushedFcn = createCallbackFcn(app, @ROI_plus_ButtonPushed, true);
            app.ROI_plus_Button.Text = '+';
            app.ROI_StatePlusButton = uibutton(app.GridLayout13, 'push');
            app.ROI_StatePlusButton.ButtonPushedFcn = createCallbackFcn(app, @ROI_StatePlusButtonPushed, true);
            app.ROI_StatePlusButton.Text = '+';
            app.ROI_StateMinusButton = uibutton(app.GridLayout13, 'push');
            app.ROI_StateMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ROI_StateMinusButtonPushed, true);
            app.ROI_StateMinusButton.Text = '-';
            app.ROI_StateLabel = uilabel(app.GridLayout13);
            app.ROI_StateLabel.Text = 'State #1';
            app.ShowlabelsegmentedimageonlyCheckBox = uicheckbox(app.GridLayout13);
            app.ShowlabelsegmentedimageonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlabelsegmentedimageonlyCheckBoxValueChanged, true);
            app.ShowlabelsegmentedimageonlyCheckBox.Text = 'Show label (segmented image only)';
            app.LabelsizeEditFieldLabel = uilabel(app.GridLayout13);
            app.LabelsizeEditFieldLabel.Text = 'Label size';
            app.LabelsizeEditField = uieditfield(app.GridLayout13, 'numeric');
            app.LabelsizeEditField.Limits = [1 Inf];
            app.LabelsizeEditField.RoundFractionalValues = 'on';
            app.LabelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @LabelsizeEditFieldValueChanged, true);
            app.LabelsizeEditField.Value = 15;
            app.LabelcolorColorPickerLabel = uilabel(app.GridLayout13);
            app.LabelcolorColorPickerLabel.Text = 'Label color';
            app.LabelcolorColorPicker = uicolorpicker(app.GridLayout13);
            app.LabelcolorColorPicker.Value = [0.8 0.8 0.8];
            app.LabelcolorColorPicker.ValueChangedFcn = createCallbackFcn(app, @LabelsizeEditFieldValueChanged, true);
            app.LargestROIwithoutbackgroundLabel = uilabel(app.ROI_GridLayout);
            app.LargestROIwithoutbackgroundLabel.Tooltip = {'- Cropping. For 2D image or 3D volume with same background along one axis: exact solution. Otherwise, approximated solution.'; '- Rotation: approximated solution (dichotomy approach).'};
            app.LargestROIwithoutbackgroundLabel.Text = 'Largest ROI without background';
            app.ROI_DropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_DropDown.Items = {'Only find parameters', 'Find and apply parameters'};
            app.ROI_DropDown.Value = 'Only find parameters';
            app.ROI_background_DoButton = uibutton(app.ROI_GridLayout, 'push');
            app.ROI_background_DoButton.ButtonPushedFcn = createCallbackFcn(app, @FindparametersforbackgroundButtonPushed, true);
            app.ROI_background_DoButton.Text = 'Do';
            app.SelectbackgroundDropDown_2Label_2 = uilabel(app.ROI_GridLayout);
            app.SelectbackgroundDropDown_2Label_2.Text = 'Select background';
            app.ROI_SelectbackgroundDropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_SelectbackgroundDropDown.Items = {'From label', 'From state'};
            app.ROI_SelectbackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_SelectbackgroundDropDownValueChanged, true);
            app.ROI_SelectbackgroundDropDown.Tooltip = {'You must identify the background first. You can identify background in different ways. For instance, in Actions/Channels you can calculate standard deviation or gradient magnitude and then threshold it to identify the background. If you apply a rotation, simply apply a +1 before rotation so that the label 0 will identify the (additional) background induced by the rotation. If you plan to create a macro, you should use "From label'''};
            app.ROI_SelectbackgroundDropDown.Value = 'From label';
            app.BackgroundlabelEditFieldLabel = uilabel(app.ROI_GridLayout);
            app.BackgroundlabelEditFieldLabel.Text = 'Background label';
            app.ROI_BackgroundlabelEditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_BackgroundlabelEditField.Limits = [0 Inf];
            app.ROI_BackgroundlabelEditField.RoundFractionalValues = 'on';
            app.FromfileDropDownLabel_6 = uilabel(app.ROI_GridLayout);
            app.FromfileDropDownLabel_6.Text = 'From file';
            app.ROI_FromfileDropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_FromfileDropDown.Items = {};
            app.ROI_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_FromfileDropDownValueChanged, true);
            app.ROI_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @ROI_FromfileDropDownValueChanged, true);
            app.ROI_FromfileDropDown.Value = {};
            app.FromworkflowDropDownLabel_6 = uilabel(app.ROI_GridLayout);
            app.FromworkflowDropDownLabel_6.Text = 'From workflow';
            app.ROI_FromworkflowDropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_FromworkflowDropDown.Items = {};
            app.ROI_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_FromworkflowDropDownValueChanged, true);
            app.ROI_FromworkflowDropDown.Value = {};
            app.FromstateDropDown_3Label_3 = uilabel(app.ROI_GridLayout);
            app.FromstateDropDown_3Label_3.Text = 'From state';
            app.ROI_FromstateDropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_FromstateDropDown.Items = {};
            app.ROI_FromstateDropDown.Value = {};
            app.Bufferaxe12and3Label = uilabel(app.ROI_GridLayout);
            app.Bufferaxe12and3Label.Tooltip = {'Crop additional pixels from the background to avoid edge effects'};
            app.Bufferaxe12and3Label.Text = 'Cropping buffer (axe 1, 2 and 3)';
            app.ROI_Buffer_axe1_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Buffer_axe1_EditField.Limits = [0 Inf];
            app.ROI_Buffer_axe1_EditField.RoundFractionalValues = 'on';
            app.ROI_Buffer_axe1_EditField.Tooltip = {'Crop additional pixels from the background to avoid edge effects'};
            app.ForLabel = uilabel(app.ROI_GridLayout);
            app.ForLabel.Text = 'For';
            app.ROI_ParametersDropDown = uidropdown(app.ROI_GridLayout);
            app.ROI_ParametersDropDown.Items = {'Cropping', 'Cropping and rotations'};
            app.ROI_ParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @ROI_ParametersDropDownValueChanged, true);
            app.ROI_ParametersDropDown.Value = 'Cropping';
            app.Rotationmaxdegaxe12and3Label = uilabel(app.ROI_GridLayout);
            app.Rotationmaxdegaxe12and3Label.Tooltip = {'Research algorithm will be bounded between +/- these angles.'};
            app.Rotationmaxdegaxe12and3Label.Text = 'Rotation max (deg., axe 1,2 and 3)';
            app.ROI_Rotationmaxdeg_x_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Rotationmaxdeg_x_EditField.Limits = [0 90];
            app.ROI_Rotationmaxdeg_x_EditField.Tooltip = {'Research algorithm will be bounded between +/- these angles.'};
            app.ROI_Rotationmaxdeg_x_EditField.Value = 25;
            app.ROI_Buffer_axe2_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Buffer_axe2_EditField.Limits = [0 Inf];
            app.ROI_Buffer_axe2_EditField.RoundFractionalValues = 'on';
            app.ROI_Buffer_axe2_EditField.Tooltip = {'Crop additional pixels from the background to avoid edge effects'};
            app.ROI_Buffer_axe3_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Buffer_axe3_EditField.Limits = [0 Inf];
            app.ROI_Buffer_axe3_EditField.RoundFractionalValues = 'on';
            app.ROI_Buffer_axe3_EditField.Tooltip = {'Crop additional pixels from the background to avoid edge effects'};
            app.ROI_Rotationmaxdeg_z_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Rotationmaxdeg_z_EditField.Limits = [0 90];
            app.ROI_Rotationmaxdeg_z_EditField.Tooltip = {'Research algorithm will be bounded between +/- these angles.'};
            app.ROI_Rotationmaxdeg_z_EditField.Value = 25;
            app.ROI_Rotationmaxdeg_y_EditField = uieditfield(app.ROI_GridLayout, 'numeric');
            app.ROI_Rotationmaxdeg_y_EditField.Limits = [0 90];
            app.ROI_Rotationmaxdeg_y_EditField.Tooltip = {'Research algorithm will be bounded between +/- these angles.'};
            app.ROI_Rotationmaxdeg_y_EditField.Value = 25;
            app.DeltaROIformacroCheckBox = uicheckbox(app.ROI_GridLayout);
            app.DeltaROIformacroCheckBox.ValueChangedFcn = createCallbackFcn(app, @DeltaROIformacroCheckBoxValueChanged, true);
            app.DeltaROIformacroCheckBox.Tooltip = {'E.g.: if you crop a 100 voxel length axis with Start = 10 and End = 95 and save it in a macro, volumes cropped with the macro will have Start = 10  and End = max dimension - 5. This can be useful for instance if you have edge effects with a given length.'};
            app.DeltaROIformacroCheckBox.Text = 'Delta ROI (for macro)';
            app.ScalingTab = uitab(app.TabGroup2);
            app.ScalingTab.Title = 'Scaling';
            app.Scaling_GridLayout = uigridlayout(app.ScalingTab);
            app.IsotropicscalingLabel = uilabel(app.Scaling_GridLayout);
            app.IsotropicscalingLabel.Text = 'Isotropic scaling';
            app.ScalingfactorEditFieldLabel = uilabel(app.Scaling_GridLayout);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.ScalingfactorEditField = uieditfield(app.Scaling_GridLayout, 'numeric');
            app.ScalingfactorEditField.Limits = [0 Inf];
            app.ScalingfactorEditField.Value = 1;
            app.upscaling1downscalingLabel = uilabel(app.Scaling_GridLayout);
            app.upscaling1downscalingLabel.Text = '>1: upscaling, <1: downscaling';
            app.Scaling_DoButton = uibutton(app.Scaling_GridLayout, 'push');
            app.Scaling_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Scaling_DoButtonPushed, true);
            app.Scaling_DoButton.Text = 'Do';
            app.Scaling_StatutIdleLabel = uilabel(app.Scaling_GridLayout);
            app.Scaling_StatutIdleLabel.Text = 'Statut: Idle';
            app.BackgroundlabelEditField_2Label = uilabel(app.Scaling_GridLayout);
            app.BackgroundlabelEditField_2Label.Text = 'Background label';
            app.Scaling_BackgroundlabelEditField = uieditfield(app.Scaling_GridLayout, 'numeric');
            app.OnlyusedforsegmentedvolumesLabel = uilabel(app.Scaling_GridLayout);
            app.OnlyusedforsegmentedvolumesLabel.Text = 'Only used for segmented volumes';
            app.AnisotropicscalingLabel = uilabel(app.Scaling_GridLayout);
            app.AnisotropicscalingLabel.Text = 'Anisotropic scaling';
            app.AnisotropicScaling_DoButton = uibutton(app.Scaling_GridLayout, 'push');
            app.AnisotropicScaling_DoButton.ButtonPushedFcn = createCallbackFcn(app, @AnisotropicScaling_DoButtonPushed, true);
            app.AnisotropicScaling_DoButton.Text = 'Do';
            app.UsefultocorrectaFIBSEM3DimagingwithananisotropicvoxelsizeLabel = uilabel(app.Scaling_GridLayout);
            app.UsefultocorrectaFIBSEM3DimagingwithananisotropicvoxelsizeLabel.Text = 'Useful to correct a FIB-SEM 3D imaging with an anisotropic voxel size';
            app.Anisotropicscaling_UITable = uitable(app.Scaling_GridLayout);
            app.Anisotropicscaling_UITable.ColumnName = {'Axe'; 'Scaling factor'};
            app.Anisotropicscaling_UITable.RowName = {};
            app.Anisotropicscaling_UITable.ColumnEditable = [false true];
            app.upscaling1downscalingLabel_2 = uilabel(app.Scaling_GridLayout);
            app.upscaling1downscalingLabel_2.Text = '>1: upscaling, <1: downscaling';
            app.ContrastcorrectionTab = uitab(app.TabGroup2);
            app.ContrastcorrectionTab.Title = 'Contrast correction';
            app.TabGroup6 = uitabgroup(app.ContrastcorrectionTab);
            app.RangeandsaturationTab = uitab(app.TabGroup6);
            app.RangeandsaturationTab.Title = 'Range and saturation';
            app.Contrast1_GridLayout10 = uigridlayout(app.RangeandsaturationTab);
            app.SaturationLabel = uilabel(app.Contrast1_GridLayout10);
            app.SaturationLabel.Text = 'Saturation';
            app.VolumeconditionLabel_2 = uilabel(app.Contrast1_GridLayout10);
            app.VolumeconditionLabel_2.Text = 'Volume% (< condition) ';
            app.Saturation_below_EditField = uieditfield(app.Contrast1_GridLayout10, 'numeric');
            app.Saturation_below_EditField.Limits = [0 100];
            app.Label_20 = uilabel(app.Contrast1_GridLayout10);
            app.Label_20.Text = {'Find grey level threshold so that the sum of voxel volume with grey level above (or below) it is equal to the volume percentage selected. Then, voxels with higher (or lower) grey level compared to ths threshold are'; 'assigned to it. Below, you can set the percentage separately for > and < conditions.'};
            app.VolumeconditionLabel = uilabel(app.Contrast1_GridLayout10);
            app.VolumeconditionLabel.Text = 'Volume% (> condition) ';
            app.Saturation_above_EditField = uieditfield(app.Contrast1_GridLayout10, 'numeric');
            app.Saturation_above_EditField.Limits = [0 100];
            app.Saturation_DoButton = uibutton(app.Contrast1_GridLayout10, 'push');
            app.Saturation_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Saturation_DoButtonPushed, true);
            app.Saturation_DoButton.Text = 'Do';
            app.LinearrescalingLabel = uilabel(app.Contrast1_GridLayout10);
            app.LinearrescalingLabel.Text = 'Linear rescaling';
            app.Contrast1_StatutIdleLabel = uilabel(app.Contrast1_GridLayout10);
            app.Contrast1_StatutIdleLabel.Text = 'Statut: Idle';
            app.RescalelinearlyusingnvaluesEditFieldLabel = uilabel(app.Contrast1_GridLayout10);
            app.RescalelinearlyusingnvaluesEditFieldLabel.Text = 'Rescale linearly using  <n> values';
            app.RescalelinearlyusingnvaluesEditField = uieditfield(app.Contrast1_GridLayout10, 'numeric');
            app.RescalelinearlyusingnvaluesEditField.Limits = [2 Inf];
            app.RescalelinearlyusingnvaluesEditField.RoundFractionalValues = 'on';
            app.RescalelinearlyusingnvaluesEditField.Value = 100;
            app.Rescalinglinearly_DoButton = uibutton(app.Contrast1_GridLayout10, 'push');
            app.Rescalinglinearly_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Rescalinglinearly_DoButtonPushed, true);
            app.Rescalinglinearly_DoButton.Text = 'Do';
            app.ScaletofulldatarangeLabel = uilabel(app.Contrast1_GridLayout10);
            app.ScaletofulldatarangeLabel.Text = 'Scale to full data range';
            app.UseFullRange_DoButton = uibutton(app.Contrast1_GridLayout10, 'push');
            app.UseFullRange_DoButton.ButtonPushedFcn = createCallbackFcn(app, @UseFullRange_DoButtonPushed, true);
            app.UseFullRange_DoButton.Text = 'Do';
            app.Egsegmentedimage012becomeLabel = uilabel(app.Contrast1_GridLayout10);
            app.Egsegmentedimage012becomeLabel.Text = {'E.g. segmented image [0,1] become [0, 255].'; 'Allow phases to be visible directly from window explorer'};
            app.HighandlowfilterLabel_2 = uilabel(app.Contrast1_GridLayout10);
            app.HighandlowfilterLabel_2.Text = 'High and low filter';
            app.Filterthreshold_EditField_2 = uieditfield(app.Contrast1_GridLayout10, 'numeric');
            app.Filterthreshold_EditField_2.Limits = [0 Inf];
            app.Filterthreshold_EditField_2.RoundFractionalValues = 'on';
            app.setthemtoEditFieldLabel_2 = uilabel(app.Contrast1_GridLayout10);
            app.setthemtoEditFieldLabel_2.Text = 'set them to';
            app.setthemtoEditField_2 = uieditfield(app.Contrast1_GridLayout10, 'numeric');
            app.setthemtoEditField_2.Limits = [0 Inf];
            app.setthemtoEditField_2.RoundFractionalValues = 'on';
            app.ForallvoxelsDropDown_2Label = uilabel(app.Contrast1_GridLayout10);
            app.ForallvoxelsDropDown_2Label.Text = 'For all voxels';
            app.ForallvoxelsDropDown_2 = uidropdown(app.Contrast1_GridLayout10);
            app.ForallvoxelsDropDown_2.Items = {'equal or above', 'equal or below'};
            app.ForallvoxelsDropDown_2.Value = 'equal or above';
            app.Highlow_filterButton = uibutton(app.Contrast1_GridLayout10, 'push');
            app.Highlow_filterButton.ButtonPushedFcn = createCallbackFcn(app, @Highlow_filterButtonPushed, true);
            app.Highlow_filterButton.Text = 'Do';
            app.HistogramequalizationTab = uitab(app.TabGroup6);
            app.HistogramequalizationTab.Title = 'Histogram equalization';
            app.Contrast2_GridLayout10 = uigridlayout(app.HistogramequalizationTab);
            app.HistogramequalizationLabel = uilabel(app.Contrast2_GridLayout10);
            app.HistogramequalizationLabel.Text = 'Histogram equalization';
            app.MethodDropDownLabel_4 = uilabel(app.Contrast2_GridLayout10);
            app.MethodDropDownLabel_4.Text = 'Method';
            app.ContrastEq_MethodDropDown = uidropdown(app.Contrast2_GridLayout10);
            app.ContrastEq_MethodDropDown.Items = {'Transforms the grayscale image so that the histogram of the output grayscale image with n bins is approximately flat', 'Transforms the grayscale image so that the histogram of the output grayscale image approximately matches the target histogram hgram', 'Transforms the grayscale image so that the histogram of the output grayscale image approximately matches the histogram hgram of another state'};
            app.ContrastEq_MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ContrastEq_MethodDropDownValueChanged, true);
            app.ContrastEq_MethodDropDown.Value = 'Transforms the grayscale image so that the histogram of the output grayscale image with n bins is approximately flat';
            app.nbinsEditFieldLabel = uilabel(app.Contrast2_GridLayout10);
            app.nbinsEditFieldLabel.Text = 'n bins';
            app.nbinsEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.nbinsEditField.Limits = [2 Inf];
            app.nbinsEditField.RoundFractionalValues = 'on';
            app.nbinsEditField.Tooltip = {'The resulting histogram is flatter when n is much smaller than the number of discrete levels in the input image.'};
            app.nbinsEditField.Value = 64;
            app.RelativenbinsCheckBox = uicheckbox(app.Contrast2_GridLayout10);
            app.RelativenbinsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RelativenbinsCheckBoxValueChanged, true);
            app.RelativenbinsCheckBox.Text = 'Relative n bins';
            app.rationbinsEditFieldLabel = uilabel(app.Contrast2_GridLayout10);
            app.rationbinsEditFieldLabel.Text = 'ratio n bins';
            app.rationbinsEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.rationbinsEditField.Limits = [0 1];
            app.rationbinsEditField.Value = 0.25;
            app.hgramEditFieldLabel = uilabel(app.Contrast2_GridLayout10);
            app.hgramEditFieldLabel.Text = 'hgram';
            app.hgramEditField = uieditfield(app.Contrast2_GridLayout10, 'text');
            app.hgramEditField.ValueChangedFcn = createCallbackFcn(app, @hgramEditFieldValueChanged, true);
            app.hgramEditField.Tooltip = {'The target histogram must have equally spaced bins spanning intensity values in the appropriate data range.'; ''; 'e.g.:'; '1 2 3 4 5 6 7'; 'or'; '256:-4:4 (for 256 252 248... 4).'};
            app.hgramEditField.Value = '256:-4:4';
            app.ContrastEqualization_DoButton = uibutton(app.Contrast2_GridLayout10, 'push');
            app.ContrastEqualization_DoButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastEqualization_DoButtonPushed, true);
            app.ContrastEqualization_DoButton.Text = 'Do';
            app.Contrast2_StatutIdleLabel = uilabel(app.Contrast2_GridLayout10);
            app.Contrast2_StatutIdleLabel.Text = 'Statut: Idle';
            app.ContrastEq_perslice_CheckBox = uicheckbox(app.Contrast2_GridLayout10);
            app.ContrastEq_perslice_CheckBox.Text = 'Apply per slice along 3rd axis (otherwise applied on whole volume all at once)';
            app.ContrastlimitedadaptativehistogramequalizationCLAHELabel = uilabel(app.Contrast2_GridLayout10);
            app.ContrastlimitedadaptativehistogramequalizationCLAHELabel.Tooltip = {'CLAHE operates on small regions in the image, called tiles, rather than the entire image. adapthisteq calculates the contrast transform function for each tile individually. Each tile''s contrast is enhanced, so that the histogram of the output region approximately matches the histogram specified by the Distribution value. The neighboring tiles are then combined using bilinear interpolation to eliminate artificially induced boundaries. The contrast, especially in homogeneous areas, can be limited to avoid amplifying any noise that might be present in the image.'};
            app.ContrastlimitedadaptativehistogramequalizationCLAHELabel.Text = 'Contrast-limited adaptative histogram equalization (CLAHE)';
            app.CLAHE_DoButton = uibutton(app.Contrast2_GridLayout10, 'push');
            app.CLAHE_DoButton.ButtonPushedFcn = createCallbackFcn(app, @CLAHE_DoButtonPushed, true);
            app.CLAHE_DoButton.Text = 'Do';
            app.CLAHE_NtileAEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.CLAHE_NtileAEditField.Limits = [2 Inf];
            app.CLAHE_NtileAEditField.RoundFractionalValues = 'on';
            app.CLAHE_NtileAEditField.Value = 8;
            app.Label_23 = uilabel(app.Contrast2_GridLayout10);
            app.Label_23.Text = 'Number of rectangular contextual regions (tiles) into which CLAHE divides the image, specified as a 2-element vector of positive integers.';
            app.CLAHE_NtileBEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.CLAHE_NtileBEditField.Limits = [2 Inf];
            app.CLAHE_NtileBEditField.RoundFractionalValues = 'on';
            app.CLAHE_NtileBEditField.Value = 8;
            app.Label_24 = uilabel(app.Contrast2_GridLayout10);
            app.Label_24.Text = 'Contrast enhancement limit, specified as a number in the range [0, 1]. Higher limits result in more contrast.';
            app.CLAHE_CliplimitEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.CLAHE_CliplimitEditField.Limits = [0 1];
            app.CLAHE_CliplimitEditField.Value = 0.01;
            app.Label_25 = uilabel(app.Contrast2_GridLayout10);
            app.Label_25.Text = 'Number of histogram bins used to build a contrast enhancing transformation, specified as a positive integer. Higher values result in greater dynamic range at the cost of slower processing speed.';
            app.CLAHE_NbinEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.CLAHE_NbinEditField.Limits = [2 Inf];
            app.CLAHE_NbinEditField.RoundFractionalValues = 'on';
            app.CLAHE_NbinEditField.Value = 256;
            app.RangeDropDownLabel = uilabel(app.Contrast2_GridLayout10);
            app.RangeDropDownLabel.Text = 'Range';
            app.CLAHE_RangeDropDown = uidropdown(app.Contrast2_GridLayout10);
            app.CLAHE_RangeDropDown.Items = {'full', 'original'};
            app.CLAHE_RangeDropDown.Tooltip = {'full: Use the full range of the output class (such as [0 255] for uint8).'; 'original: Limit the range to [min(I(:)) max(I(:))].'};
            app.CLAHE_RangeDropDown.Value = 'full';
            app.DistributionDropDownLabel = uilabel(app.Contrast2_GridLayout10);
            app.DistributionDropDownLabel.Text = 'Distribution';
            app.CLAHE_DistributionDropDown = uidropdown(app.Contrast2_GridLayout10);
            app.CLAHE_DistributionDropDown.Items = {'uniform', 'rayleigh', 'exponential'};
            app.CLAHE_DistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @CLAHE_DistributionDropDownValueChanged, true);
            app.CLAHE_DistributionDropDown.Tooltip = {'"uniform": Create a flat histogram.'; '"rayleigh": Create a bell-shaped histogram.'; '"exponential": Create a curved histogram.'};
            app.CLAHE_DistributionDropDown.Value = 'uniform';
            app.AlphaEditFieldLabel = uilabel(app.Contrast2_GridLayout10);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.CLAHE_AlphaEditField = uieditfield(app.Contrast2_GridLayout10, 'numeric');
            app.CLAHE_AlphaEditField.Limits = [0 Inf];
            app.CLAHE_AlphaEditField.Tooltip = {'Distribution parameter, specified as a nonnegative number. Alpha is only used when Distribution is set to "rayleigh" or "exponential".'};
            app.CLAHE_AlphaEditField.Value = 0.4;
            app.FilterTab = uitab(app.TabGroup2);
            app.FilterTab.Title = 'Filter';
            app.TabGroup4 = uitabgroup(app.FilterTab);
            app.OngreylevelorchannelsTab = uitab(app.TabGroup4);
            app.OngreylevelorchannelsTab.Title = 'On grey level (or channels)';
            app.GLfilter_GridLayout = uigridlayout(app.OngreylevelorchannelsTab);
            app.NonlocalmeansfilterLabel = uilabel(app.GLfilter_GridLayout);
            app.NonlocalmeansfilterLabel.Text = 'Non-local means filter';
            app.NLMF_EstimatedegreeofsmoothingCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.NLMF_EstimatedegreeofsmoothingCheckBox.Text = 'Estimate degree of smoothing';
            app.NLMF_EstimatedegreeofsmoothingCheckBox.Value = true;
            app.DegreeofsmoothingEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.DegreeofsmoothingEditFieldLabel.Text = 'Degree of smoothing';
            app.NLMF_DegreeofsmoothingEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.NLMF_DegreeofsmoothingEditField.Limits = [0 Inf];
            app.NLMF_DegreeofsmoothingEditField.Value = 5;
            app.SearchwindowsizeEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.SearchwindowsizeEditFieldLabel.Text = 'Search window size';
            app.NLMF_SearchwindowsizeEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.NLMF_SearchwindowsizeEditField.Limits = [1 Inf];
            app.NLMF_SearchwindowsizeEditField.RoundFractionalValues = 'on';
            app.NLMF_SearchwindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @NLMF_SearchwindowsizeEditFieldValueChanged, true);
            app.NLMF_SearchwindowsizeEditField.Value = 21;
            app.ComparisonwindowsizeEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.ComparisonwindowsizeEditFieldLabel.Text = 'Comparison window size';
            app.NLMF_ComparisonwindowsizeEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.NLMF_ComparisonwindowsizeEditField.Limits = [1 Inf];
            app.NLMF_ComparisonwindowsizeEditField.RoundFractionalValues = 'on';
            app.NLMF_ComparisonwindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @NLMF_SearchwindowsizeEditFieldValueChanged, true);
            app.NLMF_ComparisonwindowsizeEditField.Value = 7;
            app.NLMF_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.NLMF_DoButton.ButtonPushedFcn = createCallbackFcn(app, @NLMF_DoButtonPushed, true);
            app.NLMF_DoButton.Text = 'Do';
            app.FilterGL_StatutIdleLabel = uilabel(app.GLfilter_GridLayout);
            app.FilterGL_StatutIdleLabel.Text = 'Statut: Idle';
            app.AveragingLabel = uilabel(app.GLfilter_GridLayout);
            app.AveragingLabel.Text = 'Averaging';
            app.FiltersizeLabel = uilabel(app.GLfilter_GridLayout);
            app.FiltersizeLabel.Text = 'Filter size';
            app.Filter_RangeEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.Filter_RangeEditField.Limits = [3 Inf];
            app.Filter_RangeEditField.RoundFractionalValues = 'on';
            app.Filter_RangeEditField.ValueChangedFcn = createCallbackFcn(app, @Filter_RangeEditFieldValueChanged, true);
            app.Filter_RangeEditField.Value = 3;
            app.Filter_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.Filter_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_DoButtonPushed, true);
            app.Filter_DoButton.Text = 'Do';
            app.PaddingDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.PaddingDropDownLabel.Text = 'Padding';
            app.Filter_PaddingDropDown = uidropdown(app.GLfilter_GridLayout);
            app.Filter_PaddingDropDown.Items = {'replicate', 'symmetric', 'circular'};
            app.Filter_PaddingDropDown.Value = 'replicate';
            app.FilterDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.GLfilter_GridLayout);
            app.FilterDropDown.Items = {'Gaussian filter', 'Mean filter'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Gaussian filter';
            app.DomainLabel = uilabel(app.GLfilter_GridLayout);
            app.DomainLabel.Text = 'Domain';
            app.GaussianFilter_Domain_DropDown = uidropdown(app.GLfilter_GridLayout);
            app.GaussianFilter_Domain_DropDown.Items = {'auto', 'frequency', 'spatial'};
            app.GaussianFilter_Domain_DropDown.Value = 'auto';
            app.FiltersizeLabel_2 = uilabel(app.GLfilter_GridLayout);
            app.FiltersizeLabel_2.Text = 'Sigma';
            app.Filter_SigmaEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.Filter_SigmaEditField.Limits = [0.0001 Inf];
            app.Filter_SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @Filter_SigmaEditFieldValueChanged, true);
            app.Filter_SigmaEditField.Value = 0.5;
            app.AnisotropicdiffusionfilterLabel = uilabel(app.GLfilter_GridLayout);
            app.AnisotropicdiffusionfilterLabel.Text = 'Anisotropic diffusion filter';
            app.Anisotropic_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.Anisotropic_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Anisotropic_DoButtonPushed, true);
            app.Anisotropic_DoButton.Text = 'Do';
            app.ApplyDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.ApplyDropDownLabel.Text = 'Apply';
            app.Anisotropic_ApplyDropDown = uidropdown(app.GLfilter_GridLayout);
            app.Anisotropic_ApplyDropDown.Items = {'Full volume', 'Slice per slice, along axis 1', 'Slice per slice, along axis 2', 'Slice per slice, along axis 3'};
            app.Anisotropic_ApplyDropDown.ValueChangedFcn = createCallbackFcn(app, @Anisotropic_ApplyDropDownValueChanged, true);
            app.Anisotropic_ApplyDropDown.Value = 'Full volume';
            app.ConductionmethodDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.ConductionmethodDropDownLabel.Text = 'Conduction method';
            app.Anisotropy_ConductionmethodDropDown = uidropdown(app.GLfilter_GridLayout);
            app.Anisotropy_ConductionmethodDropDown.Items = {'exponential', 'quadratic'};
            app.Anisotropy_ConductionmethodDropDown.Tooltip = {'Exponential diffusion favors high-contrast edges over low-contrast edges. Quadratic diffusion favors wide regions over smaller regions.'};
            app.Anisotropy_ConductionmethodDropDown.Value = 'exponential';
            app.ConnectivityDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.ConnectivityDropDownLabel.Text = 'Connectivity';
            app.Anisotropy_ConnectivityDropDown = uidropdown(app.GLfilter_GridLayout);
            app.Anisotropy_ConnectivityDropDown.Items = {'maximal', 'minimal'};
            app.Anisotropy_ConnectivityDropDown.Tooltip = {'Maximal connectivity considers eight nearest neighbors and minimal connectivity considers four nearest neighbors.'};
            app.Anisotropy_ConnectivityDropDown.Value = 'maximal';
            app.Anisotropic_EstimategradientCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.Anisotropic_EstimategradientCheckBox.ValueChangedFcn = createCallbackFcn(app, @Anisotropic_EstimategradientCheckBoxValueChanged, true);
            app.Anisotropic_EstimategradientCheckBox.Text = 'Estimate gradient threshold and number of iteration';
            app.NumberofiterationsEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.Anisotropic_NumberofiterationsEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.Anisotropic_NumberofiterationsEditField.Limits = [1 Inf];
            app.Anisotropic_NumberofiterationsEditField.RoundFractionalValues = 'on';
            app.Anisotropic_NumberofiterationsEditField.Value = 5;
            app.GradientthresholdEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.GradientthresholdEditFieldLabel.Text = 'Gradient threshold';
            app.Anisotropic_GradientthresholdEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.Anisotropic_GradientthresholdEditField.Limits = [0 Inf];
            app.Anisotropic_GradientthresholdEditField.Value = 1;
            app.GLFilter_PreviewCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.GLFilter_PreviewCheckBox.Tooltip = {'Filter will be applied on a single slice (no new state is created)'};
            app.GLFilter_PreviewCheckBox.Text = 'Preview';
            app.BitlevelLabel = uilabel(app.GLfilter_GridLayout);
            app.BitlevelLabel.Tooltip = {'Most of the information is stored on the last few bits. The first few bits are mostly noise.'};
            app.BitlevelLabel.Text = 'Bit level';
            app.Bitlevel_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.Bitlevel_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Bitlevel_DoButtonPushed, true);
            app.Bitlevel_DoButton.Text = 'Do';
            app.KeeplastxbitlevelEditFieldLabel = uilabel(app.GLfilter_GridLayout);
            app.KeeplastxbitlevelEditFieldLabel.Text = 'Keep last  <x> bit level';
            app.KeeplastxbitlevelEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.KeeplastxbitlevelEditField.Limits = [1 Inf];
            app.KeeplastxbitlevelEditField.RoundFractionalValues = 'on';
            app.KeeplastxbitlevelEditField.Value = 4;
            app.ShowbitlevelCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.ShowbitlevelCheckBox.Text = 'Show bit level';
            app.Label_36 = uilabel(app.GLfilter_GridLayout);
            app.Label_36.Text = '';
            app.ValuefilterLabel = uilabel(app.GLfilter_GridLayout);
            app.ValuefilterLabel.Text = 'Value filter';
            app.IdentifypixelsDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.IdentifypixelsDropDownLabel.Text = 'Identify pixels ';
            app.IdentifypixelsDropDown = uidropdown(app.GLfilter_GridLayout);
            app.IdentifypixelsDropDown.Items = {'<=', '>'};
            app.IdentifypixelsDropDown.Value = '<=';
            app.FilterValue_EditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.FilterValue_EditField.Limits = [0 Inf];
            app.FilterValue_EditField.Value = 1;
            app.Filter_Value_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.Filter_Value_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Filter_Value_DoButtonPushed, true);
            app.Filter_Value_DoButton.Text = 'Do';
            app.BackgroundEditFieldLabel_3 = uilabel(app.GLfilter_GridLayout);
            app.BackgroundEditFieldLabel_3.Text = 'Background';
            app.FilterVal_BackgroundEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.FilterVal_BackgroundEditField.Tooltip = {'Set <0 to ignore'};
            app.FilterVal_ScalewithdimensionCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.FilterVal_ScalewithdimensionCheckBox.Tooltip = {'If selected threshold = threshold ^ dimension'};
            app.FilterVal_ScalewithdimensionCheckBox.Text = 'Scale with dimension';
            app.Label2_2 = uilabel(app.GLfilter_GridLayout);
            app.Label2_2.Text = {'You can filter based on other features than brightness that you can calculate in the Channels tab. For instance, to filter clusters based on their size: 1) connectivity/calculate clusters, then 2)'; 'Channels/Label size, then 3) Value filter. You can also filter per sphere size. To do this go to Erosion dilation / local dilation.'};
            app.Label_38 = uilabel(app.GLfilter_GridLayout);
            app.Label_38.Text = '';
            app.SuperpixelsLabel = uilabel(app.GLfilter_GridLayout);
            app.SuperpixelsLabel.Text = 'Superpixels';
            app.Superpixel_DoButton = uibutton(app.GLfilter_GridLayout, 'push');
            app.Superpixel_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Superpixel_DoButtonPushed, true);
            app.Superpixel_DoButton.Text = 'Do';
            app.SuperpixelNum_EditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.SuperpixelNum_EditField.Limits = [1 Inf];
            app.SuperpixelNum_EditField.RoundFractionalValues = 'on';
            app.SuperpixelNum_EditField.Tooltip = {'"Pixels per superpixel (volume)": number of superpixels = N / val'; '"Pixels per superpixel (length)": number of superpixels = N / (val ^ d) '; 'with N the total number of pixels in the image, d its dimension (2 or 3), and val the number put in the GUI.'};
            app.SuperpixelNum_EditField.Value = 100;
            app.NumberofDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.NumberofDropDownLabel.Text = 'Number of';
            app.SuperpixelNumChoice_EditField = uidropdown(app.GLfilter_GridLayout);
            app.SuperpixelNumChoice_EditField.Items = {'superpixels', 'pixels per superpixel (volume)', 'pixels per superpixel (length)'};
            app.SuperpixelNumChoice_EditField.Tooltip = {'"Pixels per superpixel (volume)": number of superpixels = N / val'; '"Pixels per superpixel (length)": number of superpixels = N / (val ^ d) '; 'with N the total number of pixels in the image, d its dimension (2 or 3), and val the number put in the GUI.'};
            app.SuperpixelNumChoice_EditField.Value = 'pixels per superpixel (length)';
            app.NumberofiterationsEditFieldLabel_2 = uilabel(app.GLfilter_GridLayout);
            app.NumberofiterationsEditFieldLabel_2.Text = 'Number of iterations';
            app.SuperPixel_NumberofiterationsEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.SuperPixel_NumberofiterationsEditField.Limits = [1 Inf];
            app.SuperPixel_NumberofiterationsEditField.RoundFractionalValues = 'on';
            app.SuperPixel_NumberofiterationsEditField.Tooltip = {'Number of iterations used in the clustering phase of the algorithm.'};
            app.SuperPixel_NumberofiterationsEditField.Value = 10;
            app.InitialcompactnessEditField = uieditfield(app.GLfilter_GridLayout, 'numeric');
            app.InitialcompactnessEditField.Limits = [0.0001 Inf];
            app.InitialcompactnessEditField.Tooltip = {'Control shape of superpixels. A higher value makes superpixels more regularly shaped, that is, a square. A lower value makes superpixels adhere to boundaries better, making them irregularly shaped. Typical values for compactness are in the range [1, 20] (2D) and [0.01, 0.1] (3D).'};
            app.InitialcompactnessEditField.Value = 1;
            app.InitialcompactnessDropDownLabel = uilabel(app.GLfilter_GridLayout);
            app.InitialcompactnessDropDownLabel.Tooltip = {'Control shape of superpixels. A higher value makes superpixels more regularly shaped, that is, a square. A lower value makes superpixels adhere to boundaries better, making them irregularly shaped. Typical values for compactness are in the range [1, 20] (2D) and [0.01, 0.1] (3D).'};
            app.InitialcompactnessDropDownLabel.Text = 'Initial compactness';
            app.InitialcompactnessDropDown = uidropdown(app.GLfilter_GridLayout);
            app.InitialcompactnessDropDown.Items = {'Default', 'Custom'};
            app.InitialcompactnessDropDown.ValueChangedFcn = createCallbackFcn(app, @InitialcompactnessDropDownValueChanged, true);
            app.InitialcompactnessDropDown.Tooltip = {'Control shape of superpixels. A higher value makes superpixels more regularly shaped, that is, a square. A lower value makes superpixels adhere to boundaries better, making them irregularly shaped. Typical values for compactness are in the range [1, 20] (2D) and [0.01, 0.1] (3D).'};
            app.InitialcompactnessDropDown.Value = 'Default';
            app.SuperPixel_RefineCompactnessCheckBox = uicheckbox(app.GLfilter_GridLayout);
            app.SuperPixel_RefineCompactnessCheckBox.Tooltip = {'Uses the SLIC0 algorithm to refine Compactness.'};
            app.SuperPixel_RefineCompactnessCheckBox.Text = 'Refine Compactness adaptively after the first iteration';
            app.SuperPixel_RefineCompactnessCheckBox.Value = true;
            app.OnsegmenteddataTab = uitab(app.TabGroup4);
            app.OnsegmenteddataTab.Title = 'On segmented data';
            app.SEGfilter_GridLayout = uigridlayout(app.OnsegmenteddataTab);
            app.FilterSEG_StatutIdleLabel = uilabel(app.SEGfilter_GridLayout);
            app.FilterSEG_StatutIdleLabel.Text = 'Statut: Idle';
            app.Label_15 = uilabel(app.SEGfilter_GridLayout);
            app.Label_15.Tooltip = {'Segementation (especially with threshold-based methods) can generate thin layer artifacts at the interface between bright and dark phases, with the artifact layer being in between.'};
            app.Label_15.Text = 'Thin layer artifact removal';
            app.WidththresholdEditFieldLabel = uilabel(app.SEGfilter_GridLayout);
            app.WidththresholdEditFieldLabel.Text = 'Width threshold';
            app.WidththresholdEditField = uieditfield(app.SEGfilter_GridLayout, 'numeric');
            app.WidththresholdEditField.Limits = [0 Inf];
            app.WidththresholdEditField.Value = 4;
            app.Thinlayerartifact_DoButton = uibutton(app.SEGfilter_GridLayout, 'push');
            app.Thinlayerartifact_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Thinlayerartifact_DoButtonPushed, true);
            app.Thinlayerartifact_DoButton.Text = 'Do';
            app.Image4 = uiimage(app.SEGfilter_GridLayout);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Thin_layer_artifact.png');
            app.Hyperlink3 = uihyperlink(app.SEGfilter_GridLayout);
            app.Hyperlink3.URL = 'https://doi.org/10.1016/j.jpowsour.2013.08.021';
            app.Hyperlink3.Text = 'Such artifact has been first mentioned by Joos et al for Ni-YSZ (SOFC)';
            app.Hyperlink4 = uihyperlink(app.SEGfilter_GridLayout);
            app.Hyperlink4.URL = 'https://theses.hal.science/tel-01223428/';
            app.Hyperlink4.Text = 'A simpler version of the algortihm used here has been published (see appendix 1)';
            app.ErosiondilationTab = uitab(app.TabGroup2);
            app.ErosiondilationTab.Title = 'Erosion dilation';
            app.TabGroup7 = uitabgroup(app.ErosiondilationTab);
            app.GlobalTab = uitab(app.TabGroup7);
            app.GlobalTab.Title = 'Global';
            app.GlobalErosionDilation_GridLayout = uigridlayout(app.GlobalTab);
            app.GlobalErosionDilation_StatutIdleLabel = uilabel(app.GlobalErosionDilation_GridLayout);
            app.GlobalErosionDilation_StatutIdleLabel.Text = 'Statut: Idle';
            app.GlobalmorphologyopeningorclosingLabel = uilabel(app.GlobalErosionDilation_GridLayout);
            app.GlobalmorphologyopeningorclosingLabel.Text = 'Global morphology opening or closing';
            app.OrderDropDownLabel_3 = uilabel(app.GlobalErosionDilation_GridLayout);
            app.OrderDropDownLabel_3.Text = 'Order';
            app.GlobalErosionDilation_OrderDropDown = uidropdown(app.GlobalErosionDilation_GridLayout);
            app.GlobalErosionDilation_OrderDropDown.Items = {'Opening (erosion then dilation)', 'Closing (dilation then erosion)'};
            app.GlobalErosionDilation_OrderDropDown.Value = 'Opening (erosion then dilation)';
            app.Label_26 = uilabel(app.GlobalErosionDilation_GridLayout);
            app.Label_26.Text = {'Global morphology opening (erosion followed by dilation) can be useful to reduce surface roughness'; 'Global morphology closing (dilation followed by erosion) can be useful to remove small inclusion'};
            app.ErosiondistanceEditField_2Label = uilabel(app.GlobalErosionDilation_GridLayout);
            app.ErosiondistanceEditField_2Label.Text = 'Erosion distance';
            app.GlobalErosiondistanceEditField = uieditfield(app.GlobalErosionDilation_GridLayout, 'numeric');
            app.GlobalErosiondistanceEditField.Limits = [0 Inf];
            app.GlobalErosiondistanceEditField.Value = 1;
            app.DilationdistanceEditField_2Label = uilabel(app.GlobalErosionDilation_GridLayout);
            app.DilationdistanceEditField_2Label.Text = 'Dilation distance';
            app.GlobalDilationdistanceEditField = uieditfield(app.GlobalErosionDilation_GridLayout, 'numeric');
            app.GlobalDilationdistanceEditField.Limits = [0 Inf];
            app.GlobalDilationdistanceEditField.Value = 1;
            app.DistancedefinitionDropDown_2Label = uilabel(app.GlobalErosionDilation_GridLayout);
            app.DistancedefinitionDropDown_2Label.Text = 'Distance definition';
            app.GlobalErosionDilation_DistancedefinitionDropDown = uidropdown(app.GlobalErosionDilation_GridLayout);
            app.GlobalErosionDilation_DistancedefinitionDropDown.Items = {'Euclidean', 'Cityblock', 'Chessboard', 'Quasi-Euclidean'};
            app.GlobalErosionDilation_DistancedefinitionDropDown.Value = 'Chessboard';
            app.Label_27 = uilabel(app.GlobalErosionDilation_GridLayout);
            app.Label_27.Text = 'Recommendation: for distance = 1, use ''Chessboard'' defintion, otherwise ''Euclidean''';
            app.NumberofiterationEditFieldLabel_2 = uilabel(app.GlobalErosionDilation_GridLayout);
            app.NumberofiterationEditFieldLabel_2.Text = 'Number of iteration';
            app.GlobalErosionDilation_NumberofiterationEditField = uieditfield(app.GlobalErosionDilation_GridLayout, 'numeric');
            app.GlobalErosionDilation_NumberofiterationEditField.Limits = [1 Inf];
            app.GlobalErosionDilation_NumberofiterationEditField.RoundFractionalValues = 'on';
            app.GlobalErosionDilation_NumberofiterationEditField.Value = 1;
            app.ApplyonDropDownLabel_2 = uilabel(app.GlobalErosionDilation_GridLayout);
            app.ApplyonDropDownLabel_2.Text = 'Apply on';
            app.GlabolaErosionDilation_ApplyonDropDown = uidropdown(app.GlobalErosionDilation_GridLayout);
            app.GlabolaErosionDilation_ApplyonDropDown.Items = {'Per label', 'A single label'};
            app.GlabolaErosionDilation_ApplyonDropDown.ValueChangedFcn = createCallbackFcn(app, @GlabolaErosionDilation_ApplyonDropDownValueChanged, true);
            app.GlabolaErosionDilation_ApplyonDropDown.Value = 'Per label';
            app.DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2 = uicheckbox(app.GlobalErosionDilation_GridLayout);
            app.DilationcanoverwirtteothernonbackgroundlabelsCheckBox_2.Text = 'Dilation can overwirtte other non-background labels';
            app.GlobalErosionDilation_Button = uibutton(app.GlobalErosionDilation_GridLayout, 'push');
            app.GlobalErosionDilation_Button.ButtonPushedFcn = createCallbackFcn(app, @GlobalErosionDilation_ButtonPushed, true);
            app.GlobalErosionDilation_Button.Text = 'Do';
            app.Setto0nottoperformerosionLabel = uilabel(app.GlobalErosionDilation_GridLayout);
            app.Setto0nottoperformerosionLabel.Text = 'Set to 0 not to perform erosion';
            app.Setto0nottoperformdilationLabel = uilabel(app.GlobalErosionDilation_GridLayout);
            app.Setto0nottoperformdilationLabel.Text = 'Set to 0 not to perform dilation';
            app.Image3 = uiimage(app.GlobalErosionDilation_GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Global erosion and dilation.png');
            app.AddzeroesonedgeCheckBox = uicheckbox(app.GlobalErosionDilation_GridLayout);
            app.AddzeroesonedgeCheckBox.Text = 'Add zeroes on edge';
            app.LabeltoapplyEditFieldLabel = uilabel(app.GlobalErosionDilation_GridLayout);
            app.LabeltoapplyEditFieldLabel.Text = 'Label to apply';
            app.GlobalLabeltoapplyEditField = uieditfield(app.GlobalErosionDilation_GridLayout, 'numeric');
            app.GlobalLabeltoapplyEditField.Limits = [0 Inf];
            app.GlobalLabeltoapplyEditField.RoundFractionalValues = 'on';
            app.GlobalLabeltoapplyEditField.Value = 1;
            app.BackgroundlabelEditField_4Label = uilabel(app.GlobalErosionDilation_GridLayout);
            app.BackgroundlabelEditField_4Label.Text = 'Background label';
            app.GlobalBackgroundlabelEditField = uieditfield(app.GlobalErosionDilation_GridLayout, 'numeric');
            app.GlobalBackgroundlabelEditField.Limits = [0 Inf];
            app.GlobalBackgroundlabelEditField.RoundFractionalValues = 'on';
            app.LocalerosionTab = uitab(app.TabGroup7);
            app.LocalerosionTab.Title = 'Local erosion';
            app.LocalErosion_GridLayout = uigridlayout(app.LocalerosionTab);
            app.LocalErosionDilation_StatutIdleLabel = uilabel(app.LocalErosion_GridLayout);
            app.LocalErosionDilation_StatutIdleLabel.Text = 'Statut: Idle';
            app.ConcavitybasedLocalerosionLabel = uilabel(app.LocalErosion_GridLayout);
            app.ConcavitybasedLocalerosionLabel.Text = 'Concavity-based Local erosion';
            app.WindowrangeEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.WindowrangeEditFieldLabel.Text = 'Window range';
            app.Localerosion_WindowrangeEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.Localerosion_WindowrangeEditField.Limits = [1 Inf];
            app.Localerosion_WindowrangeEditField.RoundFractionalValues = 'on';
            app.Localerosion_WindowrangeEditField.Tooltip = {'Concavity is a local feature. Low (resp. high) window range implies concavity of small (resp. big) features will control the erosion process. Vert low value are not recommended as it means surface roughness control the erosion.'};
            app.Localerosion_WindowrangeEditField.Value = 5;
            app.Localerosion_surfacesimplification_CheckBox = uicheckbox(app.LocalErosion_GridLayout);
            app.Localerosion_surfacesimplification_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Localerosion_surfacesimplification_CheckBoxValueChanged, true);
            app.Localerosion_surfacesimplification_CheckBox.Text = 'At the begining of each iteration apply global morphology opening to reduce surface roughness';
            app.Localerosion_surfacesimplification_CheckBox.Value = true;
            app.DistanceEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.Localerosion_DistanceEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.Localerosion_DistanceEditField.Limits = [1 Inf];
            app.Localerosion_DistanceEditField.Value = 1;
            app.DistancemethodDropDownLabel = uilabel(app.LocalErosion_GridLayout);
            app.DistancemethodDropDownLabel.Text = 'Distance method';
            app.Localerosion_DistancemethodDropDown = uidropdown(app.LocalErosion_GridLayout);
            app.Localerosion_DistancemethodDropDown.Items = {'Euclidean', 'Cityblock', 'Chessboard', 'Quasi-Euclidean'};
            app.Localerosion_DistancemethodDropDown.Value = 'Chessboard';
            app.StoppingconditionORLabel = uilabel(app.LocalErosion_GridLayout);
            app.StoppingconditionORLabel.Text = 'Stopping condition (OR):';
            app.MaximumnumberofiterationEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.MaximumnumberofiterationEditFieldLabel.Text = 'Maximum number of iteration';
            app.MaximumnumberofiterationEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.MaximumnumberofiterationEditField.Limits = [1 Inf];
            app.MaximumnumberofiterationEditField.RoundFractionalValues = 'on';
            app.MaximumnumberofiterationEditField.Value = 500;
            app.RatioofinitialvolumefractionEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.RatioofinitialvolumefractionEditFieldLabel.Text = 'Ratio of initial volume fraction';
            app.RatioofinitialvolumefractionEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.RatioofinitialvolumefractionEditField.Limits = [0 1];
            app.RatioofinitialvolumefractionEditField.ValueChangedFcn = createCallbackFcn(app, @RatioofinitialvolumefractionEditFieldValueChanged, true);
            app.RatioofinitialvolumefractionEditField.Value = 0.9;
            app.Erodeupto10ofthephaseLabel = uilabel(app.LocalErosion_GridLayout);
            app.Erodeupto10ofthephaseLabel.Text = 'Erode up to 10% of the phase';
            app.ConcavitymetricDropDownLabel = uilabel(app.LocalErosion_GridLayout);
            app.ConcavitymetricDropDownLabel.Text = 'Concavity metric';
            app.ConcavitymetricDropDown = uidropdown(app.LocalErosion_GridLayout);
            app.ConcavitymetricDropDown.Items = {'circularity', 'solidity'};
            app.ConcavitymetricDropDown.Value = 'circularity';
            app.ThresholdnumericallerrorEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.ThresholdnumericallerrorEditFieldLabel.Text = 'Threshold (numericall error)';
            app.ThresholdnumericallerrorEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.ThresholdnumericallerrorEditField.Limits = [0 1];
            app.ThresholdnumericallerrorEditField.Value = 0.05;
            app.metricbelowthisvaluewillbesetto0Label = uilabel(app.LocalErosion_GridLayout);
            app.metricbelowthisvaluewillbesetto0Label.Text = '(1-metric) below this value will be set to 0';
            app.max1metricgoesbelowEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.max1metricgoesbelowEditFieldLabel.Text = 'max(1-metric) goes below';
            app.Localerosion_max1metricgoesbelowEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.Localerosion_max1metricgoesbelowEditField.Value = 0.1;
            app.ErosionmagnitudeEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.ErosionmagnitudeEditFieldLabel.Text = 'Erosion magnitude';
            app.ErosionmagnitudeEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.ErosionmagnitudeEditField.Limits = [0 1];
            app.ErosionmagnitudeEditField.Value = 0.1;
            app.VoxelsattheedgeswithmetricmaxLabel = uilabel(app.LocalErosion_GridLayout);
            app.VoxelsattheedgeswithmetricmaxLabel.Text = 'Voxels at the edges with (1-metric) > (1- erosion magnitude)*max(1-metric) are removed';
            app.Localerosion_VideoCheckBox = uicheckbox(app.LocalErosion_GridLayout);
            app.Localerosion_VideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @Localerosion_VideoCheckBoxValueChanged, true);
            app.Localerosion_VideoCheckBox.Text = 'Video';
            app.FramerateEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.FramerateEditFieldLabel.Text = 'Framerate';
            app.Localerosion_FramerateEditField = uieditfield(app.LocalErosion_GridLayout, 'numeric');
            app.Localerosion_FramerateEditField.Value = 10;
            app.Localerosion_DoButton = uibutton(app.LocalErosion_GridLayout, 'push');
            app.Localerosion_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Localerosion_DoButtonPushed, true);
            app.Localerosion_DoButton.Text = 'Do';
            app.Localerosion_SavefolderButton = uibutton(app.LocalErosion_GridLayout, 'push');
            app.Localerosion_SavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @Localerosion_SavefolderButtonPushed, true);
            app.Localerosion_SavefolderButton.Text = 'Save folder';
            app.Localerosion_NofolderLabel = uilabel(app.LocalErosion_GridLayout);
            app.Localerosion_NofolderLabel.Tooltip = {'No folder'};
            app.Localerosion_NofolderLabel.Text = 'No folder';
            app.FilenameEditFieldLabel = uilabel(app.LocalErosion_GridLayout);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.Localerosion_FilenameEditField = uieditfield(app.LocalErosion_GridLayout, 'text');
            app.Localerosion_FilenameEditField.Value = 'Local erosion video';
            app.CalculatedattheedgeslocallywithinwindowrangeLabel = uilabel(app.LocalErosion_GridLayout);
            app.CalculatedattheedgeslocallywithinwindowrangeLabel.Text = 'Calculated at the edges locally within in a subvolume of length 2*window range+1';
            app.LocalerosionKeepbest_CheckBox = uicheckbox(app.LocalErosion_GridLayout);
            app.LocalerosionKeepbest_CheckBox.Text = 'Result is best image among all iterations (otherwise last iteration)';
            app.LocalerosionKeepbest_CheckBox.Value = true;
            app.Ifcheckedimagewiththelowest1metricwillbekeptLabel = uilabel(app.LocalErosion_GridLayout);
            app.Ifcheckedimagewiththelowest1metricwillbekeptLabel.Text = 'If checked, image with the lowest (1-metric) will be kept.';
            app.Label_32 = uilabel(app.LocalErosion_GridLayout);
            app.Label_32.Text = {'Note 1: After local erosion you can do particle identification. For this, go to: Combinations and select ''assign voxel to nearest cluster''.'; 'Note 2: Erosion occurs usually in this order: concave regions (e.g. junctions between spherical particles), cracks, and surface roughness.'; '            If eroding cracks is not desired, consider applying local dilation first.'};
            app.Label_34 = uilabel(app.LocalErosion_GridLayout);
            app.Label_34.Text = '';
            app.Image2 = uiimage(app.LocalErosion_GridLayout);
            app.Image2.Tooltip = {'Local erosion preferentially erodes region with high concavity while letting unchanged region with low concavity (e.g., surface roughness) or none (e.g., ideal sphere).'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Local_erosion_example.png');
            app.Hyperlink6 = uihyperlink(app.LocalErosion_GridLayout);
            app.Hyperlink6.URL = 'https://doi.org/10.1016/j.commatsci.2025.113758';
            app.Hyperlink6.Text = {'Article Hyperlink'; ''};
            app.LocaldilationTab = uitab(app.TabGroup7);
            app.LocaldilationTab.Title = 'Local dilation';
            app.LocalDilation_GridLayout = uigridlayout(app.LocaldilationTab);
            app.SpherebasedlocaldilationLabel_2 = uilabel(app.LocalDilation_GridLayout);
            app.SpherebasedlocaldilationLabel_2.Text = 'Sphere-based local dilation';
            app.LocaldilationparticleconcaveshellsLabel = uilabel(app.LocalDilation_GridLayout);
            app.LocaldilationparticleconcaveshellsLabel.Text = 'Local dilation (particle concave shells)';
            app.LocalDilation_Shell_DoButton = uibutton(app.LocalDilation_GridLayout, 'push');
            app.LocalDilation_Shell_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Particleconcaveshell_DoButtonPushed, true);
            app.LocalDilation_Shell_DoButton.Text = 'Do';
            app.Label_30 = uilabel(app.LocalDilation_GridLayout);
            app.Label_30.Text = {'To get better results with the concave shell operation, it is adivsed'; 'to perform a dilation then erosion per label just after and then a'; 'combination multiplication with missing voxels checked).'};
            app.LocalDilation_StatutIdleLabel = uilabel(app.LocalDilation_GridLayout);
            app.LocalDilation_StatutIdleLabel.Text = 'Statut: Idle';
            app.LabeltodilateDropDownLabel = uilabel(app.LocalDilation_GridLayout);
            app.LabeltodilateDropDownLabel.Text = 'Label to dilate';
            app.Localdilation_LabeltodilateDropDown = uidropdown(app.LocalDilation_GridLayout);
            app.Localdilation_LabeltodilateDropDown.Items = {};
            app.Localdilation_LabeltodilateDropDown.Value = {};
            app.DilateDropDownLabel = uilabel(app.LocalDilation_GridLayout);
            app.DilateDropDownLabel.Text = 'Dilate';
            app.Localdilation_DilateDropDown = uidropdown(app.LocalDilation_GridLayout);
            app.Localdilation_DilateDropDown.Items = {'All non-background labels', 'One label'};
            app.Localdilation_DilateDropDown.ValueChangedFcn = createCallbackFcn(app, @Localdilation_DilateDropDownValueChanged, true);
            app.Localdilation_DilateDropDown.ClickedFcn = createCallbackFcn(app, @Localdilation_DilateDropDownValueChanged, true);
            app.Localdilation_DilateDropDown.Value = 'All non-background labels';
            app.BackgroundlabelDropDownLabel_3 = uilabel(app.LocalDilation_GridLayout);
            app.BackgroundlabelDropDownLabel_3.Text = 'Background label';
            app.Localdilation_BackgroundlabelDropDown = uidropdown(app.LocalDilation_GridLayout);
            app.Localdilation_BackgroundlabelDropDown.Items = {};
            app.Localdilation_BackgroundlabelDropDown.Value = {};
            app.DiameterEditFieldLabel = uilabel(app.LocalDilation_GridLayout);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.LocalDilation_GridLayout, 'numeric');
            app.DiameterEditField.Limits = [1 Inf];
            app.DiameterEditField.Value = 5;
            app.Localdilation_Sphere_DoButton = uibutton(app.LocalDilation_GridLayout, 'push');
            app.Localdilation_Sphere_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Localdilation_Sphere_DoButtonPushed, true);
            app.Localdilation_Sphere_DoButton.Text = 'Do';
            app.ClicktoinitializeUILabel = uilabel(app.LocalDilation_GridLayout);
            app.ClicktoinitializeUILabel.Text = 'Click to initialize  UI';
            app.Localdilation_ShowdiameteronlyforvisualizationCheckBox = uicheckbox(app.LocalDilation_GridLayout);
            app.Localdilation_ShowdiameteronlyforvisualizationCheckBox.Text = 'Show diameter (only for visualization)';
            app.Localdilation_RoundDmap_CheckBox = uicheckbox(app.LocalDilation_GridLayout);
            app.Localdilation_RoundDmap_CheckBox.Tooltip = {'Voxels near the interface may have numerical errors (very low diameters). If checked, voxels up to 2 voxel length from the interface will be assigned with daimeters from closest voxels.'};
            app.Localdilation_RoundDmap_CheckBox.Text = 'Round distance map (reduce calculation time for minor approximation)';
            app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox = uicheckbox(app.LocalDilation_GridLayout);
            app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Tooltip = {'FOV for the calculaiton will be larger by 2*(diameter+1) in each axis.'};
            app.Localdilation_ExtendFOVtoavoidedgeeffectsCheckBox.Text = 'Extend FOV to avoid edge effects';
            app.Localdilation_RemoveotherlabelsCheckBox = uicheckbox(app.LocalDilation_GridLayout);
            app.Localdilation_RemoveotherlabelsCheckBox.Text = 'Remove other labels for diameter calculation';
            app.Image = uiimage(app.LocalDilation_GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Local_dilation_sphere.png');
            app.ClicktoenlargepicturesLabel = uilabel(app.LocalDilation_GridLayout);
            app.ClicktoenlargepicturesLabel.Text = 'Click to enlarge pictures';
            app.Localdialtion_Perslice3DonyzdirectionCheckBox = uicheckbox(app.LocalDilation_GridLayout);
            app.Localdialtion_Perslice3DonyzdirectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @Localdialtion_Perslice3DonyzdirectionCheckBoxValueChanged, true);
            app.Localdialtion_Perslice3DonyzdirectionCheckBox.Text = 'Per slice';
            app.Localdilation_PersliceDropDown = uidropdown(app.LocalDilation_GridLayout);
            app.Localdilation_PersliceDropDown.Items = {'along axe 1', 'along axe 2', 'along axe 3'};
            app.Localdilation_PersliceDropDown.Value = 'along axe 1';
            app.Hyperlink6_2 = uihyperlink(app.LocalDilation_GridLayout);
            app.Hyperlink6_2.URL = 'https://doi.org/10.1016/j.commatsci.2025.113758';
            app.Hyperlink6_2.Text = 'Article Hyperlink';
            app.Image5 = uiimage(app.LocalDilation_GridLayout);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Local_erosion_example2.png');
            app.Label2_6 = uilabel(app.LocalDilation_GridLayout);
            app.Label2_6.Text = {'These two pictures iliustrate application examples for the'; 'sphere-based local dilation algortihm.'; '- (Left) Open-porosity crack identification and removal. Cracks'; 'bias significantly instance segmentation'; '(see article link for further details).'; '- (Bottom right) Background edge cleaning'};
            app.ApplyDropDownLabel_2 = uilabel(app.LocalDilation_GridLayout);
            app.ApplyDropDownLabel_2.Text = 'Apply';
            app.LocalDilation_ApplyDropDown = uidropdown(app.LocalDilation_GridLayout);
            app.LocalDilation_ApplyDropDown.Items = {'once on the whole image', 'per cluster', 'per label'};
            app.LocalDilation_ApplyDropDown.ValueChangedFcn = createCallbackFcn(app, @LocalDilation_ApplyDropDownValueChanged, true);
            app.LocalDilation_ApplyDropDown.Value = 'once on the whole image';
            app.SegmentationTab = uitab(app.TabGroup2);
            app.SegmentationTab.Title = 'Segmentation';
            app.TabGroup3 = uitabgroup(app.SegmentationTab);
            app.SemanticsegmentationTab = uitab(app.TabGroup3);
            app.SemanticsegmentationTab.Tooltip = {'Segment phases (e.g., pore, solid A, solid B)'};
            app.SemanticsegmentationTab.Title = 'Semantic segmentation';
            app.SemanticsegmentationTab.ButtonDownFcn = createCallbackFcn(app, @SemanticsegmentationTabButtonDown, true);
            app.Semanticsegmentation_GridLayout = uigridlayout(app.SemanticsegmentationTab);
            app.NumberofphasesincludingporebutnotbackgroundifanyLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.NumberofphasesincludingporebutnotbackgroundifanyLabel.Text = 'Number of phases (including pore but not background, if any)';
            app.Seg_Numberofphases_editfield = uieditfield(app.Semanticsegmentation_GridLayout, 'numeric');
            app.Seg_Numberofphases_editfield.Limits = [2 Inf];
            app.Seg_Numberofphases_editfield.RoundFractionalValues = 'on';
            app.Seg_Numberofphases_editfield.ValueChangedFcn = createCallbackFcn(app, @Seg_Numberofphases_editfieldValueChanged, true);
            app.Seg_Numberofphases_editfield.Value = 2;
            app.Method1globalthresholdLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.Method1globalthresholdLabel.Text = 'Method 1: global threshold';
            app.FirstchoosethenumberofphasesincludingbackgroundLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.FirstchoosethenumberofphasesincludingbackgroundLabel.Text = 'Choose the number of phases, set the expected volume fractions if known (optional) and then pick one segmentation method.';
            app.OptionLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.OptionLabel.Text = 'Option';
            app.Global_segmentation_MethodDropDown = uidropdown(app.Semanticsegmentation_GridLayout);
            app.Global_segmentation_MethodDropDown.Items = {'Manual', 'Linear', 'Fit to reach volume fractions', 'Otsu'};
            app.Global_segmentation_MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Global_segmentation_MethodDropDownValueChanged, true);
            app.Global_segmentation_MethodDropDown.Value = 'Manual';
            app.Globalsegmentation_UITable = uitable(app.Semanticsegmentation_GridLayout);
            app.Globalsegmentation_UITable.ColumnName = {'Phase#'; 'Label'; '<= threshold'};
            app.Globalsegmentation_UITable.RowName = {};
            app.Globalsegmentation_UITable.ColumnEditable = [false false true];
            app.Globalsegmentation_UITable.CellEditCallback = createCallbackFcn(app, @Globalsegmentation_UITableCellEdit, true);
            app.Segmentation_expectedvf_UITable = uitable(app.Semanticsegmentation_GridLayout);
            app.Segmentation_expectedvf_UITable.ColumnName = {'Phase#'; 'label'; 'Expected volume fractions'};
            app.Segmentation_expectedvf_UITable.RowName = {};
            app.Segmentation_expectedvf_UITable.ColumnEditable = [false true true];
            app.Segmentation_expectedvf_UITable.CellEditCallback = createCallbackFcn(app, @Segmentation_expectedvf_UITableCellEdit, true);
            app.Segmentation_expectedvf_UITable.Tooltip = {''};
            app.Globalthreshold_DoButton = uibutton(app.Semanticsegmentation_GridLayout, 'push');
            app.Globalthreshold_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Globalthreshold_DoButtonPushed, true);
            app.Globalthreshold_DoButton.Text = 'Do';
            app.GlobalSeg_StatutIdleLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.GlobalSeg_StatutIdleLabel.Text = 'Statut: Idle';
            app.Seg_Slider_Button = uibutton(app.Semanticsegmentation_GridLayout, 'push');
            app.Seg_Slider_Button.ButtonPushedFcn = createCallbackFcn(app, @Seg_Slider_ButtonPushed, true);
            app.Seg_Slider_Button.Text = 'Interactive segmentation';
            app.GotocomparisonstabtochecksegmentationresultLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.GotocomparisonstabtochecksegmentationresultLabel.Text = 'Go to "comparisons" tab to check segmentation result';
            app.BackgroundoptionPanel = uipanel(app.Semanticsegmentation_GridLayout);
            app.BackgroundoptionPanel.Tooltip = {'Not only background will not bias the segmentation but Otsu''s algorithm will be also faster.'};
            app.BackgroundoptionPanel.Title = 'Background option';
            app.GridLayout12 = uigridlayout(app.BackgroundoptionPanel);
            app.Seg_ExcludebackgroundfromthecalculationCheckBox = uicheckbox(app.GridLayout12);
            app.Seg_ExcludebackgroundfromthecalculationCheckBox.ValueChangedFcn = createCallbackFcn(app, @Seg_ExcludebackgroundfromthecalculationCheckBoxValueChanged, true);
            app.Seg_ExcludebackgroundfromthecalculationCheckBox.Text = 'Exclude background from the calculation';
            app.SelectbackgroundDropDown_2Label = uilabel(app.GridLayout12);
            app.SelectbackgroundDropDown_2Label.Text = 'Select background';
            app.Seg_SelectbackgroundDropDown = uidropdown(app.GridLayout12);
            app.Seg_SelectbackgroundDropDown.Items = {'From label', 'From state'};
            app.Seg_SelectbackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @Seg_SelectbackgroundDropDownValueChanged, true);
            app.Seg_SelectbackgroundDropDown.Tooltip = {'If you plan to create a macro, you should use "From label'''};
            app.Seg_SelectbackgroundDropDown.Value = 'From label';
            app.BackgroundlabelEditField_3Label = uilabel(app.GridLayout12);
            app.BackgroundlabelEditField_3Label.Text = 'Background label';
            app.Seg_BackgroundlabelEditField = uieditfield(app.GridLayout12, 'numeric');
            app.Seg_BackgroundlabelEditField.Limits = [0 Inf];
            app.Seg_BackgroundlabelEditField.RoundFractionalValues = 'on';
            app.FromfileDropDownLabel_5 = uilabel(app.GridLayout12);
            app.FromfileDropDownLabel_5.Text = 'From file';
            app.Seg_FromfileDropDown = uidropdown(app.GridLayout12);
            app.Seg_FromfileDropDown.Items = {};
            app.Seg_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Seg_FromfileDropDownValueChanged, true);
            app.Seg_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @Seg_FromfileDropDownValueChanged, true);
            app.Seg_FromfileDropDown.Value = {};
            app.FromworkflowDropDownLabel_5 = uilabel(app.GridLayout12);
            app.FromworkflowDropDownLabel_5.Text = 'From workflow';
            app.Seg_FromworkflowDropDown = uidropdown(app.GridLayout12);
            app.Seg_FromworkflowDropDown.Items = {};
            app.Seg_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @Seg_FromworkflowDropDownValueChanged, true);
            app.Seg_FromworkflowDropDown.Value = {};
            app.FromstateDropDown_3Label_2 = uilabel(app.GridLayout12);
            app.FromstateDropDown_3Label_2.Text = 'From state';
            app.Seg_FromstateDropDown = uidropdown(app.GridLayout12);
            app.Seg_FromstateDropDown.Items = {};
            app.Seg_FromstateDropDown.Value = {};
            app.Method2localthresholdLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.Method2localthresholdLabel.Text = 'Method 2: local threshold';
            app.Segthreshold_DoButton = uibutton(app.Semanticsegmentation_GridLayout, 'push');
            app.Segthreshold_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Segthreshold_DoButtonPushed, true);
            app.Segthreshold_DoButton.Text = 'Do';
            app.LocalSegmentation_Axe_DropDown = uidropdown(app.Semanticsegmentation_GridLayout);
            app.LocalSegmentation_Axe_DropDown.Items = {'Per slice along axis 1', 'Per slice along axis 2', 'Per slice along axis 3'};
            app.LocalSegmentation_Axe_DropDown.Value = 'Per slice along axis 1';
            app.TranslatethresholdprofileCheckBox = uicheckbox(app.Semanticsegmentation_GridLayout);
            app.TranslatethresholdprofileCheckBox.Text = 'Translate threshold profile to match expected fractions';
            app.MovingaveragefilterrangeEditFieldLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.MovingaveragefilterrangeEditFieldLabel.Text = 'Moving average filter range';
            app.MovingaveragefilterrangeEditField = uieditfield(app.Semanticsegmentation_GridLayout, 'numeric');
            app.MovingaveragefilterrangeEditField.Limits = [0 Inf];
            app.MovingaveragefilterrangeEditField.RoundFractionalValues = 'on';
            app.MovingaveragefilterrangeEditField.Tooltip = {'If non-zero, threshold profile will be smooth to avoid discontinuity.'};
            app.PlotthresholdprofileCheckBox = uicheckbox(app.Semanticsegmentation_GridLayout);
            app.PlotthresholdprofileCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotthresholdprofileCheckBoxValueChanged, true);
            app.PlotthresholdprofileCheckBox.Text = 'Plot threshold profile';
            app.SavethresholdprofileCheckBox = uicheckbox(app.Semanticsegmentation_GridLayout);
            app.SavethresholdprofileCheckBox.Text = 'Save threshold profile';
            app.VoxelsizeEditFieldLabel_2 = uilabel(app.Semanticsegmentation_GridLayout);
            app.VoxelsizeEditFieldLabel_2.Text = 'Voxel size';
            app.Seg_VoxelsizeEditField = uieditfield(app.Semanticsegmentation_GridLayout, 'numeric');
            app.Seg_VoxelsizeEditField.Limits = [1e-09 Inf];
            app.Seg_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel_2 = uilabel(app.Semanticsegmentation_GridLayout);
            app.UnitEditFieldLabel_2.Text = 'Unit';
            app.Seg_UnitEditField = uieditfield(app.Semanticsegmentation_GridLayout, 'text');
            app.Seg_UnitEditField.Value = 'voxel';
            app.OptionDropDownLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.OptionDropDownLabel.Text = 'Option';
            app.LocalSegmentation_Method_DropDown = uidropdown(app.Semanticsegmentation_GridLayout);
            app.LocalSegmentation_Method_DropDown.Items = {'Manual', 'Otsu'};

            app.LocalSegmentation_Method_DropDown.ValueChangedFcn = createCallbackFcn(app, @LocalSegmentation_Method_DropDownValueChanged, true);
            app.LocalSegmentation_Method_DropDown.Value = 'Manual';
            app.LocalSeg_UITable = uitable(app.Semanticsegmentation_GridLayout);
            app.LocalSeg_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.LocalSeg_UITable.RowName = {};
            app.LocalSeg_UITable.CellEditCallback = createCallbackFcn(app, @LocalSeg_UITableCellEdit, true);
            app.LocalSeg_UITable.Tooltip = {'First column: position of control point (ratio of total length). Other columns: thresholds. Thresholds between control points will be linearly interpolated.'};
            app.NumberofcontrolpointsLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.NumberofcontrolpointsLabel.Text = 'Number of control points';
            app.LocalSeg_NumberofcontrolpointEditField = uieditfield(app.Semanticsegmentation_GridLayout, 'numeric');
            app.LocalSeg_NumberofcontrolpointEditField.Limits = [2 Inf];
            app.LocalSeg_NumberofcontrolpointEditField.RoundFractionalValues = 'on';
            app.LocalSeg_NumberofcontrolpointEditField.ValueChangedFcn = createCallbackFcn(app, @LocalSeg_NumberofcontrolpointEditFieldValueChanged, true);
            app.LocalSeg_NumberofcontrolpointEditField.Value = 2;
            app.Morethan2phasesYoumayneedtocorrectartifactActionsLabel = uilabel(app.Semanticsegmentation_GridLayout);
            app.Morethan2phasesYoumayneedtocorrectartifactActionsLabel.Text = {'More than 2 phases ? You may have to correct an artifact'; 'after the segmentation: go to: Actions / Filter / On segmented data'};
            app.VisualizethresholdsButton = uibutton(app.Semanticsegmentation_GridLayout, 'push');
            app.VisualizethresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizethresholdsButtonPushed, true);
            app.VisualizethresholdsButton.Text = 'Visualize thresholds';
            app.InstancesegmentationTab = uitab(app.TabGroup3);
            app.InstancesegmentationTab.Tooltip = {'Segment instances within a phase (e.g., particle 1, particle 2, particle 3, from solid A)'};
            app.InstancesegmentationTab.Title = 'Instance segmentation';
            app.Instancesegmentation_GridLayout = uigridlayout(app.InstancesegmentationTab);
            app.FirstchoosethenumberofphasesincludingbackgroundLabel_3 = uilabel(app.Instancesegmentation_GridLayout);
            app.FirstchoosethenumberofphasesincludingbackgroundLabel_3.Text = 'Choose the label to instance, the segmentation method, and the parameters';
            app.MethodDropDownLabel_2 = uilabel(app.Instancesegmentation_GridLayout);
            app.MethodDropDownLabel_2.Text = 'Method';
            app.InstanceSeg_MethodDropDown = uidropdown(app.Instancesegmentation_GridLayout);
            app.InstanceSeg_MethodDropDown.Items = {'Watershed (built-in)', 'Watershed (custom)', 'Pseudo-Coulomb Repulsive Field (PCRF)'};
            app.InstanceSeg_MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @InstanceSeg_MethodDropDownValueChanged, true);
            app.InstanceSeg_MethodDropDown.Tooltip = {'PCRF is the most accurate method usually, but is slower. Custom watershed allows you to visualize the flooding process in 2D but is slower than the built-in watershed function.'};
            app.InstanceSeg_MethodDropDown.Value = 'Watershed (built-in)';
            app.Hyperlink_5 = uihyperlink(app.Instancesegmentation_GridLayout);
            app.Hyperlink_5.URL = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.Hyperlink_5.Text = 'PCRF and oversegmentation correction article';
            app.InstanceSeg_DoButton = uibutton(app.Instancesegmentation_GridLayout, 'push');
            app.InstanceSeg_DoButton.ButtonPushedFcn = createCallbackFcn(app, @InstanceSeg_DoButtonPushed, true);
            app.InstanceSeg_DoButton.Text = 'Do';
            app.InstanceSeg_StatutIdleLabel = uilabel(app.Instancesegmentation_GridLayout);
            app.InstanceSeg_StatutIdleLabel.Text = 'Statut: Idle';
            app.Label_31 = uilabel(app.Instancesegmentation_GridLayout);
            app.Label_31.Text = 'You can also perform particle identification using local erosion. Go to Actions / Erosion dilation / Local erosion';
            app.InstancelabelEditFieldLabel = uilabel(app.Instancesegmentation_GridLayout);
            app.InstancelabelEditFieldLabel.Text = 'Instance label';
            app.InstancelabelEditField = uieditfield(app.Instancesegmentation_GridLayout, 'numeric');
            app.InstancelabelEditField.Limits = [0 Inf];
            app.InstancelabelEditField.RoundFractionalValues = 'on';
            app.InstancelabelEditField.Value = 1;
            app.Hyperlink5 = uihyperlink(app.Instancesegmentation_GridLayout);
            app.Hyperlink5.Text = 'Watershed article';
            app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox = uicheckbox(app.Instancesegmentation_GridLayout);
            app.Visualizealgorithmprogression2DonlycustomwatershedonlyCheckBox.Text = 'Visualize algorithm progression (2D only, custom watershed only)';
            app.InstanceSeg_VerboseCheckBox = uicheckbox(app.Instancesegmentation_GridLayout);
            app.InstanceSeg_VerboseCheckBox.Text = 'Verbose';
            app.Label2_4 = uilabel(app.Instancesegmentation_GridLayout);
            app.Label2_4.Text = 'Watershed is faster but more prone to oversegmentation. PCRF is much less sensitive to surface roughness and oversegmentation but is longer.';
            app.AdditionaloptionsLabel = uilabel(app.Instancesegmentation_GridLayout);
            app.AdditionaloptionsLabel.Text = 'Additional options:';
            app.Label2_5 = uilabel(app.Instancesegmentation_GridLayout);
            app.Label2_5.Text = 'You can calculate additional metrics (e.g., sphericity) in the ''Channels'' tab';
            app.ReducecalculationtimePanel = uipanel(app.Instancesegmentation_GridLayout);
            app.ReducecalculationtimePanel.Title = 'Reduce calculation time';
            app.ReduceCalcTime_GridLayout = uigridlayout(app.ReducecalculationtimePanel);
            app.SegmentingbypartsCheckBox = uicheckbox(app.ReduceCalcTime_GridLayout);
            app.SegmentingbypartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentingbypartsCheckBoxValueChanged, true);
            app.SegmentingbypartsCheckBox.Tooltip = {'Useful only for very large volumes. Volume will be cut along its longest axis in subvolumes of equal size. Each subvolume will be then segmented independently, and eventually recombined together. For the combination to work, intermediate subvolumes that overlap with the other subvolumes are also required. Therefore, for a number of part equals to i, i + (i-1) total subvolumes are segmented. Segmentation by parts is performed before downscaling or erosion, if any.'};
            app.SegmentingbypartsCheckBox.Text = 'Segmenting by parts.';
            app.NumberofpartsDropDown = uidropdown(app.ReduceCalcTime_GridLayout);
            app.NumberofpartsDropDown.Items = {'Manual', 'Auto'};
            app.NumberofpartsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofpartsDropDownValueChanged, true);
            app.NumberofpartsDropDown.Value = 'Manual';
            app.NumberofpartsEditFieldLabel = uilabel(app.ReduceCalcTime_GridLayout);
            app.NumberofpartsEditFieldLabel.Text = 'Number of parts';
            app.NumberofpartsEditField = uieditfield(app.ReduceCalcTime_GridLayout, 'numeric');
            app.NumberofpartsEditField.Limits = [2 Inf];
            app.NumberofpartsEditField.RoundFractionalValues = 'on';
            app.NumberofpartsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpartsEditFieldValueChanged, true);
            app.NumberofpartsEditField.Value = 2;
            app.TargetnumberofvoxelinmillionsEditFieldLabel_2 = uilabel(app.ReduceCalcTime_GridLayout);
            app.TargetnumberofvoxelinmillionsEditFieldLabel_2.Text = 'Target  number of voxel in millions';
            app.ByParts_TargetnumberofvoxelinmillionsEditField = uieditfield(app.ReduceCalcTime_GridLayout, 'numeric');
            app.ByParts_TargetnumberofvoxelinmillionsEditField.Limits = [0.001 Inf];
            app.ByParts_TargetnumberofvoxelinmillionsEditField.Tooltip = {'Find nearest number of parts so that independent volumes will have a number of voxels close to this number.'};
            app.ByParts_TargetnumberofvoxelinmillionsEditField.Value = 150;
            app.downscalingupscalingschemeCheckBox = uicheckbox(app.ReduceCalcTime_GridLayout);
            app.downscalingupscalingschemeCheckBox.ValueChangedFcn = createCallbackFcn(app, @downscalingupscalingschemeCheckBoxValueChanged, true);
            app.downscalingupscalingschemeCheckBox.Tooltip = {'Algorithm is applied on a downscaled version of the image to save CPU time. Relevant to identify large features such as particle instances. Result is then upscaled to the original resolution. Note that ALL voxels at the original resolution will be assigned to an instance.'};
            app.downscalingupscalingschemeCheckBox.Text = 'downscaling-upscaling scheme';
            app.DownscalingfactorDropDown = uidropdown(app.ReduceCalcTime_GridLayout);
            app.DownscalingfactorDropDown.Items = {'Manual', 'Auto'};
            app.DownscalingfactorDropDown.ValueChangedFcn = createCallbackFcn(app, @DownscalingfactorDropDownValueChanged, true);
            app.DownscalingfactorDropDown.Value = 'Manual';
            app.DownscalingfactorEditField_2Label = uilabel(app.ReduceCalcTime_GridLayout);
            app.DownscalingfactorEditField_2Label.Text = 'Downscaling factor';
            app.InstanceSeg_DownscalingfactorEditField = uieditfield(app.ReduceCalcTime_GridLayout, 'numeric');
            app.InstanceSeg_DownscalingfactorEditField.Limits = [2 Inf];
            app.InstanceSeg_DownscalingfactorEditField.RoundFractionalValues = 'on';
            app.InstanceSeg_DownscalingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @InstanceSeg_DownscalingfactorEditFieldValueChanged, true);
            app.InstanceSeg_DownscalingfactorEditField.Tooltip = {''};
            app.InstanceSeg_DownscalingfactorEditField.Value = 2;
            app.CropvolumesifneededandDropDownLabel = uilabel(app.ReduceCalcTime_GridLayout);
            app.CropvolumesifneededandDropDownLabel.Text = 'Crop volumes if needed and';
            app.CropvolumesifneededandDropDown = uidropdown(app.ReduceCalcTime_GridLayout);
            app.CropvolumesifneededandDropDown.Items = {'extrapolate to keep same dimension', 'keep cropped dimension'};
            app.CropvolumesifneededandDropDown.Tooltip = {'Downscaling and upscaling may lead to a different domain size. You can extrapolate to remedy this.'};
            app.CropvolumesifneededandDropDown.Value = 'extrapolate to keep same dimension';
            app.OverlappingpartlengthEditFieldLabel = uilabel(app.ReduceCalcTime_GridLayout);
            app.OverlappingpartlengthEditFieldLabel.Text = 'Overlapping part length';
            app.OverlappingpartlengthEditField = uieditfield(app.ReduceCalcTime_GridLayout, 'numeric');
            app.OverlappingpartlengthEditField.Limits = [1 Inf];
            app.OverlappingpartlengthEditField.Tooltip = {'Cannot be longer than length of independent parts'};
            app.OverlappingpartlengthEditField.Value = 500;
            app.OverlappinglengthDropDownLabel = uilabel(app.ReduceCalcTime_GridLayout);
            app.OverlappinglengthDropDownLabel.Text = 'Overlapping length';
            app.OverlappinglengthDropDown = uidropdown(app.ReduceCalcTime_GridLayout);
            app.OverlappinglengthDropDown.Items = {'Same as independant parts', 'Manual'};
            app.OverlappinglengthDropDown.ValueChangedFcn = createCallbackFcn(app, @OverlappinglengthDropDownValueChanged, true);
            app.OverlappinglengthDropDown.Value = 'Same as independant parts';
            app.TargetnumberofvoxelinmillionsLabel = uilabel(app.ReduceCalcTime_GridLayout);
            app.TargetnumberofvoxelinmillionsLabel.Text = 'Target number of voxel in millions';
            app.Downscaling_TargetnumberofvoxelinmillionsEditField = uieditfield(app.ReduceCalcTime_GridLayout, 'numeric');
            app.Downscaling_TargetnumberofvoxelinmillionsEditField.Limits = [0.001 Inf];
            app.Downscaling_TargetnumberofvoxelinmillionsEditField.Tooltip = {'Find nearest downscaling factor so that volume will have a number of voxels close to this number. If segmenting by parts, the downscaling factor is calculated for the first subvolume and reused for all other subvolumes.'};
            app.Downscaling_TargetnumberofvoxelinmillionsEditField.Value = 2.5;
            app.HelpButton = uibutton(app.ReduceCalcTime_GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Hyperlink7 = uihyperlink(app.ReduceCalcTime_GridLayout);
            app.Hyperlink7.Text = 'Article';
            app.InstanceSeg_ShowDownscalingCheckBox = uicheckbox(app.ReduceCalcTime_GridLayout);
            app.InstanceSeg_ShowDownscalingCheckBox.Tooltip = {'Optional. It will show you volume after erosion'};
            app.InstanceSeg_ShowDownscalingCheckBox.Text = 'Show downscaling';
            app.PreerosiontoimprovedetectionPanel = uipanel(app.Instancesegmentation_GridLayout);
            app.PreerosiontoimprovedetectionPanel.Title = 'Pre-erosion to improve detection';
            app.Preerosion_GridLayout = uigridlayout(app.PreerosiontoimprovedetectionPanel);
            app.ErosiondistanceEditFieldLabel = uilabel(app.Preerosion_GridLayout);
            app.ErosiondistanceEditFieldLabel.Text = 'Erosion distance';
            app.InstanceSeg_ErosiondistanceEditField = uieditfield(app.Preerosion_GridLayout, 'numeric');
            app.InstanceSeg_ErosiondistanceEditField.Limits = [1 Inf];
            app.InstanceSeg_ErosiondistanceEditField.RoundFractionalValues = 'on';
            app.InstanceSeg_ErosiondistanceEditField.ValueChangedFcn = createCallbackFcn(app, @InstanceSeg_DownscalingfactorEditFieldValueChanged, true);
            app.InstanceSeg_ErosiondistanceEditField.Tooltip = {''};
            app.InstanceSeg_ErosiondistanceEditField.Value = 2;
            app.OrderDropDownLabel = uilabel(app.Preerosion_GridLayout);
            app.OrderDropDownLabel.Text = 'Order';
            app.InstanceSeg_OrderDropDown = uidropdown(app.Preerosion_GridLayout);
            app.InstanceSeg_OrderDropDown.Items = {'Downscaling, erosion, segmentation, upscaling', 'Erosion, downscaling, segmentation, upscaling'};
            app.InstanceSeg_OrderDropDown.Value = 'Downscaling, erosion, segmentation, upscaling';
            app.InstanceSeg_ShowerosionCheckBox = uicheckbox(app.Preerosion_GridLayout);
            app.InstanceSeg_ShowerosionCheckBox.Tooltip = {'Optional. It will show you volume after erosion'};
            app.InstanceSeg_ShowerosionCheckBox.Text = 'Show erosion';
            app.PreerosionCheckBox = uicheckbox(app.Preerosion_GridLayout);
            app.PreerosionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreerosionCheckBoxValueChanged, true);
            app.PreerosionCheckBox.Tooltip = {'Erosion will help identifiying adjacent particles by exacerbating bottlenecks. However, you should NOT use a value too high as it will otherwise disconnect particles and remove small particles. Eroded voxels will be later assigned to nearby instances, so that all voxels of the initial image will be assigned to a particle.'};
            app.PreerosionCheckBox.Text = 'Pre erosion';
            app.MethodspecificparametersPanel = uipanel(app.Instancesegmentation_GridLayout);
            app.MethodspecificparametersPanel.Title = 'Method-specific parameters';
            app.InstanceParameterMethod_GridLayout = uigridlayout(app.MethodspecificparametersPanel);
            app.PCRFonlyparametersLabel = uilabel(app.InstanceParameterMethod_GridLayout);
            app.PCRFonlyparametersLabel.Text = 'PCRF-only parameters:';
            app.ExponentEditFieldLabel = uilabel(app.InstanceParameterMethod_GridLayout);
            app.ExponentEditFieldLabel.Text = 'Exponent';
            app.ExponentEditField = uieditfield(app.InstanceParameterMethod_GridLayout, 'numeric');
            app.ExponentEditField.Limits = [2 3];
            app.ExponentEditField.RoundFractionalValues = 'on';
            app.ExponentEditField.Tooltip = {'Exponent used for the Pseudo-Coulomb law. 2 or 3.'};
            app.ExponentEditField.Value = 3;
            app.RepulsiveforcewindowrangeEditFieldLabel = uilabel(app.InstanceParameterMethod_GridLayout);
            app.RepulsiveforcewindowrangeEditFieldLabel.Text = 'Repulsive force window range';
            app.RepulsiveforcewindowrangeEditField = uieditfield(app.InstanceParameterMethod_GridLayout, 'numeric');
            app.RepulsiveforcewindowrangeEditField.Limits = [2 Inf];
            app.RepulsiveforcewindowrangeEditField.RoundFractionalValues = 'on';
            app.RepulsiveforcewindowrangeEditField.Tooltip = {'Lower value increases speed but can degrades map of repulsive force. Typically use a range roughly equal to the largest particle radius.'};
            app.RepulsiveforcewindowrangeEditField.Value = 40;
            app.PostprocessingPanel = uipanel(app.Instancesegmentation_GridLayout);
            app.PostprocessingPanel.Title = 'Post-processing';
            app.GridLayout18 = uigridlayout(app.PostprocessingPanel);
            app.SpherefilteroversegmentationcorrectionCheckBox = uicheckbox(app.GridLayout18);
            app.SpherefilteroversegmentationcorrectionCheckBox.Tooltip = {'Voxels that belong to an instance of size smaller than the largest sphere that can contain them are reassigned to nearby non-artifact instance.'};
            app.SpherefilteroversegmentationcorrectionCheckBox.Text = 'Sphere filter (oversegmentation correction)';
            app.SpherefilteroversegmentationcorrectionCheckBox.Value = true;
            app.RandomizeinstancelabelaftersegmentationCheckBox = uicheckbox(app.GridLayout18);
            app.RandomizeinstancelabelaftersegmentationCheckBox.Text = 'Randomize instance label after segmentation';
            app.RandomizeinstancelabelaftersegmentationCheckBox.Value = true;
            app.ChannelsTab = uitab(app.TabGroup2);
            app.ChannelsTab.Title = 'Channels';
            app.TabGroup8 = uitabgroup(app.ChannelsTab);
            app.PixelwiseTab = uitab(app.TabGroup8);
            app.PixelwiseTab.Title = 'Pixel wise';
            app.Channels_Gridlayout_2 = uigridlayout(app.PixelwiseTab);
            app.Channel_StatutIdleLabel_2 = uilabel(app.Channels_Gridlayout_2);
            app.Channel_StatutIdleLabel_2.Text = 'Statut: Idle';
            app.ForgreylevelorchannelimagesPanel = uipanel(app.Channels_Gridlayout_2);
            app.ForgreylevelorchannelimagesPanel.Title = 'For grey level or channel images';
            app.GridLayout_Channel1 = uigridlayout(app.ForgreylevelorchannelimagesPanel);
            app.TextureLabel_2 = uilabel(app.GridLayout_Channel1);
            app.TextureLabel_2.Text = 'Texture';
            app.Channel_Std_DoButton_2 = uibutton(app.GridLayout_Channel1, 'push');
            app.Channel_Std_DoButton_2.ButtonPushedFcn = createCallbackFcn(app, @Channel_Std_DoButtonPushed, true);
            app.Channel_Std_DoButton_2.Text = 'Do';
            app.MetricDropDownLabel_3 = uilabel(app.GridLayout_Channel1);
            app.MetricDropDownLabel_3.Text = 'Metric';
            app.Texture_MetricDropDown_2 = uidropdown(app.GridLayout_Channel1);
            app.Texture_MetricDropDown_2.Items = {'Standard deviation', 'Entropy', 'Max-min range'};
            app.Texture_MetricDropDown_2.Value = 'Standard deviation';
            app.StructuringelementDropDownLabel_2 = uilabel(app.GridLayout_Channel1);
            app.StructuringelementDropDownLabel_2.Text = 'Structuring element';
            app.Channel_Std_StructuringelementDropDown_2 = uidropdown(app.GridLayout_Channel1);
            app.Channel_Std_StructuringelementDropDown_2.Items = {'Square or cube', 'Disc or sphere'};
            app.Channel_Std_StructuringelementDropDown_2.Value = 'Square or cube';
            app.RangeEditFieldLabel_2 = uilabel(app.GridLayout_Channel1);
            app.RangeEditFieldLabel_2.Text = 'Range';
            app.Channel_std_RangeEditField_2 = uieditfield(app.GridLayout_Channel1, 'numeric');
            app.Channel_std_RangeEditField_2.Limits = [1 Inf];
            app.Channel_std_RangeEditField_2.RoundFractionalValues = 'on';
            app.Channel_std_RangeEditField_2.Tooltip = {'The value of each output pixel is the metric caclulated within the structured element of length (2 x range+1) centered in the pixel.'};
            app.Channel_std_RangeEditField_2.Value = 1;
            app.GradientmagnitudeLabel_2 = uilabel(app.GridLayout_Channel1);
            app.GradientmagnitudeLabel_2.Text = 'Gradient magnitude';
            app.Channel_Gradientmagnitude_DoButton_2 = uibutton(app.GridLayout_Channel1, 'push');
            app.Channel_Gradientmagnitude_DoButton_2.ButtonPushedFcn = createCallbackFcn(app, @Channel_Gradientmagnitude_DoButtonPushed, true);
            app.Channel_Gradientmagnitude_DoButton_2.Text = 'Do';
            app.MethodDropDownLabel_5 = uilabel(app.GridLayout_Channel1);
            app.MethodDropDownLabel_5.Text = 'Method';
            app.Channel_GraidientMethodDropDown_2 = uidropdown(app.GridLayout_Channel1);
            app.Channel_GraidientMethodDropDown_2.Items = {'sobel', 'prewitt', 'central', 'intermediate', 'roberts'};
            app.Channel_GraidientMethodDropDown_2.Value = 'sobel';
            app.ForsegmentedsemanticimagesPanel = uipanel(app.Channels_Gridlayout_2);
            app.ForsegmentedsemanticimagesPanel.Title = 'For segmented (semantic) images';
            app.GridLayout_Channel2 = uigridlayout(app.ForsegmentedsemanticimagesPanel);
            app.SizeLabel_2 = uilabel(app.GridLayout_Channel2);
            app.SizeLabel_2.Text = 'Size';
            app.Channel_pixelsize_Button_2 = uibutton(app.GridLayout_Channel2, 'push');
            app.Channel_pixelsize_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Channel_pixelsize_ButtonPushed, true);
            app.Channel_pixelsize_Button_2.Text = 'Do';
            app.MetricLabel = uilabel(app.GridLayout_Channel2);
            app.MetricLabel.Text = 'Metric';
            app.Size_MericDropDown_2 = uidropdown(app.GridLayout_Channel2);
            app.Size_MericDropDown_2.Items = {'c-PSD', 'EDM'};
            app.Size_MericDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Size_MericDropDown_2ValueChanged, true);
            app.Size_MericDropDown_2.Tooltip = {'c-PSD: continum particle size distribution (pixel = diameter of the largest sphere that contain it wihout overlapping with the complementary volume).'; 'EDM: Euclidean distane map (pixel shortest distance to the interface).'};
            app.Size_MericDropDown_2.Value = 'c-PSD';
            app.DistanceDropDownLabel_2 = uilabel(app.GridLayout_Channel2);
            app.DistanceDropDownLabel_2.Text = 'Distance';
            app.Size_DistanceDropDown_2 = uidropdown(app.GridLayout_Channel2);
            app.Size_DistanceDropDown_2.Items = {'Euclidean', 'Cityblock', 'Chessboard', 'Quasi-Euclidean'};
            app.Size_DistanceDropDown_2.Value = 'Euclidean';
            app.Size_RoundresultCheckBox_2 = uicheckbox(app.GridLayout_Channel2);
            app.Size_RoundresultCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Size_RoundresultCheckBox_2ValueChanged, true);
            app.Size_RoundresultCheckBox_2.Tooltip = {'c-PSD calculation will also be much faster.'};
            app.Size_RoundresultCheckBox_2.Text = 'Round result';
            app.Size_RoundresultCheckBox_2.Value = true;
            app.ForDropDownLabel_2 = uilabel(app.GridLayout_Channel2);
            app.ForDropDownLabel_2.Text = 'For';
            app.ChannelLabel_ForDropDown_2 = uidropdown(app.GridLayout_Channel2);
            app.ChannelLabel_ForDropDown_2.Items = {'All labels except some labels', 'All labels', 'One label'};
            app.ChannelLabel_ForDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelLabel_ForDropDown_2ValueChanged, true);
            app.ChannelLabel_ForDropDown_2.Value = 'All labels except some labels';
            app.LabelEditFieldLabel_2 = uilabel(app.GridLayout_Channel2);
            app.Channel_LabelEditField_2 = uieditfield(app.GridLayout_Channel2, 'numeric');
            app.Channel_LabelEditField_2.Limits = [0 Inf];
            app.Channel_LabelEditField_2.RoundFractionalValues = 'on';
            app.Channel_LabelEditField_2.Value = 1;
            app.ExcludedlabelsEditFieldLabel = uilabel(app.GridLayout_Channel2);
            app.ExcludedlabelsEditFieldLabel.Text = 'Excluded label(s)';
            app.ExcludedlabelsEditField = uieditfield(app.GridLayout_Channel2, 'text');
            app.ExcludedlabelsEditField.ValueChangedFcn = createCallbackFcn(app, @ExcludedlabelsEditFieldValueChanged, true);
            app.ExcludedlabelsEditField.Tooltip = {'e.g.: 0 or 0, 1'};
            app.ExcludedlabelsEditField.Value = '0';
            app.NumberofdigitsEditFieldLabel = uilabel(app.GridLayout_Channel2);
            app.NumberofdigitsEditFieldLabel.Text = 'Number of digits';
            app.CpsdEDM_roundnumberofdigits_EditField = uieditfield(app.GridLayout_Channel2, 'numeric');
            app.CpsdEDM_roundnumberofdigits_EditField.Limits = [0 Inf];
            app.CpsdEDM_roundnumberofdigits_EditField.RoundFractionalValues = 'on';
            app.Image_cPSD_EDM = uiimage(app.GridLayout_Channel2);
            app.Image_cPSD_EDM.ImageClickedFcn = createCallbackFcn(app, @Image_cPSD_EDMClicked, true);
            app.Image_cPSD_EDM.ImageSource = fullfile(pathToMLAPP, 'cPSD_EDM.png');
            app.Hyperlink8 = uihyperlink(app.GridLayout_Channel2);
            app.Hyperlink8.URL = 'https://doi.org/10.1021/la9808418';
            app.Hyperlink8.Text = 'c-PSD: L.D. Gelb and K. E. Gubbins, Langmuir, 15, 2, 1998';
            app.Hyperlink9 = uihyperlink(app.GridLayout_Channel2);
            app.Hyperlink9.URL = 'https://www.mathworks.com/help/images/ref/bwdist.html';
            app.Hyperlink9.Text = 'EDM distance options';
            app.LabelwiseTab = uitab(app.TabGroup8);
            app.LabelwiseTab.Title = 'Label wise';
            app.GridLayout_Channel3 = uigridlayout(app.LabelwiseTab);
            app.Labelsize = uilabel(app.GridLayout_Channel3);
            app.Labelsize.Text = 'Label size';
            app.ChannelSize_DoButton_3 = uibutton(app.GridLayout_Channel3, 'push');
            app.ChannelSize_DoButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChannelSize_DoButtonPushed, true);
            app.ChannelSize_DoButton_3.Text = 'Do';
            app.Labelshape = uilabel(app.GridLayout_Channel3);
            app.Labelshape.Text = 'Label shape';
            app.ChannelShape_DoButton_3 = uibutton(app.GridLayout_Channel3, 'push');
            app.ChannelShape_DoButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChannelShape_DoButtonPushed, true);
            app.ChannelShape_DoButton_3.Text = 'Do';
            app.MetricDropDownLabel_5 = uilabel(app.GridLayout_Channel3);
            app.MetricDropDownLabel_5.Text = 'Metric';
            app.Shape_MetricDropDown_3 = uidropdown(app.GridLayout_Channel3);
            app.Shape_MetricDropDown_3.Items = {'Circularity (2D), sphericity (3D)', 'Solidity', 'Aspect ratio'};
            app.Shape_MetricDropDown_3.Value = 'Circularity (2D), sphericity (3D)';
            app.Label_37 = uilabel(app.GridLayout_Channel3);
            app.Label_37.Text = 'For segmented images. Usually dedicated for instance segmentation, but can be applied also for semantic segmentation.';
            app.ForDropDownLabel = uilabel(app.GridLayout_Channel3);
            app.ForDropDownLabel.Text = 'For';
            app.ChannelLabel_ForDropDown = uidropdown(app.GridLayout_Channel3);
            app.ChannelLabel_ForDropDown.Items = {'All labels except some labels', 'All labels', 'One label'};
            app.ChannelLabel_ForDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelLabel_ForDropDownValueChanged, true);
            app.ChannelLabel_ForDropDown.Value = 'All labels except some labels';
            app.LabelEditFieldLabel = uilabel(app.GridLayout_Channel3);
            app.Channel_LabelEditField = uieditfield(app.GridLayout_Channel3, 'numeric');
            app.Channel_LabelEditField.Limits = [0 Inf];
            app.Channel_LabelEditField.RoundFractionalValues = 'on';
            app.Channel_LabelEditField.Value = 1;
            app.Channel_StatutIdleLabel_3 = uilabel(app.GridLayout_Channel3);
            app.Channel_StatutIdleLabel_3.Text = 'Statut: Idle';
            app.PrecisionEditFieldLabel = uilabel(app.GridLayout_Channel3);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.Channel_PrecisionEditField = uieditfield(app.GridLayout_Channel3, 'numeric');
            app.Channel_PrecisionEditField.Limits = [1 Inf];
            app.Channel_PrecisionEditField.RoundFractionalValues = 'on';
            app.Channel_PrecisionEditField.Tooltip = {'round( metric * 1e^{precision) )'};
            app.Channel_PrecisionEditField.Value = 3;
            app.Channel_ConverttointegerCheckBox = uicheckbox(app.GridLayout_Channel3);
            app.Channel_ConverttointegerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel_ConverttointegerCheckBoxValueChanged, true);
            app.Channel_ConverttointegerCheckBox.Text = 'Convert to integer';
            app.Channel_ConverttointegerCheckBox.Value = true;
            app.SolidityvolumeconvexvolumeAspectratiominLxLyLzmaxLxLyLzLabel = uilabel(app.GridLayout_Channel3);
            app.SolidityvolumeconvexvolumeAspectratiominLxLyLzmaxLxLyLzLabel.Text = {'- Solidity: volume / convex volume'; '- Aspect ratio:  min([Lx Ly Lz])/max([Lx Ly Lz])'};
            app.ExcludedlabelsEditField_2Label = uilabel(app.GridLayout_Channel3);
            app.ExcludedlabelsEditField_2Label.Text = 'Excluded label(s)';
            app.ExcludedlabelsEditField_2 = uieditfield(app.GridLayout_Channel3, 'text');
            app.ExcludedlabelsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExcludedlabelsEditField_2ValueChanged, true);
            app.ExcludedlabelsEditField_2.Tooltip = {'e.g.: 0 or 0, 1'};
            app.ExcludedlabelsEditField_2.Value = '0';
            app.ConnectivityTab = uitab(app.TabGroup2);
            app.ConnectivityTab.Title = 'Connectivity';
            app.TabGroup9 = uitabgroup(app.ConnectivityTab);
            app.ClustersandconnectivitytypeTab = uitab(app.TabGroup9);
            app.ClustersandconnectivitytypeTab.Title = 'Clusters and connectivity type';
            app.Clusters_GridLayout = uigridlayout(app.ClustersandconnectivitytypeTab);
            app.Connectivity_StatutIdleLabel_3 = uilabel(app.Clusters_GridLayout);
            app.Connectivity_StatutIdleLabel_3.Text = 'Statut: Idle';
            app.Labelconnectivity_2 = uilabel(app.Clusters_GridLayout);
            app.Labelconnectivity_2.Text = 'Label connectivity';
            app.CalculateclustersLabel_3 = uilabel(app.Clusters_GridLayout);
            app.CalculateclustersLabel_3.Text = 'Calculate clusters';
            app.CalculateClusters_DoButton = uibutton(app.Clusters_GridLayout, 'push');
            app.CalculateClusters_DoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCluster_DoButtonPushed, true);
            app.CalculateClusters_DoButton.Text = 'Do';
            app.ForlabelEditFieldLabel = uilabel(app.Clusters_GridLayout);
            app.ForlabelEditFieldLabel.Text = 'For label';
            app.ForlabelEditField = uieditfield(app.Clusters_GridLayout, 'numeric');
            app.ForlabelEditField.Limits = [0 Inf];
            app.ForlabelEditField.RoundFractionalValues = 'on';
            app.ForlabelEditField.Value = 1;
            app.DconnectivitiesDropDownLabel_2 = uilabel(app.Clusters_GridLayout);
            app.DconnectivitiesDropDownLabel_2.Text = '2D connectivities';
            app.TwoDconnectivitiesDropDown_2 = uidropdown(app.Clusters_GridLayout);
            app.TwoDconnectivitiesDropDown_2.Items = {'edges (4)', 'edges or corners (8)'};
            app.TwoDconnectivitiesDropDown_2.Value = 'edges (4)';
            app.DconnectivitiesLabel_2 = uilabel(app.Clusters_GridLayout);
            app.DconnectivitiesLabel_2.Text = '3D connectivities';
            app.ThreeDconnectivitiesDropDown_2 = uidropdown(app.Clusters_GridLayout);
            app.ThreeDconnectivitiesDropDown_2.Items = {'faces (6)', 'faces or edges (18)', 'faces or edges or corners (26)'};
            app.ThreeDconnectivitiesDropDown_2.Value = 'faces (6)';
            app.SharedparametersPanel = uipanel(app.Clusters_GridLayout);
            app.SharedparametersPanel.Title = 'Shared parameters';
            app.GridLayout16_2 = uigridlayout(app.SharedparametersPanel);
            app.ComplementarylabelLabel_2 = uilabel(app.GridLayout16_2);
            app.ComplementarylabelLabel_2.Text = 'Complementary label';
            app.Cluster_complimentarylabelEditField = uieditfield(app.GridLayout16_2, 'numeric');
            app.Cluster_complimentarylabelEditField.Limits = [0 Inf];
            app.Cluster_complimentarylabelEditField.RoundFractionalValues = 'on';
            app.Cluster_complimentarylabelEditField.ValueChangedFcn = createCallbackFcn(app, @Connectivity_DefintionDropDownValueChanged, true);
            app.Cluster_complimentarylabelEditField.Value = 1;
            app.Cluster_BackgroundisdomainsedgesCheckBox = uicheckbox(app.GridLayout16_2);
            app.Cluster_BackgroundisdomainsedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Cluster_BackgroundisdomainsedgesCheckBoxValueChanged, true);
            app.Cluster_BackgroundisdomainsedgesCheckBox.Tooltip = {''};
            app.Cluster_BackgroundisdomainsedgesCheckBox.Text = 'Background is domain''s edges';
            app.Cluster_BackgroundisdomainsedgesCheckBox.Value = true;
            app.BackgroundlabelEditField_5Label_3 = uilabel(app.GridLayout16_2);
            app.BackgroundlabelEditField_5Label_3.Tooltip = {'If ROI is cylindrical, then background is NOT the domain''s edges. In such a case, output will be as follow: 0: background, 1: other labels, and 2 to n: the clusters for the selected label.'};
            app.BackgroundlabelEditField_5Label_3.Text = 'Background label';
            app.Clusters_Background_EditField = uieditfield(app.GridLayout16_2, 'numeric');
            app.Clusters_Background_EditField.Limits = [0 Inf];
            app.Clusters_Background_EditField.RoundFractionalValues = 'on';
            app.Clusters_Background_EditField.ValueChangedFcn = createCallbackFcn(app, @Connectivity_DefintionDropDownValueChanged, true);
            app.Clusters_Background_EditField.Tooltip = {''};
            app.Image_connectivity_background_2 = uiimage(app.GridLayout16_2);
            app.Image_connectivity_background_2.ImageSource = fullfile(pathToMLAPP, 'Connectivity_nobackground.png');
            app.Cluster_OuptutdescriptionLabel = uilabel(app.Clusters_GridLayout);
            app.Cluster_OuptutdescriptionLabel.Text = 'Ouptut description';
            app.DefintionDropDownLabel = uilabel(app.Clusters_GridLayout);
            app.DefintionDropDownLabel.Text = 'Defintion';
            app.Connectivity_DefintionDropDown = uidropdown(app.Clusters_GridLayout);
            app.Connectivity_DefintionDropDown.Items = {'Isotropic', 'Anisotropic', 'Custom'};
            app.Connectivity_DefintionDropDown.ValueChangedFcn = createCallbackFcn(app, @Connectivity_DefintionDropDownValueChanged, true);
            app.Connectivity_DefintionDropDown.Value = 'Isotropic';
            app.Cluster_OuptutdescriptionLabel_2 = uilabel(app.Clusters_GridLayout);
            app.Cluster_OuptutdescriptionLabel_2.Text = 'Ouptut description';
            app.Image_connectivity_definition = uiimage(app.Clusters_GridLayout);
            app.Image_connectivity_definition.ImageSource = fullfile(pathToMLAPP, 'Connectivity_definition_nobackground.png');
            app.LabelConnectivity_DoButton = uibutton(app.Clusters_GridLayout, 'push');
            app.LabelConnectivity_DoButton.ButtonPushedFcn = createCallbackFcn(app, @LabelConnectivity_DoButtonPushed, true);
            app.LabelConnectivity_DoButton.Text = 'Do';
            app.CalculateconnectivityforalllabelsexceptLabel = uilabel(app.Clusters_GridLayout);
            app.CalculateconnectivityforalllabelsexceptLabel.Text = 'Calculate connectivity for all labels except: ';
            app.alongaxeDropDownLabel = uilabel(app.Clusters_GridLayout);
            app.alongaxeDropDownLabel.Text = 'along axe';
            app.alongaxeDropDown = uidropdown(app.Clusters_GridLayout);
            app.alongaxeDropDown.Items = {'1', '2', '3'};
            app.alongaxeDropDown.Value = '1';
            app.ClustersmusttouchDropDownLabel = uilabel(app.Clusters_GridLayout);
            app.ClustersmusttouchDropDownLabel.Text = 'Clusters must touch';
            app.ClustersmusttouchDropDown = uidropdown(app.Clusters_GridLayout);
            app.ClustersmusttouchDropDown.Items = {'first slice', 'last slice', 'both opposite slices'};
            app.ClustersmusttouchDropDown.ValueChangedFcn = createCallbackFcn(app, @ClustersmusttouchDropDownValueChanged, true);
            app.ClustersmusttouchDropDown.Value = 'first slice';
            app.Custom_Connectivity_UITable_2 = uitable(app.Clusters_GridLayout);
            app.Custom_Connectivity_UITable_2.ColumnName = {'Clusters touch'; 'Axe 1'; 'Axe 2'; 'Axe 3'};
            app.Custom_Connectivity_UITable_2.RowName = {};
            app.Custom_Connectivity_UITable_2.ColumnEditable = [false true true true];
            app.CustomruleclustersmustverifyDropDownLabel = uilabel(app.Clusters_GridLayout);
            app.CustomruleclustersmustverifyDropDownLabel.Text = 'Custom rule: clusters must verify';
            app.CustomruleclustersmustverifyDropDown = uidropdown(app.Clusters_GridLayout);
            app.CustomruleclustersmustverifyDropDown.Items = {'all connectivity conditions', 'at least one connectivity condition'};
            app.CustomruleclustersmustverifyDropDown.Value = 'all connectivity conditions';
            app.ClusterinteractionsTab = uitab(app.TabGroup9);
            app.ClusterinteractionsTab.Title = 'Cluster interactions';
            app.ClusterInteractions_GridLayout = uigridlayout(app.ClusterinteractionsTab);
            app.Connectivity_StatutIdleLabel_2 = uilabel(app.ClusterInteractions_GridLayout);
            app.Connectivity_StatutIdleLabel_2.Text = 'Statut: Idle';
            app.ReassignlabelsbasedonsurroundingLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.ReassignlabelsbasedonsurroundingLabel.Text = 'Reassign labels based on surrounding';
            app.RemovelabelsincludedintootherlabelsLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.RemovelabelsincludedintootherlabelsLabel.Text = 'Remove labels included into other labels';
            app.AssigntosurroundinglabelCheckBox = uicheckbox(app.ClusterInteractions_GridLayout);
            app.AssigntosurroundinglabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @AssigntosurroundinglabelCheckBoxValueChanged, true);
            app.AssigntosurroundinglabelCheckBox.Text = 'Assign to surrounding label';
            app.AssigntosurroundinglabelCheckBox.Value = true;
            app.RemoveIncludedLabels_DoButton = uibutton(app.ClusterInteractions_GridLayout, 'push');
            app.RemoveIncludedLabels_DoButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveIncludedLabels_DoButtonPushed, true);
            app.RemoveIncludedLabels_DoButton.Text = 'Do';
            app.OtherwiseassigntoEditFieldLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.OtherwiseassigntoEditFieldLabel.Text = 'Otherwise assign to';
            app.OtherwiseassigntoEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.OtherwiseassigntoEditField.Limits = [0 Inf];
            app.OtherwiseassigntoEditField.RoundFractionalValues = 'on';
            app.SharedparametersexceptforreassignbasedonsurroundingPanel = uipanel(app.ClusterInteractions_GridLayout);
            app.SharedparametersexceptforreassignbasedonsurroundingPanel.Title = 'Shared parameters (except for ''reassign based on surrounding'')';
            app.GridLayout16 = uigridlayout(app.SharedparametersexceptforreassignbasedonsurroundingPanel);
            app.ComplementarylabelLabel = uilabel(app.GridLayout16);
            app.ComplementarylabelLabel.Text = 'Complementary label';
            app.ClusterInteractions_complimentarylabelEditField = uieditfield(app.GridLayout16, 'numeric');
            app.ClusterInteractions_complimentarylabelEditField.Limits = [0 Inf];
            app.ClusterInteractions_complimentarylabelEditField.RoundFractionalValues = 'on';
            app.ClusterInteractions_complimentarylabelEditField.Value = 1;
            app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox = uicheckbox(app.GridLayout16);
            app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClusterInerationcs_BackgroundisdomainsedgesCheckBoxValueChanged, true);
            app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Tooltip = {''};
            app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Text = 'Background is domain''s edges';
            app.ClusterInerationcs_BackgroundisdomainsedgesCheckBox.Value = true;
            app.BackgroundlabelEditField_5Label_2 = uilabel(app.GridLayout16);
            app.BackgroundlabelEditField_5Label_2.Tooltip = {'If ROI is cylindrical, then background is NOT the domain''s edges. In such a case, output will be as follow: 0: background, 1: other labels, and 2 to n: the clusters for the selected label.'};
            app.BackgroundlabelEditField_5Label_2.Text = 'Background label';
            app.ClusterInteractions_Background_EditField = uieditfield(app.GridLayout16, 'numeric');
            app.ClusterInteractions_Background_EditField.Limits = [0 Inf];
            app.ClusterInteractions_Background_EditField.RoundFractionalValues = 'on';
            app.ClusterInteractions_Background_EditField.Tooltip = {''};
            app.Image_connectivity_background = uiimage(app.GridLayout16);
            app.Image_connectivity_background.ImageSource = fullfile(pathToMLAPP, 'Connectivity_nobackground.png');
            app.AddthinseparationlayerbetweennonbackgroundlabelsincontactLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.AddthinseparationlayerbetweennonbackgroundlabelsincontactLabel.Tooltip = {'For instance, for battery microstructrues, this algorithm can be used to separate active material particles so that additives can be later added between the now isolated particles using the generation module.'};
            app.AddthinseparationlayerbetweennonbackgroundlabelsincontactLabel.Text = 'Add thin separation layer between non-background labels in contact';
            app.Removetruncated_DoButton_2 = uibutton(app.ClusterInteractions_GridLayout, 'push');
            app.Removetruncated_DoButton_2.ButtonPushedFcn = createCallbackFcn(app, @Removetruncated_DoButton_2Pushed, true);
            app.Removetruncated_DoButton_2.Text = 'Do';
            app.ChoiceDropDownLabel_4 = uilabel(app.ClusterInteractions_GridLayout);
            app.ChoiceDropDownLabel_4.Text = 'Choice';
            app.Separatelabels_ChoiceDropDown_2 = uidropdown(app.ClusterInteractions_GridLayout);
            app.Separatelabels_ChoiceDropDown_2.Items = {'Separate identically all labels', 'Separate randomly labels', 'Separate specifically some labels'};
            app.Separatelabels_ChoiceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @Separatelabels_ChoiceDropDown_2ValueChanged, true);
            app.Separatelabels_ChoiceDropDown_2.Value = 'Separate identically all labels';
            app.RatioofseparatedlabelsEditFieldLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.RatioofseparatedlabelsEditFieldLabel.Text = 'Ratio of separated labels';
            app.RatioofseparatedlabelsEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.RatioofseparatedlabelsEditField.Limits = [0 1];
            app.RatioofseparatedlabelsEditField.Value = 0.5;
            app.MeanwidthEditField_2Label = uilabel(app.ClusterInteractions_GridLayout);
            app.MeanwidthEditField_2Label.Text = 'Mean width';
            app.MeanwidthEditField_2 = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.MeanwidthEditField_2.Limits = [2 Inf];
            app.MeanwidthEditField_2.RoundFractionalValues = 'on';
            app.MeanwidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @MeanwidthEditField_2ValueChanged, true);
            app.MeanwidthEditField_2.Tooltip = {'Even integer only'};
            app.MeanwidthEditField_2.Value = 2;
            app.DistributionsigmaLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.DistributionsigmaLabel.Text = 'Distribution std deviation';
            app.DistributionstddeviationEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.DistributionstddeviationEditField.Limits = [0 Inf];
            app.DistributionstddeviationEditField.Value = 1;
            app.MaxvariationfrommeanEditFieldLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.MaxvariationfrommeanEditFieldLabel.Text = 'Max variation from mean (+/-)';
            app.MaxvariationfrommeanEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.MaxvariationfrommeanEditField.Limits = [0 Inf];
            app.MaxvariationfrommeanEditField.Value = 2;
            app.EquiprobabilityCheckBox_2 = uicheckbox(app.ClusterInteractions_GridLayout);
            app.EquiprobabilityCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EquiprobabilityCheckBox_2ValueChanged, true);
            app.EquiprobabilityCheckBox_2.Text = 'Equiprobability';
            app.ClusterSeparation_PlotdistributionButton = uibutton(app.ClusterInteractions_GridLayout, 'push');
            app.ClusterSeparation_PlotdistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterSeparation_PlotdistributionButtonPushed, true);
            app.ClusterSeparation_PlotdistributionButton.Text = 'Plot distribution';
            app.SeparateCluster_UITable = uitable(app.ClusterInteractions_GridLayout);
            app.SeparateCluster_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SeparateCluster_UITable.RowName = {};
            app.SeparateCluster_UITable.CellEditCallback = createCallbackFcn(app, @SeparateCluster_UITableCellEdit, true);
            app.SeparateCluster_UITable.Tooltip = {'Separation witdh per label doublet. Even integer only, 0 for no separation.'};
            app.Separate_labels_Image = uiimage(app.ClusterInteractions_GridLayout);
            app.Separate_labels_Image.ImageSource = fullfile(pathToMLAPP, 'Separate_labels.png');
            app.Cluster_separation_PleasewaitLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.Cluster_separation_PleasewaitLabel.Text = 'Please wait...';
            app.RemovetruncatedlabelsLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.RemovetruncatedlabelsLabel.Tooltip = {'Assign to complementary label'};
            app.RemovetruncatedlabelsLabel.Text = 'Remove truncated labels';
            app.Removetruncated_DoButton = uibutton(app.ClusterInteractions_GridLayout, 'push');
            app.Removetruncated_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Removetruncated_DoButtonPushed, true);
            app.Removetruncated_DoButton.Text = 'Do';
            app.WillcheckclustercenteredinthewindowrangeLabel = uilabel(app.ClusterInteractions_GridLayout);
            app.WillcheckclustercenteredinthewindowrangeLabel.Text = 'Will check cluster centered in the window range';
            app.WindowradiusEditFieldLabel_2 = uilabel(app.ClusterInteractions_GridLayout);
            app.WindowradiusEditFieldLabel_2.Text = 'Window radius';
            app.Surrounding_WindowradiusEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.Surrounding_WindowradiusEditField.Limits = [1 Inf];
            app.Surrounding_WindowradiusEditField.RoundFractionalValues = 'on';
            app.Surrounding_WindowradiusEditField.Tooltip = {'Window size is radius + 1 + radius. '};
            app.Surrounding_WindowradiusEditField.Value = 10;
            app.issurroundedbylabelEditField_2Label = uilabel(app.ClusterInteractions_GridLayout);
            app.issurroundedbylabelEditField_2Label.Text = 'is surrounded by label';
            app.issurroundedbylabelEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.issurroundedbylabelEditField.Limits = [1 Inf];
            app.issurroundedbylabelEditField.RoundFractionalValues = 'on';
            app.issurroundedbylabelEditField.Value = 2;
            app.IflabelEditFieldLabel_2 = uilabel(app.ClusterInteractions_GridLayout);
            app.IflabelEditFieldLabel_2.Text = 'If label';
            app.SurroundingLabel_EditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.SurroundingLabel_EditField.Limits = [0 Inf];
            app.SurroundingLabel_EditField.RoundFractionalValues = 'on';
            app.SurroundingLabel_EditField.Value = 1;
            app.morethanratioofinterfaceEditField_2Label = uilabel(app.ClusterInteractions_GridLayout);
            app.morethanratioofinterfaceEditField_2Label.Text = 'more than (ratio of interface)';
            app.morethanratioofinterfaceEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.morethanratioofinterfaceEditField.Limits = [0 1];
            app.morethanratioofinterfaceEditField.Value = 0.5;
            app.thenassigntolabelEditField_2Label = uilabel(app.ClusterInteractions_GridLayout);
            app.thenassigntolabelEditField_2Label.Text = 'then assign to label';
            app.thenassigntolabelEditField = uieditfield(app.ClusterInteractions_GridLayout, 'numeric');
            app.thenassigntolabelEditField.Limits = [0 Inf];
            app.thenassigntolabelEditField.RoundFractionalValues = 'on';
            app.thenassigntolabelEditField.Value = 3;
            app.Surrounding_DoButton = uibutton(app.ClusterInteractions_GridLayout, 'push');
            app.Surrounding_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Surrounding_DoButtonPushed, true);
            app.Surrounding_DoButton.Text = 'Do';
            app.AssembleclustersTab = uitab(app.TabGroup9);
            app.AssembleclustersTab.Title = 'Assemble clusters';
            app.Pulverize_GridLayout = uigridlayout(app.AssembleclustersTab);
            app.AssemblelabelsandfillvoidinbetweenLabel = uilabel(app.Pulverize_GridLayout);
            app.AssemblelabelsandfillvoidinbetweenLabel.Text = 'Assemble labels and fill void in between';
            app.ListoflabelstomergeEditFieldLabel = uilabel(app.Pulverize_GridLayout);
            app.ListoflabelstomergeEditFieldLabel.Text = 'List of labels to merge';
            app.ListoflabelstomergeEditField = uieditfield(app.Pulverize_GridLayout, 'text');
            app.ListoflabelstomergeEditField.ValueChangedFcn = createCallbackFcn(app, @ListoflabelstomergeEditFieldValueChanged, true);
            app.ListoflabelstomergeEditField.Value = '1, 2';
            app.eg417127Label = uilabel(app.Pulverize_GridLayout);
            app.eg417127Label.Text = 'e.g.: 4, 17, 127';
            app.VoidfillingmethodDropDownLabel = uilabel(app.Pulverize_GridLayout);
            app.VoidfillingmethodDropDownLabel.Text = 'Void filling method';
            app.VoidfillingmethodDropDown = uidropdown(app.Pulverize_GridLayout);
            app.VoidfillingmethodDropDown.Items = {'Sphere-based local dilation', 'Convex hull'};
            app.VoidfillingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @VoidfillingmethodDropDownValueChanged, true);
            app.VoidfillingmethodDropDown.Value = 'Sphere-based local dilation';
            app.DiameterEditField_2Label = uilabel(app.Pulverize_GridLayout);
            app.DiameterEditField_2Label.Text = 'Diameter';
            app.MergeSLSDiameterEditField = uieditfield(app.Pulverize_GridLayout, 'numeric');
            app.MergeSLSDiameterEditField.Limits = [1 Inf];
            app.MergeSLSDiameterEditField.RoundFractionalValues = 'on';
            app.MergeSLSDiameterEditField.Value = 10;
            app.FillingoverwritteotherlabelsCheckBox = uicheckbox(app.Pulverize_GridLayout);
            app.FillingoverwritteotherlabelsCheckBox.Text = 'Filling overwritte other labels';
            app.FillingoverwritteotherlabelsCheckBox.Value = true;
            app.MergePulverizedParts_DoButton = uibutton(app.Pulverize_GridLayout, 'push');
            app.MergePulverizedParts_DoButton.ButtonPushedFcn = createCallbackFcn(app, @MergePulverizedParts_DoButtonPushed, true);
            app.MergePulverizedParts_DoButton.Text = 'Do';
            app.Image6 = uiimage(app.Pulverize_GridLayout);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Merge_pulverized_particle.png');
            app.SeeErosiondilationlocaldilationLabel = uilabel(app.Pulverize_GridLayout);
            app.SeeErosiondilationlocaldilationLabel.Text = '(See Erosion dilation/local dilation)';
            app.Label2_7 = uilabel(app.Pulverize_GridLayout);
            app.Label2_7.Text = 'First value will be assigned to all labels of the list (e.g., 17 and 127 becomes 4)';
            app.Connectivity_StatutIdleLabel_4 = uilabel(app.Pulverize_GridLayout);
            app.Connectivity_StatutIdleLabel_4.Text = 'Statut: Idle';
            app.ReassignTab = uitab(app.TabGroup2);
            app.ReassignTab.Title = 'Re-assign';
            app.ReassignTab.ButtonDownFcn = createCallbackFcn(app, @ReassignTabSizeChanged, true);
            app.Randomize_GridLayout = uigridlayout(app.ReassignTab);
            app.Label_14 = uilabel(app.Randomize_GridLayout);
            app.Label_14.Text = '- Randomizing instance labels';
            app.Randomize_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Randomize_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Randomize_DoButtonPushed, true);
            app.Randomize_DoButton.Text = 'Do';
            app.OppositenotLabel = uilabel(app.Randomize_GridLayout);
            app.OppositenotLabel.Tooltip = {'Only relevant for binary image'};
            app.OppositenotLabel.Text = '- Opposite (not, ~)';
            app.Not_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Not_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.Not_DoButton.Tag = 'Not';
            app.Not_DoButton.Text = 'Do';
            app.Reassign_StatutIdleLabel = uilabel(app.Randomize_GridLayout);
            app.Reassign_StatutIdleLabel.Text = 'Statut: Idle';
            app.ManualreassignmentLabel = uilabel(app.Randomize_GridLayout);
            app.ManualreassignmentLabel.Text = '- Manual reassignment';
            app.Reassign_UITable = uitable(app.Randomize_GridLayout);
            app.Reassign_UITable.ColumnName = {'Old label'; 'New label'};
            app.Reassign_UITable.RowName = {};
            app.Reassign_UITable.ColumnEditable = [false true];
            app.Reassign_UITable.CellEditCallback = createCallbackFcn(app, @Reassign_UITableCellEdit, true);
            app.Reassign_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Reassign_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Reassign_DoButtonPushed, true);
            app.Reassign_DoButton.Text = 'Do';
            app.BinarizeLabel = uilabel(app.Randomize_GridLayout);
            app.BinarizeLabel.Text = '- Binarize';
            app.Binarize_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Binarize_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.Binarize_DoButton.Tag = 'Binarize';
            app.Binarize_DoButton.Text = 'Do';
            app.ArithmeticLabel = uilabel(app.Randomize_GridLayout);
            app.ArithmeticLabel.Text = '- Arithmetic ';
            app.Arithmetic_EditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.Arithmetic_EditField.RoundFractionalValues = 'on';
            app.Arithmetic_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Arithmetic_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.Arithmetic_DoButton.Tag = 'Arithmetic';
            app.Arithmetic_DoButton.Text = 'Do';
            app.RoundtoLabel = uilabel(app.Randomize_GridLayout);
            app.RoundtoLabel.Text = '- Round to';
            app.AbsoluteLabel = uilabel(app.Randomize_GridLayout);
            app.AbsoluteLabel.Text = '- Absolute';
            app.Round_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Round_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.Round_DoButton.Tag = 'Round';
            app.Round_DoButton.Text = 'Do';
            app.Absolute_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.Absolute_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.Absolute_DoButton.Tag = 'Absolute';
            app.Absolute_DoButton.Text = 'Do';
            app.SortlabelspervalueLabel = uilabel(app.Randomize_GridLayout);
            app.SortlabelspervalueLabel.Text = '- Sort labels per value';
            app.Sortchoice_DropDown = uidropdown(app.Randomize_GridLayout);
            app.Sortchoice_DropDown.Items = {'decreasing', 'increasing'};
            app.Sortchoice_DropDown.Value = 'decreasing';
            app.Arithmetic_DropDown = uidropdown(app.Randomize_GridLayout);
            app.Arithmetic_DropDown.Items = {'+', 'x'};
            app.Arithmetic_DropDown.Value = '+';
            app.SortInstance_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.SortInstance_DoButton.ButtonPushedFcn = createCallbackFcn(app, @SortInstance_DoButtonPushed, true);
            app.SortInstance_DoButton.Tag = 'Not';
            app.SortInstance_DoButton.Text = 'Do';
            app.BinarizeChoice_DropDown = uidropdown(app.Randomize_GridLayout);
            app.BinarizeChoice_DropDown.Items = {'M( M ~= x) = 1, 0 otherwise', 'M( M ~= x) = 0, 1 otherwise', 'M( M == x) = 1, 0 otherwise', 'M( M == x) = 0, 1 otherwise', 'M( M ==min(M) ) = 1, 0 otherwise', 'M( M ==max(M) ) = 1, 0 otherwise'};
            app.BinarizeChoice_DropDown.ValueChangedFcn = createCallbackFcn(app, @BinarizeChoice_DropDownValueChanged, true);
            app.BinarizeChoice_DropDown.Value = 'M( M ~= x) = 1, 0 otherwise';
            app.xEditFieldLabel = uilabel(app.Randomize_GridLayout);
            app.xEditFieldLabel.Text = 'x';
            app.Binarize_x_EditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.Binarize_x_EditField.Limits = [0 Inf];
            app.Binarize_x_EditField.RoundFractionalValues = 'on';
            app.digitsEditFieldLabel = uilabel(app.Randomize_GridLayout);
            app.digitsEditFieldLabel.Text = 'digits';
            app.rounddigitsEditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.rounddigitsEditField.Limits = [0 Inf];
            app.rounddigitsEditField.RoundFractionalValues = 'on';
            app.RandomizeDistance_CheckBox = uicheckbox(app.Randomize_GridLayout);
            app.RandomizeDistance_CheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizeDistance_CheckBoxValueChanged, true);
            app.RandomizeDistance_CheckBox.Text = 'Enforce label close from each other have different colors (slower)';
            app.CheckuptoadistanceofxvoxelsLabel = uilabel(app.Randomize_GridLayout);
            app.CheckuptoadistanceofxvoxelsLabel.Text = 'Check up to a distance of x voxels';
            app.Rnd_Checkuptoadistance_EditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.Rnd_Checkuptoadistance_EditField.Limits = [1 Inf];
            app.Rnd_Checkuptoadistance_EditField.RoundFractionalValues = 'on';
            app.Rnd_Checkuptoadistance_EditField.Value = 100;
            app.Removegapinlabelnumberingeg03711becomes0123Label = uilabel(app.Randomize_GridLayout);
            app.Removegapinlabelnumberingeg03711becomes0123Label.Text = '- Remove gap in label numbering (e.g., 0,3,7,11 becomes 0,1,2,3)';
            app.RemoveGapNumering_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.RemoveGapNumering_DoButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveGapNumering_DoButtonPushed, true);
            app.RemoveGapNumering_DoButton.Text = 'Do';
            app.IndexbasedsubstitutionLabel = uilabel(app.Randomize_GridLayout);
            app.IndexbasedsubstitutionLabel.Text = '- Index based substitution';
            app.SubstitutionChoice_DropDown = uidropdown(app.Randomize_GridLayout);
            app.SubstitutionChoice_DropDown.Items = {'M( M == x) = y', 'M( M ~= x) = y', 'M( M > x) = y', 'M( M < x) = y', 'M( M == min(M) ) = y', 'M( M == max(M) ) = y'};
            app.SubstitutionChoice_DropDown.ValueChangedFcn = createCallbackFcn(app, @SubstitutionChoice_DropDownValueChanged, true);
            app.SubstitutionChoice_DropDown.Value = 'M( M == x) = y';
            app.IndexSubstitution_DoButton = uibutton(app.Randomize_GridLayout, 'push');
            app.IndexSubstitution_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Round_DoButtonPushed, true);
            app.IndexSubstitution_DoButton.Tag = 'Substitution';
            app.IndexSubstitution_DoButton.Text = 'Do';
            app.xEditFieldLabel_2 = uilabel(app.Randomize_GridLayout);
            app.xEditFieldLabel_2.Text = 'x';
            app.IndexSubstitution_x_EditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.IndexSubstitution_x_EditField.RoundFractionalValues = 'on';
            app.yEditFieldLabel = uilabel(app.Randomize_GridLayout);
            app.yEditFieldLabel.Text = 'y';
            app.IndexSubstitution_y_EditField = uieditfield(app.Randomize_GridLayout, 'numeric');
            app.IndexSubstitution_y_EditField.RoundFractionalValues = 'on';
            app.ButdonotmodifysomelabelsCheckBox = uicheckbox(app.Randomize_GridLayout);
            app.ButdonotmodifysomelabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButdonotmodifysomelabelsCheckBoxValueChanged, true);
            app.ButdonotmodifysomelabelsCheckBox.Text = 'But do not modify some labels';
            app.ButdonotmodifysomelabelsCheckBox.Value = true;
            app.Randomize_Constant_EditField = uieditfield(app.Randomize_GridLayout, 'text');
            app.Randomize_Constant_EditField.ValueChangedFcn = createCallbackFcn(app, @Randomize_Constant_EditFieldValueChanged, true);
            app.Randomize_Constant_EditField.Tooltip = {'e.g.: 0, 1 if you want labels 0 and 1 not to be modfied.'};
            app.Randomize_Constant_EditField.Value = '0';
            app.Sort_Constant_EditField = uieditfield(app.Randomize_GridLayout, 'text');
            app.Sort_Constant_EditField.ValueChangedFcn = createCallbackFcn(app, @Sort_Constant_EditFieldValueChanged, true);
            app.Sort_Constant_EditField.Value = '0';
            app.ButdonotmodifysomelabelsCheckBox_2 = uicheckbox(app.Randomize_GridLayout);
            app.ButdonotmodifysomelabelsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ButdonotmodifysomelabelsCheckBox_2ValueChanged, true);
            app.ButdonotmodifysomelabelsCheckBox_2.Text = 'But do not modify some labels';
            app.ButdonotmodifysomelabelsCheckBox_2.Value = true;
            app.FormatTab = uitab(app.TabGroup2);
            app.FormatTab.Title = 'Format';
            app.DataType_GridLayout = uigridlayout(app.FormatTab);
            app.DatatypeLabel = uilabel(app.DataType_GridLayout);
            app.DatatypeLabel.Text = 'Data type';
            app.ChangedatatypetoDropDownLabel = uilabel(app.DataType_GridLayout);
            app.ChangedatatypetoDropDownLabel.Text = 'Change data type to';
            app.ChangedatatypetoDropDown = uidropdown(app.DataType_GridLayout);
            app.ChangedatatypetoDropDown.Items = {'Grey level', 'Segmented (phase)', 'Segmented (instance)', 'Channel'};
            app.ChangedatatypetoDropDown.Value = 'Grey level';
            app.Label2 = uilabel(app.DataType_GridLayout);
            app.Label2.Text = {'Actions automatically convert to the correct data type (e.g., semantic segmentation converts ''grey level'' to ''Segmented (phase)'', so you should not have to modify it manually.'; 'However if you have imported files with the incorrect data type, you can correct it here.'; 'Some algorithms behave differently depending on the data type (e.g. upscaling). As well, different color map are used for each data type.'};
            app.ChangeDataType_DoButton = uibutton(app.DataType_GridLayout, 'push');
            app.ChangeDataType_DoButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDataType_DoButtonPushed, true);
            app.ChangeDataType_DoButton.Text = 'Do';
            app.Format_StatutIdleLabel = uilabel(app.DataType_GridLayout);
            app.Format_StatutIdleLabel.Text = 'Statut: Idle';
            app.MultiplefilesoptionTab = uitab(app.TabGroup2);
            app.MultiplefilesoptionTab.Title = 'Multiple files option';
            app.Multiplefiles_options_GridLayout = uigridlayout(app.MultiplefilesoptionTab);
            app.Label_5 = uilabel(app.Multiplefiles_options_GridLayout);
            app.Label_5.Text = 'You can apply change to one file, or on all files sequentially.';
            app.OptionsDropDown_2Label = uilabel(app.Multiplefiles_options_GridLayout);
            app.OptionsDropDown_2Label.Text = 'Options:';
            app.Multiplefiles_options_DropDown = uidropdown(app.Multiplefiles_options_GridLayout);
            app.Multiplefiles_options_DropDown.Items = {'Only on the active state of the active workflow of the active file', 'On all files, on the same workflow index and on the same active state index'};
            app.Multiplefiles_options_DropDown.Value = 'Only on the active state of the active workflow of the active file';
            app.CalculationsTab = uitab(app.MainTabGroup);
            app.CalculationsTab.Tooltip = {''};
            app.CalculationsTab.Title = 'Calculations';
            app.TabGroup5 = uitabgroup(app.CalculationsTab);
            app.Calc_OngreylevelTab = uitab(app.TabGroup5);
            app.Calc_OngreylevelTab.Title = 'On grey level';
            app.Calc_OngreylevelTab.ButtonDownFcn = createCallbackFcn(app, @Calc_OngreylevelTabButtonDown, true);
            app.Calc_Grey_GridLayout = uigridlayout(app.Calc_OngreylevelTab);
            app.GreylevelLabel = uilabel(app.Calc_Grey_GridLayout);
            app.GreylevelLabel.Text = 'Grey level';
            app.CalculateDropDownLabel = uilabel(app.Calc_Grey_GridLayout);
            app.CalculateDropDownLabel.Text = 'Calculate ';
            app.Greylevel_CalculateDropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.Greylevel_CalculateDropDown.Items = {'Histogram for the whole volume', 'Histogram per slice (orthogonal slices)', 'Grey level per slice (orthogonal slices)', 'Histogram per radial position', 'Grey level per radial position', '2D projection maps (3D volume only)'};
            app.Greylevel_CalculateDropDown.ValueChangedFcn = createCallbackFcn(app, @Greylevel_CalculateDropDownValueChanged, true);
            app.Greylevel_CalculateDropDown.Value = 'Histogram for the whole volume';
            app.HistogramoptionLabel = uilabel(app.Calc_Grey_GridLayout);
            app.HistogramoptionLabel.Text = 'Histogram option';
            app.Greylevel_OptionDropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.Greylevel_OptionDropDown.Items = {'Bins number: auto', 'Bins number: one per grey level', 'Bins number: custom', 'Distribution function (discrete)', 'Distribution function (continuous)'};
            app.Greylevel_OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @Greylevel_OptionDropDownValueChanged, true);
            app.Greylevel_OptionDropDown.Value = 'Bins number: auto';
            app.Greylevel_CalculateButton = uibutton(app.Calc_Grey_GridLayout, 'push');
            app.Greylevel_CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Greylevel_CalculateButtonPushed, true);
            app.Greylevel_CalculateButton.Text = 'Calculate and plot';
            app.BlindnoiselevelLabel = uilabel(app.Calc_Grey_GridLayout);
            app.BlindnoiselevelLabel.Text = 'Blind noise level';
            app.NbinsEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.NbinsEditFieldLabel.Text = 'Nbins';
            app.NbinsEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.NbinsEditField.Limits = [2 Inf];
            app.NbinsEditField.RoundFractionalValues = 'on';
            app.NbinsEditField.Value = 10;
            app.Noiselevel_CalculateButton = uibutton(app.Calc_Grey_GridLayout, 'push');
            app.Noiselevel_CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Noiselevel_CalculateButtonPushed, true);
            app.Noiselevel_CalculateButton.Text = 'Calculate and plot';
            app.ImagequalityforsegmentationLabel = uilabel(app.Calc_Grey_GridLayout);
            app.ImagequalityforsegmentationLabel.Text = 'Image quality for segmentation';
            app.minEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.minEditFieldLabel.Text = 'min';
            app.SepCriterion_minEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.SepCriterion_minEditField.Limits = [2 Inf];
            app.SepCriterion_minEditField.RoundFractionalValues = 'on';
            app.SepCriterion_minEditField.ValueChangedFcn = createCallbackFcn(app, @SepCriterion_minEditFieldValueChanged, true);
            app.SepCriterion_minEditField.Value = 2;
            app.Imagequality_CalculateButton = uibutton(app.Calc_Grey_GridLayout, 'push');
            app.Imagequality_CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Imagequality_CalculateButtonPushed, true);
            app.Imagequality_CalculateButton.Text = 'Calculate and plot';
            app.BackgroundoptionPanel_2 = uipanel(app.Calc_Grey_GridLayout);
            app.BackgroundoptionPanel_2.Title = 'Background option';
            app.GridLayout11 = uigridlayout(app.BackgroundoptionPanel_2);
            app.ExcludebackgroundfromthecalculationCheckBox = uicheckbox(app.GridLayout11);
            app.ExcludebackgroundfromthecalculationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludebackgroundfromthecalculationCheckBoxValueChanged, true);
            app.ExcludebackgroundfromthecalculationCheckBox.Text = 'Exclude background from the calculation';
            app.BackgroundlabelEditField_2Label_2 = uilabel(app.GridLayout11);
            app.BackgroundlabelEditField_2Label_2.Text = 'Background label';
            app.BackgroundlabelEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.BackgroundlabelEditField_2.Limits = [0 Inf];
            app.BackgroundlabelEditField_2.RoundFractionalValues = 'on';
            app.SelectbackgroundDropDownLabel = uilabel(app.GridLayout11);
            app.SelectbackgroundDropDownLabel.Text = 'Select background';
            app.CalcGrey_SelectbackgroundDropDown = uidropdown(app.GridLayout11);
            app.CalcGrey_SelectbackgroundDropDown.Items = {'From label', 'From state'};
            app.CalcGrey_SelectbackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcGrey_SelectbackgroundDropDownValueChanged, true);
            app.CalcGrey_SelectbackgroundDropDown.Value = 'From label';
            app.FromfileDropDownLabel_4 = uilabel(app.GridLayout11);
            app.FromfileDropDownLabel_4.Tooltip = {'Background label must be 1'};
            app.FromfileDropDownLabel_4.Text = 'From file';
            app.Background_FromfileDropDown = uidropdown(app.GridLayout11);
            app.Background_FromfileDropDown.Items = {};
            app.Background_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Background_FromfileDropDownValueChanged, true);
            app.Background_FromfileDropDown.Tooltip = {'Background label must be 1'};
            app.Background_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @Background_FromfileDropDownValueChanged, true);
            app.Background_FromfileDropDown.Value = {};
            app.FromworkflowDropDownLabel_4 = uilabel(app.GridLayout11);
            app.FromworkflowDropDownLabel_4.Tooltip = {'Background label must be 1'};
            app.FromworkflowDropDownLabel_4.Text = 'From workflow';
            app.Background_FromworkflowDropDown = uidropdown(app.GridLayout11);
            app.Background_FromworkflowDropDown.Items = {};
            app.Background_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @Background_FromworkflowDropDownValueChanged, true);
            app.Background_FromworkflowDropDown.Tooltip = {'Background label must be 1'};
            app.Background_FromworkflowDropDown.Value = {};
            app.FromworkflowDropDown_2Label_2 = uilabel(app.GridLayout11);
            app.FromworkflowDropDown_2Label_2.Tooltip = {'Background label must be 1'};
            app.FromworkflowDropDown_2Label_2.Text = 'From state';
            app.Background_FromstateDropDown = uidropdown(app.GridLayout11);
            app.Background_FromstateDropDown.Items = {};
            app.Background_FromstateDropDown.Tooltip = {'Background label must be 1'};
            app.Background_FromstateDropDown.Value = {};
            app.VoxelsizeEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.VoxelsizeEditFieldLabel.Text = 'Voxel size';
            app.Calc_VoxelsizeEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.Calc_VoxelsizeEditField.Limits = [1e-09 Inf];
            app.Calc_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.UnitEditFieldLabel.Text = 'Unit';
            app.Calc_UnitEditField = uieditfield(app.Calc_Grey_GridLayout, 'text');
            app.Calc_UnitEditField.Value = 'voxel';
            app.NlinesLabel = uilabel(app.Calc_Grey_GridLayout);
            app.NlinesLabel.Text = 'N positions';
            app.Calc_NlinesEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.Calc_NlinesEditField.Limits = [2 Inf];
            app.Calc_NlinesEditField.RoundFractionalValues = 'on';
            app.Calc_NlinesEditField.Value = 25;
            app.Calc_AlllinesCheckBox = uicheckbox(app.Calc_Grey_GridLayout);
            app.Calc_AlllinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @Calc_AlllinesCheckBoxValueChanged, true);
            app.Calc_AlllinesCheckBox.Text = 'Plot all positions';
            app.Calc_AlllinesCheckBox.Value = true;
            app.LinewidthLabel = uilabel(app.Calc_Grey_GridLayout);
            app.LinewidthLabel.Text = 'Line width';
            app.Calc_LinewidthEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.Calc_LinewidthEditField.Limits = [1 Inf];
            app.Calc_LinewidthEditField.RoundFractionalValues = 'on';
            app.Calc_LinewidthEditField.Value = 1;
            app.ColormapLabel = uilabel(app.Calc_Grey_GridLayout);
            app.ColormapLabel.Text = 'Colormap';
            app.Calc_ColrmapDropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.Calc_ColrmapDropDown.Items = {'turbo', 'gray', 'parula'};
            app.Calc_ColrmapDropDown.Value = 'turbo';
            app.PerspectiveDropDownLabel = uilabel(app.Calc_Grey_GridLayout);
            app.PerspectiveDropDownLabel.Text = 'Perspective';
            app.Calc_PerspectiveDropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.Calc_PerspectiveDropDown.Items = {'2D', '3D'};
            app.Calc_PerspectiveDropDown.Value = '2D';
            app.MovingaveragefilterEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.MovingaveragefilterEditFieldLabel.Text = 'Moving average filter';
            app.Calc_MovingaveragefilterEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.Calc_MovingaveragefilterEditField.Limits = [0 Inf];
            app.Calc_MovingaveragefilterEditField.RoundFractionalValues = 'on';
            app.Hyperlink2 = uihyperlink(app.Calc_Grey_GridLayout);
            app.Hyperlink2.URL = 'https://doi.org/10.1109/TIP.2013.2283400';
            app.Hyperlink2.Text = 'Tanaka and Okutomi blind noise estimation';
            app.AlgorithmworkonthewholeLabel = uilabel(app.Calc_Grey_GridLayout);
            app.AlgorithmworkonthewholeLabel.Text = {'Algorithm works on the whole image and cannot ignore background. You can either crop it'; 'or use a symmetry (see Combinations tab) to fill it.'};
            app.UsingOtsusseparabilitycriterionLabel = uilabel(app.Calc_Grey_GridLayout);
            app.UsingOtsusseparabilitycriterionLabel.Text = 'Using Otsu''s separability criterion';
            app.CalcGreyLevel_StatutIdleLabel = uilabel(app.Calc_Grey_GridLayout);
            app.CalcGreyLevel_StatutIdleLabel.Text = 'Statut: Idle';
            app.NumberofphaseLabel = uilabel(app.Calc_Grey_GridLayout);
            app.NumberofphaseLabel.Text = 'Number of phase';
            app.maxEditFieldLabel = uilabel(app.Calc_Grey_GridLayout);
            app.maxEditFieldLabel.Text = 'max';
            app.SepCriterion_maxEditField = uieditfield(app.Calc_Grey_GridLayout, 'numeric');
            app.SepCriterion_maxEditField.Limits = [2 Inf];
            app.SepCriterion_maxEditField.RoundFractionalValues = 'on';
            app.SepCriterion_maxEditField.ValueChangedFcn = createCallbackFcn(app, @SepCriterion_minEditFieldValueChanged, true);
            app.SepCriterion_maxEditField.Value = 2;
            app.ChoiceDropDownLabel_3 = uilabel(app.Calc_Grey_GridLayout);
            app.ChoiceDropDownLabel_3.Text = 'Choice';
            app.SepCriterion_ChoiceDropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.SepCriterion_ChoiceDropDown.Items = {'Plot maximum separability critertion', 'Plot all separability criterion'};
            app.SepCriterion_ChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @SepCriterion_ChoiceDropDownValueChanged, true);
            app.SepCriterion_ChoiceDropDown.Value = 'Plot maximum separability critertion';
            app.ImageQuality_DropDown = uidropdown(app.Calc_Grey_GridLayout);
            app.Calc_OnsegmenteddataTab = uitab(app.TabGroup5);
            app.Calc_OnsegmenteddataTab.Title = 'On segmented data';
            app.Calc_Seg_GridLayout = uigridlayout(app.Calc_OnsegmenteddataTab);
            app.VolumefractionsLabel = uilabel(app.Calc_Seg_GridLayout);
            app.VolumefractionsLabel.Text = 'Volume fractions';
            app.FormoreadvancedoptionspleaseusethecharacterizationmoduleLabel = uilabel(app.Calc_Seg_GridLayout);
            app.FormoreadvancedoptionspleaseusethecharacterizationmoduleLabel.Text = 'For more advanced options, please use the characterization module';
            app.CalcSeg_StatutIdleLabel = uilabel(app.Calc_Seg_GridLayout);
            app.CalcSeg_StatutIdleLabel.Text = 'Statut: Idle';
            app.BackgroundoptionforvolumefractionsPanel = uipanel(app.Calc_Seg_GridLayout);
            app.BackgroundoptionforvolumefractionsPanel.Title = 'Background option (for volume fractions)';
            app.GridLayout15 = uigridlayout(app.BackgroundoptionforvolumefractionsPanel);
            app.CalcSeg_ExcludebackgroundfromthecalculationCheckBox = uicheckbox(app.GridLayout15);
            app.CalcSeg_ExcludebackgroundfromthecalculationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CalcSeg_ExcludebackgroundfromthecalculationCheckBoxValueChanged, true);
            app.CalcSeg_ExcludebackgroundfromthecalculationCheckBox.Text = 'Exclude background from the calculation';
            app.SelectbackgroundDropDownLabel_2 = uilabel(app.GridLayout15);
            app.SelectbackgroundDropDownLabel_2.Text = 'Select background';
            app.CalcSeg_SelectbackgroundDropDown = uidropdown(app.GridLayout15);
            app.CalcSeg_SelectbackgroundDropDown.Items = {'From label', 'From state'};
            app.CalcSeg_SelectbackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcSeg_SelectbackgroundDropDownValueChanged, true);
            app.CalcSeg_SelectbackgroundDropDown.Value = 'From label';
            app.BackgroundlabelEditField_3Label_3 = uilabel(app.GridLayout15);
            app.BackgroundlabelEditField_3Label_3.Text = 'Background label';
            app.CalcSeg_BackgroundlabelEditField = uieditfield(app.GridLayout15, 'numeric');
            app.CalcSeg_BackgroundlabelEditField.Limits = [0 Inf];
            app.CalcSeg_BackgroundlabelEditField.RoundFractionalValues = 'on';
            app.FromfileDropDownLabel_7 = uilabel(app.GridLayout15);
            app.FromfileDropDownLabel_7.Text = 'From file';
            app.CalcSeg_FromfileDropDown = uidropdown(app.GridLayout15);
            app.CalcSeg_FromfileDropDown.Items = {};
            app.CalcSeg_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Calc_FromfileDropDownValueChanged, true);
            app.CalcSeg_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @Calc_FromfileDropDownValueChanged, true);
            app.CalcSeg_FromfileDropDown.Value = {};
            app.FromworkflowDropDownLabel_7 = uilabel(app.GridLayout15);
            app.FromworkflowDropDownLabel_7.Text = 'From workflow';
            app.CalcSeg_FromworkflowDropDown = uidropdown(app.GridLayout15);
            app.CalcSeg_FromworkflowDropDown.Items = {};
            app.CalcSeg_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcSeg_FromworkflowDropDownValueChanged, true);
            app.CalcSeg_FromworkflowDropDown.Value = {};
            app.FromstateDropDown_4Label = uilabel(app.GridLayout15);
            app.FromstateDropDown_4Label.Text = 'From state';
            app.CalcSeg_FromstateDropDown = uidropdown(app.GridLayout15);
            app.CalcSeg_FromstateDropDown.Items = {};
            app.CalcSeg_FromstateDropDown.Value = {};
            app.VoxelsizeEditFieldLabel_3 = uilabel(app.Calc_Seg_GridLayout);
            app.VoxelsizeEditFieldLabel_3.Text = 'Voxel size';
            app.CalcSeg_VoxelsizeEditField = uieditfield(app.Calc_Seg_GridLayout, 'numeric');
            app.CalcSeg_VoxelsizeEditField.Limits = [1e-09 Inf];
            app.CalcSeg_VoxelsizeEditField.Value = 1;
            app.UnitEditFieldLabel_3 = uilabel(app.Calc_Seg_GridLayout);
            app.UnitEditFieldLabel_3.Text = 'Unit';
            app.CalcSeg_UnitEditField = uieditfield(app.Calc_Seg_GridLayout, 'text');
            app.CalcSeg_UnitEditField.Value = 'voxel';
            app.Volumefractions_CalculateandplotButton = uibutton(app.Calc_Seg_GridLayout, 'push');
            app.Volumefractions_CalculateandplotButton.ButtonPushedFcn = createCallbackFcn(app, @Volumefractions_CalculateandplotButtonPushed, true);
            app.Volumefractions_CalculateandplotButton.Text = 'Calculate and plot';
            app.ParticleconnectivityLabel = uilabel(app.Calc_Seg_GridLayout);
            app.ParticleconnectivityLabel.Text = 'Particle connectivity ';
            app.CalculateandplotparticleconnectivityButton = uibutton(app.Calc_Seg_GridLayout, 'push');
            app.CalculateandplotparticleconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotparticleconnectivityButtonPushed, true);
            app.CalculateandplotparticleconnectivityButton.Text = 'Calculate and plot (if 2D) particle connectivity';
            app.CombinationsTab = uitab(app.MainTabGroup);
            app.CombinationsTab.Tooltip = {''};
            app.CombinationsTab.Title = 'Combinations';
            app.CombinationsTab.ButtonDownFcn = createCallbackFcn(app, @CombinationsTabButtonDown, true);
            app.Combination_GridLayout = uigridlayout(app.CombinationsTab);
            app.SelecttwostatesAandBfromthesameordifferentworkflowsLabel = uilabel(app.Combination_GridLayout);
            app.SelecttwostatesAandBfromthesameordifferentworkflowsLabel.Text = {'1) Select two states (A and B) from any file and workflow. They must share the same size (except for histogram equalization) and be in memory.'; 'Result will be saved in a new workflow, and in a new file if states belong to different files if not specified otherwise.'};
            app.StateALabel = uilabel(app.Combination_GridLayout);
            app.StateALabel.Text = 'State A';
            app.FromworkflowDropDownLabel_2 = uilabel(app.Combination_GridLayout);
            app.FromworkflowDropDownLabel_2.Text = 'From workflow';
            app.CombinationA_FromworkflowDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationA_FromworkflowDropDown.Items = {};
            app.CombinationA_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationA_FromworkflowDropDownValueChanged, true);
            app.CombinationA_FromworkflowDropDown.ClickedFcn = createCallbackFcn(app, @CombinationA_FromworkflowDropDownValueChanged, true);
            app.CombinationA_FromworkflowDropDown.Value = {};
            app.FromworkflowDropDown_2Label = uilabel(app.Combination_GridLayout);
            app.FromworkflowDropDown_2Label.Text = 'From workflow';
            app.CombinationB_FromworkflowDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationB_FromworkflowDropDown.Items = {};
            app.CombinationB_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationA_FromworkflowDropDownValueChanged, true);
            app.CombinationB_FromworkflowDropDown.ClickedFcn = createCallbackFcn(app, @CombinationA_FromworkflowDropDownValueChanged, true);
            app.CombinationB_FromworkflowDropDown.Value = {};
            app.FromstateDropDown_2Label = uilabel(app.Combination_GridLayout);
            app.FromstateDropDown_2Label.Text = 'From state';
            app.CombinationA_FromstateDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationA_FromstateDropDown.Items = {};
            app.CombinationA_FromstateDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationA_FromstateDropDownValueChanged, true);
            app.CombinationA_FromstateDropDown.Value = {};
            app.FromstateDropDown_3Label = uilabel(app.Combination_GridLayout);
            app.FromstateDropDown_3Label.Text = 'From state';
            app.CombinationB_FromstateDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationB_FromstateDropDown.Items = {};
            app.CombinationB_FromstateDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationA_FromstateDropDownValueChanged, true);
            app.CombinationB_FromstateDropDown.Value = {};
            app.StateBLabel = uilabel(app.Combination_GridLayout);
            app.StateBLabel.Text = 'State B';
            app.CombinationA_DimensionLabel = uilabel(app.Combination_GridLayout);
            app.CombinationA_DimensionLabel.Text = 'Size';
            app.CombinationB_DimensionLabel = uilabel(app.Combination_GridLayout);
            app.CombinationB_DimensionLabel.Text = 'Size';
            app.ChoosetheoperationtoperformLabel = uilabel(app.Combination_GridLayout);
            app.ChoosetheoperationtoperformLabel.Text = '2) Choose the operation to perform';
            app.FromfileLabel = uilabel(app.Combination_GridLayout);
            app.FromfileLabel.Text = 'From file';
            app.CombinationA_FromfileDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationA_FromfileDropDown.Items = {};
            app.CombinationA_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationA_FromfileDropDownValueChanged, true);
            app.CombinationA_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @CombinationA_FromfileDropDownValueChanged, true);
            app.CombinationA_FromfileDropDown.Value = {};
            app.FromfileDropDownLabel = uilabel(app.Combination_GridLayout);
            app.FromfileDropDownLabel.Text = 'From file';
            app.CombinationB_FromfileDropDown = uidropdown(app.Combination_GridLayout);
            app.CombinationB_FromfileDropDown.Items = {};
            app.CombinationB_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinationB_FromfileDropDownValueChanged, true);
            app.CombinationB_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @CombinationB_FromfileDropDownValueChanged, true);
            app.CombinationB_FromfileDropDown.Value = {};
            app.Label_6 = uilabel(app.Combination_GridLayout);
            app.Label_6.Text = {'Combinations operations that you plan to use'; 'later in a macro should combine two states'; 'from the same file.'};
            app.Combination_StatutIdleLabel = uilabel(app.Combination_GridLayout);
            app.Combination_StatutIdleLabel.Text = 'Statut: Idle';
            app.MergelabelsofStateAtolabelsofStateBPanel = uipanel(app.Combination_GridLayout);
            app.MergelabelsofStateAtolabelsofStateBPanel.Title = 'Merge labels of State A to labels of State B';
            app.GridLayout5 = uigridlayout(app.MergelabelsofStateAtolabelsofStateBPanel);
            app.ThresholdratioEditFieldLabel = uilabel(app.GridLayout5);
            app.ThresholdratioEditFieldLabel.Text = 'Threshold ratio';
            app.ThresholdratioEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ThresholdratioEditField.Limits = [0 1];
            app.ThresholdratioEditField.Tooltip = {'For each label of state A, if the normalized (label-wize) number of voxels that belong to a label of state B is higher than this ratio, then this label will take the label of state B. Otherwise, it will be assigned to a new label.'};
            app.ThresholdratioEditField.Value = 0.6;
            app.Mergeonlyifitincreasecircularity2DonlybetaCheckBox = uicheckbox(app.GridLayout5);
            app.Mergeonlyifitincreasecircularity2DonlybetaCheckBox.Text = 'Merge only if it increase circularity (2D only, beta)';
            app.Combination_merge_DoButton = uibutton(app.GridLayout5, 'push');
            app.Combination_merge_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Combination_merge_DoButton.Tag = 'Merge';
            app.Combination_merge_DoButton.Text = 'Do';
            app.Combination_ArithmeticPanel = uipanel(app.Combination_GridLayout);
            app.Combination_ArithmeticPanel.Title = 'Arithmetic';
            app.GridLayout6 = uigridlayout(app.Combination_ArithmeticPanel);
            app.Combination_plus_DoButton = uibutton(app.GridLayout6, 'push');
            app.Combination_plus_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Combination_plus_DoButton.Tag = 'Addition';
            app.Combination_plus_DoButton.Text = 'A+B';
            app.Combination_multiply_DoButton = uibutton(app.GridLayout6, 'push');
            app.Combination_multiply_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Combination_multiply_DoButton.Tag = 'Multiplication';
            app.Combination_multiply_DoButton.Text = 'AxB';
            app.AxBonlycheckformissingvoxelsCheckBox = uicheckbox(app.GridLayout6);
            app.AxBonlycheckformissingvoxelsCheckBox.Tooltip = {'If a state type is "Segmented (instance)" and the other one is "Segmented (phase)", then voxels without instance label will be assigned to the nearest one.'};
            app.AxBonlycheckformissingvoxelsCheckBox.Text = '(AxB only) check for missing voxels';
            app.DifferenceButton = uibutton(app.GridLayout6, 'push');
            app.DifferenceButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.DifferenceButton.Tag = 'Difference';
            app.DifferenceButton.Text = 'A-B';
            app.Difference_DropDown = uidropdown(app.GridLayout6);
            app.Difference_DropDown.Items = {'A-B', '(A-B) / ((A+B)/2)', '(A-B) / <(A+B)/2)>', 'abs(A-B)', 'abs( (A-B) / ((A+B)/2) )', 'abs( (A-B) / <(A+B)/2)> )'};
            app.Difference_DropDown.Value = 'A-B';
            app.Plus_AmaxBCheckBox = uicheckbox(app.GridLayout6);
            app.Plus_AmaxBCheckBox.Text = 'A+max(B)';
            app.IndexbasedPanel = uipanel(app.Combination_GridLayout);
            app.IndexbasedPanel.Title = 'Index based';
            app.GridLayout7 = uigridlayout(app.IndexbasedPanel);
            app.ChoiceDropDownLabel = uilabel(app.GridLayout7);
            app.ChoiceDropDownLabel.Text = 'Choice';
            app.Combination_Index_ChoiceDropDown = uidropdown(app.GridLayout7);
            app.Combination_Index_ChoiceDropDown.Items = {'A(B==X)=Y', 'A(B~=X)=Y'};
            app.Combination_Index_ChoiceDropDown.Value = 'A(B==X)=Y';
            app.LabelYEditFieldLabel = uilabel(app.GridLayout7);
            app.LabelYEditFieldLabel.Text = 'Label Y';
            app.Combination_Index_LabelYEditField = uieditfield(app.GridLayout7, 'numeric');
            app.Combination_Index_LabelYEditField.Limits = [0 Inf];
            app.Combination_Index_LabelYEditField.RoundFractionalValues = 'on';
            app.LabelXEditFieldLabel = uilabel(app.GridLayout7);
            app.LabelXEditFieldLabel.Text = 'Label X';
            app.Combination_Index_LabelXEditField = uieditfield(app.GridLayout7, 'numeric');
            app.Combination_Index_LabelXEditField.Limits = [0 Inf];
            app.Combination_Index_LabelXEditField.RoundFractionalValues = 'on';
            app.Combination_index_DoButton = uibutton(app.GridLayout7, 'push');
            app.Combination_index_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Combination_index_DoButton.Tag = 'Index';
            app.Combination_index_DoButton.Text = 'Do';
            app.CombinationIndex_NewstateinworkflowACheckBox = uicheckbox(app.GridLayout7);
            app.CombinationIndex_NewstateinworkflowACheckBox.Tooltip = {'If checked, the new state will be in the selected workflow of file A. However it can break macro. To make it macro compatible, workflow of state B must be before workflow of state A. This option is mostly to improve visibility of the mosaic representation in  the "States" tab.'};
            app.CombinationIndex_NewstateinworkflowACheckBox.Text = 'New state in workflow A';
            app.SymmetryPanel = uipanel(app.Combination_GridLayout);
            app.SymmetryPanel.Tooltip = {'Background pixels will be filled using a symmetry transformation on the material domain'};
            app.SymmetryPanel.Title = 'Symmetry';
            app.GridLayout8 = uigridlayout(app.SymmetryPanel);
            app.AmaterialdomainBbackgrounddomain1background0otherwiseLabel = uilabel(app.GridLayout8);
            app.AmaterialdomainBbackgrounddomain1background0otherwiseLabel.Text = {'A: material domain B: background domain'; '(1=background, 0 otherwise)'};
            app.CombinationSymmetry_NewstateinworkflowACheckBox = uicheckbox(app.GridLayout8);
            app.CombinationSymmetry_NewstateinworkflowACheckBox.Text = 'New sate in Workflow A';
            app.Symmetry_DoButton = uibutton(app.GridLayout8, 'push');
            app.Symmetry_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Symmetry_DoButton.Tag = 'Symmetry';
            app.Symmetry_DoButton.Text = 'Do';
            app.Symmetry_Perslize3DonlyalongzCheckBox = uicheckbox(app.GridLayout8);
            app.Symmetry_Perslize3DonlyalongzCheckBox.Text = 'Per slize (3D only, along z)';
            app.HistogramequalizationPanel = uipanel(app.Combination_GridLayout);
            app.HistogramequalizationPanel.Title = 'Histogram equalization';
            app.GridLayout10 = uigridlayout(app.HistogramequalizationPanel);
            app.Label_22 = uilabel(app.GridLayout10);
            app.Label_22.Text = {'Transforms the grayscale image of A so that the histogram of the'; 'output grayscale image approximately matches the histogram of B'};
            app.DoButton = uibutton(app.GridLayout10, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.DoButton.Tag = 'Histogram';
            app.DoButton.Text = 'Do';
            app.MinmaxrangeotherwisedatarangeCheckBox = uicheckbox(app.GridLayout10);
            app.MinmaxrangeotherwisedatarangeCheckBox.Tooltip = {'If you apply this transformation on the volume itself (i.e. state A=state B), and if unchecked the output volume is the same as the input. If checked, the grey level will be translated.'};
            app.MinmaxrangeotherwisedatarangeCheckBox.Text = 'Min-max range (otherwise data range)';
            app.AssignvoxeltonearestclusterPanel = uipanel(app.Combination_GridLayout);
            app.AssignvoxeltonearestclusterPanel.Title = 'Assign voxel to nearest cluster';
            app.GridLayout14 = uigridlayout(app.AssignvoxeltonearestclusterPanel);
            app.ClustersarecalculatedonAThenLabel = uilabel(app.GridLayout14);
            app.ClustersarecalculatedonAThenLabel.Text = {'Voxels x for which A(x)~=0 but B(x)=0 are assigned to'; 'the nearest clusters calculated on B. '; 'Typical usage is particle identification with'; 'B = local erosion (A).'};
            app.DoButton_4 = uibutton(app.GridLayout14, 'push');
            app.DoButton_4.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.DoButton_4.Tag = 'Assign_to_nearest_cluster';
            app.DoButton_4.Text = 'Do';
            app.ConcatenatePanel = uipanel(app.Combination_GridLayout);
            app.ConcatenatePanel.Title = 'Concatenate';
            app.GridLayout19 = uigridlayout(app.ConcatenatePanel);
            app.AfirstthenBalongaxeDropDownLabel = uilabel(app.GridLayout19);
            app.AfirstthenBalongaxeDropDownLabel.Text = 'A first, then B, along axe';
            app.Concatenate_axisDown = uidropdown(app.GridLayout19);
            app.Concatenate_axisDown.Items = {'1', '2', '3'};
            app.Concatenate_axisDown.Value = '1';
            app.Combination_Concatenate_DoButton = uibutton(app.GridLayout19, 'push');
            app.Combination_Concatenate_DoButton.ButtonPushedFcn = createCallbackFcn(app, @Combination_plus_ButtonPushed, true);
            app.Combination_Concatenate_DoButton.Tag = 'Concatenate';
            app.Combination_Concatenate_DoButton.Text = 'Do';
            app.ComparisonsTab = uitab(app.MainTabGroup);
            app.ComparisonsTab.Title = 'Comparisons';

            app.ComparisonsTab.ButtonDownFcn = createCallbackFcn(app, @ComparisonsTabButtonDown, true);
            app.Comparison_GridLayout = uigridlayout(app.ComparisonsTab);
            app.SelectfilesLabel = uilabel(app.Comparison_GridLayout);
            app.SelectfilesLabel.Text = '1) Select files';
            app.FromfileDropDownLabel_3 = uilabel(app.Comparison_GridLayout);
            app.FromfileDropDownLabel_3.Text = 'From file';
            app.Comparison_FromfileDropDown = uidropdown(app.Comparison_GridLayout);
            app.Comparison_FromfileDropDown.Items = {};
            app.Comparison_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Comparison_FromfileDropDownValueChanged, true);
            app.Comparison_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @Comparison_FromfileDropDownValueChanged, true);
            app.Comparison_FromfileDropDown.Value = {};
            app.FromworkflowDropDownLabel_3 = uilabel(app.Comparison_GridLayout);
            app.FromworkflowDropDownLabel_3.Text = 'From workflow';
            app.Comparison_FromworkflowDropDown = uidropdown(app.Comparison_GridLayout);
            app.Comparison_FromworkflowDropDown.Items = {};
            app.Comparison_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @Comparison_FromworkflowDropDownValueChanged, true);
            app.Comparison_FromworkflowDropDown.Value = {};
            app.FromstateDropDown_2Label_2 = uilabel(app.Comparison_GridLayout);
            app.FromstateDropDown_2Label_2.Text = 'From state';
            app.Comparison_FromstateDropDown = uidropdown(app.Comparison_GridLayout);
            app.Comparison_FromstateDropDown.Items = {};
            app.Comparison_FromstateDropDown.Value = {};
            app.Comparison_AddfileButton = uibutton(app.Comparison_GridLayout, 'push');
            app.Comparison_AddfileButton.ButtonPushedFcn = createCallbackFcn(app, @Comparison_AddfileButtonPushed, true);
            app.Comparison_AddfileButton.Text = 'Add file';
            app.Comparison_UITable = uitable(app.Comparison_GridLayout);
            app.Comparison_UITable.ColumnName = {'File'; 'Workflow'; 'State#'; 'Size'; 'Data type'; 'Compare'};
            app.Comparison_UITable.RowName = {};
            app.Comparison_UITable.ColumnEditable = [false false false false false true];
            app.Comparison_UITable.CellEditCallback = createCallbackFcn(app, @Comparison_UITableCellEdit, true);
            app.SelectwhichfilestocompareLabel = uilabel(app.Comparison_GridLayout);
            app.SelectwhichfilestocompareLabel.Text = '2) Select which files to compare';
            app.SelectthetypeofcomparisonandvisualizeitLabel = uilabel(app.Comparison_GridLayout);
            app.SelectthetypeofcomparisonandvisualizeitLabel.Text = '3) Select the type of comparison and visualize it';
            app.ComparisontypeDropDownLabel = uilabel(app.Comparison_GridLayout);
            app.ComparisontypeDropDownLabel.Text = 'Comparison type';
            app.ComparisontypeDropDown = uidropdown(app.Comparison_GridLayout);
            app.ComparisontypeDropDown.Items = {'Mosaic', 'Segmentation'};
            app.ComparisontypeDropDown.Tooltip = {'To allow segmentation comparison, select one grey level volume and one or several segmented volume(s). They must share the same size.'};
            app.ComparisontypeDropDown.Value = 'Mosaic';
            app.Comparison_VisualizeButton = uibutton(app.Comparison_GridLayout, 'push');
            app.Comparison_VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @Comparison_VisualizeButtonPushed, true);
            app.Comparison_VisualizeButton.Text = 'Visualize';
            app.Comparison_CleartableButton = uibutton(app.Comparison_GridLayout, 'push');
            app.Comparison_CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @Comparison_CleartableButtonPushed, true);
            app.Comparison_CleartableButton.Text = 'Clear table';
            app.MacroTab = uitab(app.MainTabGroup);
            app.MacroTab.Title = 'Macro';
            app.Macro_GridLayout = uigridlayout(app.MacroTab);
            app.Label_4 = uilabel(app.Macro_GridLayout);
            app.Label_4.Text = {'Here, you can either create a macro and save it, or load a macro and apply it.'; '- Macros are saved in a .mat file as a structure. You can edit manually a macro by importing it in MATLAB if desired.'; '- You can apply a macro on the active state of the active workflow and file, or on all files on the same workflow and state (they must be all in memory). You can also apply a macro on all files, but without memory'; 'history: files will be imported sequentially, the macro will be apllied and the results will be saved in tif, then memory is cleared and the next file is imported (batch mode).'};
            app.Option1createamacroLabel = uilabel(app.Macro_GridLayout);
            app.Option1createamacroLabel.Text = 'Option 1: create a macro';
            app.Macro_AddastepButton = uibutton(app.Macro_GridLayout, 'push');
            app.Macro_AddastepButton.ButtonPushedFcn = createCallbackFcn(app, @Macro_AddastepButtonPushed, true);
            app.Macro_AddastepButton.Text = 'Add a step';
            app.FromworkflowDropDownLabel = uilabel(app.Macro_GridLayout);
            app.FromworkflowDropDownLabel.Text = 'From workflow';
            app.Macro_FromworkflowDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_FromworkflowDropDown.Items = {};
            app.Macro_FromworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_FromworkflowDropDownValueChanged, true);
            app.Macro_FromworkflowDropDown.ClickedFcn = createCallbackFcn(app, @Macro_FromworkflowDropDownValueChanged, true);
            app.Macro_FromworkflowDropDown.Value = {};
            app.StartatstateDropDownLabel = uilabel(app.Macro_GridLayout);
            app.StartatstateDropDownLabel.Text = 'Start at state#';
            app.Macro_StartatstateDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_StartatstateDropDown.Items = {};
            app.Macro_StartatstateDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_StartatstateDropDownValueChanged, true);
            app.Macro_StartatstateDropDown.Value = {};
            app.EndatstateDropDownLabel = uilabel(app.Macro_GridLayout);
            app.EndatstateDropDownLabel.Text = 'End at state#';
            app.Macro_EndatstateDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_EndatstateDropDown.Items = {};
            app.Macro_EndatstateDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_EndatstateDropDownValueChanged, true);
            app.Macro_EndatstateDropDown.Value = {};
            app.Macro_UITable = uitable(app.Macro_GridLayout);
            app.Macro_UITable.ColumnName = {'Step#'; 'File'; 'Workflow'; 'Start at state#'; 'End at state#'};
            app.Macro_UITable.RowName = {};
            app.ClearmacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.ClearmacroButton.ButtonPushedFcn = createCallbackFcn(app, @ClearmacroButtonPushed, true);
            app.ClearmacroButton.Text = 'Clear macro';
            app.SavemacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.SavemacroButton.ButtonPushedFcn = createCallbackFcn(app, @SavemacroButtonPushed, true);
            app.SavemacroButton.Text = 'Save macro';
            app.NameEditFieldLabel = uilabel(app.Macro_GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.MacroNameEditField = uieditfield(app.Macro_GridLayout, 'text');
            app.MacroNameEditField.Value = 'Macro1';
            app.Option2loadamacroLabel = uilabel(app.Macro_GridLayout);
            app.Option2loadamacroLabel.Text = 'Option 2: load a macro';
            app.ClicktoimportmacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.ClicktoimportmacroButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoimportmacroButtonPushed, true);
            app.ClicktoimportmacroButton.Text = 'Click to import macro';
            app.NomacroloadedLabel = uilabel(app.Macro_GridLayout);
            app.NomacroloadedLabel.Text = 'No macro loaded';
            app.ApplymacroButton = uibutton(app.Macro_GridLayout, 'push');
            app.ApplymacroButton.ButtonPushedFcn = createCallbackFcn(app, @ApplymacroButtonPushed, true);
            app.ApplymacroButton.Tooltip = {'It will create a new workflow from the state selected with the right popup menu.'};
            app.ApplymacroButton.Text = 'Apply macro ';
            app.ToworkflowDropDownLabel = uilabel(app.Macro_GridLayout);
            app.ToworkflowDropDownLabel.Text = 'To workflow';
            app.Macro_ToworkflowDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_ToworkflowDropDown.Items = {};
            app.Macro_ToworkflowDropDown.ValueChangedFcn = createCallbackFcn(app, @ToworkflowDropDownValueChanged, true);
            app.Macro_ToworkflowDropDown.ClickedFcn = createCallbackFcn(app, @ToworkflowDropDownValueChanged, true);
            app.Macro_ToworkflowDropDown.Value = {};
            app.TostateDropDownLabel = uilabel(app.Macro_GridLayout);
            app.TostateDropDownLabel.Text = 'To state#';
            app.Macro_TostateDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_TostateDropDown.Items = {};
            app.Macro_TostateDropDown.Value = {};
            app.NumberofstatesthatwillbecreatedwiththemacronaLabel = uilabel(app.Macro_GridLayout);
            app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Tooltip = {'You may have to increase the "Memory depth" parameter if the macro contains actions requiring old states.'};
            app.NumberofstatesthatwillbecreatedwiththemacronaLabel.Text = 'Number of states that will be created with the macro: n/a';
            app.FromfileDropDownLabel_2 = uilabel(app.Macro_GridLayout);
            app.FromfileDropDownLabel_2.Text = 'From file';
            app.Macro_FromfileDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_FromfileDropDown.Items = {};
            app.Macro_FromfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_FromfileDropDownValueChanged, true);
            app.Macro_FromfileDropDown.ClickedFcn = createCallbackFcn(app, @Macro_FromfileDropDownValueChanged, true);
            app.Macro_FromfileDropDown.Value = {};
            app.TofileDropDownLabel = uilabel(app.Macro_GridLayout);
            app.TofileDropDownLabel.Text = 'To file';
            app.Macro_TofileDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_TofileDropDown.Items = {};
            app.Macro_TofileDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_TofileDropDownValueChanged, true);
            app.Macro_TofileDropDown.ClickedFcn = createCallbackFcn(app, @Macro_TofileDropDownValueChanged, true);
            app.Macro_TofileDropDown.Value = {};
            app.ToLabel = uilabel(app.Macro_GridLayout);
            app.ToLabel.Text = 'To:';
            app.Macro_ToOptionsDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_ToOptionsDropDown.Items = {'Apply to one file', 'Apply to all files from same workflow and same state', 'Apply to all files (batch mode)'};
            app.Macro_ToOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @Macro_ToOptionsDropDownValueChanged, true);
            app.Macro_ToOptionsDropDown.Value = 'Apply to one file';
            app.SaveLabel = uilabel(app.Macro_GridLayout);
            app.SaveLabel.Text = 'Save:';
            app.Macro_SavDropDown = uidropdown(app.Macro_GridLayout);
            app.Macro_SavDropDown.Items = {'Save all states for all files', 'Save only final state for each file', 'Save all marked states', 'Do not save automatically'};
            app.Macro_SavDropDown.Value = 'Save all states for all files';
            app.CombinationoperationsfromdifferentfilesinamacroLabel = uilabel(app.Macro_GridLayout);
            app.CombinationoperationsfromdifferentfilesinamacroLabel.Text = {'Combination operations from different files saved in a macro are treated as from same file. '; 'For coherence, combinations operations that you plan to use in a macro should combine two states from the same file.'; ''; 'Do not create a macro from a loaded macro.'};
            app.Macro_CurrentfileLabel = uilabel(app.Macro_GridLayout);
            app.Macro_CurrentfileLabel.Text = 'Current file:';
            app.Macro_CurrentactionLabel = uilabel(app.Macro_GridLayout);
            app.Macro_CurrentactionLabel.Text = 'Current action:';
            app.Macro_currentifle_name_Label = uilabel(app.Macro_GridLayout);
            app.Macro_currentifle_name_Label.Text = 'n/a';
            app.Macro_currentaction_name_Label = uilabel(app.Macro_GridLayout);
            app.Macro_currentaction_name_Label.Text = 'n/a';
            app.Macro_IdleLabel = uilabel(app.Macro_GridLayout);
            app.Macro_IdleLabel.Text = 'Idle';
            app.MemorydepthEditField_2Label = uilabel(app.Macro_GridLayout);
            app.MemorydepthEditField_2Label.Text = 'Memory depth';
            app.MemorydepthEditField_2 = uieditfield(app.Macro_GridLayout, 'numeric');
            app.MemorydepthEditField_2.Limits = [1 Inf];
            app.MemorydepthEditField_2.RoundFractionalValues = 'on';
            app.MemorydepthEditField_2.ValueChangedFcn = createCallbackFcn(app, @MemorydepthEditField_2ValueChanged, true);
            app.MemorydepthEditField_2.Value = 10;
            app.Macro_AddacalculationButton = uibutton(app.Macro_GridLayout, 'state');
            app.Macro_AddacalculationButton.ValueChangedFcn = createCallbackFcn(app, @Macro_AddacalculationButtonValueChanged, true);
            app.Macro_AddacalculationButton.Text = 'Add a calculation';
            app.Macro_CalculationmodeLabel = uilabel(app.Macro_GridLayout);
            app.Macro_CalculationmodeLabel.Text = '';
            app.Macro_CurrentcalculationLabel = uilabel(app.Macro_GridLayout);
            app.Macro_CurrentcalculationLabel.Text = 'Current calculation:';
            app.Macro_currentcalc_naLabel = uilabel(app.Macro_GridLayout);
            app.Macro_currentcalc_naLabel.Text = 'n/a';
            app.EditmacroadvancedusersButton = uibutton(app.Macro_GridLayout, 'push');
            app.EditmacroadvancedusersButton.ButtonPushedFcn = createCallbackFcn(app, @EditmacroadvancedusersButtonPushed, true);
            app.EditmacroadvancedusersButton.Tooltip = {'You will have to import the modified macro to use it.'};
            app.EditmacroadvancedusersButton.Text = 'Edit macro (advanced users)';
            app.MacroWarningLabel = uilabel(app.Macro_GridLayout);
            app.MacroWarningLabel.Visible = 'off';
            app.MacroWarningLabel.Text = 'Warning';
            app.HelpTab = uitab(app.MainTabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout4 = uigridlayout(app.HelpTab);
            app.OperationsTextAreaLabel = uilabel(app.GridLayout4);
            app.OperationsTextAreaLabel.Text = 'Operations';
            app.OperationsTextArea = uitextarea(app.GridLayout4);
            app.OperationsTextArea.Editable = 'off';
            app.OperationsTextArea.Value = {'There are 3 types of operations:'; '- Actions: all operations that will create a new state from the analysis of 1 existing state (e.g., cropping, segmentation).'; '- Calculations: all operations that do not modify a state but provide new information (e.g., grey level histogram).'; '- Combinations: all operations that will create a new state in a new workflow from the analysis of 2 existing states from any workflow from any files. A combination from 2 states from the same file will result in a new state in a new workflow. A combination from 2 states from 2 different files will result in a new state in a new file.'};
            app.StatesTextAreaLabel = uilabel(app.GridLayout4);
            app.StatesTextAreaLabel.Text = 'States';
            app.StatesTextArea = uitextarea(app.GridLayout4);
            app.StatesTextArea.Editable = 'off';
            app.StatesTextArea.Value = {'There are 3 levels of hierarchy: files, workflows, and states:'; '- Files: all initial 2D images or 3D volumes. Files are imported in the "import" tab. Each file contains one or several workflows. Files when imported start with 1 workflow of 1 state.'; '- Workflow: each workflow contains one or several states. An action performed in the active state of the active workflow of the active file will create a new active state in the active workflow of the active file. If the active state was not the last state in the active workflow, the new active state overwrittes all subsequent states. User can create a new workflow for the active file, initiliazed with 1 state picked from any workflow of the active file. Using different workflows allows for a non-linear segmentation process. States from different workflows and active files can be then combined/merged using a "Combinations" operations.'; '- States: each state is a 2D or 3D array, grey level, segmented, or channel. Operations are performed on states. '; 'In the "States" Tab, user can select the active file, active workflow, and active state. You can select a previous state if still in memory (i.e., Undo)'};
            app.Label_13 = uilabel(app.GridLayout4);
            app.Label_13.Text = 'If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink_4 = uihyperlink(app.GridLayout4);
            app.Hyperlink_4.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_4.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.HowtoTextAreaLabel = uilabel(app.GridLayout4);
            app.HowtoTextAreaLabel.Text = 'How-to';
            app.HowtoTextArea = uitextarea(app.GridLayout4);
            app.HowtoTextArea.Editable = 'off';
            app.HowtoTextArea.Value = {'You can import:'; '- a single file.'; '- multiple files. In this case, you can either apply an operation in the different files (i) manually, by selecting each file and then applying the desired operation, or (ii) automatically and sequentially on all files (see "Actions/Multiple files option" tab).'; ''; 'You can work using:'; '- a single workflow per file. This corresponds to a single-path segmentation process: e.g., state 1 -> state 2 -> state 3, etc..'; '- several workflows per file. This corresponds to a multiple-paths (or non-linear) segmentation process: e.g.,'; '  * workflow A: state A1 -> state A2 -> state A3'; '  * workflow B: state B1=A2 (initialized from any other state) -> state B2 -> state B3'; '  * workflow C: state C1=combinations(A3,B3).'; ''; 'You can create a macro from past operations (including combinations), and later edit it in MATLAB if desired. Macros can be built using different workflows from different files. You can load a macro and apply it to a single file or all files. If you need to segment multiple files that will not fit in the computer RAM, you can choose to "Do not load all files at once" in the import tab. In this last case, the only possible interaction in the app will be to load a macro and apply it to all files sequentially without keeping in memory the previous file (i.e., import/apply macro/save/clear memory/import new file/apply macro/etc.'};
            app.DatatypeTextAreaLabel = uilabel(app.GridLayout4);
            app.DatatypeTextAreaLabel.Text = 'Data type';
            app.DatatypeTextArea = uitextarea(app.GridLayout4);
            app.DatatypeTextArea.Value = {'There are 4 different data type this module can handle:'; '- grey level (i.e., brightness levels).'; '- segmented (phase): binarized images with labels indicating the domain (pore, solid 1, solid 2, etc.)'; '- segmented (instance): binarized images with labels indicating the instance within a domain (particle 1, particle 2, particle 3, etc. from solid 1)'; '- channel: properties calculated from the brightness level, e.g., standard deviation or gradient.'};
            app.VisualizationTextAreaLabel = uilabel(app.GridLayout4);
            app.VisualizationTextAreaLabel.Text = 'Visualization';
            app.VisualizationTextArea = uitextarea(app.GridLayout4);
            app.VisualizationTextArea.Value = {'You can visualize volume(s)  in different ways:'; '- Sates tab: double click on the table to display a single volume. Click on ''Create image mosaic of all states in memory'' to have an overview of all files and their workflow organization, with their middle slice plotted.'; '- Actions/Region of interest: you will see the cropping or rotations parameters overlaid on top of the volume to help you choose your parameters.'; '- Actions/Segmentation/Semantic segmentation: click on the manual segmentation button to viusalize impact of thesholds and help you choose the relevant ones.'; '- Comparisons tab: select volumes you want to compare. If one file is a grey level, and all other files are segmented, you can also check the segmentation (e.g., overlay)'};
            app.HowtoquoteTab = uitab(app.MainTabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout3 = uigridlayout(app.HowtoquoteTab);
            app.Label_11 = uilabel(app.GridLayout3);
            app.Label_11.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label_12 = uilabel(app.GridLayout3);
            app.Label_12.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink_3 = uihyperlink(app.GridLayout3);
            app.Hyperlink_3.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_3.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.IfyouuselocalerositionorlocaldilationActionsLabel = uilabel(app.GridLayout3);
            app.IfyouuselocalerositionorlocaldilationActionsLabel.Text = 'If you use local erosition or local dilation (Actions / Erosion dilation), please ALSO quote:';
            app.Label_39 = uilabel(app.GridLayout3);
            app.Label_39.Text = {'F. L. E. Usseglio-Viretta, P. Gasper, N. Prakash, M. Popeil, K. Smith, and D. P. Finegan'; 'Concavity-based local erosion and sphere-size-based local dilation applied to lithium-ion battery electrode microstructures for particle identification'; 'Computational Materials Science, 251, 2025'};
            app.Hyperlink_6 = uihyperlink(app.GridLayout3);
            app.Hyperlink_6.URL = 'https://doi.org/10.1016/j.commatsci.2025.113758';
            app.Hyperlink_6.Text = 'https://doi.org/10.1016/j.commatsci.2025.113758';
            app.IfyouuselocalerositionorlocaldilationActionsLabel_2 = uilabel(app.GridLayout3);
            app.IfyouuselocalerositionorlocaldilationActionsLabel_2.Text = 'If you use Pseudo-Coulomb Repulsive Field (PCRF) (Actions / Segmantation / Instance segmentation), please ALSO quote:';
            app.Label_40 = uilabel(app.GridLayout3);
            app.Label_40.Text = {'F. L. E. Usseglio-Viretta, D. P. Finegan, A. Colclasure, T. M. M. Heenan, D. Abraham, P. Shearing and K. Smith'; 'Quantitative Relationships Between Pore Tortuosity, Pore Topology, and Solid Particle Morphology Using a Novel Discrete Particle Size Algorithm'; 'Journal of the Electrochemical Society, 167, 2020'};
            app.Hyperlink_7 = uihyperlink(app.GridLayout3);
            app.Hyperlink_7.URL = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.Hyperlink_7.Text = 'https://doi.org/10.1149/1945-7111/ab913b';
            app.AboutTab = uitab(app.MainTabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout2 = uigridlayout(app.AboutTab);
            app.Label_7 = uilabel(app.GridLayout2);
            app.Label_7.Text = 'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion (Li-ion) batteries and other 3D heterogeneous materials.';
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = 'The segmentation module and its graphic user-interface have been developed by Francois Usseglio-Viretta (NREL) with some help from Elizabeth Bernhardt (NREL) on contrast correction.';
            app.NREL_logo = uiimage(app.GridLayout2);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink.Text = 'MATBOX official webpage';
            app.Hyperlink_2 = uihyperlink(app.GridLayout2);
            app.Hyperlink_2.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_2.Text = 'MATBOX GitHub repository';
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = 'If you like this tool, please consider ''Star'' the GitHub repository to increase its visbility.';
            app.Label_10 = uilabel(app.GridLayout2);
            app.Label_10.Text = 'You can register there (optional). You will be notified when major updates are released.';
            app.ToDoCalculationsgreylevelCalculationssegmenteddataLabel = uilabel(app.ImagesegmentationUIFigure);
            app.ToDoCalculationsgreylevelCalculationssegmenteddataLabel.Text = {'To Do:'; 'Actions: segmentation sensitivity'; ''};
            app.SortpersizeRandomizeconnectivityLabel = uilabel(app.ImagesegmentationUIFigure);
            app.SortpersizeRandomizeconnectivityLabel.Text = {'Sort per size'; 'Randomize'; 'connectivity'};
            app.Label2_8 = uilabel(app.ImagesegmentationUIFigure);
            app.Label2_8.Text = {'Tracer x+ x+ xy+ direction etc... '; 'If within a small c-PSD pore, how many tracer can reach a large pore'; 'if a lot: this is not a crack, if very few, this is an open porosity crack'};
            % Show the figure after all components are created
            app.ImagesegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segmentation3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImagesegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImagesegmentationUIFigure)
        end
    end
end