classdef Main_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructureAnalysisToolboxUIFigure  matlab.ui.Figure
        TodoCorrelationcompatiblewithsemanticandinstanceLabel  matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        RequirementsLabel               matlab.ui.control.Label
        Hyperlink5                      matlab.ui.control.Hyperlink
        NRELSoftwareRecordofInventionSWR2076Label  matlab.ui.control.Label
        InstalledversionLabel           matlab.ui.control.Label
        CheckonlineversionButton        matlab.ui.control.Button
        OnlineversioncheckLabel         matlab.ui.control.Label
        Localversion                    matlab.ui.control.Label
        MicrostructureAnalysisToolboxMATBOXLabel  matlab.ui.control.Label
        ModulesPanel                    matlab.ui.container.Panel
        GridLayout_modules              matlab.ui.container.GridLayout
        Image2                          matlab.ui.control.Image
        Image_library                   matlab.ui.control.Image
        Image_meshing                   matlab.ui.control.Image
        Image_segmentation              matlab.ui.control.Image
        Image_generation                matlab.ui.control.Image
        Image_characterization          matlab.ui.control.Image
        Image_correlation               matlab.ui.control.Image
        Image_visualization             matlab.ui.control.Image
        ImagelibraryButton              matlab.ui.control.Button
        GenerationsubmoduleDropDown     matlab.ui.control.DropDown
        MeshingadditionalrequirementIso2meshButton  matlab.ui.control.Button
        VisualizationButton             matlab.ui.control.Button
        ParticlecharacterizationButton  matlab.ui.control.Button
        MicrostructurepropertiescorrelationButton  matlab.ui.control.Button
        ROIandsegmentationButton        matlab.ui.control.Button
        MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton  matlab.ui.control.Button
        MicrostructuregenerationButton  matlab.ui.control.Button
        RessourcesPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Hyperlink_register              matlab.ui.control.Hyperlink
        Hyperlink4_2                    matlab.ui.control.Hyperlink
        Hyperlink4                      matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink_repo                  matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GenerationsubmoduleDropDownValueChanged;
            app.OnlineversioncheckLabel.Visible = 'off';
            
            % Check image processing toolbox is installed
            is_installed_imagetoolbox = license('test','Image_Toolbox');
            v = version('-date');
            v_year = str2num(v(end-3:end));
            if v_year >= 2025 && is_installed_imagetoolbox
                app.RequirementsLabel.Text = 'Requirements: MATLAB 2025a or higher and image processing toolbox (OK)';
                app.RequirementsLabel.FontColor = [0 166 0]/255;
            elseif v_year >= 2025 && ~is_installed_imagetoolbox
                app.RequirementsLabel.Text = 'Requirements: MATLAB 2025a (PASS) or higher and image processing toolbox (FAIL)';
                app.RequirementsLabel.FontColor = [0 166 0]/255;
            elseif v_year < 2025 && is_installed_imagetoolbox
                app.RequirementsLabel.Text = 'Requirements: MATLAB 2025a (FAIL) or higher and image processing toolbox (PASS)';
                app.RequirementsLabel.FontColor = [0 166 0]/255;          
            elseif v_year < 2025 && ~is_installed_imagetoolbox
                app.RequirementsLabel.Text = 'Requirements: MATLAB 2025a or higher (FAIL) and image processing toolbox (FAIL)';
                app.RequirementsLabel.FontColor = 'r';                
            end
            app.Hyperlink_register.Tooltip = [app.Hyperlink_register.URL,app.Hyperlink_register.Tooltip];
            app.Hyperlink_repo.Tooltip = [app.Hyperlink_repo.URL,app.Hyperlink_repo.Tooltip];

            app.Image_characterization.Tooltip = app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton.Tooltip;
            app.Image_correlation.Tooltip = app.MicrostructurepropertiescorrelationButton.Tooltip;
            app.Image_visualization.Tooltip = app.VisualizationButton.Tooltip;
            app.Image_segmentation.Tooltip = app.ROIandsegmentationButton.Tooltip;
            app.Image_meshing.Tooltip = app.MeshingadditionalrequirementIso2meshButton.Tooltip;
            app.Image_library.Tooltip = app.ImagelibraryButton.Tooltip;    
        end

        % Value changed function: GenerationsubmoduleDropDown
        function GenerationsubmoduleDropDownValueChanged(app, event)
            value = app.GenerationsubmoduleDropDown.Value;
            if strcmp(value,'Ellipsoid-based stochastic')
                app.Image_generation.ImageSource = 'Icon_generation.png';
                app.MicrostructuregenerationButton.Tooltip = 'Stochastic numerical generation. N-phase material. Ellipsoid-based with control of particle overlapping (to overcome packing density limit). Control volume fractions, particle size, particle elongation, and particle rotation gradation along thickness to create multi-layer materials (i.e., through-plane heterogeneity). Set particle size, particle elongation, and particle rotation distribution with histograms (in-plane heterogeneity). Use mask to prevent particle generation within certain regions of space.';
            elseif strcmp(value,'Tube-based stochastic')
                app.Image_generation.ImageSource = 'Icon_tubegeneration.png';
                app.MicrostructuregenerationButton.Tooltip = 'Stochastic numerical generation. 1-phase material. Tube-based with no overlapping. Control tube radius, cross section shape, initial and evolving orientations. Use mask to prevent tubes to go through certain regions of space.';
            elseif strcmp(value,'Web structure')
                app.Image_generation.ImageSource = 'Icon_webgeneration.png';
                app.MicrostructuregenerationButton.Tooltip = 'Deterministic numerical generation. 1-phase material. Node locations is provided. Chords connecting nodes are created. Control node and chord radius, control number of connection (coordination number).';
            elseif strcmp(value,'Additives (distance, energetic, or coating method)')
                app.Image_generation.ImageSource = 'Icon_generation_additive.png';
                app.MicrostructuregenerationButton.Tooltip = 'Use three different additive generation algorithms (distance-based, energetic-based, simple coating) to investigate large design space.';
            elseif strcmp(value,'Surface roughness (stochastic or grain)')
                app.Image_generation.ImageSource = 'Icon_generation_surfaceroughness.png';
                app.MicrostructuregenerationButton.Tooltip = 'Iterative stochastic upscaled local dilatation and erosion followed by a connectivity step, or grain generation';
            elseif strcmp(value,'Quartet Structure Generation (WIP)')
               app.Image_generation.ImageSource = zeros(50,50,3);
               app.MicrostructuregenerationButton.Tooltip = '';
            end
            app.Image_generation.Tooltip = app.MicrostructuregenerationButton.Tooltip;
        end

        % Callback function: Image_generation,
        % MicrostructuregenerationButton
        function MicrostructuregenerationButtonPushed(app, event)
            choice = app.GenerationsubmoduleDropDown.Value;
            if strcmp(choice,'Ellipsoid-based stochastic')
                Microstructure_generation_stochastic
            elseif strcmp(choice,'Tube-based stochastic')                
                Microstructure_generation_tubes
            elseif strcmp(choice,'Web structure')                
                Microstructure_generation_web
            elseif strcmp(choice,'Additives (distance, energetic, or coating method)')
                Microstructure_generation_additives
            elseif strcmp(choice,'Deterministic geometries')
                Microstructure_generation_deterministic
            elseif strcmp(choice,'Surface roughness (stochastic or grain)')
                Microstructure_generation_surfaceroughness
            elseif strcmp(choice,'Quartet Structure Generation (WIP)')
                % QSGS % Next version
            end
        end

        % Callback function: Image_segmentation, ROIandsegmentationButton
        function ROIandsegmentationButtonPushed(app, event)
            Segmentation3
        end

        % Callback function: Image_characterization, 
        % ...and 1 other component
        function MicrostructurecharacterizationButtonPushed(app, event)
            % choice = app.VersionDropDown.Value;
            % if strcmp(choice,'Legacy version')
            %     Microstructure_characterization
            % elseif strcmp(choice,'New version (WIP)')
            %     Microstructure_characterization2
            % end
            Microstructure_characterization2
        end

        % Callback function: Image_visualization, VisualizationButton
        function VisualizationButtonPushed(app, event)
            microstructure_visualization_v3
        end

        % Callback function: Image_correlation, 
        % ...and 1 other component
        function MicrostructurepropertiescorrelationButtonPushed(app, event)
            Properties_correlation_main
        end

        % Callback function: Image_meshing, 
        % ...and 1 other component
        function MeshingButtonPushed(app, event)
            microstructure_meshing
        end

        % Callback function: Image_library, ImagelibraryButton
        function ImagelibraryButtonPushed(app, event)
            Microstructure_library
        end

        % Button pushed function: ParticlecharacterizationButton
        function ParticlecharacterizationButtonPushed(app, event)
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            web('https://www.nlr.gov/transportation/energy-storage.html');
        end

        % Button pushed function: CheckonlineversionButton
        function CheckonlineversionButtonPushed(app, event)
            localversion = app.Localversion.Text;
            app.OnlineversioncheckLabel.Visible = 'on';
            app.OnlineversioncheckLabel.FontColor = 'k';
            app.OnlineversioncheckLabel.Text = 'Please wait...'; pause(0.1);
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox/master/Version_number.txt');
                githubversionfile(end)=[]; % End of line
                if ~strcmp(localversion,githubversionfile)
                    app.OnlineversioncheckLabel.Text = ['There is a new version (' githubversionfile ') available online. Please go to the Github repositorty to update.'];
                    app.OnlineversioncheckLabel.FontColor = 'r';
                else
                    app.OnlineversioncheckLabel.Text = 'You are using the up-to-date version of MATBOX';
                    app.OnlineversioncheckLabel.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.OnlineversioncheckLabel.Text = 'Could not check current version online.';
                end
            end
            % Sometimes the catch ME fails
            if strcmp(app.OnlineversioncheckLabel.Text,'Please wait...')
                app.OnlineversioncheckLabel.Text = 'Could not check current version online.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructureAnalysisToolboxUIFigure = uifigure('Visible', 'off');
            app.MicrostructureAnalysisToolboxUIFigure.Name = 'Microstructure Analysis Toolbox';
            app.MicrostructureAnalysisToolboxUIFigure.Icon = fullfile(pathToMLAPP, 'Icon.png');
            app.GridLayout = uigridlayout(app.MicrostructureAnalysisToolboxUIFigure);
            app.RessourcesPanel = uipanel(app.GridLayout);
            app.RessourcesPanel.Title = 'Ressources';
            app.GridLayout2 = uigridlayout(app.RessourcesPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_NLR.png');
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://www.nlr.gov/transportation/matbox.html';
            app.Hyperlink.Text = 'About';
            app.Hyperlink_repo = uihyperlink(app.GridLayout2);
            app.Hyperlink_repo.Tooltip = {'If you like this tool, please consider ''Star'' the GitHub repository to increase its visbility.'};
            app.Hyperlink_repo.URL = 'https://github.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_repo.Text = 'GitHub repository';
            app.Hyperlink3 = uihyperlink(app.GridLayout2);
            app.Hyperlink3.URL = 'https://github.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink3.Text = 'Submit an issue/bug report';
            app.Hyperlink4 = uihyperlink(app.GridLayout2);
            app.Hyperlink4.Enable = 'off';
            app.Hyperlink4.Text = 'Help: documenation';
            app.Hyperlink4_2 = uihyperlink(app.GridLayout2);
            app.Hyperlink4_2.Enable = 'off';
            app.Hyperlink4_2.Text = {'Help: tutorial videos'; ''};
            app.Hyperlink_register = uihyperlink(app.GridLayout2);
            app.Hyperlink_register.Tooltip = {'You will be notified when a new updated is released.'};
            app.Hyperlink_register.URL = 'https://www.nlr.gov/transportation/matbox-registration';
            app.Hyperlink_register.Text = 'Register (optional)';
            app.ModulesPanel = uipanel(app.GridLayout);
            app.ModulesPanel.Title = 'Modules';
            app.GridLayout_modules = uigridlayout(app.ModulesPanel);
            app.MicrostructuregenerationButton = uibutton(app.GridLayout_modules, 'push');
            app.MicrostructuregenerationButton.ButtonPushedFcn = createCallbackFcn(app, @MicrostructuregenerationButtonPushed, true);
            app.MicrostructuregenerationButton.Text = 'Microstructure generation';
            app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton = uibutton(app.GridLayout_modules, 'push');
            app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton.ButtonPushedFcn = createCallbackFcn(app, @MicrostructurecharacterizationButtonPushed, true);
            app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton.Enable = 'off';
            app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton.Tooltip = {'Calculate classic properties for battery and fuel cells macromodels: volume fraction, connectivity, specific surface and interface area, triple phase boundary length, particle diameter and tortuosity factor. Choose among a set of different numerical methods to finely evaluate properties value. Perform automated Representative Volume Element (RVE) analysis and image resolution sensitivity analysis to estimate error induced by limited field of view and image resolution.'};
            app.MicrostructurecharacterizationadditionalrequirementTauFactor2CurrentlybeingrefactoredUsepreviousversioninthemeantimeButton.Text = {'Microstructure characterization'; '(additional requirement: TauFactor2)'; 'Currently being refactored.'; 'Use previous version in the meantime'};
            app.ROIandsegmentationButton = uibutton(app.GridLayout_modules, 'push');
            app.ROIandsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ROIandsegmentationButtonPushed, true);
            app.ROIandsegmentationButton.Tooltip = {'Region of interest selection and up/down scaling. Evaluate image quality, enhance contrast, and apply noise-reduction filter to improve segmentation. Apply semantic and instance segmentations. Create complex non-linear segmentation workflows and save them in re-usable macros.'};
            app.ROIandsegmentationButton.Text = 'ROI and segmentation';
            app.MicrostructurepropertiescorrelationButton = uibutton(app.GridLayout_modules, 'push');
            app.MicrostructurepropertiescorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @MicrostructurepropertiescorrelationButtonPushed, true);
            app.MicrostructurepropertiescorrelationButton.Tooltip = {'Import microstructure parameters calculated with the microstructure characterization module obtained with different volumes and plot them as a function of each other to estimate their correlation, if any. Investigate correlations per volume (generic correlation) or per group of volumes (microstructure-specific correlation). Custom correlations (Bruggeman, 3/R) are preconfigured.'};
            app.MicrostructurepropertiescorrelationButton.Text = 'Microstructure properties correlation';
            app.ParticlecharacterizationButton = uibutton(app.GridLayout_modules, 'push');
            app.ParticlecharacterizationButton.ButtonPushedFcn = createCallbackFcn(app, @ParticlecharacterizationButtonPushed, true);
            app.ParticlecharacterizationButton.Enable = 'off';
            app.ParticlecharacterizationButton.Text = 'Particle characterization';
            app.VisualizationButton = uibutton(app.GridLayout_modules, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.VisualizationButton.Tooltip = {'Visualize microstructures with 3D orthogonal slice representation. Compare grey-level and segmented volumes using overlay or checkerboard pattern. Load pixel-wise defined results calculated with the microstructure characterisation module and visualize them (e.g., particle size or connectivity). Create animation videos to share in presentations.'};
            app.VisualizationButton.Text = 'Visualization';
            app.MeshingadditionalrequirementIso2meshButton = uibutton(app.GridLayout_modules, 'push');
            app.MeshingadditionalrequirementIso2meshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshingButtonPushed, true);
            app.MeshingadditionalrequirementIso2meshButton.Tooltip = {'Generate tetrahedron-based mesh(es) of multi-phases (pore, and solids) and multi-domains (current collectors, anode, separator, cathode). Choose between structured and unstructured mesh methods.'};
            app.MeshingadditionalrequirementIso2meshButton.Text = {'Meshing'; '(additional requirement: Iso2mesh)'};
            app.GenerationsubmoduleDropDown = uidropdown(app.GridLayout_modules);
            app.GenerationsubmoduleDropDown.Items = {'Ellipsoid-based stochastic', 'Tube-based stochastic', 'Web structure', 'Additives (distance, energetic, or coating method)', 'Surface roughness (stochastic or grain)'};
            app.GenerationsubmoduleDropDown.ValueChangedFcn = createCallbackFcn(app, @GenerationsubmoduleDropDownValueChanged, true);
            app.GenerationsubmoduleDropDown.Value = 'Ellipsoid-based stochastic';
            app.ImagelibraryButton = uibutton(app.GridLayout_modules, 'push');
            app.ImagelibraryButton.ButtonPushedFcn = createCallbackFcn(app, @ImagelibraryButtonPushed, true);
            app.ImagelibraryButton.Tooltip = {'Create metadata and sort images based on their metadata.'};
            app.ImagelibraryButton.Text = 'Image library';
            app.Image_visualization = uiimage(app.GridLayout_modules);
            app.Image_visualization.ImageClickedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.Image_visualization.ImageSource = fullfile(pathToMLAPP, 'Icon_visualization_2.png');
            app.Image_correlation = uiimage(app.GridLayout_modules);
            app.Image_correlation.ImageClickedFcn = createCallbackFcn(app, @MicrostructurepropertiescorrelationButtonPushed, true);
            app.Image_correlation.ImageSource = fullfile(pathToMLAPP, 'Correlation.png');
            app.Image_characterization = uiimage(app.GridLayout_modules);
            app.Image_characterization.ImageClickedFcn = createCallbackFcn(app, @MicrostructurecharacterizationButtonPushed, true);
            app.Image_characterization.ImageSource = fullfile(pathToMLAPP, 'Icon_Characterization.png');
            app.Image_generation = uiimage(app.GridLayout_modules);
            app.Image_generation.ImageClickedFcn = createCallbackFcn(app, @MicrostructuregenerationButtonPushed, true);
            app.Image_segmentation = uiimage(app.GridLayout_modules);
            app.Image_segmentation.ImageClickedFcn = createCallbackFcn(app, @ROIandsegmentationButtonPushed, true);
            app.Image_segmentation.ImageSource = fullfile(pathToMLAPP, 'Icon_ROI.png');
            app.Image_meshing = uiimage(app.GridLayout_modules);
            app.Image_meshing.ImageClickedFcn = createCallbackFcn(app, @MeshingButtonPushed, true);
            app.Image_meshing.ImageSource = fullfile(pathToMLAPP, 'Icon_Meshing.png');
            app.Image_library = uiimage(app.GridLayout_modules);
            app.Image_library.ImageClickedFcn = createCallbackFcn(app, @ImagelibraryButtonPushed, true);
            app.Image_library.ImageSource = fullfile(pathToMLAPP, 'Icon_image_library.png');
            app.Image2 = uiimage(app.GridLayout_modules);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Icon_particlechara.png');
            app.MicrostructureAnalysisToolboxMATBOXLabel = uilabel(app.GridLayout);
            app.MicrostructureAnalysisToolboxMATBOXLabel.Text = 'Microstructure Analysis Toolbox (MATBOX)';
            app.Localversion = uilabel(app.GridLayout);
            app.Localversion.Text = '2.0f Beta May 2026';
            app.OnlineversioncheckLabel = uilabel(app.GridLayout);
            app.OnlineversioncheckLabel.Text = 'Online version check';
            app.CheckonlineversionButton = uibutton(app.GridLayout, 'push');
            app.CheckonlineversionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckonlineversionButtonPushed, true);
            app.CheckonlineversionButton.Text = 'Check online version';
            app.InstalledversionLabel = uilabel(app.GridLayout);
            app.InstalledversionLabel.Text = 'Installed version:';
            app.NRELSoftwareRecordofInventionSWR2076Label = uilabel(app.GridLayout);
            app.NRELSoftwareRecordofInventionSWR2076Label.Text = 'NREL Software Record of Invention: SWR-20-76';
            app.Hyperlink5 = uihyperlink(app.GridLayout);
            app.Hyperlink5.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/blob/master/License.txt';
            app.Hyperlink5.Text = 'See BSD Licence';
            app.RequirementsLabel = uilabel(app.GridLayout);
            app.RequirementsLabel.Text = 'MATLAB 2025a and image processing toolbox are required';
            app.TodoCorrelationcompatiblewithsemanticandinstanceLabel = uilabel(app.MicrostructureAnalysisToolboxUIFigure);
            app.TodoCorrelationcompatiblewithsemanticandinstanceLabel.Text = {'To do:'; '- Correlation: compatible with semantic and instance characterization'; '- Correlation: tag'; '- Generation: Quartet Structure Generation'; '- Particle characterization'};
            % Show the figure after all components are created
            app.MicrostructureAnalysisToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructureAnalysisToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructureAnalysisToolboxUIFigure)
        end
    end
end