classdef Visualization_multiple_volumes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VolumesvisualizerUIFigure       matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ShowTitle_DropDown              matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        ShowmultipleslicesatonceEditField  matlab.ui.control.NumericEditField
        ShowmultipleslicesatonceEditFieldLabel  matlab.ui.control.Label
        UpdatewhilemovingsliderCheckBox  matlab.ui.control.CheckBox
        TitlefontsizeEditField          matlab.ui.control.NumericEditField
        TitlefontsizeEditFieldLabel     matlab.ui.control.Label
        ShowtickCheckBox                matlab.ui.control.CheckBox
        ChooseaxisandslicetovisualizeLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Colmap_RangemaxEditField        matlab.ui.control.NumericEditField
        RangemaxEditFieldLabel          matlab.ui.control.Label
        Colmap_RangeminEditField        matlab.ui.control.NumericEditField
        RangeminEditFieldLabel          matlab.ui.control.Label
        StandardDropDown                matlab.ui.control.DropDown
        StandardDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        VideooptionsPanel               matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SavevideoButton                 matlab.ui.control.Button
        FormatDropDown                  matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        SaveslicesLabel                 matlab.ui.control.Label
        everyEditField                  matlab.ui.control.NumericEditField
        everyEditFieldLabel             matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        fromEditField                   matlab.ui.control.NumericEditField
        fromEditFieldLabel              matlab.ui.control.Label
        FramepersEditField              matlab.ui.control.NumericEditField
        FramepersEditFieldLabel         matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        VideofodlerselectedLabel        matlab.ui.control.Label
        SelectfolderButton              matlab.ui.control.Button
        Ifasingle3DvolumeisselectedPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DviewButton_2                   matlab.ui.control.Button
        DslicesviewButton               matlab.ui.control.Button
        SynchronizedslicesxyLabel       matlab.ui.control.Label
        Button_plus                     matlab.ui.control.Button
        Button_minus                    matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        CustomColmap_UITable            matlab.ui.control.Table
        AddacolormapButton              matlab.ui.control.Button
        VolumesvisualizerLabel          matlab.ui.control.Label
        PixelgridCheckBox               matlab.ui.control.CheckBox
        ViewnormaltoaxeDropDown         matlab.ui.control.DropDown
        ViewnormaltoaxeDropDownLabel    matlab.ui.control.Label
        AutorowandcolumnlayoutCheckBox  matlab.ui.control.CheckBox
        Colormap_UITable                matlab.ui.control.Table
        File_UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        listcolormaps =  {'gray' 'bone' 'copper' 'turbo' 'parula'};
        list1stcolormap =  {'Default' 'White' 'Grey' 'Black'};
        custom_colmap = [];
        custom_colmaps = [];
        colmap_number = 0;
        custom_labels = [];
        nfiles = [];
        cmin = [];
        cmax = [];
        arrays=[];
        all_sz;
        isexitFig = false;
        Fig = [];
        fontname = 'Times new roman';
    end

    
    methods (Access = private)
        
        function [] = update_axis_and_slider(app)
            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);
            show_sz = app.all_sz(idx,:);

            if max(app.all_sz(:,3))==1
                app.ViewnormaltoaxeDropDown.Items = {'3'};
                app.Slider.Enable = 'off'; app.Slider.Visible = 'off';
                app.Button_minus.Enable = 'off';  app.Button_minus.Visible = 'off';
                app.Button_plus.Enable = 'off'; app.Button_plus.Visible = 'off';      
                app.SynchronizedslicesxyLabel.Visible = 'off';  
                app.SavevideoButton.Enable = 'off';
                app.ShowmultipleslicesatonceEditField.Enable = 'off';
                app.DviewButton_2.Enable = 'off';
                app.DslicesviewButton.Enable = 'off';
            else
                if isempty(app.ViewnormaltoaxeDropDown.Items)
                    app.ViewnormaltoaxeDropDown.Items = {'1' '2' '3'};
                    app.ViewnormaltoaxeDropDown.Value = app.ViewnormaltoaxeDropDown.Items(3);
                end
                dir = app.ViewnormaltoaxeDropDown.ValueIndex;
                max_z = max(show_sz(:,dir));
                app.fromEditField.Value = 1;
                app.toEditField.Value = max_z;
                app.Slider.Limits = [1 max_z];
                app.Slider.Value = round( app.Slider.Limits(2)/2);
                app.Slider.Enable = 'on'; app.Slider.Visible = 'on';
                app.Button_minus.Enable = 'on'; app.Button_minus.Visible = 'on';
                app.Button_plus.Enable = 'on'; app.Button_plus.Visible = 'on';     
                app.SynchronizedslicesxyLabel.Visible = 'on';     
                app.SavevideoButton.Enable = 'on';
                app.ShowmultipleslicesatonceEditField.Enable = 'on';
                if sum(show)==1
                    app.DviewButton_2.Enable = 'on';
                    app.DslicesviewButton.Enable = 'on';
                else
                    app.DviewButton_2.Enable = 'off';
                    app.DslicesviewButton.Enable = 'off';
                end
            end

        end
        
        function [] = Plot_mosaic(app)

            scrsz = get(0,'ScreenSize'); % Screen resolution

            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);
            nshow = length(idx);

            show_sz = app.all_sz(idx,:);

            rc = str2double(app.File_UITable.Data(:,end-2:end-1));
            rc = rc(idx,:);
            n_row = max(rc(:,1));
            n_col =max(rc(:,2));            
            
            direction = str2double(app.ViewnormaltoaxeDropDown.Value);

            max_z = max(show_sz(:,direction));
            slider_z = round(app.Slider.Value);
            r_z = slider_z/max_z;

            showcolorbaor = cell2mat(app.File_UITable.Data(:,4));
            showcolorbaor = showcolorbaor(idx);

            ranges = app.File_UITable.Data(:,5);
            ranges = ranges(idx);

            autorange_perslice  = cell2mat(app.File_UITable.Data(:,6));
            autorange_perslice = autorange_perslice(idx);
            autorange_global  = cell2mat(app.File_UITable.Data(:,7));
            autorange_global = autorange_global(idx);            

            % Colormap
            colmap_choices = str2double(app.File_UITable.Data(:,3));
            colmap_choices = colmap_choices(idx);
            colmap_val = app.Colormap_UITable.Data(:,2);
            colmap_min = str2double(app.Colormap_UITable.Data(:,3));
            colmap_max = str2double(app.Colormap_UITable.Data(:,4));
            colmap_first = app.Colormap_UITable.Data(:,5);
            colmap_second = app.Colormap_UITable.Data(:,6);
            forceremapping = app.Colormap_UITable.Data(:,7);

            % Try catch allow to update Fig and not create a new one each time we update the UI, except if there is no figure or if it has been closed.
            try
                tiles = tiledlayout(app.Fig,n_row,n_col);
            catch ME
                if strcmp(ME.identifier,'MATLAB:tiledchartlayout:InvalidArguments') || strcmp(ME.identifier,'MATLAB:class:InvalidHandle') || strcmp(ME.identifier,'MATLAB:checkpvpairs:EvenPropValuePairs') || strcmp(ME.identifier,'MATLAB:graphics:axescheck:DeletedObject')
                    app.Fig = figure;
                    app.Fig.Name= 'Volume visualizer';
                    app.Fig.Color='white'; % Background colour
                    set(app.Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                    tiles = tiledlayout(app.Fig,n_row,n_col);
                end
            end

            tiles.TileSpacing = 'compact';
            tiles.Padding = 'compact';

            strz = 'Synchronized slices:';
            for k = 1:1:nshow
                n_tile = (rc(k,1)-1)*n_col + rc(k,2);
                ax = nexttile(tiles,n_tile);
                %ax = nexttile(n_tile);
                
                % Volume
                M = cell2mat(app.arrays( idx(k) ));

                % slice location
                zmax = show_sz(k,direction);
                if zmax==1
                    z=1;
                else
                    z = round(r_z*zmax);
                end

                % if k==1 || k==2
                %     z = z + (183-225);
                % end
                z = max([1 z]);


                %sz = size(M);
                %[k sz(1)]

                % Colormap
                col_name = char(colmap_val(colmap_choices(k),:));

                if strcmp(col_name,'Custom')
                    if cell2mat(forceremapping(colmap_choices(k))) % Force remapping

                        labels_colmap = app.custom_colmaps(colmap_choices(k)).labels;
                        rgb_colmap = app.custom_colmaps(colmap_choices(k)).c;
                        if strcmp(colmap_first(colmap_choices(k)),'White')
                            init = 1;
                        elseif strcmp(colmap_first(colmap_choices(k)),'Grey')
                            init = 0.5;
                        elseif strcmp(colmap_first(colmap_choices(k)),'Black')
                            init = 0.0;
                        end

                        if strcmp(colmap_second(colmap_choices(k)),'White')
                            init2 = 1;
                        elseif strcmp(colmap_second(colmap_choices(k)),'Grey')
                            init2 = 0.5;
                        elseif strcmp(colmap_second(colmap_choices(k)),'Black')
                            init2 = 0.0;
                        end                        

                        Domain_size = show_sz(k,:);
                        if direction==1
                            % Initializaion
                            slice_color = zeros(Domain_size(2),Domain_size(3),3); % RGB color map
                            slice_r = zeros(Domain_size(2),Domain_size(3))+init; % Red color map
                            slice_g = zeros(Domain_size(2),Domain_size(3))+init; % Green color map
                            slice_b = zeros(Domain_size(2),Domain_size(3))+init; % Blue color map
                            % Attribute RGB colors for each voxel
                             for current_label=1:1:length(labels_colmap)
                                slice_r(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                slice_g(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                slice_b(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                            end
                        elseif direction==2
                            % Initializaion
                            slice_color = zeros(Domain_size(1),Domain_size(3),3); % RGB color map
                            slice_r = zeros(Domain_size(1),Domain_size(3))+init; % Red color map
                            slice_g = zeros(Domain_size(1),Domain_size(3))+init; % Green color map
                            slice_b = zeros(Domain_size(1),Domain_size(3))+init; % Blue color map
                            % Attribute RGB colors for each voxel
                            for current_label=1:1:length(labels_colmap)
                                slice_r(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                slice_g(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                slice_b(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                            end
                        elseif direction==3
                            % Initializaion
                            slice_color = zeros(Domain_size(1),Domain_size(2),3); % RGB color map
                            slice_r = zeros(Domain_size(1),Domain_size(2))+init; % Red color map
                            slice_g = zeros(Domain_size(1),Domain_size(2))+init; % Green color map
                            slice_b = zeros(Domain_size(1),Domain_size(2))+init; % Blue color map
                            % Attribute RGB colors for each voxel
                            for current_label=1:1:length(labels_colmap)
                                slice_r(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                slice_g(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                slice_b(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                            end
                        end
                        slice_color(:,:,1)=slice_r; % Attribute RGB color
                        slice_color(:,:,2)=slice_g;
                        slice_color(:,:,3)=slice_b;
                        % Display the slice
                        image(slice_color,'parent',ax);
                    else
                        if direction==1
                            M = squeeze(M(z,:,:));
                            imagesc(M);
                        elseif direction==2
                            M = squeeze(M(:,z,:));
                            imagesc(M);
                        elseif direction==3
                            imagesc(M(:,:,z));
                        end
                        c_=app.custom_colmaps(colmap_choices(k)).c;
                        colormap(ax,c_);
                    end

                else % Standard colormap
                    if direction==1
                        M = squeeze(M(z,:,:));
                        imagesc(M);
                    elseif direction==2
                        M = squeeze(M(:,z,:));
                        imagesc(M);
                    else
                        imagesc(M(:,:,z));
                    end   

                    if autorange_perslice(k)
                        c_ = col_name;
                        colormap(ax,c_);
                    elseif autorange_global(k)
                        str = char(ranges(k));
                        str = strrep(str,'-',' ');
                        r = str2num(str);
                        n_color = round(r(2) - r(1) + 1);
                        str = [col_name '(n_color)'];
                        c_ = eval(str);
                        colormap(ax,c_); clim([r(1) r(2)]);
                    else
                        n_color = colmap_max(colmap_choices(k)) - colmap_min(colmap_choices(k)) + 1;
                        str = [col_name '(n_color)'];
                        c_ = eval(str);
                        if strcmp(colmap_first(colmap_choices(k)),'White')
                            c_(1,:) = [1 1 1];
                        elseif strcmp(colmap_first(colmap_choices(k)),'Grey')
                            c_(1,:) = [0.5 0.5 0.5];
                        elseif strcmp(colmap_first(colmap_choices(k)),'Black')
                            c_(1,:) = [0 0 0];
                        end
                        if strcmp(colmap_second(colmap_choices(k)),'White')
                            c_(2,:) = [1 1 1];
                        elseif strcmp(colmap_second(colmap_choices(k)),'Grey')
                            c_(2,:) = [0.5 0.5 0.5];
                        elseif strcmp(colmap_second(colmap_choices(k)),'Black')
                            c_(2,:) = [0 0 0];
                        end   
                        colormap(ax,c_); clim([colmap_min(colmap_choices(k)) colmap_max(colmap_choices(k))]);
                    end
                end

                if showcolorbaor(k)
                    colorbar(ax);
                end

                % Title
                str_currentz = [num2str(z) '/' num2str(zmax)];
                strz = [strz ' ' str_currentz];    


                choice = app.ShowTitle_DropDown.Value;
                if strcmp(choice,'Title and slice location')
                    titlename = app.File_UITable.Data(idx(k),2);
                    titlename = strrep(titlename,'_',' ');
                    h=title(titlename);
                    h.FontSize = app.TitlefontsizeEditField.Value;
                    h.FontName = app.fontname;
                    hs = subtitle(['Axe ' num2str(direction)  ', slice: ' str_currentz]);
                    hs.FontSize = app.TitlefontsizeEditField.Value-2;
                    hs.FontName = app.fontname; 
                elseif strcmp(choice,'Title')
                    titlename = app.File_UITable.Data(idx(k),2);
                    titlename = strrep(titlename,'_',' ');
                    h=title(titlename);
                    h.FontSize = app.TitlefontsizeEditField.Value;
                    h.FontName = app.fontname;                    
                elseif strcmp(choice,'slice location')
                    h=title(['Axe ' num2str(direction)  ', slice: ' str_currentz]);
                    h.FontSize = app.TitlefontsizeEditField.Value;
                    h.FontName = app.fontname;     
                elseif strcmp(choice,'None')
                    foo = 1;
                end

                if app.PixelgridCheckBox.Value
                    pixelgrid;
                end

                % Axis
                if ~app.ShowtickCheckBox.Value
                    set(gca,'XTick',[], 'YTick', [])
                end
                axis equal; axis tight;
            end
            app.SynchronizedslicesxyLabel.Text = strz;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            names=cell2mat(varargin(1));
            if isstruct(names)
                app.nfiles = length(varargin)-1;
                hasname = true;
            else
                app.nfiles = length(varargin);
                hasname = false;
            end

            for k=1:1:app.nfiles
                if hasname
                    kk=k+1;
                    name = {names(k).name};
                else
                    kk=k;
                    name = {['Volume #' num2str(k)]};
                end
                tmp=cell2mat(varargin(kk));
                rmin = min(min(min(tmp)));
                rmax = max(max(max(tmp)));

                if k==1
                    app.cmin = rmin;
                    app.cmax = rmax;
                end
                newline = [name name {'1'} {false} {[num2str(rmin) '-' num2str(rmax)]} {false} {true} {num2str(k)} {'1'} {true} ];
                app.File_UITable.Data = [app.File_UITable.Data; newline];
            end
            if hasname
                varargin(1)=[];
            end

            app.arrays=varargin;

            app.all_sz = zeros(app.nfiles,3);
            for k=1:1:app.nfiles
                sz = size(cell2mat(app.arrays(k)));
                if length(sz)==2
                    sz = [sz 1];
                end
                app.all_sz(k,:) = sz;
            end

            if app.nfiles==1
                app.DslicesviewButton.Enable = 'on';
                app.DviewButton_2.Enable = 'on';
            else
                app.DslicesviewButton.Enable = 'off';
                app.DviewButton_2.Enable = 'off';
            end

            app.VideofodlerselectedLabel.Text = pwd;
            app.VideofodlerselectedLabel.Tooltip = app.VideofodlerselectedLabel.Text;

            app.StandardDropDown.Items = app.listcolormaps;
            app.StandardDropDown.Value = app.StandardDropDown.Items(1);
            app.ColormapDropDownValueChanged;
            app.AddacolormapButtonPushed;
            app.AutorowandcolumnlayoutCheckBoxValueChanged;
            app.update_axis_and_slider;
            app.Plot_mosaic;
        end

        % Button pushed function: AddacolormapButton
        function AddacolormapButtonPushed(app, event)
            app.colmap_number = app.colmap_number+1;
            if strcmp(app.ColormapDropDown.Value,'Standard')
                tmp = [{num2str(app.colmap_number,'%i')} app.StandardDropDown.Value {num2str(app.cmin,'%i')} {num2str(app.cmax,'%i')} app.list1stcolormap(1) app.list1stcolormap(1) {'n/a'}];
            elseif strcmp(app.ColormapDropDown.Value,'Custom')
                tmp = [{num2str(app.colmap_number,'%i')} app.ColormapDropDown.Value {num2str(app.Colmap_RangeminEditField.Value,'%i')} {num2str(app.Colmap_RangemaxEditField.Value,'%i')} {'White'} {'White'} {false}];
                app.custom_colmaps(app.colmap_number).c = app.custom_colmap;
                app.custom_colmaps(app.colmap_number).labels = app.custom_labels;
            end
            if app.colmap_number==1
                app.Colormap_UITable.Data = tmp;
            else
                app.Colormap_UITable.Data = [app.Colormap_UITable.Data ;tmp];
            end
        end

        % Cell selection callback: CustomColmap_UITable
        function CustomColmap_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==2 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.custom_colmap(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.custom_colmap(indices(1),:));
                        addStyle(app.CustomColmap_UITable,s,'cell',[indices(1),2]);
                    end
                end
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value,'Standard')
                app.StandardDropDown.Enable = 'on';
                app.Colmap_RangemaxEditField.Enable = 'off';
                app.Colmap_RangeminEditField.Enable = 'off';
                app.CustomColmap_UITable.Enable = 'off';
                app.CustomColmap_UITable.Data = [];
            elseif strcmp(value,'Custom')
                app.StandardDropDown.Enable = 'off';
                app.Colmap_RangemaxEditField.Enable = 'on';
                app.Colmap_RangeminEditField.Enable = 'on';
                app.CustomColmap_UITable.Enable = 'on';
                app.Colmap_RangeminEditFieldValueChanged;
            end
        end

        % Value changed function: Colmap_RangemaxEditField, 
        % ...and 1 other component
        function Colmap_RangeminEditFieldValueChanged(app, event)
            c_min = app.Colmap_RangeminEditField.Value;
            c_max = app.Colmap_RangemaxEditField.Value;
            if c_min>=c_max
                c_max = c_min+1;
                app.Colmap_RangemaxEditField.Value=c_max;
            end
            col_ = colororder; % Initialize
            [n_col,~]= size(col_);
            if n_col >= c_max-c_min+1
                col_=col_(1:c_max-c_min+1,:);
            else
                col_ = [colororder; rand(c_max-c_min+1-n_col,3)];
            end
            [n_col,~]= size(col_);
            
            app.custom_colmap = [];
            for k=1:1:n_col
                phase_number(k,1)={num2str(c_min+k-1)};
                app.custom_colmap(k,:) = col_(k,:);
                s = uistyle('BackgroundColor',app.custom_colmap(k,:));
                addStyle(app.CustomColmap_UITable,s,'cell',[k,2]);
            end
            app.CustomColmap_UITable.Data = [phase_number cell(n_col,1)];
            app.custom_labels = [c_min:1:c_max]';
        end

        % Cell edit callback: Colormap_UITable
        function Colormap_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==5 || indices(2)==6  % 1st or 2nd color is
                if strcmp(newData,'w') || strcmp(newData,'W') || strcmp(newData,'white') || strcmp(newData,'White')
                    app.Colormap_UITable.Data(indices(1),indices(2)) = {'White'};
                elseif strcmp(newData,'g') || strcmp(newData,'G') || strcmp(newData,'grey') || strcmp(newData,'Grey')
                    app.Colormap_UITable.Data(indices(1),indices(2)) = {'Grey'};
                elseif strcmp(newData,'b') || strcmp(newData,'B') || strcmp(newData,'black') || strcmp(newData,'Black')
                    app.Colormap_UITable.Data(indices(1),indices(2)) = {'Black'};
                else
                    if strcmp(app.Colormap_UITable.Data(indices(1),2),'Custom')
                        app.Colormap_UITable.Data(indices(1),indices(2)) = {'White'};
                    else
                        app.Colormap_UITable.Data(indices(1),indices(2)) = {'Default'};
                    end
                end
            elseif indices(2)==3 || indices(2)==4 % Color range
                if strcmp(app.Colormap_UITable.Data(indices(1),2),'Custom')
                    app.Colormap_UITable.Data(indices(1),indices(2)) = {oldData};
                else
                    new = str2num(newData);
                    if isempty(new) % string
                        app.Colormap_UITable.Data(indices(1),indices(2)) = {oldData};
                    else
                        if  new<0 %|| new ~= round(new)
                            app.Colormap_UITable.Data(indices(1),indices(2)) = {oldData};
                        else
                            rmin = str2num(cell2mat(app.Colormap_UITable.Data(indices(1),3)));
                            rmax = str2num(cell2mat(app.Colormap_UITable.Data(indices(1),4)));
                            if rmax<=rmin
                                app.Colormap_UITable.Data(indices(1),indices(2)) = {oldData};
                            end
                        end
                    end
                end
            end
        end

        % Cell edit callback: File_UITable
        function File_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            if indices(2)==3 % Colormap
                new = str2num(newData);
                if isempty(new) % string
                    app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                else
                    [n_col,~] = size(app.Colormap_UITable.Data);
                    if new ~= round(new) || new<1 || new>n_col
                        app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
            elseif indices(2)==8 || indices(2)==9 % Layout
                new = str2num(newData);
                if isempty(new) % string
                    app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                else
                    show = cell2mat(app.File_UITable.Data(:,end));
                    if show(indices(1))
                        if new ~= round(new) || new<1
                            app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                        else
                            rc = str2double(app.File_UITable.Data(:,end-2:end-1));
                            unis = unique(rc,'rows');
                            idx = find(unis(:,1)==0);
                            if ~isempty(idx)
                                unis(idx,:)=[];
                            end
                            [nuni,~] = size(unis);
                            nshow = sum(cell2mat(app.File_UITable.Data(:,end)));
                            if nuni<nshow
                                app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                            end
                        end
                    else
                        app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
            elseif indices(2)==10 % Show
                show = cell2mat(app.File_UITable.Data(:,end));
                if show(indices(1))
                    rc = str2double(app.File_UITable.Data(:,end-2:end-1));
                    app.File_UITable.Data(indices(1),end-2) = {'1'};
                    app.File_UITable.Data(indices(1),end-1) = {num2str(max(rc(:,2)+1))};
                else
                    if sum(show)==0
                        app.File_UITable.Data(indices(1),indices(2)) = {oldData};
                    else
                        app.File_UITable.Data(indices(1),end-2) = {'0'};
                        app.File_UITable.Data(indices(1),end-1) = {'0'};
                    end
                end
                idx = find(show);
                show_sz = app.all_sz(idx,:);
                if max(show_sz(:,3))>1
                    dir = app.ViewnormaltoaxeDropDown.ValueIndex;
                    app.Slider.Limits = [1 max(show_sz(:,dir))];
                    app.Slider.Value = round( app.Slider.Limits(2)/2);
                end

            elseif indices(2)==6 || indices(2)==7 % Range
                range_perslice = cell2mat(app.File_UITable.Data(indices(1),6));
                range_global = cell2mat(app.File_UITable.Data(indices(1),7));
                if range_perslice && range_global
                    if indices(2)==6
                        app.File_UITable.Data(indices(1),6) = {true};
                        app.File_UITable.Data(indices(1),7) = {false};
                    else
                        app.File_UITable.Data(indices(1),6) = {false};
                        app.File_UITable.Data(indices(1),7) = {true};
                    end
                end
            end
            app.AutorowandcolumnlayoutCheckBoxValueChanged;

            show = cell2mat(app.File_UITable.Data(:,end));
            nshow = sum(show);
            if nshow==1
                app.DslicesviewButton.Enable = 'on';
                app.DviewButton_2.Enable = 'on';
            else
                app.DslicesviewButton.Enable = 'off';
                app.DviewButton_2.Enable = 'off';
            end

            % Update view options
            app.update_axis_and_slider;
            app.Plot_mosaic;

        end

        % Value changed function: AutorowandcolumnlayoutCheckBox
        function AutorowandcolumnlayoutCheckBoxValueChanged(app, event)
            value = app.AutorowandcolumnlayoutCheckBox.Value;
            if value
                show = cell2mat(app.File_UITable.Data(:,end));
                nshow = sum(show);
                n_row = floor(sqrt(nshow));
                if floor(sqrt(nshow)) < sqrt(nshow)
                    n_col = ceil(nshow/n_row);
                else
                    n_col = n_row;
                end

                current_row=1;
                current_col=0;
                for k=1:1:app.nfiles
                    if show(k)
                        current_col = current_col+1;
                        if current_col>n_col
                            current_row = current_row+1;
                            current_col = 1;
                        end
                        app.File_UITable.Data(k,end-2) = {num2str(current_row)};
                        app.File_UITable.Data(k,end-1) = {num2str(current_col)};                       
                    end
                end
            end
        end

        % Value changed function: ViewnormaltoaxeDropDown
        function ViewnormaltoaxeDropDownValueChanged(app, event)
            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);
            show_sz = app.all_sz(idx,:);
            if max(show_sz(:,3))>1
                dir = app.ViewnormaltoaxeDropDown.ValueIndex;
                max_z = max(show_sz(:,dir));
                app.Slider.Limits = [1 max_z];
                app.Slider.Value = round( app.Slider.Limits(2)/2);
                app.fromEditField.Value = 1;
                app.toEditField.Value = max_z;
            end
            app.Plot_mosaic;
        end

        % Value changed function: ShowtickCheckBox
        function ShowboxCheckBoxValueChanged(app, event)
            app.Plot_mosaic;
        end

        % Value changed function: TitlefontsizeEditField
        function TitlefontsizeEditFieldValueChanged(app, event)
            app.Plot_mosaic;            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Slider.Value = round(value);
            app.Plot_mosaic;              
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            if app.UpdatewhilemovingsliderCheckBox.Value
                changingValue = event.Value;
                app.Slider.Value = round(changingValue);
                app.Plot_mosaic;
            end
        end

        % Button pushed function: Button_minus
        function Button_minusPushed(app, event)
            value = round(app.Slider.Value);
            new_value = max([1 value-1]);
            app.Slider.Value = round(new_value);
            app.Plot_mosaic;  
        end

        % Button pushed function: Button_plus
        function Button_plusPushed(app, event)
            value = round(app.Slider.Value);
            new_value = min([app.Slider.Limits(2) value+1]);
            app.Slider.Value = round(new_value);
            app.Plot_mosaic;  
        end

        % Value changed function: ShowmultipleslicesatonceEditField
        function ShowmultipleslicesatonceEditFieldValueChanged(app, event)
            nslice = app.ShowmultipleslicesatonceEditField.Value;

            scrsz = get(0,'ScreenSize'); % Screen resolution

            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);
            nshow = length(idx);
            show_sz = app.all_sz(idx,:);

            direction = str2double(app.ViewnormaltoaxeDropDown.Value);

            n_row = floor(sqrt(nslice));
            if floor(sqrt(nslice)) < sqrt(nslice)
                n_col = ceil(nslice/n_row);
            else
                n_col = n_row;
            end

            showcolorbaor = cell2mat(app.File_UITable.Data(:,4));
            showcolorbaor = showcolorbaor(idx);

            ranges = app.File_UITable.Data(:,5);
            ranges = ranges(idx);

            autorange_perslice  = cell2mat(app.File_UITable.Data(:,6));
            autorange_perslice = autorange_perslice(idx);
            autorange_global  = cell2mat(app.File_UITable.Data(:,7));
            autorange_global = autorange_global(idx);

            % Colormap
            colmap_choices = str2double(app.File_UITable.Data(:,3));
            colmap_choices = colmap_choices(idx);
            colmap_val = app.Colormap_UITable.Data(:,2);
            colmap_min = str2double(app.Colormap_UITable.Data(:,3));
            colmap_max = str2double(app.Colormap_UITable.Data(:,4));
            colmap_first = app.Colormap_UITable.Data(:,5);
            colmap_second = app.Colormap_UITable.Data(:,6);
            forceremapping = app.Colormap_UITable.Data(:,7);

            for k=1:1:nshow
                Fig2 = figure;
                Fig2.Name= 'Slice summary';
                Fig2.Color='white'; % Background colour
                set(Fig2,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)*4/5]);
                tiles = tiledlayout(Fig2,n_row,n_col);

                tiles.TileSpacing = 'compact';
                tiles.Padding = 'compact';

                % Volume
                M = cell2mat(app.arrays( idx(k) ));

                max_z = max(show_sz(k,direction));
                allz = round(linspace(1,max_z,nslice));

                for ks = 1:1:nslice
                    ax = nexttile(tiles,ks);

                    % slice location
                    z = allz(ks);

                    % Colormap
                    col_name = char(colmap_val(colmap_choices(k),:));

                    if strcmp(col_name,'Custom')
                        if cell2mat(forceremapping(colmap_choices(k))) % Force remapping

                            labels_colmap = app.custom_colmaps(colmap_choices(k)).labels;
                            rgb_colmap = app.custom_colmaps(colmap_choices(k)).c;
                            if strcmp(colmap_first(colmap_choices(k)),'White')
                                init = 1;
                            elseif strcmp(colmap_first(colmap_choices(k)),'Grey')
                                init = 0.5;
                            elseif strcmp(colmap_first(colmap_choices(k)),'Black')
                                init = 0.0;
                            end
                            if strcmp(colmap_second(colmap_choices(k)),'White')
                                init2 = 1;
                            elseif strcmp(colmap_second(colmap_choices(k)),'Grey')
                                init2 = 0.5;
                            elseif strcmp(colmap_second(colmap_choices(k)),'Black')
                                init2 = 0.0;
                            end                            

                            Domain_size = show_sz(k,:);
                            if direction==1
                                % Initializaion
                                slice_color = zeros(Domain_size(2),Domain_size(3),3); % RGB color map
                                slice_r = zeros(Domain_size(2),Domain_size(3))+init; % Red color map
                                slice_g = zeros(Domain_size(2),Domain_size(3))+init; % Green color map
                                slice_b = zeros(Domain_size(2),Domain_size(3))+init; % Blue color map
                                % Attribute RGB colors for each voxel
                                for current_label=1:1:length(labels_colmap)
                                    slice_r(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                    slice_g(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                    slice_b(M(z,:,:)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                                end
                            elseif direction==2
                                % Initializaion
                                slice_color = zeros(Domain_size(1),Domain_size(3),3); % RGB color map
                                slice_r = zeros(Domain_size(1),Domain_size(3))+init; % Red color map
                                slice_g = zeros(Domain_size(1),Domain_size(3))+init; % Green color map
                                slice_b = zeros(Domain_size(1),Domain_size(3))+init; % Blue color map
                                % Attribute RGB colors for each voxel
                                for current_label=1:1:length(labels_colmap)
                                    slice_r(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                    slice_g(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                    slice_b(M(:,z,:)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                                end
                            elseif direction==3
                                % Initializaion
                                slice_color = zeros(Domain_size(1),Domain_size(2),3); % RGB color map
                                slice_r = zeros(Domain_size(1),Domain_size(2))+init; % Red color map
                                slice_g = zeros(Domain_size(1),Domain_size(2))+init; % Green color map
                                slice_b = zeros(Domain_size(1),Domain_size(2))+init; % Blue color map
                                % Attribute RGB colors for each voxel
                                for current_label=1:1:length(labels_colmap)
                                    slice_r(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,1); % Attribute red color
                                    slice_g(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,2); % Attribute green color
                                    slice_b(M(:,:,z)==labels_colmap(current_label))= rgb_colmap(current_label,3); % Attribute blue color
                                end
                            end
                            slice_color(:,:,1)=slice_r; % Attribute RGB color
                            slice_color(:,:,2)=slice_g;
                            slice_color(:,:,3)=slice_b;
                            % Display the slice
                            image(slice_color,'parent',ax);
                        else
                            if direction==1
                                M2 = squeeze(M(z,:,:));
                                imagesc(M2);
                            elseif direction==2
                                M2 = squeeze(M(:,z,:));
                                imagesc(M2);
                            else
                                imagesc(M(:,:,z));
                            end
                            c_=app.custom_colmaps(colmap_choices(k)).c;
                            colormap(ax,c_);
                        end

                    else % Standard colormap
                        if direction==1
                            M2 = squeeze(M(z,:,:));
                            imagesc(M2);
                        elseif direction==2
                            M2 = squeeze(M(:,z,:));
                            imagesc(M2);
                        else
                            imagesc(M(:,:,z));
                        end

                        if autorange_perslice(k)
                            c_ = col_name;
                            colormap(ax,c_);
                        elseif autorange_global(k)
                            str = char(ranges(k));
                            str = strrep(str,'-',' ');
                            r = str2num(str);
                            n_color = r(2) - r(1) + 1;
                            str = [col_name '(n_color)'];
                            c_ = eval(str);
                            colormap(ax,c_); clim([r(1) r(2)]);
                        else
                            n_color = colmap_max(colmap_choices(k)) - colmap_min(colmap_choices(k)) + 1;
                            str = [col_name '(n_color)'];
                            c_ = eval(str);
                            if strcmp(colmap_first(colmap_choices(k)),'White')
                                c_(1,:) = [1 1 1];
                            elseif strcmp(colmap_first(colmap_choices(k)),'Grey')
                                c_(1,:) = [0.5 0.5 0.5];
                            elseif strcmp(colmap_first(colmap_choices(k)),'Black')
                                c_(1,:) = [0 0 0];
                            end
                            colormap(ax,c_); clim([colmap_min(colmap_choices(k)) colmap_max(colmap_choices(k))]);
                        end
                    end

                    if showcolorbaor(k)
                        colorbar(ax);
                    end

                    % Title
                    h=title(['Slice: ' num2str(z) '/' num2str(max_z)]);
                    h.FontSize = app.TitlefontsizeEditField.Value;
                    h.FontName = app.fontname;                    

                    % Axis
                    if ~app.ShowtickCheckBox.Value
                        set(gca,'XTick',[], 'YTick', [])
                    end
                    axis equal; axis tight;

                end
                titlename = app.File_UITable.Data(idx(k),2);
                titlename = char(strrep(titlename,'_',' '));  
                sgt = sgtitle({titlename,['Axe ' num2str(direction)]});
                sgt.FontSize = app.TitlefontsizeEditField.Value+2;
                sgt.FontName = app.fontname;
            end
        end

        % Button pushed function: DslicesviewButton
        function DslicesviewButtonPushed(app, event)
            voxelsize = 1;
            voxelsize_unitname = '';
            arrayunit = '';

            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);
            titlename = app.File_UITable.Data(idx(1),2);
            titlename = char(strrep(titlename,'_',' '));

            microstructure_visualization_slices( cell2mat(app.arrays( idx(1) )), titlename, voxelsize, voxelsize_unitname, arrayunit);
        end

        % Button pushed function: DviewButton_2
        function DviewButton_2Pushed(app, event)
            show = cell2mat(app.File_UITable.Data(:,end));
            idx = find(show);    

            hFig = uifigure;
            viewer = viewer3d('Parent',hFig);
            viewer.RenderingQuality = 'High';
            viewer.OrientationAxes = false;
            viewer.BackgroundColor = [1 1 1];
            viewer.Lighting="on";
            vol = volshow(cell2mat(app.arrays( idx(1) )),Parent=viewer,Colormap=turbo);

            %vol = volshow(cell2mat(app.arrays( idx(1) )));
        end

        % Value changed function: fromEditField, toEditField
        function fromEditFieldValueChanged(app, event)
            from = app.fromEditField.Value;
            to = app.toEditField.Value;
            max_z = app.Slider.Limits(2);
            if from >= to || from >=max_z || to>max_z
                app.fromEditField.Value = 1;
                app.toEditField.Value = max_z;
            end            
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            pause(0.1);
            video_filename = app.FilenameEditField.Value;
            video_format = app.FormatDropDown.Value;
            videofolder = app.VideofodlerselectedLabel.Text;
            fullpath = fullfile(videofolder,video_filename);
            if ~exist(videofolder,'dir')
                mkdir(videofolder);
            end

            video_handle = VideoWriter(fullpath,video_format);
            if strcmp(video_format,'mpeg-4')
                set(video_handle,'Quality',100); % Set video quality
            end
            % Set video framerate
            set(video_handle,'FrameRate',app.FramepersEditField.Value);
            % Open video
            open(video_handle)
            

            from = app.fromEditField.Value;
            to = app.toEditField.Value;
            dz = app.everyEditField.Value;
            max_z = app.Slider.Limits(2);

            zs = from:dz:to;
            if zs(end)~=to
                zs = [zs to];
            end

            frame_number = 0;
            for kz=1:1:length(zs)
                frame_number = frame_number+1;
                % Update figure
                app.Slider.Value = zs(kz);
                app.Plot_mosaic;
                stored_frame(frame_number) = getframe(app.Fig);
                writeVideo(video_handle,stored_frame(frame_number))
                pause(0.01);
            end
                         
            % Close video
            close(video_handle)
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            str_dialogbox = 'Select save folder';
            res = uigetdir(matlabroot,str_dialogbox); % Open dialog box
            if res==0
                % User clicked cancel button or closed the dialog box
                app.VideofodlerselectedLabel.Text = 'none selected';
                app.SavevideoButton.Enable = 'off';
            else
                app.VideofodlerselectedLabel.Text = res;
                app.SavevideoButton.Enable = 'on';
            end  
            app.VideofodlerselectedLabel.Tooltip = app.VideofodlerselectedLabel.Text;
        end

        % Value changed function: PixelgridCheckBox
        function PixelgridCheckBoxValueChanged(app, event)
            app.Plot_mosaic;            
        end

        % Value changed function: ShowTitle_DropDown
        function ShowTitle_DropDownValueChanged(app, event)
            value = app.ShowTitle_DropDown.Value;
            if strcmp(value,'None')
                app.TitlefontsizeEditField.Enable = 'off';
            else
                app.TitlefontsizeEditField.Enable = 'on';
            end
            app.Plot_mosaic;                     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VolumesvisualizerUIFigure = uifigure('Visible', 'off');
            app.VolumesvisualizerUIFigure.Name = 'Volume(s) visualizer';
            app.GridLayout = uigridlayout(app.VolumesvisualizerUIFigure);
            app.File_UITable = uitable(app.GridLayout);
            app.File_UITable.ColumnName = {'File'; 'Figure title'; 'Colormap#'; 'Colorbar'; 'Range'; 'Auto range (per slice)'; 'Auto range (global)'; 'Row'; 'Column'; 'Show'};
            app.File_UITable.RowName = {};
            app.File_UITable.ColumnEditable = [false true true true false true true true true true];
            app.File_UITable.CellEditCallback = createCallbackFcn(app, @File_UITableCellEdit, true);
            app.Colormap_UITable = uitable(app.GridLayout);
            app.Colormap_UITable.ColumnName = {'Colormap#'; 'Colormap'; 'Range: min'; 'Range: max'; '1st color is'; '2nd color is'; 'Force remapping'};
            app.Colormap_UITable.RowName = {};
            app.Colormap_UITable.ColumnEditable = [false false true true true true true];
            app.Colormap_UITable.CellEditCallback = createCallbackFcn(app, @Colormap_UITableCellEdit, true);
            app.Colormap_UITable.Tooltip = {'Options for "1st color is" are white, grey or black (otherwise default will be selected). For Custom colormap, it refers to all labels without a color assigned.'};
            app.AutorowandcolumnlayoutCheckBox = uicheckbox(app.GridLayout);
            app.AutorowandcolumnlayoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutorowandcolumnlayoutCheckBoxValueChanged, true);
            app.AutorowandcolumnlayoutCheckBox.Text = 'Auto row and column layout';
            app.AutorowandcolumnlayoutCheckBox.Value = true;
            app.ViewnormaltoaxeDropDownLabel = uilabel(app.GridLayout);
            app.ViewnormaltoaxeDropDownLabel.Text = 'View normal to axe';
            app.ViewnormaltoaxeDropDown = uidropdown(app.GridLayout);
            app.ViewnormaltoaxeDropDown.Items = {};
            app.ViewnormaltoaxeDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewnormaltoaxeDropDownValueChanged, true);
            app.ViewnormaltoaxeDropDown.Value = {};
            app.PixelgridCheckBox = uicheckbox(app.GridLayout);
            app.PixelgridCheckBox.ValueChangedFcn = createCallbackFcn(app, @PixelgridCheckBoxValueChanged, true);
            app.PixelgridCheckBox.Text = 'Pixel grid';
            app.VolumesvisualizerLabel = uilabel(app.GridLayout);
            app.VolumesvisualizerLabel.Text = 'Volume(s) visualizer';
            app.AddacolormapButton = uibutton(app.GridLayout, 'push');
            app.AddacolormapButton.ButtonPushedFcn = createCallbackFcn(app, @AddacolormapButtonPushed, true);
            app.AddacolormapButton.Text = 'Add a colormap';
            app.CustomColmap_UITable = uitable(app.GridLayout);
            app.CustomColmap_UITable.ColumnName = {'Label'; 'Color'};
            app.CustomColmap_UITable.RowName = {};
            app.CustomColmap_UITable.CellSelectionCallback = createCallbackFcn(app, @CustomColmap_UITableCellSelection, true);
            app.CustomColmap_UITable.Tooltip = {''};
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Button_minus = uibutton(app.GridLayout, 'push');
            app.Button_minus.ButtonPushedFcn = createCallbackFcn(app, @Button_minusPushed, true);
            app.Button_minus.Text = '-';
            app.Button_plus = uibutton(app.GridLayout, 'push');
            app.Button_plus.ButtonPushedFcn = createCallbackFcn(app, @Button_plusPushed, true);
            app.Button_plus.Text = '+';
            app.SynchronizedslicesxyLabel = uilabel(app.GridLayout);
            app.SynchronizedslicesxyLabel.Text = 'Synchronized slices: x/y';
            app.Ifasingle3DvolumeisselectedPanel = uipanel(app.GridLayout);
            app.Ifasingle3DvolumeisselectedPanel.Title = 'If a single 3D volume is selected';
            app.GridLayout2 = uigridlayout(app.Ifasingle3DvolumeisselectedPanel);
            app.DslicesviewButton = uibutton(app.GridLayout2, 'push');
            app.DslicesviewButton.ButtonPushedFcn = createCallbackFcn(app, @DslicesviewButtonPushed, true);
            app.DslicesviewButton.Text = '3D slices view';
            app.DviewButton_2 = uibutton(app.GridLayout2, 'push');
            app.DviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @DviewButton_2Pushed, true);
            app.DviewButton_2.Text = '3D view';
            app.VideooptionsPanel = uipanel(app.GridLayout);
            app.VideooptionsPanel.Title = 'Video options';
            app.GridLayout3 = uigridlayout(app.VideooptionsPanel);
            app.SelectfolderButton = uibutton(app.GridLayout3, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.VideofodlerselectedLabel = uilabel(app.GridLayout3);
            app.VideofodlerselectedLabel.Text = 'none selected';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout3);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout3, 'text');
            app.FilenameEditField.Value = 'Video';
            app.FramepersEditFieldLabel = uilabel(app.GridLayout3);
            app.FramepersEditFieldLabel.Text = 'Frame per s';
            app.FramepersEditField = uieditfield(app.GridLayout3, 'numeric');
            app.FramepersEditField.Limits = [1 Inf];
            app.FramepersEditField.RoundFractionalValues = 'on';
            app.FramepersEditField.Value = 25;
            app.fromEditFieldLabel = uilabel(app.GridLayout3);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.GridLayout3, 'numeric');
            app.fromEditField.Limits = [1 Inf];
            app.fromEditField.RoundFractionalValues = 'on';
            app.fromEditField.ValueChangedFcn = createCallbackFcn(app, @fromEditFieldValueChanged, true);
            app.fromEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.GridLayout3);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.GridLayout3, 'numeric');
            app.toEditField.Limits = [2 Inf];
            app.toEditField.RoundFractionalValues = 'on';
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @fromEditFieldValueChanged, true);
            app.toEditField.Value = 2;
            app.everyEditFieldLabel = uilabel(app.GridLayout3);
            app.everyEditFieldLabel.Text = 'every';
            app.everyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.everyEditField.Limits = [1 Inf];
            app.everyEditField.RoundFractionalValues = 'on';
            app.everyEditField.Value = 1;
            app.SaveslicesLabel = uilabel(app.GridLayout3);
            app.SaveslicesLabel.Text = 'Save slices';
            app.FormatDropDownLabel = uilabel(app.GridLayout3);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.GridLayout3);
            app.FormatDropDown.Items = {'mpeg-4', 'Uncompressed AVI'};
            app.FormatDropDown.Value = 'mpeg-4';
            app.SavevideoButton = uibutton(app.GridLayout3, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Text = 'Save video';
            app.ColormapDropDownLabel = uilabel(app.GridLayout);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout);
            app.ColormapDropDown.Items = {'Standard', 'Custom'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Standard';
            app.StandardDropDownLabel = uilabel(app.GridLayout);
            app.StandardDropDownLabel.Text = 'Standard';
            app.StandardDropDown = uidropdown(app.GridLayout);
            app.RangeminEditFieldLabel = uilabel(app.GridLayout);
            app.RangeminEditFieldLabel.Text = 'Range: min';
            app.Colmap_RangeminEditField = uieditfield(app.GridLayout, 'numeric');
            app.Colmap_RangeminEditField.Limits = [0 Inf];
            app.Colmap_RangeminEditField.RoundFractionalValues = 'on';
            app.Colmap_RangeminEditField.ValueChangedFcn = createCallbackFcn(app, @Colmap_RangeminEditFieldValueChanged, true);
            app.RangemaxEditFieldLabel = uilabel(app.GridLayout);
            app.RangemaxEditFieldLabel.Text = 'Range: max';
            app.Colmap_RangemaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.Colmap_RangemaxEditField.Limits = [1 Inf];
            app.Colmap_RangemaxEditField.RoundFractionalValues = 'on';
            app.Colmap_RangemaxEditField.ValueChangedFcn = createCallbackFcn(app, @Colmap_RangeminEditFieldValueChanged, true);
            app.Colmap_RangemaxEditField.Value = 3;
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Assign to each file a colormap# in the first table below. You can set and add colormaps in the two other tables below.';
            app.ChooseaxisandslicetovisualizeLabel = uilabel(app.GridLayout);
            app.ChooseaxisandslicetovisualizeLabel.Text = 'Choose axis and slice to visualize.';
            app.ShowtickCheckBox = uicheckbox(app.GridLayout);
            app.ShowtickCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowboxCheckBoxValueChanged, true);
            app.ShowtickCheckBox.Text = 'Show tick';
            app.TitlefontsizeEditFieldLabel = uilabel(app.GridLayout);
            app.TitlefontsizeEditFieldLabel.Text = 'Title font size';
            app.TitlefontsizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.TitlefontsizeEditField.Limits = [1 Inf];
            app.TitlefontsizeEditField.RoundFractionalValues = 'on';
            app.TitlefontsizeEditField.ValueChangedFcn = createCallbackFcn(app, @TitlefontsizeEditFieldValueChanged, true);
            app.TitlefontsizeEditField.Value = 14;
            app.UpdatewhilemovingsliderCheckBox = uicheckbox(app.GridLayout);
            app.UpdatewhilemovingsliderCheckBox.Text = 'Update while moving slider';
            app.ShowmultipleslicesatonceEditFieldLabel = uilabel(app.GridLayout);
            app.ShowmultipleslicesatonceEditFieldLabel.Text = 'Show multiple slices at once';
            app.ShowmultipleslicesatonceEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShowmultipleslicesatonceEditField.Limits = [2 Inf];
            app.ShowmultipleslicesatonceEditField.RoundFractionalValues = 'on';
            app.ShowmultipleslicesatonceEditField.ValueChangedFcn = createCallbackFcn(app, @ShowmultipleslicesatonceEditFieldValueChanged, true);
            app.ShowmultipleslicesatonceEditField.Value = 5;
            app.ShowDropDownLabel = uilabel(app.GridLayout);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowTitle_DropDown = uidropdown(app.GridLayout);
            app.ShowTitle_DropDown.Items = {'Title and slice location', 'Title', 'slice location', 'None'};
            app.ShowTitle_DropDown.ValueChangedFcn = createCallbackFcn(app, @ShowTitle_DropDownValueChanged, true);
            app.ShowTitle_DropDown.Value = 'Title and slice location';
            % Show the figure after all components are created
            app.VolumesvisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualization_multiple_volumes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VolumesvisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VolumesvisualizerUIFigure)
        end
    end
end