classdef microstructure_visualization_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructurevisualizationUIFigure  matlab.ui.Figure
        Label2_2                        matlab.ui.control.Label
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        InstructionsTab                 matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        YoucancroptrimchangetheframepersecondorthelengthofavideoYoucanalsoconcatenatetwovideosinoneThisisparticularlyusefulascreatingavideowithalotofsequencescanbecumbersomesoyoucaninsteadcreatingseveralshortervideosandthenassemblethemtogetherLabel  matlab.ui.control.Label
        VideoexplorerLabel              matlab.ui.control.Label
        SeeexamplesinthegallerytabButton  matlab.ui.control.Button
        Both2Dand3DstacktifortifffilesaresupportedLabel_2  matlab.ui.control.Label
        Inthe3DvolumevisualizationtabyoucanDisplay3DrenderingLabel  matlab.ui.control.Label
        Youcancreates2Dimagesor3DvideosLabel  matlab.ui.control.Label
        Label3_2                        matlab.ui.control.Label
        ViewcharacterizationresultsLabel  matlab.ui.control.Label
        ViewgreylevelorsegmentedimagesLabel  matlab.ui.control.Label
        ViewgreylevelorsegmentedimagesTab  matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        ImportTab_2                     matlab.ui.container.Tab
        GridLayout2_3                   matlab.ui.container.GridLayout
        IdleLabel                       matlab.ui.control.Label
        ModifyROIPanel                  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        toEditField_3                   matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        Cropaxe3fromEditField           matlab.ui.control.NumericEditField
        Cropaxe3fromEditFieldLabel      matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        Cropaxe2fromEditField           matlab.ui.control.NumericEditField
        Cropaxe2fromEditFieldLabel      matlab.ui.control.Label
        CropButton                      matlab.ui.control.Button
        toEditField                     matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        Cropaxe1fromEditField           matlab.ui.control.NumericEditField
        Cropaxe1fromEditFieldLabel      matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ResetallchangesforallselectedfilesButton  matlab.ui.control.Button
        ModifygreylevelimagesPanel      matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        PlothistogramButton             matlab.ui.control.Button
        RescaleButton                   matlab.ui.control.Button
        LinearrescalingwithvaluesEditField  matlab.ui.control.NumericEditField
        LinearrescalingwithvaluesEditFieldLabel  matlab.ui.control.Label
        ContrastsaturationButton        matlab.ui.control.Button
        SaturationbrightestEditField    matlab.ui.control.NumericEditField
        SaturationbrightestEditFieldLabel  matlab.ui.control.Label
        SaturationdarkestEditField      matlab.ui.control.NumericEditField
        SaturationdarkestEditFieldLabel  matlab.ui.control.Label
        ModifyimagesPanel               matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RemovegapsinlabelnumberingButton  matlab.ui.control.Button
        FliporswapaxisButton            matlab.ui.control.Button
        FlipSwap_Axis_DropDown          matlab.ui.control.DropDown
        RemovevolumeswithonlyporeorbackgroundautocropButton  matlab.ui.control.Button
        ReassignButton_semantic         matlab.ui.control.Button
        Semantic_tolabelEditField       matlab.ui.control.NumericEditField
        tolabelEditFieldLabel           matlab.ui.control.Label
        Semantic_AssignlabelEditField   matlab.ui.control.NumericEditField
        AssignlabelEditFieldLabel       matlab.ui.control.Label
        RescaleallselectedfilesButton   matlab.ui.control.Button
        RescaletovoxelsizeEditField     matlab.ui.control.NumericEditField
        RescaletovoxelsizeEditFieldLabel  matlab.ui.control.Label
        ResetallchangesforthisfileButton  matlab.ui.control.Button
        VoxelsizeunitEditField          matlab.ui.control.EditField
        VoxelsizeunitEditFieldLabel     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ModifyinstanceimagesPanel       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        RemovetruncatedinstancesButton_3  matlab.ui.control.Button
        RandomizelabelsButton_3         matlab.ui.control.Button
        Import_UITable                  matlab.ui.control.Table
        ImportButton_3                  matlab.ui.control.Button
        DslicevisualizationTab          matlab.ui.container.Tab
        GridLayout_2Dslicevisualization  matlab.ui.container.GridLayout
        SegComp_HowtoLabel              matlab.ui.control.Label
        TwoDview_Button                 matlab.ui.control.Button
        SegmentationcomparisonCheckBox  matlab.ui.control.CheckBox
        TwoD_UITable                    matlab.ui.control.Table
        SelectfilestovisualizeLabel     matlab.ui.control.Label
        DvolumevisualizationTab         matlab.ui.container.Tab
        GridLayout_3Dmain               matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        ColormapTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        InstancePanel                   matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        PorelabelistransparentBackgroundlabelifanyistransparentLabel  matlab.ui.control.Label
        ColormapDropDown_2              matlab.ui.control.DropDown
        ColormapDropDown_2Label         matlab.ui.control.Label
        SemanticPanel                   matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        CummulativeopacityCheckBox      matlab.ui.control.CheckBox
        UpdateSemanticColormapButton    matlab.ui.control.Button
        SemanticColor_UITable           matlab.ui.control.Table
        GreylevelPanel                  matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        OpacityprofileDropDown          matlab.ui.control.DropDown
        OpacityprofileDropDownLabel     matlab.ui.control.Label
        BackgroundlabelifanyistransparentLabel  matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        PositionTab                     matlab.ui.container.Tab
        GridLayout_3Dvisualization_2    matlab.ui.container.GridLayout
        Label_7                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        IfyouhaveselectedmultiplesfilestheywillbedisplayedtogetherLabel  matlab.ui.control.Label
        ZposalignmentDropDown           matlab.ui.control.DropDown
        ZposalignmentDropDown_2Label    matlab.ui.control.Label
        YposalignmentDropDown           matlab.ui.control.DropDown
        YposalignmentDropDown_2Label    matlab.ui.control.Label
        XposalignmentDropDown           matlab.ui.control.DropDown
        XposalignmentDropDown_2Label    matlab.ui.control.Label
        ThreeD_position_UITable         matlab.ui.control.Table
        ThreeD_Zaxis_distance_EditField  matlab.ui.control.NumericEditField
        zaxisEditField_4Label_2         matlab.ui.control.Label
        ThreeD_Yaxis_distance_EditField  matlab.ui.control.NumericEditField
        yaxisEditField_4Label_2         matlab.ui.control.Label
        DistanceinpixelbewteenvolumesLabel_5  matlab.ui.control.Label
        ThreeD_Xaxis_distance_EditField  matlab.ui.control.NumericEditField
        xaxisEditField_4Label_2         matlab.ui.control.Label
        BackgroundaxesandlightingTab    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        LightsourcelocationDropDown     matlab.ui.control.DropDown
        LightsourcelocationDropDownLabel  matlab.ui.control.Label
        Light_Coordinatealongaxez_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxezLabel_2      matlab.ui.control.Label
        Light_Coordinatealongaxey_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxeyLabel_2      matlab.ui.control.Label
        Light_Coordinatealongaxex_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxexEditField_2Label_2  matlab.ui.control.Label
        CoordinateexpressedinratioofthedomainsizeLabel_3  matlab.ui.control.Label
        AngleexpressedindegreesLabel_2  matlab.ui.control.Label
        Light_ElevationEditField        matlab.ui.control.NumericEditField
        ElevationEditField_2Label       matlab.ui.control.Label
        Light_AzimuthEditField          matlab.ui.control.NumericEditField
        AzimuthEditField_2Label         matlab.ui.control.Label
        LightcolorColorPicker           matlab.ui.control.ColorPicker
        LightcolorColorPickerLabel      matlab.ui.control.Label
        LightsourceintensityEditField   matlab.ui.control.NumericEditField
        LightsourceintensityEditFieldLabel  matlab.ui.control.Label
        AmbientlightEditField           matlab.ui.control.NumericEditField
        AmbientlightEditFieldLabel      matlab.ui.control.Label
        LightingLabel                   matlab.ui.control.Label
        BoxaxesandscalebaroptionsLabel  matlab.ui.control.Label
        DistancefromplaneyzEditField    matlab.ui.control.NumericEditField
        DistancefromplaneyzEditFieldLabel  matlab.ui.control.Label
        DistancefromplanexzEditField    matlab.ui.control.NumericEditField
        DistancefromplanexzEditFieldLabel  matlab.ui.control.Label
        DistancefromplanexyEditField    matlab.ui.control.NumericEditField
        DistancefromplanexyEditFieldLabel  matlab.ui.control.Label
        ScaleLengthexpressedinDropDown  matlab.ui.control.DropDown
        LengthexpressedinDropDownLabel  matlab.ui.control.Label
        Scalebar_lengthEditField        matlab.ui.control.NumericEditField
        LengthLabel                     matlab.ui.control.Label
        ScaleBar_Z_LocatedDropDown      matlab.ui.control.DropDown
        ScaleBar_Y_LocatedDropDown      matlab.ui.control.DropDown
        ScaleBar_X_LocatedDropDown      matlab.ui.control.DropDown
        LocatedDropDownLabel            matlab.ui.control.Label
        Scalebar_location_DropDown      matlab.ui.control.DropDown
        RatioofscreensizeLabel          matlab.ui.control.Label
        FiguresizeEditField             matlab.ui.control.NumericEditField
        FiguresizeEditFieldLabel        matlab.ui.control.Label
        ShoworientationaxesCheckBox     matlab.ui.control.CheckBox
        ScalebarCheckBox                matlab.ui.control.CheckBox
        ShowboxCheckBox                 matlab.ui.control.CheckBox
        BackgroundGradientCheckBox      matlab.ui.control.CheckBox
        GradientcolorColorPicker        matlab.ui.control.ColorPicker
        GradientcolorColorPickerLabel   matlab.ui.control.Label
        BackgroundcoloroptionsLabel     matlab.ui.control.Label
        BackgroundcolorColorPicker      matlab.ui.control.ColorPicker
        BackgroundcolorColorPickerLabel  matlab.ui.control.Label
        CamerapositionTab               matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        Startvisible_UITable            matlab.ui.control.Table
        Initial_3Dview_Button           matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        CamVector_3_EditField           matlab.ui.control.NumericEditField
        CamVector_2_EditField           matlab.ui.control.NumericEditField
        CamVector_1_EditField           matlab.ui.control.NumericEditField
        CameravectorLabel               matlab.ui.control.Label
        SphericalcoordinatescenteristhecameratargetLabel  matlab.ui.control.Label
        CamerazoomEditField             matlab.ui.control.NumericEditField
        CamerazoomEditFieldLabel        matlab.ui.control.Label
        DistanceexpressedinratioofthesquarerootoftheLabel  matlab.ui.control.Label
        DistancefromcameratargetEditField  matlab.ui.control.NumericEditField
        DistancefromcameratargetEditFieldLabel  matlab.ui.control.Label
        AngleexpressedindegreesLabel    matlab.ui.control.Label
        ElevationEditField              matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        AzimuthEditField                matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel           matlab.ui.control.Label
        CoordinateexpressedinratioofthedomainsizeLabel_2  matlab.ui.control.Label
        Coordinatealongaxez_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxezLabel        matlab.ui.control.Label
        Coordinatealongaxey_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxeyLabel        matlab.ui.control.Label
        Coordinatealongaxex_Pos_EditField  matlab.ui.control.NumericEditField
        CoordinatealongaxexEditField_2Label  matlab.ui.control.Label
        SystemofcoordinatesDropDown     matlab.ui.control.DropDown
        SystemofcoordinatesDropDownLabel  matlab.ui.control.Label
        andfromwhereLabel               matlab.ui.control.Label
        WherearewelookingatLabel        matlab.ui.control.Label
        CamerapositionLabel             matlab.ui.control.Label
        CoordinateexpressedinratioofthedomainsizeLabel  matlab.ui.control.Label
        CoordinatealongaxezEditField    matlab.ui.control.NumericEditField
        CoordinatealongaxezEditFieldLabel  matlab.ui.control.Label
        CoordinatealongaxeyEditField    matlab.ui.control.NumericEditField
        CoordinatealongaxeyEditFieldLabel  matlab.ui.control.Label
        CoordinatealongaxexEditField    matlab.ui.control.NumericEditField
        CoordinatealongaxexEditFieldLabel  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        CameratargetLabel               matlab.ui.control.Label
        Videosequence1Tab               matlab.ui.container.Tab
        GridLayout17_2                  matlab.ui.container.GridLayout
        LightingPanel                   matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Light_Sequence_Camera_AddButton  matlab.ui.control.Button
        Light_AddtosequenceEditField    matlab.ui.control.NumericEditField
        AddtosequenceEditField_3Label   matlab.ui.control.Label
        LightIntensity_SensDropDown     matlab.ui.control.DropDown
        SensDropDown_2Label_3           matlab.ui.control.Label
        LightIntensity_ProfileDropDown  matlab.ui.control.DropDown
        ProfileDropDown_3Label_3        matlab.ui.control.Label
        LightIntensity_ChangeDropDown   matlab.ui.control.DropDown
        ChangeisLabel_7                 matlab.ui.control.Label
        NewLightPos_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel_5             matlab.ui.control.Label
        NewLightPos_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label_5        matlab.ui.control.Label
        NewLightPos_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisLabel_6                 matlab.ui.control.Label
        Video_new_sourceintensity_EditField  matlab.ui.control.NumericEditField
        SourceLabel                     matlab.ui.control.Label
        Video_new_ambientintensity_EditField  matlab.ui.control.NumericEditField
        AmbientEditFieldLabel           matlab.ui.control.Label
        LightintensityCheckBox          matlab.ui.control.CheckBox
        LightPos_new_elevation_EditField  matlab.ui.control.NumericEditField
        LightPos_new_azimuth_EditField  matlab.ui.control.NumericEditField
        LightPos_elevation_choice_DropDown  matlab.ui.control.DropDown
        azimuthLabel_5                  matlab.ui.control.Label
        LightPos_azimuth_choice_DropDown  matlab.ui.control.DropDown
        azimuthLabel_4                  matlab.ui.control.Label
        Light_new_z_EditField           matlab.ui.control.NumericEditField
        Light_new_y_EditField           matlab.ui.control.NumericEditField
        Light_new_x_EditField           matlab.ui.control.NumericEditField
        VideoLight_z_DropDown           matlab.ui.control.DropDown
        zLabel_2                        matlab.ui.control.Label
        VideoLight_y_DropDown           matlab.ui.control.DropDown
        yLabel_2                        matlab.ui.control.Label
        VideoLight_x_DropDown           matlab.ui.control.DropDown
        xDropDownLabel_2                matlab.ui.control.Label
        Light_coordinatechoice_DropDown  matlab.ui.control.DropDown
        LightsourcepositionCheckBox     matlab.ui.control.CheckBox
        Transformations_Panel           matlab.ui.container.Panel
        Transformations_GridLayout      matlab.ui.container.GridLayout
        Transformation_Sequence_Camera_AddButton  matlab.ui.control.Button
        Transformation_AddtosequenceEditField  matlab.ui.control.NumericEditField
        AddtosequenceEditField_2Label   matlab.ui.control.Label
        Transformation_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel_3             matlab.ui.control.Label
        Transformation_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label_3        matlab.ui.control.Label
        Transformation_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisLabel_4                 matlab.ui.control.Label
        Transformation_UITable          matlab.ui.control.Table
        CameramovementsPanel            matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Vector_SensDropDown             matlab.ui.control.DropDown
        SensDropDown_2Label_2           matlab.ui.control.Label
        Vector_ProfileDropDown          matlab.ui.control.DropDown
        ProfileDropDown_3Label_2        matlab.ui.control.Label
        Vector_ChangeDropDown           matlab.ui.control.DropDown
        ChangeisLabel_3                 matlab.ui.control.Label
        CameraVector_3_EditField        matlab.ui.control.NumericEditField
        CameraVector_2_EditField        matlab.ui.control.NumericEditField
        CameraVector_1_EditField        matlab.ui.control.NumericEditField
        CameravectorCheckBox            matlab.ui.control.CheckBox
        NewCamPos_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel               matlab.ui.control.Label
        NewCamPos_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label          matlab.ui.control.Label
        NewCamPos_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisLabel_2                 matlab.ui.control.Label
        CameraNewPosition_CoordinatesDropDown  matlab.ui.control.DropDown
        CamPos_z_choice_DropDown        matlab.ui.control.DropDown
        zLabel                          matlab.ui.control.Label
        CamPos_y_choice_DropDown        matlab.ui.control.DropDown
        yLabel                          matlab.ui.control.Label
        CamPos_distance_choice_DropDown  matlab.ui.control.DropDown
        azimuthLabel_3                  matlab.ui.control.Label
        CamPos_elevation_choice_DropDown  matlab.ui.control.DropDown
        azimuthLabel_2                  matlab.ui.control.Label
        CamPos_azimuth_choice_DropDown  matlab.ui.control.DropDown
        azimuthLabel                    matlab.ui.control.Label
        CamPos_x_choice_DropDown        matlab.ui.control.DropDown
        xDropDownLabel                  matlab.ui.control.Label
        CamPos_new_distance_EditField   matlab.ui.control.NumericEditField
        CamPos_new_elevation_EditField  matlab.ui.control.NumericEditField
        CamPos_new_azimuth_EditField    matlab.ui.control.NumericEditField
        NewCamTar_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel_2             matlab.ui.control.Label
        NewCamTar_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label_2        matlab.ui.control.Label
        NewCamTar_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisDropDownLabel           matlab.ui.control.Label
        NewCamTar_z_isDropDown          matlab.ui.control.DropDown
        zisDropDownLabel                matlab.ui.control.Label
        NewCamTar_y_isDropDown          matlab.ui.control.DropDown
        yisDropDownLabel                matlab.ui.control.Label
        NewCamTar_x_isDropDown          matlab.ui.control.DropDown
        xisDropDownLabel                matlab.ui.control.Label
        NewCamTar_z_EditField           matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        NewCamTar_y_EditField           matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        NewCamTar_x_EditField           matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        CamerazoomCheckBox              matlab.ui.control.CheckBox
        CameratargetCheckBox            matlab.ui.control.CheckBox
        CamerapositionCheckBox          matlab.ui.control.CheckBox
        Zoom_SensDropDown               matlab.ui.control.DropDown
        SensDropDown_2Label             matlab.ui.control.Label
        Zoom_ProfileDropDown            matlab.ui.control.DropDown
        ProfileDropDown_3Label          matlab.ui.control.Label
        Zoom_ChangeDropDown             matlab.ui.control.DropDown
        ChangeisLabel                   matlab.ui.control.Label
        NewZoomEditField                matlab.ui.control.NumericEditField
        ZoomEditFieldLabel              matlab.ui.control.Label
        Sequence_Camera_AddButton       matlab.ui.control.Button
        AddtosequenceEditField          matlab.ui.control.NumericEditField
        AddtosequenceEditFieldLabel     matlab.ui.control.Label
        CamPos_new_z_EditField          matlab.ui.control.NumericEditField
        CamPos_new_y_EditField          matlab.ui.control.NumericEditField
        CamPos_new_x_EditField          matlab.ui.control.NumericEditField
        Videosequence2Tab               matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        OpacityPanel                    matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        Opacity_Sequence_Camera_AddButton  matlab.ui.control.Button
        Opacity_AddtosequenceEditField  matlab.ui.control.NumericEditField
        AddtosequenceEditField_4Label   matlab.ui.control.Label
        Opacity_ChangeSensDropDown      matlab.ui.control.DropDown
        SensDropDownLabel_7             matlab.ui.control.Label
        Opacity_ChangeProfileDropDown   matlab.ui.control.DropDown
        ProfileDropDownLabel            matlab.ui.control.Label
        Opacity_ChangeisDropDown        matlab.ui.control.DropDown
        ChangeisDropDownLabel_2         matlab.ui.control.Label
        ChangeOpacity_UITable           matlab.ui.control.Table
        ClippinglabelswithoutclippingFOVsPanel  matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        ClippingLabel_UITable           matlab.ui.control.Table
        ClippingLabel_Sequence_Camera_AddButton  matlab.ui.control.Button
        ClippingLabel_AddtosequenceEditField  matlab.ui.control.NumericEditField
        AddtosequenceEditField_2Label_3  matlab.ui.control.Label
        ClippingLabel_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel_6             matlab.ui.control.Label
        ClippingLabel_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label_6        matlab.ui.control.Label
        ClippingLabel_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisLabel_8                 matlab.ui.control.Label
        ClippingFOVsPanel               matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Clipping_AddtosequenceEditField  matlab.ui.control.NumericEditField
        AddtosequenceEditField_2Label_2  matlab.ui.control.Label
        Clipping_Sequence_Camera_AddButton  matlab.ui.control.Button
        Clipping_ChangeProfileSens_DropDown  matlab.ui.control.DropDown
        SensDropDownLabel_4             matlab.ui.control.Label
        Clipping_ChangeProfile_DropDown  matlab.ui.control.DropDown
        ProfileDropDown_2Label_4        matlab.ui.control.Label
        Clipping_Coordinate_ChangeIs_DropDown  matlab.ui.control.DropDown
        ChangeisLabel_5                 matlab.ui.control.Label
        Clipping_UITable                matlab.ui.control.Table
        FramepersEditField              matlab.ui.control.NumericEditField
        FramepersEditFieldLabel_2       matlab.ui.control.Label
        DvideoButton                    matlab.ui.control.Button
        Videofilename                   matlab.ui.control.EditField
        VideofilenameEditField_6Label   matlab.ui.control.Label
        Videofolder                     matlab.ui.control.Label
        SavefolderButton_6              matlab.ui.control.Button
        ViewcharacterizationresultsTab  matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        VideoexplorerTab                matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        TabGroup5                       matlab.ui.container.TabGroup
        CropandtrimvideoTab             matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        heightEditField                 matlab.ui.control.NumericEditField
        heightEditFieldLabel            matlab.ui.control.Label
        VideoresolutionwidthEditField   matlab.ui.control.NumericEditField
        VideoresolutionwidthEditFieldLabel  matlab.ui.control.Label
        otherframesLabel                matlab.ui.control.Label
        KeepframeeveryEditField         matlab.ui.control.NumericEditField
        KeepframeeveryEditFieldLabel    matlab.ui.control.Label
        formatDropDown                  matlab.ui.control.DropDown
        formatDropDownLabel             matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        FrameEditField                  matlab.ui.control.NumericEditField
        Button_plusframe                matlab.ui.control.Button
        Button_minusframe               matlab.ui.control.Button
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        NewfpsEditField                 matlab.ui.control.NumericEditField
        NewfpsEditFieldLabel            matlab.ui.control.Label
        NewlengthsEditField             matlab.ui.control.NumericEditField
        NewlengthsEditFieldLabel        matlab.ui.control.Label
        KeepframetoEditField            matlab.ui.control.NumericEditField
        toEditField_6Label              matlab.ui.control.Label
        KeepframesfromEditField         matlab.ui.control.NumericEditField
        KeepframesfromEditFieldLabel    matlab.ui.control.Label
        FramepersecondfpsnaLabel        matlab.ui.control.Label
        LengthnaLabel                   matlab.ui.control.Label
        CropyaxistoEditField            matlab.ui.control.NumericEditField
        toEditField_5Label              matlab.ui.control.Label
        CropyaxisfromEditField          matlab.ui.control.NumericEditField
        CropyaxisfromEditFieldLabel     matlab.ui.control.Label
        CropxaxistoEditField            matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        CropxaxisfromEditField          matlab.ui.control.NumericEditField
        CropxaxisfromEditFieldLabel     matlab.ui.control.Label
        NumberofframesnaLabel           matlab.ui.control.Label
        DimensionnaLabel                matlab.ui.control.Label
        NovideofileLabel                matlab.ui.control.Label
        ImportvideofileButton           matlab.ui.control.Button
        Frame_UIAxes                    matlab.ui.control.UIAxes
        ConcatenatevideosTab            matlab.ui.container.Tab
        GridLayout27_2                  matlab.ui.container.GridLayout
        NumberofframesnaLabel_4         matlab.ui.control.Label
        NofolderselectedLabel           matlab.ui.control.Label
        ConcatenatedvideoLabel          matlab.ui.control.Label
        SavefolderButton_7              matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        formatDropDown_2                matlab.ui.control.DropDown
        formatDropDown_2Label           matlab.ui.control.Label
        heightEditField_2               matlab.ui.control.NumericEditField
        heightEditField_2Label          matlab.ui.control.Label
        VideoresolutionwidthEditField_2  matlab.ui.control.NumericEditField
        VideoresolutionwidthEditField_2Label  matlab.ui.control.Label
        NewfpsEditField_2               matlab.ui.control.NumericEditField
        NewfpsEditField_2Label          matlab.ui.control.Label
        NewlengthsEditField_2           matlab.ui.control.NumericEditField
        NewlengthsEditField_2Label      matlab.ui.control.Label
        FramepersecondfpsnaLabel_3      matlab.ui.control.Label
        LengthnaLabel_3                 matlab.ui.control.Label
        NumberofframesnaLabel_3         matlab.ui.control.Label
        DimensionnaLabel_3              matlab.ui.control.Label
        NovideofileLabel_3              matlab.ui.control.Label
        FramepersecondfpsnaLabel_2      matlab.ui.control.Label
        LengthnaLabel_2                 matlab.ui.control.Label
        NumberofframesnaLabel_2         matlab.ui.control.Label
        DimensionnaLabel_2              matlab.ui.control.Label
        NovideofileLabel_2              matlab.ui.control.Label
        Importvideo2Button              matlab.ui.control.Button
        Importvideo1Button              matlab.ui.control.Button
        VideosmustsharesamedimensionLabel  matlab.ui.control.Label
        GalleryTab                      matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        VideosanimationsshownbelowarefromgiffilesafterconversionOriginalvideosaremuchsmootherwithhigherframeratePanel  matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Image5                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        HereyoucanseesomeexamplesproducedwiththistoolLabel  matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        Onlineversion_Label             matlab.ui.control.Label
        NREL_logo                       matlab.ui.control.Image
        Label3                          matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Hyperlink_3                     matlab.ui.control.Hyperlink
        IfyouproducevisualswiththistoolpleasequoteLabel  matlab.ui.control.Label
        Hyperlink_2                     matlab.ui.control.Hyperlink
    end


    properties (Access = private)
        importpath = [];
        Img = [];
        colorsemantic = [];
        seq = [];
        camsav = [];
        box_sz = [];
        SequenceApp
        seqdata = [];
        vidObj = [];
        vidObj1 = [];
        vidObj2 = [];
    end
    
    methods (Access = private)
        
        function [] = Check_files_selection(app)
            if ~isempty(app.Import_UITable.Data)
                selected = cell2mat(app.Import_UITable.Data(:,end));
                id_selected = find(selected);
                n_selected = length(id_selected);
                if id_selected > 0
                    app.RescaleallselectedfilesButton.Enable = 'on';

                    app.ModifyimagesPanel.Enable = 'on';

                    % One or multiple file selected
                    if n_selected==1
                        sz = size(app.Img(id_selected).original);
                        if ~isempty(app.Img(id_selected).modified)
                            sz = size(app.Img(id_selected).modified);
                        end
                        app.Cropaxe1fromEditField.Limits = [1 sz(1)]; app.Cropaxe1fromEditField.Value = 1; 
                        app.Cropaxe2fromEditField.Limits = [1 sz(2)]; app.Cropaxe2fromEditField.Value = 1;
                        app.toEditField.Limits = [1 sz(1)]; app.toEditField.Value = sz(1); 
                        app.toEditField_2.Limits = [1 sz(2)]; app.toEditField_2.Value = sz(2); 
                        if length(sz)==3
                            app.Cropaxe3fromEditField.Enable = 'on'; app.Cropaxe3fromEditField.Limits = [1 sz(3)]; app.Cropaxe3fromEditField.Value = 1; 
                            app.toEditField_3.Enable = 'on'; app.toEditField_3.Limits = [1 sz(3)]; app.toEditField_3.Value = sz(3); 
                        else
                            app.Cropaxe3fromEditField.Enable = 'off';
                            app.toEditField_3.Enable = 'off';          
                            app.Cropaxe3fromEditField.Limits = [1 2]; app.Cropaxe3fromEditField.Value = 1;
                            app.toEditField_3.Limits = [1 2]; app.toEditField_3.Value = 1;
                        end

                        app.ModifyROIPanel.Enable = "on";
                        app.ResetallchangesforallselectedfilesButton.Enable = "off";
                        app.ResetallchangesforthisfileButton.Enable = "on";

                        app.ThreeD_position_UITable.Data = [];
                        app.ThreeD_position_UITable.Enable = 'off';
                        app.ThreeD_Xaxis_distance_EditField.Enable = 'off';
                        app.ThreeD_Yaxis_distance_EditField.Enable = 'off';
                        app.ThreeD_Zaxis_distance_EditField.Enable = 'off';
                        app.XposalignmentDropDown.Enable = 'off';
                        app.YposalignmentDropDown.Enable = 'off';
                        app.ZposalignmentDropDown.Enable = 'off';                        
                    else
                        app.ModifyROIPanel.Enable = 'off';
                        app.ResetallchangesforallselectedfilesButton.Enable = "on";
                        app.ResetallchangesforthisfileButton.Enable = "off";

                        tmp = cell(n_selected,4);
                        for k=1:n_selected
                            tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                            tmp(k,2) = {k};
                            tmp(k,3) = {1};
                            tmp(k,4) = {1};
                        end
                        app.ThreeD_position_UITable.Data = tmp;
                        app.ThreeD_position_UITable.Enable = 'on';
                        app.ThreeD_Xaxis_distance_EditField.Enable = 'on';
                        app.ThreeD_Yaxis_distance_EditField.Enable = 'on';
                        app.ThreeD_Zaxis_distance_EditField.Enable = 'on';
                        app.XposalignmentDropDown.Enable = 'on';
                        app.YposalignmentDropDown.Enable = 'on';
                        app.ZposalignmentDropDown.Enable = 'on';
                    end

                    % Transformation
                    tmp = cell(n_selected,9);
                    for k=1:n_selected
                        tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                        tmp(k,2) = {0};
                        tmp(k,3) = {0};
                        tmp(k,4) = {0};
                        tmp(k,5) = {0};
                        tmp(k,6) = {0};
                        tmp(k,7) = {0};
                        tmp(k,8) = {0};
                        tmp(k,9) = {1};
                    end
                    app.Transformation_UITable.Data = tmp;
                    app.Transformation_UITable.Enable = 'on';

                    % Clipping
                    tmp = cell(n_selected,13);
                    for k=1:n_selected
                        tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                        tmp(k,2) = {''};
                        tmp(k,3) = {''};
                        tmp(k,4) = {''};
                        tmp(k,5) = {''};
                        tmp(k,6) = {''};
                        tmp(k,7) = {''};
                        tmp(k,8) = {true};
                        tmp(k,9) = {[0]};
                        tmp(k,10) = {[1]};
                        tmp(k,11) = {''};
                        tmp(k,12) = {true};
                        tmp(k,13) = {false};                        
                    end
                    app.Clipping_UITable.Data = tmp;
                    app.Clipping_UITable.Enable = 'on';    

                    % Clipping labels                   
                    tmp = cell(n_selected,8);
                    %cat = categorical({'value','size'});
                    for k=1:n_selected
                        tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                        %tmp(k,2) = {cat(1)};
                        tmp(k,2) = {false};
                        tmp(k,3) = {[0]};
                        tmp(k,4) = {[1]};
                        tmp(k,5) = {[0]};
                        tmp(k,6) = {[1]};                        
                        tmp(k,7) = {true};
                        tmp(k,8) = {true};
                    end
                    %tdata = table(tmp(1,1),tmp(1,2),tmp(1,3),tmp(1,4),tmp(1,5),tmp(1,6),tmp(1,7),tmp(1,8));
                    %app.ClippingLabel_UITable.Data = tdata;
                    %app.ClippingLabel_UITable.ColumnEditable = true;
                    app.ClippingLabel_UITable.Data = tmp;
                    app.ClippingLabel_UITable.Enable = 'on';      


                    % Opacity
                    tmp = cell(n_selected,4);
                    for k=1:n_selected
                        tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                        tmp(k,2) = {'0, 1'};         
                        tmp(k,3) = {'0, 1'};                        
                        tmp(k,4) = {true};
                    end
                    app.ChangeOpacity_UITable.Data = tmp;
                    app.ChangeOpacity_UITable.Enable = 'on';   
         
                    % Start visible
                    tmp = cell(n_selected,2);
                    for k=1:n_selected
                        tmp(k,1) = app.Import_UITable.Data(id_selected(k),1);
                        tmp(k,2) = {true};
                    end
                    app.Startvisible_UITable.Data = tmp;
                    app.Startvisible_UITable.Enable = 'on';  

                    n_greylevel = sum(cell2mat(app.Import_UITable.Data(id_selected,8)));
                    n_semantic =  sum(cell2mat(app.Import_UITable.Data(id_selected,9)));
                    n_instance =  sum(cell2mat(app.Import_UITable.Data(id_selected,10)));
                    if n_greylevel>0
                        app.ModifygreylevelimagesPanel.Enable = "on";
                        app.GreylevelPanel.Enable = 'on';
                    else
                        app.ModifygreylevelimagesPanel.Enable = "off";
                        app.GreylevelPanel.Enable = 'off';
                    end
                    if n_instance>0
                        app.ModifyinstanceimagesPanel.Enable = "on";
                        app.InstancePanel.Enable = 'on';
                    else
                        app.ModifyinstanceimagesPanel.Enable = "off";
                        app.InstancePanel.Enable = 'off';
                    end
                    if n_semantic>0
                        app.SemanticPanel.Enable = 'on';
                    else
                        app.SemanticPanel.Enable = 'off';
                    end                    

                    app.GridLayout_2Dslicevisualization.Visible = 'on';
                    app.GridLayout_3Dmain.Visible = 'on';
                    
                else
                    app.RescaleallselectedfilesButton.Enable = 'off';
                    app.ModifyinstanceimagesPanel.Enable = "off";
                    app.ModifygreylevelimagesPanel.Enable = "off";
                    app.ModifyimagesPanel.Enable = "off";
                    app.ModifyROIPanel.Enable = "off";
                    app.ResetallchangesforthisfileButton.Enable = "off";
                    app.ResetallchangesforallselectedfilesButton.Enable = "off";
                    app.GridLayout_3Dmain.Visible = 'off';
                    app.GridLayout_2Dslicevisualization.Visible = 'off';
                    app.Transformation_UITable.Data = [];
                    app.Transformation_UITable.Enable = 'off';
                end
                app.SegmentationcomparisonCheckBoxValueChanged;
            else
                app.RescaleallselectedfilesButton.Enable = 'off';
                app.ModifyinstanceimagesPanel.Enable = "off";
                app.ModifygreylevelimagesPanel.Enable = "off";
                app.ModifyimagesPanel.Enable = "off";
                app.ModifyROIPanel.Enable = "off";
                app.ResetallchangesforthisfileButton.Enable = "off";
                app.ResetallchangesforallselectedfilesButton.Enable = "off";
                app.GridLayout_3Dmain.Visible = 'off';
                app.GridLayout_2Dslicevisualization.Visible = 'off';
            end
            app.LightsourcelocationDropDownValueChanged;

        end
        
        function [] = UpdateImport_table(app,k)
            if ~isempty(app.Img(k).modified)
                sz = size(app.Img(k).modified);
                app.Import_UITable.Data(k,2) = {[num2str(min(min(min(app.Img(k).modified)))) ' - ' num2str(max(max(max(app.Img(k).modified))))]};
                app.Import_UITable.Data(k,3) = {length(unique(app.Img(k).modified))};
                app.Import_UITable.Data(k,4) = {min(min(min(app.Img(k).modified)))};
            else
                sz = size(app.Img(k).original);
                app.Import_UITable.Data(k,2) = {[num2str(min(min(min(app.Img(k).original)))) ' - ' num2str(max(max(max(app.Img(k).original))))]};
                app.Import_UITable.Data(k,3) = {length(unique(app.Img(k).original))};
                app.Import_UITable.Data(k,4) = {min(min(min(app.Img(k).original)))};
            end
            dimension = length(sz);
            if dimension == 2
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
            else
                s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
            end
            app.Import_UITable.Data(k,6) = s;

            app.Cropaxe1fromEditField.Limits = [1 sz(1)]; app.Cropaxe1fromEditField.Value = 1;
            app.Cropaxe2fromEditField.Limits = [1 sz(2)]; app.Cropaxe2fromEditField.Value = 1;
            app.toEditField.Limits = [1 sz(1)]; app.toEditField.Value = sz(1);
            app.toEditField_2.Limits = [1 sz(2)]; app.toEditField_2.Value = sz(2);
            if length(sz)==3
                app.Cropaxe3fromEditField.Enable = 'on'; app.Cropaxe3fromEditField.Limits = [1 sz(3)]; app.Cropaxe3fromEditField.Value = 1;
                app.toEditField_3.Enable = 'on'; app.toEditField_3.Limits = [1 sz(3)]; app.toEditField_3.Value = sz(3);
            else
                app.Cropaxe3fromEditField.Enable = 'off';
                app.toEditField_3.Enable = 'off';
                app.Cropaxe3fromEditField.Limits = [1 2]; app.Cropaxe3fromEditField.Value = 1;
                app.toEditField_3.Limits = [1 2]; app.toEditField_3.Value = 1;
            end
        end      
        
        function [] = Set_colormap(app)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            datatype = cell2mat(app.Import_UITable.Data(:,8:10));
            id_grey = find(selected.*datatype(:,1));
            id_sem = find(selected.*datatype(:,2));
            id_inst = find(selected.*datatype(:,3));    

            % Reset
            for k=1:length(app.Img)
                app.Img(k).selected = false; 
            end
            % Each image starts at 0

            % Set colormap for grey level
            if ~isempty(id_grey)
                for k=1:length(id_grey)
                    if ~isempty(app.Img(id_grey(k)).modified)
                        M = app.Img(id_grey(k)).modified;
                    else
                        M = app.Img(id_grey(k)).original;
                    end                    
                    background = cell2mat(app.Import_UITable.Data(id_grey(k),5));
                    app.Img(id_grey(k)).selected = true;
                    app.Img(id_grey(k)).datatype = 'grey';
                    % Renumeroting: must start at 0
                    if ~isempty(background)
                        id0 = find(M==background);
                        tmp = M;
                        tmp(id0)=[];
                        min_notbackground = min(tmp);
                        M = M - min_notbackground;
                        M(id0) = 0;
                        app.Img(id_grey(k)).modified_forcolormap = M;
                    else
                        M = M - min(min(min(M)));
                        app.Img(id_grey(k)).modified_forcolormap = M;
                    end
                    max_grey = double(max(max(max(M))));
                    ngrey = double(max_grey- 0 + 1);
                    col = app.ColormapDropDown.Value;
                    fh = str2func(col);
                    app.Img(id_grey(k)).colmap = fh(ngrey);
                    app.Img(id_grey(k)).alphamap = app.OpacityprofileDropDown.Value;
                end
            end

            % Set colormap for semantic
            if ~isempty(id_sem)
                if numel(app.SemanticColor_UITable.Data)==0
                    app.UpdateSemanticColormapButtonPushed;
                end
                labels_val = cell2mat(app.SemanticColor_UITable.Data(:,1));
                labels_col = app.colorsemantic;
                labels_alp = cell2mat(app.SemanticColor_UITable.Data(:,4));
                for k=1:length(id_sem)
                    if ~isempty(app.Img(id_sem(k)).modified)
                        M = app.Img(id_sem(k)).modified;
                    else
                        M = app.Img(id_sem(k)).original;
                    end                    
                    app.Img(id_sem(k)).selected = true;
                    app.Img(id_sem(k)).datatype = 'semantic';

                    p.oldlabels = unique(M);
                    n_label = length(p.oldlabels);
                    p.newlabels = 0:1:n_label-1;
                    [M,~,~] = fct_manual_reassign(M,p);
                    app.Img(id_sem(k)).modified_forcolormap = M;
                    col = zeros(n_label,3);
                    alp = zeros(n_label,1);
                    for kk=1:n_label
                        id = find(labels_val==p.oldlabels(kk));
                        col(kk,:) = labels_col(id,:);
                        alp(kk,:) = labels_alp(id);
                    end
                    app.Img(id_sem(k)).colmap = col;
                    app.Img(id_sem(k)).alphamap = alp;
                end    
             end            

            % Set colormap for instance
            if ~isempty(id_inst)            
                for k=1:length(id_inst)
                    if ~isempty(app.Img(id_inst(k)).modified)
                        M = app.Img(id_inst(k)).modified;
                    else
                        M = app.Img(id_inst(k)).original;
                    end
                    app.Img(id_inst(k)).selected = true;
                    app.Img(id_inst(k)).datatype = 'instance';

                    pore = cell2mat(app.Import_UITable.Data(id_inst(k),4));
                    idpore = find(M==pore);
                    background = cell2mat(app.Import_UITable.Data(id_inst(k),5));
                    % Renumeroting: must start at 0
                    if ~isempty(background)
                        idback = find(M==background);
                    else
                        idback = [];
                    end
                    id0 = [idback;idpore];
                    %M = M+1; % CAN BE VERY LONG
                    %M(id0)=0;
                    %[M,~,~] = fct_remvovegapinnumbering(M,[]);
                    app.Img(id_inst(k)).modified_forcolormap = M;

                    ninst = max(max(max(M)))+1;
                    colmap_inst = ones(ninst,3);
                    if strcmp(app.ColormapDropDown_2.Value,'random')
                        colmap_inst(2:end,:) = rand(ninst-1,3);
                    else
                        col = app.ColormapDropDown_2.Value;
                        fh = str2func(col);
                        colmap_inst(2:end,:) = fh(ninst-1);
                    end
                    app.Img(id_inst(k)).colmap = colmap_inst;
                end                  
            end
        end
        
        function [] = Set_positions(app)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            n_selected = length(id_selected);

            if n_selected == 1 % Single volume
                % Downscale if necessary
                tot = numel(app.Img(id_selected(1)).modified_forcolormap)*1e-6;
                % maxtot = app.MaximumnumberofpixelsinmillionsEditField.Value;
                maxtot = 100e9;
                current_voxelsize = 1;
                new_voxelsize = (tot/maxtot)^(1/3);
                if tot > maxtot % Need downscaling
                    app.NoadditionalrescalingappliedLabel.Text = ['Downscaling with a factor ' num2str(new_voxelsize) ', please wait...']; pause(0.1);
                    p.scaling_factor = current_voxelsize/new_voxelsize;
                    p.background = 0;
                    if cell2mat(app.Import_UITable.Data(id_selected(1),8))
                        p.datatype = 'Grey level';
                    elseif cell2mat(app.Import_UITable.Data(id_selected(1),9))
                        p.datatype = 'Label';
                    elseif cell2mat(app.Import_UITable.Data(id_selected(1),10))
                        p.datatype = 'Label';
                    end
                    [app.Img(id_selected(1)).modified_forcolormap,~,~] = fct_isotropicscaling(app.Img(id_selected(1)).modified_forcolormap, p);
                    app.NoadditionalrescalingappliedLabel.Text = ['Downscaling with a factor ' num2str(new_voxelsize) ', done.'];
                else
                    % app.NoadditionalrescalingappliedLabel.Text = 'No additional rescaling applied';
                end
                app.Img(id_selected(1)).tx = 0;
                app.Img(id_selected(1)).ty = 0;                
                app.Img(id_selected(1)).tz = 0;
                app.box_sz = size(app.Img(id_selected(1)).modified_forcolormap);

            else % Multiple volumes
                % Set positions and downscale if necessary
                szs = zeros(n_selected,3);
                for k=1:n_selected
                    szs(k,:) = size(app.Img(id_selected(k)).modified_forcolormap);
                end

                pos = cell2mat(app.ThreeD_position_UITable.Data(:,2:end));
                xsav = pos(:,1); ysav = pos(:,2);
                pos(:,1) = ysav;
                pos(:,2) = xsav;
                xposs = unique(pos(:,1)); xposs =sort(xposs,'ascend');
                yposs = unique(pos(:,2)); yposs =sort(yposs,'ascend');
                zposs = unique(pos(:,3)); zposs =sort(zposs,'ascend');

                dx = app.ThreeD_Yaxis_distance_EditField.Value;
                dy = app.ThreeD_Xaxis_distance_EditField.Value;
                dz = app.ThreeD_Zaxis_distance_EditField.Value;

                x0 = 1;
                for kpos=1:length(xposs)
                    ks = find(pos(:,1)==xposs(kpos));
                    L = 0;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        dl = szs(k,1);
                        L = max([L dl]);
                    end
                    x1 = x0 + L - 1;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        app.Img(id_selected(k)).x0 = x0;
                        app.Img(id_selected(k)).x1 = x1;
                    end
                    x0 = x1 + dx + 1;
                end

                y0 = 1;
                for kpos=1:length(yposs)
                    ks = find(pos(:,2)==yposs(kpos));
                    L = 0;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        dl = szs(k,2);
                        L = max([L dl]);
                    end
                    y1 = y0 + L - 1;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        app.Img(id_selected(k)).y0 = y0;
                        app.Img(id_selected(k)).y1 = y1;
                    end
                    y0 = y1 + dy + 1;
                end

                z0 = 1;
                for kpos=1:length(zposs)
                    ks = find(pos(:,3)==zposs(kpos));
                    L = 0;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        dl = szs(k,3);
                        L = max([L dl]);
                    end
                    z1 = z0 + L - 1;
                    for kidx = 1:length(ks)
                        k=ks(kidx);
                        app.Img(id_selected(k)).z0 = z0;
                        app.Img(id_selected(k)).z1 = z1;
                    end
                    z0 = z1 + dz + 1;
                end             
                              
                % Is total volume too big?
                tot = x1*y1*z1*1e-6;
                %maxtot = app.MaximumnumberofpixelsinmillionsEditField.Value;
                maxtot = 100e9;
                current_voxelsize = 1;
                new_voxelsize = (tot/maxtot)^(1/3);
                if tot > maxtot % Need downscaling
                    app.NoadditionalrescalingappliedLabel.Text = ['Downscaling with a factor ' num2str(new_voxelsize) ', please wait...']; pause(0.1);
                    p.scaling_factor = current_voxelsize/new_voxelsize;
                    p.background = 0;
                    for k=1:length(id_selected)
                        if cell2mat(app.Import_UITable.Data(id_selected(k),8))
                            p.datatype = 'Grey level';
                        elseif cell2mat(app.Import_UITable.Data(id_selected(k),9))
                            p.datatype = 'Label';
                        elseif cell2mat(app.Import_UITable.Data(id_selected(k),10))
                            p.datatype = 'Label';
                        end
                        [app.Img(id_selected(k)).modified_forcolormap,~,~] = fct_isotropicscaling(app.Img(id_selected(k)).modified_forcolormap, p);
                        szs(k,:) = size(app.Img(id_selected(k)).modified_forcolormap);
                    end
                    dx = round(dx * current_voxelsize/new_voxelsize);
                    dy = round(dy * current_voxelsize/new_voxelsize);
                    dz = round(dz * current_voxelsize/new_voxelsize);
                    app.NoadditionalrescalingappliedLabel.Text = ['Downscaling with a factor ' num2str(new_voxelsize) ', done.'];

                    x0 = 1;
                    for kpos=1:length(xposs)
                        ks = find(pos(:,1)==xposs(kpos));
                        L = 0;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            dl = szs(k,1);
                            L = max([L dl]);
                        end
                        x1 = x0 + L - 1;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            app.Img(id_selected(k)).x0 = x0;
                            app.Img(id_selected(k)).x1 = x1;
                        end
                        x0 = x1 + dx + 1;
                    end

                    y0 = 1;
                    for kpos=1:length(yposs)
                        ks = find(pos(:,2)==yposs(kpos));
                        L = 0;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            dl = szs(k,2);
                            L = max([L dl]);
                        end
                        y1 = y0 + L - 1;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            app.Img(id_selected(k)).y0 = y0;
                            app.Img(id_selected(k)).y1 = y1;
                        end
                        y0 = y1 + dy + 1;
                    end

                    z0 = 1;
                    for kpos=1:length(zposs)
                        ks = find(pos(:,3)==zposs(kpos));
                        L = 0;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            dl = szs(k,3);
                            L = max([L dl]);
                        end
                        z1 = z0 + L - 1;
                        for kidx = 1:length(ks)
                            k=ks(kidx);
                            app.Img(id_selected(k)).z0 = z0;
                            app.Img(id_selected(k)).z1 = z1;
                        end
                        z0 = z1 + dz + 1;
                    end
                else
                    % app.NoadditionalrescalingappliedLabel.Text = 'No additional rescaling applied';
                end

                % Box size
                app.box_sz = [x1 y1 z1];

                % Set transform
                datatype = cell2mat(app.Import_UITable.Data(:,8:10));
                id_grey = find(selected.*datatype(:,1));
                id_sem = find(selected.*datatype(:,2));
                id_inst = find(selected.*datatype(:,3));
                for dataformat = 1:3
                    if dataformat==1
                        ids = id_grey;
                    elseif dataformat==2
                        ids = id_sem;
                    elseif dataformat==3
                        ids = id_inst;            
                    end

                    for k=1:length(ids)
                        currentM = app.Img(ids(k)).modified_forcolormap;
                        sz = size(currentM);
                        bounds_x0 = app.Img(ids(k)).x0-1;
                        bounds_x1 = app.Img(ids(k)).x1-1;
                        bounds_y0 = app.Img(ids(k)).y0-1;
                        bounds_y1 = app.Img(ids(k)).y1-1;
                        bounds_z0 = app.Img(ids(k)).z0-1;
                        bounds_z1 = app.Img(ids(k)).z1-1;
                        if strcmp(app.YposalignmentDropDown.Value,'Bottom')
                            app.Img(ids(k)).tx = bounds_x0;
                        elseif strcmp(app.YposalignmentDropDown.Value,'Middle')
                            center = (bounds_x0+bounds_x1)/2;
                            app.Img(ids(k)).tx = max([1 round(center-sz(1)/2)]);
                        elseif strcmp(app.YposalignmentDropDown.Value,'Top')
                            x1 = bounds_x1;
                            app.Img(ids(k)).tx = x1-sz(1)+1;
                        end
                        if strcmp(app.XposalignmentDropDown.Value,'Bottom')
                            app.Img(ids(k)).ty = bounds_y0;
                        elseif strcmp(app.XposalignmentDropDown.Value,'Middle')
                            center = (bounds_y0+bounds_y1)/2;
                            app.Img(ids(k)).ty = max([1 round(center-sz(2)/2)]);
                            y1 = y0+sz(2)-1;
                        elseif strcmp(app.XposalignmentDropDown.Value,'Top')
                            y1 = bounds_y1;
                            app.Img(ids(k)).ty = y1-sz(2)+1;
                        end
                        if strcmp(app.ZposalignmentDropDown.Value,'Bottom')
                            %z0 = bounds_z0;
                            %z1 = z0+sz(3)-1;
                            app.Img(ids(k)).tz = bounds_z0;
                        elseif strcmp(app.ZposalignmentDropDown.Value,'Middle')
                            center = (bounds_z0+bounds_z1)/2;
                            z0 = max([1 round(center-sz(3)/2)]);
                            z1 = z0+sz(3)-1;
                            app.Img(ids(k)).tz = max([1 round(center-sz(3)/2)]);
                        elseif strcmp(app.ZposalignmentDropDown.Value,'Top')
                            z1 = bounds_z1;
                            z0 = z1-sz(3)+1;
                            app.Img(ids(k)).tz = z1-sz(3)+1;
                        end
                    end
                end 

            end
        end
        
        function p = Set_viewer(app,id_selected)
           if app.ScalebarCheckBox.Value
                voxelsize = cell2mat(app.Import_UITable.Data(id_selected,7));
                if length(unique(voxelsize))==1
                    p.voxellength = voxelsize(1);
                    p.voxelunit = app.VoxelsizeunitEditField.Value;
                    p.scalebar = true;
                    p.scalebar_alignment = app.Scalebar_location_DropDown.Value;
                    p.scalebar_length = app.Scalebar_lengthEditField.Value;
                    p.scalebar_lengthexpressedin = app.ScaleLengthexpressedinDropDown.Value;
                    p.scalebar_xlocation = app.ScaleBar_X_LocatedDropDown.Value;
                    p.scalebar_ylocation = app.ScaleBar_Y_LocatedDropDown.Value;
                    p.scalebar_zlocation = app.ScaleBar_Z_LocatedDropDown.Value;
                    p.scalebar_dist_fromplane_xy = app.DistancefromplanexyEditField.Value;
                    p.scalebar_dist_fromplane_xz = app.DistancefromplanexzEditField.Value;
                    p.scalebar_dist_fromplane_yz = app.DistancefromplaneyzEditField.Value;
                else
                    p.scalebar = false;
                end
            else
                p.scalebar = false;
            end
            p.figure_size = app.FiguresizeEditField.Value;
            p.box = app.ShowboxCheckBox.Value;
            p.orientationaxes = app.ShoworientationaxesCheckBox.Value;
            p.backgroundcolor = app.BackgroundcolorColorPicker.Value;
            p.gradientcolor = app.GradientcolorColorPicker.Value;
            p.gradient = app.BackgroundGradientCheckBox.Value;

            % Camera
            p.CameraTarget = [app.CoordinatealongaxexEditField.Value app.CoordinatealongaxeyEditField.Value app.CoordinatealongaxezEditField.Value];

            p.CameraPosition_coordinatesystem = app.SystemofcoordinatesDropDown.Value;
            p.CameraPosition_azimuth = app.AzimuthEditField.Value;
            p.CameraPosition_elevation = app.ElevationEditField.Value;
            p.CameraPosition_distance = app.DistancefromcameratargetEditField.Value;
            p.CameraPosition_x = app.Coordinatealongaxex_Pos_EditField.Value;
            p.CameraPosition_y = app.Coordinatealongaxey_Pos_EditField.Value;
            p.CameraPosition_z = app.Coordinatealongaxez_Pos_EditField.Value;

            vec = [app.CamVector_1_EditField.Value app.CamVector_2_EditField.Value app.CamVector_3_EditField.Value];            
            p.CameraVector = vec/norm(vec);
                        
            p.zoom = app.CamerazoomEditField.Value;

            % Lighting
            p.ambient_light = app.AmbientlightEditField.Value;
            p.diffuse_light = app.LightsourceintensityEditField.Value;
            p.light_color = app.LightcolorColorPicker.Value;
            p.light_position_mode = app.LightsourcelocationDropDown.Value;
            p.light_position_az = app.Light_AzimuthEditField.Value;
            p.light_position_el = app.Light_ElevationEditField.Value;
            p.light_position_x = app.Light_Coordinatealongaxex_Pos_EditField.Value;
            p.light_position_y = app.Light_Coordinatealongaxey_Pos_EditField.Value;
            p.light_position_z = app.Light_Coordinatealongaxez_Pos_EditField.Value;
        end
        
        function Addseqdata(app, seqnum, val)
            if isempty(app.seqdata)
                app.seqdata = val;
                app.AddtosequenceEditField.Limits = [1,2];
                app.Light_AddtosequenceEditField.Limits = [1,2];
                app.Transformation_AddtosequenceEditField.Limits = [1,2];
                app.Clipping_AddtosequenceEditField.Limits = [1,2];
                app.Opacity_AddtosequenceEditField.Limits = [1,2];
                app.SequenceApp = VideoSequence(app,app.seqdata);
            else
                [n,~] = size(app.seqdata);
                app.seqdata(n+1,:) = val;
                app.AddtosequenceEditField.Limits = [1,seqnum+1];
                app.Light_AddtosequenceEditField.Limits = [1,seqnum+1];
                app.Transformation_AddtosequenceEditField.Limits = [1,seqnum+1];
                app.Clipping_AddtosequenceEditField.Limits = [1,seqnum+1];
                app.Opacity_AddtosequenceEditField.Limits = [1,seqnum+1];
                try
                    UpdateGUI_from_main(app.SequenceApp,app.seqdata);
                catch % App has been closed
                    app.SequenceApp = VideoSequence(app,app.seqdata);
                end
            end
            app.AddtosequenceEditField.Value = app.AddtosequenceEditField.Limits(2);
            app.Light_AddtosequenceEditField.Value = app.Light_AddtosequenceEditField.Limits(2);
            app.Transformation_AddtosequenceEditField.Value = app.Transformation_AddtosequenceEditField.Limits(2);
            app.Clipping_AddtosequenceEditField.Value = app.Clipping_AddtosequenceEditField.Limits(2);
            app.Opacity_AddtosequenceEditField.Value = app.Opacity_AddtosequenceEditField.Limits(2);
            app.DvideoButton.Enable = 'on';            
        end
        
        function [] = check_filesforsegmentation(app)
            selected_2D = cell2mat(app.TwoD_UITable.Data(:,end));
            id_selected_2D = find(selected_2D);
            n_selected_2D = length(id_selected_2D);
            if n_selected_2D>1
                % Check size
                sizes = app.TwoD_UITable.Data(id_selected_2D,2);
                if length(unique(sizes))==1
                    isgroundtruth = cell2mat(app.TwoD_UITable.Data(id_selected_2D,5));
                    isgroundtruth = find(isgroundtruth);
                    isgrey = cell2mat(app.TwoD_UITable.Data(id_selected_2D,4));
                    isgrey = find(isgrey);
                    ngrey = length(isgrey);
                    if isempty(isgroundtruth)
                        if ngrey==1 && n_selected_2D>1
                            app.TwoDview_Button.Enable = 'on';
                        else
                            app.TwoDview_Button.Enable = 'off';
                        end
                    else
                        if ngrey==0 && n_selected_2D>1
                            app.TwoDview_Button.Enable = 'on';
                        else
                            app.TwoDview_Button.Enable = 'off';
                        end  
                    end
                else
                    app.TwoDview_Button.Enable = 'off';
                end
            else
                app.TwoDview_Button.Enable = 'off';
            end            
        end
        
        function [] = update_frame(app,frame)
            vidframe = read(app.vidObj,frame);
            from_x = app.CropxaxisfromEditField.Value;
            to_x = app.CropxaxistoEditField.Value;
            from_y = app.CropyaxisfromEditField.Value;
            to_y = app.CropyaxistoEditField.Value;

            if from_x>1
                %vidframe(1:end,from_x,1) = 255;
                %vidframe(1:end,from_x,2) = 0;
                %vidframe(1:end,from_x,3) = 0;
                vidframe(1:end,1:from_x,:) = vidframe(1:end,1:from_x,:)/2;
            end

            if to_x < app.vidObj.Width
                %vidframe(1:end,to_x,1) = 255;
                %vidframe(1:end,to_x,2) = 0;
                %vidframe(1:end,to_x,3) = 0;
                vidframe(1:end,to_x:end,:) = vidframe(1:end,to_x:end,:)/2;
            end

            if from_y>1
                %vidframe(from_y,1:end,1) = 255;
                %vidframe(from_y,1:end,2) = 0;
                %vidframe(from_y,1:end,3) = 0;
                vidframe(1:from_y,1:end,:) = vidframe(1:from_y,1:end,:)/2;
            end

            if to_y < app.vidObj.Height
                %vidframe(to_y,1:end,1) = 255;
                %vidframe(to_y,1:end,2) = 0;
                %vidframe(to_y,1:end,3) = 0;
                vidframe(to_y:end,1:end,:) = vidframe(to_y:end,1:end,:)/2;
            end

            imshow(vidframe,'Parent',app.Frame_UIAxes);
        end
        
        function [] = Calculate_fps_time(app,choice)
            frame_start = app.KeepframesfromEditField.Value;
            frame_end = app.KeepframetoEditField.Value;
            everyother = app.KeepframeeveryEditField.Value;
            frames = [frame_start:everyother:frame_end];
            n_frame = length(frames);
            video_length = app.NewlengthsEditField.Value;
            fps = app.NewfpsEditField.Value;
            if strcmp(choice,'imposed_length')
                fps = n_frame*1/video_length;
                app.NewfpsEditField.Value = fps;
            elseif strcmp(choice,'imposed_fps')
                video_length = n_frame*1/fps;
                app.NewlengthsEditField.Value = video_length;
            end  
        end
        
        function [] = Check_bothvideos(app)
            if isempty(app.vidObj1) || isempty(app.vidObj2)
                app.VideoresolutionwidthEditField_2.Enable = 'off';
                app.heightEditField_2.Enable = 'off';
                app.NewfpsEditField_2.Enable = 'off';
                app.NewlengthsEditField_2.Enable = 'off';
                app.SaveButton_2.Enable = 'off';
                app.NumberofframesnaLabel_4.Text = 'Number of frames: n/a';
                app.NofolderselectedLabel.Text = 'No folder selected';
            else
                if app.vidObj1.Width == app.vidObj2.Width && app.vidObj2.Height == app.vidObj2.Height
                    app.VideoresolutionwidthEditField_2.Value = app.vidObj1.Width;
                    app.heightEditField_2.Value = app.vidObj1.Height;
                    app.NumberofframesnaLabel_4.Text = ['Number of frames: ' num2str(app.vidObj1.NumFrames + app.vidObj2.NumFrames)];
                    app.NewlengthsEditField_2.Value = app.vidObj1.Duration + app.vidObj2.Duration;
                    app.Calculate_fps_time_concatenatedvideo('imposed_length');
                    [folder,~,~] = fileparts(app.NovideofileLabel_2.Text);
                    app.NofolderselectedLabel.Text = folder;

                    app.VideoresolutionwidthEditField_2.Enable = 'on';
                    app.heightEditField_2.Enable = 'on';
                    app.NewfpsEditField_2.Enable = 'on';
                    app.NewlengthsEditField_2.Enable = 'on';
                    app.SaveButton_2.Enable = 'on';
                else
                    app.VideoresolutionwidthEditField_2.Enable = 'off';
                    app.heightEditField_2.Enable = 'off';
                    app.NewfpsEditField_2.Enable = 'off';
                    app.NewlengthsEditField_2.Enable = 'off';
                    app.SaveButton_2.Enable = 'off';
                    app.NumberofframesnaLabel_4.Text = 'Number of frames: n/a';
                    app.NofolderselectedLabel.Text = 'No folder selected';
                end
            end            
        end        
        
        function [] = Calculate_fps_time_concatenatedvideo(app,choice)
            n_frame = app.vidObj1.NumFrames + app.vidObj2.NumFrames;
            video_length = app.NewlengthsEditField_2.Value;
            fps = app.NewfpsEditField_2.Value;
            if strcmp(choice,'imposed_length')
                fps = n_frame*1/video_length;
                app.NewfpsEditField_2.Value = fps;
            elseif strcmp(choice,'imposed_fps')
                video_length = n_frame*1/fps;
                app.NewlengthsEditField_2.Value = video_length;
            end  
        end
    end
    
    methods (Access = public)
        
        function UpdateGUI_fromVideoSequence(app,SelectedSeq)

            if isfield(app.seq(SelectedSeq),'camera_zoom_todo') && ~isempty(app.seq(SelectedSeq).camera_zoom_todo)
                % Camera zoom
                app.CamerazoomCheckBox.Value = app.seq(SelectedSeq).camera_zoom_todo;
                app.NewZoomEditField.Value = app.seq(SelectedSeq).camera_zoom_value;
                app.Zoom_ChangeDropDown.Value = app.seq(SelectedSeq).camera_zoom_change_is;
                app.Zoom_ProfileDropDown.Value = app.seq(SelectedSeq).camera_zoom_profile;
                app.Zoom_SensDropDown.Value = app.seq(SelectedSeq).camera_zoom_sens;
                app.CamerazoomCheckBoxValueChanged;
                app.Zoom_ChangeDropDownValueChanged;

                % Camera vector
                app.CamerazoomCheckBox.Value; app.seq(SelectedSeq).camera_vector_todo;
                app.CameraVector_1_EditField.Value = app.seq(SelectedSeq).camera_vector_value(1);
                app.CameraVector_2_EditField.Value = app.seq(SelectedSeq).camera_vector_value(2);
                app.CameraVector_3_EditField.Value = app.seq(SelectedSeq).camera_vector_value(3);
                app.Vector_ChangeDropDown.Value = app.seq(SelectedSeq).camera_vector_change_is;
                app.Vector_ProfileDropDown.Value = app.seq(SelectedSeq).camera_vector_profile;
                app.Vector_SensDropDown.Value = app.seq(SelectedSeq).camera_vector_sens;
                app.CameravectorCheckBoxValueChanged;
                app.Vector_ChangeDropDownValueChanged;

                % Camera position
                app.CamPos_new_azimuth_EditField.Limits = app.camsav(SelectedSeq).CamPos_new_azimuth_EditField_limits;
                app.CamPos_new_elevation_EditField.Limits = app.camsav(SelectedSeq).CamPos_new_elevation_EditField_limits;
                app.CamPos_new_distance_EditField.Limits = app.camsav(SelectedSeq).CamPos_new_distance_EditField_limits;

                app.CamerapositionCheckBox.Value = app.seq(SelectedSeq).camera_position_todo;
                app.CameraNewPosition_CoordinatesDropDown.Value = app.seq(SelectedSeq).camera_coordinate;
                app.CamPos_new_x_EditField.Value = app.seq(SelectedSeq).camera_x;
                app.CamPos_new_y_EditField.Value = app.seq(SelectedSeq).camera_y;
                app.CamPos_new_z_EditField.Value = app.seq(SelectedSeq).camera_z;
                app.CamPos_x_choice_DropDown.Value = app.seq(SelectedSeq).camera_x_choice;
                app.CamPos_y_choice_DropDown.Value = app.seq(SelectedSeq).camera_y_choice;
                app.CamPos_z_choice_DropDown.Value = app.seq(SelectedSeq).camera_z_choice;
                app.CamPos_new_azimuth_EditField.Value = app.seq(SelectedSeq).camera_azimuth;
                app.CamPos_new_elevation_EditField.Value = app.seq(SelectedSeq).camera_elevation;
                app.CamPos_new_distance_EditField.Value = app.seq(SelectedSeq).camera_dist;
                app.CamPos_azimuth_choice_DropDown.Value = app.seq(SelectedSeq).camera_azimuth_choice;
                app.CamPos_elevation_choice_DropDown.Value = app.seq(SelectedSeq).camera_elevation_choice;
                app.CamPos_distance_choice_DropDown.Value = app.seq(SelectedSeq).camera_dist_choice;
                app.NewCamPos_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).camera_coordinate_change_is;
                app.NewCamPos_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).camera_coordinate_profile;
                app.NewCamPos_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).camera_coordinate_sens;
                app.CamerapositionCheckBoxValueChanged;
                app.NewCamPos_Coordinate_ChangeIs_DropDownValueChanged;

                % Camera target
                app.CameratargetCheckBox.Value = app.seq(SelectedSeq).camera_target_todo;
                app.NewCamTar_x_isDropDown.Value = app.seq(SelectedSeq).camera_target_x_is;
                app.NewCamTar_y_isDropDown.Value = app.seq(SelectedSeq).camera_target_y_is;
                app.NewCamTar_z_isDropDown.Value = app.seq(SelectedSeq).camera_target_z_is;
                app.NewCamTar_x_EditField.Value = app.seq(SelectedSeq).camera_target_x;
                app.NewCamTar_y_EditField.Value = app.seq(SelectedSeq).camera_target_y;
                app.NewCamTar_z_EditField.Value = app.seq(SelectedSeq).camera_target_z;
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).camera_target_change_is;
                app.NewCamTar_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).camera_target_profile;
                app.NewCamTar_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).camera_target_sens;
                app.CameratargetCheckBoxValueChanged;
                app.NewCamTar_Coordinate_ChangeIs_DropDownValueChanged;
            end

            if isfield(app.seq(SelectedSeq),'light_intensity_todo') && ~isempty(app.seq(SelectedSeq).light_intensity_todo)
                % Light intensity
                app.LightintensityCheckBox.Value = app.seq(SelectedSeq).light_intensity_todo;
                app.Video_new_ambientintensity_EditField.Value = app.seq(SelectedSeq).light_newambientintensity_value;
                app.Video_new_sourceintensity_EditField.Value = app.seq(SelectedSeq).light_newsourceintensity_value;
                app.LightIntensity_ChangeDropDown.Value = app.seq(SelectedSeq).light_intensity_change;
                app.LightIntensity_ProfileDropDown.Value = app.seq(SelectedSeq).light_intensity_profile;
                app.LightIntensity_SensDropDown.Value = app.seq(SelectedSeq).light_intensity_sens;
                app.LightintensityCheckBoxValueChanged2;
                app.LightIntensity_ChangeDropDownValueChanged;

                % Light position
                app.LightPos_new_azimuth_EditField.Limits = app.camsav(SelectedSeq).LightPos_new_azimuth_EditField_limits;
                app.LightPos_new_elevation_EditField.Limits = app.camsav(SelectedSeq).LightPos_new_elevation_EditField_limits;
                app.LightsourcepositionCheckBox.Value = app.seq(SelectedSeq).light_movement_todo;
                app.Light_coordinatechoice_DropDown.Value = app.seq(SelectedSeq).light_coordinate;
                app.Light_new_x_EditField.Value = app.seq(SelectedSeq).light_x;
                app.Light_new_y_EditField.Value = app.seq(SelectedSeq).light_y;
                app.Light_new_z_EditField.Value = app.seq(SelectedSeq).light_z;
                app.VideoLight_x_DropDown.Value = app.seq(SelectedSeq).light_x_choice;
                app.VideoLight_y_DropDown.Value = app.seq(SelectedSeq).light_y_choice;
                app.VideoLight_z_DropDown.Value = app.seq(SelectedSeq).light_z_choice;
                app.LightPos_new_azimuth_EditField.Value = app.seq(SelectedSeq).light_azimuth;
                app.LightPos_new_elevation_EditField.Value = app.seq(SelectedSeq).light_elevation;
                app.LightPos_azimuth_choice_DropDown.Value = app.seq(SelectedSeq).light_azimuth_choice;
                app.LightPos_elevation_choice_DropDown.Value = app.seq(SelectedSeq).light_elevation_choice;
                app.NewLightPos_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).light_coordinate_change_is;
                app.NewLightPos_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).light_coordinate_profile;
                app.NewLightPos_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).light_coordinate_sens;
                app.LightsourcepositionCheckBoxValueChanged;
                app.NewLightPos_Coordinate_ChangeIs_DropDownValueChanged;
            end           

            % Transformation.
            if isfield(app.seq(SelectedSeq),'transformation') && ~isempty(app.seq(SelectedSeq).transformation)
                [n,~] = size(app.Transformation_UITable.Data);
                for k=1:n
                    app.Transformation_UITable.Data(k,2) = {app.seq(SelectedSeq).vol(k).tr(1)};
                    app.Transformation_UITable.Data(k,3) = {app.seq(SelectedSeq).vol(k).tr(2)};
                    app.Transformation_UITable.Data(k,4) = {app.seq(SelectedSeq).vol(k).tr(3)};
                    app.Transformation_UITable.Data(k,5) = {app.seq(SelectedSeq).vol(k).rot(1)};
                    app.Transformation_UITable.Data(k,6) = {app.seq(SelectedSeq).vol(k).rot(2)};
                    app.Transformation_UITable.Data(k,7) = {app.seq(SelectedSeq).vol(k).rot(3)};
                    app.Transformation_UITable.Data(k,8) = {app.seq(SelectedSeq).vol(k).time(1)};
                    app.Transformation_UITable.Data(k,9) = {app.seq(SelectedSeq).vol(k).time(2)};  
                end
                app.Transformation_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).transformation_change_is;
                app.Transformation_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).transformation_profile;
                app.Transformation_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).transformation_sens;
                app.Transformation_Coordinate_ChangeIs_DropDownValueChanged;
            end

            % Clipping (FOV)
            if isfield(app.seq(SelectedSeq),'clipping') && ~isempty(app.seq(SelectedSeq).clipping)
                [n,~] = size(app.Clipping_UITable.Data);
                for k=1:n
                    app.Clipping_UITable.Data(k,2) = app.seq(SelectedSeq).vol(k).cliping_xyz(1);
                    app.Clipping_UITable.Data(k,3) = app.seq(SelectedSeq).vol(k).cliping_xyz(2);
                    app.Clipping_UITable.Data(k,4) = app.seq(SelectedSeq).vol(k).cliping_xyz(3);
                    app.Clipping_UITable.Data(k,5) = app.seq(SelectedSeq).vol(k).cliping_xyz(4);
                    app.Clipping_UITable.Data(k,6) = app.seq(SelectedSeq).vol(k).cliping_xyz(5);
                    app.Clipping_UITable.Data(k,7) = app.seq(SelectedSeq).vol(k).cliping_xyz(6);
                    app.Clipping_UITable.Data(k,8) = {app.seq(SelectedSeq).vol(k).orthogonal(1)};
                    app.Clipping_UITable.Data(k,9) = {app.seq(SelectedSeq).vol(k).clipping_time(1)};
                    app.Clipping_UITable.Data(k,10) = {app.seq(SelectedSeq).vol(k).clipping_time(2)};
                    app.Clipping_UITable.Data(k,11) = {app.seq(SelectedSeq).vol(k).clipping_label};
                    app.Clipping_UITable.Data(k,12) = {app.seq(SelectedSeq).vol(k).clipping_additive};
                    app.Clipping_UITable.Data(k,13) = {app.seq(SelectedSeq).vol(k).clipping_todo};
                end
                app.Clipping_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).clipping_change_is;
                app.Clipping_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).clipping_profile;
                app.Clipping_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).clipping_sens;
                app.Clipping_Coordinate_ChangeIs_DropDownValueChanged;
            end       

            % Clipping (labels)
            if isfield(app.seq(SelectedSeq),'cliplabel') && ~isempty(app.seq(SelectedSeq).cliplabel)
                [n,~] = size(app.ClippingLabel_UITable.Data);
                for k=1:n
                    app.Clipping_UITable.Data(k,2) = app.seq(SelectedSeq).vol(k).cliplabel_ordering_size;
                    app.Clipping_UITable.Data(k,3) = {app.seq(SelectedSeq).vol(k).cliplabel_fromto(1)};
                    app.Clipping_UITable.Data(k,4) = {app.seq(SelectedSeq).vol(k).cliplabel_fromto(2)};
                    app.Clipping_UITable.Data(k,5) = {app.seq(SelectedSeq).vol(k).cliplabel_times(1)};
                    app.Clipping_UITable.Data(k,6) = {app.seq(SelectedSeq).vol(k).cliplabel_times(2)};
                    app.Clipping_UITable.Data(k,7) = {app.seq(SelectedSeq).vol(k).cliplabel_additive};
                    app.Clipping_UITable.Data(k,8) = {app.seq(SelectedSeq).vol(k).cliplabel_todo};
                end
                app.ClippingLabel_Coordinate_ChangeIs_DropDown.Value = app.seq(SelectedSeq).cliplabel_change_is;
                app.ClippingLabel_ChangeProfile_DropDown.Value = app.seq(SelectedSeq).cliplabel_profile;
                app.ClippingLabel_ChangeProfileSens_DropDown.Value = app.seq(SelectedSeq).cliplabel_sens;
                app.ClippingLabel_Coordinate_ChangeIs_DropDownValueChanged;
            end                   
        end       
        
        function FromSequence_RemoveallsequencesButton(app)
            app.seq = [];
            app.seqdata = [];
            app.DvideoButton.Enable = 'off';
            app.AddtosequenceEditField.Limits = [1,1];
            app.Light_AddtosequenceEditField.Limits = [1,1];
            app.Transformation_AddtosequenceEditField.Limits = [1,1];
            app.Clipping_AddtosequenceEditField.Limits = [1,1];
            app.ClippingLabel_AddtosequenceEditField.Limits = [1,1];
            app.Opacity_AddtosequenceEditField.Limits = [1,1];
        end
        
        function FromSequence_RemovelastsequenceButton(app,seqdata)
            if ~isempty(app.seq)
                app.seq(end) = [];               
                if isempty(app.seq)
                    app.DvideoButton.Enable = 'off';
                    app.AddtosequenceEditField.Limits = [1,1];
                    app.Light_AddtosequenceEditField.Limits = [1,1];
                    app.Transformation_AddtosequenceEditField.Limits = [1,1];
                    app.Clipping_AddtosequenceEditField.Limits = [1,1];
                    app.ClippingLabel_AddtosequenceEditField.Limits = [1,1];
                    app.Opacity_AddtosequenceEditField.Limits = [1,1];
                else
                    [n,~] = size(seqdata);
                    app.seqdata = seqdata;
                    app.AddtosequenceEditField.Limits = [1,n+1];
                    app.Light_AddtosequenceEditField.Limits = [1,n+1];
                    app.Transformation_AddtosequenceEditField.Limits = [1,n+1];
                    app.Clipping_AddtosequenceEditField.Limits = [1,n+1];
                    app.ClippingLabel_AddtosequenceEditField.Limits = [1,n+1];
                    app.Opacity_AddtosequenceEditField.Limits = [1,n+1];
                end
            end

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GridLayout_3Dmain.Visible = 'off';
            app.GridLayout_2Dslicevisualization.Visible = 'off';
            app.BackgroundGradientCheckBoxValueChanged;
            app.ScalebarCheckBoxValueChanged;
            app.VoxelsizeunitEditFieldValueChanged;
            app.ScaleLengthexpressedinDropDownValueChanged;
            app.Scalebar_location_DropDownValueChanged;
            app.SystemofcoordinatesDropDownValueChanged;

            app.CamerapositionCheckBoxValueChanged;
            app.CameratargetCheckBoxValueChanged;
            app.CamerazoomCheckBoxValueChanged;    
            app.CameravectorCheckBoxValueChanged;

            app.Transformation_Coordinate_ChangeIs_DropDownValueChanged;

            app.DvideoButton.Enable = 'off';
            app.Videofolder.Text = pwd;

            app.LightsourcepositionCheckBoxValueChanged;
            app.LightintensityCheckBoxValueChanged2

        end

        % Button pushed function: ImportButton_3
        function ImportButton_3Pushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select one or several 2D images or 3D volumes from the same folder';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,'MultiSelect','on');
            else
                [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},str_dialogbox,app.importpath,'MultiSelect','on');
            end
            datatype = class(FileName);
            if strcmp(datatype,'double')
                % User clicked cancel button or closed the dialog box                
            else
                if strcmp(datatype,'char')
                    FileName = {FileName};
                end

                n_file = length(FileName);
                if ~isempty(app.Import_UITable.Data)
                    tabledata = app.Import_UITable.Data;
                    [ktable,~] = size(tabledata);
                else
                    ktable = 0;
                    tabledata = cell(n_file,11);
                end
                app.importpath = PathName; 
                app.Videofolder.Text = app.importpath;

                app.IdleLabel.Text = 'Importing... please wait.'; pause(0.1);
                for kfile = 1:1:n_file
                    [M, outcome] = function_load_tif([PathName char(FileName(kfile))], 'double' ); % Load new volume
                    if outcome % Success to import
                        ktable = ktable + 1;
                        [M] = fct_intconvert(M);
                        app.Img(ktable).original = M;
                        app.Img(ktable).modified = [];
                        sz = size(M);
                        dimension = length(sz);
                        if dimension == 2
                            s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i')]};
                        else
                            s = {[num2str(sz(1),'%i') 'x' num2str(sz(2),'%i') 'x' num2str(sz(3),'%i')]};
                        end
                        % Table
                        tabledata(ktable,1) = {char(FileName(kfile))};
                        tabledata(ktable,2) = {[num2str(min(min(min(M)))) ' - ' num2str(max(max(max(M))))]};
                        tabledata(ktable,3) = {length(unique(M))};
                        tabledata(ktable,4) = {min(min(min(M)))};
                        tabledata(ktable,5) = {[]};
                        tabledata(ktable,6) = s;
                        tabledata(ktable,7) = {100};
                        tabledata(ktable,8) = {true};
                        tabledata(ktable,9) = {false};
                        tabledata(ktable,10) = {false};
                        tabledata(ktable,11) = {true};
                        app.Import_UITable.Data = tabledata(1:ktable,:); pause(0.1);
                        st = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                        addStyle(app.Import_UITable,st,'row',ktable);
                    end
                end
                app.IdleLabel.Text = 'Idle';                
            end
            app.Check_files_selection;  
        end

        % Cell edit callback: Import_UITable
        function Import_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            sz = size(app.Import_UITable.Data);
            if indices(2)==sz(2) % Selection
                if newData
                    st = uistyle('BackgroundColor',[0.4660    0.6740    0.1880]);
                else
                    st = uistyle('BackgroundColor',[0.75    0.75    0.75]);
                end
                addStyle(app.Import_UITable,st,'row',indices(1));
                app.Check_files_selection;  
            elseif indices(2)==8 || indices(2)==9 || indices(2)==10 % Data format
                vals = cell2mat(app.Import_UITable.Data(indices(1),8:10));
                if indices(2)==8
                     if newData==0 && vals(2)==0  && vals(3)==0
                         app.Import_UITable.Data(indices(1),indices(2))={true};
                     else
                         app.Import_UITable.Data(indices(1),9)={false};
                         app.Import_UITable.Data(indices(1),10)={false};
                     end
                elseif indices(2)==9
                     if newData==0 && vals(1)==0  && vals(3)==0
                         app.Import_UITable.Data(indices(1),indices(2))={true};
                     else
                         app.Import_UITable.Data(indices(1),8)={false};
                         app.Import_UITable.Data(indices(1),10)={false};
                     end
                elseif indices(2)==10
                     if newData==0 && vals(1)==0  && vals(2)==0
                         app.Import_UITable.Data(indices(1),indices(2))={true};
                     else
                         app.Import_UITable.Data(indices(1),8)={false};
                         app.Import_UITable.Data(indices(1),9)={false};
                     end
                end
                app.Check_files_selection;  
            elseif indices(2)==7 % Voxel size
                if ~isnan(newData) && newData>0
                    foo=1;
                else
                    app.Import_UITable.Data(indices(1),indices(2))={oldData};
                end
                app.SegmentationcomparisonCheckBoxValueChanged;
            elseif indices(2)==4 % Pore label
                if isnan(newData) || newData~=round(newData)
                    app.Import_UITable.Data(indices(1),indices(2))={oldData};
                end
            elseif indices(2)==5 % background label
                if isnan(newData) || newData~=round(newData)
                    app.Import_UITable.Data(indices(1),indices(2))={[]};
                end                
            end

        end

        % Button pushed function: RescaleallselectedfilesButton
        function RescaleallselectedfilesButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (rescale)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            if ~isempty(id_selected)
                for k=1:length(id_selected)
                    current_voxelsize = cell2mat(app.Import_UITable.Data(id_selected(k),7));
                    new_voxelsize = app.RescaletovoxelsizeEditField.Value;
                    p.scaling_factor = current_voxelsize/new_voxelsize;
                    p.background = 0;
                    if cell2mat(app.Import_UITable.Data(id_selected(k),8))
                        p.datatype = 'Grey level';
                    elseif cell2mat(app.Import_UITable.Data(id_selected(k),9))
                        p.datatype = 'Label';
                    elseif cell2mat(app.Import_UITable.Data(id_selected(k),10))
                        p.datatype = 'Label';
                    end
                    if ~isempty(app.Img(id_selected(k)).modified)
                        [app.Img(id_selected(k)).modified,~,~] = fct_isotropicscaling(app.Img(id_selected(k)).modified, p);
                    else
                        [app.Img(id_selected(k)).modified,~,~] = fct_isotropicscaling(app.Img(id_selected(k)).original, p);
                    end
                    % Update GUI
                    app.Import_UITable.Data(id_selected(k),7) = {new_voxelsize};
                    app.UpdateImport_table(id_selected(k));
                end
            end
            app.Check_files_selection;
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: RandomizelabelsButton_3
        function RandomizelabelsButton_3Pushed(app, event)
            app.IdleLabel.Text = 'Please wait (randomize)'; pause(0.1);
            p.basedondistance = false;
            p.dist = [];
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            for k=1:length(id_selected)
                if cell2mat(app.Import_UITable.Data(id_selected(k),10))
                    pore_label = cell2mat(app.Import_UITable.Data(id_selected(k),4));
                    background_label = cell2mat(app.Import_UITable.Data(id_selected(k),5));
                    p.keepunchangedsomelabels = true;
                    if isempty(background_label)
                        p.cst = [num2str(pore_label)];
                    else
                        p.cst = [num2str(pore_label) ' ' num2str(background_label)];
                    end

                    if ~isempty(app.Img(id_selected(k)).modified)
                        [app.Img(id_selected(k)).modified,~,~] = fct_randomize(app.Img(id_selected(k)).modified,p);
                    else
                        [app.Img(id_selected(k)).modified,~,~] = fct_randomize(app.Img(id_selected(k)).original,p);
                    end
                    app.UpdateImport_table(id_selected(k));
                    %figure; imagesc( app.Img(id_selected(k)).original(:,:,50) ); axis equal; axis tight; colormap turbo;
                    %figure; imagesc( app.Img(id_selected(k)).modified(:,:,50) ); axis equal; axis tight; colormap turbo;
                end
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: RemovetruncatedinstancesButton_3
        function RemovetruncatedinstancesButton_3Pushed(app, event)
            app.IdleLabel.Text = 'Please wait (remove truncated instances)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            for k=1:length(id_selected)
                if cell2mat(app.Import_UITable.Data(id_selected(k),10))
                    p.complimentarylabel = cell2mat(app.Import_UITable.Data(id_selected(k),4));
                    background_label = cell2mat(app.Import_UITable.Data(id_selected(k),5));
                    if isempty(background_label)
                        p.background_is_edges = true;
                    else
                        p.background_is_edges = false;
                        p.backgroundlabel = background_label;
                    end
                    if ~isempty(app.Img(id_selected(k)).modified)
                        [app.Img(id_selected(k)).modified,~,~] = fct_removetruncated(app.Img(id_selected(k)).modified,p);
                    else
                        [app.Img(id_selected(k)).modified,~,~] = fct_removetruncated(app.Img(id_selected(k)).original,p);
                    end
                    app.UpdateImport_table(id_selected(k));
                end
            end                      
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: RemovegapsinlabelnumberingButton
        function RemovegapsinlabelnumberingButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (remvove gap in numeration)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            p=[];
            for k=1:length(id_selected)
                if ~isempty(app.Img(id_selected(k)).modified)
                    [app.Img(id_selected(k)).modified,~,~] = fct_remvovegapinnumbering(app.Img(id_selected(k)).modified,p);
                else
                    [app.Img(id_selected(k)).modified,~,~] = fct_remvovegapinnumbering(app.Img(id_selected(k)).original,p);
                end
                app.UpdateImport_table(id_selected(k));
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: ReassignButton_semantic
        function ReassignButton_semanticPushed(app, event)
            app.IdleLabel.Text = 'Please wait (reassign)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            old = app.Semantic_AssignlabelEditField.Value;
            new = app.Semantic_tolabelEditField.Value;
            for k=1:length(id_selected)
                if ~isempty(app.Img(id_selected(k)).modified)
                    app.Img(id_selected(k)).modified( app.Img(id_selected(k)).modified==old ) = new;
                else
                    app.Img(id_selected(k)).modified = app.Img(id_selected(k)).original;
                    app.Img(id_selected(k)).modified( app.Img(id_selected(k)).modified==old ) = new;
                end
                app.UpdateImport_table(id_selected(k));
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: ContrastsaturationButton
        function ContrastsaturationButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (contrast saturation)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            p.volumethreshold_below = app.SaturationdarkestEditField.Value;
            p.volumethreshold_above = app.SaturationbrightestEditField.Value;
            for k=1:length(id_selected)
                if cell2mat(app.Import_UITable.Data(id_selected(k),8))
                    if ~isempty(app.Img(id_selected(k)).modified)
                        [app.Img(id_selected(k)).modified,~,~] = fct_contrastsaturation(app.Img(id_selected(k)).modified,p);
                    else
                        [app.Img(id_selected(k)).modified,~,~] = fct_contrastsaturation(app.Img(id_selected(k)).original,p);
                    end
                    app.UpdateImport_table(id_selected(k));
                end
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: RescaleButton
        function RescaleButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (linear rescaling)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            p.nvalues = app.LinearrescalingwithvaluesEditField.Value;
            for k=1:length(id_selected)
                if cell2mat(app.Import_UITable.Data(id_selected(k),8))
                    if ~isempty(app.Img(id_selected(k)).modified)
                        [app.Img(id_selected(k)).modified,~,~] = fct_linearrescaling(app.Img(id_selected(k)).modified,p);
                    else
                        [app.Img(id_selected(k)).modified,~,~] = fct_linearrescaling(app.Img(id_selected(k)).original,p);
                    end
                    app.UpdateImport_table(id_selected(k));
                end
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: ResetallchangesforthisfileButton
        function ResetallchangesforthisfileButtonPushed(app, event)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            app.Img(id_selected(1)).modified = [];
            app.UpdateImport_table(id_selected(1)); 
        end

        % Button pushed function: ResetallchangesforallselectedfilesButton
        function ResetallchangesforallselectedfilesButtonPushed(app, event)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            for k=1:length(id_selected)
                app.Img(id_selected(k)).modified = [];
                app.UpdateImport_table(id_selected(k)); 
            end
        end

        % Button pushed function: PlothistogramButton
        function PlothistogramButtonPushed(app, event)
            p.xlabel = 'Grey level';
            p.excludebackground = false;
            p.option = 'Bins number: custom';
            p.Nbin = 50;
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            for k=1:length(id_selected)
                if cell2mat(app.Import_UITable.Data(id_selected(k),8))
                    p.filename = char(app.Import_UITable.Data(id_selected(k),1));
                    if ~isempty(app.Img(id_selected(k)).modified)
                        p.filename = [p.filename ', modified'];
                        fct_plothistogram_wholevolume(app.Img(id_selected(k)).modified,p);
                    else
                        fct_plothistogram_wholevolume(app.Img(id_selected(k)).original,p);
                    end
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Import_UITable.Data = [];
            app.Img = [];  
            app.RescaleallselectedfilesButton.Enable = 'off';
            app.ModifyinstanceimagesPanel.Enable = "off";
            app.ModifygreylevelimagesPanel.Enable = "off";
            app.ModifyimagesPanel.Enable = "off";
            app.ModifyROIPanel.Enable = "off";
            app.ResetallchangesforthisfileButton.Enable = "off";
            app.ResetallchangesforallselectedfilesButton.Enable = "off";
            app.GridLayout_3Dmain.Visible = 'off';
            app.GridLayout_2Dslicevisualization.Visible = 'off';
        end

        % Value changed function: Cropaxe1fromEditField
        function Cropaxe1fromEditFieldValueChanged(app, event)
            from = app.Cropaxe1fromEditField.Value;
            to = app.toEditField.Value;
            if from > to
                app.Cropaxe1fromEditField.Value = event.PreviousValue;
            end            
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            from = app.Cropaxe1fromEditField.Value;
            to = app.toEditField.Value;
            if from > to
                app.toEditField.Value = event.PreviousValue;
            end                   
        end

        % Value changed function: Cropaxe2fromEditField
        function Cropaxe2fromEditFieldValueChanged(app, event)
            from = app.Cropaxe2fromEditField.Value;
            to = app.toEditField_2.Value;
            if from > to
                app.Cropaxe2fromEditField.Value = event.PreviousValue;
            end                   
        end

        % Value changed function: toEditField_2
        function toEditField_2ValueChanged(app, event)
            from = app.Cropaxe2fromEditField.Value;
            to = app.toEditField_2.Value;
            if from > to
                app.toEditField_2.Value = event.PreviousValue;
            end                  
        end

        % Value changed function: Cropaxe3fromEditField
        function Cropaxe3fromEditFieldValueChanged(app, event)
            from = app.Cropaxe3fromEditField.Value;
            to = app.toEditField_3.Value;
            if from > to
                app.Cropaxe3fromEditField.Value = event.PreviousValue;
            end                   
        end

        % Value changed function: toEditField_3
        function toEditField_3ValueChanged(app, event)
            from = app.Cropaxe3fromEditField.Value;
            to = app.toEditField_3.Value;
            if from > to
                app.toEditField_3.Value = event.PreviousValue;
            end                  
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (crop)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            x0 = app.Cropaxe1fromEditField.Value; x1 = app.toEditField.Value;
            y0 = app.Cropaxe2fromEditField.Value; y1 = app.toEditField_2.Value;
            z0 = app.Cropaxe3fromEditField.Value; z1 = app.toEditField_3.Value;            
            if ~isempty(app.Img(id_selected(1)).modified)
                app.Img(id_selected(1)).modified = app.Img(id_selected(1)).modified(x0:x1,y0:y1,z0:z1);
            else
                app.Img(id_selected(1)).modified = app.Img(id_selected(1)).original(x0:x1,y0:y1,z0:z1);
            end
            app.UpdateImport_table(id_selected(1)); 
            app.IdleLabel.Text = 'Done!';
        end

        % Cell edit callback: ThreeD_position_UITable
        function ThreeD_position_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData) || isempty(newData) || newData<=0 || newData~=round(newData)
                app.ThreeD_position_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
            end            
            pos = cell2mat(app.ThreeD_position_UITable.Data(:,2:end));
            unis = unique(pos,'rows');            
            % if numel(pos)~=numel(unis) || ~sum(sum(unis,2)==3)
            %     app.Initial3DviewButton_6.Enable = 'off';
            % else
            %     app.Initial3DviewButton_6.Enable = 'on';
            % end
        end

        % Button pushed function: Initial_3Dview_Button
        function ThreeD_InitialviewButton2Pushed(app, event)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);

            % Set colormap and alphamap
            app.Set_colormap;

            % Set positions
            app.Set_positions;

            % Set viewer options
            p= app.Set_viewer(id_selected);
            p.opacitfy_cummulative = app.CummulativeopacityCheckBox.Value;

            % Initial visible
            vis = cell2mat(app.Startvisible_UITable.Data(:,end));

            % Call function
            View3D_v4(app.Img,app.box_sz,vis,p)
        end

        % Button pushed function: 
        % RemovevolumeswithonlyporeorbackgroundautocropButton
        function RemovevolumeswithonlyporeorbackgroundautocropButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (auto-crop)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            for k=1:length(id_selected)
                if ~isempty(app.Img(id_selected(k)).modified)
                    M = app.Img(id_selected(k)).modified;
                else
                    M = app.Img(id_selected(k)).original;
                end
                sz = size(M);
                pore_label = cell2mat(app.Import_UITable.Data(id_selected(k),4));
                background_label = cell2mat(app.Import_UITable.Data(id_selected(k),5));                
                cond_pore = M~=pore_label;
                if ~isempty(background_label)
                    cond_back = M~=background_label;
                    cond_pore = cond_pore.*cond_back;
                end
                idx = find(cond_pore);
                [IX,IY,IZ] = ind2sub(sz,idx);
                x0 = min(IX); x1 = max(IX);
                y0 = min(IY); y1 = max(IY);
                z0 = min(IZ); z1 = max(IZ);
                if ~isempty(app.Img(id_selected(k)).modified)
                    app.Img(id_selected(k)).modified = app.Img(id_selected(k)).modified(x0:x1,y0:y1,z0:z1);
                else
                    app.Img(id_selected(k)).modified = app.Img(id_selected(k)).original(x0:x1,y0:y1,z0:z1);
                end
                app.UpdateImport_table(id_selected(k));
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: FliporswapaxisButton
        function FliporswapaxisButtonPushed(app, event)
            app.IdleLabel.Text = 'Please wait (flip or swap axis)'; pause(0.1);
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            p.action = app.FlipSwap_Axis_DropDown.Value;
            for k=1:length(id_selected)
                if ~isempty(app.Img(id_selected(k)).modified)
                    [app.Img(id_selected(k)).modified,~,~] = fct_flipswap(app.Img(id_selected(k)).modified, p);
                else
                    [app.Img(id_selected(k)).modified,~,~] = fct_flipswap(app.Img(id_selected(k)).original, p);
                end
                app.UpdateImport_table(id_selected(k)); 
            end
            app.IdleLabel.Text = 'Done!';
        end

        % Button pushed function: UpdateSemanticColormapButton
        function UpdateSemanticColormapButtonPushed(app, event)
            selected = cell2mat(app.Import_UITable.Data(:,end));

            datatype = cell2mat(app.Import_UITable.Data(:,8:10));
            id_sem = find(selected.*datatype(:,2));
      
            if ~isempty(id_sem)
                unis = [];
                for k=1:length(id_sem)
                    if ~isempty(app.Img(id_sem(k)).modified)
                        M = app.Img(id_sem(k)).modified;
                    else
                        M = app.Img(id_sem(k)).original;
                    end
                    unis =[unis; unique(M)];
                end
                unis = unique(unis);
                nsem = length(unis);

                vol = cell(nsem,1);
                if length(id_sem)==1 && nsem<100
                    for k=1:nsem
                        vol(k) = {sum(sum(sum( M==unis(k) )))/numel(M)};
                    end 
                else                   
                    for k=1:nsem
                        vol(k) = {'n/a'};
                    end 
                end

                col_ = [[1 1 1]; colororder; rand(1e6,3)];
                for k=1:nsem
                    app.colorsemantic(k,:) = col_(k,:);
                    s = uistyle('BackgroundColor',app.colorsemantic(k,:));
                    addStyle(app.SemanticColor_UITable,s,'cell',[k,3]);
                end

                opacity = ones(nsem,1);
                opacity(1) = 0;

                SemColmap = cell(nsem,4);
                SemColmap(:,1) = num2cell(unis);
                SemColmap(:,2) = vol;
                SemColmap(:,4) = num2cell(opacity);
                app.SemanticColor_UITable.Data = SemColmap;   
            end 
        end

        % Cell selection callback: SemanticColor_UITable
        function SemanticColor_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==3 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.colorsemantic(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.colorsemantic(indices(1),:));
                        addStyle(app.SemanticColor_UITable,s,'cell',[indices(1),3]);                      
                    end
                end
            end
        end

        % Value changed function: BackgroundGradientCheckBox
        function BackgroundGradientCheckBoxValueChanged(app, event)
            value = app.BackgroundGradientCheckBox.Value;
            if value
                app.GradientcolorColorPicker.Enable = 'on';
            else
                app.GradientcolorColorPicker.Enable = 'off';
            end
        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            value = app.ScalebarCheckBox.Value;
            if value
                app.Scalebar_location_DropDown.Enable = 'on';
                app.Scalebar_lengthEditField.Enable = 'on';
                app.ScaleBar_X_LocatedDropDown.Enable = 'on';
                app.ScaleBar_Y_LocatedDropDown.Enable = 'on';
                app.ScaleBar_Z_LocatedDropDown.Enable = 'on';
                app.ScaleLengthexpressedinDropDown.Enable = 'on';
            else
                app.Scalebar_location_DropDown.Enable = 'off';
                app.Scalebar_lengthEditField.Enable = 'off';
                app.ScaleBar_X_LocatedDropDown.Enable = 'off';
                app.ScaleBar_Y_LocatedDropDown.Enable = 'off';
                app.ScaleBar_Z_LocatedDropDown.Enable = 'off';                
                app.ScaleLengthexpressedinDropDown.Enable = 'off';
            end
        end

        % Value changed function: VoxelsizeunitEditField
        function VoxelsizeunitEditFieldValueChanged(app, event)
            value = app.VoxelsizeunitEditField.Value;
            app.ScaleLengthexpressedinDropDown.Items(2)={value};
        end

        % Value changed function: ScaleLengthexpressedinDropDown
        function ScaleLengthexpressedinDropDownValueChanged(app, event)
            value = app.ScaleLengthexpressedinDropDown.Value;
            if strcmp(value,'in ratio of the aligned axe length')
                app.Scalebar_lengthEditField.Value = 0.1;
                app.Scalebar_lengthEditField.Limits = [0,1];
            else
                app.Scalebar_lengthEditField.Value = 1.0;
                app.Scalebar_lengthEditField.Limits = [0,1e9];
            end            
        end

        % Value changed function: Scalebar_location_DropDown
        function Scalebar_location_DropDownValueChanged(app, event)
            value = app.Scalebar_location_DropDown.Value;
            if strcmp(value,'Along axe x')
                app.DistancefromplanexyEditField.Enable = "on"; app.DistancefromplanexyEditField.Value = 0.1;
                app.DistancefromplanexzEditField.Enable = "on"; app.DistancefromplanexzEditField.Value = 0.1;  
                app.DistancefromplaneyzEditField.Enable = "off"; app.DistancefromplaneyzEditField.Value = 0.0;       
            elseif strcmp(value,'Along axe y')
                app.DistancefromplanexyEditField.Enable = "on"; app.DistancefromplanexyEditField.Value = 0.1;
                app.DistancefromplanexzEditField.Enable = "off"; app.DistancefromplanexzEditField.Value = 0.0;  
                app.DistancefromplaneyzEditField.Enable = "on"; app.DistancefromplaneyzEditField.Value = 0.1;  
            elseif strcmp(value,'Along axe z')
                app.DistancefromplanexyEditField.Enable = "off"; app.DistancefromplanexyEditField.Value = 0.0;
                app.DistancefromplanexzEditField.Enable = "on"; app.DistancefromplanexzEditField.Value = 0.1;  
                app.DistancefromplaneyzEditField.Enable = "on"; app.DistancefromplaneyzEditField.Value = 0.1;     
            end
            
        end

        % Value changed function: SystemofcoordinatesDropDown
        function SystemofcoordinatesDropDownValueChanged(app, event)
            value = app.SystemofcoordinatesDropDown.Value;
            if strcmp(value,'spherical')
                app.AzimuthEditField.Enable = 'on';
                app.ElevationEditField.Enable = 'on';
                app.DistancefromcameratargetEditField.Enable = 'on';
                app.Coordinatealongaxex_Pos_EditField.Enable = 'off';
                app.Coordinatealongaxey_Pos_EditField.Enable = 'off';
                app.Coordinatealongaxez_Pos_EditField.Enable = 'off';   
            elseif strcmp(value,'cartesian ')
                app.AzimuthEditField.Enable = 'off';
                app.ElevationEditField.Enable = 'off';
                app.DistancefromcameratargetEditField.Enable = 'off';
                app.Coordinatealongaxex_Pos_EditField.Enable = 'on';
                app.Coordinatealongaxey_Pos_EditField.Enable = 'on';
                app.Coordinatealongaxez_Pos_EditField.Enable = 'on';  
            end
        end

        % Value changed function: NewCamPos_Coordinate_ChangeIs_DropDown
        function NewCamPos_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.NewCamPos_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.NewCamPos_ChangeProfile_DropDown.Enable = "on";
                app.NewCamPos_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.NewCamPos_ChangeProfile_DropDown.Enable = "off";
                app.NewCamPos_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: CameraNewPosition_CoordinatesDropDown
        function SystemofcoordinatesDropDown_2ValueChanged(app, event)
            value = app.CameraNewPosition_CoordinatesDropDown.Value;
            if strcmp(value,'spherical')
                app.CamPos_x_choice_DropDown.Enable = 'off';
                app.CamPos_y_choice_DropDown.Enable = 'off';
                app.CamPos_z_choice_DropDown.Enable = 'off';
                app.CamPos_new_x_EditField.Enable = 'off';
                app.CamPos_new_y_EditField.Enable = 'off';
                app.CamPos_new_z_EditField.Enable = 'off';                

                app.CamPos_azimuth_choice_DropDown.Enable = 'on';
                app.CamPos_elevation_choice_DropDown.Enable = 'on';
                app.CamPos_distance_choice_DropDown.Enable = 'on';
                app.CamPos_azimuth_choice_DropDownValueChanged;
                app.CamPos_elevation_choice_DropDownValueChanged;
                app.CamPos_distance_choice_DropDownValueChanged;
            elseif strcmp(value,'cartesian')
                app.CamPos_x_choice_DropDown.Enable = 'on';
                app.CamPos_y_choice_DropDown.Enable = 'on';
                app.CamPos_z_choice_DropDown.Enable = 'on';
                app.CamPos_x_choice_DropDownValueChanged;
                app.CamPos_y_choice_DropDownValueChanged;
                app.CamPos_z_choice_DropDownValueChanged;                

                app.CamPos_azimuth_choice_DropDown.Enable = 'off';
                app.CamPos_elevation_choice_DropDown.Enable = 'off';
                app.CamPos_distance_choice_DropDown.Enable = 'off';      
                app.CamPos_new_azimuth_EditField.Enable = 'off';
                app.CamPos_new_elevation_EditField.Enable = 'off';
                app.CamPos_new_distance_EditField.Enable = 'off';    
            elseif strcmp(value,'helical')                
                app.CamPos_x_choice_DropDown.Enable = 'off';
                app.CamPos_y_choice_DropDown.Enable = 'off';
                app.CamPos_z_choice_DropDown.Enable = 'on';     
                app.CamPos_new_x_EditField.Enable = 'off';
                app.CamPos_new_y_EditField.Enable = 'off';    
                app.CamPos_z_choice_DropDownValueChanged;  

                app.CamPos_azimuth_choice_DropDown.Enable = 'on';
                app.CamPos_elevation_choice_DropDown.Enable = 'off';
                app.CamPos_distance_choice_DropDown.Enable = 'on';
                app.CamPos_azimuth_choice_DropDownValueChanged;
                app.CamPos_new_elevation_EditField.Enable = 'off';
                app.CamPos_distance_choice_DropDownValueChanged;                
            end     
        end

        % Button pushed function: Sequence_Camera_AddButton
        function Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.AddtosequenceEditField.Value;

            % Check if a camera movment has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),2)) % 2 is camera
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                % Camera zoom
                app.seq(seqnum).camera_zoom_todo = app.CamerazoomCheckBox.Value;
                app.seq(seqnum).camera_zoom_value = app.NewZoomEditField.Value;
                app.seq(seqnum).camera_zoom_change_is = app.Zoom_ChangeDropDown.Value;
                app.seq(seqnum).camera_zoom_profile = app.Zoom_ProfileDropDown.Value;
                app.seq(seqnum).camera_zoom_sens = app.Zoom_SensDropDown.Value;

                % Camera vector
                app.seq(seqnum).camera_vector_todo = app.CameravectorCheckBox.Value;
                app.seq(seqnum).camera_vector_value = [app.CameraVector_1_EditField.Value app.CameraVector_2_EditField.Value app.CameraVector_3_EditField.Value];
                app.seq(seqnum).camera_vector_change_is = app.Vector_ChangeDropDown.Value;
                app.seq(seqnum).camera_vector_profile = app.Vector_ProfileDropDown.Value;
                app.seq(seqnum).camera_vector_sens = app.Vector_SensDropDown.Value;

                % Camera position
                app.seq(seqnum).camera_position_todo = app.CamerapositionCheckBox.Value;
                app.seq(seqnum).camera_coordinate= app.CameraNewPosition_CoordinatesDropDown.Value;
                app.seq(seqnum).camera_x_choice = app.CamPos_x_choice_DropDown.Value;
                app.seq(seqnum).camera_y_choice = app.CamPos_y_choice_DropDown.Value;
                app.seq(seqnum).camera_z_choice = app.CamPos_z_choice_DropDown.Value;
                app.seq(seqnum).camera_x = app.CamPos_new_x_EditField.Value;
                app.seq(seqnum).camera_y = app.CamPos_new_y_EditField.Value;
                app.seq(seqnum).camera_z = app.CamPos_new_z_EditField.Value;
                app.seq(seqnum).camera_azimuth_choice = app.CamPos_azimuth_choice_DropDown.Value;
                app.seq(seqnum).camera_elevation_choice = app.CamPos_elevation_choice_DropDown.Value;
                app.seq(seqnum).camera_dist_choice = app.CamPos_distance_choice_DropDown.Value;
                app.seq(seqnum).camera_azimuth = app.CamPos_new_azimuth_EditField.Value;
                app.seq(seqnum).camera_elevation = app.CamPos_new_elevation_EditField.Value;
                app.seq(seqnum).camera_dist = app.CamPos_new_distance_EditField.Value;
                app.seq(seqnum).camera_coordinate_change_is= app.NewCamPos_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).camera_coordinate_profile= app.NewCamPos_ChangeProfile_DropDown.Value;
                app.seq(seqnum).camera_coordinate_sens= app.NewCamPos_ChangeProfileSens_DropDown.Value;
                app.camsav(seqnum).CamPos_new_azimuth_EditField_limits = app.CamPos_new_azimuth_EditField.Limits;
                app.camsav(seqnum).CamPos_new_elevation_EditField_limits = app.CamPos_new_elevation_EditField.Limits;
                app.camsav(seqnum).CamPos_new_distance_EditField_limits = app.CamPos_new_distance_EditField.Limits;

                % Camera target
                app.seq(seqnum).camera_target_todo = app.CameratargetCheckBox.Value;
                app.seq(seqnum).camera_target_x_is = app.NewCamTar_x_isDropDown.Value;
                app.seq(seqnum).camera_target_y_is = app.NewCamTar_y_isDropDown.Value;
                app.seq(seqnum).camera_target_z_is = app.NewCamTar_z_isDropDown.Value;
                app.seq(seqnum).camera_target_x = app.NewCamTar_x_EditField.Value;
                app.seq(seqnum).camera_target_y = app.NewCamTar_y_EditField.Value;
                app.seq(seqnum).camera_target_z = app.NewCamTar_z_EditField.Value;
                app.seq(seqnum).camera_target_change_is= app.NewCamTar_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).camera_target_profile= app.NewCamTar_ChangeProfile_DropDown.Value;
                app.seq(seqnum).camera_target_sens= app.NewCamTar_ChangeProfileSens_DropDown.Value;

                app.Addseqdata(seqnum, [{seqnum}, {true}, {false}, {false}, {false}, {false}, {false}, {5}]);
            else
                f = msgbox("This sequence has already a camera movement","Warning","warn");
            end
        end

        % Button pushed function: DvideoButton
        function DvideoButtonPushed(app, event)
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);

            % Set colormap and alphamap
            app.Set_colormap;

            % Set positions
            app.Set_positions;
           
            % % Fix colormap
            % id_transparent = find(alphamap==0);
            % id_transparent(id_transparent==1)=[];
            % if ~isempty(id_transparent)
            %     id_transparent = sort(id_transparent,'ascend');
            %     alphamap(id_transparent)=[];
            %     colmap(id_transparent,:)=[];           
            % end             

            % Set viewer options
            p= app.Set_viewer(id_selected);
            p.opacitfy_cummulative = app.CummulativeopacityCheckBox.Value;

            % Initial visible
            vis = cell2mat(app.Startvisible_UITable.Data(:,end));

            % Save option
            p.savefolder = app.Videofolder.Text;
            p.filename = app.Videofilename.Value;

            % Call function
            seq_tmp = cell2mat(app.SequenceApp.sequence_UITable.Data(:,1));
            times_tmp = cell2mat(app.SequenceApp.sequence_UITable.Data(:,end));
            unis = unique(seq_tmp);
            n = length(unis);
            times = zeros(n,1);
            for k = 1:n
                idx = find(seq_tmp==unis(k));
                times(k) = times_tmp(idx(1));
            end

            p.fps = app.FramepersEditField.Value;

            keyboard

            Video3D_v4(app.Img,app.box_sz,app.seq,times,vis,p)
        end

        % Button pushed function: SavefolderButton_6
        function SavefolderButton_6Pushed(app, event)
            str_dialogbox = 'Select the folder where the video file will be saved'; % Set string of the dialog box
            videosavefolder = uigetdir(app.Videofolder.Text,str_dialogbox); % Open dialog box to choose file path
            if videosavefolder~=0
                app.Videofolder.Text = videosavefolder;
            end
        end

        % Value changed function: NewCamPos_ChangeProfile_DropDown
        function NewCamPos_ChangeProfile_DropDownValueChanged(app, event)
            value = app.NewCamPos_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.NewCamPos_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.NewCamPos_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Value changed function: Zoom_ChangeDropDown
        function Zoom_ChangeDropDownValueChanged(app, event)
            value = app.Zoom_ChangeDropDown.Value;
            if strcmp(value,'Progressive')
                app.Zoom_ProfileDropDown.Enable = "on";
                app.Zoom_ProfileDropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.Zoom_ProfileDropDown.Enable = "off";
                app.Zoom_SensDropDown.Enable = "off";
            end
        end

        % Value changed function: Zoom_ProfileDropDown
        function Zoom_ProfileDropDownValueChanged(app, event)
            value = app.Zoom_ProfileDropDown.Value;
            if strcmp(value,'Linear')
                app.Zoom_SensDropDown.Enable = 'off';
            else
                app.Zoom_SensDropDown.Enable = 'on';
            end
        end

        % Value changed function: CamerazoomCheckBox
        function CamerazoomCheckBoxValueChanged(app, event)
            value = app.CamerazoomCheckBox.Value;
            if value
                app.NewZoomEditField.Enable = "on";
                app.Zoom_ChangeDropDown.Enable = "on";
                app.Zoom_ChangeDropDownValueChanged;
            else
                app.NewZoomEditField.Enable = "off";
                app.Zoom_ChangeDropDown.Enable = "off";
                app.Zoom_ProfileDropDown.Enable = "off";
                app.Zoom_SensDropDown.Enable = "off";
            end        
            value2 = app.CameratargetCheckBox.Value;
            value3 = app.CamerapositionCheckBox.Value;
            value4 = app.CameravectorCheckBox.Value;
            if value || value2 || value3 || value4
                app.AddtosequenceEditField.Enable = 'on';
                app.Sequence_Camera_AddButton.Enable = 'on';
            else
                app.AddtosequenceEditField.Enable = 'off';
                app.Sequence_Camera_AddButton.Enable = 'off';
            end            
        end

        % Value changed function: CamerapositionCheckBox
        function CamerapositionCheckBoxValueChanged(app, event)
            value = app.CamerapositionCheckBox.Value;
            if value
                app.CameraNewPosition_CoordinatesDropDown.Enable = 'on';
                app.SystemofcoordinatesDropDown_2ValueChanged;
                app.NewCamPos_Coordinate_ChangeIs_DropDown.Enable = 'on';
                app.NewCamPos_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.CameraNewPosition_CoordinatesDropDown.Enable = 'off';
                app.CamPos_new_x_EditField.Enable = 'off';
                app.CamPos_new_y_EditField.Enable = 'off';
                app.CamPos_new_z_EditField.Enable = 'off';
                app.CamPos_x_choice_DropDown.Enable = 'off';
                app.CamPos_y_choice_DropDown.Enable = 'off';
                app.CamPos_z_choice_DropDown.Enable = 'off';                
                app.CamPos_new_azimuth_EditField.Enable = 'off';
                app.CamPos_new_elevation_EditField.Enable = 'off';
                app.CamPos_new_distance_EditField.Enable = 'off';
                app.CamPos_azimuth_choice_DropDown.Enable = 'off';
                app.CamPos_elevation_choice_DropDown.Enable = 'off';
                app.CamPos_distance_choice_DropDown.Enable = 'off';                   
                app.NewCamPos_Coordinate_ChangeIs_DropDown.Enable = 'off';
                app.NewCamPos_ChangeProfile_DropDown.Enable = 'off';
                app.NewCamPos_ChangeProfileSens_DropDown.Enable = 'off';
            end
            value2 = app.CameratargetCheckBox.Value;
            value3 = app.CamerazoomCheckBox.Value;
            value4 = app.CamerazoomCheckBox.Value;
            if value || value2 || value3 || value4
                app.AddtosequenceEditField.Enable = 'on';
                app.Sequence_Camera_AddButton.Enable = 'on';
            else
                app.AddtosequenceEditField.Enable = 'off';
                app.Sequence_Camera_AddButton.Enable = 'off';
            end
        end

        % Value changed function: CameratargetCheckBox
        function CameratargetCheckBoxValueChanged(app, event)
            value = app.CameratargetCheckBox.Value;
            if value
                app.NewCamTar_x_isDropDown.Enable = 'on';
                app.NewCamTar_y_isDropDown.Enable = 'on';
                app.NewCamTar_z_isDropDown.Enable = 'on';                
                app.NewCamTar_x_isDropDownValueChanged;
                app.NewCamTar_y_isDropDownValueChanged;
                app.NewCamTar_z_isDropDownValueChanged;
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = "on";
                app.NewCamTar_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.NewCamTar_x_isDropDown.Enable = 'off';
                app.NewCamTar_y_isDropDown.Enable = 'off';
                app.NewCamTar_z_isDropDown.Enable = 'off';
                app.NewCamTar_x_EditField.Enable = 'off';
                app.NewCamTar_y_EditField.Enable = 'off';
                app.NewCamTar_z_EditField.Enable = 'off';                
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = "off";
                app.NewCamTar_ChangeProfile_DropDown.Enable = "off";
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = "off";
            end
            value2 = app.CamerapositionCheckBox.Value;
            value3 = app.CamerazoomCheckBox.Value;
            value4 = app.CamerazoomCheckBox.Value;
            if value || value2 || value3 || value4
                app.AddtosequenceEditField.Enable = 'on';
                app.Sequence_Camera_AddButton.Enable = 'on';
            else
                app.AddtosequenceEditField.Enable = 'off';
                app.Sequence_Camera_AddButton.Enable = 'off';
            end        
        end

        % Value changed function: NewCamTar_Coordinate_ChangeIs_DropDown
        function NewCamTar_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.NewCamTar_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.NewCamTar_ChangeProfile_DropDown.Enable = "on";
                app.NewCamTar_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.NewCamTar_ChangeProfile_DropDown.Enable = "off";
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: NewCamTar_x_isDropDown
        function NewCamTar_x_isDropDownValueChanged(app, event)
            value = app.NewCamTar_x_isDropDown.Value;
            if strcmp(value,'free')
                app.NewCamTar_x_EditField.Enable = 'on';
            else
                app.NewCamTar_x_EditField.Enable = 'off';
            end
            value3 = app.NewCamTar_y_isDropDown.Value;
            value2 = app.NewCamTar_z_isDropDown.Value;
            if strcmp(value,'free') || strcmp(value2,'free') || strcmp(value3,'free')
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'on';
                app.NewCamTar_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfile_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = 'off';                
            end   
        end

        % Value changed function: NewCamTar_y_isDropDown
        function NewCamTar_y_isDropDownValueChanged(app, event)
            value = app.NewCamTar_y_isDropDown.Value;
            if strcmp(value,'free')
                app.NewCamTar_y_EditField.Enable = 'on';
            else
                app.NewCamTar_y_EditField.Enable = 'off';
            end            
            value3 = app.NewCamTar_x_isDropDown.Value;
            value2 = app.NewCamTar_z_isDropDown.Value;
            if strcmp(value,'free') || strcmp(value2,'free') || strcmp(value3,'free')
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'on';
                app.NewCamTar_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfile_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = 'off';                
            end            
        end

        % Value changed function: NewCamTar_z_isDropDown
        function NewCamTar_z_isDropDownValueChanged(app, event)
            value = app.NewCamTar_z_isDropDown.Value;
            if strcmp(value,'free')
                app.NewCamTar_z_EditField.Enable = 'on';
            else
                app.NewCamTar_z_EditField.Enable = 'off';
            end           
            value3 = app.NewCamTar_y_isDropDown.Value;
            value2 = app.NewCamTar_x_isDropDown.Value;
            if strcmp(value,'free') || strcmp(value2,'free') || strcmp(value3,'free')
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'on';
                app.NewCamTar_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.NewCamTar_Coordinate_ChangeIs_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfile_DropDown.Enable = 'off';
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = 'off';                
            end
        end

        % Value changed function: NewCamTar_ChangeProfile_DropDown
        function NewCamTar_ChangeProfile_DropDownValueChanged(app, event)
            value = app.NewCamTar_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.NewCamTar_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Value changed function: CamPos_x_choice_DropDown
        function CamPos_x_choice_DropDownValueChanged(app, event)
            value = app.CamPos_x_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_x_EditField.Enable = 'off';
            else
                app.CamPos_new_x_EditField.Enable = 'on';
            end            
        end

        % Value changed function: CamPos_y_choice_DropDown
        function CamPos_y_choice_DropDownValueChanged(app, event)
            value = app.CamPos_y_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_y_EditField.Enable = 'off';
            else
                app.CamPos_new_y_EditField.Enable = 'on';
            end                 
        end

        % Value changed function: CamPos_z_choice_DropDown
        function CamPos_z_choice_DropDownValueChanged(app, event)
            value = app.CamPos_z_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_z_EditField.Enable = 'off';
            else
                app.CamPos_new_z_EditField.Enable = 'on';
            end                 
        end

        % Value changed function: CamPos_azimuth_choice_DropDown
        function CamPos_azimuth_choice_DropDownValueChanged(app, event)
            value = app.CamPos_azimuth_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_azimuth_EditField.Enable = 'off';
            else
                app.CamPos_new_azimuth_EditField.Enable = 'on';
                if strcmp(value,'absolute')
                    app.CamPos_new_azimuth_EditField.Limits = [-180, 180];
                elseif strcmp(value,'delta')
                    app.CamPos_new_azimuth_EditField.Limits = [-Inf, Inf];
                end
            end                
        end

        % Value changed function: CamPos_elevation_choice_DropDown
        function CamPos_elevation_choice_DropDownValueChanged(app, event)
            value = app.CamPos_elevation_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_elevation_EditField.Enable = 'off';
            else
                app.CamPos_new_elevation_EditField.Enable = 'on';
                if strcmp(value,'absolute')
                    app.CamPos_new_elevation_EditField.Limits = [-90, 90];
                elseif strcmp(value,'delta')
                    app.CamPos_new_elevation_EditField.Limits = [-Inf, Inf];
                end
            end  
        end

        % Value changed function: CamPos_distance_choice_DropDown
        function CamPos_distance_choice_DropDownValueChanged(app, event)
            value = app.CamPos_distance_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.CamPos_new_distance_EditField.Enable = 'off';
            else
                app.CamPos_new_distance_EditField.Enable = 'on';
                if strcmp(value,'absolute')
                    app.CamPos_new_distance_EditField.Limits = [1e-9, Inf];
                elseif strcmp(value,'delta')
                    app.CamPos_new_distance_EditField.Limits = [-Inf, Inf];
                end
            end  
        end

        % Value changed function: CameravectorCheckBox
        function CameravectorCheckBoxValueChanged(app, event)
            value = app.CameravectorCheckBox.Value;
            if value
                app.CameraVector_1_EditField.Enable = 'on';
                app.CameraVector_2_EditField.Enable = 'on';
                app.CameraVector_3_EditField.Enable = 'on';
                app.Vector_ChangeDropDown.Enable = 'on';
                app.Vector_ChangeDropDownValueChanged;
            else
                app.CameraVector_1_EditField.Enable = 'off';
                app.CameraVector_2_EditField.Enable = 'off';
                app.CameraVector_3_EditField.Enable = 'off';
                app.Vector_ChangeDropDown.Enable = 'off';
                app.Vector_ProfileDropDown.Enable = 'off';
                app.Vector_SensDropDown.Enable = 'off';
            end
            value2 = app.CameratargetCheckBox.Value;
            value3 = app.CamerapositionCheckBox.Value;
            value4 = app.CamerazoomCheckBox.Value;
            if value || value2 || value3 || value4
                app.AddtosequenceEditField.Enable = 'on';
                app.Sequence_Camera_AddButton.Enable = 'on';
            else
                app.AddtosequenceEditField.Enable = 'off';
                app.Sequence_Camera_AddButton.Enable = 'off';
            end               
        end

        % Value changed function: Vector_ChangeDropDown
        function Vector_ChangeDropDownValueChanged(app, event)
            value = app.Vector_ChangeDropDown.Value;
            if strcmp(value,'Progressive')
                app.Vector_ProfileDropDown.Enable = "on";
                app.Vector_ProfileDropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.Vector_ProfileDropDown.Enable = "off";
                app.Vector_SensDropDown.Enable = "off";
            end
        end

        % Value changed function: Vector_ProfileDropDown
        function Vector_ProfileDropDownValueChanged(app, event)
            value = app.Vector_ProfileDropDown.Value;
            if strcmp(value,'Linear')
                app.Vector_SensDropDown.Enable = 'off';
            else
                app.Vector_SensDropDown.Enable = 'on';
            end            
        end

        % Value changed function: 
        % Transformation_Coordinate_ChangeIs_DropDown
        function Transformation_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.Transformation_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.Transformation_ChangeProfile_DropDown.Enable = "on";
                app.Transformation_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.Transformation_ChangeProfile_DropDown.Enable = "off";
                app.Transformation_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: Transformation_ChangeProfile_DropDown
        function Transformation_ChangeProfile_DropDownValueChanged(app, event)
            value = app.Transformation_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.Transformation_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.Transformation_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Button pushed function: Transformation_Sequence_Camera_AddButton
        function Transformation_Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.Transformation_AddtosequenceEditField.Value;

            % Check if a camera movment has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),4)) % colum 4 is transformations
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                [n,~] = size(app.Transformation_UITable.Data);
                app.seq(seqnum).transformation = true;
                for k=1:n
                    app.seq(seqnum).vol(k).tr = cell2mat(app.Transformation_UITable.Data(k,2:4));
                    app.seq(seqnum).vol(k).tr_history = [0 0 0];
                    app.seq(seqnum).vol(k).rot = cell2mat(app.Transformation_UITable.Data(k,5:7));
                    app.seq(seqnum).vol(k).rot_history = [0 0 0];
                    app.seq(seqnum).vol(k).time = cell2mat(app.Transformation_UITable.Data(k,8:9));
                end
                app.seq(seqnum).transformation_change_is = app.Transformation_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).transformation_profile = app.Transformation_ChangeProfile_DropDown.Value;
                app.seq(seqnum).transformation_sens = app.Transformation_ChangeProfileSens_DropDown.Value;                
                
                app.Addseqdata(seqnum, [{seqnum}, {false}, {false}, {true}, {false}, {false}, {false}, {5}]);                
            else
                f = msgbox("This sequence has already a translation or rotation movement","Warning","warn");
            end
        end

        % Cell edit callback: Transformation_UITable
        function Transformation_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)>1
                if isnan(newData) || isempty(newData)
                    app.Transformation_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
                if indices(2)==8 || indices(2)==9
                    tmp = cell2mat(app.Transformation_UITable.Data(indices(1),8:9));
                    if tmp(1)>=tmp(2) || tmp(1)<0 || tmp(2)>1
                        app.Transformation_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                end
            end            
        end

        % Close request function: MicrostructurevisualizationUIFigure
        function MicrostructurevisualizationUIFigureCloseRequest(app, event)
            delete(app.SequenceApp);
            delete(app);            
        end

        % Cell edit callback: Clipping_UITable
        function Clipping_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)~=1 && indices(2) ~=8 && indices(2) ~=12 && indices(2) ~=13
                if indices(2)>=2 && indices(2)<=7 % xyz start end
                    tmp = str2num(newData);
                    if sum(isnan(tmp)) || sum(tmp<0) || sum(tmp>1) % || length(tmp)>2
                        app.Clipping_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                end
                if indices(2)==9 || indices(2)==10 % Time
                    if isnan(newData) || isempty(newData)
                        app.Clipping_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                    tmp = cell2mat(app.Clipping_UITable.Data(indices(1),9:10));
                    if tmp(1)>=tmp(2) || tmp(1)<0 || tmp(2)>1
                        app.Clipping_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                    end
                end
                if indices(2)==11 % Labels
                    if ~isempty(newData)
                        tmp = str2num(newData);
                        if strcmp(newData,'All') || strcmp(newData,'all') % No need
                            %foo=1;
                            app.Clipping_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                        else
                            if isempty(tmp) || sum(isnan(tmp))
                                app.Clipping_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                            end
                        end
                    end
                end                
            end    

            dos = cell2mat(app.Clipping_UITable.Data(:,end));
            if sum(dos)
                app.Clipping_Sequence_Camera_AddButton.Enable = 'on';
            else
                app.Clipping_Sequence_Camera_AddButton.Enable = 'off';
            end            
        end

        % Value changed function: Clipping_Coordinate_ChangeIs_DropDown
        function Clipping_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.Clipping_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.Clipping_ChangeProfile_DropDown.Enable = "on";
                app.Clipping_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.Clipping_ChangeProfile_DropDown.Enable = "off";
                app.Clipping_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: Clipping_ChangeProfile_DropDown
        function Clipping_ChangeProfile_DropDownValueChanged(app, event)
            value = app.Clipping_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.Clipping_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.Clipping_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Button pushed function: Clipping_Sequence_Camera_AddButton
        function Clipping_Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.Clipping_AddtosequenceEditField.Value;

            % Check if a clipping has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),5)) % idx 5 is clipping
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                [n,~] = size(app.Clipping_UITable.Data);
                app.seq(seqnum).clipping = true;
                for k=1:n
                    app.seq(seqnum).vol(k).cliping_xyz = app.Clipping_UITable.Data(k,2:7);
                    app.seq(seqnum).vol(k).orthogonal = cell2mat(app.Clipping_UITable.Data(k,8));
                    app.seq(seqnum).vol(k).clipping_time = cell2mat(app.Clipping_UITable.Data(k,9:10));
                    app.seq(seqnum).vol(k).clipping_label = cell2mat(app.Clipping_UITable.Data(k,11));
                    app.seq(seqnum).vol(k).clipping_additive = cell2mat(app.Clipping_UITable.Data(k,12));
                    app.seq(seqnum).vol(k).clipping_todo = cell2mat(app.Clipping_UITable.Data(k,13));
                end
                app.seq(seqnum).clipping_change_is = app.Clipping_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).clipping_profile = app.Clipping_ChangeProfile_DropDown.Value;
                app.seq(seqnum).clipping_sens = app.Clipping_ChangeProfileSens_DropDown.Value;                
                
                app.Addseqdata(seqnum, [{seqnum}, {false}, {false}, {false}, {true}, {false}, {false}, {5}]);               
            else
                f = msgbox("This sequence has already a cropping FOV","Warning","warn");
            end    
        end

        % Value changed function: LightsourcelocationDropDown
        function LightsourcelocationDropDownValueChanged(app, event)
            value = app.LightsourcelocationDropDown.Value;
            if strcmp(value,'auto')
                app.Light_AzimuthEditField.Enable = 'off';
                app.Light_ElevationEditField.Enable = 'off';
                app.Light_Coordinatealongaxex_Pos_EditField.Enable = 'off';
                app.Light_Coordinatealongaxey_Pos_EditField.Enable = 'off';
                app.Light_Coordinatealongaxez_Pos_EditField.Enable = 'off';               
            elseif strcmp(value,'manual (cartesian)')
                app.Light_AzimuthEditField.Enable = 'off';
                app.Light_ElevationEditField.Enable = 'off';
                app.Light_Coordinatealongaxex_Pos_EditField.Enable = 'on';
                app.Light_Coordinatealongaxey_Pos_EditField.Enable = 'on';
                app.Light_Coordinatealongaxez_Pos_EditField.Enable = 'on';  
            elseif strcmp(value,'manual (spherical)')
                app.Light_AzimuthEditField.Enable = 'on';
                app.Light_ElevationEditField.Enable = 'on';
                app.Light_Coordinatealongaxex_Pos_EditField.Enable = 'off';
                app.Light_Coordinatealongaxey_Pos_EditField.Enable = 'off';
                app.Light_Coordinatealongaxez_Pos_EditField.Enable = 'off';  
            end     
        end

        % Value changed function: LightsourcepositionCheckBox
        function LightsourcepositionCheckBoxValueChanged(app, event)
            value = app.LightsourcepositionCheckBox.Value;
            if value
                app.Light_coordinatechoice_DropDown.Enable = 'on';
                app.Light_coordinatechoice_DropDownValueChanged;
                app.NewLightPos_Coordinate_ChangeIs_DropDown.Enable = 'on';
                app.NewLightPos_Coordinate_ChangeIs_DropDownValueChanged;
            else
                app.Light_coordinatechoice_DropDown.Enable = 'off';
                app.VideoLight_x_DropDown.Enable = 'off';
                app.VideoLight_y_DropDown.Enable = 'off';
                app.VideoLight_z_DropDown.Enable = 'off';
                app.Light_new_x_EditField.Enable = 'off';
                app.Light_new_y_EditField.Enable = 'off';
                app.Light_new_z_EditField.Enable = 'off';                
                app.LightPos_azimuth_choice_DropDown.Enable = 'off';
                app.LightPos_elevation_choice_DropDown.Enable = 'off';
                app.LightPos_new_azimuth_EditField.Enable = 'off';
                app.LightPos_new_elevation_EditField.Enable = 'off';
                app.NewLightPos_Coordinate_ChangeIs_DropDown.Enable = 'off';
                app.NewLightPos_ChangeProfile_DropDown.Enable = 'off';
                app.NewLightPos_ChangeProfileSens_DropDown.Enable = 'off';
            end
            value2 = app.LightintensityCheckBox.Value;
            if value || value2
                app.Light_AddtosequenceEditField.Enable = 'on';
                app.Light_Sequence_Camera_AddButton.Enable = 'on';
            else
                app.Light_AddtosequenceEditField.Enable = 'off';
                app.Light_Sequence_Camera_AddButton.Enable = 'off';
            end
        end

        % Value changed function: VideoLight_x_DropDown
        function VideoLight_x_DropDownValueChanged(app, event)
            value = app.VideoLight_x_DropDown.Value;
            if strcmp(value,'unchanged')
                app.Light_new_x_EditField.Enable = 'off';
            else
                app.Light_new_x_EditField.Enable = 'on';
            end  
        end

        % Value changed function: VideoLight_y_DropDown
        function VideoLight_y_DropDownValueChanged(app, event)
            value = app.VideoLight_y_DropDown.Value;
            if strcmp(value,'unchanged')
                app.Light_new_y_EditField.Enable = 'off';
            else
                app.Light_new_y_EditField.Enable = 'on';
            end  
        end

        % Value changed function: VideoLight_z_DropDown
        function VideoLight_z_DropDownValueChanged(app, event)
            value = app.VideoLight_z_DropDown.Value;
            if strcmp(value,'unchanged')
                app.Light_new_z_EditField.Enable = 'off';
            else
                app.Light_new_z_EditField.Enable = 'on';
            end  
        end

        % Value changed function: LightPos_azimuth_choice_DropDown
        function LightPos_azimuth_choice_DropDownValueChanged(app, event)
            value = app.LightPos_azimuth_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.LightPos_new_azimuth_EditField.Enable = 'off';
            else
                app.LightPos_new_azimuth_EditField.Enable = 'on';
                if strcmp(value,'absolute')
                    app.LightPos_new_azimuth_EditField.Limits = [-180, 180];
                elseif strcmp(value,'delta')
                    app.LightPos_new_azimuth_EditField.Limits = [-Inf, Inf];
                end
            end  
        end

        % Value changed function: LightPos_elevation_choice_DropDown
        function LightPos_elevation_choice_DropDownValueChanged(app, event)
            value = app.LightPos_elevation_choice_DropDown.Value;
            if strcmp(value,'unchanged')
                app.LightPos_new_elevation_EditField.Enable = 'off';
            else
                app.LightPos_new_elevation_EditField.Enable = 'on';
                if strcmp(value,'absolute')
                    app.LightPos_new_elevation_EditField.Limits = [-90, 90];
                elseif strcmp(value,'delta')
                    app.LightPos_new_elevation_EditField.Limits = [-Inf, Inf];
                end
            end  
        end

        % Value changed function: NewLightPos_Coordinate_ChangeIs_DropDown
        function NewLightPos_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.NewLightPos_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.NewLightPos_ChangeProfile_DropDown.Enable = "on";
                app.NewLightPos_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.NewLightPos_ChangeProfile_DropDown.Enable = "off";
                app.NewLightPos_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: NewLightPos_ChangeProfile_DropDown
        function NewLightPos_ChangeProfile_DropDownValueChanged(app, event)
            value = app.NewLightPos_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.NewLightPos_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.NewLightPos_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Value changed function: Light_coordinatechoice_DropDown
        function Light_coordinatechoice_DropDownValueChanged(app, event)
            value = app.Light_coordinatechoice_DropDown.Value;
            if strcmp(value,'spherical')
                app.VideoLight_x_DropDown.Enable = 'off';
                app.VideoLight_y_DropDown.Enable = 'off';
                app.VideoLight_z_DropDown.Enable = 'off';
                app.Light_new_x_EditField.Enable = 'off';
                app.Light_new_y_EditField.Enable = 'off';
                app.Light_new_z_EditField.Enable = 'off';                

                app.LightPos_azimuth_choice_DropDown.Enable = 'on';
                app.LightPos_elevation_choice_DropDown.Enable = 'on';
                app.LightPos_azimuth_choice_DropDownValueChanged;
                app.LightPos_elevation_choice_DropDownValueChanged;
            elseif strcmp(value,'cartesian')
                app.VideoLight_x_DropDown.Enable = 'on';
                app.VideoLight_y_DropDown.Enable = 'on';
                app.VideoLight_z_DropDown.Enable = 'on';
                app.VideoLight_x_DropDownValueChanged;
                app.VideoLight_y_DropDownValueChanged;
                app.VideoLight_z_DropDownValueChanged;                

                app.LightPos_azimuth_choice_DropDown.Enable = 'off';
                app.LightPos_elevation_choice_DropDown.Enable = 'off';
                app.LightPos_new_azimuth_EditField.Enable = 'off';      
                app.LightPos_new_elevation_EditField.Enable = 'off';
            end     
        end

        % Value changed function: LightintensityCheckBox
        function LightintensityCheckBoxValueChanged2(app, event)
            value = app.LightintensityCheckBox.Value;
            if value
                app.Video_new_ambientintensity_EditField.Enable = 'on';
                app.Video_new_sourceintensity_EditField.Enable = 'on';
                app.LightIntensity_ChangeDropDown.Enable = 'on';
                app.LightIntensity_ChangeDropDownValueChanged;
            else
                app.Video_new_ambientintensity_EditField.Enable = 'off';
                app.Video_new_sourceintensity_EditField.Enable = 'off';
                app.LightIntensity_ChangeDropDown.Enable = 'off';    
                app.LightIntensity_ProfileDropDown.Enable = 'off';
                app.LightIntensity_SensDropDown.Enable = 'off';
            end            

            value2 = app.LightsourcepositionCheckBox.Value;
            if value || value2
                app.Light_AddtosequenceEditField.Enable = 'on';
                app.Light_Sequence_Camera_AddButton.Enable = 'on';
            else
                app.Light_AddtosequenceEditField.Enable = 'off';
                app.Light_Sequence_Camera_AddButton.Enable = 'off';
            end

        end

        % Value changed function: LightIntensity_ChangeDropDown
        function LightIntensity_ChangeDropDownValueChanged(app, event)
            value = app.LightIntensity_ChangeDropDown.Value;
            if strcmp(value,'Progressive')
                app.LightIntensity_ProfileDropDown.Enable = "on";
                app.LightIntensity_ProfileDropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.LightIntensity_ProfileDropDown.Enable = "off";
                app.LightIntensity_SensDropDown.Enable = "off";
            end
        end

        % Value changed function: LightIntensity_ProfileDropDown
        function LightIntensity_ProfileDropDownValueChanged(app, event)
            value = app.LightIntensity_ProfileDropDown.Value;
            if strcmp(value,'Linear')
                app.LightIntensity_SensDropDown.Enable = 'off';
            else
                app.LightIntensity_SensDropDown.Enable = 'on';
            end
        end

        % Button pushed function: Light_Sequence_Camera_AddButton
        function Light_Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.Light_AddtosequenceEditField.Value;

            % Check if a light change has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),3)) % 3 Light
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                % Light intensity
                app.seq(seqnum).light_intensity_todo = app.LightintensityCheckBox.Value;
                app.seq(seqnum).light_newambientintensity_value = app.Video_new_ambientintensity_EditField.Value;
                app.seq(seqnum).light_newsourceintensity_value = app.Video_new_sourceintensity_EditField.Value;
                app.seq(seqnum).light_intensity_change = app.LightIntensity_ChangeDropDown.Value;
                app.seq(seqnum).light_intensity_profile = app.LightIntensity_ProfileDropDown.Value;
                app.seq(seqnum).light_intensity_sens = app.LightIntensity_SensDropDown.Value;

                % Light position
                app.seq(seqnum).light_movement_todo = app.LightsourcepositionCheckBox.Value;
                app.seq(seqnum).light_coordinate= app.Light_coordinatechoice_DropDown.Value;
                app.seq(seqnum).light_x_choice = app.VideoLight_x_DropDown.Value;
                app.seq(seqnum).light_y_choice = app.VideoLight_y_DropDown.Value;
                app.seq(seqnum).light_z_choice = app.VideoLight_z_DropDown.Value;
                app.seq(seqnum).light_x = app.Light_new_x_EditField.Value;
                app.seq(seqnum).light_y = app.Light_new_y_EditField.Value;
                app.seq(seqnum).light_z = app.Light_new_z_EditField.Value;
                app.seq(seqnum).light_azimuth_choice = app.LightPos_azimuth_choice_DropDown.Value;
                app.seq(seqnum).light_elevation_choice = app.LightPos_elevation_choice_DropDown.Value;
                app.seq(seqnum).light_azimuth = app.LightPos_new_azimuth_EditField.Value;
                app.seq(seqnum).light_elevation = app.LightPos_new_elevation_EditField.Value;
                app.seq(seqnum).light_coordinate_change_is= app.NewLightPos_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).light_coordinate_profile= app.NewLightPos_ChangeProfile_DropDown.Value;
                app.seq(seqnum).light_coordinate_sens= app.NewLightPos_ChangeProfileSens_DropDown.Value;
                app.camsav(seqnum).LightPos_new_azimuth_EditField_limits = app.LightPos_new_azimuth_EditField.Limits;
                app.camsav(seqnum).LightPos_new_elevation_EditField_limits = app.LightPos_new_elevation_EditField.Limits;

                app.Addseqdata(seqnum, [{seqnum}, {false}, {true}, {false}, {false}, {false}, {false}, {5}]);
            else
                f = msgbox("This sequence has already a light change","Warning","warn");
            end
        end

        % Cell edit callback: ClippingLabel_UITable
        function ClippingLabel_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==3 || indices(2)==4 % From-to
                if isnan(newData) || isempty(newData)
                    app.ClippingLabel_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
                tmp = cell2mat(app.ClippingLabel_UITable.Data(indices(1),3:4));
                if sum(tmp<0) || sum(tmp>1) || tmp(1)==tmp(2)
                    app.ClippingLabel_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            end
            if indices(2)==5 || indices(2)==6 % Time
                if isnan(newData) || isempty(newData)
                    app.ClippingLabel_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
                tmp = cell2mat(app.ClippingLabel_UITable.Data(indices(1),5:6));
                if tmp(1)>=tmp(2) || tmp(1)<0 || tmp(2)>1
                    app.ClippingLabel_UITable.Data(indices(1),indices(2)) = {event.PreviousData};
                end
            end                    

            dos = cell2mat(app.ClippingLabel_UITable.Data(:,end));
            if sum(dos)
                app.ClippingLabel_Sequence_Camera_AddButton.Enable = 'on';
            else
                app.ClippingLabel_Sequence_Camera_AddButton.Enable = 'off';
            end      

        end

        % Value changed function: 
        % ClippingLabel_Coordinate_ChangeIs_DropDown
        function ClippingLabel_Coordinate_ChangeIs_DropDownValueChanged(app, event)
            value = app.ClippingLabel_Coordinate_ChangeIs_DropDown.Value;
            if strcmp(value,'Progressive')
                app.ClippingLabel_ChangeProfile_DropDown.Enable = "on";
                app.ClippingLabel_ChangeProfile_DropDownValueChanged;
            elseif strcmp(value,'Instantaneous')
                app.ClippingLabel_ChangeProfile_DropDown.Enable = "off";
                app.ClippingLabel_ChangeProfileSens_DropDown.Enable = "off";
            end
        end

        % Value changed function: ClippingLabel_ChangeProfile_DropDown
        function ClippingLabel_ChangeProfile_DropDownValueChanged(app, event)
            value = app.ClippingLabel_ChangeProfile_DropDown.Value;
            if strcmp(value,'Linear')
                app.ClippingLabel_ChangeProfileSens_DropDown.Enable = 'off';
            else
                app.ClippingLabel_ChangeProfileSens_DropDown.Enable = 'on';
            end
        end

        % Button pushed function: ClippingLabel_Sequence_Camera_AddButton
        function ClippingLabel_Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.ClippingLabel_AddtosequenceEditField.Value;

            % Check if a clipping has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),6)) % idx 6 is clipping label
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                [n,~] = size(app.Clipping_UITable.Data);
                app.seq(seqnum).cliplabel = true;
                for k=1:n
                    app.seq(seqnum).vol(k).cliplabel_ordering_size = app.ClippingLabel_UITable.Data(k,2);
                    app.seq(seqnum).vol(k).cliplabel_fromto = cell2mat(app.ClippingLabel_UITable.Data(k,3:4));
                    app.seq(seqnum).vol(k).cliplabel_times = cell2mat(app.ClippingLabel_UITable.Data(k,5:6));
                    app.seq(seqnum).vol(k).cliplabel_additive = cell2mat(app.ClippingLabel_UITable.Data(k,7));
                    app.seq(seqnum).vol(k).cliplabel_todo = cell2mat(app.ClippingLabel_UITable.Data(k,8));
                end
                app.seq(seqnum).cliplabel_change_is = app.ClippingLabel_Coordinate_ChangeIs_DropDown.Value;
                app.seq(seqnum).cliplabel_profile = app.ClippingLabel_ChangeProfile_DropDown.Value;
                app.seq(seqnum).cliplabel_sens = app.ClippingLabel_ChangeProfileSens_DropDown.Value;                
                
                app.Addseqdata(seqnum, [{seqnum}, {false}, {false}, {false}, {false}, {true}, {false}, {5}]);               
            else
                f = msgbox("This sequence has already a cropping label","Warning","warn");
            end    
        end

        % Value changed function: SegmentationcomparisonCheckBox
        function SegmentationcomparisonCheckBoxValueChanged(app, event)
            seg_comparison = app.SegmentationcomparisonCheckBox.Value;
            selected = cell2mat(app.Import_UITable.Data(:,end));
            id_selected = find(selected);
            n = length(id_selected);
            if seg_comparison
                app.SegComp_HowtoLabel.Visible = 'on';
                app.TwoD_UITable.ColumnName = {'Files','Size','Voxel size','Grey level','Ground truth','Select'};
                app.TwoD_UITable.ColumnEditable = [false, false, false, false, true, true];
                app.TwoD_UITable.Data = cell(n,6);
                app.TwoD_UITable.Data(:,1) = app.Import_UITable.Data(id_selected,1);
                app.TwoD_UITable.Data(:,2) = app.Import_UITable.Data(id_selected,6);
                app.TwoD_UITable.Data(:,3) = app.Import_UITable.Data(id_selected,7);
                app.TwoD_UITable.Data(:,4) = app.Import_UITable.Data(id_selected,8);
                for k=1:n
                    app.TwoD_UITable.Data(k,5) = {false};
                end
                app.TwoD_UITable.Data(:,6) = app.Import_UITable.Data(id_selected,end);   
                app.check_filesforsegmentation;
            else
                app.SegComp_HowtoLabel.Visible = 'off';
                app.TwoD_UITable.ColumnName = {'Files','Voxel size','Select'};
                app.TwoD_UITable.ColumnEditable = [false, false, true];
                app.TwoD_UITable.Data = cell(n,3);
                app.TwoD_UITable.Data(:,1) = app.Import_UITable.Data(id_selected,1);
                app.TwoD_UITable.Data(:,2) = app.Import_UITable.Data(id_selected,7);
                app.TwoD_UITable.Data(:,3) = app.Import_UITable.Data(id_selected,end);
            end            
        end

        % Button pushed function: TwoDview_Button
        function TwoDview_ButtonPushed(app, event)
            seg_comparison = app.SegmentationcomparisonCheckBox.Value;
            selected_import = cell2mat(app.Import_UITable.Data(:,end));
            id_selected_import = find(selected_import);

            selected_2D = cell2mat(app.TwoD_UITable.Data(:,end));
            id_selected_2D = find(selected_2D);
            n_2D = length(id_selected_2D);    

            voxelsize_unitname = app.VoxelsizeunitEditField.Value;

            kfiles = id_selected_import(id_selected_2D);

            if seg_comparison
                manual_segmentation = false;
                voxelsize = cell2mat(app.TwoD_UITable.Data(id_selected_2D,3));

                isgroundtruth = cell2mat(app.TwoD_UITable.Data(id_selected_2D,5));
                isgroundtruth = find(isgroundtruth);
                if isempty(isgroundtruth)
                    isgroundtruth = false;
                    selected_2Dgrey = cell2mat(app.TwoD_UITable.Data(:,4));
                    id_selected_2Dgrey = find(selected_2Dgrey.*selected_2D);
                    kgrey = id_selected_import(id_selected_2Dgrey);

                    if ~isempty(app.Img(kgrey).modified)
                        greyvolume(1).array = app.Img(kgrey).modified;
                    else
                        greyvolume(1).array = app.Img(kgrey).original;
                    end  
                    greyvolume(1).name = char(app.TwoD_UITable.Data(id_selected_2Dgrey,1));

                    selected_2Dsegmented = ~cell2mat(app.TwoD_UITable.Data(:,4));
                    id_selected_2Dsegmented = find(selected_2Dsegmented.*selected_2D);
                    klabel = id_selected_import(id_selected_2Dsegmented);

                    for k = 1:length(klabel)
                        if ~isempty(app.Img(klabel(k)).modified)
                            segmentedvolumes(k).array = app.Img(klabel(k)).modified;
                        else
                            segmentedvolumes(k).array = app.Img(klabel(k)).original;
                        end
                        segmentedvolumes(k).name = char(app.TwoD_UITable.Data(id_selected_2Dsegmented(k),1));
                    end
                else
                    isgroundtruth = true;
                    selected_2Dgroundtruth = cell2mat(app.TwoD_UITable.Data(:,5));
                    id_selected_2Dgroundtruth = find(selected_2Dgroundtruth.*selected_2D);
                    kgroundtruth = id_selected_import(id_selected_2Dgroundtruth);                    

                    if ~isempty(app.Img(kgroundtruth).modified)
                        greyvolume(1).array = app.Img(kgroundtruth).modified;
                    else
                        greyvolume(1).array = app.Img(kgroundtruth).original;
                    end  
                    greyvolume(1).name = char(app.TwoD_UITable.Data(id_selected_2Dgroundtruth,1));                    

                    selected_2Dsegmented = ~cell2mat(app.TwoD_UITable.Data(:,4));
                    selected_2Dsegmented(selected_2Dgroundtruth) = 0;
                    id_selected_2Dsegmented = find(selected_2Dsegmented.*selected_2D);
                    klabel = id_selected_import(id_selected_2Dsegmented);

                    for k = 1:length(klabel)
                        if ~isempty(app.Img(klabel(k)).modified)
                            segmentedvolumes(k).array = app.Img(klabel(k)).modified;
                        else
                            segmentedvolumes(k).array = app.Img(klabel(k)).original;
                        end
                        segmentedvolumes(k).name = char(app.TwoD_UITable.Data(id_selected_2Dsegmented(k),1));
                    end

                end
                microstructure_visualization_segmentation_v2(greyvolume, segmentedvolumes, isgroundtruth,  voxelsize, voxelsize_unitname, manual_segmentation);
                %microstructure_visualization_segmentation(greyvolume, segmentedvolumes, groundtruth,  app.Checkseg_VoxelsizeEditField.Value, app.Checkseg_UnitEditField.Value,manual_segmentation);
            else

                if n_2D==1
                    name = char(app.TwoD_UITable.Data(id_selected_2D,1));
                    voxelsize = cell2mat(app.TwoD_UITable.Data(id_selected_2D,2));
                    arrayunit = '';
                    if ~isempty(app.Img(kfiles).modified)
                        microstructure_visualization_slices(app.Img(kfiles).modified, name, voxelsize, voxelsize_unitname, arrayunit);
                    else
                        microstructure_visualization_slices(app.Img(kfiles).original, name, voxelsize, voxelsize_unitname, arrayunit);
                    end                    

                else
                    % % Build the string require to call the visualization function
                    % str = 'Microstructure_comparison_visualization_interface(';
                    % first_volume = true;
                    % for k=1:1:length(choice)
                    %     if choice(k)
                    %         if first_volume
                    %             str = [str 'app.volumes(' num2str(k) ').array'];
                    %             first_volume = false;
                    %         else
                    %             str = [str ',app.volumes(' num2str(k) ').array'];
                    %         end
                    %     end
                    % end
                    % str = [str ');'];
                    % eval(str);

                    % Build the string require to call the visualization function
                    str = 'Visualization_multiple_volumes(p,';
                    first_volume = true;
                    names = app.TwoD_UITable.Data(id_selected_2D,1);
                    for k=1:1:n_2D
                        if first_volume
                            if ~isempty(app.Img(kfiles(k)).modified)
                                str = [str 'app.Img(' num2str(kfiles(k)) ').modified'];
                            else
                                str = [str 'app.Img(' num2str(kfiles(k)) ').original'];                                
                            end
                            %str = [str 'app.volumes(' num2str(k) ').array'];
                            first_volume = false;
                        else
                            if ~isempty(app.Img(kfiles(k)).modified)
                                str = [str ',app.Img(' num2str(kfiles(k)) ').modified'];
                            else
                                str = [str ',app.Img(' num2str(kfiles(k)) ').original'];                           
                            end
                            %str = [str ',app.volumes(' num2str(k) ').array'];
                        end
                        p(k).name = char(names(k));
                    end
                    str = [str ');'];
                    eval(str);
                end
            end
        end

        % Cell edit callback: TwoD_UITable
        function TwoD_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;            
            seg_comparison = app.SegmentationcomparisonCheckBox.Value;
            if seg_comparison
                if indices(2)==5
                    if cell2mat(app.TwoD_UITable.Data(indices(1),4))
                        app.TwoD_UITable.Data(indices(1),5) = {false};
                    else
                        if newData
                            [n,~] = size(app.TwoD_UITable.Data);
                            for k=1:n
                                if k~=indices(1)
                                    app.TwoD_UITable.Data(k,5) = {false};
                                end
                            end
                        end
                    end                              
                end
                app.check_filesforsegmentation;
            else
                selected_2D = cell2mat(app.TwoD_UITable.Data(:,end));
                id_selected_2D = find(selected_2D);
                if ~isempty(id_selected_2D)
                    app.TwoDview_Button.Enable = 'on';
                else
                    app.TwoDview_Button.Enable = 'off';
                end
            end            
        end

        % Image clicked function: NREL_logo
        function NREL_logoImageClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)   
        end

        % Callback function
        function CheckonlineversionButtonPushed(app, event)
            localversion = app.Localversion.Text;
            app.Onlineversion_Label.Text = 'Please wait...'; pause(0.1);
            try
                githubversionfile=webread('https://raw.githubusercontent.com/NREL/MATBOX_Microstructure_analysis_toolbox/master/License.txt');
                if ~strcmp(localversion,githubversionfile)
                    app.Onlineversion_Label.Text = ['There is a new version (' localversion ') available online. Please go to the Github repositorty to update your MATBOX installation'];
                    app.Onlineversion_Label.FontColor = 'r';
                else
                    app.Onlineversion_Label.Text = 'You are using the up-to-date version of MATBOX';
                    app.Onlineversion_Label.FontColor = 'k';
                end
            catch ME
                if strcmp(ME.identifier,'MATLAB:webservices:Timeout')
                    app.Onlineversion_Label.Text = 'Could not check current version online.';
                end
            end
            % Sometimes the catch ME fails
            if strcmp(app.Onlineversion_Label.Text,'Please wait...')
                app.Onlineversion_Label.Text = 'Could not check current version online.';
            end
        end

        % Button pushed function: SeeexamplesinthegallerytabButton
        function SeeexamplesinthegallerytabButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GalleryTab;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            frame = round(app.FrameSlider.Value);
            app.FrameSlider.Value = frame;
            app.FrameEditField.Value = frame;
            app.update_frame(frame);            
        end

        % Button pushed function: ImportvideofileButton
        function ImportvideofileButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select one video file';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,'MultiSelect','off');
            else
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,app.importpath,'MultiSelect','off');
            end
            datatype = class(FileName);

            if strcmp(datatype,'double')
                % User clicked cancel button or closed the dialog box  
                app.vidObj = [];
                app.NovideofileLabel.Text = 'No video file';
                app.NovideofileLabel.Tooltip = app.NovideofileLabel.Text;
                app.DimensionnaLabel.Text = 'Dimension: n/a';
                app.LengthnaLabel.Text = 'Length: n/a';
                app.NumberofframesnaLabel.Text = 'Number of frames: n/a';
                app.FramepersecondfpsnaLabel.Text = 'Frame per second (fps): n/a';
                app.CropxaxisfromEditField.Value = 1; app.CropxaxistoEditField.Value = 1000;
                app.CropyaxisfromEditField.Value = 1; app.CropyaxistoEditField.Value = 1000;
                app.KeepframesfromEditField.Value = 1; app.KeepframetoEditField.Value = 1000;
                app.NewlengthsEditField.Value = 1;
                app.NewfpsEditField.Value = 1;     

                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 100];

                imshow([],'Parent',app.Frame_UIAxes);

                app.KeepframeeveryEditField.Enable = 'off'; app.KeepframeeveryEditField.Value = 1;
                app.VideoresolutionwidthEditField.Enable = 'off'; app.VideoresolutionwidthEditField.Value = 1;
                app.heightEditField.Enable = 'off'; app.heightEditField.Value = 1;
                app.CropyaxistoEditField.Enable = 'off';
                app.CropyaxisfromEditField.Enable = 'off';
                app.CropxaxistoEditField.Enable = 'off';
                app.CropxaxisfromEditField.Enable = 'off';
                app.KeepframetoEditField.Enable = 'off';
                app.KeepframesfromEditField.Enable = 'off';
                app.NewlengthsEditField.Enable = 'off';
                app.NewfpsEditField.Enable = 'off';
                app.SaveButton.Enable = 'off';

                app.FrameSlider.Enable = 'off';
                app.Button_minusframe.Enable = 'off';
                app.Button_plusframe.Enable = 'off';
                app.FrameEditField.Enable = 'off';
            else
                if strcmp(datatype,'char')
                    app.vidObj = VideoReader(fullfile(PathName,FileName));
                    app.NovideofileLabel.Text = fullfile(PathName,FileName);
                    app.NovideofileLabel.Tooltip = app.NovideofileLabel.Text;
                    app.DimensionnaLabel.Text = ['Dimension: ' num2str(app.vidObj.Width) ' x ' num2str(app.vidObj.Height)];
                    app.LengthnaLabel.Text = ['Length: ' num2str(app.vidObj.Duration) ' s'];
                    app.NumberofframesnaLabel.Text = ['Number of frames: ' num2str(app.vidObj.NumFrames)];
                    app.FramepersecondfpsnaLabel.Text = ['Frame per second (fps): ' num2str(app.vidObj.FrameRate)];
                    app.CropxaxisfromEditField.Value = 1; app.CropxaxistoEditField.Value = app.vidObj.Width;
                    app.CropyaxisfromEditField.Value = 1; app.CropyaxistoEditField.Value = app.vidObj.Height;
                    app.KeepframesfromEditField.Value = 1; app.KeepframetoEditField.Value = app.vidObj.NumFrames;
                    app.NewlengthsEditField.Value = app.vidObj.Duration;
                    app.NewfpsEditField.Value = app.vidObj.FrameRate;  

                    scrsz = get(0,'ScreenSize');
                    app.KeepframeeveryEditField.Enable = 'on'; app.KeepframeeveryEditField.Value = 1;
                    app.VideoresolutionwidthEditField.Enable = 'on'; app.VideoresolutionwidthEditField.Value = scrsz(3);
                    app.heightEditField.Enable = 'on'; app.heightEditField.Value = scrsz(4);

                    app.FrameSlider.Value = 1;
                    app.FrameSlider.Limits = [1 app.vidObj.NumFrames];

                    vidframe = read(app.vidObj,1);
                    imshow(vidframe,'Parent',app.Frame_UIAxes);

                    app.CropyaxistoEditField.Enable = 'on';
                    app.CropyaxisfromEditField.Enable = 'on';
                    app.CropxaxistoEditField.Enable = 'on';
                    app.CropxaxisfromEditField.Enable = 'on';
                    app.KeepframetoEditField.Enable = 'on';
                    app.KeepframesfromEditField.Enable = 'on';
                    app.NewlengthsEditField.Enable = 'on';
                    app.NewfpsEditField.Enable = 'on';
                    app.SaveButton.Enable = 'on';

                    app.FrameSlider.Enable = 'on';
                    app.Button_minusframe.Enable = 'on';
                    app.Button_plusframe.Enable = 'on';
                    app.FrameEditField.Enable = 'on';
                    
                end
            end

        end

        % Button pushed function: Button_minusframe
        function Button_minusframePushed(app, event)
            current_frame = app.FrameEditField.Value;
            new_frame = current_frame-1;
            if new_frame>=1
                app.FrameEditField.Value = new_frame;
                app.FrameSlider.Value = new_frame;
                app.update_frame(new_frame);    
            end
        end

        % Button pushed function: Button_plusframe
        function Button_plusframePushed(app, event)
            current_frame = app.FrameEditField.Value;
            new_frame = current_frame+1;
            if new_frame<=app.vidObj.NumFrames
                app.FrameEditField.Value = new_frame;
                app.FrameSlider.Value = new_frame;
                app.update_frame(new_frame);    
            end            
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            new_frame = round(app.FrameEditField.Value);
            if new_frame >=1 && new_frame<=app.vidObj.NumFrames
                app.FrameEditField.Value = new_frame;
                app.FrameSlider.Value = new_frame;
                app.update_frame(new_frame);    
            else
                app.FrameEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: CropxaxisfromEditField
        function CropxaxisfromEditFieldValueChanged(app, event)
            from = round(app.CropxaxisfromEditField.Value);
            to = app.CropxaxistoEditField.Value;
            if from >=1 && from<to
                frame = app.FrameEditField.Value;
                app.update_frame(frame);    
            else
                app.CropxaxisfromEditField.Value = event.PreviousValue;
            end   
        end

        % Value changed function: CropxaxistoEditField
        function CropxaxistoEditFieldValueChanged(app, event)
            from = app.CropxaxisfromEditField.Value;
            to = round(app.CropxaxistoEditField.Value);
            if from<to && from<=app.vidObj.Width
                frame = app.FrameEditField.Value;
                app.update_frame(frame);    
            else
                app.CropxaxistoEditField.Value = event.PreviousValue;
            end               
        end

        % Value changed function: CropyaxisfromEditField
        function CropyaxisfromEditFieldValueChanged(app, event)
            from = round(app.CropyaxisfromEditField.Value);
            to = app.CropyaxistoEditField.Value;
            if from >=1 && from<to
                frame = app.FrameEditField.Value;
                app.update_frame(frame);    
            else
                app.CropyaxisfromEditField.Value = event.PreviousValue;
            end 
        end

        % Value changed function: CropyaxistoEditField
        function CropyaxistoEditFieldValueChanged(app, event)
            from = app.CropyaxisfromEditField.Value;
            to = round(app.CropyaxistoEditField.Value);
            if from<to && from<=app.vidObj.Height
                frame = app.FrameEditField.Value;
                app.update_frame(frame);    
            else
                app.CropyaxistoEditField.Value = event.PreviousValue;
            end 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [folder,filename,~] = fileparts(app.NovideofileLabel.Text);
                        
            fps = app.NewfpsEditField.Value;
            frame_start = app.KeepframesfromEditField.Value;
            frame_end = app.KeepframetoEditField.Value;
            everyother = app.KeepframeeveryEditField.Value;
            frames = [frame_start:everyother:frame_end];            

            from_x = app.CropxaxisfromEditField.Value;
            to_x = app.CropxaxistoEditField.Value;
            from_y = app.CropyaxisfromEditField.Value;
            to_y = app.CropyaxistoEditField.Value;

            hFig = uifigure;
            ax = axes('Parent',hFig);
            width = app.VideoresolutionwidthEditField.Value;
            height = app.heightEditField.Value;
            scrsz = get(0,'ScreenSize');
            width = min([width scrsz(3)]);
            height = min([height scrsz(4)]);
            set(hFig,'position',[1 1 width height]);  

            if strcmp(app.formatDropDown.Value,'mp4')
                fullpath = fullfile(folder,[filename '_modified.mp4']);
                video_handle = VideoWriter(fullpath,'mpeg-4');
                set(video_handle,'Quality',100); % Set video quality
                set(video_handle,'FrameRate',fps);
                open(video_handle);
                for frame=1:app.vidObj.NumFrames
                    if sum(frame==frames)
                        vidframe = read(app.vidObj,frame);
                        cropped = vidframe(from_y:to_y,from_x:to_x,:);
                        imshow(cropped,'Parent',ax);
                        set(hFig,'position',[1 1 width height]); 
                        writeVideo(video_handle,getframe(hFig))
                    end
                end
                close(video_handle)
            elseif strcmp(app.formatDropDown.Value,'gif')
                fullpath = fullfile(folder,[filename '_modified.gif']);
                first_frame = true;
                for frame=1:app.vidObj.NumFrames
                    if sum(frame==frames)
                        vidframe = read(app.vidObj,frame);
                        cropped = vidframe(from_y:to_y,from_x:to_x,:);
                        imshow(cropped,'Parent',ax);
                        set(hFig,'position',[1 1 width height]); 
                        if first_frame
                            exportgraphics(hFig,fullpath);
                            first_frame = false;
                        else
                            exportgraphics(hFig,fullpath,'Append',true);
                        end
                    end
                end
            end
        end

        % Value changed function: KeepframesfromEditField
        function KeepframesfromEditFieldValueChanged(app, event)
            from = round(app.KeepframesfromEditField.Value);
            to = app.KeepframetoEditField.Value;
            if from >=1 && from<to
                app.Calculate_fps_time('imposed_length');    
            else
                app.KeepframesfromEditField.Value = event.PreviousValue;
            end  
        end

        % Value changed function: KeepframetoEditField
        function KeepframetoEditFieldValueChanged(app, event)
            from = round(app.KeepframesfromEditField.Value);
            to = app.KeepframetoEditField.Value;
            if from<to && to <= app.vidObj.NumFrames
                app.Calculate_fps_time('imposed_length');    
            else
                app.KeepframetoEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: NewlengthsEditField
        function NewlengthsEditFieldValueChanged(app, event)
            app.Calculate_fps_time('imposed_length');
        end

        % Value changed function: NewfpsEditField
        function NewfpsEditFieldValueChanged(app, event)
            app.Calculate_fps_time('imposed_fps');
        end

        % Value changed function: VideoresolutionwidthEditField
        function VideoresolutionwidthEditFieldValueChanged(app, event)
            new_width = app.VideoresolutionwidthEditField.Value;
            scrsz = get(0,'ScreenSize');
            if new_width>scrsz(3)
                app.VideoresolutionwidthEditField.Value = scrsz(3);
            end            
        end

        % Value changed function: heightEditField
        function heightEditFieldValueChanged(app, event)
            new_height = app.heightEditField.Value;
            scrsz = get(0,'ScreenSize');
            if new_height>scrsz(4)
                app.heightEditField.Value = scrsz(4);
            end                
        end

        % Value changed function: KeepframeeveryEditField
        function KeepframeeveryEditFieldValueChanged(app, event)
            app.Calculate_fps_time('imposed_length');
        end

        % Button pushed function: Importvideo1Button
        function Importvideo1ButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select first video file';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,'MultiSelect','off');
            else
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,app.importpath,'MultiSelect','off');
            end
            datatype = class(FileName);

            if strcmp(datatype,'double')
                % User clicked cancel button or closed the dialog box  
                app.vidObj1 = [];
                app.NovideofileLabel_2.Text = 'No video file';
                app.NovideofileLabel_2.Tooltip = app.NovideofileLabel_2.Text;
                app.DimensionnaLabel_2.Text = 'Dimension: n/a';
                app.LengthnaLabel_2.Text = 'Length: n/a';
                app.NumberofframesnaLabel_2.Text = 'Number of frames: n/a';
                app.FramepersecondfpsnaLabel_2.Text = 'Frame per second (fps): n/a';
            else
                if strcmp(datatype,'char')
                    app.vidObj1 = VideoReader(fullfile(PathName,FileName));
                    app.NovideofileLabel_2.Text = fullfile(PathName,FileName);
                    app.NovideofileLabel_2.Tooltip = app.NovideofileLabel_2.Text;
                    app.DimensionnaLabel_2.Text = ['Dimension: ' num2str(app.vidObj1.Width) ' x ' num2str(app.vidObj1.Height)];
                    app.LengthnaLabel_2.Text = ['Length: ' num2str(app.vidObj1.Duration) ' s'];
                    app.NumberofframesnaLabel_2.Text = ['Number of frames: ' num2str(app.vidObj1.NumFrames)];
                    app.FramepersecondfpsnaLabel_2.Text = ['Frame per second (fps): ' num2str(app.vidObj1.FrameRate)];                    
                end
            end
            app.Check_bothvideos;
        end

        % Button pushed function: Importvideo2Button
        function Importvideo2ButtonPushed(app, event)
            % Open dialog box to choose file path
            str_dialogbox = 'Select second video file';
            if isempty(app.importpath)
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,'MultiSelect','off');
            else
                [FileName,PathName,~] = uigetfile({'*.mp4','Video file (*.mp4)'},str_dialogbox,app.importpath,'MultiSelect','off');
            end
            datatype = class(FileName);

            if strcmp(datatype,'double')
                % User clicked cancel button or closed the dialog box  
                app.vidObj2 = [];
                app.NovideofileLabel_3.Text = 'No video file';
                app.NovideofileLabel_3.Tooltip = app.NovideofileLabel_3.Text;
                app.DimensionnaLabel_3.Text = 'Dimension: n/a';
                app.LengthnaLabel_3.Text = 'Length: n/a';
                app.NumberofframesnaLabel_3.Text = 'Number of frames: n/a';
                app.FramepersecondfpsnaLabel_3.Text = 'Frame per second (fps): n/a';
            else
                if strcmp(datatype,'char')
                    app.vidObj2 = VideoReader(fullfile(PathName,FileName));
                    app.NovideofileLabel_3.Text = fullfile(PathName,FileName);
                    app.NovideofileLabel_3.Tooltip = app.NovideofileLabel_3.Text;
                    app.DimensionnaLabel_3.Text = ['Dimension: ' num2str(app.vidObj2.Width) ' x ' num2str(app.vidObj2.Height)];
                    app.LengthnaLabel_3.Text = ['Length: ' num2str(app.vidObj2.Duration) ' s'];
                    app.NumberofframesnaLabel_3.Text = ['Number of frames: ' num2str(app.vidObj2.NumFrames)];
                    app.FramepersecondfpsnaLabel_3.Text = ['Frame per second (fps): ' num2str(app.vidObj2.FrameRate)];                    
                end
            end
            app.Check_bothvideos;
        end

        % Value changed function: VideoresolutionwidthEditField_2
        function VideoresolutionwidthEditField_2ValueChanged(app, event)
            new_width = app.VideoresolutionwidthEditField_2.Value;
            scrsz = get(0,'ScreenSize');
            if new_width>scrsz(3)
                app.VideoresolutionwidthEditField_2.Value = scrsz(3);
            end      
        end

        % Value changed function: heightEditField_2
        function heightEditField_2ValueChanged(app, event)
            new_height = app.heightEditField_2.Value;
            scrsz = get(0,'ScreenSize');
            if new_height>scrsz(4)
                app.heightEditField_2.Value = scrsz(4);
            end       
        end

        % Value changed function: NewfpsEditField_2
        function NewfpsEditField_2ValueChanged(app, event)
            app.Calculate_fps_time_concatenatedvideo('imposed_fps');
        end

        % Value changed function: NewlengthsEditField_2
        function NewlengthsEditField_2ValueChanged(app, event)
            app.Calculate_fps_time_concatenatedvideo('imposed_length');            
        end

        % Button pushed function: SavefolderButton_7
        function SavefolderButton_7Pushed(app, event)
            str_dialogbox = 'Select the folder where the video file will be saved'; % Set string of the dialog box
            videosavefolder = uigetdir(app.NofolderselectedLabel.Text,str_dialogbox); % Open dialog box to choose file path
            if videosavefolder~=0
                app.NofolderselectedLabel.Text = videosavefolder;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            folder = app.NofolderselectedLabel.Text;
            filename = app.FilenameEditField.Value;
            
            fps = app.NewfpsEditField_2.Value;

            hFig = uifigure;
            ax = axes('Parent',hFig);
            width = app.VideoresolutionwidthEditField_2.Value;
            height = app.heightEditField_2.Value;
            scrsz = get(0,'ScreenSize');
            width = min([width scrsz(3)]);
            height = min([height scrsz(4)]);
            set(hFig,'position',[1 1 width height]);  

            if strcmp(app.formatDropDown_2.Value,'mp4')
                fullpath = fullfile(folder,[filename '.mp4']);
                video_handle = VideoWriter(fullpath,'mpeg-4');
                set(video_handle,'Quality',100); % Set video quality
                set(video_handle,'FrameRate',fps);
                open(video_handle);
                for frame=1:app.vidObj1.NumFrames
                    vidframe = read(app.vidObj1,frame);
                    imshow(vidframe,'Parent',ax);
                    set(hFig,'position',[1 1 width height]);
                    writeVideo(video_handle,getframe(hFig))
                end
                for frame=1:app.vidObj2.NumFrames
                    vidframe = read(app.vidObj2,frame);
                    imshow(vidframe,'Parent',ax);
                    set(hFig,'position',[1 1 width height]);
                    writeVideo(video_handle,getframe(hFig))
                end                
                close(video_handle)
            elseif strcmp(app.formatDropDown_2.Value,'gif')
                fullpath = fullfile(folder,[filename '.gif']);
                first_frame = true;
                for frame=1:app.vidObj1.NumFrames
                    vidframe = read(app.vidObj1,frame);
                    imshow(vidframe,'Parent',ax);
                    set(hFig,'position',[1 1 width height]);
                    if first_frame
                        exportgraphics(hFig,fullpath);
                        first_frame = false;
                    else
                        exportgraphics(hFig,fullpath,'Append',true);
                    end
                end
                for frame=1:app.vidObj2.NumFrames
                    vidframe = read(app.vidObj2,frame);
                    imshow(vidframe,'Parent',ax);
                    set(hFig,'position',[1 1 width height]);
                    exportgraphics(hFig,fullpath,'Append',true);
                end                
            end
        end

        % Value changed function: Opacity_ChangeisDropDown
        function Opacity_ChangeisDropDownValueChanged(app, event)
            value = app.Opacity_ChangeisDropDown.Value;
            
        end

        % Value changed function: Opacity_ChangeProfileDropDown
        function Opacity_ChangeProfileDropDownValueChanged(app, event)
            value = app.Opacity_ChangeProfileDropDown.Value;
            
        end

        % Value changed function: Opacity_ChangeSensDropDown
        function Opacity_ChangeSensDropDownValueChanged(app, event)
            value = app.Opacity_ChangeSensDropDown.Value;
            
        end

        % Button pushed function: Opacity_Sequence_Camera_AddButton
        function Opacity_Sequence_Camera_AddButtonPushed(app, event)
            seqnum = app.Opacity_AddtosequenceEditField.Value;

            % Check if an opacity has not been already assigned to this sequence
            can_add_sequence = true;
            if ~isempty(app.seqdata)
                idseq = find(  cell2mat(app.seqdata(:,1))== seqnum);
                for k=1:length(idseq)
                    if cell2mat(app.seqdata(idseq(k),7)) % idx 7 is opacity label
                        can_add_sequence = false;
                    end
                end
            end

            if can_add_sequence
                [n,~] = size(app.ChangeOpacity_UITable.Data);
                app.seq(seqnum).opacity = true;
                for k=1:n
                    app.seq(seqnum).vol(k).opacity_from = cell2mat(app.ChangeOpacity_UITable.Data(k,2));
                    app.seq(seqnum).vol(k).opacity_to = cell2mat(app.ChangeOpacity_UITable.Data(k,3));
                    app.seq(seqnum).vol(k).cliplabel_todo = cell2mat(app.ChangeOpacity_UITable.Data(k,4));
                end
                app.seq(seqnum).opacity_change_is = app.Opacity_ChangeisDropDown.Value;
                app.seq(seqnum).opacity_profile = app.Opacity_ChangeProfileDropDown.Value;
                app.seq(seqnum).opacity_sens = app.Opacity_ChangeSensDropDown.Value;                
                
                app.Addseqdata(seqnum, [{seqnum}, {false}, {false}, {false}, {false}, {false}, {true}, {5}]);               
            else
                f = msgbox("This sequence has already a opcacity label","Warning","warn");
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MicrostructurevisualizationUIFigure = uifigure('Visible', 'off');
            app.MicrostructurevisualizationUIFigure.Name = 'Microstructure visualization';
            app.MicrostructurevisualizationUIFigure.Icon = 'Icon_visualization.png';
            app.MicrostructurevisualizationUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrostructurevisualizationUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.MicrostructurevisualizationUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions';
            app.GridLayout23 = uigridlayout(app.InstructionsTab);
            app.ViewgreylevelorsegmentedimagesLabel = uilabel(app.GridLayout23);
            app.ViewgreylevelorsegmentedimagesLabel.Text = 'View grey level or segmented images';
            app.ViewcharacterizationresultsLabel = uilabel(app.GridLayout23);
            app.ViewcharacterizationresultsLabel.Text = 'View characterization results';
            app.Label3_2 = uilabel(app.GridLayout23);
            app.Label3_2.Text = 'This visualization mode will be re-added later once the characterization module reword will be finished';
            app.Youcancreates2Dimagesor3DvideosLabel = uilabel(app.GridLayout23);
            app.Youcancreates2Dimagesor3DvideosLabel.Text = {'You can create static images and videos, combining grey level, semantic segmentation, and instance segmentation data. Both 2D and 3D (stack) tif (or tiff) files are supported.'; 'First, import one or multiple files in the ''Import tab'', set their data type (grey level, semantic segmentation, or instance segmentation), and then:'};
            app.Inthe3DvolumevisualizationtabyoucanDisplay3DrenderingLabel = uilabel(app.GridLayout23);
            app.Inthe3DvolumevisualizationtabyoucanDisplay3DrenderingLabel.Text = {'In the "3D volume visualization" tab, you can:'; '   - Display 3D rendering of one or several volumes, not necessarily sharing dimension and data type.'; '   - Volumes with the same data type share the same colormap.'; '   - If showing mulitple volumes, control their spatial distribution and alignment in a 3D grid.'; '   - Control background color, box, axes, lighting, and scale bar'; '   - Control camera position, camera target, camera vector, and camera zoom'; ''; '   You can then configure several sequences to create a video:'; '   - A video is divided into sequences.'; '   - Each sequence has its own length.'; '   - A sequence is made of different actions (camera movements, lighting change, volume translations/rotations, field of view clipping, label clipping)'; '   - Some actions are applied to the whole scene (e.g. camera movement) while some actions are applied per volume (e.g., volume translation)'; '   - Each sequence can mix different actions (e.g. camera movements and volume translations).'; '   - Each action speed can be controlled (e.g., a rotation with a constant angle increment per frame, or smoothly increasing then decreasing).'; ''; '   Tips: setting a video can be time consumming, especially if it is made of several sequences. To speed-up the process, a mock-up can be realized using a downscale version'; '   of the volumes (see "import" tab) and with a low frame rate.'};
            app.Both2Dand3DstacktifortifffilesaresupportedLabel_2 = uilabel(app.GridLayout23);
            app.Both2Dand3DstacktifortifffilesaresupportedLabel_2.Text = {'In the "2D slice visualization" tab, you can:'; '   - Display a single volume, and look at orthogonal slices simultaneously.'; '   - Display multiple volumes simulateneously, each with their own colormap, and not necessarily sharing dimension and data type.'; '   - Compare grey level and segmented volumes with different comparison modes (e.g., overlay). Multiple segmented volumes can be compared for the same grey level image.'; '   It is then possible to create a video from these 2D representations (e.g., slicing through an axis).'};
            app.SeeexamplesinthegallerytabButton = uibutton(app.GridLayout23, 'push');
            app.SeeexamplesinthegallerytabButton.ButtonPushedFcn = createCallbackFcn(app, @SeeexamplesinthegallerytabButtonPushed, true);
            app.SeeexamplesinthegallerytabButton.Text = 'See examples in the gallery tab';
            app.VideoexplorerLabel = uilabel(app.GridLayout23);
            app.VideoexplorerLabel.Text = 'Video explorer';
            app.YoucancroptrimchangetheframepersecondorthelengthofavideoYoucanalsoconcatenatetwovideosinoneThisisparticularlyusefulascreatingavideowithalotofsequencescanbecumbersomesoyoucaninsteadcreatingseveralshortervideosandthenassemblethemtogetherLabel = uilabel(app.GridLayout23);
            app.YoucancroptrimchangetheframepersecondorthelengthofavideoYoucanalsoconcatenatetwovideosinoneThisisparticularlyusefulascreatingavideowithalotofsequencescanbecumbersomesoyoucaninsteadcreatingseveralshortervideosandthenassemblethemtogetherLabel.Text = {'You can crop, trim, change the frame per second or the length of a video. You can also concatenate two videos in one.'; 'This is particularly useful as creating a video with a lot of sequences can be cumbersome, so you can instead creating several shorter videos and then assemble them together.'};
            app.ViewgreylevelorsegmentedimagesTab = uitab(app.TabGroup);
            app.ViewgreylevelorsegmentedimagesTab.Title = 'View grey level or segmented images';
            app.GridLayout5 = uigridlayout(app.ViewgreylevelorsegmentedimagesTab);
            app.TabGroup3 = uitabgroup(app.GridLayout5);
            app.ImportTab_2 = uitab(app.TabGroup3);
            app.ImportTab_2.Title = 'Import';
            app.GridLayout2_3 = uigridlayout(app.ImportTab_2);
            app.ImportButton_3 = uibutton(app.GridLayout2_3, 'push');
            app.ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_3Pushed, true);
            app.ImportButton_3.Text = 'Import';
            app.Import_UITable = uitable(app.GridLayout2_3);
            app.Import_UITable.ColumnName = {'File'; 'Range'; '#Unique'; 'Pore'; 'Background'; 'Size'; 'Voxel size'; 'Grey level'; 'Semantic'; 'Instance'; 'Select'};
            app.Import_UITable.RowName = {};
            app.Import_UITable.ColumnEditable = [false false false true true false true true true true true];
            app.Import_UITable.CellEditCallback = createCallbackFcn(app, @Import_UITableCellEdit, true);
            app.Import_UITable.Tooltip = {''};
            app.ModifyinstanceimagesPanel = uipanel(app.GridLayout2_3);
            app.ModifyinstanceimagesPanel.Enable = 'off';
            app.ModifyinstanceimagesPanel.Title = 'Modify instance image(s)';
            app.GridLayout6 = uigridlayout(app.ModifyinstanceimagesPanel);
            app.RandomizelabelsButton_3 = uibutton(app.GridLayout6, 'push');
            app.RandomizelabelsButton_3.ButtonPushedFcn = createCallbackFcn(app, @RandomizelabelsButton_3Pushed, true);
            app.RandomizelabelsButton_3.Tooltip = {'Sometimes some instances close from each other or in contact have colors very similar, not different enough to be distinguishable. Pore and Background labels are not modified.'};
            app.RandomizelabelsButton_3.Text = 'Randomize labels';
            app.RemovetruncatedinstancesButton_3 = uibutton(app.GridLayout6, 'push');
            app.RemovetruncatedinstancesButton_3.ButtonPushedFcn = createCallbackFcn(app, @RemovetruncatedinstancesButton_3Pushed, true);
            app.RemovetruncatedinstancesButton_3.Tooltip = {'Remove instances truncated by the domain''s field of view. If background label is not empty, instances in contact with the background are also removed and assinged to the pore label.'};
            app.RemovetruncatedinstancesButton_3.Text = 'Remove truncated instances';
            app.Label = uilabel(app.GridLayout2_3);
            app.Label.Text = 'Import files and indicate what is their format type (grey level, semantic segmentation, or instance segmentation)';
            app.Label_2 = uilabel(app.GridLayout2_3);
            app.Label_2.Text = 'The selected files can be visualized in the next tabs. You can apply temporary modifications on the selected files (changes will not be saved on the disk).';
            app.VoxelsizeunitEditFieldLabel = uilabel(app.GridLayout2_3);
            app.VoxelsizeunitEditFieldLabel.Text = 'Voxel size unit';
            app.VoxelsizeunitEditField = uieditfield(app.GridLayout2_3, 'text');
            app.VoxelsizeunitEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizeunitEditFieldValueChanged, true);
            app.VoxelsizeunitEditField.Value = 'nm';
            app.ResetallchangesforthisfileButton = uibutton(app.GridLayout2_3, 'push');
            app.ResetallchangesforthisfileButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallchangesforthisfileButtonPushed, true);
            app.ResetallchangesforthisfileButton.Enable = 'off';
            app.ResetallchangesforthisfileButton.Text = 'Reset all changes for this file';
            app.RescaletovoxelsizeEditFieldLabel = uilabel(app.GridLayout2_3);
            app.RescaletovoxelsizeEditFieldLabel.Text = 'Rescale to voxel size';
            app.RescaletovoxelsizeEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.RescaletovoxelsizeEditField.Limits = [1e-09 Inf];
            app.RescaletovoxelsizeEditField.Value = 100;
            app.RescaleallselectedfilesButton = uibutton(app.GridLayout2_3, 'push');
            app.RescaleallselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleallselectedfilesButtonPushed, true);
            app.RescaleallselectedfilesButton.Enable = 'off';
            app.RescaleallselectedfilesButton.Text = 'Rescale all selected files';
            app.ModifyimagesPanel = uipanel(app.GridLayout2_3);
            app.ModifyimagesPanel.Enable = 'off';
            app.ModifyimagesPanel.Title = 'Modify image(s)';
            app.GridLayout7 = uigridlayout(app.ModifyimagesPanel);
            app.AssignlabelEditFieldLabel = uilabel(app.GridLayout7);
            app.AssignlabelEditFieldLabel.Text = 'Assign label';
            app.Semantic_AssignlabelEditField = uieditfield(app.GridLayout7, 'numeric');
            app.Semantic_AssignlabelEditField.RoundFractionalValues = 'on';
            app.tolabelEditFieldLabel = uilabel(app.GridLayout7);
            app.tolabelEditFieldLabel.Text = 'to label';
            app.Semantic_tolabelEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ReassignButton_semantic = uibutton(app.GridLayout7, 'push');
            app.ReassignButton_semantic.ButtonPushedFcn = createCallbackFcn(app, @ReassignButton_semanticPushed, true);
            app.ReassignButton_semantic.Text = 'Re-assign';
            app.RemovevolumeswithonlyporeorbackgroundautocropButton = uibutton(app.GridLayout7, 'push');
            app.RemovevolumeswithonlyporeorbackgroundautocropButton.ButtonPushedFcn = createCallbackFcn(app, @RemovevolumeswithonlyporeorbackgroundautocropButtonPushed, true);
            app.RemovevolumeswithonlyporeorbackgroundautocropButton.Text = 'Remove volumes with only pore or background (auto crop)';
            app.FlipSwap_Axis_DropDown = uidropdown(app.GridLayout7);
            app.FlipSwap_Axis_DropDown.Items = {'Flip axis 1', 'Flip axis 2', 'Flip axis 3', 'Swap axis 1 with axis 2', 'Swap axis 1 with axis 3', 'Swap axis 2 with axis 3'};
            app.FlipSwap_Axis_DropDown.Value = 'Flip axis 1';
            app.FliporswapaxisButton = uibutton(app.GridLayout7, 'push');
            app.FliporswapaxisButton.ButtonPushedFcn = createCallbackFcn(app, @FliporswapaxisButtonPushed, true);
            app.FliporswapaxisButton.Text = 'Flip or swap axis';
            app.RemovegapsinlabelnumberingButton = uibutton(app.GridLayout7, 'push');
            app.RemovegapsinlabelnumberingButton.ButtonPushedFcn = createCallbackFcn(app, @RemovegapsinlabelnumberingButtonPushed, true);
            app.RemovegapsinlabelnumberingButton.Tooltip = {'So that 0,1,7, 15 becomes 0,1,2,3'};
            app.RemovegapsinlabelnumberingButton.Text = 'Remove gaps in label numbering';
            app.ModifygreylevelimagesPanel = uipanel(app.GridLayout2_3);
            app.ModifygreylevelimagesPanel.Enable = 'off';
            app.ModifygreylevelimagesPanel.Title = 'Modify grey level image(s)';
            app.GridLayout8 = uigridlayout(app.ModifygreylevelimagesPanel);
            app.SaturationdarkestEditFieldLabel = uilabel(app.GridLayout8);
            app.SaturationdarkestEditFieldLabel.Text = 'Saturation (% darkest)';
            app.SaturationdarkestEditField = uieditfield(app.GridLayout8, 'numeric');
            app.SaturationdarkestEditField.Limits = [0 100];
            app.SaturationdarkestEditField.Value = 1;
            app.SaturationbrightestEditFieldLabel = uilabel(app.GridLayout8);
            app.SaturationbrightestEditFieldLabel.Text = 'Saturation (% brightest)';
            app.SaturationbrightestEditField = uieditfield(app.GridLayout8, 'numeric');
            app.SaturationbrightestEditField.Limits = [0 100];
            app.SaturationbrightestEditField.Value = 1;
            app.ContrastsaturationButton = uibutton(app.GridLayout8, 'push');
            app.ContrastsaturationButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastsaturationButtonPushed, true);
            app.ContrastsaturationButton.Text = 'Contrast saturation';
            app.LinearrescalingwithvaluesEditFieldLabel = uilabel(app.GridLayout8);
            app.LinearrescalingwithvaluesEditFieldLabel.Text = 'Linear rescaling with <> values';
            app.LinearrescalingwithvaluesEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LinearrescalingwithvaluesEditField.Limits = [2 Inf];
            app.LinearrescalingwithvaluesEditField.RoundFractionalValues = 'on';
            app.LinearrescalingwithvaluesEditField.Value = 1000;
            app.RescaleButton = uibutton(app.GridLayout8, 'push');
            app.RescaleButton.ButtonPushedFcn = createCallbackFcn(app, @RescaleButtonPushed, true);
            app.RescaleButton.Text = 'Rescale';
            app.PlothistogramButton = uibutton(app.GridLayout8, 'push');
            app.PlothistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlothistogramButtonPushed, true);
            app.PlothistogramButton.Text = 'Plot histogram';
            app.ResetallchangesforallselectedfilesButton = uibutton(app.GridLayout2_3, 'push');
            app.ResetallchangesforallselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallchangesforallselectedfilesButtonPushed, true);
            app.ResetallchangesforallselectedfilesButton.Enable = 'off';
            app.ResetallchangesforallselectedfilesButton.Text = 'Reset all changes for all selected files';
            app.ResetButton = uibutton(app.GridLayout2_3, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ModifyROIPanel = uipanel(app.GridLayout2_3);
            app.ModifyROIPanel.Enable = 'off';
            app.ModifyROIPanel.Title = 'Modify region of interest (must select only one file)';
            app.GridLayout9 = uigridlayout(app.ModifyROIPanel);
            app.Cropaxe1fromEditFieldLabel = uilabel(app.GridLayout9);
            app.Cropaxe1fromEditFieldLabel.Text = 'Crop axe 1 from';
            app.Cropaxe1fromEditField = uieditfield(app.GridLayout9, 'numeric');
            app.Cropaxe1fromEditField.Limits = [1 Inf];
            app.Cropaxe1fromEditField.RoundFractionalValues = 'on';
            app.Cropaxe1fromEditField.ValueChangedFcn = createCallbackFcn(app, @Cropaxe1fromEditFieldValueChanged, true);
            app.Cropaxe1fromEditField.Value = 1;
            app.toEditFieldLabel = uilabel(app.GridLayout9);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.GridLayout9, 'numeric');
            app.toEditField.Limits = [2 Inf];
            app.toEditField.RoundFractionalValues = 'on';
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = 2;
            app.CropButton = uibutton(app.GridLayout9, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.Cropaxe2fromEditFieldLabel = uilabel(app.GridLayout9);
            app.Cropaxe2fromEditFieldLabel.Text = 'Crop axe 2 from';
            app.Cropaxe2fromEditField = uieditfield(app.GridLayout9, 'numeric');
            app.Cropaxe2fromEditField.Limits = [1 Inf];
            app.Cropaxe2fromEditField.RoundFractionalValues = 'on';
            app.Cropaxe2fromEditField.ValueChangedFcn = createCallbackFcn(app, @Cropaxe2fromEditFieldValueChanged, true);
            app.Cropaxe2fromEditField.Value = 1;
            app.toEditField_2Label = uilabel(app.GridLayout9);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.toEditField_2.Limits = [2 Inf];
            app.toEditField_2.RoundFractionalValues = 'on';
            app.toEditField_2.ValueChangedFcn = createCallbackFcn(app, @toEditField_2ValueChanged, true);
            app.toEditField_2.Value = 2;
            app.Cropaxe3fromEditFieldLabel = uilabel(app.GridLayout9);
            app.Cropaxe3fromEditFieldLabel.Text = 'Crop axe 3 from';
            app.Cropaxe3fromEditField = uieditfield(app.GridLayout9, 'numeric');
            app.Cropaxe3fromEditField.Limits = [1 Inf];
            app.Cropaxe3fromEditField.RoundFractionalValues = 'on';
            app.Cropaxe3fromEditField.ValueChangedFcn = createCallbackFcn(app, @Cropaxe3fromEditFieldValueChanged, true);
            app.Cropaxe3fromEditField.Value = 1;
            app.toEditField_3Label = uilabel(app.GridLayout9);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.toEditField_3.Limits = [2 Inf];
            app.toEditField_3.RoundFractionalValues = 'on';
            app.toEditField_3.ValueChangedFcn = createCallbackFcn(app, @toEditField_3ValueChanged, true);
            app.toEditField_3.Value = 2;
            app.IdleLabel = uilabel(app.GridLayout2_3);
            app.IdleLabel.Text = 'Idle';
            app.DslicevisualizationTab = uitab(app.TabGroup3);
            app.DslicevisualizationTab.Title = '2D slice visualization';
            app.GridLayout_2Dslicevisualization = uigridlayout(app.DslicevisualizationTab);
            app.SelectfilestovisualizeLabel = uilabel(app.GridLayout_2Dslicevisualization);
            app.SelectfilestovisualizeLabel.Text = 'Select file(s) to visualize. Files must share same size if used to check segmentation';
            app.TwoD_UITable = uitable(app.GridLayout_2Dslicevisualization);
            app.TwoD_UITable.ColumnName = {'File'; 'Type'; 'Size'; 'Select'};
            app.TwoD_UITable.RowName = {};
            app.TwoD_UITable.ColumnEditable = [false false false true];
            app.TwoD_UITable.CellEditCallback = createCallbackFcn(app, @TwoD_UITableCellEdit, true);
            app.SegmentationcomparisonCheckBox = uicheckbox(app.GridLayout_2Dslicevisualization);
            app.SegmentationcomparisonCheckBox.ValueChangedFcn = createCallbackFcn(app, @SegmentationcomparisonCheckBoxValueChanged, true);
            app.SegmentationcomparisonCheckBox.Text = 'Segmentation comparison';
            app.TwoDview_Button = uibutton(app.GridLayout_2Dslicevisualization, 'push');
            app.TwoDview_Button.ButtonPushedFcn = createCallbackFcn(app, @TwoDview_ButtonPushed, true);
            app.TwoDview_Button.Text = '2D view';
            app.SegComp_HowtoLabel = uilabel(app.GridLayout_2Dslicevisualization);
            app.SegComp_HowtoLabel.Text = {'- Files must share same size and same voxel size'; '- Only one file can be marked as ground truth, and it must not be a grey level file'; '- 2 modes:'; '  i) no ground truth image has been selected   : you must select one grey level image and at least one non-grey level image.'; '  ii) one ground truth image has been selected: you must not select any grey level image, and you must select at least two non-grey level images.'};
            app.DvolumevisualizationTab = uitab(app.TabGroup3);
            app.DvolumevisualizationTab.Title = '3D volume visualization';
            app.GridLayout_3Dmain = uigridlayout(app.DvolumevisualizationTab);
            app.TabGroup4 = uitabgroup(app.GridLayout_3Dmain);
            app.ColormapTab = uitab(app.TabGroup4);
            app.ColormapTab.Title = 'Colormap';
            app.GridLayout11 = uigridlayout(app.ColormapTab);
            app.GreylevelPanel = uipanel(app.GridLayout11);
            app.GreylevelPanel.Title = 'Grey level';
            app.GridLayout12 = uigridlayout(app.GreylevelPanel);
            app.ColormapDropDownLabel = uilabel(app.GridLayout12);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.GridLayout12);
            app.ColormapDropDown.Items = {'gray', 'bone', 'copper'};
            app.ColormapDropDown.Value = 'gray';
            app.BackgroundlabelifanyistransparentLabel = uilabel(app.GridLayout12);
            app.BackgroundlabelifanyistransparentLabel.Text = 'Background label (if any) is transparent';
            app.OpacityprofileDropDownLabel = uilabel(app.GridLayout12);
            app.OpacityprofileDropDownLabel.Text = 'Opacity profile';
            app.OpacityprofileDropDown = uidropdown(app.GridLayout12);
            app.OpacityprofileDropDown.Items = {'linear', 'quadratic', 'cubic'};
            app.OpacityprofileDropDown.Value = 'cubic';
            app.SemanticPanel = uipanel(app.GridLayout11);
            app.SemanticPanel.Title = 'Semantic';
            app.GridLayout14 = uigridlayout(app.SemanticPanel);
            app.SemanticColor_UITable = uitable(app.GridLayout14);
            app.SemanticColor_UITable.ColumnName = {'Label'; 'Volume fraction'; 'Color'; 'Opacity'};
            app.SemanticColor_UITable.RowName = {};
            app.SemanticColor_UITable.ColumnEditable = [false false false true];
            app.SemanticColor_UITable.CellSelectionCallback = createCallbackFcn(app, @SemanticColor_UITableCellSelection, true);
            app.UpdateSemanticColormapButton = uibutton(app.GridLayout14, 'push');
            app.UpdateSemanticColormapButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSemanticColormapButtonPushed, true);
            app.UpdateSemanticColormapButton.Text = 'Update';
            app.CummulativeopacityCheckBox = uicheckbox(app.GridLayout14);
            app.CummulativeopacityCheckBox.Tooltip = {'True: more opaque deeper into the volume. Use very low opacity value.'; 'False: opacity is similar everywhere in the label (as in volume viewer app)'};
            app.CummulativeopacityCheckBox.Text = 'Cummulative opacity';
            app.InstancePanel = uipanel(app.GridLayout11);
            app.InstancePanel.Title = 'Instance';
            app.GridLayout15 = uigridlayout(app.InstancePanel);
            app.ColormapDropDown_2Label = uilabel(app.GridLayout15);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.ColormapDropDown_2 = uidropdown(app.GridLayout15);
            app.ColormapDropDown_2.Items = {'turbo', 'jet', 'parula', 'random'};
            app.ColormapDropDown_2.Value = 'turbo';
            app.PorelabelistransparentBackgroundlabelifanyistransparentLabel = uilabel(app.GridLayout15);
            app.PorelabelistransparentBackgroundlabelifanyistransparentLabel.Text = {'Pore label is transparent. '; 'Background label (if any) is transparent'};
            app.PositionTab = uitab(app.TabGroup4);
            app.PositionTab.Title = 'Position';
            app.GridLayout_3Dvisualization_2 = uigridlayout(app.PositionTab);
            app.xaxisEditField_4Label_2 = uilabel(app.GridLayout_3Dvisualization_2);
            app.xaxisEditField_4Label_2.Text = 'x-axis';
            app.ThreeD_Xaxis_distance_EditField = uieditfield(app.GridLayout_3Dvisualization_2, 'numeric');
            app.ThreeD_Xaxis_distance_EditField.Limits = [1 Inf];
            app.ThreeD_Xaxis_distance_EditField.RoundFractionalValues = 'on';
            app.ThreeD_Xaxis_distance_EditField.Value = 50;
            app.DistanceinpixelbewteenvolumesLabel_5 = uilabel(app.GridLayout_3Dvisualization_2);
            app.DistanceinpixelbewteenvolumesLabel_5.Text = 'Distance (in pixel) bewteen volumes';
            app.yaxisEditField_4Label_2 = uilabel(app.GridLayout_3Dvisualization_2);
            app.yaxisEditField_4Label_2.Text = 'y-axis';
            app.ThreeD_Yaxis_distance_EditField = uieditfield(app.GridLayout_3Dvisualization_2, 'numeric');
            app.ThreeD_Yaxis_distance_EditField.Limits = [1 Inf];
            app.ThreeD_Yaxis_distance_EditField.RoundFractionalValues = 'on';
            app.ThreeD_Yaxis_distance_EditField.Value = 50;
            app.zaxisEditField_4Label_2 = uilabel(app.GridLayout_3Dvisualization_2);
            app.zaxisEditField_4Label_2.Text = 'z-axis';
            app.ThreeD_Zaxis_distance_EditField = uieditfield(app.GridLayout_3Dvisualization_2, 'numeric');
            app.ThreeD_Zaxis_distance_EditField.Limits = [1 Inf];
            app.ThreeD_Zaxis_distance_EditField.RoundFractionalValues = 'on';
            app.ThreeD_Zaxis_distance_EditField.Value = 50;
            app.ThreeD_position_UITable = uitable(app.GridLayout_3Dvisualization_2);
            app.ThreeD_position_UITable.ColumnName = {'File'; 'X-pos'; 'Y-pos'; 'Z-pos'};
            app.ThreeD_position_UITable.RowName = {};
            app.ThreeD_position_UITable.ColumnEditable = [false true true true];
            app.ThreeD_position_UITable.CellEditCallback = createCallbackFcn(app, @ThreeD_position_UITableCellEdit, true);
            app.XposalignmentDropDown_2Label = uilabel(app.GridLayout_3Dvisualization_2);
            app.XposalignmentDropDown_2Label.Text = 'X-pos alignment';
            app.XposalignmentDropDown = uidropdown(app.GridLayout_3Dvisualization_2);
            app.XposalignmentDropDown.Items = {'Bottom', 'Middle', 'Top'};
            app.XposalignmentDropDown.Value = 'Bottom';
            app.YposalignmentDropDown_2Label = uilabel(app.GridLayout_3Dvisualization_2);
            app.YposalignmentDropDown_2Label.Text = 'Y-pos alignment';
            app.YposalignmentDropDown = uidropdown(app.GridLayout_3Dvisualization_2);
            app.YposalignmentDropDown.Items = {'Bottom', 'Middle', 'Top'};
            app.YposalignmentDropDown.Value = 'Bottom';
            app.ZposalignmentDropDown_2Label = uilabel(app.GridLayout_3Dvisualization_2);
            app.ZposalignmentDropDown_2Label.Text = 'Z-pos alignment';
            app.ZposalignmentDropDown = uidropdown(app.GridLayout_3Dvisualization_2);
            app.ZposalignmentDropDown.Items = {'Bottom', 'Middle', 'Top'};
            app.ZposalignmentDropDown.Value = 'Bottom';
            app.IfyouhaveselectedmultiplesfilestheywillbedisplayedtogetherLabel = uilabel(app.GridLayout_3Dvisualization_2);
            app.IfyouhaveselectedmultiplesfilestheywillbedisplayedtogetherLabel.Text = 'If you have selected multiples files, they will be displayed together. You can order them here.';
            app.Label_4 = uilabel(app.GridLayout_3Dvisualization_2);
            app.Label_4.Text = 'A volume with X-pos=2 will be placed after the volumes with X-pos=1 along the axe x.';
            app.Label_5 = uilabel(app.GridLayout_3Dvisualization_2);
            app.Label_5.Text = 'Volumes can have different dimensions. You can choose their alignement here.';
            app.Label_7 = uilabel(app.GridLayout_3Dvisualization_2);
            app.Label_7.Text = 'If you apply rotations (cf. video sequence), consider a spacing high enough to avoid volumes overlapping';
            app.BackgroundaxesandlightingTab = uitab(app.TabGroup4);
            app.BackgroundaxesandlightingTab.Title = 'Background, axes, and lighting';
            app.GridLayout16 = uigridlayout(app.BackgroundaxesandlightingTab);
            app.BackgroundcolorColorPickerLabel = uilabel(app.GridLayout16);
            app.BackgroundcolorColorPickerLabel.Text = 'Background color';
            app.BackgroundcolorColorPicker = uicolorpicker(app.GridLayout16);
            app.BackgroundcolorColorPicker.Value = [1 1 1];
            app.BackgroundcoloroptionsLabel = uilabel(app.GridLayout16);
            app.BackgroundcoloroptionsLabel.Text = 'Background color options';
            app.GradientcolorColorPickerLabel = uilabel(app.GridLayout16);
            app.GradientcolorColorPickerLabel.Text = 'Gradient color';
            app.GradientcolorColorPicker = uicolorpicker(app.GridLayout16);
            app.GradientcolorColorPicker.Value = [0 0.4471 0.7412];
            app.BackgroundGradientCheckBox = uicheckbox(app.GridLayout16);
            app.BackgroundGradientCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundGradientCheckBoxValueChanged, true);
            app.BackgroundGradientCheckBox.Text = 'Gradient';
            app.ShowboxCheckBox = uicheckbox(app.GridLayout16);
            app.ShowboxCheckBox.Text = 'Show box';
            app.ScalebarCheckBox = uicheckbox(app.GridLayout16);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Text = 'Show scale bar (all selected volumes must share the same voxel size)';
            app.ShoworientationaxesCheckBox = uicheckbox(app.GridLayout16);
            app.ShoworientationaxesCheckBox.Text = 'Show orientation axes';
            app.FiguresizeEditFieldLabel = uilabel(app.GridLayout16);
            app.FiguresizeEditFieldLabel.Text = 'Figure size';
            app.FiguresizeEditField = uieditfield(app.GridLayout16, 'numeric');
            app.FiguresizeEditField.Limits = [0.1 1];
            app.FiguresizeEditField.Value = 1;
            app.RatioofscreensizeLabel = uilabel(app.GridLayout16);
            app.RatioofscreensizeLabel.Text = 'Ratio of screen size';
            app.Scalebar_location_DropDown = uidropdown(app.GridLayout16);
            app.Scalebar_location_DropDown.Items = {'Along axe x', 'Along axe y', 'Along axe z'};
            app.Scalebar_location_DropDown.ValueChangedFcn = createCallbackFcn(app, @Scalebar_location_DropDownValueChanged, true);
            app.Scalebar_location_DropDown.Value = 'Along axe x';
            app.LocatedDropDownLabel = uilabel(app.GridLayout16);
            app.LocatedDropDownLabel.Text = 'Located';
            app.ScaleBar_X_LocatedDropDown = uidropdown(app.GridLayout16);
            app.ScaleBar_X_LocatedDropDown.Items = {'beginning of axe x', 'middle of axe x', 'end of axe x'};
            app.ScaleBar_X_LocatedDropDown.Value = 'beginning of axe x';
            app.ScaleBar_Y_LocatedDropDown = uidropdown(app.GridLayout16);
            app.ScaleBar_Y_LocatedDropDown.Items = {'beginning of axe y', 'middle of axe y', 'end of axe y'};
            app.ScaleBar_Y_LocatedDropDown.Value = 'end of axe y';
            app.ScaleBar_Z_LocatedDropDown = uidropdown(app.GridLayout16);
            app.ScaleBar_Z_LocatedDropDown.Items = {'beginning of axe z', 'middle of axe z', 'end of axe z'};
            app.ScaleBar_Z_LocatedDropDown.Value = 'beginning of axe z';
            app.LengthLabel = uilabel(app.GridLayout16);
            app.LengthLabel.Text = 'Length';
            app.Scalebar_lengthEditField = uieditfield(app.GridLayout16, 'numeric');
            app.Scalebar_lengthEditField.Limits = [1e-09 Inf];
            app.Scalebar_lengthEditField.Value = 0.1;
            app.LengthexpressedinDropDownLabel = uilabel(app.GridLayout16);
            app.LengthexpressedinDropDownLabel.Text = 'Length expressed in';
            app.ScaleLengthexpressedinDropDown = uidropdown(app.GridLayout16);
            app.ScaleLengthexpressedinDropDown.Items = {'in ratio of the aligned axe length', 'Option 2'};
            app.ScaleLengthexpressedinDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleLengthexpressedinDropDownValueChanged, true);
            app.ScaleLengthexpressedinDropDown.Value = 'in ratio of the aligned axe length';
            app.DistancefromplanexyEditFieldLabel = uilabel(app.GridLayout16);
            app.DistancefromplanexyEditFieldLabel.Text = 'Distance from plane xy';
            app.DistancefromplanexyEditField = uieditfield(app.GridLayout16, 'numeric');
            app.DistancefromplanexyEditField.Limits = [0 1];
            app.DistancefromplanexzEditFieldLabel = uilabel(app.GridLayout16);
            app.DistancefromplanexzEditFieldLabel.Text = 'Distance from plane xz';
            app.DistancefromplanexzEditField = uieditfield(app.GridLayout16, 'numeric');
            app.DistancefromplanexzEditField.Limits = [0 1];
            app.DistancefromplanexzEditField.Value = 0.1;
            app.DistancefromplaneyzEditFieldLabel = uilabel(app.GridLayout16);
            app.DistancefromplaneyzEditFieldLabel.Text = 'Distance from plane yz';
            app.DistancefromplaneyzEditField = uieditfield(app.GridLayout16, 'numeric');
            app.DistancefromplaneyzEditField.Limits = [0 1];
            app.DistancefromplaneyzEditField.Value = 0.1;
            app.BoxaxesandscalebaroptionsLabel = uilabel(app.GridLayout16);
            app.BoxaxesandscalebaroptionsLabel.Text = 'Box, axes and scale bar options';
            app.LightingLabel = uilabel(app.GridLayout16);
            app.LightingLabel.Text = 'Lighting';
            app.AmbientlightEditFieldLabel = uilabel(app.GridLayout16);
            app.AmbientlightEditFieldLabel.Text = 'Ambient light';
            app.AmbientlightEditField = uieditfield(app.GridLayout16, 'numeric');
            app.AmbientlightEditField.Limits = [0 1];
            app.AmbientlightEditField.Value = 0.4;
            app.LightsourceintensityEditFieldLabel = uilabel(app.GridLayout16);
            app.LightsourceintensityEditFieldLabel.Text = 'Light source intensity';
            app.LightsourceintensityEditField = uieditfield(app.GridLayout16, 'numeric');
            app.LightsourceintensityEditField.Limits = [0 1];
            app.LightsourceintensityEditField.Value = 0.6;
            app.LightcolorColorPickerLabel = uilabel(app.GridLayout16);
            app.LightcolorColorPickerLabel.Text = 'Light color';
            app.LightcolorColorPicker = uicolorpicker(app.GridLayout16);
            app.LightcolorColorPicker.Value = [1 1 1];
            app.AzimuthEditField_2Label = uilabel(app.GridLayout16);
            app.AzimuthEditField_2Label.Text = 'Azimuth ';
            app.Light_AzimuthEditField = uieditfield(app.GridLayout16, 'numeric');
            app.Light_AzimuthEditField.Limits = [-180 180];
            app.Light_AzimuthEditField.Value = 45;
            app.ElevationEditField_2Label = uilabel(app.GridLayout16);
            app.ElevationEditField_2Label.Text = 'Elevation';
            app.Light_ElevationEditField = uieditfield(app.GridLayout16, 'numeric');
            app.Light_ElevationEditField.Limits = [-90 90];
            app.Light_ElevationEditField.Value = 25;
            app.AngleexpressedindegreesLabel_2 = uilabel(app.GridLayout16);
            app.AngleexpressedindegreesLabel_2.Text = 'Angle expressed in degrees';
            app.CoordinateexpressedinratioofthedomainsizeLabel_3 = uilabel(app.GridLayout16);
            app.CoordinateexpressedinratioofthedomainsizeLabel_3.Text = {'Coordinates expressed in ratio of the domain size.'; 'Center is [0,0,0] (volume center is [0.5 0.5 0.5]).'};
            app.CoordinatealongaxexEditField_2Label_2 = uilabel(app.GridLayout16);
            app.CoordinatealongaxexEditField_2Label_2.Text = 'Coordinate along axe x';
            app.Light_Coordinatealongaxex_Pos_EditField = uieditfield(app.GridLayout16, 'numeric');
            app.Light_Coordinatealongaxex_Pos_EditField.Value = 1;
            app.CoordinatealongaxeyLabel_2 = uilabel(app.GridLayout16);
            app.CoordinatealongaxeyLabel_2.Text = 'Coordinate along axe y';
            app.Light_Coordinatealongaxey_Pos_EditField = uieditfield(app.GridLayout16, 'numeric');
            app.Light_Coordinatealongaxey_Pos_EditField.Value = 1;
            app.CoordinatealongaxezLabel_2 = uilabel(app.GridLayout16);
            app.CoordinatealongaxezLabel_2.Text = 'Coordinate along axe z';
            app.Light_Coordinatealongaxez_Pos_EditField = uieditfield(app.GridLayout16, 'numeric');
            app.Light_Coordinatealongaxez_Pos_EditField.Value = 1;
            app.LightsourcelocationDropDownLabel = uilabel(app.GridLayout16);
            app.LightsourcelocationDropDownLabel.Text = 'Light source location';
            app.LightsourcelocationDropDown = uidropdown(app.GridLayout16);
            app.LightsourcelocationDropDown.Items = {'auto', 'manual (cartesian)', 'manual (spherical)'};
            app.LightsourcelocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LightsourcelocationDropDownValueChanged, true);
            app.LightsourcelocationDropDown.Value = 'auto';
            app.CamerapositionTab = uitab(app.TabGroup4);
            app.CamerapositionTab.Title = 'Camera position';
            app.GridLayout17 = uigridlayout(app.CamerapositionTab);
            app.CameratargetLabel = uilabel(app.GridLayout17);
            app.CameratargetLabel.Text = 'Camera target';
            app.Label_6 = uilabel(app.GridLayout17);
            app.Label_6.Text = 'This is mostly useful to setup the camera for the first frame of a video, as otherwise user can simply modify camera interactively';
            app.CoordinatealongaxexEditFieldLabel = uilabel(app.GridLayout17);
            app.CoordinatealongaxexEditFieldLabel.Text = 'Coordinate along axe x';
            app.CoordinatealongaxexEditField = uieditfield(app.GridLayout17, 'numeric');
            app.CoordinatealongaxexEditField.Value = 0.5;
            app.CoordinatealongaxeyEditFieldLabel = uilabel(app.GridLayout17);
            app.CoordinatealongaxeyEditFieldLabel.Text = 'Coordinate along axe y';
            app.CoordinatealongaxeyEditField = uieditfield(app.GridLayout17, 'numeric');
            app.CoordinatealongaxeyEditField.Value = 0.5;
            app.CoordinatealongaxezEditFieldLabel = uilabel(app.GridLayout17);
            app.CoordinatealongaxezEditFieldLabel.Text = 'Coordinate along axe z';
            app.CoordinatealongaxezEditField = uieditfield(app.GridLayout17, 'numeric');
            app.CoordinatealongaxezEditField.Value = 0.5;
            app.CoordinateexpressedinratioofthedomainsizeLabel = uilabel(app.GridLayout17);
            app.CoordinateexpressedinratioofthedomainsizeLabel.Text = 'Coordinates expressed in ratio of the domain size, i.e. [0.5,0.5,0.5] is the volume center.';
            app.CamerapositionLabel = uilabel(app.GridLayout17);
            app.CamerapositionLabel.Text = 'Camera position';
            app.WherearewelookingatLabel = uilabel(app.GridLayout17);
            app.WherearewelookingatLabel.Text = 'Where are we looking at ?...';
            app.andfromwhereLabel = uilabel(app.GridLayout17);
            app.andfromwhereLabel.Text = '...and from where ?';
            app.SystemofcoordinatesDropDownLabel = uilabel(app.GridLayout17);
            app.SystemofcoordinatesDropDownLabel.Text = 'System of coordinates';
            app.SystemofcoordinatesDropDown = uidropdown(app.GridLayout17);
            app.SystemofcoordinatesDropDown.Items = {'spherical', 'cartesian '};
            app.SystemofcoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemofcoordinatesDropDownValueChanged, true);
            app.SystemofcoordinatesDropDown.Value = 'spherical';
            app.CoordinatealongaxexEditField_2Label = uilabel(app.GridLayout17);
            app.CoordinatealongaxexEditField_2Label.Text = 'Coordinate along axe x';
            app.Coordinatealongaxex_Pos_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.Coordinatealongaxex_Pos_EditField.Value = 1;
            app.CoordinatealongaxeyLabel = uilabel(app.GridLayout17);
            app.CoordinatealongaxeyLabel.Text = 'Coordinate along axe y';
            app.Coordinatealongaxey_Pos_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.Coordinatealongaxey_Pos_EditField.Value = 1;
            app.CoordinatealongaxezLabel = uilabel(app.GridLayout17);
            app.CoordinatealongaxezLabel.Text = 'Coordinate along axe z';
            app.Coordinatealongaxez_Pos_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.Coordinatealongaxez_Pos_EditField.Value = 1;
            app.CoordinateexpressedinratioofthedomainsizeLabel_2 = uilabel(app.GridLayout17);
            app.CoordinateexpressedinratioofthedomainsizeLabel_2.Text = 'Coordinates expressed in ratio of the domain size. Center is [0,0,0] (volume center is [0.5 0.5 0.5]).';
            app.AzimuthEditFieldLabel = uilabel(app.GridLayout17);
            app.AzimuthEditFieldLabel.Text = 'Azimuth ';
            app.AzimuthEditField = uieditfield(app.GridLayout17, 'numeric');
            app.AzimuthEditField.Limits = [-180 180];
            app.AzimuthEditField.Value = 45;
            app.ElevationEditFieldLabel = uilabel(app.GridLayout17);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ElevationEditField.Limits = [-90 90];
            app.ElevationEditField.Value = 25;
            app.AngleexpressedindegreesLabel = uilabel(app.GridLayout17);
            app.AngleexpressedindegreesLabel.Text = 'Angle expressed in degrees';
            app.DistancefromcameratargetEditFieldLabel = uilabel(app.GridLayout17);
            app.DistancefromcameratargetEditFieldLabel.Text = 'Distance from camera target';
            app.DistancefromcameratargetEditField = uieditfield(app.GridLayout17, 'numeric');
            app.DistancefromcameratargetEditField.Limits = [1e-09 Inf];
            app.DistancefromcameratargetEditField.Value = 1;
            app.DistanceexpressedinratioofthesquarerootoftheLabel = uilabel(app.GridLayout17);
            app.DistanceexpressedinratioofthesquarerootoftheLabel.Text = {'Distance expressed in ratio of sqrt(sz(1)^2 + sz(2)^2 + sz(3)^2)'; 'with sz the size of the domain'};
            app.CamerazoomEditFieldLabel = uilabel(app.GridLayout17);
            app.CamerazoomEditFieldLabel.Text = 'Camera zoom';
            app.CamerazoomEditField = uieditfield(app.GridLayout17, 'numeric');
            app.CamerazoomEditField.Limits = [1e-09 Inf];
            app.CamerazoomEditField.Value = 1;
            app.SphericalcoordinatescenteristhecameratargetLabel = uilabel(app.GridLayout17);
            app.SphericalcoordinatescenteristhecameratargetLabel.Text = 'Spherical coordinates center is the camera target';
            app.CameravectorLabel = uilabel(app.GridLayout17);
            app.CameravectorLabel.Text = 'Camera vector';
            app.CamVector_1_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.CamVector_2_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.CamVector_3_EditField = uieditfield(app.GridLayout17, 'numeric');
            app.CamVector_3_EditField.Value = 1;
            app.Hyperlink = uihyperlink(app.GridLayout17);
            app.Hyperlink.URL = 'https://www.mathworks.com/help/matlab/creating_plots/defining-scenes-with-camera-graphics.html';
            app.Hyperlink.Text = 'Camera terminology';
            app.Initial_3Dview_Button = uibutton(app.GridLayout17, 'push');
            app.Initial_3Dview_Button.ButtonPushedFcn = createCallbackFcn(app, @ThreeD_InitialviewButton2Pushed, true);
            app.Initial_3Dview_Button.Text = '3D view';
            app.Startvisible_UITable = uitable(app.GridLayout17);
            app.Startvisible_UITable.ColumnName = {'Volume'; 'Start visible'};
            app.Startvisible_UITable.RowName = {};
            app.Startvisible_UITable.ColumnEditable = [false true];
            app.Videosequence1Tab = uitab(app.TabGroup4);
            app.Videosequence1Tab.Title = 'Video sequence 1';
            app.GridLayout17_2 = uigridlayout(app.Videosequence1Tab);
            app.CameramovementsPanel = uipanel(app.GridLayout17_2);
            app.CameramovementsPanel.Title = 'Camera movements';
            app.GridLayout18 = uigridlayout(app.CameramovementsPanel);
            app.CamPos_new_x_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CamPos_new_y_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CamPos_new_z_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.AddtosequenceEditFieldLabel = uilabel(app.GridLayout18);
            app.AddtosequenceEditFieldLabel.Text = 'Add to sequence #';
            app.AddtosequenceEditField = uieditfield(app.GridLayout18, 'numeric');
            app.AddtosequenceEditField.Limits = [1 1];
            app.AddtosequenceEditField.RoundFractionalValues = 'on';
            app.AddtosequenceEditField.Value = 1;
            app.Sequence_Camera_AddButton = uibutton(app.GridLayout18, 'push');
            app.Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Sequence_Camera_AddButtonPushed, true);
            app.Sequence_Camera_AddButton.Text = 'Add';
            app.ZoomEditFieldLabel = uilabel(app.GridLayout18);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.NewZoomEditField = uieditfield(app.GridLayout18, 'numeric');
            app.NewZoomEditField.Limits = [1e-09 Inf];
            app.NewZoomEditField.Value = 1;
            app.ChangeisLabel = uilabel(app.GridLayout18);
            app.ChangeisLabel.Text = 'Change is';
            app.Zoom_ChangeDropDown = uidropdown(app.GridLayout18);
            app.Zoom_ChangeDropDown.Items = {'Progressive', 'Instantaneous'};
            app.Zoom_ChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @Zoom_ChangeDropDownValueChanged, true);
            app.Zoom_ChangeDropDown.Value = 'Progressive';
            app.ProfileDropDown_3Label = uilabel(app.GridLayout18);
            app.ProfileDropDown_3Label.Text = 'Profile';
            app.Zoom_ProfileDropDown = uidropdown(app.GridLayout18);
            app.Zoom_ProfileDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Zoom_ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Zoom_ProfileDropDownValueChanged, true);
            app.Zoom_ProfileDropDown.Value = 'Quadratic';
            app.SensDropDown_2Label = uilabel(app.GridLayout18);
            app.SensDropDown_2Label.Text = 'Sens';
            app.Zoom_SensDropDown = uidropdown(app.GridLayout18);
            app.Zoom_SensDropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.Zoom_SensDropDown.Value = 'Start slow';
            app.CamerapositionCheckBox = uicheckbox(app.GridLayout18);
            app.CamerapositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamerapositionCheckBoxValueChanged, true);
            app.CamerapositionCheckBox.Text = 'Camera position';
            app.CameratargetCheckBox = uicheckbox(app.GridLayout18);
            app.CameratargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameratargetCheckBoxValueChanged, true);
            app.CameratargetCheckBox.Text = 'Camera target';
            app.CamerazoomCheckBox = uicheckbox(app.GridLayout18);
            app.CamerazoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @CamerazoomCheckBoxValueChanged, true);
            app.CamerazoomCheckBox.Text = 'Camera zoom';
            app.xEditFieldLabel = uilabel(app.GridLayout18);
            app.xEditFieldLabel.Text = 'x';
            app.NewCamTar_x_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout18);
            app.yEditFieldLabel.Text = 'y';
            app.NewCamTar_y_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout18);
            app.zEditFieldLabel.Text = 'z';
            app.NewCamTar_z_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.xisDropDownLabel = uilabel(app.GridLayout18);
            app.xisDropDownLabel.Text = 'x is';
            app.NewCamTar_x_isDropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_x_isDropDown.Items = {'free', 'unchanged', 'Constant angle: t=p-(old p-old t)', 'linked to camera position x'};
            app.NewCamTar_x_isDropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamTar_x_isDropDownValueChanged, true);
            app.NewCamTar_x_isDropDown.Value = 'free';
            app.yisDropDownLabel = uilabel(app.GridLayout18);
            app.yisDropDownLabel.Text = 'y is';
            app.NewCamTar_y_isDropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_y_isDropDown.Items = {'free', 'unchanged', 'Constant angle: t=p-(old p-old t)', 'linked to camera position y'};
            app.NewCamTar_y_isDropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamTar_y_isDropDownValueChanged, true);
            app.NewCamTar_y_isDropDown.Value = 'free';
            app.zisDropDownLabel = uilabel(app.GridLayout18);
            app.zisDropDownLabel.Text = 'z is';
            app.NewCamTar_z_isDropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_z_isDropDown.Items = {'free', 'unchanged', 'Constant angle: t=p-(old p-old t)', 'linked to camera position z'};
            app.NewCamTar_z_isDropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamTar_z_isDropDownValueChanged, true);
            app.NewCamTar_z_isDropDown.Value = 'free';
            app.ChangeisDropDownLabel = uilabel(app.GridLayout18);
            app.ChangeisDropDownLabel.Text = 'Change is';
            app.NewCamTar_Coordinate_ChangeIs_DropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.NewCamTar_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamTar_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.NewCamTar_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label_2 = uilabel(app.GridLayout18);
            app.ProfileDropDown_2Label_2.Text = 'Profile';
            app.NewCamTar_ChangeProfile_DropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.NewCamTar_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamTar_ChangeProfile_DropDownValueChanged, true);
            app.NewCamTar_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel_2 = uilabel(app.GridLayout18);
            app.SensDropDownLabel_2.Text = 'Sens';
            app.NewCamTar_ChangeProfileSens_DropDown = uidropdown(app.GridLayout18);
            app.NewCamTar_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.NewCamTar_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.CamPos_new_azimuth_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CamPos_new_elevation_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CamPos_new_distance_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.xDropDownLabel = uilabel(app.GridLayout18);
            app.xDropDownLabel.Text = 'x';
            app.CamPos_x_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_x_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_x_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_x_choice_DropDownValueChanged, true);
            app.CamPos_x_choice_DropDown.Value = 'absolute';
            app.azimuthLabel = uilabel(app.GridLayout18);
            app.azimuthLabel.Text = 'azimuth';
            app.CamPos_azimuth_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_azimuth_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_azimuth_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_azimuth_choice_DropDownValueChanged, true);
            app.CamPos_azimuth_choice_DropDown.Value = 'absolute';
            app.azimuthLabel_2 = uilabel(app.GridLayout18);
            app.azimuthLabel_2.Text = 'elevation';
            app.CamPos_elevation_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_elevation_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_elevation_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_elevation_choice_DropDownValueChanged, true);
            app.CamPos_elevation_choice_DropDown.Value = 'absolute';
            app.azimuthLabel_3 = uilabel(app.GridLayout18);
            app.azimuthLabel_3.Text = 'distance';
            app.CamPos_distance_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_distance_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_distance_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_distance_choice_DropDownValueChanged, true);
            app.CamPos_distance_choice_DropDown.Value = 'absolute';
            app.yLabel = uilabel(app.GridLayout18);
            app.yLabel.Text = 'y';
            app.CamPos_y_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_y_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_y_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_y_choice_DropDownValueChanged, true);
            app.CamPos_y_choice_DropDown.Value = 'absolute';
            app.zLabel = uilabel(app.GridLayout18);
            app.zLabel.Text = 'z';
            app.CamPos_z_choice_DropDown = uidropdown(app.GridLayout18);
            app.CamPos_z_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.CamPos_z_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @CamPos_z_choice_DropDownValueChanged, true);
            app.CamPos_z_choice_DropDown.Value = 'absolute';
            app.CameraNewPosition_CoordinatesDropDown = uidropdown(app.GridLayout18);
            app.CameraNewPosition_CoordinatesDropDown.Items = {'spherical', 'cartesian', 'helical'};
            app.CameraNewPosition_CoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemofcoordinatesDropDown_2ValueChanged, true);
            app.CameraNewPosition_CoordinatesDropDown.Value = 'spherical';
            app.ChangeisLabel_2 = uilabel(app.GridLayout18);
            app.ChangeisLabel_2.Text = 'Change is';
            app.NewCamPos_Coordinate_ChangeIs_DropDown = uidropdown(app.GridLayout18);
            app.NewCamPos_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.NewCamPos_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamPos_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.NewCamPos_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label = uilabel(app.GridLayout18);
            app.ProfileDropDown_2Label.Text = 'Profile';
            app.NewCamPos_ChangeProfile_DropDown = uidropdown(app.GridLayout18);
            app.NewCamPos_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.NewCamPos_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewCamPos_ChangeProfile_DropDownValueChanged, true);
            app.NewCamPos_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel = uilabel(app.GridLayout18);
            app.SensDropDownLabel.Text = 'Sens';
            app.NewCamPos_ChangeProfileSens_DropDown = uidropdown(app.GridLayout18);
            app.NewCamPos_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.NewCamPos_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.CameravectorCheckBox = uicheckbox(app.GridLayout18);
            app.CameravectorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameravectorCheckBoxValueChanged, true);
            app.CameravectorCheckBox.Text = 'Camera vector';
            app.CameraVector_1_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CameraVector_2_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CameraVector_3_EditField = uieditfield(app.GridLayout18, 'numeric');
            app.CameraVector_3_EditField.Value = 1;
            app.ChangeisLabel_3 = uilabel(app.GridLayout18);
            app.ChangeisLabel_3.Text = 'Change is';
            app.Vector_ChangeDropDown = uidropdown(app.GridLayout18);
            app.Vector_ChangeDropDown.Items = {'Progressive', 'Instantaneous'};
            app.Vector_ChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @Vector_ChangeDropDownValueChanged, true);
            app.Vector_ChangeDropDown.Value = 'Progressive';
            app.ProfileDropDown_3Label_2 = uilabel(app.GridLayout18);
            app.ProfileDropDown_3Label_2.Text = 'Profile';
            app.Vector_ProfileDropDown = uidropdown(app.GridLayout18);
            app.Vector_ProfileDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Vector_ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Vector_ProfileDropDownValueChanged, true);
            app.Vector_ProfileDropDown.Value = 'Quadratic';
            app.SensDropDown_2Label_2 = uilabel(app.GridLayout18);
            app.SensDropDown_2Label_2.Text = 'Sens';
            app.Vector_SensDropDown = uidropdown(app.GridLayout18);
            app.Vector_SensDropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.Vector_SensDropDown.Value = 'Start slow';
            app.Transformations_Panel = uipanel(app.GridLayout17_2);
            app.Transformations_Panel.Title = 'Translations (tx, ty, tz, in voxel length) and rotations (rx, ry, rz, in degrees). Begin/End controls when the transformation is applied during the sequence.';
            app.Transformations_GridLayout = uigridlayout(app.Transformations_Panel);
            app.Transformation_UITable = uitable(app.Transformations_GridLayout);
            app.Transformation_UITable.ColumnName = {'Volume'; 'tx'; 'ty'; 'tz'; 'Rx'; 'Ry'; 'Rz'; 'Begin at'; 'End at'};
            app.Transformation_UITable.RowName = {};
            app.Transformation_UITable.ColumnEditable = [false true true true true true true true true];
            app.Transformation_UITable.CellEditCallback = createCallbackFcn(app, @Transformation_UITableCellEdit, true);
            app.ChangeisLabel_4 = uilabel(app.Transformations_GridLayout);
            app.ChangeisLabel_4.Text = 'Change is';
            app.Transformation_Coordinate_ChangeIs_DropDown = uidropdown(app.Transformations_GridLayout);
            app.Transformation_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.Transformation_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @Transformation_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.Transformation_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label_3 = uilabel(app.Transformations_GridLayout);
            app.ProfileDropDown_2Label_3.Text = 'Profile';
            app.Transformation_ChangeProfile_DropDown = uidropdown(app.Transformations_GridLayout);
            app.Transformation_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Transformation_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @Transformation_ChangeProfile_DropDownValueChanged, true);
            app.Transformation_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel_3 = uilabel(app.Transformations_GridLayout);
            app.SensDropDownLabel_3.Text = 'Sens';
            app.Transformation_ChangeProfileSens_DropDown = uidropdown(app.Transformations_GridLayout);
            app.Transformation_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.Transformation_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.AddtosequenceEditField_2Label = uilabel(app.Transformations_GridLayout);
            app.AddtosequenceEditField_2Label.Text = 'Add to sequence #';
            app.Transformation_AddtosequenceEditField = uieditfield(app.Transformations_GridLayout, 'numeric');
            app.Transformation_AddtosequenceEditField.Limits = [1 1];
            app.Transformation_AddtosequenceEditField.RoundFractionalValues = 'on';
            app.Transformation_AddtosequenceEditField.Value = 1;
            app.Transformation_Sequence_Camera_AddButton = uibutton(app.Transformations_GridLayout, 'push');
            app.Transformation_Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Transformation_Sequence_Camera_AddButtonPushed, true);
            app.Transformation_Sequence_Camera_AddButton.Text = 'Add';
            app.LightingPanel = uipanel(app.GridLayout17_2);
            app.LightingPanel.Title = 'Lighting';
            app.GridLayout20 = uigridlayout(app.LightingPanel);
            app.LightsourcepositionCheckBox = uicheckbox(app.GridLayout20);
            app.LightsourcepositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LightsourcepositionCheckBoxValueChanged, true);
            app.LightsourcepositionCheckBox.Text = 'Light source position';
            app.Light_coordinatechoice_DropDown = uidropdown(app.GridLayout20);
            app.Light_coordinatechoice_DropDown.Items = {'spherical', 'cartesian'};
            app.Light_coordinatechoice_DropDown.ValueChangedFcn = createCallbackFcn(app, @Light_coordinatechoice_DropDownValueChanged, true);
            app.Light_coordinatechoice_DropDown.Value = 'spherical';
            app.xDropDownLabel_2 = uilabel(app.GridLayout20);
            app.xDropDownLabel_2.Text = 'x';
            app.VideoLight_x_DropDown = uidropdown(app.GridLayout20);
            app.VideoLight_x_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.VideoLight_x_DropDown.ValueChangedFcn = createCallbackFcn(app, @VideoLight_x_DropDownValueChanged, true);
            app.VideoLight_x_DropDown.Value = 'absolute';
            app.yLabel_2 = uilabel(app.GridLayout20);
            app.yLabel_2.Text = 'y';
            app.VideoLight_y_DropDown = uidropdown(app.GridLayout20);
            app.VideoLight_y_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.VideoLight_y_DropDown.ValueChangedFcn = createCallbackFcn(app, @VideoLight_y_DropDownValueChanged, true);
            app.VideoLight_y_DropDown.Value = 'absolute';
            app.zLabel_2 = uilabel(app.GridLayout20);
            app.zLabel_2.Text = 'z';
            app.VideoLight_z_DropDown = uidropdown(app.GridLayout20);
            app.VideoLight_z_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.VideoLight_z_DropDown.ValueChangedFcn = createCallbackFcn(app, @VideoLight_z_DropDownValueChanged, true);
            app.VideoLight_z_DropDown.Value = 'absolute';
            app.Light_new_x_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.Light_new_y_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.Light_new_z_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.azimuthLabel_4 = uilabel(app.GridLayout20);
            app.azimuthLabel_4.Text = 'azimuth';
            app.LightPos_azimuth_choice_DropDown = uidropdown(app.GridLayout20);
            app.LightPos_azimuth_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.LightPos_azimuth_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @LightPos_azimuth_choice_DropDownValueChanged, true);
            app.LightPos_azimuth_choice_DropDown.Value = 'absolute';
            app.azimuthLabel_5 = uilabel(app.GridLayout20);
            app.azimuthLabel_5.Text = 'elevation';
            app.LightPos_elevation_choice_DropDown = uidropdown(app.GridLayout20);
            app.LightPos_elevation_choice_DropDown.Items = {'absolute', 'delta', 'unchanged'};
            app.LightPos_elevation_choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @LightPos_elevation_choice_DropDownValueChanged, true);
            app.LightPos_elevation_choice_DropDown.Value = 'absolute';
            app.LightPos_new_azimuth_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.LightPos_new_elevation_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.LightintensityCheckBox = uicheckbox(app.GridLayout20);
            app.LightintensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LightintensityCheckBoxValueChanged2, true);
            app.LightintensityCheckBox.Text = 'Light intensity';
            app.AmbientEditFieldLabel = uilabel(app.GridLayout20);
            app.AmbientEditFieldLabel.Text = 'Ambient';
            app.Video_new_ambientintensity_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.Video_new_ambientintensity_EditField.Limits = [0 1];
            app.SourceLabel = uilabel(app.GridLayout20);
            app.SourceLabel.Text = 'Source';
            app.Video_new_sourceintensity_EditField = uieditfield(app.GridLayout20, 'numeric');
            app.Video_new_sourceintensity_EditField.Limits = [0 1];
            app.ChangeisLabel_6 = uilabel(app.GridLayout20);
            app.ChangeisLabel_6.Text = 'Change is';
            app.NewLightPos_Coordinate_ChangeIs_DropDown = uidropdown(app.GridLayout20);
            app.NewLightPos_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.NewLightPos_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewLightPos_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.NewLightPos_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label_5 = uilabel(app.GridLayout20);
            app.ProfileDropDown_2Label_5.Text = 'Profile';
            app.NewLightPos_ChangeProfile_DropDown = uidropdown(app.GridLayout20);
            app.NewLightPos_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.NewLightPos_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @NewLightPos_ChangeProfile_DropDownValueChanged, true);
            app.NewLightPos_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel_5 = uilabel(app.GridLayout20);
            app.SensDropDownLabel_5.Text = 'Sens';
            app.NewLightPos_ChangeProfileSens_DropDown = uidropdown(app.GridLayout20);
            app.NewLightPos_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.NewLightPos_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.ChangeisLabel_7 = uilabel(app.GridLayout20);
            app.ChangeisLabel_7.Text = 'Change is';
            app.LightIntensity_ChangeDropDown = uidropdown(app.GridLayout20);
            app.LightIntensity_ChangeDropDown.Items = {'Progressive', 'Instantaneous'};
            app.LightIntensity_ChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @LightIntensity_ChangeDropDownValueChanged, true);
            app.LightIntensity_ChangeDropDown.Value = 'Progressive';
            app.ProfileDropDown_3Label_3 = uilabel(app.GridLayout20);
            app.ProfileDropDown_3Label_3.Text = 'Profile';
            app.LightIntensity_ProfileDropDown = uidropdown(app.GridLayout20);
            app.LightIntensity_ProfileDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.LightIntensity_ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @LightIntensity_ProfileDropDownValueChanged, true);
            app.LightIntensity_ProfileDropDown.Value = 'Quadratic';
            app.SensDropDown_2Label_3 = uilabel(app.GridLayout20);
            app.SensDropDown_2Label_3.Text = 'Sens';
            app.LightIntensity_SensDropDown = uidropdown(app.GridLayout20);
            app.LightIntensity_SensDropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.LightIntensity_SensDropDown.Value = 'Start slow';
            app.AddtosequenceEditField_3Label = uilabel(app.GridLayout20);
            app.AddtosequenceEditField_3Label.Text = 'Add to sequence #';
            app.Light_AddtosequenceEditField = uieditfield(app.GridLayout20, 'numeric');
            app.Light_AddtosequenceEditField.Limits = [1 1];
            app.Light_AddtosequenceEditField.RoundFractionalValues = 'on';
            app.Light_AddtosequenceEditField.Value = 1;
            app.Light_Sequence_Camera_AddButton = uibutton(app.GridLayout20, 'push');
            app.Light_Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Light_Sequence_Camera_AddButtonPushed, true);
            app.Light_Sequence_Camera_AddButton.Text = 'Add';
            app.Videosequence2Tab = uitab(app.TabGroup4);
            app.Videosequence2Tab.Title = 'Video sequence 2';
            app.GridLayout13 = uigridlayout(app.Videosequence2Tab);
            app.SavefolderButton_6 = uibutton(app.GridLayout13, 'push');
            app.SavefolderButton_6.ButtonPushedFcn = createCallbackFcn(app, @SavefolderButton_6Pushed, true);
            app.SavefolderButton_6.Text = 'Save folder';
            app.Videofolder = uilabel(app.GridLayout13);
            app.Videofolder.Text = 'None selected';
            app.VideofilenameEditField_6Label = uilabel(app.GridLayout13);
            app.VideofilenameEditField_6Label.Text = 'Video filename';
            app.Videofilename = uieditfield(app.GridLayout13, 'text');
            app.Videofilename.Value = 'video';
            app.DvideoButton = uibutton(app.GridLayout13, 'push');
            app.DvideoButton.ButtonPushedFcn = createCallbackFcn(app, @DvideoButtonPushed, true);
            app.DvideoButton.Text = '3D video';
            app.FramepersEditFieldLabel_2 = uilabel(app.GridLayout13);
            app.FramepersEditFieldLabel_2.Text = 'Frame per s';
            app.FramepersEditField = uieditfield(app.GridLayout13, 'numeric');
            app.FramepersEditField.Limits = [1 Inf];
            app.FramepersEditField.RoundFractionalValues = 'on';
            app.FramepersEditField.Value = 25;
            app.ClippingFOVsPanel = uipanel(app.GridLayout13);
            app.ClippingFOVsPanel.Title = 'Clipping FOVs';
            app.GridLayout19 = uigridlayout(app.ClippingFOVsPanel);
            app.Clipping_UITable = uitable(app.GridLayout19);
            app.Clipping_UITable.ColumnName = {'Volume'; 'x start'; 'x end'; 'y start'; 'y end'; 'z start'; 'z end'; 'orthogonal'; 'Begin at'; 'End at'; 'Label(s)'; 'additive'; 'Do'};
            app.Clipping_UITable.RowName = {};
            app.Clipping_UITable.ColumnEditable = [false true true true true true true true true true true true true];
            app.Clipping_UITable.CellEditCallback = createCallbackFcn(app, @Clipping_UITableCellEdit, true);
            app.Clipping_UITable.Tooltip = {'x start = 0.3'; 'x end = 1.0'; 'Clipping will start at 0.3 and finish at 1.0 along this axis (normalized value).'; ''; 'If it finishes with cst (e.g., x start = 0.3, and x end = 1.0 cst), then the cliiping is applied between these two locations.'};
            app.ChangeisLabel_5 = uilabel(app.GridLayout19);
            app.ChangeisLabel_5.Text = 'Change is';
            app.Clipping_Coordinate_ChangeIs_DropDown = uidropdown(app.GridLayout19);
            app.Clipping_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.Clipping_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @Clipping_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.Clipping_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label_4 = uilabel(app.GridLayout19);
            app.ProfileDropDown_2Label_4.Text = 'Profile';
            app.Clipping_ChangeProfile_DropDown = uidropdown(app.GridLayout19);
            app.Clipping_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Clipping_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @Clipping_ChangeProfile_DropDownValueChanged, true);
            app.Clipping_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel_4 = uilabel(app.GridLayout19);
            app.SensDropDownLabel_4.Text = 'Sens';
            app.Clipping_ChangeProfileSens_DropDown = uidropdown(app.GridLayout19);
            app.Clipping_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.Clipping_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.Clipping_Sequence_Camera_AddButton = uibutton(app.GridLayout19, 'push');
            app.Clipping_Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Clipping_Sequence_Camera_AddButtonPushed, true);
            app.Clipping_Sequence_Camera_AddButton.Text = 'Add';
            app.AddtosequenceEditField_2Label_2 = uilabel(app.GridLayout19);
            app.AddtosequenceEditField_2Label_2.Text = 'Add to sequence #';
            app.Clipping_AddtosequenceEditField = uieditfield(app.GridLayout19, 'numeric');
            app.Clipping_AddtosequenceEditField.Limits = [1 1];
            app.Clipping_AddtosequenceEditField.RoundFractionalValues = 'on';
            app.Clipping_AddtosequenceEditField.Value = 1;
            app.ClippinglabelswithoutclippingFOVsPanel = uipanel(app.GridLayout13);
            app.ClippinglabelswithoutclippingFOVsPanel.Title = 'Clipping labels (without clipping FOVs). Warning, cannot be combined with some actions.';
            app.GridLayout21 = uigridlayout(app.ClippinglabelswithoutclippingFOVsPanel);
            app.ChangeisLabel_8 = uilabel(app.GridLayout21);
            app.ChangeisLabel_8.Text = 'Change is';
            app.ClippingLabel_Coordinate_ChangeIs_DropDown = uidropdown(app.GridLayout21);
            app.ClippingLabel_Coordinate_ChangeIs_DropDown.Items = {'Progressive', 'Instantaneous'};
            app.ClippingLabel_Coordinate_ChangeIs_DropDown.ValueChangedFcn = createCallbackFcn(app, @ClippingLabel_Coordinate_ChangeIs_DropDownValueChanged, true);
            app.ClippingLabel_Coordinate_ChangeIs_DropDown.Value = 'Progressive';
            app.ProfileDropDown_2Label_6 = uilabel(app.GridLayout21);
            app.ProfileDropDown_2Label_6.Text = 'Profile';
            app.ClippingLabel_ChangeProfile_DropDown = uidropdown(app.GridLayout21);
            app.ClippingLabel_ChangeProfile_DropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.ClippingLabel_ChangeProfile_DropDown.ValueChangedFcn = createCallbackFcn(app, @ClippingLabel_ChangeProfile_DropDownValueChanged, true);
            app.ClippingLabel_ChangeProfile_DropDown.Value = 'Quadratic';
            app.SensDropDownLabel_6 = uilabel(app.GridLayout21);
            app.SensDropDownLabel_6.Text = 'Sens';
            app.ClippingLabel_ChangeProfileSens_DropDown = uidropdown(app.GridLayout21);
            app.ClippingLabel_ChangeProfileSens_DropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.ClippingLabel_ChangeProfileSens_DropDown.Value = 'Start slow';
            app.AddtosequenceEditField_2Label_3 = uilabel(app.GridLayout21);
            app.AddtosequenceEditField_2Label_3.Text = 'Add to sequence #';
            app.ClippingLabel_AddtosequenceEditField = uieditfield(app.GridLayout21, 'numeric');
            app.ClippingLabel_AddtosequenceEditField.Limits = [1 1];
            app.ClippingLabel_AddtosequenceEditField.RoundFractionalValues = 'on';
            app.ClippingLabel_AddtosequenceEditField.Value = 1;
            app.ClippingLabel_Sequence_Camera_AddButton = uibutton(app.GridLayout21, 'push');
            app.ClippingLabel_Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @ClippingLabel_Sequence_Camera_AddButtonPushed, true);
            app.ClippingLabel_Sequence_Camera_AddButton.Text = 'Add';
            app.ClippingLabel_UITable = uitable(app.GridLayout21);
            app.ClippingLabel_UITable.ColumnName = {'Volume'; 'Ordering size'; 'From'; 'To'; 'Begin at'; 'End at'; 'additive'; 'Do'};
            app.ClippingLabel_UITable.RowName = {};
            app.ClippingLabel_UITable.ColumnEditable = [false true true true true true true true];
            app.ClippingLabel_UITable.CellEditCallback = createCallbackFcn(app, @ClippingLabel_UITableCellEdit, true);
            app.OpacityPanel = uipanel(app.GridLayout13);
            app.OpacityPanel.Tooltip = {'Only applicable to semantic volumes'};
            app.OpacityPanel.Title = 'Opacity';
            app.GridLayout28 = uigridlayout(app.OpacityPanel);
            app.ChangeOpacity_UITable = uitable(app.GridLayout28);
            app.ChangeOpacity_UITable.ColumnName = {'Volume'; 'From'; 'To'; 'Do'};
            app.ChangeOpacity_UITable.RowName = {};
            app.ChangeOpacity_UITable.ColumnEditable = [false true true true];
            app.ChangeisDropDownLabel_2 = uilabel(app.GridLayout28);
            app.ChangeisDropDownLabel_2.Text = 'Change is';
            app.Opacity_ChangeisDropDown = uidropdown(app.GridLayout28);
            app.Opacity_ChangeisDropDown.Items = {'Progressive', 'Instantaneous'};
            app.Opacity_ChangeisDropDown.ValueChangedFcn = createCallbackFcn(app, @Opacity_ChangeisDropDownValueChanged, true);
            app.Opacity_ChangeisDropDown.Value = 'Progressive';
            app.ProfileDropDownLabel = uilabel(app.GridLayout28);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.Opacity_ChangeProfileDropDown = uidropdown(app.GridLayout28);
            app.Opacity_ChangeProfileDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.Opacity_ChangeProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @Opacity_ChangeProfileDropDownValueChanged, true);
            app.Opacity_ChangeProfileDropDown.Value = 'Quadratic';
            app.SensDropDownLabel_7 = uilabel(app.GridLayout28);
            app.SensDropDownLabel_7.Text = 'Sens';
            app.Opacity_ChangeSensDropDown = uidropdown(app.GridLayout28);
            app.Opacity_ChangeSensDropDown.Items = {'Start slow', 'End slow', 'Symmetric'};
            app.Opacity_ChangeSensDropDown.ValueChangedFcn = createCallbackFcn(app, @Opacity_ChangeSensDropDownValueChanged, true);
            app.Opacity_ChangeSensDropDown.Value = 'Start slow';
            app.AddtosequenceEditField_4Label = uilabel(app.GridLayout28);
            app.AddtosequenceEditField_4Label.Text = 'Add to sequence #';
            app.Opacity_AddtosequenceEditField = uieditfield(app.GridLayout28, 'numeric');
            app.Opacity_AddtosequenceEditField.Limits = [1 1];
            app.Opacity_AddtosequenceEditField.RoundFractionalValues = 'on';
            app.Opacity_AddtosequenceEditField.Value = 1;
            app.Opacity_Sequence_Camera_AddButton = uibutton(app.GridLayout28, 'push');
            app.Opacity_Sequence_Camera_AddButton.ButtonPushedFcn = createCallbackFcn(app, @Opacity_Sequence_Camera_AddButtonPushed, true);
            app.Opacity_Sequence_Camera_AddButton.Text = 'Add';
            app.ViewcharacterizationresultsTab = uitab(app.TabGroup);
            app.ViewcharacterizationresultsTab.Title = 'View characterization results';
            app.Label_8 = uilabel(app.ViewcharacterizationresultsTab);
            app.Label_8.Text = 'This visualization mode will be re-added later once the characterization module reword will be finished';
            app.VideoexplorerTab = uitab(app.TabGroup);
            app.VideoexplorerTab.Title = 'Video explorer';
            app.GridLayout26 = uigridlayout(app.VideoexplorerTab);
            app.TabGroup5 = uitabgroup(app.GridLayout26);
            app.CropandtrimvideoTab = uitab(app.TabGroup5);
            app.CropandtrimvideoTab.Title = 'Crop and trim video';
            app.GridLayout27 = uigridlayout(app.CropandtrimvideoTab);
            app.Frame_UIAxes = uiaxes(app.GridLayout27);
            zlabel(app.Frame_UIAxes, 'Z')
            app.ImportvideofileButton = uibutton(app.GridLayout27, 'push');
            app.ImportvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportvideofileButtonPushed, true);
            app.ImportvideofileButton.Text = 'Import video file';
            app.NovideofileLabel = uilabel(app.GridLayout27);
            app.NovideofileLabel.Text = 'No video file';
            app.DimensionnaLabel = uilabel(app.GridLayout27);
            app.DimensionnaLabel.Text = 'Dimension: n/a';
            app.NumberofframesnaLabel = uilabel(app.GridLayout27);
            app.NumberofframesnaLabel.Text = 'Number of frames: n/a';
            app.CropxaxisfromEditFieldLabel = uilabel(app.GridLayout27);
            app.CropxaxisfromEditFieldLabel.Text = 'Crop x-axis from';
            app.CropxaxisfromEditField = uieditfield(app.GridLayout27, 'numeric');
            app.CropxaxisfromEditField.Limits = [1 Inf];
            app.CropxaxisfromEditField.RoundFractionalValues = 'on';
            app.CropxaxisfromEditField.ValueChangedFcn = createCallbackFcn(app, @CropxaxisfromEditFieldValueChanged, true);
            app.CropxaxisfromEditField.Enable = 'off';
            app.CropxaxisfromEditField.Value = 1;
            app.toEditField_4Label = uilabel(app.GridLayout27);
            app.toEditField_4Label.Text = 'to';
            app.CropxaxistoEditField = uieditfield(app.GridLayout27, 'numeric');
            app.CropxaxistoEditField.Limits = [1 Inf];
            app.CropxaxistoEditField.RoundFractionalValues = 'on';
            app.CropxaxistoEditField.ValueChangedFcn = createCallbackFcn(app, @CropxaxistoEditFieldValueChanged, true);
            app.CropxaxistoEditField.Enable = 'off';
            app.CropxaxistoEditField.Value = 1000;
            app.CropyaxisfromEditFieldLabel = uilabel(app.GridLayout27);
            app.CropyaxisfromEditFieldLabel.Text = 'Crop y-axis from';
            app.CropyaxisfromEditField = uieditfield(app.GridLayout27, 'numeric');
            app.CropyaxisfromEditField.Limits = [1 Inf];
            app.CropyaxisfromEditField.RoundFractionalValues = 'on';
            app.CropyaxisfromEditField.ValueChangedFcn = createCallbackFcn(app, @CropyaxisfromEditFieldValueChanged, true);
            app.CropyaxisfromEditField.Enable = 'off';
            app.CropyaxisfromEditField.Value = 1;
            app.toEditField_5Label = uilabel(app.GridLayout27);
            app.toEditField_5Label.Text = 'to';
            app.CropyaxistoEditField = uieditfield(app.GridLayout27, 'numeric');
            app.CropyaxistoEditField.Limits = [1 Inf];
            app.CropyaxistoEditField.RoundFractionalValues = 'on';
            app.CropyaxistoEditField.ValueChangedFcn = createCallbackFcn(app, @CropyaxistoEditFieldValueChanged, true);
            app.CropyaxistoEditField.Enable = 'off';
            app.CropyaxistoEditField.Value = 1000;
            app.LengthnaLabel = uilabel(app.GridLayout27);
            app.LengthnaLabel.Text = 'Length: n/a';
            app.FramepersecondfpsnaLabel = uilabel(app.GridLayout27);
            app.FramepersecondfpsnaLabel.Text = 'Frame per second (fps): n/a';
            app.KeepframesfromEditFieldLabel = uilabel(app.GridLayout27);
            app.KeepframesfromEditFieldLabel.Text = 'Keep frames from';
            app.KeepframesfromEditField = uieditfield(app.GridLayout27, 'numeric');
            app.KeepframesfromEditField.Limits = [1 Inf];
            app.KeepframesfromEditField.RoundFractionalValues = 'on';
            app.KeepframesfromEditField.ValueChangedFcn = createCallbackFcn(app, @KeepframesfromEditFieldValueChanged, true);
            app.KeepframesfromEditField.Enable = 'off';
            app.KeepframesfromEditField.Value = 1;
            app.toEditField_6Label = uilabel(app.GridLayout27);
            app.toEditField_6Label.Text = 'to';
            app.KeepframetoEditField = uieditfield(app.GridLayout27, 'numeric');
            app.KeepframetoEditField.Limits = [1 Inf];
            app.KeepframetoEditField.RoundFractionalValues = 'on';
            app.KeepframetoEditField.ValueChangedFcn = createCallbackFcn(app, @KeepframetoEditFieldValueChanged, true);
            app.KeepframetoEditField.Enable = 'off';
            app.KeepframetoEditField.Value = 1000;
            app.NewlengthsEditFieldLabel = uilabel(app.GridLayout27);
            app.NewlengthsEditFieldLabel.Text = 'New length (s)';
            app.NewlengthsEditField = uieditfield(app.GridLayout27, 'numeric');
            app.NewlengthsEditField.Limits = [1 Inf];
            app.NewlengthsEditField.ValueChangedFcn = createCallbackFcn(app, @NewlengthsEditFieldValueChanged, true);
            app.NewlengthsEditField.Enable = 'off';
            app.NewlengthsEditField.Value = 1;
            app.NewfpsEditFieldLabel = uilabel(app.GridLayout27);
            app.NewfpsEditFieldLabel.Text = 'New fps';
            app.NewfpsEditField = uieditfield(app.GridLayout27, 'numeric');
            app.NewfpsEditField.Limits = [0.01 Inf];
            app.NewfpsEditField.ValueChangedFcn = createCallbackFcn(app, @NewfpsEditFieldValueChanged, true);
            app.NewfpsEditField.Enable = 'off';
            app.NewfpsEditField.Value = 1;
            app.FrameSliderLabel = uilabel(app.GridLayout27);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.GridLayout27);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.Button_minusframe = uibutton(app.GridLayout27, 'push');
            app.Button_minusframe.ButtonPushedFcn = createCallbackFcn(app, @Button_minusframePushed, true);
            app.Button_minusframe.Enable = 'off';
            app.Button_minusframe.Text = '-';
            app.Button_plusframe = uibutton(app.GridLayout27, 'push');
            app.Button_plusframe.ButtonPushedFcn = createCallbackFcn(app, @Button_plusframePushed, true);
            app.Button_plusframe.Enable = 'off';
            app.Button_plusframe.Text = '+';
            app.FrameEditField = uieditfield(app.GridLayout27, 'numeric');
            app.FrameEditField.Limits = [1 Inf];
            app.FrameEditField.RoundFractionalValues = 'on';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Enable = 'off';
            app.FrameEditField.Value = 1;
            app.SaveButton = uibutton(app.GridLayout27, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.formatDropDownLabel = uilabel(app.GridLayout27);
            app.formatDropDownLabel.Text = 'format';
            app.formatDropDown = uidropdown(app.GridLayout27);

            app.formatDropDown.Items = {'mp4', 'gif'};
            app.formatDropDown.Value = 'mp4';
            app.KeepframeeveryEditFieldLabel = uilabel(app.GridLayout27);
            app.KeepframeeveryEditFieldLabel.Text = 'Keep frame every';
            app.KeepframeeveryEditField = uieditfield(app.GridLayout27, 'numeric');
            app.KeepframeeveryEditField.Limits = [1 Inf];
            app.KeepframeeveryEditField.RoundFractionalValues = 'on';
            app.KeepframeeveryEditField.ValueChangedFcn = createCallbackFcn(app, @KeepframeeveryEditFieldValueChanged, true);
            app.KeepframeeveryEditField.Enable = 'off';
            app.KeepframeeveryEditField.Value = 1;
            app.otherframesLabel = uilabel(app.GridLayout27);
            app.otherframesLabel.Text = 'other frame(s)';
            app.VideoresolutionwidthEditFieldLabel = uilabel(app.GridLayout27);
            app.VideoresolutionwidthEditFieldLabel.Text = 'Video resolution: width';
            app.VideoresolutionwidthEditField = uieditfield(app.GridLayout27, 'numeric');
            app.VideoresolutionwidthEditField.Limits = [1 Inf];
            app.VideoresolutionwidthEditField.RoundFractionalValues = 'on';
            app.VideoresolutionwidthEditField.ValueChangedFcn = createCallbackFcn(app, @VideoresolutionwidthEditFieldValueChanged, true);
            app.VideoresolutionwidthEditField.Enable = 'off';
            app.VideoresolutionwidthEditField.Value = 1;
            app.heightEditFieldLabel = uilabel(app.GridLayout27);
            app.heightEditFieldLabel.Text = 'height';
            app.heightEditField = uieditfield(app.GridLayout27, 'numeric');
            app.heightEditField.Limits = [1 Inf];
            app.heightEditField.RoundFractionalValues = 'on';
            app.heightEditField.ValueChangedFcn = createCallbackFcn(app, @heightEditFieldValueChanged, true);
            app.heightEditField.Enable = 'off';
            app.heightEditField.Value = 1;
            app.ConcatenatevideosTab = uitab(app.TabGroup5);
            app.ConcatenatevideosTab.Title = 'Concatenate videos';
            app.GridLayout27_2 = uigridlayout(app.ConcatenatevideosTab);
            app.VideosmustsharesamedimensionLabel = uilabel(app.GridLayout27_2);
            app.VideosmustsharesamedimensionLabel.Text = 'Videos must share same dimension';
            app.Importvideo1Button = uibutton(app.GridLayout27_2, 'push');
            app.Importvideo1Button.ButtonPushedFcn = createCallbackFcn(app, @Importvideo1ButtonPushed, true);
            app.Importvideo1Button.Text = 'Import video 1';
            app.Importvideo2Button = uibutton(app.GridLayout27_2, 'push');
            app.Importvideo2Button.ButtonPushedFcn = createCallbackFcn(app, @Importvideo2ButtonPushed, true);
            app.Importvideo2Button.Text = 'Import video 2';
            app.NovideofileLabel_2 = uilabel(app.GridLayout27_2);
            app.NovideofileLabel_2.Text = 'No video file';
            app.DimensionnaLabel_2 = uilabel(app.GridLayout27_2);
            app.DimensionnaLabel_2.Text = 'Dimension: n/a';
            app.NumberofframesnaLabel_2 = uilabel(app.GridLayout27_2);
            app.NumberofframesnaLabel_2.Text = 'Number of frames: n/a';
            app.LengthnaLabel_2 = uilabel(app.GridLayout27_2);
            app.LengthnaLabel_2.Text = 'Length: n/a';
            app.FramepersecondfpsnaLabel_2 = uilabel(app.GridLayout27_2);
            app.FramepersecondfpsnaLabel_2.Text = 'Frame per second (fps): n/a';
            app.NovideofileLabel_3 = uilabel(app.GridLayout27_2);
            app.NovideofileLabel_3.Text = 'No video file';
            app.DimensionnaLabel_3 = uilabel(app.GridLayout27_2);
            app.DimensionnaLabel_3.Text = 'Dimension: n/a';
            app.NumberofframesnaLabel_3 = uilabel(app.GridLayout27_2);
            app.NumberofframesnaLabel_3.Text = 'Number of frames: n/a';
            app.LengthnaLabel_3 = uilabel(app.GridLayout27_2);
            app.LengthnaLabel_3.Text = 'Length: n/a';
            app.FramepersecondfpsnaLabel_3 = uilabel(app.GridLayout27_2);
            app.FramepersecondfpsnaLabel_3.Text = 'Frame per second (fps): n/a';
            app.NewlengthsEditField_2Label = uilabel(app.GridLayout27_2);
            app.NewlengthsEditField_2Label.Text = 'New length (s)';
            app.NewlengthsEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.NewlengthsEditField_2.Limits = [1 Inf];
            app.NewlengthsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NewlengthsEditField_2ValueChanged, true);
            app.NewlengthsEditField_2.Enable = 'off';
            app.NewlengthsEditField_2.Value = 1;
            app.NewfpsEditField_2Label = uilabel(app.GridLayout27_2);
            app.NewfpsEditField_2Label.Text = 'New fps';
            app.NewfpsEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.NewfpsEditField_2.Limits = [0.01 Inf];
            app.NewfpsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NewfpsEditField_2ValueChanged, true);
            app.NewfpsEditField_2.Enable = 'off';
            app.NewfpsEditField_2.Value = 1;
            app.VideoresolutionwidthEditField_2Label = uilabel(app.GridLayout27_2);
            app.VideoresolutionwidthEditField_2Label.Text = 'Video resolution: width';
            app.VideoresolutionwidthEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.VideoresolutionwidthEditField_2.Limits = [1 Inf];
            app.VideoresolutionwidthEditField_2.RoundFractionalValues = 'on';
            app.VideoresolutionwidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @VideoresolutionwidthEditField_2ValueChanged, true);
            app.VideoresolutionwidthEditField_2.Enable = 'off';
            app.VideoresolutionwidthEditField_2.Value = 1;
            app.heightEditField_2Label = uilabel(app.GridLayout27_2);
            app.heightEditField_2Label.Text = 'height';
            app.heightEditField_2 = uieditfield(app.GridLayout27_2, 'numeric');
            app.heightEditField_2.Limits = [1 Inf];
            app.heightEditField_2.RoundFractionalValues = 'on';
            app.heightEditField_2.ValueChangedFcn = createCallbackFcn(app, @heightEditField_2ValueChanged, true);
            app.heightEditField_2.Enable = 'off';
            app.heightEditField_2.Value = 1;
            app.formatDropDown_2Label = uilabel(app.GridLayout27_2);
            app.formatDropDown_2Label.Text = 'format';
            app.formatDropDown_2 = uidropdown(app.GridLayout27_2);
            app.formatDropDown_2.Items = {'mp4', 'gif'};
            app.formatDropDown_2.Value = 'mp4';
            app.SaveButton_2 = uibutton(app.GridLayout27_2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Enable = 'off';
            app.SaveButton_2.Text = 'Save';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout27_2);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.GridLayout27_2, 'text');
            app.FilenameEditField.Value = 'Concatenated video';
            app.SavefolderButton_7 = uibutton(app.GridLayout27_2, 'push');
            app.SavefolderButton_7.ButtonPushedFcn = createCallbackFcn(app, @SavefolderButton_7Pushed, true);
            app.SavefolderButton_7.Text = 'Save folder';
            app.ConcatenatedvideoLabel = uilabel(app.GridLayout27_2);
            app.ConcatenatedvideoLabel.Text = 'Concatenated video';
            app.NofolderselectedLabel = uilabel(app.GridLayout27_2);
            app.NofolderselectedLabel.Text = 'No folder selected';
            app.NumberofframesnaLabel_4 = uilabel(app.GridLayout27_2);
            app.NumberofframesnaLabel_4.Text = 'Number of frames: n/a';
            app.GalleryTab = uitab(app.TabGroup);
            app.GalleryTab.Title = 'Gallery';
            app.GridLayout25 = uigridlayout(app.GalleryTab);
            app.HereyoucanseesomeexamplesproducedwiththistoolLabel = uilabel(app.GridLayout25);
            app.HereyoucanseesomeexamplesproducedwiththistoolLabel.Text = 'Here you can see some examples produced with this tool';
            app.VideosanimationsshownbelowarefromgiffilesafterconversionOriginalvideosaremuchsmootherwithhigherframeratePanel = uipanel(app.GridLayout25);
            app.VideosanimationsshownbelowarefromgiffilesafterconversionOriginalvideosaremuchsmootherwithhigherframeratePanel.Title = 'Videos (animations shown below are from gif files after conversion. Original videos are much smoother with higher frame rate)';
            app.GridLayout24 = uigridlayout(app.VideosanimationsshownbelowarefromgiffilesafterconversionOriginalvideosaremuchsmootherwithhigherframeratePanel);
            app.Image2 = uiimage(app.GridLayout24);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'NMC_video_example.gif');
            app.Image = uiimage(app.GridLayout24);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Graphite_video_example.gif');
            app.Image4 = uiimage(app.GridLayout24);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'NMC_video_example2.gif');
            app.Image3 = uiimage(app.GridLayout24);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Laserablated_video_example.gif');
            app.Image5 = uiimage(app.GridLayout24);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'NMC_video_example3.gif');
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout22 = uigridlayout(app.AboutTab);
            app.Hyperlink_2 = uihyperlink(app.GridLayout22);
            app.Hyperlink_2.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_2.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.IfyouproducevisualswiththistoolpleasequoteLabel = uilabel(app.GridLayout22);
            app.IfyouproducevisualswiththistoolpleasequoteLabel.Text = 'If you produce visuals with this tool, please quote:';
            app.Hyperlink_3 = uihyperlink(app.GridLayout22);
            app.Hyperlink_3.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink_3.Text = 'MATBOX official webpage';
            app.Hyperlink_4 = uihyperlink(app.GridLayout22);
            app.Hyperlink_4.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_4.Text = 'MATBOX GitHub repository';
            app.Label_10 = uilabel(app.GridLayout22);
            app.Label_10.Text = 'If you like this tool, please consider ''Star'' the GitHub repository to increase its visbility.';
            app.Label_11 = uilabel(app.GridLayout22);
            app.Label_11.Text = 'You can register there (optional). You will be notified when major updates are released.';
            app.Label3 = uilabel(app.GridLayout22);
            app.Label3.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion (Li-ion) batteries'; 'and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.GridLayout22);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoImageClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Onlineversion_Label = uilabel(app.GridLayout22);
            app.Onlineversion_Label.Text = '';
            app.TextArea = uitextarea(app.GridLayout22);
            app.TextArea.Value = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Label2_2 = uilabel(app.MicrostructurevisualizationUIFigure);
            app.Label2_2.Text = 'Label2';
            % Show the figure after all components are created
            app.MicrostructurevisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microstructure_visualization_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructurevisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructurevisualizationUIFigure)
        end
    end
end