classdef Microstructure_generation_stochastic_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_8                         matlab.ui.control.Label
        Label_69                        matlab.ui.control.Label
        MainGridLayout                  matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        IntroductionTab                 matlab.ui.container.Tab
        GridLayout5_5                   matlab.ui.container.GridLayout
        AquickoverviewLabel             matlab.ui.control.Label
        HighlevelfeaturesLabel          matlab.ui.control.Label
        Label_92                        matlab.ui.control.Label
        Label_91                        matlab.ui.control.Label
        Label_90                        matlab.ui.control.Label
        WelcometotheLabel               matlab.ui.control.Label
        Label_89                        matlab.ui.control.Label
        LitteratureLabel                matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_87                        matlab.ui.control.Label
        CharacterizationLabel           matlab.ui.control.Label
        Label_86                        matlab.ui.control.Label
        ParametersweepLabel             matlab.ui.control.Label
        AnalysisLabel                   matlab.ui.control.Label
        Label_85                        matlab.ui.control.Label
        SaveyoursessionLabel            matlab.ui.control.Label
        LearningfromexamplesLabel       matlab.ui.control.Label
        ExamplesLabel                   matlab.ui.control.Label
        Label_84                        matlab.ui.control.Label
        QualityoflifeLabel              matlab.ui.control.Label
        InitializationLabel             matlab.ui.control.Label
        Label_83                        matlab.ui.control.Label
        StreamlinedworkflowLabel        matlab.ui.control.Label
        Label_82                        matlab.ui.control.Label
        IntegratedhelpLabel             matlab.ui.control.Label
        Label_81                        matlab.ui.control.Label
        UpscalingLabel                  matlab.ui.control.Label
        Label2_3                        matlab.ui.control.Label
        Label2_2                        matlab.ui.control.Label
        HeterogeneityLabel              matlab.ui.control.Label
        FeaturecontrolLabel             matlab.ui.control.Label
        Label_80                        matlab.ui.control.Label
        Label_79                        matlab.ui.control.Label
        Label_78                        matlab.ui.control.Label
        OverlappingcontrolLabel         matlab.ui.control.Label
        Label_77                        matlab.ui.control.Label
        MasksLabel                      matlab.ui.control.Label
        EachdomainLabel                 matlab.ui.control.Label
        Label_76                        matlab.ui.control.Label
        MultiphaseLabel                 matlab.ui.control.Label
        MultishapeLabel                 matlab.ui.control.Label
        InstructionsTab_3               matlab.ui.container.Tab
        GridLayout5_6                   matlab.ui.container.GridLayout
        Label_102                       matlab.ui.control.Label
        Label_101                       matlab.ui.control.Label
        Label_99                        matlab.ui.control.Label
        Label_98                        matlab.ui.control.Label
        Label_97                        matlab.ui.control.Label
        Label_96                        matlab.ui.control.Label
        Label_95                        matlab.ui.control.Label
        Label_94                        matlab.ui.control.Label
        Label_93                        matlab.ui.control.Label
        TherearetwomainworkflowsLabel   matlab.ui.control.Label
        GenerateTab                     matlab.ui.container.Tab
        GenerateGridLayout              matlab.ui.container.GridLayout
        GenerateTabGroup                matlab.ui.container.TabGroup
        InstructionsTab_2               matlab.ui.container.Tab
        GridLayout3_3                   matlab.ui.container.GridLayout
        Label_103                       matlab.ui.control.Label
        DimensionsTab                   matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        DomainSweep_CropLabel           matlab.ui.control.Label
        VisualizeButton                 matlab.ui.control.Button
        Labelsvolumefractionsna         matlab.ui.control.Label
        LengthanddimensionalitynaLabel  matlab.ui.control.Label
        Label_46                        matlab.ui.control.Label
        ImportedfilenoneLabel           matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        ImportapreexistingdomainLabel   matlab.ui.control.Label
        ORLabel                         matlab.ui.control.Label
        Dimension_UITable               matlab.ui.control.Table
        Dimension_ParSweep_CheckBox     matlab.ui.control.CheckBox
        Label_4                         matlab.ui.control.Label
        SetdomainslengthLabel           matlab.ui.control.Label
        DimensionalityDropDown          matlab.ui.control.DropDown
        DimensionalityDropDown_2Label   matlab.ui.control.Label
        aSelectdimensionalityLabel      matlab.ui.control.Label
        PhasesTab                       matlab.ui.container.Tab
        PhasesGridLayout                matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        NumberandmorphologyTab          matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GenerationsteperrorLabel        matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        NamearereusedintheGUIsoLabel    matlab.ui.control.Label
        SolidPhase_UITable              matlab.ui.control.Table
        Label_18                        matlab.ui.control.Label
        NumberofphasesEditField         matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        PhasePhaseinteractionsmasksTab  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        A_morhpology_Label              matlab.ui.control.Label
        B_morhpology_Label              matlab.ui.control.Label
        Label_105                       matlab.ui.control.Label
        CaseBsmorphologyisShapePanel    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Mask_Shape_Image                matlab.ui.control.Image
        Shape_RemoveParticle_x_EditField  matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        Shape_RemoveParticle_CheckBox   matlab.ui.control.CheckBox
        Shape_BisoverwritingACheckBox   matlab.ui.control.CheckBox
        Label_74                        matlab.ui.control.Label
        CaseBsmorphologyisamorphPanel   matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Amorph_RuleformultiplemasksDropDown  matlab.ui.control.DropDown
        DropDownLabel_2                 matlab.ui.control.Label
        Amorph_UsePhaseAasmaskCheckBox  matlab.ui.control.CheckBox
        Label_73                        matlab.ui.control.Label
        CaseBisEllipsoidPanel           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        Enterdasd2d1dLabel              matlab.ui.control.Label
        MaskParticle_VolumeoverlapDropDown  matlab.ui.control.DropDown
        ParticleBLabel                  matlab.ui.control.Label
        RuleformultiplemasksDropDown    matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Ellipsoids_Notmorethan_SweepUITable  matlab.ui.control.Table
        Ellipsoids_mask_image           matlab.ui.control.Image
        Ellipsoids_Notmorethan_DoaparameterSweep_checkbox  matlab.ui.control.CheckBox
        Ellipsoids_Notmorethan_CheckBox  matlab.ui.control.CheckBox
        Ellipsoids_Atleast_SweepUITable  matlab.ui.control.Table
        Ellipsoids_Atleast_DoaparametersweepCheckBox  matlab.ui.control.CheckBox
        Ellipsoids_Atleast_checkbox     matlab.ui.control.CheckBox
        Label_63                        matlab.ui.control.Label
        ParticlesBaregeneratedDropDown  matlab.ui.control.DropDown
        ParticlescanDropDownLabel       matlab.ui.control.Label
        TypeALabel                      matlab.ui.control.Label
        TypeBLabel                      matlab.ui.control.Label
        MasksarenaLabel                 matlab.ui.control.Label
        PhaseADropDown                  matlab.ui.control.DropDown
        PhaseADropDownLabel             matlab.ui.control.Label
        PhaseBDropDown                  matlab.ui.control.DropDown
        PhaseBDropDown_2Label           matlab.ui.control.Label
        Label_53                        matlab.ui.control.Label
        MorphologyTab                   matlab.ui.container.Tab
        MorphologyGridLayout            matlab.ui.container.GridLayout
        MorphologyTabGroup              matlab.ui.container.TabGroup
        TubesTab                        matlab.ui.container.Tab
        ParticlesTab                    matlab.ui.container.Tab
        ParticlesGridLayout             matlab.ui.container.GridLayout
        ParticlesTabGroup               matlab.ui.container.TabGroup
        InstructionsTab_4               matlab.ui.container.Tab
        VolumefractionsTab              matlab.ui.container.Tab
        ParticleVfGridLayout            matlab.ui.container.GridLayout
        ParticleVfTabGroup              matlab.ui.container.TabGroup
        HeterogeneityTab                matlab.ui.container.Tab
        Label_70                        matlab.ui.control.Label
        Label_61                        matlab.ui.control.Label
        UniformTab                      matlab.ui.container.Tab
        WorworenormalizationLabel       matlab.ui.control.Label
        Label_59                        matlab.ui.control.Label
        AxialgradationTab               matlab.ui.container.Tab
        Label_58                        matlab.ui.control.Label
        DTab                            matlab.ui.container.Tab
        TypenaLabel                     matlab.ui.control.Label
        heterogenousimportmapLabel      matlab.ui.control.Label
        DiametersellipoidsTab           matlab.ui.container.Tab
        LengthsplatesTab                matlab.ui.container.Tab
        RotationsTab                    matlab.ui.container.Tab
        CracksTab                       matlab.ui.container.Tab
        SurfaceroughnessTab             matlab.ui.container.Tab
        OrderTab                        matlab.ui.container.Tab
        EdgesTab                        matlab.ui.container.Tab
        CroppingandPeriodicBCLabel      matlab.ui.control.Label
        OverlappingTab                  matlab.ui.container.Tab
        Label_60                        matlab.ui.control.Label
        AlgorithmTab                    matlab.ui.container.Tab
        StoppingconditionsTab           matlab.ui.container.Tab
        AmorphTab                       matlab.ui.container.Tab
        WebTab                          matlab.ui.container.Tab
        PredeterminedshapeTab           matlab.ui.container.Tab
        UpscaleTab                      matlab.ui.container.Tab
        PostprocessingTab               matlab.ui.container.Tab
        Label_104                       matlab.ui.control.Label
        ReassignLabel                   matlab.ui.control.Label
        Label_50                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ParametersweepTab               matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ReproducibilityDropDown         matlab.ui.control.DropDown
        ReproducibilityDropDownLabel    matlab.ui.control.Label
        Error_linkeallparameters        matlab.ui.control.Label
        Error_linkedparameters          matlab.ui.control.Label
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        TotalnumberofgenerationsLabel   matlab.ui.control.Label
        Study_UITable                   matlab.ui.control.Table
        DeletestudiesButton             matlab.ui.control.Button
        AddastudyButton                 matlab.ui.control.Button
        ParameterSweep_help_Button      matlab.ui.control.Button
        Label_55                        matlab.ui.control.Label
        Sweepparameter_UITable          matlab.ui.control.Table
        Label_54                        matlab.ui.control.Label
        Parameter_sweep_messageLabel    matlab.ui.control.Label
        SaveimportparametersTab         matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        Example_Image                   matlab.ui.control.Image
        ImportexampleButton             matlab.ui.control.Button
        Example_DescriptionnoneLabel    matlab.ui.control.Label
        ExamplesDropDown                matlab.ui.control.DropDown
        ExamplesDropDownLabel           matlab.ui.control.Label
        Label_57                        matlab.ui.control.Label
        ImportButton_2                  matlab.ui.control.Button
        SaveParameters_ErrormessageLabel  matlab.ui.control.Label
        SaveParameter_FIlenameEditField  matlab.ui.control.EditField
        FlenameEditFieldLabel           matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Label_56                        matlab.ui.control.Label
        RunTab                          matlab.ui.container.Tab
        ReproducibilityLabel            matlab.ui.control.Label
        RegenerateTab                   matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        AboutGridLayout                 matlab.ui.container.GridLayout
        AboutTabGroup                   matlab.ui.container.TabGroup
        NREL_Tab                        matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        Hyperlink15                     matlab.ui.control.Hyperlink
        Hyperlink14                     matlab.ui.control.Hyperlink
        Label_68                        matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Label_67                        matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        Hyperlink_2                     matlab.ui.control.Hyperlink
        IfyouhavegeneratedTextArea      matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        Hyperlink12                     matlab.ui.control.Hyperlink
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        IfyouLabel                      matlab.ui.control.Label
        ReferencesTab                   matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Label_65                        matlab.ui.control.Label
        Hyperlink11                     matlab.ui.control.Hyperlink
        Hyperlink10                     matlab.ui.control.Hyperlink
        Hyperlink9                      matlab.ui.control.Hyperlink
        Hyperlink8                      matlab.ui.control.Hyperlink
        Hyperlink7                      matlab.ui.control.Hyperlink
        Hyperlink6                      matlab.ui.control.Hyperlink
        Hyperlink5                      matlab.ui.control.Hyperlink
        Hyperlink4                      matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        AgglomeratesLabel               matlab.ui.control.Label
        PredeterminedshapesLabel        matlab.ui.control.Label
        TubesLabel                      matlab.ui.control.Label
        Amorph131011Label               matlab.ui.control.Label
        EllipsoidsparticlesLabel        matlab.ui.control.Label
        Label_64                        matlab.ui.control.Label
        IssuesTab                       matlab.ui.container.Tab
        GridLayout5_4                   matlab.ui.container.GridLayout
        Hyperlink13                     matlab.ui.control.Hyperlink
        Label_66                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        importpath = [];
        
        dimensionality = [];
        
        preexistingphases_array_path = [];
        preexistingphases_array = [];
        preexistingphases_labels = [];
        preexistingphases_n = [];

        number_phase_togenerate = [];
        color_phase = NaN(1e3,3);

        generationstep_correct = [];

        has_masks = [];
        maskparsBA = [];
        mask_is_initializing = [];

        Examples = [];
        has_parameter_sweeping = [];
        parameter_sweep_is_valid = [];
        saveimport_parameter_path = [];

        study = [];
    end
    
    methods (Access = private)

        function [] = Check_sweeping_parameter(app,h,indices, newData, oldData, p)
            if indices(2)==6 % Default
                if isnan(newData) || newData<=0
                    h.Data(indices(1),indices(2)) = {oldData};
                end        
                if ~isnan(newData) && ~isempty(p.upperbound) && newData > p.upperbound(indices(1))
                    h.Data(indices(1),indices(2)) = {oldData};
                end
                if ~isnan(newData) && p.mustberound && round(newData)~=newData
                    h.Data(indices(1),indices(2)) = {oldData};
                end

            elseif indices(2)==4
                if isnan(newData) || newData<=1 || newData~=round(newData)
                    h.Data(indices(1),indices(2)) = {oldData};
                end
                lowerbound = cell2mat(h.Data(indices(1),2));
                upperbound = cell2mat(h.Data(indices(1),3));
                ncase = cell2mat(h.Data(indices(1),4));
                if ncase > 2
                    intermediate_values = linspace(lowerbound,upperbound,ncase);
                    intermediate_values = intermediate_values(2:end-1);
                    if p.mustberound
                        intermediate_values = round(intermediate_values);
                        intermediate_values = strrep(num2str(intermediate_values),'  ',',');
                        intermediate_values = strrep(num2str(intermediate_values),',,',',');                        
                    else
                        intermediate_values = num2str(intermediate_values);
                    end
                    h.Data(indices(1),5) = {intermediate_values};
                else
                    h.Data(indices(1),5) = {''};
                end
            elseif indices(2)==2 || indices(2)==3 % Lower, upper
                if isnan(newData) || newData<=0 || (p.mustberound && newData~=round(newData))
                    h.Data(indices(1),indices(2)) = {oldData};
                else
                    if ~isempty(p.upperbound) && indices(2)==3 && newData > p.upperbound(indices(1))
                        h.Data(indices(1),indices(2)) = {oldData};
                    else
                        lowerbound = cell2mat(h.Data(indices(1),2));
                        upperbound = cell2mat(h.Data(indices(1),3));
                        if lowerbound>=upperbound
                            h.Data(indices(1),indices(2)) = {oldData};
                        else
                            ncase = cell2mat(h.Data(indices(1),4));
                            if ncase > 2
                                intermediate_values = linspace(lowerbound,upperbound,ncase);
                                intermediate_values = intermediate_values(2:end-1);
                                if p.mustberound
                                    intermediate_values = round(intermediate_values);
                                    intermediate_values = strrep(num2str(intermediate_values),'  ',',');
                                    intermediate_values = strrep(num2str(intermediate_values),',,',',');                        
                                else
                                    intermediate_values = num2str(intermediate_values);
                                end                                
                                h.Data(indices(1),5) = {intermediate_values};
                            else
                                h.Data(indices(1),5) = {''};
                            end
                        end
                    end
                end
            elseif indices(2)==5 % Intermediate value
                newData = str2num(newData);
                if ~isempty(newData) && sum(isnan(newData))==0
                    if sum(newData==sort(newData)) == length(newData)
                        lowerbound = cell2mat(h.Data(indices(1),2));
                        upperbound = cell2mat(h.Data(indices(1),3));
                        if newData(1) > lowerbound && newData(end) < upperbound
                            ncase = cell2mat(h.Data(indices(1),4));
                            if length(newData) == ncase-2
                                if p.mustberound && sum(newData==round(newData)) ~= length(newData)
                                    h.Data(indices(1),indices(2)) = {oldData};
                                end
                            else
                                h.Data(indices(1),indices(2)) = {oldData};
                            end
                        else
                            h.Data(indices(1),indices(2)) = {oldData};
                        end
                    else
                        h.Data(indices(1),indices(2)) = {oldData};
                    end
                else
                    h.Data(indices(1),indices(2)) = {oldData};
                end
            end
        end

        
        function [] = Solidphase_checkgenorder_initializemask(app)

            steps = cell2mat(app.SolidPhase_UITable.Data(:,end));
            ellipsoids = cell2mat(app.SolidPhase_UITable.Data(:,5));
            tube = cell2mat(app.SolidPhase_UITable.Data(:,6));
            amorph = cell2mat(app.SolidPhase_UITable.Data(:,7));
            webstructure = cell2mat(app.SolidPhase_UITable.Data(:,8));
            predshape = cell2mat(app.SolidPhase_UITable.Data(:,9));

            step_ellipsoids = steps(ellipsoids);
            uniquestep_ellipsoid = unique(step_ellipsoids);
            step_tube = steps(tube);
            step_amorph = steps(amorph);
            step_webstructure = steps(webstructure);
            step_predshape = steps(predshape);

            app.generationstep_correct = true;
            if isempty(app.preexistingphases_array) && ~isempty(step_amorph) && min(step_amorph)==1 % Amorph can not be first generation
                app.generationstep_correct = false;
            end
            steps_newphases = steps;
            steps_newphases(steps_newphases==0)=[];
            if min(steps_newphases)~=1 % Step order should start at 1
                app.generationstep_correct = false;
            end
            if max(abs(diff(unique(steps))))~=1 % Step order should be continuous 
                app.generationstep_correct = false;
            end
            if ~isempty(intersect(uniquestep_ellipsoid,step_tube)) || ~isempty(intersect(uniquestep_ellipsoid,step_amorph)) || ~isempty(intersect(uniquestep_ellipsoid,step_webstructure)) || ~isempty(intersect(uniquestep_ellipsoid,step_predshape))...
                    || ~isempty(intersect(step_tube,step_amorph))  || ~isempty(intersect(step_tube,step_webstructure)) || ~isempty(intersect(step_tube,step_predshape))...
                    || ~isempty(intersect(step_amorph,step_webstructure)) || ~isempty(intersect(step_amorph,step_predshape))...
                    || ~isempty(intersect(step_webstructure,step_predshape))
                app.generationstep_correct = false;
            end
            if app.generationstep_correct
                app.GenerationsteperrorLabel.Visible = 'off';
            else
                app.GenerationsteperrorLabel.Visible = 'on';
            end            

            % Phase-Phase interactions
            app.has_masks = false;
            app.maskparsBA = [];
            if app.generationstep_correct
                tmp = steps;
                tmp(1)=[]; % Remove background
                if length(unique(tmp)) > 1
                    app.has_masks = true;
                    app.MasksarenaLabel.Visible = 'off';
                    tmp(tmp==min(tmp)) = [];

                    phasename = app.SolidPhase_UITable.Data(:,3);
                    Lia = ismember(steps,tmp);
                    idx = find(Lia);
                    
                    app.PhaseBDropDown.Items = phasename(idx);
                    app.PhaseBDropDown.Enable = 'on';                    
                
                    app.Initialize_all_mask;
                    %app.PhaseBDropDownValueChanged;
                else
                    app.MasksarenaLabel.Text = 'n/a with your phases and generation order';
                    app.MasksarenaLabel.Visible = 'on';
                    app.PhaseADropDown.Items = {};
                    app.PhaseADropDown.Enable = 'off';                
                    app.PhaseBDropDown.Items = {};
                    app.PhaseBDropDown.Enable = 'off';
                    app.A_morhpology_Label.Text = 'n/a';
                    app.B_morhpology_Label.Text = 'n/a';
                    app.CaseBisEllipsoidPanel.Enable = 'off';
                    app.CaseBsmorphologyisamorphPanel.Enable = 'off';
                    app.CaseBsmorphologyisShapePanel.Enable = 'off';
                end
            else
                app.MasksarenaLabel.Text = 'Fix first the generation order.';
                app.MasksarenaLabel.Visible = 'on';
                app.PhaseADropDown.Items = {};
                app.PhaseADropDown.Enable = 'off';                
                app.PhaseBDropDown.Items = {};
                app.PhaseBDropDown.Enable = 'off';
                app.A_morhpology_Label.Text = 'n/a';
                app.B_morhpology_Label.Text = 'n/a';      
                app.CaseBisEllipsoidPanel.Enable = 'off';
                app.CaseBsmorphologyisamorphPanel.Enable = 'off';
                app.CaseBsmorphologyisShapePanel.Enable = 'off';
            end

        end
        
        function [] = Load_example(app,imported)
            %  Set all private properties
            for k = 1:length(imported.app_privateproperties)
                app.(imported.app_privateproperties(k).Name) = imported.app_privateproperties(k).Value;
            end

            %  Set all public properties to setup the GUI
            for k = 1:length(imported.app_publicproperties)
                app.(imported.app_publicproperties(k).Name).Visible = imported.app_publicproperties(k).Visible;
                app.(imported.app_publicproperties(k).Name).Enable = imported.app_publicproperties(k).Enable;
                if strcmp(imported.app_publicproperties(k).Type,'uilabel')
                    app.(imported.app_publicproperties(k).Name).Text = imported.app_publicproperties(k).Text;
                    app.(imported.app_publicproperties(k).Name).FontColor = imported.app_publicproperties(k).FontColor;
                elseif strcmp(imported.app_publicproperties(k).Type,'uitable')
                    app.(imported.app_publicproperties(k).Name).ColumnName = imported.app_publicproperties(k).ColumnName;
                    app.(imported.app_publicproperties(k).Name).ColumnEditable = imported.app_publicproperties(k).ColumnEditable;
                    app.(imported.app_publicproperties(k).Name).Data = imported.app_publicproperties(k).Data;
                    %app.(imported.app_publicproperties(k).Name).StyleConfigurations = imported.app_publicproperties(k).StyleConfigurations;
                elseif strcmp(imported.app_publicproperties(k).Type,'uicheckbox')
                    app.(imported.app_publicproperties(k).Name).Value = imported.app_publicproperties(k).Value;
                elseif strcmp(imported.app_publicproperties(k).Type,'uinumericeditfield')
                    app.(imported.app_publicproperties(k).Name).Limits = imported.app_publicproperties(k).Limits; 
                    app.(imported.app_publicproperties(k).Name).Value = imported.app_publicproperties(k).Value;        
                    app.(imported.app_publicproperties(k).Name).RoundFractionalValues = imported.app_publicproperties(k).RoundFractionalValues;                         
                elseif strcmp(imported.app_publicproperties(k).Type,'uidropdown')
                    app.(imported.app_publicproperties(k).Name).Items = imported.app_publicproperties(k).Items;
                    app.(imported.app_publicproperties(k).Name).Value = imported.app_publicproperties(k).Value;
                elseif strcmp(imported.app_publicproperties(k).Type,'uiimage')
                    app.(imported.app_publicproperties(k).Name).ImageSource = imported.app_publicproperties(k).ImageSource;
                end
            end

            % How to do this as above? StyleConfigurations is read only!
            [n,~] = size(app.SolidPhase_UITable.Data);
            for k=1:n
                s = uistyle('BackgroundColor',app.color_phase(k,:));
                addStyle(app.SolidPhase_UITable,s,'cell',[k,4]);    
            end
        end
        
        function [] = Calculate_numbergeneration_perstudy(app)
            nvalues = cell2mat(app.Sweepparameter_UITable.Data(:,end));
            ids = [1:1:length(nvalues)];
            [n_study ,~] = size(app.Study_UITable.Data);

            error_linkedvalues = false;
            error_alllinkedvalues = false;

            for k = 1:n_study
                k_error_linkedvalues = false;
                k_error_alllinkedvalues = false;

                allvalues_id = app.Study_UITable.Data(k,3);
                allvalues_id = str2num(cell2mat(allvalues_id));
                linkedvalues_id = app.Study_UITable.Data(k,4);
                linkedvalues_id = str2num(cell2mat(linkedvalues_id));
                n_default = 1;
                
                if isempty(linkedvalues_id)
                    n_linked = 1;
                else
                    n_values = [];
                    for kk = 1:length(linkedvalues_id)
                        row = find(ids == linkedvalues_id(kk));
                        n_values = [n_values nvalues(row)];
                    end
                    if isempty(n_values)
                        n_linked = 1;
                    else
                        if length(unique(n_values))==1
                            n_linked = n_values(1);
                        else
                            error_linkedvalues = true;
                            k_error_linkedvalues = true;
                        end
                    end
                end
                if isempty(allvalues_id)
                    n_all = 1;
                else
                    n_values = [];
                    for kk = 1:length(allvalues_id)
                        row = find(ids == allvalues_id(kk));
                        n_values = [n_values nvalues(row)];
                    end
                    if isempty(n_values)
                        n_all = 1;
                    else
                        n_all = prod(n_values);
                    end
                end
                
                if ~isempty(allvalues_id) && ~isempty(linkedvalues_id)
                    % Check no shared id
                    C = intersect(allvalues_id,linkedvalues_id);
                    if ~isempty(C)
                        error_alllinkedvalues = true;
                        k_error_alllinkedvalues = true;
                    end
                end

                if ~k_error_linkedvalues && ~k_error_alllinkedvalues
                    n_reproducibility = cell2mat(app.Study_UITable.Data(k,end-1));
                    n_generation = n_default * n_linked * n_all * n_reproducibility;
                    app.Study_UITable.Data(k,end) = {n_generation};
                else
                    app.Study_UITable.Data(k,end) = {'Error'};
                end
            end

            if ~error_linkedvalues && ~error_alllinkedvalues
                app.Error_linkedparameters.Visible = 'off';
                app.Error_linkeallparameters.Visible = 'off';
                total = sum(cell2mat(app.Study_UITable.Data(:,end)));
                app.TotalnumberofgenerationsLabel.Text = ['Total number of generations: ' num2str(total)];
                app.parameter_sweep_is_valid = true;
            else
                app.TotalnumberofgenerationsLabel.Text = 'Total number of generations: n/a';
                if error_linkedvalues
                    app.Error_linkedparameters.Visible = 'on';
                else
                    app.Error_linkedparameters.Visible = 'off';
                end
                if error_alllinkedvalues
                    app.Error_linkeallparameters.Visible = 'on';
                else
                    app.Error_linkeallparameters.Visible = 'off';
                end
                app.parameter_sweep_is_valid = false;
            end
        end
        
        function [] = Initialize_all_mask(app)
            phasename = app.SolidPhase_UITable.Data(:,3);
            phaselabels = cell2mat(app.SolidPhase_UITable.Data(:,2));
            app.maskparsBA = [];
            app.mask_is_initializing = true;
            if app.has_masks
                n = length(app.PhaseBDropDown.Items);
                for k = 1:n
                    app.PhaseBDropDown.Value = app.PhaseBDropDown.Items(k);
                    app.PhaseBDropDownValueChanged;
                    nn = length(app.PhaseADropDown.Items);
    
                    idxB = find(ismember(phasename,app.PhaseBDropDown.Value));
                    %vals = cell2mat(app.SolidPhase_UITable.Data(idxB,5:9));
                    %idmorphology = find(vals);
                    %morphologyname = app.SolidPhase_UITable.ColumnName(5:9);

                    for kk = 1:nn
                        app.PhaseADropDown.Value = app.PhaseADropDown.Items(kk);

                        idxA = find(ismember(phasename,app.PhaseADropDown.Value));
                        %vals = cell2mat(app.SolidPhase_UITable.Data(idxB,5:9));
                        %idmorphology = find(vals);                        

                        app.PhaseADropDown.Value = app.PhaseADropDown.Items(kk);
                        app.maskparsBA(k,kk).PhaseB_name = app.PhaseBDropDown.Value;
                        app.maskparsBA(k,kk).PhaseB_morphology = app.B_morhpology_Label.Text;
                        app.maskparsBA(k,kk).PhaseB_label = phaselabels(idxB);
                        %app.maskparsBA(k,kk).PhaseB_idx = k;

                        app.maskparsBA(k,kk).PhaseA_name = app.PhaseADropDown.Value;
                        app.maskparsBA(k,kk).PhaseA_morphology = app.A_morhpology_Label.Text;
                        %app.maskparsBA(k,kk).PhaseA_idx = kk;
                        app.maskparsBA(k,kk).PhaseA_label = phaselabels(idxA);
                        app.maskparsBA(k,kk).p = [];
                        app.PhaseADropDownValueChanged;
                    end
                end
            end
            app.mask_is_initializing = false;
        end
        
        function [] = Create_inputs_file(app)
            app.study = [];
            
            % % Names of all parameters that can be swept

            % Dimension
            dir_names = app.Dimension_UITable.Data(:,1);

            % Mask
            mask_particles_atleast_names = [];
            mask_particles_atleast_idBA = [];
            mask_particles_notmorethan_names = [];
            mask_particles_notmorethan_idBA = [];            
            [szB,szA] = size(app.maskparsBA);
            kk=0;
            for kB = 1:szB
                for kA = 1:szA
                    if ~isempty(app.maskparsBA(kB,kA).p)
                        Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                        if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web')) && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule
                            row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                            mask_particles_atleast_names = [mask_particles_atleast_names; row(1)];
                            kk=kk+1;
                            mask_particles_atleast_idBA(kk,1) = kB;
                            mask_particles_atleast_idBA(kk,2) = kA;
                        end
                    end
                end
            end
            kk=0;
            for kB = 1:szB
                for kA = 1:szA
                    if ~isempty(app.maskparsBA(kB,kA).p)
                        Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                        if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web')) && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule
                            row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                            mask_particles_notmorethan_names = [mask_particles_notmorethan_names; row(1)];
                            kk=kk+1;
                            mask_particles_notmorethan_idBA(kk,1) = kB;
                            mask_particles_notmorethan_idBA(kk,2) = kA;
                        end
                    end
                end
            end


            if app.has_parameter_sweeping
                pars_sweep_name = app.Sweepparameter_UITable.Data(:,1);
                pars_sweep_name_in_file = app.Sweepparameter_UITable.Data(:,2);
                pars_sweep_id = cell2mat(app.Sweepparameter_UITable.Data(:,3));
                studies = app.Study_UITable.Data;
                [n_study,~] = size(studies);
                for k_study = 1:n_study
                    n_total_gen = cell2mat(studies(k_study,6));
                    n_repro = cell2mat(studies(k_study,5));
                    n_unique_gen = n_total_gen / n_repro;
                    pars_allvalues_id = str2num(cell2mat(studies(k_study,3)));
                    n_allvalues = length(pars_allvalues_id);
                    pars_linkedvalues_id = str2num(cell2mat(studies(k_study,4)));


                    % 1) Get all the name and value for all parameter with "All values"
                    pars_allcombination = [];
                    if ~isempty(pars_allvalues_id)
                        res = ismember(pars_sweep_id,pars_allvalues_id);
                        names = pars_sweep_name(res);
                        if ~isempty(names)
                            for k=1:length(names)
                                pars_allcombination(k).name = names(k);

                                % % ADD SPECIFICALLY FOR EACH PARAMATER HERE
                                % Direction tab
                                idx = find(ismember(dir_names,pars_allcombination(k).name));
                                if ~isempty(idx)
                                    row = app.Dimension_UITable.Data(idx,:);
                                end

                                % Mask
                                idx = find(ismember(mask_particles_atleast_names,pars_allcombination(k).name));
                                if ~isempty(idx)
                                    idxB = mask_particles_atleast_idBA(idx,1);
                                    idxA = mask_particles_atleast_idBA(idx,2);
                                    row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                                end
                                idx = find(ismember(mask_particles_notmorethan_names,pars_allcombination(k).name));
                                if ~isempty(idx)
                                    idxB = mask_particles_notmorethan_idBA(idx,1);
                                    idxA = mask_particles_notmorethan_idBA(idx,2);
                                    row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                                end                                
                    

                                lowerbound = cell2mat(row(2));
                                upperbound = cell2mat(row(3));
                                n_val = cell2mat(row(4));
                                if n_val > 2
                                    intermediate = str2num(cell2mat(row(5)));
                                    pars_allcombination(k).values = [lowerbound intermediate upperbound];
                                else
                                    pars_allcombination(k).values = [lowerbound upperbound];
                                end
                            end
                        end

                        % Get all the combinations
                        [pars_allcombination] = Combination_all(pars_allcombination);        
                    end

                    % 2) Get all the name and value for all parameter with "Linked values" 
                    par_linked = [];
                    if ~isempty(pars_linkedvalues_id)
                        res = ismember(pars_sweep_id,pars_linkedvalues_id);
                        names = pars_sweep_name(res);
                        if ~isempty(names)
                            for k=1:length(names)
                                par_linked(k).name = names(k);

                                % % ADD SPECIFICALLY FOR EACH PARAMATER HERE
                                % Direction tab
                                idx = find(ismember(dir_names,par_linked(k).name));
                                if ~isempty(idx)
                                    row = app.Dimension_UITable.Data(idx,:);
                                end

                                % Mask
                                idx = find(ismember(mask_particles_atleast_names,par_linked(k).name));
                                if ~isempty(idx)
                                    idxB = mask_particles_atleast_idBA(idx,1);
                                    idxA = mask_particles_atleast_idBA(idx,2);
                                    row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                                end
                                idx = find(ismember(mask_particles_notmorethan_names,par_linked(k).name));
                                if ~isempty(idx)
                                    idxB = mask_particles_notmorethan_idBA(idx,1);
                                    idxA = mask_particles_notmorethan_idBA(idx,2);
                                    row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                                end    

                                lowerbound = cell2mat(row(2));
                                upperbound = cell2mat(row(3));
                                n_val = cell2mat(row(4));
                                if n_val > 2
                                    intermediate = str2num(cell2mat(row(5)));
                                    par_linked(k).values = [lowerbound intermediate upperbound];
                                else
                                    par_linked(k).values = [lowerbound upperbound];
                                end

                            end
                        end

                    end
                    
                    % 3) Combine both pars_allcombination and par_linked
                    if isempty(par_linked) && isempty(pars_allcombination)
                        foo = 1;
                    elseif ~isempty(par_linked) && isempty(pars_allcombination)
                        for k=1:length(par_linked)
                            par_linked(k).combinedvalues = par_linked(k).values;
                        end
                    elseif isempty(par_linked) && ~isempty(pars_allcombination)
                        for k=1:length(pars_allcombination)
                            pars_allcombination(k).combinedvalues = pars_allcombination(k).allvalues;
                        end
                    elseif ~isempty(par_linked) && ~isempty(pars_allcombination)
                        n_perm = length(pars_allcombination(1).allvalues);
                        n_linked = length(par_linked(1).values);
                        for k=1:length(par_linked)
                            par_linked(k).combinedvalues = zeros(1,n_perm*n_linked);
                        end       
                        for k=1:length(pars_allcombination)
                            pars_allcombination(k).combinedvalues = zeros(1,n_perm*n_linked);
                        end

                        kidx=0;
                        for k1=1:n_perm
                            for k2=1:n_linked
                                kidx=kidx+1;
                                for k=1:length(par_linked)
                                    par_linked(k).combinedvalues(kidx) = par_linked(k).values(k2);
                                end                                   
                                for k=1:length(pars_allcombination)
                                    pars_allcombination(k).combinedvalues(kidx) = pars_allcombination(k).allvalues(k1);
                                end
                            end
                        end
                    end

                    % 4) Add parameters with default values
                    par_default = [];
                    if length(pars_sweep_id) ~= length(pars_allvalues_id) + length(pars_linkedvalues_id)
                        idx_default = find(~(ismember(pars_sweep_id,pars_allvalues_id) + ismember(pars_sweep_id,pars_linkedvalues_id)));
                        names = pars_sweep_name(idx_default);
                        for k=1:length(names)
                            par_default(k).name = names(k);

                            % % ADD SPECIFICALLY FOR EACH PARAMETER HERE
                            % Direction tab
                            idx = find(ismember(dir_names,par_default(k).name));
                            if ~isempty(idx)
                                row = app.Dimension_UITable.Data(idx,:);
                            end

                            % Mask
                            idx = find(ismember(mask_particles_atleast_names,par_default(k).name));
                            if ~isempty(idx)
                                idxB = mask_particles_atleast_idBA(idx,1);
                                idxA = mask_particles_atleast_idBA(idx,2);
                                row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                            end
                            idx = find(ismember(mask_particles_notmorethan_names,par_default(k).name));
                            if ~isempty(idx)
                                idxB = mask_particles_notmorethan_idBA(idx,1);
                                idxA = mask_particles_notmorethan_idBA(idx,2);
                                row = app.maskparsBA(idxB,idxA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                            end

                            default = cell2mat(row(6));
                            par_default(k).values = ones(1,n_unique_gen) * default;
                        end
                    end

                    % 5) Combine all parameters
                    allpars = par_default;
                    n = length(allpars);
                    for k=1:length(par_linked)
                        allpars(n+k).name = par_linked(k).name;
                        allpars(n+k).values = par_linked(k).combinedvalues;
                    end
                    n = length(allpars);
                    for k=1:length(pars_allcombination)
                        allpars(n+k).name = pars_allcombination(k).name;
                        allpars(n+k).values = pars_allcombination(k).combinedvalues;
                    end                    
                    
                    % 6) Reproducibility
                    n = length(allpars);
                    if n_repro>1
                        allpars(n+1).name = 'Reproducibility';
                        allpars(n+1).name_in_file = 'Repr#';
                        for k=1:n
                            tmp = allpars(k).values;
                            if strcmp(app.ReproducibilityDropDown.Value,'Do all unique cases, then do reproducibility: A B C then A B C')
                                allpars(n+1).values = ones(1,length(tmp));
                                for krepro=2:n_repro
                                    allpars(k).values = [allpars(k).values tmp];
                                    allpars(n+1).values = [allpars(n+1).values ones(1,length(tmp))*krepro];
                                end

                            elseif strcmp(app.ReproducibilityDropDown.Value,'Do all reproducibility realizations before moving to the next case:  A A then B B then C C')
                                allpars(n+1).values = [1:1:length(tmp)];
                                allpars(k).values = zeros(1,length(tmp)*n_repro);
                                kk=0;
                                for k1=1:length(tmp)
                                    for k2=1:n_repro
                                        kk=kk+1;
                                        allpars(k).values(kk) = tmp(k1);
                                        allpars(n+1).values = [allpars(n+1).values [1:1:length(tmp)]];
                                    end
                                end
                            end
                        end
                    end

                    % 7) Name in files
                    for k=1:n
                        idx = find(strcmp(pars_sweep_name,allpars(k).name));
                        allpars(k).name_in_file = char(pars_sweep_name_in_file(idx));
                    end

                    for k_gen = 1:n_total_gen
                        %% Parameters that can be swept
                        % All parameters that are swept
                        for k=1:length(allpars)
                            str = strrep(char(allpars(k).name),' ','_');
                            app.study(k_study).gen(k_gen).(str) = allpars(k).values(k_gen);
                        end                        

                        % All parameters that could be swept but are not
                        % Dimension tab
                        if ~app.Dimension_ParSweep_CheckBox.Value
                            for k=1:app.dimensionality
                                str = strrep(char(app.Dimension_UITable.Data(k,1)),' ','_');
                                app.study(k_study).gen(k_gen).(str) = cell2mat(app.Dimension_UITable.Data(k,2));
                            end
                        end

                        % Mask
                        [szB,szA] = size(app.maskparsBA);
                        kk=0;
                        for kB = 1:szB
                            for kA = 1:szA
                                if ~isempty(app.maskparsBA(kB,kA).p)
                                    Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                                    if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web')) && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule && ~app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep
                                        row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                                        str = strrep(char(row(1)),' ','_');
                                        app.study(k_study).gen(k_gen).(str) = cell2mat(row(2));
                                    end
                                    if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web')) && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule && ~app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep
                                        row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                                        str = strrep(char(row(1)),' ','_');
                                        app.study(k_study).gen(k_gen).(str) = cell2mat(row(2));
                                    end
                                end
                            end
                        end


                        %% These parameters can NOT be swept
                        % Dimension tab
                        app.study(k_study).gen(k_gen).dimension = app.dimensionality;
                        app.study(k_study).gen(k_gen).preexistingphases_array =  app.preexistingphases_array;
                        if ~isempty(app.preexistingphases_array)
                            app.study(k_study).gen(k_gen).preexistingphases_array_path =  app.preexistingphases_array_path;
                        end

                        % Number and morphology shape
                        app.study(k_study).gen(k_gen).number_phase_togenerate = app.number_phase_togenerate;
                        app.study(k_study).gen(k_gen).morphology = app.Dimension_UITable;

                        % Mask
                        for kB = 1:szB
                            for kA = 1:szA
                                if ~isempty(app.maskparsBA(kB,kA).p)
                                    labelB = app.maskparsBA(kB,kA).p.PhaseB_label;
                                    labelA = app.maskparsBA(kB,kA).p.PhaseA_label;

                                    Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                                    if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web'))
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).ParticlesBcanbegenerated = app.maskparsBA(kB,kA).p.ParticlesBcanbegenerated;
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Centroids_mustbe_distant_fromA_ATLEAST_applyrule = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule;
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule;
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).MaskParticle_Volumeoverlap_Value = app.maskparsBA(kB,kA).p.MaskParticle_Volumeoverlap_Value;
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).MaskParticle_multiplemaskrule = app.RuleformultiplemasksDropDown.Value;
                                    elseif strcmp(Bmorphology,'Amorph')
                                         app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Amorph_UsePhaseAasmask = app.maskparsBA(kB,kA).p.Amorph_UsePhaseAasmask;
                                         app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Amorph_multiplemaskrule = app.Amorph_RuleformultiplemasksDropDown.Value;
                                    elseif strcmp(Bmorphology,'Predetermined shape')
                                        app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Shape_BisoverwritingA = app.maskparsBA(kB,kA).p.Shape_BisoverwritingA;
                                        Amorphology = app.maskparsBA(kB,kA).PhaseA_morphology;
                                        if app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Shape_BisoverwritingA && strcmp(Amorphology,'Particles')
                                            app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Shape_RemoveParticle = app.maskparsBA(kB,kA).p.Shape_RemoveParticle;
                                            app.study(k_study).gen(k_gen).maskBA(labelB,labelA).Shape_RemoveParticle_threshold = app.maskparsBA(kB,kA).p.Shape_RemoveParticle_threshold;
                                        end
                                    end

                                end
                            end
                        end


                        %% CASE NAME
                        casename = ['S' num2str(k_study) 'G' num2str(k_gen)];
                        for k=1:n
                            val = allpars(k).values(k_gen);
                            if val==round(val)
                                strval = num2str(val);
                            else
                                strval = num2str(round(val,3));
                            end
                            casename = [casename '_' allpars(k).name_in_file '_' strval];
                        end
                        if n_repro>1
                            casename = [casename '_' allpars(n+1).name_in_file num2str(allpars(n+1).values(k_gen))];
                        end
                        app.study(k_study).gen(k_gen).casename = casename;


                        %% STUDY NAME
                        app.study(k_study).gen(k_gen).studyname = char(app.Study_UITable.Data(k_study,2));
                    end


                end

            else % No parameter sweeping
                
                % Dimension tab
                app.study(1).gen(1).dimension = app.dimensionality;
                for k=1:app.dimensionality
                    str = strrep(char(app.Dimension_UITable.Data(k,1)),' ','_');
                    app.study(1).gen(1).(str) = cell2mat(app.Dimension_UITable.Data(k,2));
                end

                app.study(1).gen(1).preexistingphases_array =  app.preexistingphases_array;
                if ~isempty(app.preexistingphases_array)
                    app.study(1).gen(1).preexistingphases_array_path =  app.preexistingphases_array_path;
                end                
                
                % Number and morphology shape
                app.study(1).gen(1).number_phase_togenerate = app.number_phase_togenerate;
                app.study(1).gen(1).morphology = app.Dimension_UITable;    

                % Mask
                [szB,szA] = size(app.maskparsBA);
                for kB = 1:szB
                    for kA = 1:szA
                        if ~isempty(app.maskparsBA(kB,kA).p)
                            % Particles
                            Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                            if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web'))
                                if app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule && ~app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep
                                    row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                                    str = strrep(char(row(1)),' ','_');
                                    app.study(1).gen(1).(str) = cell2mat(row(2));
                                end
                                if app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule && ~app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep
                                    row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                                    str = strrep(char(row(1)),' ','_');
                                    app.study(1).gen(1).(str) = cell2mat(row(2));
                                end
                            end
                        end
                    end
                end
                
                for kB = 1:szB
                    for kA = 1:szA
                        if ~isempty(app.maskparsBA(kB,kA).p)
                            labelB = app.maskparsBA(kB,kA).p.PhaseB_label;
                            labelA = app.maskparsBA(kB,kA).p.PhaseA_label;
                            Bmorphology = app.maskparsBA(kB,kA).PhaseB_morphology;
                            % Particles
                            if (strcmp(Bmorphology,'Particles') || strcmp(Bmorphology,'Tubes') || strcmp(Bmorphology,'Web'))
                                app.study(1).gen(1).maskBA(labelB,labelA).ParticlesBcanbegenerated = app.maskparsBA(kB,kA).p.ParticlesBcanbegenerated;
                                app.study(1).gen(1).maskBA(labelB,labelA).Centroids_mustbe_distant_fromA_ATLEAST_applyrule = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule;
                                app.study(1).gen(1).maskBA(labelB,labelA).Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule;
                                app.study(1).gen(1).maskBA(labelB,labelA).Particleoverlap_rule = app.maskparsBA(kB,kA).p.Particleoverlap_rule;
                                app.study(1).gen(1).maskBA(labelB,labelA).Particleoverlap_rule_apply = app.maskparsBA(kB,kA).p.Particleoverlap_rule_apply;
                                app.study(1).gen(1).MaskParticle_multiplemaskrule = app.RuleformultiplemasksDropDown.Value;
                            elseif strcmp(Bmorphology,'Amorph')
                                app.study(1).gen(1).maskBA(labelB,labelA).Amorph_UsePhaseAasmask = app.maskparsBA(kB,kA).p.Amorph_UsePhaseAasmask;
                                app.study(1).gen(1).maskBA(labelB,labelA).Amorph_multiplemaskrule = app.Amorph_RuleformultiplemasksDropDown.Value;
                            elseif strcmp(Bmorphology,'Predetermined shape')
                                app.study(1).gen(1).maskBA(labelB,labelA).Shape_BisoverwritingA = app.maskparsBA(kB,kA).p.Shape_BisoverwritingA;
                                Amorphology = app.maskparsBA(kB,kA).PhaseA_morphology;
                                if app.study(1).gen(1).maskBA(labelB,labelA).Shape_BisoverwritingA && strcmp(Amorphology,'Particles')
                                    app.study(1).gen(1).maskBA(labelB,labelA).Shape_RemoveParticle = app.maskparsBA(kB,kA).p.Shape_RemoveParticle;
                                    app.study(1).gen(1).maskBA(labelB,labelA).Shape_RemoveParticle_threshold = app.maskparsBA(kB,kA).p.Shape_RemoveParticle_threshold;
                                end

                            end
                        end
                    end
                end

            end

        end
        
        function [] = Update_Ellipsoids_mask_image(app)
            %keyboard
            app.MaskParticle_VolumeoverlapDropDown.Value
            %if strcmp(app.B_morhpology_Label.Text,'Particles')
                if ~app.Ellipsoids_Atleast_checkbox.Value && ~app.Ellipsoids_Notmorethan_CheckBox.Value
                    if strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Default_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Default_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap not A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Default_volumegoestoB.png';
                        end
                    elseif strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Not phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Default_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Default_volumegoestoA.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Default_volumegoestoB.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A (equirepartition)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Default_equiditribution.png';
                        end
                    elseif strcmp(app.ParticlesBaregeneratedDropDown.Value,'Both within A and not A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'overwrites A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Both_overwrite.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'does not overwrite A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Both_nooverwrite.png';
                        end
                    end

                elseif  app.Ellipsoids_Atleast_checkbox.Value && ~app.Ellipsoids_Notmorethan_CheckBox.Value
                     if strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Atleast_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Atleast_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap not A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Atleast_volumegoestoB.png';
                        end
                    elseif strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Not phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Atleast_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Atleast_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Atleast_volumegoestoB.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A (equirepartition)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Atleast_equidistribution.png';
                        end
                     end

                elseif  ~app.Ellipsoids_Atleast_checkbox.Value && app.Ellipsoids_Notmorethan_CheckBox.Value
                    if strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Phase A')
                         if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_NotMorethan_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_NotMorethan_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap not A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_NotMorethan_volumegoestoB.png';
                        end
                    elseif strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Not phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_NotMorethan_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_NotMorethan_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_NotMorethan_volumegoestoB.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A (equirepartition)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_NotMorethan_equidistribution.png';
                        end
                    end

                    
                elseif  app.Ellipsoids_Atleast_checkbox.Value && app.Ellipsoids_Notmorethan_CheckBox.Value
                    if strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Phase A')
                         if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Both_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap not A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Both_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap not A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_Within_Both_volumegoestoB.png';
                        end
                    elseif strcmp(app.ParticlesBaregeneratedDropDown.Value,'Within Not phase A')
                        if strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (rejection)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Both_cannotoverlap.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'cannot overlap A (truncated)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Both_truncated.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Both_volumegoestoB.png';
                        elseif strcmp(app.MaskParticle_VolumeoverlapDropDown.Value,'can overlap A (equirepartition)')
                            app.Ellipsoids_mask_image.ImageSource = 'Particles_NotWithin_Both_equidistribution.png';
                        end
                    end
                end

            %elseif strcmp(app.B_morhpology_Label.Text,'Tubes')


            %elseif strcmp(app.B_morhpology_Label.Text,'Web')

            %end


        end
        
        function [] = Update_Shape_mask_image(app)
            if app.Shape_BisoverwritingACheckBox.Value
                if strcmp(app.A_morhpology_Label.Text,'Particles')
                    if app.Shape_RemoveParticle_CheckBox.Value
                        app.Mask_Shape_Image.ImageSource = 'Shape_overwritting_particle_allornothing.png';
                    else
                        app.Mask_Shape_Image.ImageSource = 'Shape_overwritting_particle_truncation.png';
                    end
                else
                    app.Mask_Shape_Image.ImageSource = 'Shape_overwritting.png';
                end
            else
                if strcmp(app.A_morhpology_Label.Text,'Particles')
                    app.Mask_Shape_Image.ImageSource = 'Shape_noverwritting_particle.png';
                else
                    app.Mask_Shape_Image.ImageSource = 'Shape_nooverwritting.png';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Need to find files in \src\Microstructure_generation\Ellipsoids_stochastic\Examples\
            try fullPath = mfilename('fullpath');
                [appFolder, ~, ~] = fileparts(fullPath);
                listing = dir(fullfile(appFolder,'Examples'));

                % Get all the examples .mat
                example_name = [];
                n = 0;
                for k=1:length(listing)
                    if ~listing(k).isdir
                        [~,name,ext] = fileparts(listing(k).name);
                        if strcmp(ext,'.mat')
                            example_name = [example_name; {name}];
                            n=n+1;
                            app.Examples(n).parameterpath = fullfile(listing(1).folder,[name '.mat']);
                        end
                    end
                end

                if ~isempty(example_name)
                    app.ExamplesDropDown.Items = example_name;
                    app.ImportexampleButton.Enable = 'on';
                    for k=1:length(example_name)
                        % Search for companion txt file
                        fullpath = fullfile(listing(1).folder,[char(example_name(k)) '.txt']);
                        if exist(fullpath)==2
                            fileID = fopen(fullpath, 'r');
                            fileContent = fscanf(fileID, '%c');
                            fclose(fileID);
                            app.Examples(k).description = fileContent;
                        else
                            app.Examples(k).description = [];
                        end

                        % Search for companion png file
                        fullpath = fullfile(listing(1).folder,[char(example_name(k)) '.png']);
                        if exist(fullpath)==2
                            app.Examples(k).picturepath = fullpath;
                        else
                            app.Examples(k).picturepath = [];
                        end
                    end
                    app.ExamplesDropDownValueChanged;
                else
                    app.ExamplesDropDown.Items = {};
                    app.ImportexampleButton.Enable = 'off';
                    app.Example_DescriptionnoneLabel.Text = 'Description: none';
                    app.Example_Image.Visible = 'off';
                end

            catch ME
                app.ExamplesDropDown.Items = {};
                app.ImportexampleButton.Enable = 'off';
                app.Example_DescriptionnoneLabel.Text = 'Could not find examples, if any (look at \src\Microstructure_generation\Ellipsoids_stochastic\Examples\';
                app.Example_Image.Visible = 'off';
            end            


            % Initialize
            app.DimensionalityDropDownValueChanged;
        end

        % Value changed function: DimensionalityDropDown
        function DimensionalityDropDownValueChanged(app, event)
            % Initialize all

            % Parameter sweeping
            app.Dimension_ParSweep_CheckBox.Value = false;
            app.has_parameter_sweeping = 0;

            app.preexistingphases_array_path = [];
            app.preexistingphases_array = [];
            app.preexistingphases_labels = [];
            app.preexistingphases_n = 0;
            app.ImportedfilenoneLabel.Text = 'Imported file: none';
            app.ImportedfilenoneLabel.Tooltip = '';
            app.LengthanddimensionalitynaLabel.Text = 'Length and dimensionality: n/a';
            app.Labelsvolumefractionsna.Text = 'Labels (volume fractions): n/a';
            app.VisualizeButton.Enable = 'off';
           
            % Dimension
            value = app.DimensionalityDropDown.Value;
            if strcmp(value,'2D')
                app.dimensionality = 2;
            elseif strcmp(value,'3D')
                app.dimensionality = 3;
            end
            app.Dimension_ParSweep_CheckBoxValueChanged;

            % Number of phase
            app.NumberofphasesEditFieldValueChanged;


        end

        % Value changed function: Dimension_ParSweep_CheckBox
        function Dimension_ParSweep_CheckBoxValueChanged(app, event)
            value = app.Dimension_ParSweep_CheckBox.Value;
            if value
                app.has_parameter_sweeping = app.has_parameter_sweeping + app.dimensionality;
                ColumnName = {'Parameter','Lower bound','Upper bound','#Values','Intermediate values','Default value'};
                ColumnEditable = [false, true, true, true, true, true];
                if isempty(app.preexistingphases_array)
                    app.DomainSweep_CropLabel.Visible = 'off';
                    if app.dimensionality==2
                        Data = [{'FOV direction 1 length'} {50} {100} {4} {'67, 83'} {100};
                            {'FOV direction 2 length'} {50} {100} {4} {'67, 83'} {100}];
                    elseif app.dimensionality==3
                        Data = [{'FOV direction 1 length'} {50} {100} {4} {'67, 83'} {100};
                            {'FOV direction 2 length'} {50} {100} {4} {'67, 83'} {100};
                            {'FOV direction 3 length'} {50} {100} {4} {'67, 83'} {100}];
                    end
                else
                    app.DomainSweep_CropLabel.Visible = 'on';
                    sz = size(app.preexistingphases_array);
                    if app.dimensionality==2
                        Data = [{'FOV direction 1 length'} {50} {sz(1)} {4} {'67, 83'} {sz(1)};
                            {'FOV direction 2 length'} {50} {sz(2)} {4} {'67, 83'} {sz(2)}];
                    elseif app.dimensionality==3
                        Data = [{'FOV direction 1 length'} {50} {sz(1)} {4} {'67, 83'} {sz(1)};
                            {'FOV direction 2 length'} {50} {sz(2)} {4} {'67, 83'} {sz(2)};
                            {'FOV direction 3 length'} {50} {sz(3)} {4} {'67, 83'} {sz(3)}];
                    end
                end
            else
                app.DomainSweep_CropLabel.Visible = 'off';
                app.has_parameter_sweeping = max([0, app.has_parameter_sweeping - app.dimensionality]);
                ColumnName = {'Parameter','Value'};
                if isempty(app.preexistingphases_array)
                    ColumnEditable = [false true];
                    if app.dimensionality==2
                        Data = [{'FOV direction 1 length'} {100};
                            {'FOV direction 2 length'} {100}];
                    elseif app.dimensionality==3
                        Data = [{'FOV direction 1 length'} {100};
                            {'FOV direction 2 length'} {100};
                            {'FOV direction 3 length'} {100}];
                    end
                else
                    ColumnEditable = [false false];
                    sz = size(app.preexistingphases_array);
                    if app.dimensionality==2
                        Data = [{'FOV direction 1 length'} {sz(1)};
                            {'FOV direction 2 length'} {sz(2)}];
                    elseif app.dimensionality==3
                        Data = [{'FOV direction 1 length'} {sz(1)};
                            {'FOV direction 2 length'} {sz(2)};
                            {'FOV direction 3 length'} {sz(3)}];
                    end
                end
            end
            app.Dimension_UITable.Data = Data;
            app.Dimension_UITable.ColumnName = ColumnName;
            app.Dimension_UITable.ColumnEditable = ColumnEditable;     
            app.ParametersweepTabButtonDown;
        end

        % Value changed function: NumberofphasesEditField
        function NumberofphasesEditFieldValueChanged(app, event)
            number_phase_togenerate = app.NumberofphasesEditField.Value;
            app.number_phase_togenerate = number_phase_togenerate;

            number_phase = app.preexistingphases_n + number_phase_togenerate;

            phase_idx = num2cell([1:1:number_phase]');
            
            phase_name = cell(number_phase,1);
            for k=1:number_phase
                phase_name(k,1) = {['Phase ' num2str(k)]};
            end
            for k=1:app.preexistingphases_n
                phase_name(k,1) = {['Pre-existing phase ' num2str(k)]};
            end

            Tube = num2cell(logical(zeros(number_phase,1)));
            Amorph = num2cell(logical(zeros(number_phase,1)));
            Presphape = num2cell(logical(zeros(number_phase,1)));
            Webstructure = num2cell(logical(zeros(number_phase,1)));

            ellipsoid = ones(number_phase,1);
            if ~isempty(app.preexistingphases_array)
                ellipsoid(1:app.preexistingphases_n) = 0;
            end
            ellipsoid = num2cell(logical(ellipsoid));

            if isempty(app.preexistingphases_array)
                genstep = phase_idx;
                phase_label = phase_idx;
            else
                genstep = num2cell([zeros(app.preexistingphases_n,1); [1:1:number_phase_togenerate]']);
                phase_label = num2cell([app.preexistingphases_labels; [1:1:number_phase_togenerate]'+double(max(app.preexistingphases_labels))]);
            end

            % Add background
            phase_idx = [0;phase_idx];
            phase_label = [0;phase_label];
            phase_name = [{'Background'};phase_name];
            ellipsoid = [{false};ellipsoid];
            Tube = [{false};Tube];
            Amorph = [{false};Amorph];
            Webstructure = [{false};Webstructure];
            Presphape = [{false};Presphape];
            genstep = [0;genstep];

            col_ = [[0 0 0];colororder; rand(100,3)];
            for k=1:number_phase+1
                if isnan(app.color_phase(k,1))
                    app.color_phase(k,:) = col_(k,:);
                end     
                s = uistyle('BackgroundColor',app.color_phase(k,:));
                addStyle(app.SolidPhase_UITable,s,'cell',[k,4]);                
            end
            
            app.SolidPhase_UITable.Data = [phase_idx phase_label phase_name cell(number_phase+1,1) ellipsoid Tube Amorph Webstructure Presphape genstep];
            %app.SolidPhase_UITableCellEdit;
            app.Solidphase_checkgenorder_initializemask;            

            %app.Initialize_all_mask;
        end

        % Button down function: ParametersweepTab
        function ParametersweepTabButtonDown(app, event)
            if app.has_parameter_sweeping > 0
                parnames = {};
                nvalues = [];

                % Dimension
                if app.Dimension_ParSweep_CheckBox.Value
                    parnames = [parnames; app.Dimension_UITable.Data(:,1)];
                    nvalues = [nvalues; app.Dimension_UITable.Data(:,4)];
                end

                % Mask
                if ~isempty(app.maskparsBA)
                    [szB,szA] = size(app.maskparsBA);
                    for kB = 1:szB
                        for kA = 1:szA
                            if ~isempty(app.maskparsBA(kB,kA).p)
                                if strcmp(app.maskparsBA(kB,kA).PhaseB_morphology,'Particles')
                                    if app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep
                                        row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                                        parnames = [parnames; row(1)];
                                        nvalues = [nvalues; row(4)];
                                    end
                                    if app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule && app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep
                                        row = app.maskparsBA(kB,kA).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                                        parnames = [parnames; row(1)];
                                        nvalues = [nvalues; row(4)];
                                    end
                                end
                            end
                        end
                    end
                end

                % Name in file
                if ~isempty(app.Sweepparameter_UITable.Data)
                    parnames_infile = app.Sweepparameter_UITable.Data(:,2);
                end
                if isempty(app.Sweepparameter_UITable.Data) ||  length(parnames)~=length(parnames_infile)
                    parnames_infile = {};
                    for k=1:length(parnames)
                        name_initial = char(parnames(k));
                        name_wo_space = strrep(name_initial,' ','');
                        parnames_infile(k,1) = {name_wo_space};
                    end                    
                else
                    parnames_infile = app.Sweepparameter_UITable.Data(:,2);
                end

                % Update table
                n_parameter = length(parnames);
                sweepid = [1:1:n_parameter]';
                app.Sweepparameter_UITable.Data = [parnames, parnames_infile, num2cell(sweepid), nvalues];

                % Initialize parameter study
                if isempty(app.Study_UITable.Data)
                    app.AddastudyButtonPushed;
                end
                app.Calculate_numbergeneration_perstudy;                

                [n,~] = size(app.Study_UITable.Data);
                if n==1
                    app.DeletestudiesButton.Enable = 'off';
                else
                    app.DeletestudiesButton.Enable = 'on';
                end

                app.Parameter_sweep_messageLabel.Text = 'You have setup at least one parameter with a parameter sweep';
                app.Sweepparameter_UITable.Enable = 'on';
                app.Study_UITable.Enable = 'on';
                app.AddastudyButton.Enable = 'on';
            else
                app.Parameter_sweep_messageLabel.Text = 'There is no parameter sweeping.';
                app.TotalnumberofgenerationsLabel.Text = 'Total number of generations: n/a';
                app.Sweepparameter_UITable.Data = [];
                app.Sweepparameter_UITable.Enable = 'off';
                app.Study_UITable.Data = [];
                app.Study_UITable.Enable = 'off';
                app.AddastudyButton.Enable = 'off';
                app.DeletestudiesButton.Enable = 'off';
                app.Error_linkedparameters.Visible = 'off';
                app.Error_linkeallparameters.Visible = 'off';
            end  
        end

        % Cell edit callback: Dimension_UITable
        function Dimension_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            
            if app.Dimension_ParSweep_CheckBox.Value
                p.upperbound = [];
                if ~isempty(app.preexistingphases_array)
                    p.upperbound = size(app.preexistingphases_array);
                end
                p.mustberound = true;
                app.Check_sweeping_parameter(app.Dimension_UITable, indices, newData, oldData, p);
            else
                if isnan(newData) || newData<=0 || round(newData)~=newData
                    app.Dimension_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            end          
        end

        % Cell edit callback: SolidPhase_UITable
        function SolidPhase_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            if indices(2)==2 % Label
                if indices(1)==1 || isnan(newData) || newData<1 || newData~=round(newData)
                    app.SolidPhase_UITable.Data(indices(1),indices(2)) = {oldData};
                end
                if ~isnan(newData)
                    labels = cell2mat(app.SolidPhase_UITable.Data(:,2));
                    if length(labels)~=length(unique(labels))
                        app.SolidPhase_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
            elseif indices(2) ==3
                phasename = app.SolidPhase_UITable.Data(:,3);
                if length(phasename) ~= length(unique(phasename))
                    app.SolidPhase_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            elseif indices(2) >=5 && indices(2) <=9
                app.SolidPhase_UITable.Data(indices(1),5) = {false};
                app.SolidPhase_UITable.Data(indices(1),6) = {false};
                app.SolidPhase_UITable.Data(indices(1),7) = {false};
                app.SolidPhase_UITable.Data(indices(1),8) = {false};           
                app.SolidPhase_UITable.Data(indices(1),9) = {false}; 
                app.SolidPhase_UITable.Data(indices(1),10)
                if cell2mat(app.SolidPhase_UITable.Data(indices(1),10))~=0
                    app.SolidPhase_UITable.Data(indices(1),indices(2)) = {true};
                end
            elseif indices(2) == 10
                if oldData==0 || isnan(newData) || newData<1 || newData~=round(newData)
                    app.SolidPhase_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            end 
            app.Solidphase_checkgenorder_initializemask;
            %app.Initialize_all_mask;            
        end

        % Cell selection callback: SolidPhase_UITable
        function SolidPhase_UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                if indices(2)==4 % color
                    c = uisetcolor;
                    if length(c)==3 % RGB
                        app.color_phase(indices(1),:) = c;
                        s = uistyle('BackgroundColor',app.color_phase(indices(1),:));
                        addStyle(app.SolidPhase_UITable,s,'cell',[indices(1),4]);
                    end
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if isempty(app.importpath)
                [FileName,PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',pwd);
            else
                [FileName,PathImport,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file (semantic segmentation)',app.importpath);
            end
            if ~FileName==0
                app.ImportedfilenoneLabel.Text = 'Loading file... please wait'; pause(0.1);
                [tmp,outcome] = function_load_tif([PathImport FileName]);
                if outcome
                    app.preexistingphases_array = tmp;
                    app.importpath = PathImport;

                    app.preexistingphases_array_path =  fullfile(PathImport,FileName);
                    app.ImportedfilenoneLabel.Text = ['Imported file: ' fullfile(PathImport,FileName)];
                    app.ImportedfilenoneLabel.Tooltip = app.ImportedfilenoneLabel.Text;
                   
                    sz = size(app.preexistingphases_array);
                    app.dimensionality = length(sz);

                    app.LengthanddimensionalitynaLabel.Text = ['Length and dimensionality: ' strrep(num2str(sz),'  ',' x ') ', dimensionality = ' num2str(app.dimensionality) 'D'];
                    
                    unis = unique(app.preexistingphases_array);
                    n = length(unis);
                    app.Labelsvolumefractionsna.Text = 'Labels (volume fractions):';
                    for k = 1:n
                        vf =  round(sum(sum(sum(app.preexistingphases_array==unis(k))))/numel(app.preexistingphases_array),3);
                        str = [' ' num2str(unis(k)) ' (' num2str(vf) ')'];
                        app.Labelsvolumefractionsna.Text = [app.Labelsvolumefractionsna.Text str];
                    end
                    unis(unis==0)=[];
                    app.preexistingphases_labels = unis;
                    app.preexistingphases_n = n - 1;

                    app.VisualizeButton.Enable = 'on';

                    app.Dimension_ParSweep_CheckBoxValueChanged;

                    % Number of phase
                    app.NumberofphasesEditFieldValueChanged;
                end
            end

        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            Microstructure_basic_visualization_interface(app.preexistingphases_array);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Set string of the dialog box
            str_dialogbox = 'Select the folder where you want to save the generation parameters';
            % Open dialog box to choose folder path
            if ~isempty(app.saveimport_parameter_path)
                res = uigetdir(app.saveimport_parameter_path,str_dialogbox);
            else
                res = uigetdir(pwd,str_dialogbox);
            end
           
            if res
                app.saveimport_parameter_path = res;
                % % UI

                % Get all public properties to setup the GUI and all private properties
                mc = ?Microstructure_generation_stochastic_v3;
                P = mc.PropertyList;
                k_private = 1;
                k_public = 1;
                for n = 1:numel(P)
                    if ~strcmp(P(n).Name,'TimingFields')

                        if strcmp(P(n).GetAccess,'private')
                            app_privateproperties(k_private).Name = P(n).Name;
                            if strcmp(P(n).Name,'saveimport_parameter_path')
                                app_privateproperties(k_private).Value = [];
                            else
                                app_privateproperties(k_private).Value = app.(P(n).Name);
                            end
                            k_private = k_private + 1;
                        else
                            obj = app.(P(n).Name);
                            if ~isempty(obj.Parent.Tag) && strcmp(obj.Parent.Tag,'save') && ~strcmp(obj.Type,'uigridlayout')
                                app_publicproperties(k_public).Name = P(n).Name;
                                app_publicproperties(k_public).Type = obj.Type;
                                app_publicproperties(k_public).Visible = obj.Visible;
                                app_publicproperties(k_public).Enable = obj.Enable;
                                if strcmp(obj.Type,'uilabel')
                                    app_publicproperties(k_public).Text = obj.Text;
                                    app_publicproperties(k_public).FontColor = obj.FontColor;
                                elseif strcmp(obj.Type,'uitable')
                                    app_publicproperties(k_public).ColumnName = obj.ColumnName;
                                    app_publicproperties(k_public).ColumnEditable = obj.ColumnEditable;
                                    app_publicproperties(k_public).Data = obj.Data;
                                    % app_publicproperties(k_public).StyleConfigurations = obj.StyleConfigurations;
                                elseif strcmp(obj.Type,'uicheckbox')
                                    app_publicproperties(k_public).Value = obj.Value;
                                elseif strcmp(obj.Type,'uinumericeditfield')
                                    app_publicproperties(k_public).Limits = obj.Limits; 
                                    app_publicproperties(k_public).Value = obj.Value;        
                                    app_publicproperties(k_public).RoundFractionalValues = obj.RoundFractionalValues;           
                                elseif strcmp(obj.Type,'uidropdown')
                                    app_publicproperties(k_public).Items = obj.Items;         
                                    app_publicproperties(k_public).Value = obj.Value;  
                                elseif strcmp(obj.Type,'uiimage')
                                     app_publicproperties(k_public).ImageSource = obj.ImageSource;    
                                end                                                      
                                k_public = k_public + 1;
                            end
                        end

                    end
                end
                % Export
                save(fullfile(res,[app.SaveParameter_FIlenameEditField.Value '.mat']),"app_privateproperties","app_publicproperties");

                % % Create input files
                app.Create_inputs_file;
                % save app.study
                study = app.study;
                save(fullfile(res,[app.SaveParameter_FIlenameEditField.Value '_pars.mat']),"study");
            end 
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            if isempty(app.saveimport_parameter_path)
                [FileName,PathImport,~] = uigetfile({'*.mat'},'Select a .mat file previously saved with this app',pwd);
            else
                [FileName,PathImport,~] = uigetfile({'*.mat'},'Select a .mat file previously saved with this app',app.saveimport_parameter_path);
            end
            if ~FileName==0
                app.saveimport_parameter_path = PathImport;
                imported = load(fullfile(PathImport,FileName));
                app.Load_example(imported);
            end  
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            idx = app.ExamplesDropDown.ValueIndex;

            if isempty(app.Examples(idx).description)
                app.Example_DescriptionnoneLabel.Text = 'Description: none';
            else
                app.Example_DescriptionnoneLabel.Text = app.Examples(idx).description;
            end
            if isempty(app.Examples(idx).picturepath)
                app.Example_Image.Visible = 'off';
            else
                app.Example_Image.ImageSource = app.Examples(idx).picturepath;
                app.Example_Image.Visible = 'on';
            end  
        end

        % Button pushed function: ImportexampleButton
        function ImportexampleButtonPushed(app, event)
            idx = app.ExamplesDropDown.ValueIndex;
            imported = load( app.Examples(idx).parameterpath);
            app.Load_example(imported);
        end

        % Button pushed function: AddastudyButton
        function AddastudyButtonPushed(app, event)
            [n_parameter,~] = size(app.Sweepparameter_UITable.Data);
            if ~isempty(app.Sweepparameter_UITable.Data)
                [n_study,~] = size(app.Study_UITable.Data);
            else
                n_study = 0;
            end
            if n_parameter == 1
                all_id = {'1'};
            else
                all_id = '';
                for k = 1:n_parameter-1
                    all_id = [all_id [num2str(k) ', ']];
                end
                all_id = [all_id [num2str(n_parameter)]];
            end
            n_study = n_study + 1;
            new_row =  [{n_study}, {['Study' num2str(n_study)]} all_id, {''}, {1}, {''}];
            if isempty(app.Study_UITable.Data)
                app.Study_UITable.Data = new_row;
            else
                app.Study_UITable.Data = [app.Study_UITable.Data; new_row];
            end
            app.Calculate_numbergeneration_perstudy;
            app.DeletestudiesButton.Enable = 'on';
        end

        % Button pushed function: DeletestudiesButton
        function DeletestudiesButtonPushed(app, event)
            app.Study_UITable.Data = app.Study_UITable.Data(1,:);
        end

        % Cell edit callback: Study_UITable
        function Study_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;            
            if indices(2)==5 % reproducibility
                if isnan(newData) || newData<1 || newData~=round(newData)
                    app.Study_UITable.Data(indices(1),indices(2)) = {oldData};
                end
            elseif indices(2)==3 % all values
                par_id = cell2mat(app.Sweepparameter_UITable.Data(:,3));
                if ~isempty(newData)
                    ids = str2num(newData);
                    if isempty(ids) || sum(ismember(ids,par_id))~=length(ids)
                        app.Study_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
            elseif indices(2)==4 % linked values
                par_id = cell2mat(app.Sweepparameter_UITable.Data(:,3));
                if ~isempty(newData)
                    ids = str2num(newData);
                    if isempty(ids) || sum(ismember(ids,par_id))~=length(ids) || length(ids)<2
                        app.Study_UITable.Data(indices(1),indices(2)) = {oldData};
                    end
                end
            end

            app.Calculate_numbergeneration_perstudy;    
        end

        % Button pushed function: ParameterSweep_help_Button
        function ParameterSweep_help_ButtonPushed(app, event)
            winopen('Parameter_sweep_example.png');
        end

        % Button down function: SaveimportparametersTab
        function SaveimportparametersTabButtonDown(app, event)
            app.SaveParameters_ErrormessageLabel.Visible = 'off';
            if app.has_parameter_sweeping > 0
                if app.parameter_sweep_is_valid
                    app.SaveParameters_ErrormessageLabel.Visible = 'off';
                    app.SaveButton.Enable = 'on';
                else
                    app.SaveParameters_ErrormessageLabel.Text = 'You cannot save your parameters: parameter sweeping study is incorrect';
                    app.SaveParameters_ErrormessageLabel.Visible = 'on';
                    app.SaveButton.Enable = 'off';
                end
            else
                app.SaveButton.Enable = 'on';
            end
        end

        % Value changed function: PhaseBDropDown
        function PhaseBDropDownValueChanged(app, event)
            value = app.PhaseBDropDown.Value;
            phasename = app.SolidPhase_UITable.Data(:,3);

            idxB = find(ismember(phasename,value));
            steps = cell2mat(app.SolidPhase_UITable.Data(:,end));
            genB = steps(idxB);

            vals = cell2mat(app.SolidPhase_UITable.Data(idxB,5:9));
            idmorphology = find(vals);
            morphologyname = app.SolidPhase_UITable.ColumnName(5:9);
            app.B_morhpology_Label.Text = char(morphologyname(idmorphology));

            if strcmp(char(morphologyname(idmorphology)),'Particles') || strcmp(char(morphologyname(idmorphology)),'Tubes') ||  strcmp(char(morphologyname(idmorphology)),'Web')
                app.CaseBisEllipsoidPanel.Enable = 'on';
                app.CaseBsmorphologyisamorphPanel.Enable = 'off';
                app.CaseBsmorphologyisShapePanel.Enable = 'off';
            elseif strcmp(char(morphologyname(idmorphology)),'Amorph')
                app.CaseBisEllipsoidPanel.Enable = 'off';
                app.CaseBsmorphologyisamorphPanel.Enable = 'on';
                app.CaseBsmorphologyisShapePanel.Enable = 'off';
            elseif strcmp(char(morphologyname(idmorphology)),'Predetermined shape')
                app.CaseBisEllipsoidPanel.Enable = 'off';
                app.CaseBsmorphologyisamorphPanel.Enable = 'off';     
                app.CaseBsmorphologyisShapePanel.Enable = 'on';
            end

            idxA = find(steps<genB);
            idxA(1) = []; % Remove background
            app.PhaseADropDown.Items = phasename(idxA);
            app.PhaseADropDown.Value = app.PhaseADropDown.Items(1);
            
            app.PhaseADropDown.Enable = 'on';       
            if ~app.mask_is_initializing
                app.PhaseADropDownValueChanged;
            end
        end

        % Value changed function: PhaseADropDown
        function PhaseADropDownValueChanged(app, event)
            value = app.PhaseADropDown.Value;
            phasename = app.SolidPhase_UITable.Data(:,3);

            idxB = find(ismember(phasename,value));

            vals = cell2mat(app.SolidPhase_UITable.Data(idxB,5:9));
            idmorphology = find(vals);
            
            if ~isempty(idmorphology)
                morphologyname = app.SolidPhase_UITable.ColumnName(5:9);
                app.A_morhpology_Label.Text = char(morphologyname(idmorphology));            
            else
                app.A_morhpology_Label.Text = 'unknown';   
            end         

            if ~isempty(app.maskparsBA)
                PhaseB_idx = app.PhaseBDropDown.ValueIndex;
                PhaseA_idx = app.PhaseADropDown.ValueIndex;
                if isempty(app.maskparsBA(PhaseB_idx,PhaseA_idx).p) % Initialize
                    app.maskparsBA(PhaseB_idx,PhaseA_idx).p.PhaseB_label = app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_label;
                    app.maskparsBA(PhaseB_idx,PhaseA_idx).p.PhaseA_label = app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseA_label;
                    if strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Particles') || strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Tubes') || strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Web')
                        app.ParticlesBaregeneratedDropDownValueChanged;
                        app.Ellipsoids_Atleast_checkbox.Value = 0;
                        app.Ellipsoids_Atleast_checkboxValueChanged;
                        app.Ellipsoids_Notmorethan_CheckBox.Value = 0;
                        app.Ellipsoids_Notmorethan_CheckBoxValueChanged;
                    elseif strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Amorph')
                        app.Amorph_UsePhaseAasmaskCheckBox.Value = 1;
                        app.Amorph_UsePhaseAasmaskCheckBoxValueChanged;
                    elseif strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Predetermined shape')
                        app.Shape_BisoverwritingACheckBox.Value = 1;
                        app.Shape_BisoverwritingACheckBoxValueChanged;
                    end
                    
                else % Update GUI without changing values
                    if strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Particles') || strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Tubes') || strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Web')
                        app.ParticlesBaregeneratedDropDown.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.ParticlesBcanbegenerated;
                        app.MaskParticle_VolumeoverlapDropDown.Items = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.MaskParticle_Volumeoverlap_Items;
                        app.MaskParticle_VolumeoverlapDropDown.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.MaskParticle_Volumeoverlap_Value;
                        
                        app.Ellipsoids_Atleast_checkbox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule;
                        if app.Ellipsoids_Atleast_checkbox.Value
                            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep;
                            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Enable = 'on';
                            app.Ellipsoids_Atleast_SweepUITable.Data = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_value;
                            app.Ellipsoids_Atleast_SweepUITable.ColumnName = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_ColumnName;
                            app.Ellipsoids_Atleast_SweepUITable.Enable = 'on';                            
                        else
                            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value = 0;
                            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Enable = 'off';
                            app.Ellipsoids_Atleast_SweepUITable.Data = [];
                            app.Ellipsoids_Atleast_SweepUITable.Enable = 'off';
                        end

                        app.Ellipsoids_Notmorethan_CheckBox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule;
                        if app.Ellipsoids_Notmorethan_CheckBox.Value
                            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep;
                            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Enable = 'on';
                            app.Ellipsoids_Notmorethan_SweepUITable.Data = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value;
                            app.Ellipsoids_Notmorethan_SweepUITable.ColumnName = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_ColumnName;
                            app.Ellipsoids_Notmorethan_SweepUITable.Enable = 'on';                            
                        else
                            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value = 0;
                            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Enable = 'off';
                            app.Ellipsoids_Notmorethan_SweepUITable.Data = [];
                            app.Ellipsoids_Notmorethan_SweepUITable.Enable = 'off';
                        end
                    elseif strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Amorph')
                        app.Amorph_UsePhaseAasmaskCheckBox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Amorph_UsePhaseAasmask;

                    elseif strcmp(app.maskparsBA(PhaseB_idx,PhaseA_idx).PhaseB_morphology,'Predetermined shape')
                        app.Shape_BisoverwritingACheckBox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_BisoverwritingA;
                        if strcmp(app.A_morhpology_Label.Text,'Particles') && app.Shape_BisoverwritingACheckBox.Value
                            app.Shape_RemoveParticle_CheckBox.Enable = 'on';
                            app.Shape_RemoveParticle_CheckBox.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_RemoveParticle;
                            if app.Shape_RemoveParticle_CheckBox.Value
                                app.Shape_RemoveParticle_x_EditField.Enable = 'on';
                                app.Shape_RemoveParticle_x_EditField.Value = app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_RemoveParticle_threshold;
                            else
                                app.Shape_RemoveParticle_x_EditField.Enable = 'off';
                            end
                        else
                            app.Shape_RemoveParticle_CheckBox.Enable = 'off';
                            app.Shape_RemoveParticle_CheckBox.Value = 0;
                            app.Shape_RemoveParticle_x_EditField.Enable = 'off';
                        end

                    end


                end
                
            end
            if app.CaseBisEllipsoidPanel.Enable
                app.Update_Ellipsoids_mask_image;
            elseif app.CaseBsmorphologyisShapePanel.Enable
                app.Update_Shape_mask_image;
            end

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            url = 'https://www.nlr.gov/';
            web(url)               
        end

        % Value changed function: ParticlesBaregeneratedDropDown
        function ParticlesBaregeneratedDropDownValueChanged(app, event)
            value = app.ParticlesBaregeneratedDropDown.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.ParticlesBcanbegenerated = value;
            if strcmp(value,'Within Phase A')
                app.MaskParticle_VolumeoverlapDropDown.Items = {'cannot overlap not A (rejection)',...
                    'cannot overlap not A (truncated)',...
                    'can overlap not A'};
                app.Ellipsoids_Atleast_checkbox.Enable = 'on';
                app.Ellipsoids_Notmorethan_CheckBox.Enable = 'on';                
            elseif strcmp(value,'Within Not phase A')
                if strcmp(app.A_morhpology_Label.Text,'Particles') || strcmp(app.A_morhpology_Label.Text,'Tubes')
                    app.MaskParticle_VolumeoverlapDropDown.Items = {'cannot overlap A (rejection)',...
                                                                    'cannot overlap A (truncated)',...
                                                                    'can overlap A',...
                                                                    'can overlap A (equirepartition)'};
                else
                    app.MaskParticle_VolumeoverlapDropDown.Items = {'cannot overlap A (rejection)',...
                        'cannot overlap A (truncated)',...
                        'can overlap A'};
                end

                app.Ellipsoids_Atleast_checkbox.Enable = 'on';
                app.Ellipsoids_Notmorethan_CheckBox.Enable = 'on';                  
            elseif strcmp(value,'Both within A and not A')
                app.MaskParticle_VolumeoverlapDropDown.Items = {'overwrites A',...
                                                                'does not overwrite A'};                
                app.Ellipsoids_Atleast_checkbox.Value = 0;
                app.Ellipsoids_Atleast_checkbox.Enable = 'off';
                app.Ellipsoids_Atleast_checkboxValueChanged;
                app.Ellipsoids_Notmorethan_CheckBox.Value = 0;
                app.Ellipsoids_Notmorethan_CheckBox.Enable = 'off';                
                app.Ellipsoids_Notmorethan_CheckBoxValueChanged;
            end
            app.MaskParticle_VolumeoverlapDropDownValueChanged;
        end

        % Value changed function: Ellipsoids_Atleast_checkbox
        function Ellipsoids_Atleast_checkboxValueChanged(app, event)
            value = app.Ellipsoids_Atleast_checkbox.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;            
            if value == 0
                app.Enterdasd2d1dLabel.Visible = 'off';
                if app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value
                    app.has_parameter_sweeping = max([0, app.has_parameter_sweeping - 1]);
                end
                app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Enable = 'off';
                app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value = 0;
                app.Ellipsoids_Atleast_SweepUITable.Enable = 'off';
                app.Ellipsoids_Atleast_SweepUITable.Data = [];
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule = false;
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_value = [];
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep = false;
                app.Update_Ellipsoids_mask_image;
            else
                if app.Ellipsoids_Notmorethan_CheckBox.Value
                    app.Enterdasd2d1dLabel.Visible = 'on';
                else
                    app.Enterdasd2d1dLabel.Visible = 'off';
                end
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_applyrule = true;
                app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Enable = 'on';
                app.has_parameter_sweeping = app.has_parameter_sweeping + 1; % Because next one will decrease it by one
                app.Ellipsoids_Atleast_DoaparametersweepCheckBoxValueChanged;
            end                        
        end

        % Value changed function: 
        % Ellipsoids_Atleast_DoaparametersweepCheckBox
        function Ellipsoids_Atleast_DoaparametersweepCheckBoxValueChanged(app, event)
            value = app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;          
            %parname = {[char(app.PhaseBDropDown.Value) '_centroids_at_least_far_from_' char(app.PhaseADropDown.Value)]};

            phasename = app.SolidPhase_UITable.Data(:,3);
            phaselabels = cell2mat(app.SolidPhase_UITable.Data(:,2));     
            idxA = find(ismember(phasename,app.PhaseADropDown.Value));
            idxB = find(ismember(phasename,app.PhaseBDropDown.Value));
            labelA = phaselabels(idxA);
            labelB = phaselabels(idxB);

            parname = {['MASK LabelB ' num2str(labelB) ' centroids at least far from LabelA ' num2str(labelA)]};

            if value
                app.has_parameter_sweeping = app.has_parameter_sweeping + 1;
                ColumnName = {'Parameter','Lower bound','Upper bound','#Values','Intermediate values','Default value'};
                ColumnEditable = [false, true, true, true, true, true];
                Data = [parname {5} {10} {3} {'7.5'} {10}]; 
            else
                app.has_parameter_sweeping = max([0, app.has_parameter_sweeping - 1]);
                ColumnName = {'Parameter','Value'};
                ColumnEditable = [false true];
                Data = [parname {5}];  
            end
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_doparametersweep = value;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_value = Data;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_ColumnName = ColumnName;
            app.Ellipsoids_Atleast_SweepUITable.Data = Data;
            app.Ellipsoids_Atleast_SweepUITable.ColumnName = ColumnName;
            app.Ellipsoids_Atleast_SweepUITable.ColumnEditable = ColumnEditable;     
            app.Ellipsoids_Atleast_SweepUITable.Enable = 'on';
            app.ParametersweepTabButtonDown;
            app.Update_Ellipsoids_mask_image;
        end

        % Cell edit callback: Ellipsoids_Atleast_SweepUITable
        function Ellipsoids_Atleast_SweepUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            if app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Value
                p.upperbound = [];
                p.mustberound = false;
                app.Check_sweeping_parameter(app.Ellipsoids_Atleast_SweepUITable, indices, newData, oldData, p);

                PhaseB_idx = app.PhaseBDropDown.ValueIndex;
                PhaseA_idx = app.PhaseADropDown.ValueIndex;    
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_ATLEAST_value = app.Ellipsoids_Atleast_SweepUITable.Data;
            else
                if isnan(newData) || newData<=0
                    app.Ellipsoids_Atleast_SweepUITable.Data(indices(1),indices(2)) = {oldData};
                end
            end 
        end

        % Value changed function: Ellipsoids_Notmorethan_CheckBox
        function Ellipsoids_Notmorethan_CheckBoxValueChanged(app, event)
            value = app.Ellipsoids_Notmorethan_CheckBox.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;   
            if value == 0
                app.Enterdasd2d1dLabel.Visible = 'off';
                if app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value
                    app.has_parameter_sweeping = max([0, app.has_parameter_sweeping - 1]);
                end                
                app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Enable = 'off';
                app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value = 0;
                app.Ellipsoids_Notmorethan_SweepUITable.Enable = 'off';
                app.Ellipsoids_Notmorethan_SweepUITable.Data = [];
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule = false;
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value = [];
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep = false;
                app.Update_Ellipsoids_mask_image;
            else
                if app.Ellipsoids_Atleast_checkbox.Value
                    app.Enterdasd2d1dLabel.Visible = 'on';
                else
                    app.Enterdasd2d1dLabel.Visible = 'off';
                end
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_applyrule = true;
                app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Enable = 'on';
                app.has_parameter_sweeping = app.has_parameter_sweeping + 1; % Because next one will decrease it by one
                app.Ellipsoids_Notmorethan_DoaparameterSweep_checkboxValueChanged;
            end    
        end

        % Value changed function: 
        % Ellipsoids_Notmorethan_DoaparameterSweep_checkbox
        function Ellipsoids_Notmorethan_DoaparameterSweep_checkboxValueChanged(app, event)
            value = app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;              

            %parname = {[char(app.PhaseBDropDown.Value) '_centroids_not_far_more_than_from_' char(app.PhaseADropDown.Value)]};

            phasename = app.SolidPhase_UITable.Data(:,3);
            phaselabels = cell2mat(app.SolidPhase_UITable.Data(:,2));     
            idxA = find(ismember(phasename,app.PhaseADropDown.Value));
            idxB = find(ismember(phasename,app.PhaseBDropDown.Value));
            labelA = phaselabels(idxA);
            labelB = phaselabels(idxB);
            parname = {['MASK LabelB ' num2str(labelB) ' centroidsnot far more than from LabelA ' num2str(labelA)]};

            if value
                app.has_parameter_sweeping = app.has_parameter_sweeping + 1;
                ColumnName = {'Parameter','Lower bound','Upper bound','#Values','Intermediate values','Default value'};
                ColumnEditable = [false, true, true, true, true, true];
                Data = [parname {5} {10} {3} {'7.5'} {10}]; 
            else
                app.has_parameter_sweeping = max([0, app.has_parameter_sweeping - 1]);
                ColumnName = {'Parameter','Value'};
                ColumnEditable = [false true];
                Data = [parname {5}];  
            end

            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_doparametersweep = value;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value = Data;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_ColumnName = ColumnName;
            app.Ellipsoids_Notmorethan_SweepUITable.Data = Data;
            app.Ellipsoids_Notmorethan_SweepUITable.ColumnName = ColumnName;
            app.Ellipsoids_Notmorethan_SweepUITable.ColumnEditable = ColumnEditable;     
            app.Ellipsoids_Notmorethan_SweepUITable.Enable = 'on';
            app.ParametersweepTabButtonDown;
            app.Update_Ellipsoids_mask_image;
        end

        % Cell edit callback: Ellipsoids_Notmorethan_SweepUITable
        function Ellipsoids_Notmorethan_SweepUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            if app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Value
                p.upperbound = [];
                p.mustberound = false;
                app.Check_sweeping_parameter(app.Ellipsoids_Notmorethan_SweepUITable, indices, newData, oldData, p);

                PhaseB_idx = app.PhaseBDropDown.ValueIndex;
                PhaseA_idx = app.PhaseADropDown.ValueIndex;    
                app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Centroids_mustbe_distant_fromA_NOTMORETHAN_value = app.Ellipsoids_Notmorethan_SweepUITable.Data;
            else
                if isnan(newData) || newData<=0
                    app.Ellipsoids_Notmorethan_SweepUITable.Data(indices(1),indices(2)) = {oldData};
                end
            end             
        end

        % Value changed function: RuleformultiplemasksDropDown
        function RuleformultiplemasksDropDownValueChanged(app, event)
            value = app.RuleformultiplemasksDropDown.Value;
            if strcmp(value,'AND')
                app.RuleformultiplemasksDropDown.Tooltip = {'Mask rules are multiplicative: e.g. you can set phase B (particles) to be close from phase A1 ("Within not A" + "not more than")',
                    'AND far from phase A2 ("Within not A" + "at least").'};
            elseif strcmp(value,'OR')
                app.RuleformultiplemasksDropDown.Tooltip = {'Mask rules are additive: e.g. you can set phase B (particles) to be close from phase A1 ("Within not A" + "not more than")',
                    'OR far from phase A2 ("Within not A" + "at least").'};
            end
        end

        % Value changed function: Shape_BisoverwritingACheckBox
        function Shape_BisoverwritingACheckBoxValueChanged(app, event)
            value = app.Shape_BisoverwritingACheckBox.Value;
            if value
                if strcmp(app.A_morhpology_Label.Text,'Particles')
                    app.Shape_RemoveParticle_CheckBox.Value = 0;
                    app.Shape_RemoveParticle_CheckBox.Enable = 'on';
                    app.Shape_RemoveParticle_CheckBoxValueChanged;
                else
                    app.Shape_RemoveParticle_CheckBox.Value = 0;
                    app.Shape_RemoveParticle_CheckBox.Enable = 'off';
                    app.Shape_RemoveParticle_x_EditField.Enable = 'off';                    
                    %app.Mask_Shape_Image.ImageSource = 'Shape_overwritting.png';
                end
            else
                % if strcmp(app.A_morhpology_Label.Text,'Particles')
                %     app.Mask_Shape_Image.ImageSource = 'Shape_noverwritting_particle.png';
                % else
                %     app.Mask_Shape_Image.ImageSource = 'Shape_nooverwritting.png';
                % end
                app.Shape_RemoveParticle_CheckBox.Value = 0;
                app.Shape_RemoveParticle_CheckBox.Enable = 'off';
                app.Shape_RemoveParticle_x_EditField.Enable = 'off';
            end

            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_BisoverwritingA = value;

            app.Update_Shape_mask_image;

        end

        % Value changed function: Shape_RemoveParticle_CheckBox
        function Shape_RemoveParticle_CheckBoxValueChanged(app, event)
            value = app.Shape_RemoveParticle_CheckBox.Value;
            if value
                app.Shape_RemoveParticle_x_EditField.Enable = 'on';      
                app.Shape_RemoveParticle_x_EditFieldValueChanged;
                %app.Mask_Shape_Image.ImageSource = 'Shape_overwritting_particle_allornothing.png';
            else
                app.Shape_RemoveParticle_x_EditField.Enable = 'off';      
                %app.Mask_Shape_Image.ImageSource = 'Shape_overwritting_particle_truncation.png';
            end   
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;            
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_RemoveParticle = value;
            app.Update_Shape_mask_image;
        end

        % Value changed function: MaskParticle_VolumeoverlapDropDown
        function MaskParticle_VolumeoverlapDropDownValueChanged(app, event)
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.MaskParticle_Volumeoverlap_Items = app.MaskParticle_VolumeoverlapDropDown.Items;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.MaskParticle_Volumeoverlap_Value = app.MaskParticle_VolumeoverlapDropDown.Value;
            app.Update_Ellipsoids_mask_image;
        end

        % Value changed function: Amorph_UsePhaseAasmaskCheckBox
        function Amorph_UsePhaseAasmaskCheckBoxValueChanged(app, event)
            value = app.Amorph_UsePhaseAasmaskCheckBox.Value;
            
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Amorph_UsePhaseAasmask = value;
        end

        % Value changed function: Shape_RemoveParticle_x_EditField
        function Shape_RemoveParticle_x_EditFieldValueChanged(app, event)
            value = app.Shape_RemoveParticle_x_EditField.Value;
            PhaseB_idx = app.PhaseBDropDown.ValueIndex;
            PhaseA_idx = app.PhaseADropDown.ValueIndex;            
            app.maskparsBA(PhaseB_idx,PhaseA_idx).p.Shape_RemoveParticle_threshold = value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Unified microstructure generation';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.MainTabGroup = uitabgroup(app.MainGridLayout);
            app.IntroductionTab = uitab(app.MainTabGroup);
            app.IntroductionTab.Title = 'Introduction';
            app.GridLayout5_5 = uigridlayout(app.IntroductionTab);
            app.MultishapeLabel = uilabel(app.GridLayout5_5);
            app.MultishapeLabel.Text = 'Multi-shape';
            app.MultiphaseLabel = uilabel(app.GridLayout5_5);
            app.MultiphaseLabel.Text = 'Multi-phase';
            app.Label_76 = uilabel(app.GridLayout5_5);
            app.Label_76.Text = 'You can generate volumes made of several phases.';
            app.EachdomainLabel = uilabel(app.GridLayout5_5);
            app.EachdomainLabel.Text = 'Each phase is assigned to a particular shape (ellipsoids, pancakes, tubes, web, amorph, or a predetermined shape). The same microstructure can combined different shapes from different phases.';
            app.MasksLabel = uilabel(app.GridLayout5_5);
            app.MasksLabel.Text = 'Masks';
            app.Label_77 = uilabel(app.GridLayout5_5);
            app.Label_77.Text = {'The generation of the current phase uses the phases already generated (or imported) as masks. There is one mask per pre-existing phase, then all combined together to produce the final mask. Masks are defined'; 'with customizable rules (e.g., within, not within, distant from at least/less than). Masks can be used to generate agglomerates. The user can choose in which order the phases are generated.'};
            app.OverlappingcontrolLabel = uilabel(app.GridLayout5_5);
            app.OverlappingcontrolLabel.Text = 'Overlapping control';
            app.Label_78 = uilabel(app.GridLayout5_5);
            app.Label_78.Text = {'Overlapping between instances from the same phase is controllable. It can be forbidden completely, or allowed up to a certain extent. Overlapping constraint can also be set to relax incrementally during the'; 'generation progresses, if the generation rate is slowing down too much. Overlapping can be used to overcome packing density limit.'};
            app.Label_79 = uilabel(app.GridLayout5_5);
            app.Label_79.Text = {'Stochastic and/or'; 'distance based'};
            app.Label_80 = uilabel(app.GridLayout5_5);
            app.Label_80.Text = {'All the algorithms used in this module rely on stochastic and/or distance-based methods. It can be slower than other approaches (e.g., generative AI) but it provides a very high control of all the features of the'; 'microstructure to generate. It is then relevant for parameter space analysis, especially when coupled with a parameter sweep analysis.'};
            app.FeaturecontrolLabel = uilabel(app.GridLayout5_5);
            app.FeaturecontrolLabel.Text = 'Feature control';
            app.HeterogeneityLabel = uilabel(app.GridLayout5_5);
            app.HeterogeneityLabel.Text = 'Heterogeneity';
            app.Label2_2 = uilabel(app.GridLayout5_5);
            app.Label2_2.Text = 'Each feature defining a shape (e.g., particle alignment) can be finely tuned.';
            app.Label2_3 = uilabel(app.GridLayout5_5);
            app.Label2_3.Text = 'Heterogeneity is controlled through 1) volume fractions (e.g., dual layer), 2) distributions (e.g., size and alignment distributions, with distributions that can vary spatially), and 3) masks.';
            app.UpscalingLabel = uilabel(app.GridLayout5_5);
            app.UpscalingLabel.Text = 'Upscaling';
            app.Label_81 = uilabel(app.GridLayout5_5);
            app.Label_81.Text = {'To save time, you can generate first on a coarse grid, then upscale the instances'' metrics, and then re-generate all the instances (for instance, with ellipsoidal particles, centroid location and diameter will be'; 'rescaled). This upscaling approach is better than upscaling directly the volume as it preserves the shapes and smooth interfaces. It can be setup in the generation workflow or performed later (Re-generate tab).'};
            app.IntegratedhelpLabel = uilabel(app.GridLayout5_5);
            app.IntegratedhelpLabel.Text = 'Integrated help';
            app.Label_82 = uilabel(app.GridLayout5_5);
            app.Label_82.Text = 'Figures directly placed in the GUI guide the user to setup correctly the most complicated features. As well, "Instructions" tabs provide essential texts to guide the user.';
            app.StreamlinedworkflowLabel = uilabel(app.GridLayout5_5);
            app.StreamlinedworkflowLabel.Text = {'Streamlined'; 'workflow'};
            app.Label_83 = uilabel(app.GridLayout5_5);
            app.Label_83.Text = 'The tabs should be always configured from top to bottom and from left to right. If you go backward, you may trigger subsequent tabs/parameters to be re-initialized.';
            app.InitializationLabel = uilabel(app.GridLayout5_5);
            app.InitializationLabel.Text = 'Initialization';
            app.QualityoflifeLabel = uilabel(app.GridLayout5_5);
            app.QualityoflifeLabel.Text = 'Quality of life';
            app.Label_84 = uilabel(app.GridLayout5_5);
            app.Label_84.Text = {'When you start the application, all the parameters and tabs are initialized to generate a very simple microstructure (you could go to the "Generate / Run" tab and start the generation without modifying'; 'any parameters).'};
            app.ExamplesLabel = uilabel(app.GridLayout5_5);
            app.ExamplesLabel.Text = 'Examples';
            app.LearningfromexamplesLabel = uilabel(app.GridLayout5_5);
            app.LearningfromexamplesLabel.Text = {'Learning from examples is easier. Go to the "Generate / Save import parameters" tab to see a list of curated examples (with illustrations). After selecting one example and read its description, you can import it:'; 'the whole app will be initialized with the associated parameters so that you can generate the selected example for yourself. You can also add your own examples easily.'};
            app.SaveyoursessionLabel = uilabel(app.GridLayout5_5);
            app.SaveyoursessionLabel.Text = 'Save your session';
            app.Label_85 = uilabel(app.GridLayout5_5);
            app.Label_85.Text = 'Go to the "Generate / Save import parameters" tab to save/import your current session at any moment. When you import a session, the whole GUI is re-loaded.';
            app.AnalysisLabel = uilabel(app.GridLayout5_5);
            app.AnalysisLabel.Text = 'Analysis';
            app.ParametersweepLabel = uilabel(app.GridLayout5_5);
            app.ParametersweepLabel.Text = 'Parameter sweep';
            app.Label_86 = uilabel(app.GridLayout5_5);
            app.Label_86.Text = 'Many parameters can be set with different values to investigate a large parameter space in a single setup. Parameters combinations can be finely tuned.';
            app.CharacterizationLabel = uilabel(app.GridLayout5_5);
            app.CharacterizationLabel.Text = 'Characterization';
            app.Label_87 = uilabel(app.GridLayout5_5);
            app.Label_87.Text = {'While the characterization module can be used for more in-depth analysis, you can configure simple characterization analysis (e.g., calculate effective transport) in the "Generate / Post-processing" tab.'; 'It works in tandem with the parameter sweep analysis as graphs with the swept parameters in the x-axis will be automatically generated.'};
            app.Label_88 = uilabel(app.GridLayout5_5);
            app.Label_88.Text = 'It is not just about microstructure generation';
            app.LitteratureLabel = uilabel(app.GridLayout5_5);
            app.LitteratureLabel.Text = 'Litterature';
            app.Label_89 = uilabel(app.GridLayout5_5);
            app.Label_89.Text = 'You can find peer-reviewed journal articles that have used MATBOX to generate microstructures in the "About / References" tab. Some of these papers are from our group, but many come from others groups.';
            app.WelcometotheLabel = uilabel(app.GridLayout5_5);
            app.WelcometotheLabel.Text = 'Welcome to the Unified Microstructure Generation MATBOX''s module. Below are summarized the module''s main features. Go to the "Instructions" tab to start. ';
            app.Label_90 = uilabel(app.GridLayout5_5);
            app.Label_90.Text = {'Well and ill-defined'; 'parameters'};
            app.Label_91 = uilabel(app.GridLayout5_5);
            app.Label_91.Text = {'There is no solution for some combination of parameters: e.g., spheres with a single particle size and no overlapping cannot go above their packing density limit. If the generation stalls, it may be because you'; 'have specified an impossible microstructure. You can visualize the generation rate to identify such an issue.'};
            app.Label_92 = uilabel(app.GridLayout5_5);
            app.Label_92.Text = 'It does not have to be complicated';
            app.HighlevelfeaturesLabel = uilabel(app.GridLayout5_5);
            app.HighlevelfeaturesLabel.Text = 'High-level features';
            app.AquickoverviewLabel = uilabel(app.GridLayout5_5);
            app.AquickoverviewLabel.Text = 'A quick overview';
            app.InstructionsTab_3 = uitab(app.MainTabGroup);
            app.InstructionsTab_3.Title = 'Instructions';
            app.GridLayout5_6 = uigridlayout(app.InstructionsTab_3);
            app.TherearetwomainworkflowsLabel = uilabel(app.GridLayout5_6);
            app.TherearetwomainworkflowsLabel.Text = 'There are two main workflows:';
            app.Label_93 = uilabel(app.GridLayout5_6);
            app.Label_93.Text = {'Go to the "Generate" tab (you do not need to use the "Re-generate" tab at all) and follow instructions.'; 'There, you will be able to setup parameters to generate a volume either completely from scratch, or starting from an imported volume with pre-existing phases.'; 'In the second case, these pre-existing phases will be handled as masks for which you will be able to setup rules.'; ''};
            app.Label_94 = uilabel(app.GridLayout5_6);
            app.Label_94.Text = 'Alternatively, you can run src/Microstructure_generation/Unified_microstructure_generation_genalgorithm.m (this GUI is used to create the input for this file), but it is not recommended if you are not familiar with the data structure.';
            app.Label_95 = uilabel(app.GridLayout5_6);
            app.Label_95.Text = {'There are lots of tabs to configure, but keep in mind: (i) all tabs are already pre-configured for a very simple generation, (ii) always setup tabs from top to bottom and left to right, and (iii) you can initialize the GUI with complex microstructures to learn'; 'how to generate such complex cases (go to the "Generate / Save import parameters" tab to import examples).'};
            app.Label_96 = uilabel(app.GridLayout5_6);
            app.Label_96.Text = '1) You want to generate a new microstructure (and maybe do some parameter sweep analysis and microstructure characterization)';
            app.Label_97 = uilabel(app.GridLayout5_6);
            app.Label_97.Text = '2) You want to re-generate a microstructure';
            app.Label_98 = uilabel(app.GridLayout5_6);
            app.Label_98.Text = {'This workflow requires that you have already generated a microstructure with this tool. When a microstructure is generated, not only the volume (.tiff) is saved, but also the particles and tube information (e.g., particle centroid, diameter, and'; 'alignment). In the "Re-generate" tab you can import this data, and specify you want to re-generate the particles or tubes, but at a different porosity and/or at a different image resolution.'};
            app.Label_99 = uilabel(app.GridLayout5_6);
            app.Label_99.Text = {'For instance, if you want to investigate a particular microstructure at different porosities, you have two choices:'; '- You can generate several volumes in workflow 1 ("Generate" tab)  by setting a parameter sweep analysis on volume fraction. However, each new realization requires to generate starting from scratch each time (it may take some times).'; '- You can generate a single volume in workflow 1 ("Generate" tab) but with a very low porosity. Then, in workflow 2 ("Re-generate" tab), you can import the data and re-generate at higher porosities. This approach is faster, as once you'; 'have the data from workflow 1, workflow 2 essentially samples particle subsets to create microstructures with higher porosities and this is trivial. Note that this second approach is only relevant if reproducibility is not an issue.'};
            app.Label_101 = uilabel(app.GridLayout5_6);
            app.Label_101.Text = 'Alternatively, you can run src/Microstructure_generation/Unified_microstructure_generation_regenalgorithm.m (this GUI is used to create the input for this file), but it is not recommended if you are not familiar with the data structure.';
            app.Label_102 = uilabel(app.GridLayout5_6);
            app.Label_102.Text = 'Limitation: only work for phases with morphology "Particles" or "Tubes".';
            app.GenerateTab = uitab(app.MainTabGroup);
            app.GenerateTab.Title = 'Generate';
            app.GenerateGridLayout = uigridlayout(app.GenerateTab);
            app.GenerateTabGroup = uitabgroup(app.GenerateGridLayout);
            app.InstructionsTab_2 = uitab(app.GenerateTabGroup);
            app.InstructionsTab_2.Title = 'Instructions';
            app.GridLayout3_3 = uigridlayout(app.InstructionsTab_2);
            app.Label_103 = uilabel(app.GridLayout3_3);
            app.Label_103.Text = {'The tabs should be always configured from top to bottom and from left to right. If you go backward, you may trigger subsequent tabs/parameters to be re-initialized. '; 'The only exception is in the Morphology tab: you can setup "tubes", "particles", etc. in the order of your choice. However, within a morphology (e.g., the sub tab of "particles"), you must proceed again from left to right.'};
            app.DimensionsTab = uitab(app.GenerateTabGroup);
            app.DimensionsTab.Title = 'Dimensions';
            app.GridLayout = uigridlayout(app.DimensionsTab);
            app.GridLayout.Tag = 'save';
            app.aSelectdimensionalityLabel = uilabel(app.GridLayout);
            app.aSelectdimensionalityLabel.Text = '1a) Select dimensionality';
            app.DimensionalityDropDown_2Label = uilabel(app.GridLayout);
            app.DimensionalityDropDown_2Label.Text = 'Dimensionality';
            app.DimensionalityDropDown = uidropdown(app.GridLayout);
            app.DimensionalityDropDown.Items = {'2D', '3D'};
            app.DimensionalityDropDown.ValueChangedFcn = createCallbackFcn(app, @DimensionalityDropDownValueChanged, true);
            app.DimensionalityDropDown.Value = '2D';
            app.SetdomainslengthLabel = uilabel(app.GridLayout);
            app.SetdomainslengthLabel.Text = '2) Set domain''s length';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'The algorithm is adimensional. Therefore, all lengths are expressed in voxel''s length';
            app.Dimension_ParSweep_CheckBox = uicheckbox(app.GridLayout);
            app.Dimension_ParSweep_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Dimension_ParSweep_CheckBoxValueChanged, true);
            app.Dimension_ParSweep_CheckBox.Text = 'Do a parameter sweep';
            app.Dimension_UITable = uitable(app.GridLayout);
            app.Dimension_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Dimension_UITable.RowName = {};
            app.Dimension_UITable.CellEditCallback = createCallbackFcn(app, @Dimension_UITableCellEdit, true);
            app.ORLabel = uilabel(app.GridLayout);
            app.ORLabel.Text = '- OR -';
            app.ImportapreexistingdomainLabel = uilabel(app.GridLayout);
            app.ImportapreexistingdomainLabel.Text = '1b) Import an image or a volume with pre-existing phases (label 0 must be the background)';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportedfilenoneLabel = uilabel(app.GridLayout);
            app.ImportedfilenoneLabel.Text = 'Imported file: none';
            app.Label_46 = uilabel(app.GridLayout);
            app.Label_46.Text = 'You can either work from scratch 1a) or from an existing microstructure 1b). Selecting either option will re-initialize all other parameters.';
            app.LengthanddimensionalitynaLabel = uilabel(app.GridLayout);
            app.LengthanddimensionalitynaLabel.Text = 'Length and dimensionality: n/a';
            app.Labelsvolumefractionsna = uilabel(app.GridLayout);
            app.Labelsvolumefractionsna.Text = 'Labels (volume fractions): n/a';
            app.VisualizeButton = uibutton(app.GridLayout, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.DomainSweep_CropLabel = uilabel(app.GridLayout);
            app.DomainSweep_CropLabel.Text = 'Imported volume will be cropped. Cropped volumes and imported volume share the same center.';
            app.PhasesTab = uitab(app.GenerateTabGroup);
            app.PhasesTab.Title = 'Phases';
            app.PhasesGridLayout = uigridlayout(app.PhasesTab);
            app.TabGroup = uitabgroup(app.PhasesGridLayout);
            app.NumberandmorphologyTab = uitab(app.TabGroup);
            app.NumberandmorphologyTab.Title = 'Number and morphology';
            app.GridLayout_2 = uigridlayout(app.NumberandmorphologyTab);
            app.GridLayout_2.Tag = 'save';
            app.Label_43 = uilabel(app.GridLayout_2);
            app.Label_43.Text = '1) Choose the number of phases to be generated';
            app.EditFieldLabel = uilabel(app.GridLayout_2);
            app.EditFieldLabel.Text = 'Number of phases to generate';
            app.NumberofphasesEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.NumberofphasesEditField.Limits = [1 Inf];
            app.NumberofphasesEditField.RoundFractionalValues = 'on';
            app.NumberofphasesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofphasesEditFieldValueChanged, true);
            app.NumberofphasesEditField.Value = 1;
            app.Label_18 = uilabel(app.GridLayout_2);
            app.Label_18.Text = '2) For each phase, write their label and name, pick a color, choose one morphology, and decide when it is generated';
            app.SolidPhase_UITable = uitable(app.GridLayout_2);
            app.SolidPhase_UITable.ColumnName = {'Phase#'; 'Label'; 'Name'; 'Color'; 'Particles'; 'Tubes'; 'Amorph'; 'Web'; 'Predetermined shape'; 'Generation step'};
            app.SolidPhase_UITable.RowName = {};
            app.SolidPhase_UITable.ColumnEditable = [false true true false true true true true true true];
            app.SolidPhase_UITable.CellEditCallback = createCallbackFcn(app, @SolidPhase_UITableCellEdit, true);
            app.SolidPhase_UITable.CellSelectionCallback = createCallbackFcn(app, @SolidPhase_UITableCellSelection, true);
            app.NamearereusedintheGUIsoLabel = uilabel(app.GridLayout_2);
            app.NamearereusedintheGUIsoLabel.Text = 'Name and color are reused in the GUI so you can better identify phases when assigning parameters. They will be used also for plotting results.';
            app.Label_20 = uilabel(app.GridLayout_2);
            app.Label_20.Text = 'Label is the value assigned to all voxels assigned to a phase';
            app.Label_44 = uilabel(app.GridLayout_2);
            app.Label_44.Text = {'For each phase, you can select a morphology: particles, tubes, amorph, web, or predetermined shape. For battery electrode microstructures, it could correspond to:'; '- particles: active material particles. Two sub shapes: ellipsoids (as illustrated) or pancakes. Generate both semantic and instance maps.'; '- tube (if scale is relevant): carbon additive nanotubes. Generate both semantic and instance maps.'; '- amorph (if scale is relevant): carbon and binder additive'; '- predetermined shape: separator layer, channels (as illustrated)'};
            app.Label_45 = uilabel(app.GridLayout_2);
            app.Label_45.Text = {'Generation step controls the order of generation. Phases with a particle morphology can be generated together in the same generation step. Step 0 is reserved for background (and pre-existing phases if a volume has been imported).'; 'Controlling the order can facilitate the generation process for complex microstructures. For instance, it is usually simpler to generate first tubes and then particles, than the opposite.'; 'Phases with an amorph morphology can not be generated on the first generation step (unless you have imported pre-existing phases)'};
            app.GenerationsteperrorLabel = uilabel(app.GridLayout_2);
            app.GenerationsteperrorLabel.Text = 'Generation step error: Incorrect values';
            app.Image = uiimage(app.GridLayout_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Pictures', 'Shapes_generation.png');
            app.PhasePhaseinteractionsmasksTab = uitab(app.TabGroup);
            app.PhasePhaseinteractionsmasksTab.Title = 'Phase-Phase interactions (masks)';
            app.PhasePhaseinteractionsmasksTab.Tag = 'save';
            app.GridLayout2 = uigridlayout(app.PhasePhaseinteractionsmasksTab);
            app.GridLayout2.Tag = 'save';
            app.Label_53 = uilabel(app.GridLayout2);
            app.Label_53.Text = '3) For each phase B to be generated, setup how phase B interacts with previously generated (or preexisting phase, if any) phase A based on the generation step';
            app.PhaseBDropDown_2Label = uilabel(app.GridLayout2);
            app.PhaseBDropDown_2Label.Text = 'Phase B';
            app.PhaseBDropDown = uidropdown(app.GridLayout2);
            app.PhaseBDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseBDropDownValueChanged, true);
            app.PhaseADropDownLabel = uilabel(app.GridLayout2);
            app.PhaseADropDownLabel.Text = 'Phase A';
            app.PhaseADropDown = uidropdown(app.GridLayout2);
            app.PhaseADropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseADropDownValueChanged, true);
            app.MasksarenaLabel = uilabel(app.GridLayout2);
            app.MasksarenaLabel.Text = 'n/a with your phases and generation order';
            app.TypeBLabel = uilabel(app.GridLayout2);
            app.TypeBLabel.Text = 'Morphology:';
            app.TypeALabel = uilabel(app.GridLayout2);
            app.TypeALabel.Text = 'Morphology:';
            app.CaseBisEllipsoidPanel = uipanel(app.GridLayout2);
            app.CaseBisEllipsoidPanel.Title = 'Case: B''s morphology is "particles", "tubes", or "web"';
            app.GridLayout4 = uigridlayout(app.CaseBisEllipsoidPanel);
            app.GridLayout4.Tag = 'save';
            app.ParticlescanDropDownLabel = uilabel(app.GridLayout4);
            app.ParticlescanDropDownLabel.Text = 'Instances for phase B can be generated ';
            app.ParticlesBaregeneratedDropDown = uidropdown(app.GridLayout4);
            app.ParticlesBaregeneratedDropDown.Items = {'Within Phase A', 'Within Not phase A', 'Both within A and not A'};
            app.ParticlesBaregeneratedDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticlesBaregeneratedDropDownValueChanged, true);
            app.ParticlesBaregeneratedDropDown.Value = 'Within Phase A';
            app.Label_63 = uilabel(app.GridLayout4);
            app.Label_63.Text = {'By default, the condition is only applied on the particle centroid (or tube control points or nodes).'; 'Additional constraints can be added.'; ''};
            app.Ellipsoids_Atleast_checkbox = uicheckbox(app.GridLayout4);
            app.Ellipsoids_Atleast_checkbox.ValueChangedFcn = createCallbackFcn(app, @Ellipsoids_Atleast_checkboxValueChanged, true);
            app.Ellipsoids_Atleast_checkbox.Text = 'Phase B centroids must be distant from Phase A surface by AT LEAST...';
            app.Ellipsoids_Atleast_DoaparametersweepCheckBox = uicheckbox(app.GridLayout4);
            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.ValueChangedFcn = createCallbackFcn(app, @Ellipsoids_Atleast_DoaparametersweepCheckBoxValueChanged, true);
            app.Ellipsoids_Atleast_DoaparametersweepCheckBox.Text = 'Do a parameter sweep';
            app.Ellipsoids_Atleast_SweepUITable = uitable(app.GridLayout4);
            app.Ellipsoids_Atleast_SweepUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Ellipsoids_Atleast_SweepUITable.RowName = {};
            app.Ellipsoids_Atleast_SweepUITable.CellEditCallback = createCallbackFcn(app, @Ellipsoids_Atleast_SweepUITableCellEdit, true);
            app.Ellipsoids_Notmorethan_CheckBox = uicheckbox(app.GridLayout4);
            app.Ellipsoids_Notmorethan_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ellipsoids_Notmorethan_CheckBoxValueChanged, true);
            app.Ellipsoids_Notmorethan_CheckBox.Text = 'Phase B centroids must be distant from Phase A surface NOT MORE THAN...';
            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox = uicheckbox(app.GridLayout4);
            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.ValueChangedFcn = createCallbackFcn(app, @Ellipsoids_Notmorethan_DoaparameterSweep_checkboxValueChanged, true);
            app.Ellipsoids_Notmorethan_DoaparameterSweep_checkbox.Text = 'Do a parameter sweep';
            app.Ellipsoids_mask_image = uiimage(app.GridLayout4);
            app.Ellipsoids_Notmorethan_SweepUITable = uitable(app.GridLayout4);
            app.Ellipsoids_Notmorethan_SweepUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Ellipsoids_Notmorethan_SweepUITable.RowName = {};
            app.Ellipsoids_Notmorethan_SweepUITable.CellEditCallback = createCallbackFcn(app, @Ellipsoids_Notmorethan_SweepUITableCellEdit, true);
            app.DropDownLabel = uilabel(app.GridLayout4);
            app.DropDownLabel.Text = 'Rule for multiple masks';
            app.RuleformultiplemasksDropDown = uidropdown(app.GridLayout4);
            app.RuleformultiplemasksDropDown.Items = {'AND', 'OR'};
            app.RuleformultiplemasksDropDown.ValueChangedFcn = createCallbackFcn(app, @RuleformultiplemasksDropDownValueChanged, true);
            app.RuleformultiplemasksDropDown.Value = 'AND';
            app.ParticleBLabel = uilabel(app.GridLayout4);
            app.ParticleBLabel.Text = 'Particle B';
            app.MaskParticle_VolumeoverlapDropDown = uidropdown(app.GridLayout4);
            app.MaskParticle_VolumeoverlapDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskParticle_VolumeoverlapDropDownValueChanged, true);
            app.Enterdasd2d1dLabel = uilabel(app.GridLayout4);
            app.Enterdasd2d1dLabel.Text = 'Enter d, as d2 = d1 + d';
            app.CaseBsmorphologyisamorphPanel = uipanel(app.GridLayout2);
            app.CaseBsmorphologyisamorphPanel.Title = 'Case: B''s morphology is "amorph"';
            app.GridLayout6 = uigridlayout(app.CaseBsmorphologyisamorphPanel);
            app.GridLayout6.Tag = 'save';
            app.Label_73 = uilabel(app.GridLayout6);
            app.Label_73.Text = 'If phase A is ignored, Phase B cannot be generated within phase A';
            app.Amorph_UsePhaseAasmaskCheckBox = uicheckbox(app.GridLayout6);
            app.Amorph_UsePhaseAasmaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @Amorph_UsePhaseAasmaskCheckBoxValueChanged, true);
            app.Amorph_UsePhaseAasmaskCheckBox.Text = 'Use Phase A as mask';
            app.DropDownLabel_2 = uilabel(app.GridLayout6);
            app.DropDownLabel_2.Text = 'Rule for multiple masks';
            app.Amorph_RuleformultiplemasksDropDown = uidropdown(app.GridLayout6);
            app.Amorph_RuleformultiplemasksDropDown.Items = {'AND', 'OR'};
            app.Amorph_RuleformultiplemasksDropDown.Value = 'AND';
            app.Label_74 = uilabel(app.GridLayout2);
            app.Label_74.Text = 'Mask rules take priority over other inputs.';
            app.CaseBsmorphologyisShapePanel = uipanel(app.GridLayout2);
            app.CaseBsmorphologyisShapePanel.Title = 'Case: B''s morphology is "Predetermined shape"';
            app.GridLayout7 = uigridlayout(app.CaseBsmorphologyisShapePanel);
            app.GridLayout7.Tag = 'save';
            app.Shape_BisoverwritingACheckBox = uicheckbox(app.GridLayout7);
            app.Shape_BisoverwritingACheckBox.ValueChangedFcn = createCallbackFcn(app, @Shape_BisoverwritingACheckBoxValueChanged, true);
            app.Shape_BisoverwritingACheckBox.Text = 'B is overwriting A';
            app.Shape_RemoveParticle_CheckBox = uicheckbox(app.GridLayout7);
            app.Shape_RemoveParticle_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Shape_RemoveParticle_CheckBoxValueChanged, true);
            app.Shape_RemoveParticle_CheckBox.Text = {'But only remove whole particle A, if it has more than x% of its'; 'volume within B (otherwise, particles will be truncated)'};
            app.xLabel = uilabel(app.GridLayout7);
            app.xLabel.Text = 'x (%)';
            app.Shape_RemoveParticle_x_EditField = uieditfield(app.GridLayout7, 'numeric');
            app.Shape_RemoveParticle_x_EditField.Limits = [1 100];
            app.Shape_RemoveParticle_x_EditField.ValueChangedFcn = createCallbackFcn(app, @Shape_RemoveParticle_x_EditFieldValueChanged, true);
            app.Shape_RemoveParticle_x_EditField.Value = 50;
            app.Mask_Shape_Image = uiimage(app.GridLayout7);
            app.Label_105 = uilabel(app.GridLayout2);
            app.Label_105.Text = {'Note: if you generate more than 1 phase with the "Particle" morphology in the same generation step, you can finely control the '; 'overlapping (in the "Morphology / Particles / Overlapping" tab). Here, we look at masks for phases generated at a different'; 'generation step.'};
            app.B_morhpology_Label = uilabel(app.GridLayout2);
            app.B_morhpology_Label.Text = 'n/a';
            app.A_morhpology_Label = uilabel(app.GridLayout2);
            app.A_morhpology_Label.Text = 'n/a';
            app.MorphologyTab = uitab(app.GenerateTabGroup);
            app.MorphologyTab.Title = 'Morphology';
            app.MorphologyGridLayout = uigridlayout(app.MorphologyTab);
            app.MorphologyTabGroup = uitabgroup(app.MorphologyGridLayout);
            app.TubesTab = uitab(app.MorphologyTabGroup);
            app.TubesTab.Title = 'Tubes';
            app.ParticlesTab = uitab(app.MorphologyTabGroup);
            app.ParticlesTab.Title = 'Particles';
            app.ParticlesGridLayout = uigridlayout(app.ParticlesTab);
            app.ParticlesTabGroup = uitabgroup(app.ParticlesGridLayout);
            app.InstructionsTab_4 = uitab(app.ParticlesTabGroup);
            app.InstructionsTab_4.Title = 'Instructions';
            app.VolumefractionsTab = uitab(app.ParticlesTabGroup);
            app.VolumefractionsTab.Title = 'Volume fractions';
            app.ParticleVfGridLayout = uigridlayout(app.VolumefractionsTab);
            app.ParticleVfTabGroup = uitabgroup(app.ParticleVfGridLayout);
            app.HeterogeneityTab = uitab(app.ParticleVfTabGroup);
            app.HeterogeneityTab.Title = 'Heterogeneity';
            app.Label_61 = uilabel(app.HeterogeneityTab);
            app.Label_61.Text = {'There are 3 distribution modes:'; '- Uniform: specify a single volume fraction for the phase'; '- Axial gradation: specify the volume fraction evolution along one axis (e.g., to generate a bi-layer)'; '- Heterogeneous: specify the volume fraction on different locations of the domain, for all axes'; 'Note: spatial distribution is overwritten by mask, if any.'};
            app.Label_70 = uilabel(app.HeterogeneityTab);
            app.Label_70.Text = {'Two options for porosity'; '- only save end result'; '- save intermediate result'};
            app.UniformTab = uitab(app.ParticleVfTabGroup);
            app.UniformTab.Title = 'Uniform';
            app.Label_59 = uilabel(app.UniformTab);
            app.Label_59.Text = 'All phases here have a uniform volume fraction: the heterogeneity will be stochastic';
            app.WorworenormalizationLabel = uilabel(app.UniformTab);
            app.WorworenormalizationLabel.Text = 'W/ or w/o renormalization';
            app.AxialgradationTab = uitab(app.ParticleVfTabGroup);
            app.AxialgradationTab.Title = 'Axial gradation';
            app.Label_58 = uilabel(app.AxialgradationTab);
            app.Label_58.Text = 'Two modes: absolute or relative (relative: give <eps> and then ratio along axis: good for sweeping analysi as you only need to vary the global value)';
            app.DTab = uitab(app.ParticleVfTabGroup);
            app.DTab.Title = '3D';
            app.heterogenousimportmapLabel = uilabel(app.DTab);
            app.heterogenousimportmapLabel.Text = 'heterogenous, import map';
            app.TypenaLabel = uilabel(app.DTab);
            app.TypenaLabel.Text = 'Visualization of the inputs target?';
            app.DiametersellipoidsTab = uitab(app.ParticlesTabGroup);
            app.DiametersellipoidsTab.Title = 'Diameters (ellipoids)';
            app.LengthsplatesTab = uitab(app.ParticlesTabGroup);
            app.LengthsplatesTab.Title = 'Lengths (plates)';
            app.RotationsTab = uitab(app.ParticlesTabGroup);
            app.RotationsTab.Title = 'Rotations';
            app.CracksTab = uitab(app.ParticlesTabGroup);
            app.CracksTab.Title = 'Cracks';
            app.SurfaceroughnessTab = uitab(app.ParticlesTabGroup);
            app.SurfaceroughnessTab.Title = 'Surface roughness';
            app.OrderTab = uitab(app.ParticlesTabGroup);
            app.OrderTab.Title = 'Order';
            app.EdgesTab = uitab(app.ParticlesTabGroup);
            app.EdgesTab.Title = 'Edges';
            app.CroppingandPeriodicBCLabel = uilabel(app.EdgesTab);
            app.CroppingandPeriodicBCLabel.Text = 'Cropping and Periodic BC';
            app.OverlappingTab = uitab(app.ParticlesTabGroup);
            app.OverlappingTab.Title = 'Overlapping';
            app.Label_60 = uilabel(app.OverlappingTab);
            app.Label_60.Text = 'Overlapping: with conditional statement? E.g. porosity > 0.7: no overlapping allowed?';
            app.AlgorithmTab = uitab(app.ParticlesTabGroup);
            app.AlgorithmTab.Title = 'Algorithm';
            app.StoppingconditionsTab = uitab(app.ParticlesTabGroup);
            app.StoppingconditionsTab.Title = 'Stopping conditions';
            app.AmorphTab = uitab(app.MorphologyTabGroup);
            app.AmorphTab.Title = 'Amorph';
            app.WebTab = uitab(app.MorphologyTabGroup);
            app.WebTab.Title = 'Web';
            app.PredeterminedshapeTab = uitab(app.MorphologyTabGroup);
            app.PredeterminedshapeTab.Title = 'Pre-determined shape';
            app.UpscaleTab = uitab(app.GenerateTabGroup);
            app.UpscaleTab.Title = 'Upscale';
            app.PostprocessingTab = uitab(app.GenerateTabGroup);
            app.PostprocessingTab.Title = 'Post-processing';
            app.Label_2 = uilabel(app.PostprocessingTab);
            app.Label_2.Text = 'Set visualization (auto create matrix), P2D results';
            app.Label_16 = uilabel(app.PostprocessingTab);
            app.Label_16.Text = {'Calculate'; '- P2D parameters'; '- non-P2D parameters (e.g., index of dispersion)'; ''; 'Then, find microstructures that have similar P2D parameters but different non P2D parameters. Do they exist?'};
            app.Label_41 = uilabel(app.PostprocessingTab);
            app.Label_41.Text = {'Diffisuivity: put nano poroisy and brugeeman exponent'; ''; 'Analysis per phase (diameter), or per group of phase (electrode effective transport)'};
            app.Label_50 = uilabel(app.PostprocessingTab);
            app.Label_50.Text = 'Indicate voxel size (before upscaling)';
            app.ReassignLabel = uilabel(app.PostprocessingTab);
            app.ReassignLabel.Text = 'Re-assign';
            app.Label_104 = uilabel(app.PostprocessingTab);
            app.Label_104.Text = 'Format (fontname, figure size etc)';
            app.ParametersweepTab = uitab(app.GenerateTabGroup);
            app.ParametersweepTab.Title = 'Parameter sweep';
            app.ParametersweepTab.ButtonDownFcn = createCallbackFcn(app, @ParametersweepTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.ParametersweepTab);
            app.GridLayout3.Tag = 'save';
            app.Parameter_sweep_messageLabel = uilabel(app.GridLayout3);
            app.Parameter_sweep_messageLabel.Text = 'Parameter_sweep_message';
            app.Label_54 = uilabel(app.GridLayout3);
            app.Label_54.Text = 'This table lists all the parameters for which a parameter sweep has been selected. Use short names for "Name in file"';
            app.Sweepparameter_UITable = uitable(app.GridLayout3);
            app.Sweepparameter_UITable.ColumnName = {'Parameter name'; 'Name in file'; 'Parameter id'; '#Values'};
            app.Sweepparameter_UITable.RowName = {};
            app.Sweepparameter_UITable.ColumnEditable = [false true false false];
            app.Label_55 = uilabel(app.GridLayout3);
            app.Label_55.Text = 'You can add parameter sweep studies here';
            app.ParameterSweep_help_Button = uibutton(app.GridLayout3, 'push');
            app.ParameterSweep_help_Button.ButtonPushedFcn = createCallbackFcn(app, @ParameterSweep_help_ButtonPushed, true);
            app.ParameterSweep_help_Button.Icon = 'Icon_help.png';
            app.ParameterSweep_help_Button.Text = '';
            app.AddastudyButton = uibutton(app.GridLayout3, 'push');
            app.AddastudyButton.ButtonPushedFcn = createCallbackFcn(app, @AddastudyButtonPushed, true);
            app.AddastudyButton.Text = 'Add a study';
            app.DeletestudiesButton = uibutton(app.GridLayout3, 'push');
            app.DeletestudiesButton.ButtonPushedFcn = createCallbackFcn(app, @DeletestudiesButtonPushed, true);
            app.DeletestudiesButton.Text = 'Delete studies';
            app.Study_UITable = uitable(app.GridLayout3);
            app.Study_UITable.ColumnName = {'#Study'; 'Name in folder'; 'Id: all combinations'; 'Id: linked values'; 'Reproducibility'; '#Generation'};
            app.Study_UITable.RowName = {};
            app.Study_UITable.ColumnEditable = [false true true true true false];
            app.Study_UITable.CellEditCallback = createCallbackFcn(app, @Study_UITableCellEdit, true);
            app.TotalnumberofgenerationsLabel = uilabel(app.GridLayout3);
            app.TotalnumberofgenerationsLabel.Text = 'Total number of generations: n/a';
            app.Label2 = uilabel(app.GridLayout3);
            app.Label2.Text = 'Reproducibility means regenerating using the same set of parameters.';
            app.Label3 = uilabel(app.GridLayout3);
            app.Label3.Text = {'- Write in "Id: all combinations" the id of all the parameters for which you want all their values to be swept.'; '- Write in "Id: linked values" the id of all the parameters (at least 2) you want to sweep together.'; '- Write using this format: a,b,c, with a,b,c the "Parameter id" listed in the left table.'; '- Parameters for which their ids are not included will use their default value for this study. If Both "Id: all combinations"'; 'and "Id: linked values" are empty, then only default values are used for all parameter (not a parameter sweep analysis).'};
            app.Error_linkedparameters = uilabel(app.GridLayout3);
            app.Error_linkedparameters.Text = 'Error: linked parameters should share the same #values.';
            app.Error_linkeallparameters = uilabel(app.GridLayout3);
            app.Error_linkeallparameters.Text = 'Error: for a given study, a parameter id cannot be both in "Id: all combinations" and in "Id: linked values".';
            app.ReproducibilityDropDownLabel = uilabel(app.GridLayout3);
            app.ReproducibilityDropDownLabel.Text = 'Reproducibility';
            app.ReproducibilityDropDown = uidropdown(app.GridLayout3);
            app.ReproducibilityDropDown.Items = {'Do all unique cases, then do reproducibility: A B C then A B C', 'Do all reproducibility realizations before moving to the next case:  A A then B B then C C'};
            app.ReproducibilityDropDown.Value = 'Do all unique cases, then do reproducibility: A B C then A B C';
            app.SaveimportparametersTab = uitab(app.GenerateTabGroup);
            app.SaveimportparametersTab.Title = 'Save/import parameters';
            app.SaveimportparametersTab.ButtonDownFcn = createCallbackFcn(app, @SaveimportparametersTabButtonDown, true);
            app.GridLayout3_2 = uigridlayout(app.SaveimportparametersTab);
            app.Label_56 = uilabel(app.GridLayout3_2);
            app.Label_56.Text = '(optional) You can save or import all the GUI parameters';
            app.SaveButton = uibutton(app.GridLayout3_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'icon_save.png';
            app.SaveButton.Text = 'Save';
            app.FlenameEditFieldLabel = uilabel(app.GridLayout3_2);
            app.FlenameEditFieldLabel.Text = 'Flename';
            app.SaveParameter_FIlenameEditField = uieditfield(app.GridLayout3_2, 'text');
            app.SaveParameter_FIlenameEditField.Value = 'Generation_parameters';
            app.SaveParameters_ErrormessageLabel = uilabel(app.GridLayout3_2);
            app.SaveParameters_ErrormessageLabel.Text = 'Error message';
            app.ImportButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Icon = 'icon_import.png';
            app.ImportButton_2.Text = 'Import';
            app.Label_57 = uilabel(app.GridLayout3_2);
            app.Label_57.Text = '(optional) You can also import presets GUI parameters, ideal to understand how to use the app';
            app.ExamplesDropDownLabel = uilabel(app.GridLayout3_2);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.GridLayout3_2);
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.Example_DescriptionnoneLabel = uilabel(app.GridLayout3_2);
            app.Example_DescriptionnoneLabel.Text = 'Description: none';
            app.ImportexampleButton = uibutton(app.GridLayout3_2, 'push');
            app.ImportexampleButton.ButtonPushedFcn = createCallbackFcn(app, @ImportexampleButtonPushed, true);
            app.ImportexampleButton.Icon = 'icon_import.png';
            app.ImportexampleButton.Text = 'Import example';
            app.Example_Image = uiimage(app.GridLayout3_2);
            app.RunTab = uitab(app.GenerateTabGroup);
            app.RunTab.Title = 'Run';
            app.ReproducibilityLabel = uilabel(app.RunTab);
            app.ReproducibilityLabel.Text = 'Reproducibility for not sweeping analysis';
            app.RegenerateTab = uitab(app.MainTabGroup);
            app.RegenerateTab.Title = 'Re-generate';
            app.Label_7 = uilabel(app.RegenerateTab);
            app.Label_7.Text = {'Rescale, change dimensions, etc...'; ''; 'Target different porosity'; ''; 'But also force redistribution'};
            app.AboutTab = uitab(app.MainTabGroup);
            app.AboutTab.Title = 'About';
            app.AboutGridLayout = uigridlayout(app.AboutTab);
            app.AboutTabGroup = uitabgroup(app.AboutGridLayout);
            app.NREL_Tab = uitab(app.AboutTabGroup);
            app.NREL_Tab.Title = 'National Laboratory of the Rockies';
            app.GridLayout5_3 = uigridlayout(app.NREL_Tab);
            app.Label_67 = uilabel(app.GridLayout5_3);
            app.Label_67.Text = 'MATBOX has been developed at the National Laboratory of the Rockies (NLR), formerly the National Renewable Energy Laboratory (NREL) to analyze electrode microstructures for lithium-ion, and other 3D heterogeneous materials.';
            app.Image2 = uiimage(app.GridLayout5_3);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Tooltip = {'https://www.nlr.gov/'};
            app.Image2.ImageSource = 'logo_NLR.png';
            app.Label_68 = uilabel(app.GridLayout5_3);
            app.Label_68.Text = 'MATBOX''s lead developer is Francois L. E. Usseglio-Viretta.';
            app.Hyperlink14 = uihyperlink(app.GridLayout5_3);
            app.Hyperlink14.URL = 'https://www.nlr.gov/transportation/matbox';
            app.Hyperlink14.Text = 'MATBOX official webpage';
            app.Hyperlink15 = uihyperlink(app.GridLayout5_3);
            app.Hyperlink15.URL = 'https://github.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink15.Text = 'MATBOX GitHub repository';
            app.HowtoquoteTab = uitab(app.AboutTabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout5_2 = uigridlayout(app.HowtoquoteTab);
            app.IfyouLabel = uilabel(app.GridLayout5_2);
            app.IfyouLabel.Text = 'If you use MATBOX, please quote it as follow:';
            app.TextAreaLabel = uilabel(app.GridLayout5_2);
            app.TextAreaLabel.Text = '- For ANY work produced with MATBOX, always quote:';
            app.TextArea = uitextarea(app.GridLayout5_2);
            app.TextArea.Value = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith, MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing, SoftwareX, 17, 2022'};
            app.Hyperlink12 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink12.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink12.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.TextArea_2Label = uilabel(app.GridLayout5_2);
            app.TextArea_2Label.Text = '- If you have generated additives with the "energy-based" method (Generate/Morphology/Amorph tab), please ALSO quote:';
            app.IfyouhavegeneratedTextArea = uitextarea(app.GridLayout5_2);
            app.IfyouhavegeneratedTextArea.Value = {'A. N. Mistry, K. Smith, and P. P. Mukherjee, Secondary Phase Stochastics in Lithium-Ion Battery Electrodes, ACS Appl. Mater. Interfaces, 10(7) pp. 6317-6326, 2018'};
            app.Hyperlink_2 = uihyperlink(app.GridLayout5_2);
            app.Hyperlink_2.URL = 'https://doi.org/10.1021/acsami.7b17771';
            app.Hyperlink_2.Text = 'https://doi.org/10.1021/acsami.7b17771';
            app.ReferencesTab = uitab(app.AboutTabGroup);
            app.ReferencesTab.Title = 'References';
            app.GridLayout5 = uigridlayout(app.ReferencesTab);
            app.Label_64 = uilabel(app.GridLayout5);
            app.Label_64.Text = 'Here, you can find a list of peer-reviewed journal articles that have used MATBOX to generate virtual microstructures';
            app.EllipsoidsparticlesLabel = uilabel(app.GridLayout5);
            app.EllipsoidsparticlesLabel.Text = '- Ellipsoids particles [2,4,5,6,7,8,9]';
            app.Amorph131011Label = uilabel(app.GridLayout5);
            app.Amorph131011Label.Text = '- Amorph [1,3,10,11]';
            app.TubesLabel = uilabel(app.GridLayout5);
            app.TubesLabel.Text = '- Tubes []';
            app.PredeterminedshapesLabel = uilabel(app.GridLayout5);
            app.PredeterminedshapesLabel.Text = '- Pre-determined shapes []';
            app.AgglomeratesLabel = uilabel(app.GridLayout5);
            app.AgglomeratesLabel.Text = '- Agglomerates []';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.URL = 'https://doi.org/10.1149/1945-7111/add420';
            app.Hyperlink.Text = '[9] S. Shimpalee et al., Numerical Prediction of Lithium-Ion Battery Aging due to SEI Growth Using 3D Microstructure-Based Modeling Method, Journal of the Electrochemical Society, 172, 053501, 2025';
            app.Hyperlink2 = uihyperlink(app.GridLayout5);
            app.Hyperlink2.URL = 'https://doi.org/10.1149/1945-7111/ad6e22';
            app.Hyperlink2.Text = '[5] H. Teel et al., Quantifying the Impact of Microstructure Variation on Charging Capability in Lithium-Ion Batteries, Journal of the Electrochemical Society, 171, 083504, 2024';
            app.Hyperlink3 = uihyperlink(app.GridLayout5);
            app.Hyperlink3.URL = 'https://doi.org/10.1149/1945-7111/acbc9e';
            app.Hyperlink3.Text = '[2] J. S. Lopata et al., Dynamic Multi-Dimensional Numerical Transport Study of Lithium-Ion Battery Active Material Microstructures for Automotive Applications, Journal of the Electrochemical Society, 170, 020530, 2023';
            app.Hyperlink4 = uihyperlink(app.GridLayout5);
            app.Hyperlink4.URL = 'https://doi.org/10.1021/acs.iecr.5c02169';
            app.Hyperlink4.Text = ['[8] H. Teel et al., Numerical Investigation of Susceptor-Catalyst Design for Ethylene Generation in Radio Frequency Based Reactors, Industrial and Engineering Chemistry Research, 64, 2025' newline ''];
            app.Hyperlink5 = uihyperlink(app.GridLayout5);
            app.Hyperlink5.URL = 'https://doi.org/10.1149/1945-7111/adf35d';
            app.Hyperlink5.Text = ['[6] F. Usseglio-Viretta et al., Microstructure Scale Lithium-Ion Battery Modeling: Part I. On Through-Plane Heterogeneity, Impact of Mesh Representation, and Differences between Macro- and Microscale Models,' newline 'Journal of the Electrochemical Society, 172, 080508, 2025'];
            app.Hyperlink6 = uihyperlink(app.GridLayout5);
            app.Hyperlink6.URL = 'https://doi.org/10.1149/1945-7111/ae04a2';
            app.Hyperlink6.Text = '[7] F. Usseglio-Viretta et al., Microstructure Scale Lithium-Ion Battery Modeling: Part II. On In-Plane Heterogeneities and the Mechanisms that Regulate Them, Journal of the Electrochemical Society, 172, 090527, 2025';
            app.Hyperlink7 = uihyperlink(app.GridLayout5);
            app.Hyperlink7.URL = 'https://doi.org/10.1002/aic.18622';
            app.Hyperlink7.Text = ['[4] S. Lee et al., Pore classification method with steady-state diffusion in complex porous media, AIChE Journal, 71, e18622, 2024' newline ''];
            app.Hyperlink8 = uihyperlink(app.GridLayout5);
            app.Hyperlink8.URL = 'https://doi.org/10.1016/j.jpowsour.2025.236904';
            app.Hyperlink8.Text = '[10] W. Sun et al., Exploring the role of carbon binder domain morphology in enhancing the electrochemical performance of Li-ion battery, Journal of Power Sources, 641, 236904, 2025';
            app.Hyperlink9 = uihyperlink(app.GridLayout5);
            app.Hyperlink9.URL = 'https://doi.org/10.1016/j.ensm.2025.104381';
            app.Hyperlink9.Text = '[11] H. Huang et al., Digital techniques assisted in tailoring electrode structure to optimize electrode performance, Energy Storage Materials, 80, 104381, 2025';
            app.Hyperlink10 = uihyperlink(app.GridLayout5);
            app.Hyperlink10.URL = 'https://doi.org/10.1016/j.ensm.2024.103792';
            app.Hyperlink10.Text = '[3] H. Huang et al., XCT images-based modeling for elucidating electrochemical inert phase-dependent multiscale electrode kinetic behaviors, Energy Storage Materials, 73, 103792, 2024';
            app.Hyperlink11 = uihyperlink(app.GridLayout5);
            app.Hyperlink11.URL = 'https://doi.org/10.1149/1945-7111/ac7ef9';
            app.Hyperlink11.Text = '[1] F. Usseglio-Viretta et al., Carbon-Binder Weight Loading Optimization for Improved Lithium-Ion Battery Rate Capability, Journal of the Electrochemical Society, 169, 070519, 2022';
            app.Label_65 = uilabel(app.GridLayout5);
            app.Label_65.Text = '+ Si (2: particles, amorph, tubes, agglomerates) + Wetting (1: particles, Amorph, Shapes)';
            app.IssuesTab = uitab(app.AboutTabGroup);
            app.IssuesTab.Title = 'Issues';
            app.GridLayout5_4 = uigridlayout(app.IssuesTab);
            app.Label_66 = uilabel(app.GridLayout5_4);
            app.Label_66.Text = 'If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink13 = uihyperlink(app.GridLayout5_4);
            app.Hyperlink13.URL = 'https://github.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink13.Text = 'https://github.com/NatLabRockies/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Label_69 = uilabel(app.UIFigure);
            app.Label_69.Text = {'Plan:'; '1) creates all inputs + naming convention + save all inputs: DONE'; '2) Mask options: DONE'; '3) Particle options'; '4) Run'; '5) Create new file: unifed_microstructure_generation.m    loop over generation steps and call algorithm for each morphology type'; '6) Additive options'; '7) Post-processing options'; '8) Predetermined options '; '9) tubes options '; '10) Web options'; '11) Upscaling'; '12) Re-generates'; '13) Examples'; '14) Make sure all instructions are done'; '15) Show to the group'};
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Additions compared to v1'; '- parameter sweeping'; '- 3D heterogeneity (and not only 1D gradation)'; '- particle redistribution'; '- regenerate in the UI'; '- import examples'; '- merge ellipsoids, tube, additives, surface roughness in one'; '- multi-step generation in one call'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_stochastic_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end