classdef Microstructure_generation_stochastic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EllipsoidbasedstochasticgenerationsubmoduleUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Instructions                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SeeexamplesButton_3             matlab.ui.control.Button
        ClickonthisiconwhenvisibletoseesomeparameterexamplesLabel  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_19  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_18  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_17  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        SelectasolidphaseandsetthenumberofLabel_16  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_15  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_14  matlab.ui.control.Label
        RunthegenerationalgorithmLabel  matlab.ui.control.Label
        StepsperformedafterthegenerationprogressLabel  matlab.ui.control.Label
        SetalgorithmadditionalparametersLabel  matlab.ui.control.Label
        SetpropertiesdefinedwithanhistogramdistributionLabel  matlab.ui.control.Label
        SetpropertiesdefinedwithascalarLabel  matlab.ui.control.Label
        Instructions_title              matlab.ui.control.Label
        SaveTab                         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ForphaseandparticlelablelsaveforDropDown  matlab.ui.control.DropDown
        ForphaseandparticlelablelsaveforDropDownLabel  matlab.ui.control.Label
        SaveinputsoutpuscomparisonifselectedCheckBox  matlab.ui.control.CheckBox
        ThenselectwhatinformationyouwanttosaveLabel  matlab.ui.control.Label
        SelectwhereyouwanttosaveinputsandresultsLabel  matlab.ui.control.Label
        NosavefolderselectedLabel       matlab.ui.control.Label
        Save_folder_button              matlab.ui.control.Button
        SaveparticleadditionalinformationCheckBox  matlab.ui.control.CheckBox
        SavecharacterizationresultsifselectedCheckBox  matlab.ui.control.CheckBox
        SavealgorithmprogressionplotifselectedCheckBox  matlab.ui.control.CheckBox
        AdditionalLabel                 matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        SaveinmatinputsofthegenerationalgorithmfunctionCheckBox  matlab.ui.control.CheckBox
        Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox  matlab.ui.control.CheckBox
        Savephaselabelinformation3Dtifstackfileuint8formatCheckBox  matlab.ui.control.CheckBox
        Instructions_title_3            matlab.ui.control.Label
        VolumefractionsTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditField_2Label       matlab.ui.control.Label
        NumberSlice_volumefraction      matlab.ui.control.NumericEditField
        NumberofsolidphaseEditField_2Label  matlab.ui.control.Label
        NumberofsolidphaseEditFieldLabel  matlab.ui.control.Label
        NumberofsolidphaseEditField     matlab.ui.control.NumericEditField
        VoxelsizenmEditFieldLabel       matlab.ui.control.Label
        VoxelsizenmEditField            matlab.ui.control.NumericEditField
        ThensetthesolidvolumefractionsLabel_3  matlab.ui.control.Label
        Seeexamples_volumefraction_Button  matlab.ui.control.Button
        ThensetthesolidvolumefractionsLabel_2  matlab.ui.control.Label
        Phase_vf_save                   matlab.ui.control.Button
        Phase_vf_plot                   matlab.ui.control.Button
        PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel  matlab.ui.control.Label
        Volumefraction_UITable          matlab.ui.control.Table
        ThensetthesolidvolumefractionsLabel  matlab.ui.control.Label
        Instructions_title_2            matlab.ui.control.Label
        SetdimensionsLabel              matlab.ui.control.Label
        AlgothmisfasterforLabel         matlab.ui.control.Label
        Phase_domainUITable             matlab.ui.control.Table
        AlgothmisfasterforLabel_2       matlab.ui.control.Label
        SetNumberofsolidphasesLabel     matlab.ui.control.Label
        Phase_LabelnameUItable          matlab.ui.control.Table
        AlgothmisfasterforLabel_3       matlab.ui.control.Label
        SetvolumefractionsalongthemicrostructurethicknessLabel  matlab.ui.control.Label
        AlgothmisfasterforLabel_4       matlab.ui.control.Label
        AlgothmisfasterforLabel_5       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DiametersTab                    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        Selectphase_diameters_DropDown  matlab.ui.control.DropDown
        SelectphaseDropDownLabel        matlab.ui.control.Label
        DistributionsLabel              matlab.ui.control.Label
        Seeexamples_diameters_Button    matlab.ui.control.Button
        ForceodddiameterCheckBox        matlab.ui.control.CheckBox
        PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_2  matlab.ui.control.Label
        Diameter_save                   matlab.ui.control.Button
        Diameter_plot                   matlab.ui.control.Button
        DxDz_UITable                    matlab.ui.control.Table
        DxDy_UITable                    matlab.ui.control.Table
        AlgothmisfasterforLabel_7       matlab.ui.control.Label
        Rowii1isthediameterhistogramLabel  matlab.ui.control.Label
        Diameters_UITable               matlab.ui.control.Table
        SelectasolidphaseandsetthenumberofLabel_2  matlab.ui.control.Label
        Diameters_main_UITable          matlab.ui.control.Table
        AlgothmisfasterforLabel_6       matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel  matlab.ui.control.Label
        Instructions_diameter           matlab.ui.control.Label
        RotationsTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        InstructionsTextArea_2          matlab.ui.control.TextArea
        InstructionsTextArea_2Label     matlab.ui.control.Label
        Selectphase_orientation_DropDown  matlab.ui.control.DropDown
        SelectphaseDropDownLabel_2      matlab.ui.control.Label
        DistributionsLabel_2            matlab.ui.control.Label
        PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_3  matlab.ui.control.Label
        Rotation_save                   matlab.ui.control.Button
        Rotation_plot                   matlab.ui.control.Button
        Rotation_z_UITable              matlab.ui.control.Table
        Rotation_y_UITable              matlab.ui.control.Table
        AlgothmisfasterforLabel_9       matlab.ui.control.Label
        Rowii1isthediameterhistogramLabel_2  matlab.ui.control.Label
        Rotation_x_UITable              matlab.ui.control.Table
        SelectasolidphaseandsetthenumberofLabel_4  matlab.ui.control.Label
        Orientation_main_UITable        matlab.ui.control.Table
        AlgothmisfasterforLabel_8       matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_3  matlab.ui.control.Label
        Instructions_orientation        matlab.ui.control.Label
        ParticlevisualizationTab        matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Plot_example_particle_2         matlab.ui.control.Button
        Plot_example_particle           matlab.ui.control.Button
        Parameter_example_UITable       matlab.ui.control.Table
        AlgothmisfasterforLabel_10      matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_5  matlab.ui.control.Label
        Instructions_Visualization      matlab.ui.control.Label
        CroppingTab                     matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        Label2                          matlab.ui.control.Label
        ClickonthefiguretoenlargeLabel_2  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Cropping_UITable                matlab.ui.control.Table
        Cropping_Label                  matlab.ui.control.Label
        Instructions_overlapping_3      matlab.ui.control.Label
        OverlappingTab                  matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        SetvalueforwholematrixEditField  matlab.ui.control.NumericEditField
        SetvalueforwholematrixEditFieldLabel  matlab.ui.control.Label
        eachtimeEditField               matlab.ui.control.NumericEditField
        eachtimeEditFieldLabel          matlab.ui.control.Label
        IncreaseoverlappingmatrixbyEditField  matlab.ui.control.NumericEditField
        IncreaseoverlappingmatrixbyEditFieldLabel  matlab.ui.control.Label
        OverlappingrelaxationparametersLabel  matlab.ui.control.Label
        particlegenerationfailLabel     matlab.ui.control.Label
        Overlapping_save                matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        DisableparticlecontiguitycheckCheckBox  matlab.ui.control.CheckBox
        Minimumvolume_overlapping_UITable  matlab.ui.control.Table
        Label_4                         matlab.ui.control.Label
        overlapping_UITable             matlab.ui.control.Table
        Overlapping_Image               matlab.ui.control.Image
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Instructions_overlapping        matlab.ui.control.Label
        AlgorithmTab                    matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        rtthreshold_EditField           matlab.ui.control.NumericEditField
        rtEditFieldLabel                matlab.ui.control.Label
        TopselectionEditField           matlab.ui.control.NumericEditField
        TopselectionEditFieldLabel      matlab.ui.control.Label
        SelectionmethodDropDown         matlab.ui.control.DropDown
        SelectionmethodDropDownLabel    matlab.ui.control.Label
        n2selectbestcandidateEditField  matlab.ui.control.NumericEditField
        n2selectbestcandidateEditFieldLabel  matlab.ui.control.Label
        n1switchselectionmethodEditField  matlab.ui.control.NumericEditField
        n1switchselectionmethodEditFieldLabel  matlab.ui.control.Label
        n0overlappingcounterEditField   matlab.ui.control.NumericEditField
        n0overlappingcounterLabel       matlab.ui.control.Label
        CandidateselectionDropDown      matlab.ui.control.DropDown
        CandidateselectionDropDownLabel  matlab.ui.control.Label
        Rthreshold_CheckBox             matlab.ui.control.CheckBox
        Constraintn2n1n0Label           matlab.ui.control.Label
        ClickonthefiguretoenlargeLabel  matlab.ui.control.Label
        Algorithm_description           matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_27  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Instructions_overlapping_2      matlab.ui.control.Label
        OrderTab                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        NumberofgenerationpassEditField  matlab.ui.control.NumericEditField
        NumberofgenerationpassEditFieldLabel  matlab.ui.control.Label
        PseudocodeTextArea              matlab.ui.control.TextArea
        PseudocodeTextAreaLabel         matlab.ui.control.Label
        Order_save                      matlab.ui.control.Button
        Order_UITable                   matlab.ui.control.Table
        Label_6                         matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_7  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SelectasolidphaseandsetthenumberofLabel_6  matlab.ui.control.Label
        Instructions_order              matlab.ui.control.Label
        StopconditionsTab               matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        RefreshfigureseachsEditField    matlab.ui.control.NumericEditField
        RefreshfigureseachsEditFieldLabel  matlab.ui.control.Label
        MaximumwallclocktimesEditField  matlab.ui.control.NumericEditField
        MaximumwallclocktimesEditFieldLabel  matlab.ui.control.Label
        whenaveragedonthelastEditField  matlab.ui.control.NumericEditField
        whenaveragedonthelastEditFieldLabel  matlab.ui.control.Label
        particles1goesbelowEditField    matlab.ui.control.NumericEditField
        particles1goesbelowEditFieldLabel  matlab.ui.control.Label
        Ifvolumefractions1goesbelowEditField  matlab.ui.control.NumericEditField
        Ifvolumefractions1goesbelowEditFieldLabel  matlab.ui.control.Label
        PlotalsoporosityprogressionCheckBox  matlab.ui.control.CheckBox
        Label_8                         matlab.ui.control.Label
        AlgothmisfasterforLabel_11      matlab.ui.control.Label
        stopcondition_action_DropDown   matlab.ui.control.DropDown
        thenLabel                       matlab.ui.control.Label
        iterationsLabel                 matlab.ui.control.Label
        stopcondition_andor_DropDown    matlab.ui.control.DropDown
        SelectasolidphaseandsetthenumberofLabel_9  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_8  matlab.ui.control.Label
        PlotalgorithmprogressionCheckBox  matlab.ui.control.CheckBox
        Instructions_order_2            matlab.ui.control.Label
        MaskTab                         matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        ParticlecanbegeneratedonlyiftheircenterisEditField_2  matlab.ui.control.NumericEditField
        ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel_2  matlab.ui.control.Label
        ParticlecanbegeneratedonlyiftheircenterisEditField  matlab.ui.control.NumericEditField
        ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel  matlab.ui.control.Label
        ParticlescanNOToverlapwithmaskCheckBox  matlab.ui.control.CheckBox
        ClickonthefiguretoenlargeLabel_3  matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Mask_addexistingmaterial        matlab.ui.control.CheckBox
        UsewithinmaskCheckBox           matlab.ui.control.CheckBox
        UseNOTMOREconditionCheckBox     matlab.ui.control.CheckBox
        UseATLEASTconditionCheckBox     matlab.ui.control.CheckBox
        mask_volumefraction_label_2     matlab.ui.control.Label
        Mask_volume_name_label_2        matlab.ui.control.Label
        Visualize_mask_button_2         matlab.ui.control.Button
        iClicktoimportwithinmaskLabel   matlab.ui.control.Label
        ImportmaskButton_2              matlab.ui.control.Button
        AlgothmisfasterforLabel_18      matlab.ui.control.Label
        Cropping_Label_3                matlab.ui.control.Label
        Setto0toignoretwoconditionsyoucanusebothLabel  matlab.ui.control.Label
        mask_volumefraction_label       matlab.ui.control.Label
        Visualize_mask_button           matlab.ui.control.Button
        iiSetadditionaloverlappingdistanceconditionsoptionalLabel  matlab.ui.control.Label
        UsenotwithinmaskCheckBox        matlab.ui.control.CheckBox
        Mask_volume_name_label          matlab.ui.control.Label
        iClicktoimportnotwithinmaskLabel  matlab.ui.control.Label
        ImportmaskButton                matlab.ui.control.Button
        AlgothmisfasterforLabel_17      matlab.ui.control.Label
        Cropping_Label_2                matlab.ui.control.Label
        Instructions_order_3            matlab.ui.control.Label
        UpscaleoptionalTab              matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SolifVf_upscaling_DropDown      matlab.ui.control.DropDown
        StopregenerationatporosityEditField  matlab.ui.control.NumericEditField
        StopregenerationatporosityEditFieldLabel  matlab.ui.control.Label
        DistanceseparationvoxellengthEditField  matlab.ui.control.NumericEditField
        DistanceseparationvoxellengthEditFieldLabel  matlab.ui.control.Label
        DiameterscalingratioEditField   matlab.ui.control.NumericEditField
        DiameterscalingratioEditFieldLabel  matlab.ui.control.Label
        HowtoDropDown                   matlab.ui.control.DropDown
        HowtoDropDownLabel              matlab.ui.control.Label
        Scalingfactor1upscaling1downscaling1noscalingEditField  matlab.ui.control.NumericEditField
        Scalingfactor1upscaling1downscaling1noscalingEditFieldLabel  matlab.ui.control.Label
        ShuffleparticlesCheckBox        matlab.ui.control.CheckBox
        TargetnewporosityCheckBox       matlab.ui.control.CheckBox
        Upscale_ForceodddiametersCheckBox  matlab.ui.control.CheckBox
        ForceseparationCheckBox         matlab.ui.control.CheckBox
        ApplyscalingalsotoparticlelabelCheckBox  matlab.ui.control.CheckBox
        ApplyscalingtophaselabelCheckBox  matlab.ui.control.CheckBox
        Label_7                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SelectasolidphaseandsetthenumberofLabel_13  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_12  matlab.ui.control.Label
        Instructions_upscaling          matlab.ui.control.Label
        VerificationandCharacterizationTab  matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CharacterizeDropDown            matlab.ui.control.DropDown
        CharacterizeDropDownLabel       matlab.ui.control.Label
        CompareoutputsversusinputsCheckBox  matlab.ui.control.CheckBox
        SelectasolidphaseandsetthenumberofLabel_22  matlab.ui.control.Label
        PoretortuosityfactorslowforlargevolumesCheckBox  matlab.ui.control.CheckBox
        ConnectivityslowforlargevolumesCheckBox  matlab.ui.control.CheckBox
        VolumefractionsCheckBox         matlab.ui.control.CheckBox
        SelectasolidphaseandsetthenumberofLabel_21  matlab.ui.control.Label
        Instructions_characterization   matlab.ui.control.Label
        GenerationTab                   matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        NumberofrunsEditField           matlab.ui.control.NumericEditField
        NumberofrunsEditFieldLabel      matlab.ui.control.Label
        MakevideoCheckBox               matlab.ui.control.CheckBox
        WarningdonotresizefigureswhilegenerationisongoingLabel  matlab.ui.control.Label
        outcometime_UITable             matlab.ui.control.Table
        AlgothmisfasterforLabel_16      matlab.ui.control.Label
        AlgothmisfasterforLabel_15      matlab.ui.control.Label
        Tortuosity_UITable              matlab.ui.control.Table
        AlgothmisfasterforLabel_14      matlab.ui.control.Label
        Volume_fraction_UITable         matlab.ui.control.Table
        AlgothmisfasterforLabel_13      matlab.ui.control.Label
        AlgothmisfasterforLabel_12      matlab.ui.control.Label
        Connectivity_UITable            matlab.ui.control.Table
        SelectasolidphaseandsetthenumberofLabel_11  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_10  matlab.ui.control.Label
        Instructions_generation         matlab.ui.control.Label
        Generate_Button                 matlab.ui.control.Button
        VisualizationTab                matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        ForfurthercontrolintherenderingortocreatevideospleaseusethevisualizationmoduleLabel  matlab.ui.control.Label
        ParticlecolormapDropDown        matlab.ui.control.DropDown
        ParticlecolormapDropDownLabel   matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_26  matlab.ui.control.Label
        Plot_particle_label_3D          matlab.ui.control.Button
        Plot_phase_label_3D             matlab.ui.control.Button
        DviewLabel                      matlab.ui.control.Label
        DslicesLabel                    matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_25  matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_24  matlab.ui.control.Label
        Plot_particle_label             matlab.ui.control.Button
        Plot_phase_label                matlab.ui.control.Button
        SelectasolidphaseandsetthenumberofLabel_23  matlab.ui.control.Label
        Visualization_UITable           matlab.ui.control.Table
        Instructions_generation_3       matlab.ui.control.Label
        HowtoquoteTab                   matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Label_10                        matlab.ui.control.Label
        SelectasolidphaseandsetthenumberofLabel_20  matlab.ui.control.Label
        Instructions_generation_2       matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        SeeexamplesButton_2             matlab.ui.control.Button
        Label_16                        matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        OpendocumentationButton         matlab.ui.control.Button
        Instructions_title_4            matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Hyperlink_5                     matlab.ui.control.Hyperlink
        NREL_logo                       matlab.ui.control.Image
        Label_15                        matlab.ui.control.Label
        Instructions_generation_4       matlab.ui.control.Label
    end

    
    properties (Access = private)
        voxel_size = []; % Voxel size (before upscaling)
        domain_size = [];
        number_solidphase = [];
        currentphase_diameter_setup = 1;
        currentphase_elongation_setup = 1;
        sav_diameter = [];
        sav_elongation = [];
        
        % Input of the generation algorithm
        phase = []; 
        Maximum_overlapping = [];
        Minimum_particle_volume_conservated = [];
        disable_contiguituy_check = [];        
        
        % Mask
        mask_notwithin = [];
        mask_within = [];

        % Output
        generation_result = [];

        % Save options
        Savefolder = [];  
    end
    
    methods (Access = private)
        
        function [] = update_Phase_Labelname(app)
            str_name = {};
            for k=1:1:app.number_solidphase
                str_name = [str_name; ['Phase ' num2str(k)]];
            end
            app.Phase_LabelnameUItable.Data = [ num2cell([1:1:app.number_solidphase]') num2cell([1:1:app.number_solidphase]') str_name ];
        end
                
        function [] = update_volumefraction_table(app)
            c_ = {'Normalized position along direction 3'};
            for k=1:1:app.number_solidphase
                c_(k+1,1) = app.Phase_LabelnameUItable.Data(k,3);
            end
            c_(k+2,1) = {'Porosity'};
            app.Volumefraction_UITable.ColumnName = c_;
            pos_ = round( linspace(0,1,app.NumberSlice_volumefraction.Value)' ,3);
            vf = round( ones(app.NumberSlice_volumefraction.Value, app.number_solidphase+1) .* 1/(app.number_solidphase+1) , 3);
            app.Volumefraction_UITable.Data = [num2cell(pos_) num2cell(vf)];
            
            edit_ = ones(1,app.number_solidphase+2); edit_(1,end) = 0; % Can edit all except porosity
            app.Volumefraction_UITable.ColumnEditable = logical(edit_);            
        end
        
        function [] = initialize_diameter_table(app)
            c_ = {'Normalized position along direction 3'};
            number_diameter = cell2mat(app.Diameters_main_UITable.Data(1,3));
            for k=1:1:number_diameter
                c_(k+1) = {['Dx ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Diameters_main_UITable.Data(1,2));
            d = zeros(number_slice+1,number_diameter+2)+NaN;
            d(1,2:end-1) = 17:2:17+2*(number_diameter-1);
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_diameter) *round(100/number_diameter,3);
            if number_diameter>1
                d(2:end,end-1) = 100 - (number_diameter-1) * round(100/number_diameter,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.Diameters_UITable.ColumnName = c_;
            app.Diameters_UITable.Data = d;
            edit_ = ones(1,number_diameter+2); edit_(1,end) = 0;
            app.Diameters_UITable.ColumnEditable = logical(edit_);   
        end
        
        function [] = initialize_dxdy_table(app)
            c_ = {'Normalized position along direction 3'};
            number_ratio = cell2mat(app.Diameters_main_UITable.Data(2,3));
            for k=1:1:number_ratio
                c_(k+1) = {['Dx/Dy ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Diameters_main_UITable.Data(2,2));
            d = zeros(number_slice+1,number_ratio+2)+NaN;
            d(1,2:end-1) = 1:0.5:1+0.5*(number_ratio-1);
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_ratio) *round(100/number_ratio,3);
            if number_ratio>1
                d(2:end,end-1) = 100 - (number_ratio-1) * round(100/number_ratio,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.DxDy_UITable.ColumnName = c_;
            app.DxDy_UITable.Data = d;       
            edit_ = ones(1,number_ratio+2); edit_(1,end) = 0;
            app.DxDy_UITable.ColumnEditable = logical(edit_);               
        end
        
        function [] = initialize_dxdz_table(app)
            c_ = {'Normalized position along direction 3'};
            number_ratio = cell2mat(app.Diameters_main_UITable.Data(3,3));
            for k=1:1:number_ratio
                c_(k+1) = {['Dx/Dz ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Diameters_main_UITable.Data(3,2));
            d = zeros(number_slice+1,number_ratio+2)+NaN;
            d(1,2:end-1) = 1:0.5:1+0.5*(number_ratio-1);
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_ratio) *round(100/number_ratio,3);
            if number_ratio>1
                d(2:end,end-1) = 100 - (number_ratio-1) * round(100/number_ratio,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.DxDz_UITable.ColumnName = c_;
            app.DxDz_UITable.Data = d;              
            edit_ = ones(1,number_ratio+2); edit_(1,end) = 0;
            app.DxDz_UITable.ColumnEditable = logical(edit_); 
        end
        
        function [] = initialize_Ox_table(app)
            c_ = {'Normalized position along direction 3'};
            number_orientation = cell2mat(app.Orientation_main_UITable.Data(1,3));
            for k=1:1:number_orientation
                c_(k+1) = {['Rx ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Orientation_main_UITable.Data(1,2));
            d = zeros(number_slice+1,number_orientation+2)+NaN;
            initial_degree = linspace(0,180,number_orientation+1);
            d(1,2:end-1) = initial_degree(2:end); % do not take 0
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_orientation) *round(100/number_orientation,3);
            if number_orientation>1
                d(2:end,end-1) = 100 - (number_orientation-1) * round(100/number_orientation,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.Rotation_x_UITable.ColumnName = c_;
            app.Rotation_x_UITable.Data = d;
            edit_ = ones(1,number_orientation+2); edit_(1,end) = 0;
            app.Rotation_x_UITable.ColumnEditable = logical(edit_);              
        end
        
        function [] = initialize_Oy_table(app)
            c_ = {'Normalized position along direction 3'};
            number_orientation = cell2mat(app.Orientation_main_UITable.Data(2,3));
            for k=1:1:number_orientation
                c_(k+1) = {['Ry ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Orientation_main_UITable.Data(2,2));
            d = zeros(number_slice+1,number_orientation+2)+NaN;
            initial_degree = linspace(0,180,number_orientation+1);
            d(1,2:end-1) = initial_degree(2:end); % do not take 0
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_orientation) *round(100/number_orientation,3);
            if number_orientation>1
                d(2:end,end-1) = 100 - (number_orientation-1) * round(100/number_orientation,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.Rotation_y_UITable.ColumnName = c_;
            app.Rotation_y_UITable.Data = d;
            edit_ = ones(1,number_orientation+2); edit_(1,end) = 0;
            app.Rotation_y_UITable.ColumnEditable = logical(edit_);               
        end
        
        function [] = initialize_Oz_table(app)
            c_ = {'Normalized position along direction 3'};
            number_orientation = cell2mat(app.Orientation_main_UITable.Data(3,3));
            for k=1:1:number_orientation
                c_(k+1) = {['Rz ' num2str(k)]};
            end
            c_(k+2) = {'Total'};     
            number_slice = cell2mat(app.Orientation_main_UITable.Data(3,2));
            d = zeros(number_slice+1,number_orientation+2)+NaN;
            initial_degree = linspace(0,180,number_orientation+1);
            d(1,2:end-1) = initial_degree(2:end); % do not take 0
            d(2:end,1) = round(linspace(0,1,number_slice), 3);
            d(2:end,2:end-1) = ones(number_slice,number_orientation) *round(100/number_orientation,3);
            if number_orientation>1
                d(2:end,end-1) = 100 - (number_orientation-1) * round(100/number_orientation,3); % Make sure total is exactly 100
            end
            d(2:end,end) = sum(d(2:end,2:end-1),2);
            app.Rotation_z_UITable.ColumnName = c_;
            app.Rotation_z_UITable.Data = d;
            edit_ = ones(1,number_orientation+2); edit_(1,end) = 0;
            app.Rotation_z_UITable.ColumnEditable = logical(edit_);              
        end        
        
        
        function [] = Initialize_order_table(app)
            number_pass = app.NumberofgenerationpassEditField.Value;
            n_phase = length(app.Phase_LabelnameUItable.Data(:,3));
            for k=1:1:number_pass
                r_(k,1) = {['Pass ' num2str(k)]};
            end
            app.Order_UITable.ColumnName = app.Phase_LabelnameUItable.Data(:,3);
            app.Order_UITable.RowName = r_;
            order_ = zeros(number_pass,n_phase);
            for k=1:1:n_phase
                order_(:,k) = linspace(0.25,1,number_pass)';
            end
            app.Order_UITable.Data = order_;
            edit_ = ones(1,n_phase);
            app.Order_UITable.ColumnEditable = logical(edit_);
        end
        
        function Res = Connectivity_mainclusterpercent(app,M,label)
            % Simplified version of the function_connectivity of the characterization module
            binary_phase=zeros(size(M)); % Initialization
            binary_phase(M == label) = 1;
            connected_id = 1;
            isolated_id = 2;
            unknown_id = 3;
            [Connectivity_structure] = Function_Connectivity_Algorithm(binary_phase, 1, connected_id, unknown_id, isolated_id); % Call algorithm
            Res = Connectivity_structure.Clusters_LargestIsolatedUnknown.main_cluster_phasefraction;
        end

        
        function t = Build_table_fromUItab(app,uit)
            d = uit.Data;
            [~,n_column] = size(d);
            str = 'table(d(:,1)';
            for k=2:1:n_column
                str = [str ',d(:,' num2str(k) ')'];
            end
            str = [str ',''VariableNames'',(uit.ColumnName)'');'];
            t = eval(str);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Phase tab
            app.voxel_size = app.VoxelsizenmEditField.Value;
            app.domain_size = [100 100 100];
            app.Phase_domainUITable.Data = [ num2cell([1;2;3]) num2cell(app.domain_size') num2cell([app.domain_size.*app.voxel_size./1000]') ];
            % Phase name
            app.number_solidphase = app.NumberofsolidphaseEditField.Value;
            app.update_Phase_Labelname;
            % Volume fraction table
            app.update_volumefraction_table;
            % Cropping table
            app.Cropping_UITable.ColumnFormat = {'numeric','numeric','logical','numeric','logical'};
            app.Cropping_UITable.Data = [ num2cell([1;2;3]) num2cell([0;0;0]) {false;false;false} num2cell([0;0;0]) {false;false;false}];
            % Particle visualization table
            app.Parameter_example_UITable.Data = [{'Diameter Dx';'Diameter ratio Dx/Dy';'Diameter ratio Dx/Dz';'Rotation normal with x-axis Rx';'Rotation normal with y-axis Ry';'Rotation normal with z-axis Rz'} num2cell([17;1;1;180;180;180]) {'Number of voxel';'[]';'[]';'Degree';'Degree';'Degree'} {'>1, integer';']0,+inf[';']0,+inf[';']0,180]';']0,180]';']0,180]'} ];
            % Visualization table
            app.Visualization_UITable.ColumnFormat = {'numeric','logical','logical'};
            
            % Algorithm
            app.CandidateselectionDropDownValueChanged;
        end

        % Cell edit callback: Phase_domainUITable
        function Phase_domainUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2 % Modified number of voxel
                if isnan(newData) || newData<1 || round(newData)~=newData % Incorrect input
                    app.Phase_domainUITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                    f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');
                else
                    app.Phase_domainUITable.Data(:,3) = num2cell([ cell2mat(app.Phase_domainUITable.Data(:,2)).*app.voxel_size./1000]');
                end
            end
        end

        % Value changed function: VoxelsizenmEditField
        function VoxelsizenmEditFieldValueChanged(app, event)
            value = app.VoxelsizenmEditField.Value;
            if isnan(value) || value<0 % Incorrect input
                app.VoxelsizenmEditField.Value = app.voxel_size;
                f = msgbox('Invalid Value: must be >0', 'Error','warn');
            else
                app.voxel_size = value;
                app.Phase_domainUITable.Data(:,3) = num2cell([ cell2mat(app.Phase_domainUITable.Data(:,2)).*app.voxel_size./1000]');
            end     
        end

        % Value changed function: NumberofsolidphaseEditField
        function NumberofsolidphaseEditFieldValueChanged(app, event)
            value = app.NumberofsolidphaseEditField.Value;
            if isnan(value) || value<1 || round(value)~=value % Incorrect input
                app.NumberofsolidphaseEditField.Value = app.number_solidphase;
                f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');
            else
                app.number_solidphase = value;
                app.update_Phase_Labelname;
                app.update_volumefraction_table;
            end            
        end

        % Cell edit callback: Phase_LabelnameUItable
        function Phase_LabelnameUItableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2 % Modified label
                if isnan(newData) || newData<1 || round(newData)~=newData % Incorrect input
                    app.Phase_LabelnameUItable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                    f = msgbox({'Invalid Value: must be integer >=1','Label 0 is reserved for background'}, 'Error','warn');
                else
                    tentative = cell2mat(app.Phase_LabelnameUItable.Data(:, 2));
                    if length(tentative)~=length(unique(tentative)) % Not unique
                        app.Phase_LabelnameUItable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                        f = msgbox('Invalid Value: all label must be different', 'Error','warn');
                    else
                        app.update_volumefraction_table;
                    end
                end
            elseif indices(2)==3 % Modified name
                app.update_volumefraction_table;
            end
        end

        % Value changed function: NumberSlice_volumefraction
        function NumberSlice_volumefractionValueChanged(app, event)
            value = app.NumberSlice_volumefraction.Value;
            if isnan(value) || value<2 || round(value)~=value % Incorrect input
                f = msgbox('Invalid Value: must be integer >=2', 'Error','warn');
            else
                app.update_volumefraction_table;
            end  
        end

        % Cell edit callback: Volumefraction_UITable
        function Volumefraction_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData)
                f = msgbox('Invalid Value: must be float between 0 and 1', 'Error','warn');
                app.Volumefraction_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
            else
                pos_ = round(cell2mat(app.Volumefraction_UITable.Data(:,1)), 4);
                vf_solid = round(cell2mat(app.Volumefraction_UITable.Data(:,2:end-1)), 4);
                % Check position
                if min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.Volumefraction_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
                % Check solid volume fraction
                if min(min(vf_solid))<0 || max(max(vf_solid))>1 || sum(sum(vf_solid,2)>=1)>=1
                    f = msgbox({'Invalid Value: must be float between 0 and 1','and sum of solid volume fraction below 1'}, 'Error','warn');
                    app.Volumefraction_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
                % Round
                app.Volumefraction_UITable.Data(:,1) = num2cell( round( cell2mat(app.Volumefraction_UITable.Data(:,1)), 4));
                app.Volumefraction_UITable.Data(:,2:end-1) = num2cell( round( cell2mat(app.Volumefraction_UITable.Data(:,2:end-1)), 4));
                % Deduce porosity
                vf_solid = cell2mat(app.Volumefraction_UITable.Data(:,2:end-1));
                vf_pore = 1-sum(vf_solid,2);
                app.Volumefraction_UITable.Data(:,end) = num2cell(vf_pore);
            end
            

        end

        % Button pushed function: Phase_vf_plot
        function Phase_vf_plotButtonPushed(app, event)
            % Data
            pos_ = cell2mat(app.Volumefraction_UITable.Data(:,1));
            vf_solid = cell2mat(app.Volumefraction_UITable.Data(:,2:end-1));
            vf_pore = cell2mat(app.Volumefraction_UITable.Data(:,end));
            phase_name = app.Phase_LabelnameUItable.Data(:,3);
            [~,n_phase] = size(vf_solid);
            
            Fig = figure; % Create figure
            Fig.Name= 'Volume fractions input'; % Figure name
            Fig.Color='white'; % Background colour
            ax_=axes('parent',Fig);
            h_title = title('Volume fractions input');
            hold(ax_,'on'); % Active subplot
            for k=1:1:n_phase
                plot(pos_,vf_solid(:,k),'LineWidth',2,'LineStyle','-','MarkerSize',12,'Marker','+','DisplayName',char(phase_name(k)));
            end
            plot(pos_,vf_pore,'LineWidth',2,'LineStyle','--','MarkerSize',12,'Marker','+','Color','k','DisplayName','Porosity');
            xlabel('Normalized position along direction 3 (thickness)');
            ylabel('Volume fraction');
            legend('Location','best');
            grid(ax_,'on'); % Display grid
            set(ax_,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize
            hold(ax_,'off'); % Relase axe
        end

        % Button pushed function: Phase_vf_save
        function Phase_vf_saveButtonPushed(app, event)
            % Save in phase
            pos_ = cell2mat(app.Volumefraction_UITable.Data(:,1));
            vf_solid = cell2mat(app.Volumefraction_UITable.Data(:,2:end-1));
            phase_name = app.Phase_LabelnameUItable.Data(:,3);
            phase_label = app.Phase_LabelnameUItable.Data(:,2);
            [~,n_phase] = size(vf_solid);
            for k = 1:1:n_phase
                app.phase(k).name = char(phase_name(k));
                app.phase(k).code = cell2mat(phase_label(k));
                app.phase(k).volumefraction.along_3rd_axis = [pos_ vf_solid(:,k)]';
            end 

            app.domain_size = cell2mat(app.Phase_domainUITable.Data(:,2)');
            % % Diameter tab
            % Prepare diameter main tab
            app.Selectphase_diameters_DropDown.Items = phase_name;
            app.Selectphase_diameters_DropDown.Value = phase_name(1);
            app.Diameters_main_UITable.Data = [{'Diameter Dx';'Diameter ratio Dx/Dy';'Diameter ratio Dx/Dz'}  num2cell([2;2;2]) num2cell([1;1;1])];
            % Diameter and elongation tab
            app.initialize_diameter_table;
            app.initialize_dxdy_table;
            app.initialize_dxdz_table;
            % Save default values
            for k=1:1:n_phase
                app.currentphase_diameter_setup = k;
                app.Diameter_saveButtonPushed;
            end
            app.currentphase_diameter_setup = 1;
            % Enable GUI
            app.Selectphase_diameters_DropDown.Enable = 'on'; app.SelectphaseDropDownLabel.Enable = 'on';
            app.Diameters_main_UITable.Enable = 'on';
            app.Diameters_UITable.Enable = 'on'; app.DxDz_UITable.Enable = 'on'; app.DxDy_UITable.Enable = 'on';
            app.Diameter_plot.Enable = 'on'; app.Diameter_save.Enable = 'on';
            
            % % Orientations tab
            % Prepare orientation main tab
            app.Selectphase_orientation_DropDown.Items = phase_name;
            app.Selectphase_orientation_DropDown.Value = phase_name(1);
            app.Orientation_main_UITable.Data = [{'Rotation normal to x-axis (Rx)';'Rotation normal to y-axis (Ry)';'Rotation normal to z-axis (Rz)'}  num2cell([2;2;2]) num2cell([1;1;1])];
            % Diameter and elongation tab
            app.initialize_Ox_table;
            app.initialize_Oy_table;
            app.initialize_Oz_table;
            % Save default values
            for k=1:1:n_phase
                app.currentphase_elongation_setup = k;
                app.Rotation_saveButtonPushed;
            end
            app.currentphase_elongation_setup = 1;
            % Enable GUI
            app.Selectphase_orientation_DropDown.Enable = 'on'; app.SelectphaseDropDownLabel_2.Enable = 'on';
            app.Orientation_main_UITable.Enable = 'on';
            app.Rotation_x_UITable.Enable = 'on'; app.Rotation_y_UITable.Enable = 'on'; app.Rotation_z_UITable.Enable = 'on';
            app.Rotation_plot.Enable = 'on'; app.Rotation_save.Enable = 'on';            
            
            % % Overlapping tab
            % Prepare tab
            app.overlapping_UITable.ColumnName = app.Phase_LabelnameUItable.Data(:,3);
            app.overlapping_UITable.RowName = app.Phase_LabelnameUItable.Data(:,3);
            app.overlapping_UITable.Data = zeros(n_phase,n_phase)+0.5;
            edit_ = ones(1,n_phase);
            app.overlapping_UITable.ColumnEditable = logical(edit_);                       
            app.overlapping_UITable.Enable = 'on'; app.overlapping_UITable.Visible = 'on';
            app.Minimumvolume_overlapping_UITable.ColumnName = app.Phase_LabelnameUItable.Data(:,3);
            app.Minimumvolume_overlapping_UITable.Data = zeros(1,n_phase)+0.5;
            app.Minimumvolume_overlapping_UITable.ColumnEditable = logical(edit_);   
            app.Minimumvolume_overlapping_UITable.Enable = 'on'; app.Minimumvolume_overlapping_UITable.Visible = 'on';
            app.Overlapping_save.Enable = 'on';
            % Save default value
            app.Overlapping_saveButtonPushed;
            
            % Mask tab
            app.ImportmaskButton.Enable = 'on';
            app.Mask_volume_name_label.Text ='No mask file imported.';
            app.mask_volumefraction_label.Text ='No mask file imported.';
            app.mask_notwithin=[]; % Reset mask array
            app.Visualize_mask_button.Enable = 'off';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'off';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'off';
            app.UseATLEASTconditionCheckBox.Enable = 'off';
            app.UseNOTMOREconditionCheckBox.Enable = 'off';            
            app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
            app.Mask_addexistingmaterial.Enable = 'off';         

            app.ImportmaskButton_2.Enable = 'on';
            app.Mask_volume_name_label_2.Text ='No mask file imported.';
            app.mask_volumefraction_label_2.Text ='No mask file imported.';   
            app.mask_within=[]; % Reset mask array
            app.Visualize_mask_button_2.Enable = 'off';
            app.UsewithinmaskCheckBox.Enable = 'off'; app.UsewithinmaskCheckBox.Value = 0;

            % % Order tab
            app.Initialize_order_table;
            app.Order_UITable.Enable = 'on'; app.Order_UITable.Visible = 'on';
            app.Order_save.Enable = 'on';
            % Save default value
            app.Order_saveButtonPushed;            
            
            % % Generation tab
            app.Volume_fraction_UITable.ColumnName = [{'Run#'}, {'Pores'} app.Phase_LabelnameUItable.Data(:,3)'];
            app.Connectivity_UITable.ColumnName = [{'Run#'}, {'Pores'} app.Phase_LabelnameUItable.Data(:,3)', {'Union of solid phases'}];
                        
            app.Generate_Button.Enable = 'on';
            
            % % Move to diameter tab  
            app.TabGroup.SelectedTab = app.DiametersTab;           
        end

        % Value changed function: Selectphase_diameters_DropDown
        function Selectphase_diameters_DropDownValueChanged(app, event)
            value = app.Selectphase_diameters_DropDown.Value;
            app.currentphase_diameter_setup = find(ismember(app.Selectphase_diameters_DropDown.Items,value));
            % Re-load previous state
            app.Diameters_main_UITable.Data = app.sav_diameter(app.currentphase_diameter_setup).D_data;
            app.Diameters_UITable.Data = app.sav_diameter(app.currentphase_diameter_setup).Dx_data;
            app.Diameters_UITable.ColumnName = app.sav_diameter(app.currentphase_diameter_setup).Dx_ColumnName;
            app.DxDy_UITable.Data = app.sav_diameter(app.currentphase_diameter_setup).DxDy_data;
            app.DxDy_UITable.ColumnName = app.sav_diameter(app.currentphase_diameter_setup).DxDy_ColumnName;
            app.DxDz_UITable.Data = app.sav_diameter(app.currentphase_diameter_setup).DxDz_data;
            app.DxDz_UITable.ColumnName = app.sav_diameter(app.currentphase_diameter_setup).DxDz_ColumnName;              
        end

        % Cell edit callback: Diameters_main_UITable
        function Diameters_main_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2 % Number of slices
                if isnan(newData) || newData<2 || round(newData)~=newData
                    f = msgbox('Invalid Value: must be integer >=2', 'Error','warn');
                    app.Diameters_main_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
            elseif indices(2)==3 % Number of values
                if isnan(newData) || newData<1 || round(newData)~=newData
                    f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');
                    app.Diameters_main_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end                
            end
            if indices(1)==1
                app.initialize_diameter_table;
            elseif indices(1)==2
                app.initialize_dxdy_table;
            elseif indices(1)==3
                app.initialize_dxdz_table;
            end            
        end

        % Cell edit callback: Diameters_UITable
        function Diameters_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Diameter
                diam = app.Diameters_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<1 || round(newData)~=newData || sum(diam==sort(diam))~=length(diam) || length(diam)~=length(unique(diam) )
                    f = msgbox({'Invalid Value: must be integer >1','Diameters must be increasing and with no repeated value'}, 'Error','warn');
                    app.Diameters_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
                if app.ForceodddiameterCheckBox.Value
                    if rem(diam, 2) == 0
                        f = msgbox('Invalid Value: must be an odd integer', 'Error','warn');
                        app.Diameters_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                    end
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.Diameters_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.Diameters_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.Diameters_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.Diameters_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_diameter = cell2mat(app.Diameters_main_UITable.Data(1,3));
                    if number_diameter>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.Diameters_UITable.Data(indices(1),2:end-1) = rowp;
                    app.Diameters_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Diameter_plot.Enable = 'off';
                        app.Diameter_save.Enable = 'off';
                    else
                        app.Diameter_plot.Enable = 'on';
                        app.Diameter_save.Enable = 'on'; 
                    end
                end
            end
            app.Diameters_UITable.Data(1,1) = NaN;
            app.Diameters_UITable.Data(1,end) = NaN;
        end

        % Cell edit callback: DxDy_UITable
        function DxDy_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Ratio
                ratio = app.DxDy_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<0 || sum(ratio==sort(ratio))~=length(ratio) || length(ratio)~=length(unique(ratio) )
                    f = msgbox({'Invalid Value: must be >0','Ratio must be increasing and with no repeated value'}, 'Error','warn');
                    app.DxDy_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.DxDy_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.DxDy_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.DxDy_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.DxDy_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_ratio = cell2mat(app.Diameters_main_UITable.Data(2,3));
                    if number_ratio>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.DxDy_UITable.Data(indices(1),2:end-1) = rowp;
                    app.DxDy_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Diameter_plot.Enable = 'off';
                        app.Diameter_save.Enable = 'off';
                    else
                        app.Diameter_plot.Enable = 'on';
                        app.Diameter_save.Enable = 'on'; 
                    end
                end
            end
            app.DxDy_UITable.Data(1,1) = NaN;
            app.DxDy_UITable.Data(1,end) = NaN;            
        end

        % Cell edit callback: DxDz_UITable
        function DxDz_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Ratio
                ratio = app.DxDz_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<0 || sum(ratio==sort(ratio))~=length(ratio) || length(ratio)~=length(unique(ratio) )
                    f = msgbox({'Invalid Value: must be >0','Ratio must be increasing and with no repeated value'}, 'Error','warn');
                    app.DxDz_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.DxDz_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.DxDz_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.DxDz_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.DxDz_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_ratio = cell2mat(app.Diameters_main_UITable.Data(3,3));
                    if number_ratio>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.DxDz_UITable.Data(indices(1),2:end-1) = rowp;
                    app.DxDz_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Diameter_plot.Enable = 'off';
                        app.Diameter_save.Enable = 'off';
                    else
                        app.Diameter_plot.Enable = 'on';
                        app.Diameter_save.Enable = 'on'; 
                    end
                end
            end
            app.DxDz_UITable.Data(1,1) = NaN;
            app.DxDz_UITable.Data(1,end) = NaN;             
        end

        % Button pushed function: Diameter_plot
        function Diameter_plotButtonPushed(app, event)
            Fig = figure; % Create figure
            Fig.Name= 'Particle diameter and elongation inputs'; % Figure name
            scrsz = get(0,'ScreenSize'); % Screen resolution
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)/2]); % Full screen figure
            Fig.Color='white'; % Background colour
            for id_axe=1:1:3
                if id_axe==1
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplot
                    h_title=title ('Diameter Dx (voxel length)');
                    x = app.Diameters_UITable.Data(2:end,1);
                    y = app.Diameters_UITable.Data(2:end,2:end-1);
                    A1 = area(x,y);
                    [~,number_diameter] = size(y);
                    for k=1:1:number_diameter
                        set(A1(k),'DisplayName',['Dx ' num2str(k) ' = ' num2str(app.Diameters_UITable.Data(1,k+1))]);
                    end
                elseif id_axe==2
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplo
                    h_title=title ('Diameter ratio Dx/Dy');
                    x = app.DxDy_UITable.Data(2:end,1);
                    y = app.DxDy_UITable.Data(2:end,2:end-1);  
                    A2 = area(x,y);
                    [~,number_ratio] = size(y);
                    for k=1:1:number_ratio
                        set(A2(k),'DisplayName',['Dx/Dy ' num2str(k) ' = ' num2str(app.DxDy_UITable.Data(1,k+1))]);
                    end                    
                elseif id_axe==3
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplo
                    h_title=title ('Diameter ratio Dx/Dz');
                    x = app.DxDz_UITable.Data(2:end,1);
                    y = app.DxDz_UITable.Data(2:end,2:end-1);    
                    A3 = area(x,y);
                    [~,number_ratio] = size(y);
                    for k=1:1:number_ratio
                        set(A3(k),'DisplayName',['Dx/Dz ' num2str(k) ' = ' num2str(app.DxDz_UITable.Data(1,k+1))]);
                    end                          
                end
                xlabel('Normalized position along direction 3 (thickness)');
                ylabel('%');
                legend('Location','best');
                h_maintitle=sgtitle('Particle diameter and elongation inputs');
                set(sub_axes,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
                set(h_title,'FontSize',14)
                set(h_maintitle,'FontSize',16,'FontName','Times new roman')
                hold(sub_axes,'off'); % Relase axe,'off'); % Relase axe
            end     
        end

        % Button pushed function: Diameter_save
        function Diameter_saveButtonPushed(app, event)
            % Save in phase
            Dz = app.Diameters_UITable.Data;
            DxDy = app.DxDy_UITable.Data;
            DxDz = app.DxDz_UITable.Data;
            app.phase(app.currentphase_diameter_setup).size_histogram.along_3rd_axis = (Dz(:,1:end-1));
            app.phase(app.currentphase_diameter_setup).elongation_histogram_dx_over_dy.along_3rd_axis = DxDy(:,1:end-1);
            app.phase(app.currentphase_diameter_setup).elongation_histogram_dx_over_dz.along_3rd_axis = DxDz(:,1:end-1);
            % Save for re-load
            app.sav_diameter(app.currentphase_diameter_setup).D_data = app.Diameters_main_UITable.Data;
            app.sav_diameter(app.currentphase_diameter_setup).Dx_data = app.Diameters_UITable.Data;
            app.sav_diameter(app.currentphase_diameter_setup).Dx_ColumnName = app.Diameters_UITable.ColumnName;
            app.sav_diameter(app.currentphase_diameter_setup).DxDy_data = app.DxDy_UITable.Data;
            app.sav_diameter(app.currentphase_diameter_setup).DxDy_ColumnName = app.DxDy_UITable.ColumnName;
            app.sav_diameter(app.currentphase_diameter_setup).DxDz_data = app.DxDz_UITable.Data;
            app.sav_diameter(app.currentphase_diameter_setup).DxDz_ColumnName = app.DxDz_UITable.ColumnName;            

        end

        % Value changed function: Selectphase_orientation_DropDown
        function Selectphase_orientation_DropDownValueChanged(app, event)
            value = app.Selectphase_orientation_DropDown.Value;
            app.currentphase_elongation_setup = find(ismember(app.Selectphase_orientation_DropDown.Items,value));
            % Re-load previous state
            app.Orientation_main_UITable.Data = app.sav_elongation(app.currentphase_elongation_setup).R_data;
            app.Rotation_x_UITable.Data = app.sav_elongation(app.currentphase_elongation_setup).Rx_data;
            app.Rotation_x_UITable.ColumnName = app.sav_elongation(app.currentphase_elongation_setup).Rx_ColumnName;
            app.Rotation_y_UITable.Data = app.sav_elongation(app.currentphase_elongation_setup).Ry_data;
            app.Rotation_y_UITable.ColumnName = app.sav_elongation(app.currentphase_elongation_setup).Ry_ColumnName;
            app.Rotation_z_UITable.Data = app.sav_elongation(app.currentphase_elongation_setup).Rz_data;
            app.Rotation_z_UITable.ColumnName = app.sav_elongation(app.currentphase_elongation_setup).Rz_ColumnName;               
        end

        % Cell edit callback: Orientation_main_UITable
        function Orientation_main_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2 % Number of slices
                if isnan(newData) || newData<2 || round(newData)~=newData
                    f = msgbox('Invalid Value: must be integer >=2', 'Error','warn');
                    app.Orientation_main_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
            elseif indices(2)==3 % Number of values
                if isnan(newData) || newData<1 || round(newData)~=newData
                    f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');
                    app.Diameters_maiOrientation_main_UITablen_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end                
            end
            if indices(1)==1
                app.initialize_Ox_table;
            elseif indices(1)==2
                app.initialize_Oy_table;
            elseif indices(1)==3
                app.initialize_Oz_table;
            end     
            
        end

        % Cell edit callback: Rotation_x_UITable
        function Rotation_x_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Rotation
                rot_ = app.Rotation_x_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<=0 || newData>180 || sum(rot_==sort(rot_))~=length(rot_) || length(rot_)~=length(unique(rot_) )
                    f = msgbox({'Invalid Value: must be >0, <=180','Rotations must be increasing and with no repeated value'}, 'Error','warn');
                    app.Rotation_x_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.Rotation_x_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.Rotation_x_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.Rotation_x_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.Rotation_x_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_rotation = cell2mat(app.Orientation_main_UITable.Data(1,3));
                    if number_rotation>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.Rotation_x_UITable.Data(indices(1),2:end-1) = rowp;
                    app.Rotation_x_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Rotation_plot.Enable = 'off';
                        app.Rotation_save.Enable = 'off';
                    else
                        app.Rotation_plot.Enable = 'on';
                        app.Rotation_save.Enable = 'on'; 
                    end
                end
            end
            app.Rotation_x_UITable.Data(1,1) = NaN;
            app.Rotation_x_UITable.Data(1,end) = NaN;            
        end

        % Cell edit callback: Rotation_y_UITable
        function Rotation_y_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Rotation
                rot_ = app.Rotation_y_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<=0 || newData>180 || sum(rot_==sort(rot_))~=length(rot_) || length(rot_)~=length(unique(rot_) )
                    f = msgbox({'Invalid Value: must be >0, <=180','Rotations must be increasing and with no repeated value'}, 'Error','warn');
                    app.Rotation_y_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.Rotation_y_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.Rotation_y_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.Rotation_y_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.Rotation_y_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_rotation = cell2mat(app.Orientation_main_UITable.Data(2,3));
                    if number_rotation>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.Rotation_y_UITable.Data(indices(1),2:end-1) = rowp;
                    app.Rotation_y_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Rotation_plot.Enable = 'off';
                        app.Rotation_save.Enable = 'off';
                    else
                        app.Rotation_plot.Enable = 'on';
                        app.Rotation_save.Enable = 'on'; 
                    end
                end
            end
            app.Rotation_y_UITable.Data(1,1) = NaN;
            app.Rotation_y_UITable.Data(1,end) = NaN;              
        end

        % Cell edit callback: Rotation_z_UITable
        function Rotation_z_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 && indices(2)>1 % Rotation
                rot_ = app.Rotation_z_UITable.Data(1,2:end-1);
                if isnan(newData) || newData<=0 || newData>180 || sum(rot_==sort(rot_))~=length(rot_) || length(rot_)~=length(unique(rot_) )
                    f = msgbox({'Invalid Value: must be >0, <=180','Rotations must be increasing and with no repeated value'}, 'Error','warn');
                    app.Rotation_z_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)==1 % Position
                pos_ = app.Rotation_z_UITable.Data(2:end,1);
                if isnan(newData) || min(pos_)<0 || max(pos_)>1 || pos_(1)~=0 || pos_(end)~=1 || sum(pos_==sort(pos_))~=length(pos_) || length(pos_)~=length(unique(pos_))
                    f = msgbox({'Invalid Value: must be float between 0 and 1','increasing, with first value=0, last value=1','and with no repeated value'}, 'Error','warn');
                    app.Rotation_z_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                end
            elseif indices(1)>1 && indices(2)>1 % Percentage
                rowp = round(app.Rotation_z_UITable.Data(indices(1),2:end-1), 3); % Round it
                if isnan(newData) || newData<0 || newData>100
                    f = msgbox({'Invalid Value: must be float between 0 and 100','with sum equal to 100'}, 'Error','warn');
                    app.Rotation_z_UITable.Data(indices(1), indices(2)) = event.PreviousData;
                else
                    number_rotation = cell2mat(app.Orientation_main_UITable.Data(3,3));
                    if number_rotation>1 && abs(sum(rowp)-100)<0.1
                        rowp(end) = 100-sum(rowp(1:end-1)); % Make sure 100%
                    end
                    app.Rotation_z_UITable.Data(indices(1),2:end-1) = rowp;
                    app.Rotation_z_UITable.Data(indices(1),end) = sum(rowp);
                    if sum(rowp)~=100
                        app.Rotation_plot.Enable = 'off';
                        app.Rotation_save.Enable = 'off';
                    else
                        app.Rotation_plot.Enable = 'on';
                        app.Rotation_save.Enable = 'on'; 
                    end
                end
            end
            app.Rotation_z_UITable.Data(1,1) = NaN;
            app.Rotation_z_UITable.Data(1,end) = NaN;             
        end

        % Button pushed function: Rotation_plot
        function Rotation_plotButtonPushed(app, event)
            Fig = figure; % Create figure
            Fig.Name= 'Particle rotation inputs'; % Figure name
            scrsz = get(0,'ScreenSize'); % Screen resolution
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*4/5 scrsz(4)/2]); % Full screen figure
            Fig.Color='white'; % Background colour
            for id_axe=1:1:3
                if id_axe==1
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplot
                    h_title=title ('Rotation Rx (degrees)');
                    x = app.Rotation_x_UITable.Data(2:end,1);
                    y = app.Rotation_x_UITable.Data(2:end,2:end-1);
                    A1 = area(x,y);
                    [~,number_diameter] = size(y);
                    for k=1:1:number_diameter
                        set(A1(k),'DisplayName',['Rx ' num2str(k) ' = ' num2str(app.Rotation_x_UITable.Data(1,k+1))]);
                    end
                elseif id_axe==2
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplo
                    h_title=title ('Rotation Ry (degrees)');
                    x = app.Rotation_y_UITable.Data(2:end,1);
                    y = app.Rotation_y_UITable.Data(2:end,2:end-1);
                    A2 = area(x,y);
                    [~,number_ratio] = size(y);
                    for k=1:1:number_ratio
                        set(A2(k),'DisplayName',['Ry ' num2str(k) ' = ' num2str(app.Rotation_y_UITable.Data(1,k+1))]);
                    end
                elseif id_axe==3
                    sub_axes=subplot(1,3,id_axe,'Parent',Fig);
                    hold(sub_axes,'on'); % Active subplo
                    h_title=title ('Rotation Rz (degrees)');
                    x = app.Rotation_z_UITable.Data(2:end,1);
                    y = app.Rotation_z_UITable.Data(2:end,2:end-1);
                    A3 = area(x,y);
                    [~,number_ratio] = size(y);
                    for k=1:1:number_ratio
                        set(A3(k),'DisplayName',['Rz ' num2str(k) ' = ' num2str(app.Rotation_z_UITable.Data(1,k+1))]);
                    end
                end
                xlabel('Normalized position along direction 3 (thickness)');
                ylabel('%');
                legend('Location','best');
                h_maintitle=sgtitle('Particle rotation inputs');
                set(sub_axes,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
                set(h_title,'FontSize',14)
                set(h_maintitle,'FontSize',16,'FontName','Times new roman')
                hold(sub_axes,'off'); % Relase axe,'off'); % Relase axe
            end
        end

        % Button pushed function: Rotation_save
        function Rotation_saveButtonPushed(app, event)
            % Save in phase
            Rx = app.Rotation_x_UITable.Data;
            Ry = app.Rotation_y_UITable.Data;
            Rz = app.Rotation_z_UITable.Data;
            app.phase(app.currentphase_elongation_setup).orientation_histogram_angledeg_x.along_3rd_axis = Rx(:,1:end-1);
            app.phase(app.currentphase_elongation_setup).orientation_histogram_angledeg_y.along_3rd_axis = Ry(:,1:end-1);
            app.phase(app.currentphase_elongation_setup).orientation_histogram_angledeg_z.along_3rd_axis = Rz (:,1:end-1);
            % Save for re-load
            app.sav_elongation(app.currentphase_elongation_setup).R_data = app.Orientation_main_UITable.Data;
            app.sav_elongation(app.currentphase_elongation_setup).Rx_data = app.Rotation_x_UITable.Data;
            app.sav_elongation(app.currentphase_elongation_setup).Rx_ColumnName = app.Rotation_x_UITable.ColumnName;
            app.sav_elongation(app.currentphase_elongation_setup).Ry_data = app.Rotation_y_UITable.Data;
            app.sav_elongation(app.currentphase_elongation_setup).Ry_ColumnName = app.Rotation_y_UITable.ColumnName;
            app.sav_elongation(app.currentphase_elongation_setup).Rz_data = app.Rotation_z_UITable.Data;
            app.sav_elongation(app.currentphase_elongation_setup).Rz_ColumnName = app.Rotation_z_UITable.ColumnName;             
        end

        % Cell edit callback: Parameter_example_UITable
        function Parameter_example_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(1)==1 % Diameter
                if isnan(newData) || newData<1 || round(newData)~=newData % Incorrect input
                    f = msgbox('Invalid Value: must be integer >=1', 'Error','warn');    
                    app.Parameter_example_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
            elseif indices(1)==2 || indices(1)==3 % Ratio
                if isnan(newData) || newData<=0 % Incorrect input
                    f = msgbox('Invalid Value: must be >0', 'Error','warn');
                    app.Parameter_example_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end
            else % Rotation
                if isnan(newData) || newData<=0 || newData>180 % Incorrect input
                    f = msgbox('Invalid Value: must be >0 <=180', 'Error','warn');
                    app.Parameter_example_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                end                
            end           
        end

        % Button pushed function: Plot_example_particle
        function Plot_example_particleButtonPushed(app, event)
            % Get inputs
            Dx = cell2mat(app.Parameter_example_UITable.Data(1,2));
            DxDy = cell2mat(app.Parameter_example_UITable.Data(2,2));
            DxDz = cell2mat(app.Parameter_example_UITable.Data(3,2));
            Rx = cell2mat(app.Parameter_example_UITable.Data(4,2));
            Ry = cell2mat(app.Parameter_example_UITable.Data(5,2));
            Rz = cell2mat(app.Parameter_example_UITable.Data(6,2));
            % Generate ellipsoids
            Dy = max(Dx/DxDy,1);
            Dz = max(Dx/DxDz,1);
            [binary_ellipsoid] = create_ellipsoid(Dx,Dy,Dz);
            % Rotation
            if Rx~=180 || Ry~=180 || Rz~=180
                [binary_ellipsoid] = rotate_domain(binary_ellipsoid,Rx, Ry, Rz);
            end
            % Patch
            [f,v] = function_patch_3Darray(binary_ellipsoid);
            
            Fig = figure; % Create figure
            scrsz = get(0,'ScreenSize'); % Screen resolution
            set(Fig,'position',[scrsz(1) scrsz(2) scrsz(3)*2/3 scrsz(4)*2/3]); % Full screen figure            
            Fig.Name= 'Particle example'; % Figure name
            Fig.Color='white'; % Background colour
            ax_=axes('parent',Fig);
            h_title = title('Particle example');
            hold(ax_,'on'); % Active subplot
            %linelength = (1/3)*min([Dx,Dy,Dz]);
            %plot3([0 linelength],[0 0],[0 0],'DisplayName','Axe 1','Parent',ax_);
            %plot3([0 0],[0 linelength],[0 0],'DisplayName','Axe 2','Parent',ax_);
            %plot3([0 0],[0 0],[0 linelength],'DisplayName','Axe 3','Parent',ax_);
            col=v(:,3); % Z-axis is color altitude
            patch('Faces',f,'Vertices',v,'FaceVertexCData',col,'FaceColor','interp','DisplayName','Particle','Parent',ax_);
            h=colorbar(ax_);
            ylabel(h, 'z position');
            xlabel('x - 1st axis')
            ylabel('y - 2nd axis')
            zlabel('z - 3rd axis (thickness)')
            axis(ax_,'equal'); axis(ax_,'tight');
            %legend(ax_,{'x - 1st axis';'y - 2nd axis';'z - 3rd axis (thickness)';'Particle'},'Location','best');
            view(ax_,3)
            set(ax_,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
            set(h,'FontName','Times new roman','FontSize',12); % Fontname and fontsize
            h_title.FontSize = 14; % Set title fontsize   
            hold(ax_,'off'); % Relase axe
        end

        % Button pushed function: Plot_example_particle_2
        function Plot_example_particle_2ButtonPushed(app, event)
            % Get inputs
            Dx = cell2mat(app.Parameter_example_UITable.Data(1,2));
            DxDy = cell2mat(app.Parameter_example_UITable.Data(2,2));
            DxDz = cell2mat(app.Parameter_example_UITable.Data(3,2));
            Rx = cell2mat(app.Parameter_example_UITable.Data(4,2));
            Ry = cell2mat(app.Parameter_example_UITable.Data(5,2));
            Rz = cell2mat(app.Parameter_example_UITable.Data(6,2));
            % Generate ellipsoids
            Dy = Dx/DxDy;
            Dz = Dx/DxDz;
            [binary_ellipsoid] = create_ellipsoid(Dx,Dy,Dz);
            % Rotation
            if (Rx~=0 && Rx~=180) || (Ry~=0 && Ry~=180) || (Rz~=0 && Rz~=180)
                [binary_ellipsoid] = rotate_domain(binary_ellipsoid,Rx, Ry, Rz);
            end
            % Plot
            %Fig_3D=figure;
            %volshow(binary_ellipsoid,'Parent', Fig_3D,'BackgroundColor','w','Colormap',copper);
            vol = volshow(binary_ellipsoid);
        end

        % Cell edit callback: overlapping_UITable
        function overlapping_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData) || newData<0 % Incorrect input
                f = msgbox('Invalid Value: must be >0', 'Error','warn');
                app.overlapping_UITable.Data(indices(1), indices(2)) = event.PreviousData;
            else
                if indices(1)~=indices(2) % Enfore symmetry
                    app.overlapping_UITable.Data(indices(2), indices(1)) = app.overlapping_UITable.Data(indices(1), indices(2));
                end
            end
        end

        % Cell edit callback: Minimumvolume_overlapping_UITable
        function Minimumvolume_overlapping_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if isnan(newData) || newData<0 || newData>1  % Incorrect input
                f = msgbox('Invalid Value: must be [0,1]', 'Error','warn');
                app.Minimumvolume_overlapping_UITable.Data(indices(1), indices(2)) = event.PreviousData;
            end            
            
        end

        % Button pushed function: Overlapping_save
        function Overlapping_saveButtonPushed(app, event)
            app.Maximum_overlapping = app.overlapping_UITable.Data;
            app.Minimum_particle_volume_conservated = app.Minimumvolume_overlapping_UITable.Data;    
            app.disable_contiguituy_check = app.DisableparticlecontiguitycheckCheckBox.Value;
            % Move to next tab
            app.TabGroup.SelectedTab = app.AlgorithmTab;   
        end

        % Button pushed function: Order_save
        function Order_saveButtonPushed(app, event)
            % Get data
            fillratio = app.Order_UITable.Data;
            [~,number_phase] = size(fillratio);
            % Save
            for k = 1:1:number_phase
                app.phase(k).fillratio = fillratio(:,k);
            end
            % Move to next tab
            app.TabGroup.SelectedTab = app.StopconditionsTab;  
            
        end

        % Value changed function: NumberofgenerationpassEditField
        function NumberofgenerationpassEditFieldValueChanged(app, event)
              app.Initialize_order_table;   
        end

        % Cell edit callback: Order_UITable
        function Order_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            column_ = app.Order_UITable.Data(:,indices(2));
            if isnan(newData) || newData<0 || newData>1 || column_(end)<1 || sum(column_==sort(column_))~=length(column_)
                f = msgbox({'Invalid Value: must be [0,1]','and increasing (or stable) from pass i to pass i+1','Fill ratio for last pass must be 1'}, 'Error','warn');
                app.Order_UITable.Data(indices(1), indices(2)) = event.PreviousData;
            end
        end

        % Button pushed function: Generate_Button
        function Generate_ButtonPushed(app, event)
            % Clear table
            app.Volume_fraction_UITable.Data = [];
            app.outcometime_UITable.Data = [];
            app.Connectivity_UITable.Data = [];
            app.Tortuosity_UITable.Data = [];
            app.Visualization_UITable.Data = [];
            
            % Diameter
            forceodddiameter = app.ForceodddiameterCheckBox.Value;

            % Stop condition options
            stoping_conditions.plot = app.PlotalgorithmprogressionCheckBox.Value;
            stoping_conditions.plotporosity = app.PlotalsoporosityprogressionCheckBox.Value;
            stoping_conditions.refresheachs = app.RefreshfigureseachsEditField.Value;
            stoping_conditions.vfrate_threshold = app.Ifvolumefractions1goesbelowEditField.Value;
            stoping_conditions.andor = app.stopcondition_andor_DropDown.Value;
            stoping_conditions.particlerate_threshold = app.particles1goesbelowEditField.Value;
            stoping_conditions.average_on_last_n_iterations = app.whenaveragedonthelastEditField.Value;
            stoping_conditions.action = app.stopcondition_action_DropDown.Value;
            stoping_conditions.maxtime = app.MaximumwallclocktimesEditField.Value;
                        
            % Verification and characterization options
            do_verification = app.CompareoutputsversusinputsCheckBox.Value;
            postprocessing.volumefractions = app.VolumefractionsCheckBox.Value;
            postprocessing.connectivity = app.ConnectivityslowforlargevolumesCheckBox.Value;
            postprocessing.tortuosity = app.PoretortuosityfactorslowforlargevolumesCheckBox.Value;
            postprocessing.perform_on = app.CharacterizeDropDown.Value;
            postprocessing.save = app.SavecharacterizationresultsifselectedCheckBox.Value;
            
            % Scaling options
            scaling_factor = 1/app.Scalingfactor1upscaling1downscaling1noscalingEditField.Value;
            scale_phaselabel = app.ApplyscalingtophaselabelCheckBox.Value;
            scale_particlelabel = app.ApplyscalingalsotoparticlelabelCheckBox.Value;
            scale_diameterratio = app.DiameterscalingratioEditField.Value;
            if app.TargetnewporosityCheckBox.Value
                scale_porosity_target = app.StopregenerationatporosityEditField.Value;
            else
                scale_porosity_target = 0; % Ignore value
            end
            scale_particleshuffle = app.ShuffleparticlesCheckBox.Value;
            if strcmp(app.SolifVf_upscaling_DropDown.Value,'Keep solid phase volume fractions')
                scale_relativesolidvf = false;
                scale_solidvf = true;
            elseif strcmp(app.SolifVf_upscaling_DropDown.Value,'Keep solid phase relative volume fractions')
                scale_relativesolidvf = true;
                scale_solidvf = false;
            end

            % Numerical parameters
            tolerance = app.ToleranceEditField.Value;
            
            % Visualization tab
            app.Plot_phase_label.Enable = 'off';
            app.Plot_particle_label.Enable = 'off';      
            
            % Save options (within algorithm)
            save_options.folder = app.Savefolder;
            save_options.save_progression = app.SavealgorithmprogressionplotifselectedCheckBox.Value;
            save_options.save_verification = app.SaveinputsoutpuscomparisonifselectedCheckBox.Value;
            save_options.makevideo = app.MakevideoCheckBox.Value;
            
            % Prepare parameters
            overlapping.max = app.Maximum_overlapping;
            overlapping.minvolume = app.Minimum_particle_volume_conservated;
            overlapping.relax_increment = app.IncreaseoverlappingmatrixbyEditField.Value;            
            overlapping.relax_nfail = app.eachtimeEditField.Value;            
            
            % Voxel selection method
            acc.id_selector_behavior = app.CandidateselectionDropDown.Value;
            acc.n_fail_1 =  app.n1switchselectionmethodEditField.Value;
            acc.n_fail_2 =  app.n2selectbestcandidateEditField.Value;
            if strcmp(app.SelectionmethodDropDown.Value,'Distance map (dmap)')
                acc.idxsecondchoice = 'dmap';
            elseif strcmp(app.SelectionmethodDropDown.Value,'Volume fraction')
                acc.idxsecondchoice = 'z';
            else
                acc.idxsecondchoice = 'dmap and z';
            end
            acc.selectamongbest = app.TopselectionEditField.Value;
            acc.dmap_threshold = app.Rthreshold_CheckBox.Value;
            acc.dmapthreshold_ratio = app.rtthreshold_EditField.Value;

            % Cropping
            cropparameters = app.Cropping_UITable.Data(:,2:end);
            cropparameters = [cell2mat(cropparameters(:,1)) double(cell2mat(cropparameters(:,2))) cell2mat(cropparameters(:,3)) double(cell2mat(cropparameters(:,4)))];           

            % mask
            pMask.use_notwithin = app.UsenotwithinmaskCheckBox.Value;
            pMask.add_preexistingmaterial = app.Mask_addexistingmaterial.Value;
            pMask.mask_notwithin = app.mask_notwithin;
            pMask.dist_fromatleast = app.ParticlecanbegeneratedonlyiftheircenterisEditField.Value;  
            pMask.dist_fromnotmore = app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Value; 
            pMask.use_atleast = app.UseATLEASTconditionCheckBox.Value; 
            pMask.use_notmore = app.UseNOTMOREconditionCheckBox.Value;   
            pMask.use_within = app.UsewithinmaskCheckBox.Value;
            pMask.mask_within = app.mask_within;
            pMask.mask_used = [];
            if pMask.use_notwithin
                pMask.mask_used = pMask.mask_notwithin;
                if (pMask.use_atleast && pMask.dist_fromatleast>1) || (pMask.use_notmore && pMask.dist_fromnotmore>=2)
                    dmapmask = bwdist(pMask.mask_notwithin);
                    if pMask.use_atleast
                        pMask.mask_used( dmapmask < pMask.dist_fromatleast ) = 1;
                    end
                    if pMask.use_notmore
                        pMask.mask_used( dmapmask >= pMask.dist_fromnotmore ) = 1;
                    end
                end
                pMask.cannot_overlap_with_mask = app.ParticlescanNOToverlapwithmaskCheckBox.Value;
            end
            if pMask.use_within
                if pMask.use_notwithin
                    tmp = pMask.mask_notwithin + uint8(~pMask.mask_within);
                    tmp(tmp~=0)=1;
                    pMask.mask_used = tmp;
                else                    
                    pMask.mask_used = ~pMask.mask_within;
                end
                pMask.cannot_overlap_with_mask = true;
            end

            % Save inputs
            if ~isempty(app.Savefolder)
                if app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox.Value
                    domain_size = app.domain_size;
                    phase = app.phase;
                    check_contiguity = ~app.disable_contiguituy_check;
                    stopingconditions = stoping_conditions;
                    stopingconditions.plot = true; % Overwritte
                    stopingconditions.plotporosity = true; % Overwritte
                    stopingconditions.refresheachs = 5; % Overwritte
                    doverification = true; % Overwritte
                    saveoptions.folder = []; % Overwritte
                    save([app.Savefolder 'Inputs.mat'],'domain_size','phase','tolerance','forceodddiameter','overlapping','acc','cropparameters','pMask','check_contiguity','stopingconditions', 'doverification', 'saveoptions','-mat');
                end
            end

            % Save outputs
            save_phaselabel = app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox.Value;
            save_particlelabel = app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox.Value;
            save_original_andor_scaled = app.ForphaseandparticlelablelsaveforDropDown.Value;
            save_additionalinfo = app.SaveparticleadditionalinformationCheckBox.Value;
            
            number_run = app.NumberofrunsEditField.Value;
            wallclocktime = zeros(number_run,3);
            for k_run=1:1:number_run
                save_options.run_number = k_run;

                tic
                % Call generation algorithm
                [microstructure3D, ~, particle_data, outcome, overlapping_stat] = function_generate_ellipsoid_microstructure(app.domain_size,app.phase,tolerance,forceodddiameter,overlapping,acc,cropparameters,~app.disable_contiguituy_check, pMask, stoping_conditions, do_verification, save_options);
                %[microstructure3D, ~, particle_data, outcome, overlapping_stat] = function_generate_ellipsoid_microstructure_noconstraint(app.domain_size,app.phase,tolerance,forceodddiameter,overlapping,acc,cropparameters,~app.disable_contiguituy_check, pMask, stoping_conditions, do_verification, save_options);
                
                % [microstructure3D, ~, particle_data, outcome, overlapping_stat] = function_generate_ellipsoid_microstructure_adhoc(app.domain_size,app.phase,tolerance,forceodddiameter,overlapping,acc,cropparameters,~app.disable_contiguituy_check, pMask, stoping_conditions, do_verification, save_options);
                % iddiam = find( particle_data(:,6)==9 );
                % ids = particle_data(iddiam,1);
                % idx = find(microstructure3D.particle_id>=min(ids));
                % microstructure3D.phase(idx)=2;

                wallclocktime(k_run,1) = toc;
                % Save for later visualization
                app.generation_result(k_run).microstructure3D_phaselabel = microstructure3D.phase;
                app.generation_result(k_run).microstructure3D_particlelabel = microstructure3D.particle_id;

                % Save additional info
                if ~isempty(app.Savefolder)
                    if save_additionalinfo
                        %save([app.Savefolder 'Additionalinfo_run_' num2str(k_run) '.mat'],'microstructure3D','-mat'); Not needed
                        save([app.Savefolder 'Additionalinfo_particle_run_' num2str(k_run) '.mat'],'particle_data','-mat');  
                    end
                end

                % Save overlapping stat (porosity when overlappin starts, and ratio of overlapping over solid phases volume
                writematrix(overlapping_stat,[app.Savefolder 'Overlapping_run_' num2str(k_run) '.txt'],'Delimiter',';') 

                % Upscaling
                if scaling_factor~=1
                    tic
                    if strcmp(app.HowtoDropDown.Value,'Re-create from partice information (recommended)')
                        domain_size_rescaled = round(app.domain_size*1/scaling_factor);
                        particle_data_scaled = particle_data;
                        particle_data_scaled(:,3) = min(round( 1/scaling_factor * particle_data(:,3) + 0.5 ), domain_size_rescaled(1));
                        particle_data_scaled(:,4) = min(round( 1/scaling_factor * particle_data(:,4) + 0.5 ), domain_size_rescaled(2));
                        particle_data_scaled(:,5) = min(round( 1/scaling_factor * particle_data(:,5) + 0.5 ), domain_size_rescaled(3));
                        particle_data_scaled(:,6) = round(particle_data(:,6) * 1/scaling_factor);
                        particle_data_scaled(:,7) = round(particle_data(:,7) * 1/scaling_factor);
                        particle_data_scaled(:,8) = round(particle_data(:,8) * 1/scaling_factor);                        
                        % Force odd diameter
                        if app.Upscale_ForceodddiametersCheckBox.Value
                            id_iseven = find(rem(particle_data_scaled(:,6), 2) == 0);
                            particle_data_scaled(id_iseven,6) = particle_data_scaled(id_iseven,6)+1;
                            id_iseven = find(rem(particle_data_scaled(:,7), 2) == 0);
                            particle_data_scaled(id_iseven,7) = particle_data_scaled(id_iseven,7)+1;
                            id_iseven = find(rem(particle_data_scaled(:,8), 2) == 0);
                            particle_data_scaled(id_iseven,8) = particle_data_scaled(id_iseven,8)+1;
                        end
                        cropparameters(:,1) = round(cropparameters(:,1)*1/scaling_factor);

                        number_phase = length(app.phase);
                        label_original_vf = zeros(number_phase,2);
                        for kphase = 1:number_phase
                            label_original_vf(kphase,1) = app.phase(kphase).code;
                            x=app.phase(kphase).volumefraction.along_3rd_axis(1,:);
                            y=app.phase(kphase).volumefraction.along_3rd_axis(2,:);                            
                            label_original_vf(kphase,2) = trapz(x,y);
                        end
                        
                        [app.generation_result(k_run).microstructure3D_phaselabel_scaled, app.generation_result(k_run).microstructure3D_particlelabel_scaled, overlapping_stat_scaled] = function_createvolume_fromparticledata(particle_data_scaled,domain_size_rescaled, cropparameters, scale_diameterratio, app.ForceseparationCheckBox.Value, app.DistanceseparationvoxellengthEditField.Value, scale_porosity_target, label_original_vf, scale_particleshuffle, scale_relativesolidvf, scale_solidvf);                        
                        if pMask.use_notwithin && pMask.add_preexistingmaterial
                            parameters_scaling.scaling_factor = scaling_factor;
                            parameters_scaling.label_or_greylevel = 'Label';
                            parameters_scaling.background = 0;
                            mask_upscaled = function_scaling(pMask.mask_notwithin,parameters_scaling);  
                            idmask_upscaled = find(mask_upscaled);
                            app.generation_result(k_run).microstructure3D_phaselabel_scaled(idmask_upscaled) = max(max(max(app.generation_result(k_run).microstructure3D_phaselabel_scaled))) + 1;
                            app.generation_result(k_run).microstructure3D_particlelabel_scaled(idmask_upscaled) = max(max(max(app.generation_result(k_run).microstructure3D_particlelabel_scaled))) + 1;   
                        end
                        % Save overlapping stat (porosity when overlappin starts, and ratio of overlapping over solid phases volume
                        writematrix(overlapping_stat_scaled,[app.Savefolder 'Overlapping_scaled_run_' num2str(k_run) '.txt'],'Delimiter',';') 
                    else
                        % Set parameters
                        parameters_scaling.scaling_factor = scaling_factor;
                        parameters_scaling.label_or_greylevel = 'Label';
                        parameters_scaling.background = 0;
                        % Scale
                        if scale_phaselabel
                            app.generation_result(k_run).microstructure3D_phaselabel_scaled = function_scaling(app.generation_result(k_run).microstructure3D_phaselabel,parameters_scaling);
                        end
                        if scale_particlelabel
                            app.generation_result(k_run).microstructure3D_particlelabel_scaled = function_scaling(app.generation_result(k_run).microstructure3D_particlelabel,parameters_scaling);
                        end 
                    end
                    wallclocktime(k_run,2) = toc;
                end

                % % Characterization
                tic
                
                % Volume fractions
                if postprocessing.volumefractions
                    vf = zeros(1,app.number_solidphase+1);
                    if scaling_factor~=1 && strcmp(postprocessing.perform_on,'volume after upscaling')
                        vf(1) = sum(sum(sum( app.generation_result(k_run).microstructure3D_phaselabel_scaled==0 )));
                        for k_phase = 1:1:app.number_solidphase
                            vf(k_phase+1) = sum(sum(sum( app.generation_result(k_run).microstructure3D_phaselabel_scaled == app.phase(k_phase).code )));
                        end
                        vf = vf/numel(app.generation_result(k_run).microstructure3D_phaselabel_scaled);
                    else
                        vf(1) = sum(sum(sum( app.generation_result(k_run).microstructure3D_phaselabel==0 )));
                        for k_phase = 1:1:app.number_solidphase
                            vf(k_phase+1) = sum(sum(sum( app.generation_result(k_run).microstructure3D_phaselabel == app.phase(k_phase).code )));
                        end
                        vf = vf/numel(app.generation_result(k_run).microstructure3D_phaselabel);
                    end
                    if k_run==1
                        app.Volume_fraction_UITable.Data = [k_run, vf];
                    else
                        app.Volume_fraction_UITable.Data(k_run,:) = [k_run, vf];
                    end
                end
                
                % Connectivity
                if postprocessing.connectivity
                    conn = zeros(1,app.number_solidphase+2);
                    if scaling_factor~=1 && strcmp(postprocessing.perform_on,'volume after upscaling')
                        conn(1) = app.Connectivity_mainclusterpercent(app.generation_result(k_run).microstructure3D_phaselabel_scaled,0);
                        for k_phase = 1:1:app.number_solidphase
                            conn(k_phase+1) = app.Connectivity_mainclusterpercent(app.generation_result(k_run).microstructure3D_phaselabel_scaled,app.phase(k_phase).code);
                        end
                        tmp = zeros(size(app.generation_result(k_run).microstructure3D_phaselabel_scaled));
                        tmp(app.generation_result(k_run).microstructure3D_phaselabel_scaled~=0)=1;
                        conn(end) = app.Connectivity_mainclusterpercent(tmp,1);
                    else
                        conn(1) = app.Connectivity_mainclusterpercent(app.generation_result(k_run).microstructure3D_phaselabel,0);
                        for k_phase = 1:1:app.number_solidphase
                            conn(k_phase+1) = app.Connectivity_mainclusterpercent(app.generation_result(k_run).microstructure3D_phaselabel,app.phase(k_phase).code);
                        end       
                        tmp = zeros(size(app.generation_result(k_run).microstructure3D_phaselabel));
                        tmp(app.generation_result(k_run).microstructure3D_phaselabel~=0)=1;
                        conn(end) = app.Connectivity_mainclusterpercent(tmp,1);                        
                    end
                    if k_run==1
                        app.Connectivity_UITable.Data = [k_run, conn];
                    else
                        app.Connectivity_UITable.Data(k_run,:) = [k_run, conn];
                    end
                end
                
                % Pore tortuosity factor
                if postprocessing.tortuosity
                    tau = zeros(1,3);
                    if scaling_factor~=1 && strcmp(postprocessing.perform_on,'volume after upscaling')
                        binary_phase = zeros(size(app.generation_result(k_run).microstructure3D_phaselabel_scaled));
                        binary_phase(app.generation_result(k_run).microstructure3D_phaselabel_scaled==0)=1;
                    else
                        binary_phase = zeros(size(app.generation_result(k_run).microstructure3D_phaselabel));
                        binary_phase(app.generation_result(k_run).microstructure3D_phaselabel==0)=1;
                    end
                    tau(1) = TauFactor('InLine',1,0,0,binary_phase,[0 0 0;0 0 0;1 0 0],[1 1 1]).Tau_W1.Tau;
                    tau(2) = TauFactor('InLine',1,0,0,binary_phase,[0 0 0;0 0 0;0 1 0],[1 1 1]).Tau_W2.Tau;
                    tau(3) = TauFactor('InLine',1,0,0,binary_phase,[0 0 0;0 0 0;0 0 1],[1 1 1]).Tau_W3.Tau;
                    if k_run==1
                        app.Tortuosity_UITable.Data = [k_run, tau];
                    else
                        app.Tortuosity_UITable.Data(k_run,:) = [k_run, tau];
                    end
                end
                
                % End of characterization
                wallclocktime(k_run,3) = toc;
                
                % Outcome and wallclock time
                if k_run==1
                    app.outcometime_UITable.Data = [outcome, {num2str(wallclocktime(k_run,1),'%1.1f')}, {num2str(wallclocktime(k_run,2),'%1.1f')}, {num2str(wallclocktime(k_run,3),'%1.1f')}];
                else
                    app.outcometime_UITable.Data(k_run,:) = [outcome, {num2str(wallclocktime(k_run,1),'%1.1f')}, {num2str(wallclocktime(k_run,2),'%1.1f')}, {num2str(wallclocktime(k_run,3),'%1.1f')}];
                end                  
                
                % Save phase and particle label
                if ~isempty(app.Savefolder)
                    if save_phaselabel
                        if strcmp(save_original_andor_scaled,'volume before upscaling') || strcmp(save_original_andor_scaled,'both')
                            function_save_tif( uint8(app.generation_result(k_run).microstructure3D_phaselabel), [app.Savefolder 'Phaselabel_run_' num2str(k_run) '.tif']);
                        end
                        if scaling_factor~=1 && (strcmp(save_original_andor_scaled,'volume after upscaling') || strcmp(save_original_andor_scaled,'both'))
                            function_save_tif( uint8(app.generation_result(k_run).microstructure3D_phaselabel_scaled), [app.Savefolder 'Phaselabel_scaled_run_' num2str(k_run) '.tif']);
                        end
                    end
                    if save_particlelabel
                        if strcmp(save_original_andor_scaled,'volume before upscaling') || strcmp(save_original_andor_scaled,'both')
                            function_save_tif( uint16(app.generation_result(k_run).microstructure3D_particlelabel), [app.Savefolder 'Particlelabel_run_' num2str(k_run) '.tif']);
                        end
                        if scaling_factor~=1 && scale_particlelabel && (strcmp(save_original_andor_scaled,'volume after upscaling') || strcmp(save_original_andor_scaled,'both'))
                            function_save_tif( uint16(app.generation_result(k_run).microstructure3D_particlelabel_scaled), [app.Savefolder 'Particlelabel_scaled_run_' num2str(k_run) '.tif']);
                        end
                    end                    
                end
                
                % Save additional info
                if ~isempty(app.Savefolder)
                    if save_additionalinfo
                        %save([app.Savefolder 'Additionalinfo_run_' num2str(k_run) '.mat'],'microstructure3D','-mat');
                        %save([app.Savefolder 'Additionalinfo_particle_run_' num2str(k_run) '.mat'],'particle_data','-mat');  
                        if scaling_factor~=1 && strcmp(app.HowtoDropDown.Value,'Re-create from partice information (recommended)')
                            save([app.Savefolder 'Additionalinfo_particle_scaled_run_' num2str(k_run) '.mat'],'particle_data_scaled','-mat');
                        end
                    end
                end
                
                % % Visualization tab
                if scaling_factor==1
                    if k_run==1
                        app.Visualization_UITable.Data = [k_run, false, true];
                    else
                        app.Visualization_UITable.Data(k_run,:) = [k_run, false, true];
                    end 
                else
                    if k_run==1
                        app.Visualization_UITable.Data = [k_run, false, true];
                        app.Visualization_UITable.Data(2,:) = [k_run, true, true];
                    else
                        app.Visualization_UITable.Data(2*k_run-1,:) = [k_run, false, true];
                        app.Visualization_UITable.Data(2*k_run,:) = [k_run, true, true];
                    end                     
                end
                         
            end
            
            % Save characterization
            if ~isempty(app.Savefolder) && postprocessing.save
                filename = 'Characterization'; % Filename without extension
                % Prepare the data
                clear DATA_writetable

                if postprocessing.volumefractions
                    t = app.Build_table_fromUItab(app.Volume_fraction_UITable);
                    DATA_writetable.sheet(1).name='Volume_fractions';
                    DATA_writetable.sheet(1).table=t;
                end

                if postprocessing.connectivity
                    t = app.Build_table_fromUItab(app.Connectivity_UITable);
                    DATA_writetable.sheet(2).name='Connectivity';
                    DATA_writetable.sheet(2).table=t;
                end

                if postprocessing.tortuosity
                    t = app.Build_table_fromUItab(app.Tortuosity_UITable);
                    DATA_writetable.sheet(3).name='Tortuosity_factor';
                    DATA_writetable.sheet(3).table=t;
                end
                
                t = app.Build_table_fromUItab(app.outcometime_UITable);
                DATA_writetable.sheet(4).name='Outcome_time';
                DATA_writetable.sheet(4).table=t;                

                % Save function
                Function_Writetable(app.Savefolder,filename,DATA_writetable)
            end

            % Visualization tab
            app.Plot_phase_label.Enable = 'on';
            app.Plot_particle_label.Enable = 'on';
            app.Visualization_UITableCellEdit;                        
        end

        % Callback function
        function Doc_buttonButtonPushed(app, event)
            Find_file('NREL_MATBOX_Microstructure_analysis_toolbox_documentation.pdf','MATBOX_Microstructure_analysis_toolbox','Default location is \MATBOX_Microstructure_analysis_toolbox\Documentation\');                        
        end

        % Callback function
        function Github_buttonButtonPushed(app, event)
            url = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/';
            web(url)
        end

        % Image clicked function: NREL_logo
        function NREL_logoClicked(app, event)
            url = 'https://www.nrel.gov/';
            web(url)            
        end

        % Button pushed function: Plot_phase_label
        function Plot_phase_labelButtonPushed(app, event)
            runs = app.Visualization_UITable.Data(:,1);
            scaled = app.Visualization_UITable.Data(:,2);
            choices = app.Visualization_UITable.Data(:,3);
            idx = find(choices==1);
            if ~isempty(idx)
                if length(idx)==1 % One volume to visualize
                    if scaled(idx)
                        microstructure_visualization_slices(app.generation_result(runs(idx)).microstructure3D_phaselabel_scaled, ['Generated volume #' num2str(runs(idx)) ', scaled'], 1, 'voxel', [])
                    else
                        microstructure_visualization_slices(app.generation_result(runs(idx)).microstructure3D_phaselabel, ['Generated volume #' num2str(runs(idx))], 1, 'voxel', [])
                    end
                else % Several volumes to visualize
                    str = 'Microstructure_comparison_visualization_interface(';
                    for k=1:1:length(idx)
                        if scaled(idx(k))
                            str = [str 'app.generation_result(runs(idx(' num2str(k) '))).microstructure3D_phaselabel_scaled,'];
                        else
                            str = [str 'app.generation_result(runs(idx(' num2str(k) '))).microstructure3D_phaselabel,'];
                        end
                    end
                    str(end)=[]; % Remove last ,
                    str = [str ');'];
                    eval(str);
                end
            end            
        end

        % Button pushed function: Plot_particle_label
        function Plot_particle_labelButtonPushed(app, event)
            runs = app.Visualization_UITable.Data(:,1);
            scaled = app.Visualization_UITable.Data(:,2);
            choices = app.Visualization_UITable.Data(:,3);
            idx = find(choices==1);
            if ~isempty(idx)
                if length(idx)==1 % One volume to visualize
                    if scaled(idx)
                        microstructure_visualization_slices(app.generation_result(runs(idx)).microstructure3D_particlelabel_scaled, ['Generated volume #' num2str(runs(idx))], 1, 'voxel', [])
                    else
                        microstructure_visualization_slices(app.generation_result(runs(idx)).microstructure3D_particlelabel, ['Generated volume #' num2str(runs(idx)) ', scaled'], 1, 'voxel', [])
                    end
                else % Several volumes to visualize
                    str = 'Microstructure_comparison_visualization_interface(';
                    for k=1:1:length(idx)
                        if scaled(idx(k))
                            str = [str 'app.generation_result(runs(idx(' num2str(k) '))).microstructure3D_particlelabel_scaled,'];
                        else
                            str = [str 'app.generation_result(runs(idx(' num2str(k) '))).microstructure3D_particlelabel,'];
                        end
                    end
                    str(end)=[]; % Remove last ,
                    str = [str ');'];
                    eval(str);
                end
            end
        end

        % Button pushed function: Save_folder_button
        function Save_folder_buttonButtonPushed(app, event)
            if isempty(app.Savefolder)
                selpath = uigetdir(pwd,'Select save folder');
            else
                selpath = uigetdir(app.Savefolder,'Select save folder');
            end
            if selpath~=0
                if ispc
                    selpath=[selpath '\'];
                else
                    selpath=[selpath '/'];
                end
                app.Savefolder=selpath;
                app.NosavefolderselectedLabel.Text = app.Savefolder;
                statut = 'on';
            else
                app.Savefolder = [];
                app.NosavefolderselectedLabel.Text = 'No save folder selected';
                statut = 'off';
            end
                app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox.Enable = statut;
                app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox.Enable = statut;
                app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox.Enable = statut;
                app.ForphaseandparticlelablelsaveforDropDown.Enable = statut;
                app.ForphaseandparticlelablelsaveforDropDownLabel.Enable = statut;
                app.SaveparticleadditionalinformationCheckBox.Enable = statut;
                app.SavealgorithmprogressionplotifselectedCheckBox.Enable = statut;
                app.SavecharacterizationresultsifselectedCheckBox.Enable = statut;           
                app.SaveinputsoutpuscomparisonifselectedCheckBox.Enable = statut;
            
        end

        % Cell edit callback: Visualization_UITable
        function Visualization_UITableCellEdit(app, event)
            choices = app.Visualization_UITable.Data(:,3);
            idx = find(choices==1);
            if length(idx)==1
                app.Plot_phase_label_3D.Enable = 'on';
                app.Plot_particle_label_3D.Enable = 'on';
            else
                app.Plot_phase_label_3D.Enable = 'off';
                app.Plot_particle_label_3D.Enable = 'off';    
            end
            if ~isempty(idx)
                app.Plot_phase_label.Enable = 'on';
                app.Plot_particle_label.Enable = 'on';                
            else
                app.Plot_phase_label.Enable = 'off';
                app.Plot_particle_label.Enable = 'off';
            end
        end

        % Button pushed function: Plot_phase_label_3D
        function Plot_phase_label_3DButtonPushed(app, event)
            %function_save_tif( uint8(app.generation_result(1).microstructure3D_phaselabel), [app.Savefolder 'Phaselabel_run_' num2str(1) '.tif']);

            hFig = uifigure;
            viewer = viewer3d('Parent',hFig);
            viewer.RenderingQuality = 'High';

            % Box and scale bar
            viewer.Box = false;
            viewer.ScaleBar = false;
            viewer.OrientationAxes = 'on';

            % Background, gradient and lighting
            viewer.BackgroundColor = [1 1 1];
            viewer.BackgroundGradient = false;
            viewer.Lighting="on";
            
            runs = app.Visualization_UITable.Data(:,1);
            scaled = app.Visualization_UITable.Data(:,2);
            choices = app.Visualization_UITable.Data(:,3);
            idx = find(choices==1);
            if scaled(idx)
                %volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel_scaled,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','VolumeRendering');
                % vol = volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel_scaled,Colormap=col_);                                
                nphase = length(unique(app.generation_result(runs(idx)).microstructure3D_phaselabel_scaled));
                obj_sem = volshow([],"Parent",viewer,"OverlayData",app.generation_result(runs(idx)).microstructure3D_phaselabel_scaled);
            else
                %volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','VolumeRendering');
                % vol = volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel,Colormap=col_);
                nphase = length(unique(app.generation_result(runs(idx)).microstructure3D_phaselabel));
                obj_sem = volshow([],"Parent",viewer,"OverlayData",app.generation_result(runs(idx)).microstructure3D_phaselabel);
            end

            tmp = [colororder; rand(1000,3)];
            tmp(1,:) = [1 1 1];
            colmap = tmp(1:nphase,:);

            alphamap = ones(nphase,1);
            alphamap(1,1) = 0;

            obj_sem.OverlayColormap(1:nphase,:) = colmap;
            obj_sem.OverlayAlphamap(1:nphase,:) = alphamap;            
        end

        % Button pushed function: Plot_particle_label_3D
        function Plot_particle_label_3DButtonPushed(app, event)
            hFig = uifigure;
            viewer = viewer3d('Parent',hFig);
            viewer.RenderingQuality = 'High';

            % Box and scale bar
            viewer.Box = false;
            viewer.ScaleBar = false;
            viewer.OrientationAxes = 'on';

            % Background, gradient and lighting
            viewer.BackgroundColor = [1 1 1];
            viewer.BackgroundGradient = false;
            viewer.Lighting="on";
            
            runs = app.Visualization_UITable.Data(:,1);
            scaled = app.Visualization_UITable.Data(:,2);
            choices = app.Visualization_UITable.Data(:,3);
            idx = find(choices==1);

            colmapfcts = {@parula,@turbo,@jet,@gray,@bone,@copper};
            colmapvals = {'parula','turbo','jet','gray','bone','copper'};
            idx_colmap = find(strcmp(colmapvals, app.ParticlecolormapDropDown.Value)); % The things we do to avoid using eval...
            if scaled(idx)
                ninst = length(unique(app.generation_result(runs(idx)).microstructure3D_particlelabel_scaled));
                colmap = [1 1 1; colmapfcts{idx_colmap}(ninst-1)];
                %colmap = [1 1 1; turbo(ninst-1)];
                obj_inst = volshow([],"Parent",viewer,"OverlayData",app.generation_result(runs(idx)).microstructure3D_particlelabel_scaled,"OverlayAlpha",1,"OverlayColormap",colmap,'OverlayDisplayRangeMode',"data-range");
            else
                %volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel,'Parent', Fig_3D,'BackgroundColor','w','Colormap',col_,'Renderer','VolumeRendering');
                % vol = volshow(app.generation_result(runs(idx)).microstructure3D_phaselabel,Colormap=col_);
                ninst = length(unique(app.generation_result(runs(idx)).microstructure3D_particlelabel));
                colmap = [1 1 1; colmapfcts{idx_colmap}(ninst-1)];
                %colmap = [1 1 1; turbo(ninst-1)];
                obj_inst = volshow([],"Parent",viewer,"OverlayData",app.generation_result(runs(idx)).microstructure3D_particlelabel,"OverlayAlpha",1,"OverlayColormap",colmap,'OverlayDisplayRangeMode',"data-range");
            end
        end

        % Value changed function: HowtoDropDown
        function HowtoDropDownValueChanged(app, event)
            value = app.HowtoDropDown.Value;
            if strcmp(value,'Re-create from partice information (recommended)')
                app.ApplyscalingtophaselabelCheckBox.Value = true; app.ApplyscalingtophaselabelCheckBox.Enable = 'off';
                app.ApplyscalingalsotoparticlelabelCheckBox.Value = true; app.ApplyscalingalsotoparticlelabelCheckBox.Enable = 'off';
                app.Image2.ImageSource = 'Scaling_regen.png';
                app.SelectasolidphaseandsetthenumberofLabel_13.Text = 'Particles are redrawn in a different grid, knowing their centroid, diameters, and rotation, with centroid and diameters scaled. Provides exact ellipsoid geometry';               
                app.DiameterscalingratioEditField.Enable = 'on';
                app.DiameterscalingratioEditFieldLabel.Enable = 'on';
                app.ForceseparationCheckBox.Enable = 'on';
                app.ForceseparationCheckBoxValueChanged;
                app.Upscale_ForceodddiametersCheckBox.Enable = 'on';
                app.TargetnewporosityCheckBox.Enable = 'on'; app.TargetnewporosityCheckBoxValueChanged;
            else
                app.ApplyscalingtophaselabelCheckBox.Value = true; app.ApplyscalingtophaselabelCheckBox.Enable = 'off';
                app.ApplyscalingalsotoparticlelabelCheckBox.Value = false; app.ApplyscalingalsotoparticlelabelCheckBox.Enable = 'on';
                app.Image2.ImageSource = 'Scaling_upscale.png';
                app.SelectasolidphaseandsetthenumberofLabel_13.Text = 'In-house upscale algorithm limits aliasing, so interface will be better represented compared with a simple MATLAB "imresize3" with "neareast" option';
                app.DiameterscalingratioEditField.Enable = 'off';
                app.DiameterscalingratioEditFieldLabel.Enable = 'off';
                app.ForceseparationCheckBox.Enable = 'off';
                app.DistanceseparationvoxellengthEditField.Enable = 'off';
                app.DistanceseparationvoxellengthEditFieldLabel.Enable = 'off';  
                app.Upscale_ForceodddiametersCheckBox.Enable = 'off';
                app.TargetnewporosityCheckBox.Enable = 'off'; 
                app.StopregenerationatporosityEditField.Enable = 'off';     
                app.StopregenerationatporosityEditFieldLabel.Enable = 'off';
                app.ShuffleparticlesCheckBox.Enable = 'off';
            end            
        end

        % Value changed function: PlotalgorithmprogressionCheckBox
        function PlotalgorithmprogressionCheckBoxValueChanged(app, event)
            value = app.PlotalgorithmprogressionCheckBox.Value;
            if ~value
                app.PlotalsoporosityprogressionCheckBox.Value = 0;
                app.RefreshfigureseachsEditField.Enable = 'off';
            else
                app.RefreshfigureseachsEditField.Enable = 'on';
            end
        end

        % Value changed function: ForceseparationCheckBox
        function ForceseparationCheckBoxValueChanged(app, event)
            if app.ForceseparationCheckBox.Value
                app.DistanceseparationvoxellengthEditField.Enable = 'on';
                app.DistanceseparationvoxellengthEditFieldLabel.Enable = 'on';
            else
                app.DistanceseparationvoxellengthEditField.Enable = 'off';
                app.DistanceseparationvoxellengthEditFieldLabel.Enable = 'off';
           end
            
        end

        % Value changed function: MakevideoCheckBox
        function MakevideoCheckBoxValueChanged(app, event)
            value = app.MakevideoCheckBox.Value;
            if value
                app.WarningdonotresizefigureswhilegenerationisongoingLabel.Enable = "on";
            else
                app.WarningdonotresizefigureswhilegenerationisongoingLabel.Enable = "off";
            end    
        end

        % Value changed function: SetvalueforwholematrixEditField
        function SetvalueforwholematrixEditFieldValueChanged(app, event)
            value = app.SetvalueforwholematrixEditField.Value;
            tmp = app.overlapping_UITable.Data;
            tmp = zeros(size(tmp)) + value;
            app.overlapping_UITable.Data = tmp;
        end

        % Value changed function: TargetnewporosityCheckBox
        function TargetnewporosityCheckBoxValueChanged(app, event)
            value = app.TargetnewporosityCheckBox.Value;
            if value
                app.StopregenerationatporosityEditField.Enable = 'on';
                app.StopregenerationatporosityEditFieldLabel.Enable = 'on';
                app.SolifVf_upscaling_DropDown.Enable = 'on';
                app.ShuffleparticlesCheckBox.Enable = 'on';
            else
                app.StopregenerationatporosityEditField.Enable = 'off';     
                app.StopregenerationatporosityEditFieldLabel.Enable = 'off';
                app.SolifVf_upscaling_DropDown.Enable = 'off';
                app.ShuffleparticlesCheckBox.Enable = 'off';
            end
        end

        % Button pushed function: OpendocumentationButton
        function OpendocumentationButtonPushed(app, event)
            Find_file('NREL_MATBOX_Microstructure_analysis_toolbox_documentation.pdf','MATBOX_Microstructure_analysis_toolbox','Default location is \MATBOX_Microstructure_analysis_toolbox\Documentation\');
        end

        % Button pushed function: Seeexamples_volumefraction_Button
        function Seeexamples_volumefraction_ButtonPushed(app, event)
            winopen('Volumefraction_example_1.png');
        end

        % Button pushed function: Seeexamples_diameters_Button
        function Seeexamples_diameters_ButtonPushed(app, event)
            winopen('Diameter_example_1.png');
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            winopen('Stochastic_algorithm.png');
        end

        % Value changed function: CandidateselectionDropDown
        function CandidateselectionDropDownValueChanged(app, event)
            value = app.CandidateselectionDropDown.Value;
            if strcmp(value,'Random')
                str_algo = ['Unassigned voxels are selected randomly and tested versus volume fraction, particle diameter/elongation/rotation, and particle overlapping.' ...
                    ' If unsuccessful, a counter is incremented, and if below the ''each time x particles generation fail'' (''no'' in the right figure) a new voxel is selected among the unassigned voxels.' ...
                    ' If above the ''each time x particles generation fail'' the overlapping constraint is relaxed (see the ''Overlapping tab''), the counter is reset, and then a new voxel is selected among the unassigned voxels.' ...
                    ' If successful, a new particle is generated and all probabilities are updated and the counter is reset. Click on the picture on the right to see the algorithm chart. Switch to ''Accelerated'' method if generation rate is decreasing too much.'];


                app.n0overlappingcounterEditField.Enable = 'off'; app.n0overlappingcounterLabel.Enable = 'off';
                app.n1switchselectionmethodEditField.Enable = 'off'; app.n1switchselectionmethodEditFieldLabel.Enable = 'off';
                app.SelectionmethodDropDown.Enable = 'off';  app.SelectionmethodDropDownLabel.Enable = 'off'; 
                app.n2selectbestcandidateEditField.Enable = 'off';  app.n2selectbestcandidateEditFieldLabel.Enable = 'off'; 
                app.TopselectionEditField.Enable = 'off';  app.TopselectionEditFieldLabel.Enable = 'off'; 
                app.Constraintn2n1n0Label.Enable = 'off';
                app.Rthreshold_CheckBox.Enable = 'off';
                app.rtthreshold_EditField.Enable = 'off'; app.rtEditFieldLabel.Enable = 'off'; 

            elseif strcmp(value,'Accelerated')
                str_algo = ['Each phase is initially generated using the ''Random'' method to select voxel candidate for new particle center. This will ensure volume has no ordered structure, apart from the user-inputs. However, if particle generation fails more than ''n1'' times (you should choose ''n1'' < ''n0''),' ...
                    ' then the voxel selection switches to a second method, for this phase (the method switch is tracked per phase), until the end of the generation. If the option ''Distance map (dmap)'' is selected, unassigned voxels far from existing particles are preferred.' ...
                    ' If the option ''Volume fraction'' is selected, unassigned voxels within slices for which the target volume fraction has not been reached yet are preferred. One or both options can be selected.' ...
                    ' For the first time the current phase hits this switch (i.e., ''n1'' failed attempts), only the best candidate voxel is selected and is tested versus volume fraction, particle diameter/elongation/rotation, and particle overlapping. If it fails, the overlapping constraint is relaxed and the counter is reset. ' ...
                    ' It it succeeds, the counter is also reset (as each time a particle has been successfully generated). Whatever it fails or not, from now on the candidate voxel is selected randonly among the ''top selection'' % (considering distance map and/or volume fractions) if the counter is < ''n2'' (with ''n2'' < ''n1''), and if above only the best candidate is considered.' ...
                    ' If the best candidate is selected, and the particle generation still fails, the overlaping matrix is relaxed and the counter is reset. Click on the picture on the right to see the algorithm chart. The accelerated method should always be selected if only spherical particles are generated. For elongated particles, the ''Distance map (dmap)'' options is less relevant.'];

                app.n0overlappingcounterEditField.Enable = 'off'; app.n0overlappingcounterLabel.Enable = 'on';
                app.n1switchselectionmethodEditField.Enable = 'on'; app.n1switchselectionmethodEditFieldLabel.Enable = 'on';
                app.SelectionmethodDropDown.Enable = 'on';  app.SelectionmethodDropDownLabel.Enable = 'on'; 
                app.n2selectbestcandidateEditField.Enable = 'on';  app.n2selectbestcandidateEditFieldLabel.Enable = 'on';
                app.TopselectionEditField.Enable = 'on';  app.TopselectionEditFieldLabel.Enable = 'on'; 
                app.Constraintn2n1n0Label.Enable = 'on';
                app.Rthreshold_CheckBox.Enable = 'on';
                app.Rthreshold_CheckBoxValueChanged;

            end   
            app.Algorithm_description.Text = str_algo;
            app.Algorithm_description.WordWrap = "on";
        end

        % Value changed function: n1switchselectionmethodEditField
        function n1switchselectionmethodEditFieldValueChanged(app, event)
            n1 = app.n1switchselectionmethodEditField.Value;
            n0 = app.eachtimeEditField.Value;
            if n1>=n0
                app.n1switchselectionmethodEditField.Value = event.PreviousValue;
            end            
        end

        % Value changed function: eachtimeEditField
        function eachtimeEditFieldValueChanged(app, event)
            n0 = app.eachtimeEditField.Value;
            n1 = app.n1switchselectionmethodEditField.Value;
            n2 = app.n2selectbestcandidateEditField.Value;
            app.n0overlappingcounterEditField.Value = n0;
            if n1 >= n0
                n1 = round(n0/2);
            end
            if n2 >= n1
                n2 = round(n1/2);
            end            
            app.n1switchselectionmethodEditField.Value = n1;
            app.n2selectbestcandidateEditField.Value = n2;            
        end

        % Value changed function: Rthreshold_CheckBox
        function Rthreshold_CheckBoxValueChanged(app, event)
            value = app.Rthreshold_CheckBox.Value;
            if value
                app.rtthreshold_EditField.Enable = 'on'; app.rtEditFieldLabel.Enable = 'on';
            else
                app.rtthreshold_EditField.Enable = 'off'; app.rtEditFieldLabel.Enable = 'off';
            end
        end

        % Value changed function: n2selectbestcandidateEditField
        function n2selectbestcandidateEditFieldValueChanged(app, event)
            n2 = app.n2selectbestcandidateEditField.Value;
            n1 = app.n1switchselectionmethodEditField.Value;
            if n2>=n1
                app.n2selectbestcandidateEditField.Value = event.PreviousValue;
            end            
        end

        % Cell edit callback: Cropping_UITable
        function Cropping_UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2
                if isnan(newData) || newData<0 || round(newData)~=newData % Incorrect input
                    app.Cropping_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                    f = msgbox('Invalid Value: must be integer >=0', 'Error','warn');
                else
                    d = cell2mat(app.Phase_domainUITable.Data(indices(1), 2));
                    r = d-(2*newData);
                    if r<1
                        f = msgbox('Invalid Value: cropped domain is too small', 'Error','warn');
                        app.Cropping_UITable.Data(indices(1), indices(2)) = num2cell(event.PreviousData);
                    end
                end
            end    
        end

        % Image clicked function: Image4
        function Image4Clicked(app, event)
            winopen('Cropping.png');
        end

        % Button pushed function: ImportmaskButton
        function ImportmaskButtonPushed(app, event)
            % Open dialog box to choose file path
            [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file');
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Mask_volume_name_label.Text ='No mask file imported.';
                app.mask_volumefraction_label.Text ='No mask file imported.';
                app.mask_notwithin=[]; % Reset mask array
                app.Visualize_mask_button.Enable = 'off';
                app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'off';
                app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'off';
                app.UseATLEASTconditionCheckBox.Enable = 'off';
                app.UseNOTMOREconditionCheckBox.Enable = 'off';
                app.ParticlescanNOToverlapwithmaskCheckBox.Enable = 'off';
                app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
                app.Mask_addexistingmaterial.Enable = 'off'; app.Mask_addexistingmaterial.Value = 0;
            else
                [tmp,outcome] = function_load_tif([PathName FileName]);
                if outcome % Success to import
                    % Check dimension
                    if sum(size(tmp) == app.domain_size)==3
                        % Create mask
                        tmp2 = zeros(size(tmp));
                        tmp2(tmp~=0)=1;
                        % Save
                        app.mask_notwithin=uint8(tmp2);
                        clear tmp tmp2;
                        app.Mask_volume_name_label.Text = [PathName FileName];
                        mask_vf = sum(sum(sum(app.mask_notwithin)))/numel(app.mask_notwithin);
                        app.mask_volumefraction_label.Text = ['Volume fractions: Mask (1) : ' num2str(mask_vf,"%1.3f") ', Background (0) : ' num2str(1-mask_vf,"%1.3f")];
                        app.Visualize_mask_button.Enable = 'on';
                        app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'on';
                        app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'on';
                        app.UseATLEASTconditionCheckBox.Enable = 'on';
                        app.UseNOTMOREconditionCheckBox.Enable = 'on';    
                        app.ParticlescanNOToverlapwithmaskCheckBox.Enable = 'on';
                        app.UsenotwithinmaskCheckBox.Enable = 'on'; app.UsenotwithinmaskCheckBox.Value = 1;  
                        app.Mask_addexistingmaterial.Enable = 'on'; app.Mask_addexistingmaterial.Value = 1;
                    else
                        sz = size(tmp);
                        f = msgbox(['Size mask ' num2str(sz(1),'%i') '-' num2str(sz(2),'%i') '-' num2str(sz(3),'%i') ' is different from domain size'], 'Error','warn');
                        app.Mask_volume_name_label.Text ='No mask file imported.';
                        app.mask_volumefraction_label.Text ='No mask file imported.';
                        app.mask_notwithin=[]; % Reset mask array
                        app.Visualize_mask_button.Enable = 'off';
                        app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'off';
                        app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'off';
                        app.UseATLEASTconditionCheckBox.Enable = 'off';
                        app.UseNOTMOREconditionCheckBox.Enable = 'off'; 
                        app.ParticlescanNOToverlapwithmaskCheckBox.Enable = 'off';
                        app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
                        app.Mask_addexistingmaterial.Enable = 'off'; app.Mask_addexistingmaterial.Value = 0;
                    end    
                else
                    app.Mask_volume_name_label.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.mask_volumefraction_label.Text ='No mask file imported.';
                    app.mask_notwithin=[]; % Reset mask array
                    app.Visualize_mask_button.Enable = 'off';
                    app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'off';
                    app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'off';
                    app.UseATLEASTconditionCheckBox.Enable = 'off';
                    app.UseNOTMOREconditionCheckBox.Enable = 'off';     
                    app.ParticlescanNOToverlapwithmaskCheckBox.Enable = 'off';
                    app.UsenotwithinmaskCheckBox.Enable = 'off'; app.UsenotwithinmaskCheckBox.Value = 0;
                    app.Mask_addexistingmaterial.Enable = 'off'; app.Mask_addexistingmaterial.Value = 0;
                end
            end
        end

        % Button pushed function: Visualize_mask_button
        function Visualize_mask_buttonButtonPushed(app, event)
            Microstructure_basic_visualization_interface(app.mask_notwithin);
        end

        % Button pushed function: ImportmaskButton_2
        function ImportmaskButton_2Pushed(app, event)
            % Open dialog box to choose file path
            [FileName,PathName,~] = uigetfile({'*.tif;*.tiff','Tif image (*.tif, *.tiff)'},'Select a .tif file');
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Mask_volume_name_label_2.Text ='No mask file imported.';
                app.mask_volumefraction_label_2.Text ='No mask file imported.';
                app.mask_within=[]; % Reset mask array
                app.Visualize_mask_button_2.Enable = 'off';
                app.UsewithinmaskCheckBox.Enable = 'off'; app.UsewithinmaskCheckBox.Value = 0;
            else
                [tmp,outcome] = function_load_tif([PathName FileName]);
                if outcome % Success to import
                    % Check dimension
                    if sum(size(tmp) == app.domain_size)==3
                        % Create mask
                        tmp2 = zeros(size(tmp));
                        tmp2(tmp~=0)=1;
                        % Save
                        app.mask_within=uint8(tmp2);
                        clear tmp tmp2;
                        app.Mask_volume_name_label_2.Text = [PathName FileName];
                        mask_vf = sum(sum(sum(app.mask_within)))/numel(app.mask_within);
                        app.mask_volumefraction_label_2.Text = ['Volume fractions: Mask (1) : ' num2str(mask_vf,"%1.3f") ', Background (0) : ' num2str(1-mask_vf,"%1.3f")];
                        app.Visualize_mask_button_2.Enable = 'on';
                        app.UsewithinmaskCheckBox.Enable = 'on'; app.UsewithinmaskCheckBox.Value = 1;
                    else
                        sz = size(tmp);
                        f = msgbox(['Size mask ' num2str(sz(1),'%i') '-' num2str(sz(2),'%i') '-' num2str(sz(3),'%i') ' is different from domain size'], 'Error','warn');
                        app.Mask_volume_name_label_2.Text ='No mask file imported.';
                        app.mask_volumefraction_label_2.Text ='No mask file imported.';
                        app.mask_within=[]; % Reset mask array
                        app.Visualize_mask_button_2.Enable = 'off';
                        app.UsewithinmaskCheckBox.Enable = 'off'; app.UsewithinmaskCheckBox.Value = 0;
                    end
                else
                    app.Mask_volume_name_label_2.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.mask_volumefraction_label_2.Text ='No mask file imported.';
                    app.mask_within=[]; % Reset mask array
                    app.Visualize_mask_button_2.Enable = 'off';
                    app.UsewithinmaskCheckBox.Enable = 'off'; app.UsewithinmaskCheckBox.Value = 0;
                end
            end
        end

        % Button pushed function: Visualize_mask_button_2
        function Visualize_mask_button_2ButtonPushed(app, event)
            Microstructure_basic_visualization_interface(app.mask_within);
        end

        % Image clicked function: Image5
        function Image5Clicked(app, event)
            winopen('Mask.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure = uifigure('Visible', 'off');
            app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure.Name = 'Ellipsoid-based stochastic generation submodule';
            app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure.Icon = 'Icon_generation.png';
            app.TabGroup = uitabgroup(app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure);
            app.TabGroup.Tooltip = {'Run the generation algorithm'};
            app.Instructions = uitab(app.TabGroup);
            app.Instructions.Tooltip = {'Instructions'};
            app.Instructions.Title = 'Instructions';
            app.GridLayout2 = uigridlayout(app.Instructions);
            app.Instructions_title = uilabel(app.GridLayout2);
            app.Instructions_title.Text = 'Main instructions';
            app.SetpropertiesdefinedwithascalarLabel = uilabel(app.GridLayout2);
            app.SetpropertiesdefinedwithascalarLabel.Text = '   Set properties defined with a scalar per slice along thickness (though-plane heterogeneity)';
            app.SetpropertiesdefinedwithanhistogramdistributionLabel = uilabel(app.GridLayout2);
            app.SetpropertiesdefinedwithanhistogramdistributionLabel.Text = '   Set properties defined with an histogram per slice along thickness (in-plane and through-plane heterogeneity)';
            app.SetalgorithmadditionalparametersLabel = uilabel(app.GridLayout2);
            app.SetalgorithmadditionalparametersLabel.Text = '   Set algorithm additional parameters';
            app.StepsperformedafterthegenerationprogressLabel = uilabel(app.GridLayout2);
            app.StepsperformedafterthegenerationprogressLabel.Text = '   Set parameters for tasks performed after the generation algorithm is finished, but that we configure in advance';
            app.RunthegenerationalgorithmLabel = uilabel(app.GridLayout2);
            app.RunthegenerationalgorithmLabel.Text = '   Run the generation algorithm';
            app.SelectasolidphaseandsetthenumberofLabel_14 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_14.Text = {'Stochastic (i.e., based on randomness) microstructure generation algorithm. Main features are listed below:'; ''};
            app.SelectasolidphaseandsetthenumberofLabel_15 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_15.Text = {'- n phase microstructure'; '- Ellipsoid-based, with particle overlapping control (so that you can overcome particle packing density limit)'; '- Control of volume fractions, particle diameters, particle elongation, and particle orientation/rotation.'; '  Properties are defined along the volume thickness, so that you can generate graded microstructures.'; '  Particle size and morphology are defined with histograms, so that you can generate in-plane heterogeneities.'; '- Particle generation order (see dedicated tab for more details).'};
            app.SelectasolidphaseandsetthenumberofLabel_16 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_16.Text = 'You can see examples in the Git hub repository using the link below:';
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/tree/master/Data_example';
            app.Hyperlink.Text = 'Example of numerically generated microstructures (scroll to the bottom to see pictures, tif files are also availabe in a subfolder)';
            app.SelectasolidphaseandsetthenumberofLabel_17 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_17.Text = {'As-generated microstructures look obviously numerically generated. The aim of the algorithm is to generate microstructures with very well defined properties, not to generate volumes'; 'that look like obtained from imaging, i.e., prioritize control over fidelity.'; 'Combined with the MATBOX characterization and meshing module, it enables establishing correlations between microstructure parameters (e.g., effective diffusion coefficient as'; 'function of particle elongation) or between microstructure parameters and battery performances (using the mesh in a microscale electrochemical model).'};
            app.SelectasolidphaseandsetthenumberofLabel_18 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_18.Text = {'Therefore, the intented original use of this algorithm is to generate a variety of microstructures for design space analysis'; 'to identify promising set of parameters for a given application.'};
            app.SelectasolidphaseandsetthenumberofLabel_19 = uilabel(app.GridLayout2);
            app.SelectasolidphaseandsetthenumberofLabel_19.Text = {'How to use it: simply follow instructions for each tab, from top to bottom.'; 'Each tab is color-coded as indicated below:'};
            app.ClickonthisiconwhenvisibletoseesomeparameterexamplesLabel = uilabel(app.GridLayout2);
            app.ClickonthisiconwhenvisibletoseesomeparameterexamplesLabel.Text = 'In some tabs, click on this icon (when visible, located in the top right corner of the window) to see some parameter examples.';
            app.SeeexamplesButton_3 = uibutton(app.GridLayout2, 'push');
            app.SeeexamplesButton_3.Text = {'See'; 'examples'};
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.GridLayout3 = uigridlayout(app.SaveTab);
            app.Instructions_title_3 = uilabel(app.GridLayout3);
            app.Instructions_title_3.Text = 'Saving options';
            app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox = uicheckbox(app.GridLayout3);
            app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox.Enable = 'off';
            app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox.Text = 'Save phase label information (3D tif stack file, uint8 format)';
            app.Savephaselabelinformation3Dtifstackfileuint8formatCheckBox.Value = true;
            app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox = uicheckbox(app.GridLayout3);
            app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox.Enable = 'off';
            app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox.Text = 'Save particle label information (3D tif stack file, uint16 format)';
            app.Saveparticlelabelinformation3Dtifstackfileuint16formatCheckBox.Value = true;
            app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox = uicheckbox(app.GridLayout3);
            app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox.Enable = 'off';
            app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox.Text = 'Save in .mat (inputs of the generation algorithm function, so that you can re-run the algorithm in command line if needed)';
            app.SaveinmatinputsofthegenerationalgorithmfunctionCheckBox.Value = true;
            app.InputsLabel = uilabel(app.GridLayout3);
            app.InputsLabel.Text = 'Inputs:';
            app.OutputsLabel = uilabel(app.GridLayout3);
            app.OutputsLabel.Text = 'Outputs:';
            app.AdditionalLabel = uilabel(app.GridLayout3);
            app.AdditionalLabel.Text = 'Additional:';
            app.SavealgorithmprogressionplotifselectedCheckBox = uicheckbox(app.GridLayout3);
            app.SavealgorithmprogressionplotifselectedCheckBox.Enable = 'off';
            app.SavealgorithmprogressionplotifselectedCheckBox.Text = 'Save algorithm progression plot (if selected in the stop conditions tab)';
            app.SavealgorithmprogressionplotifselectedCheckBox.Value = true;
            app.SavecharacterizationresultsifselectedCheckBox = uicheckbox(app.GridLayout3);
            app.SavecharacterizationresultsifselectedCheckBox.Enable = 'off';
            app.SavecharacterizationresultsifselectedCheckBox.Text = 'Save characterization results (if selected in the verification and characterization tab)';
            app.SavecharacterizationresultsifselectedCheckBox.Value = true;
            app.SaveparticleadditionalinformationCheckBox = uicheckbox(app.GridLayout3);
            app.SaveparticleadditionalinformationCheckBox.Enable = 'off';
            app.SaveparticleadditionalinformationCheckBox.Text = 'Save particle additional information: particle centroid, diameter, elongation (.mat). (Both before and after upscaling)';
            app.SaveparticleadditionalinformationCheckBox.Value = true;
            app.Save_folder_button = uibutton(app.GridLayout3, 'push');
            app.Save_folder_button.ButtonPushedFcn = createCallbackFcn(app, @Save_folder_buttonButtonPushed, true);
            app.Save_folder_button.Text = 'Click to select save folder';
            app.NosavefolderselectedLabel = uilabel(app.GridLayout3);
            app.NosavefolderselectedLabel.Text = 'No save folder selected';
            app.SelectwhereyouwanttosaveinputsandresultsLabel = uilabel(app.GridLayout3);
            app.SelectwhereyouwanttosaveinputsandresultsLabel.Text = '1) Select where you want to save inputs and results';
            app.ThenselectwhatinformationyouwanttosaveLabel = uilabel(app.GridLayout3);
            app.ThenselectwhatinformationyouwanttosaveLabel.Text = '2) Then select what information you want to save';
            app.SaveinputsoutpuscomparisonifselectedCheckBox = uicheckbox(app.GridLayout3);
            app.SaveinputsoutpuscomparisonifselectedCheckBox.Enable = 'off';
            app.SaveinputsoutpuscomparisonifselectedCheckBox.Text = 'Save inputs/outpus comparison (if selected in the verification and characterization tab)';
            app.SaveinputsoutpuscomparisonifselectedCheckBox.Value = true;
            app.ForphaseandparticlelablelsaveforDropDownLabel = uilabel(app.GridLayout3);
            app.ForphaseandparticlelablelsaveforDropDownLabel.Enable = 'off';
            app.ForphaseandparticlelablelsaveforDropDownLabel.Text = '     For phase and particle lablel, save for';
            app.ForphaseandparticlelablelsaveforDropDown = uidropdown(app.GridLayout3);
            app.ForphaseandparticlelablelsaveforDropDown.Items = {'volume before upscaling', 'volume after upscaling', 'both'};
            app.ForphaseandparticlelablelsaveforDropDown.Enable = 'off';
            app.ForphaseandparticlelablelsaveforDropDown.Value = 'both';
            app.VolumefractionsTab = uitab(app.TabGroup);
            app.VolumefractionsTab.Tooltip = {'Phase and volume fractions'};
            app.VolumefractionsTab.Title = 'Volume fractions';
            app.GridLayout4 = uigridlayout(app.VolumefractionsTab);
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = {'If you ask for a high density microstructure, you will have to allow particle overlapping (specified in the'; 'tab of same name) otherwise packing density will block the algorithm progression.'};
            app.AlgothmisfasterforLabel_5 = uilabel(app.GridLayout4);
            app.AlgothmisfasterforLabel_5.Text = {'- Microstructure parameter gradations (volume fraction and particle size and morphology) are defined along'; 'the microstructure thickness, which is by convention in this code the direction 3 (see left table).'; '- Properties are defined on some slices, and interpolated in between so that user does not have to specify'; 'values for each slices.'; '- In the table below you can specify volume fractions of the solid phases (porosity is deduced) on some slices.'};
            app.AlgothmisfasterforLabel_4 = uilabel(app.GridLayout4);
            app.AlgothmisfasterforLabel_4.Text = {'Algorithm is adimentional. Voxel size is an optional value to'; 'simply convert dimension from voxel length to a physical length.'};
            app.SetvolumefractionsalongthemicrostructurethicknessLabel = uilabel(app.GridLayout4);
            app.SetvolumefractionsalongthemicrostructurethicknessLabel.Text = '3) Set volume fractions along the microstructure thickness.';
            app.AlgothmisfasterforLabel_3 = uilabel(app.GridLayout4);
            app.AlgothmisfasterforLabel_3.Text = 'You can then choose the phase label and name.';
            app.Phase_LabelnameUItable = uitable(app.GridLayout4);
            app.Phase_LabelnameUItable.ColumnName = {'Phase#'; 'Label'; 'Name'};
            app.Phase_LabelnameUItable.RowName = {};
            app.Phase_LabelnameUItable.ColumnEditable = [false true true];
            app.Phase_LabelnameUItable.CellEditCallback = createCallbackFcn(app, @Phase_LabelnameUItableCellEdit, true);
            app.SetNumberofsolidphasesLabel = uilabel(app.GridLayout4);
            app.SetNumberofsolidphasesLabel.Text = '2) Set Number of solid phase(s)';
            app.AlgothmisfasterforLabel_2 = uilabel(app.GridLayout4);
            app.AlgothmisfasterforLabel_2.Text = '';
            app.Phase_domainUITable = uitable(app.GridLayout4);
            app.Phase_domainUITable.ColumnName = {'Direction'; 'Number of voxel'; 'Length (um)'};
            app.Phase_domainUITable.RowName = {};
            app.Phase_domainUITable.ColumnEditable = [false true false];
            app.Phase_domainUITable.CellEditCallback = createCallbackFcn(app, @Phase_domainUITableCellEdit, true);
            app.AlgothmisfasterforLabel = uilabel(app.GridLayout4);
            app.AlgothmisfasterforLabel.Text = {'Algorithm is faster for larger (particle volume / domain volume)'; 'ratio. It is then recommended to start with a high ratio to setup'; 'properly your parameters, and only then moving to a larger'; 'domain when you are satisfied with your early generation results.'};
            app.SetdimensionsLabel = uilabel(app.GridLayout4);
            app.SetdimensionsLabel.Text = '1) Set dimensions';
            app.Instructions_title_2 = uilabel(app.GridLayout4);
            app.Instructions_title_2.Text = 'Specify domain size, number of phases, and volume fractions';
            app.ThensetthesolidvolumefractionsLabel = uilabel(app.GridLayout4);
            app.ThensetthesolidvolumefractionsLabel.Text = {'Then set the solid volume fractions input and plot them. Notice in the plot the interpolation for the volume'; 'fractions between the slices you have manually specified.'};
            app.Volumefraction_UITable = uitable(app.GridLayout4);
            app.Volumefraction_UITable.ColumnName = {'Normalized position along direction 3'; 'Phase 1'; 'Porosity'};
            app.Volumefraction_UITable.RowName = {};
            app.Volumefraction_UITable.ColumnEditable = [false true false];
            app.Volumefraction_UITable.CellEditCallback = createCallbackFcn(app, @Volumefraction_UITableCellEdit, true);
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel = uilabel(app.GridLayout4);
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel.Text = {'4) Plot (optional) to check your input and'; '(mandatory) save them to move on.'};
            app.Phase_vf_plot = uibutton(app.GridLayout4, 'push');
            app.Phase_vf_plot.ButtonPushedFcn = createCallbackFcn(app, @Phase_vf_plotButtonPushed, true);
            app.Phase_vf_plot.Text = 'Plot volume fractions';
            app.Phase_vf_save = uibutton(app.GridLayout4, 'push');
            app.Phase_vf_save.ButtonPushedFcn = createCallbackFcn(app, @Phase_vf_saveButtonPushed, true);
            app.Phase_vf_save.Text = 'Save';
            app.ThensetthesolidvolumefractionsLabel_2 = uilabel(app.GridLayout4);
            app.ThensetthesolidvolumefractionsLabel_2.Text = {'If you have a particle count distribution, and not a particle volume distribution, use the function'; 'Count2volume_distribution to do the conversion.'};
            app.Seeexamples_volumefraction_Button = uibutton(app.GridLayout4, 'push');
            app.Seeexamples_volumefraction_Button.ButtonPushedFcn = createCallbackFcn(app, @Seeexamples_volumefraction_ButtonPushed, true);
            app.Seeexamples_volumefraction_Button.Text = {'See'; 'examples'};
            app.ThensetthesolidvolumefractionsLabel_3 = uilabel(app.GridLayout4);
            app.ThensetthesolidvolumefractionsLabel_3.Text = {'If you will use a mask, the mask volume fraction is not considered in the table below for the porosity'; 'calculation. Make sure the sum of solid phase volume fractions and mask volume fraction is not > 1.'};
            app.VoxelsizenmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.VoxelsizenmEditField.Limits = [1e-09 Inf];
            app.VoxelsizenmEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelsizenmEditFieldValueChanged, true);
            app.VoxelsizenmEditField.Value = 500;
            app.VoxelsizenmEditFieldLabel = uilabel(app.GridLayout4);
            app.VoxelsizenmEditFieldLabel.Text = 'Voxel size  (nm)';
            app.NumberofsolidphaseEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumberofsolidphaseEditField.Limits = [1 Inf];
            app.NumberofsolidphaseEditField.RoundFractionalValues = 'on';
            app.NumberofsolidphaseEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsolidphaseEditFieldValueChanged, true);
            app.NumberofsolidphaseEditField.Value = 1;
            app.NumberofsolidphaseEditFieldLabel = uilabel(app.GridLayout4);
            app.NumberofsolidphaseEditFieldLabel.Text = 'Number of solid phase';
            app.NumberofsolidphaseEditField_2Label = uilabel(app.GridLayout4);
            app.NumberofsolidphaseEditField_2Label.Text = 'First specify the number of slices for which you want to define volume fractions';
            app.NumberSlice_volumefraction = uieditfield(app.GridLayout4, 'numeric');
            app.NumberSlice_volumefraction.Limits = [2 Inf];
            app.NumberSlice_volumefraction.RoundFractionalValues = 'on';
            app.NumberSlice_volumefraction.ValueChangedFcn = createCallbackFcn(app, @NumberSlice_volumefractionValueChanged, true);
            app.NumberSlice_volumefraction.Value = 2;
            app.ToleranceEditField_2Label = uilabel(app.GridLayout4);
            app.ToleranceEditField_2Label.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ToleranceEditField.Limits = [0 1];
            app.ToleranceEditField.Tooltip = {'Tolerance on the volume fraction. Decrease it to enforce generated volumes = inputs. Relax it (i.e., increase it) to facilitate algorithm progression. Tolerance is used to test each new particle generation (not a global tolerance).'};
            app.ToleranceEditField.Value = 0.001;
            app.DiametersTab = uitab(app.TabGroup);
            app.DiametersTab.Tooltip = {'Particle diameter'};
            app.DiametersTab.Title = 'Diameters';
            app.GridLayout5 = uigridlayout(app.DiametersTab);
            app.Instructions_diameter = uilabel(app.GridLayout5);
            app.Instructions_diameter.Text = 'For each solid phase, set the particle diameter distributions along the microstructure thickness';
            app.SelectasolidphaseandsetthenumberofLabel = uilabel(app.GridLayout5);
            app.SelectasolidphaseandsetthenumberofLabel.Text = '1) Select a solid phase and set the number of slices for which you want to define particle diameter/elongation, and also how many values for each of these properties.';
            app.AlgothmisfasterforLabel_6 = uilabel(app.GridLayout5);
            app.AlgothmisfasterforLabel_6.Text = 'Your choice will format the table in the section 2).';
            app.Diameters_main_UITable = uitable(app.GridLayout5);
            app.Diameters_main_UITable.ColumnName = {'Parameters'; 'Number of slices'; 'Number of values'};
            app.Diameters_main_UITable.RowName = {};
            app.Diameters_main_UITable.ColumnEditable = [false true true];
            app.Diameters_main_UITable.CellEditCallback = createCallbackFcn(app, @Diameters_main_UITableCellEdit, true);
            app.Diameters_main_UITable.Enable = 'off';
            app.SelectasolidphaseandsetthenumberofLabel_2 = uilabel(app.GridLayout5);
            app.SelectasolidphaseandsetthenumberofLabel_2.Text = '2) Set diameter and elongation. If you set n values in the table above, you will have  *1, *2,... *n. Set their value in the first row. Set their distribution in the subsequent rows.';
            app.Diameters_UITable = uitable(app.GridLayout5);
            app.Diameters_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx 1'; 'Total'};
            app.Diameters_UITable.RowName = {};
            app.Diameters_UITable.ColumnEditable = [false true false];
            app.Diameters_UITable.CellEditCallback = createCallbackFcn(app, @Diameters_UITableCellEdit, true);
            app.Diameters_UITable.Enable = 'off';
            app.Rowii1isthediameterhistogramLabel = uilabel(app.GridLayout5);
            app.Rowii1isthediameterhistogramLabel.Text = {'Row i (i>1) is the diameter'; 'histogram expressed in'; 'percentages. Total must be'; '100%.'; ''; 'Ignore NaN values'};
            app.AlgothmisfasterforLabel_7 = uilabel(app.GridLayout5);
            app.AlgothmisfasterforLabel_7.Text = 'First table is for diameter Dx (set values in voxel length). Second and third tables are diameter ratios (i.e., equal to 1 for a sphere).';
            app.DxDy_UITable = uitable(app.GridLayout5);
            app.DxDy_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx/Dy 1'; 'Total'};
            app.DxDy_UITable.RowName = {};
            app.DxDy_UITable.ColumnEditable = [false true false];
            app.DxDy_UITable.CellEditCallback = createCallbackFcn(app, @DxDy_UITableCellEdit, true);
            app.DxDy_UITable.Enable = 'off';
            app.DxDz_UITable = uitable(app.GridLayout5);
            app.DxDz_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx/Dz 1'; 'Total'};
            app.DxDz_UITable.RowName = {};
            app.DxDz_UITable.ColumnEditable = [false true false];
            app.DxDz_UITable.CellEditCallback = createCallbackFcn(app, @DxDz_UITableCellEdit, true);
            app.DxDz_UITable.Enable = 'off';
            app.Diameter_plot = uibutton(app.GridLayout5, 'push');
            app.Diameter_plot.ButtonPushedFcn = createCallbackFcn(app, @Diameter_plotButtonPushed, true);
            app.Diameter_plot.Enable = 'off';
            app.Diameter_plot.Text = {'Plot'; 'distributions'};
            app.Diameter_save = uibutton(app.GridLayout5, 'push');
            app.Diameter_save.ButtonPushedFcn = createCallbackFcn(app, @Diameter_saveButtonPushed, true);
            app.Diameter_save.Enable = 'off';
            app.Diameter_save.Text = 'Save';
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_2 = uilabel(app.GridLayout5);
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_2.Text = {'3) Plot (optional) to check'; 'your inputs and (mandatory)'; 'save them before moving'; 'to the next phase.'; '4) Repeat steps 1,2,3 for'; 'each phase.'; '5) Move to next tab.'};
            app.ForceodddiameterCheckBox = uicheckbox(app.GridLayout5);
            app.ForceodddiameterCheckBox.Tooltip = {'Pixel-perfect representation for sphere with even or odd diameters. Pixel-perfect representation for ellipsoids with  odd diameters. Approximation for ellipsoids with at least one even diameter. You can force all diameters to be odd to enforce ellipsoids will be accurately represented.'};
            app.ForceodddiameterCheckBox.Text = 'Force odd diameter';
            app.Seeexamples_diameters_Button = uibutton(app.GridLayout5, 'push');
            app.Seeexamples_diameters_Button.ButtonPushedFcn = createCallbackFcn(app, @Seeexamples_diameters_ButtonPushed, true);
            app.Seeexamples_diameters_Button.Text = {'See'; 'examples'};
            app.DistributionsLabel = uilabel(app.GridLayout5);
            app.DistributionsLabel.Text = {'Distributions are weighted'; 'with particle volume and'; 'not particle number. Use'; 'Count2volume_distribution'; 'to convert particle frequency'; 'to particle volume'};
            app.SelectphaseDropDownLabel = uilabel(app.GridLayout5);
            app.SelectphaseDropDownLabel.Enable = 'off';
            app.SelectphaseDropDownLabel.Text = 'Select phase';
            app.Selectphase_diameters_DropDown = uidropdown(app.GridLayout5);
            app.Selectphase_diameters_DropDown.Items = {};
            app.Selectphase_diameters_DropDown.ValueChangedFcn = createCallbackFcn(app, @Selectphase_diameters_DropDownValueChanged, true);
            app.Selectphase_diameters_DropDown.Enable = 'off';
            app.Selectphase_diameters_DropDown.Value = {};
            app.InstructionsTextAreaLabel = uilabel(app.GridLayout5);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.GridLayout5);
            app.InstructionsTextArea.Value = {'- Each phase is setup by default with a unique particle diameter and with no particle elongation (i.e., spheres).'; '- You can set up your custom diameter and elongation distributions for each phase. You will have to repeat all steps for each phase.'; '- Before moving to a next phase you have to click on the save button to keep your choice.'; '- Diameters and elongations are defined for each normalized position along the thickness not by a single scalar (as done for volume fractions in the previous tab) but with an histogram.'; '- You can visualize particle morphology in the particle viusalization tab for testing.'};
            app.RotationsTab = uitab(app.TabGroup);
            app.RotationsTab.Tooltip = {'Particle rotation (orientation)'};
            app.RotationsTab.Title = 'Rotations';
            app.GridLayout6 = uigridlayout(app.RotationsTab);
            app.Instructions_orientation = uilabel(app.GridLayout6);
            app.Instructions_orientation.Text = 'For each solid phase, set the particle rotation distributions along the microstructure thickness';
            app.SelectasolidphaseandsetthenumberofLabel_3 = uilabel(app.GridLayout6);
            app.SelectasolidphaseandsetthenumberofLabel_3.Text = '1) Select a solid phase and set the number of slices for which you want to define particle rotations, and also how many values for each of these properties.';
            app.AlgothmisfasterforLabel_8 = uilabel(app.GridLayout6);
            app.AlgothmisfasterforLabel_8.Text = 'Your choice will format the table in the section 2).';
            app.Orientation_main_UITable = uitable(app.GridLayout6);
            app.Orientation_main_UITable.ColumnName = {'Parameters'; 'Number of slices'; 'Number of values'};
            app.Orientation_main_UITable.RowName = {};
            app.Orientation_main_UITable.ColumnEditable = [false true true];
            app.Orientation_main_UITable.CellEditCallback = createCallbackFcn(app, @Orientation_main_UITableCellEdit, true);
            app.Orientation_main_UITable.Enable = 'off';
            app.SelectasolidphaseandsetthenumberofLabel_4 = uilabel(app.GridLayout6);
            app.SelectasolidphaseandsetthenumberofLabel_4.Text = '2) Set Orientations. If you set n values in the table above, you will have  *1, *2,... *n. Set their value in the first row. Set their distribution in the subsequent rows.';
            app.Rotation_x_UITable = uitable(app.GridLayout6);
            app.Rotation_x_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx 1'; 'Total'};
            app.Rotation_x_UITable.RowName = {};
            app.Rotation_x_UITable.ColumnEditable = [false true false];
            app.Rotation_x_UITable.CellEditCallback = createCallbackFcn(app, @Rotation_x_UITableCellEdit, true);
            app.Rotation_x_UITable.Enable = 'off';
            app.Rowii1isthediameterhistogramLabel_2 = uilabel(app.GridLayout6);
            app.Rowii1isthediameterhistogramLabel_2.Text = {'Row i (i>1) is the rotation'; 'histogram expressed in'; 'percentages. Total must be'; '100%.'; ''; 'Ignore NaN values'};
            app.AlgothmisfasterforLabel_9 = uilabel(app.GridLayout6);
            app.AlgothmisfasterforLabel_9.Text = 'First, second, and third table are for rotation normal to x-axis,y-axis, and z-axis, respectively. Values are in degrees. Do not use 0, as 180 degree is used instead.';
            app.Rotation_y_UITable = uitable(app.GridLayout6);
            app.Rotation_y_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx/Dy 1'; 'Total'};
            app.Rotation_y_UITable.RowName = {};
            app.Rotation_y_UITable.ColumnEditable = [false true false];
            app.Rotation_y_UITable.CellEditCallback = createCallbackFcn(app, @Rotation_y_UITableCellEdit, true);
            app.Rotation_y_UITable.Enable = 'off';
            app.Rotation_z_UITable = uitable(app.GridLayout6);
            app.Rotation_z_UITable.ColumnName = {'Normalized position along direction 3'; 'Dx/Dz 1'; 'Total'};
            app.Rotation_z_UITable.RowName = {};
            app.Rotation_z_UITable.ColumnEditable = [false true false];
            app.Rotation_z_UITable.CellEditCallback = createCallbackFcn(app, @Rotation_z_UITableCellEdit, true);
            app.Rotation_z_UITable.Enable = 'off';
            app.Rotation_plot = uibutton(app.GridLayout6, 'push');
            app.Rotation_plot.ButtonPushedFcn = createCallbackFcn(app, @Rotation_plotButtonPushed, true);
            app.Rotation_plot.Enable = 'off';
            app.Rotation_plot.Text = {'Plot'; 'distributions'};
            app.Rotation_save = uibutton(app.GridLayout6, 'push');
            app.Rotation_save.ButtonPushedFcn = createCallbackFcn(app, @Rotation_saveButtonPushed, true);
            app.Rotation_save.Enable = 'off';
            app.Rotation_save.Text = 'Save';
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_3 = uilabel(app.GridLayout6);
            app.PlotoptionaltocheckyourinputandmandatorysavethemtomoveonLabel_3.Text = {'3) Plot (optional) to check'; 'your inputs and (mandatory)'; 'save them before moving'; 'to the next phase.'; '4) Repeat steps 1,2,3 for'; 'each phase.'; '5) Move to next tab.'};
            app.DistributionsLabel_2 = uilabel(app.GridLayout6);
            app.DistributionsLabel_2.Text = {'Distributions are weighted'; 'with particle volume and'; 'not particle number. Use'; 'Count2volume_distribution'; 'to convert particle frequency'; 'to particle volume'};
            app.SelectphaseDropDownLabel_2 = uilabel(app.GridLayout6);
            app.SelectphaseDropDownLabel_2.Enable = 'off';
            app.SelectphaseDropDownLabel_2.Text = 'Select phase';
            app.Selectphase_orientation_DropDown = uidropdown(app.GridLayout6);
            app.Selectphase_orientation_DropDown.Items = {};
            app.Selectphase_orientation_DropDown.ValueChangedFcn = createCallbackFcn(app, @Selectphase_orientation_DropDownValueChanged, true);
            app.Selectphase_orientation_DropDown.Enable = 'off';
            app.Selectphase_orientation_DropDown.Value = {};
            app.InstructionsTextArea_2Label = uilabel(app.GridLayout6);
            app.InstructionsTextArea_2Label.Text = 'Instructions';
            app.InstructionsTextArea_2 = uitextarea(app.GridLayout6);
            app.InstructionsTextArea_2.Value = {'- Each phase is setup by default with a unique particle rotation (or orientation)'; 'for each cartesian axis x,y,z.'; '- You can set up your custom rotation distributions for each phase. You will have to repeat all steps for each phase.'; '- Before moving to a next phase you have to click on the save button to keep your choice.'; '- Rotations are defined for each normalized position along the thickness not by a single scalar but with an histogram (as done for particle diameters in the previous tab).'; '- You can visualize particle morphology in the particle viusalization tab for testing.'};
            app.ParticlevisualizationTab = uitab(app.TabGroup);
            app.ParticlevisualizationTab.Tooltip = {'Particle visualization'};
            app.ParticlevisualizationTab.Title = 'Particle visualization';
            app.GridLayout7 = uigridlayout(app.ParticlevisualizationTab);
            app.Instructions_Visualization = uilabel(app.GridLayout7);
            app.Instructions_Visualization.Text = 'Here you can test the diameter and rotation parameters for a single particle';
            app.SelectasolidphaseandsetthenumberofLabel_5 = uilabel(app.GridLayout7);
            app.SelectasolidphaseandsetthenumberofLabel_5.Text = '1) Enter parameters in the table below and plot the particle.';
            app.AlgothmisfasterforLabel_10 = uilabel(app.GridLayout7);
            app.AlgothmisfasterforLabel_10.Text = 'This is only for visualization. Parameters entered here are not used elsewhere. The aim of this tab is to familiarize the user with the parameters of the two previous tabs.';
            app.Parameter_example_UITable = uitable(app.GridLayout7);
            app.Parameter_example_UITable.ColumnName = {'Parameter'; 'Value'; 'Unit'; 'Range'};
            app.Parameter_example_UITable.RowName = {};
            app.Parameter_example_UITable.ColumnEditable = [false true false false];
            app.Parameter_example_UITable.CellEditCallback = createCallbackFcn(app, @Parameter_example_UITableCellEdit, true);
            app.Plot_example_particle = uibutton(app.GridLayout7, 'push');
            app.Plot_example_particle.ButtonPushedFcn = createCallbackFcn(app, @Plot_example_particleButtonPushed, true);
            app.Plot_example_particle.Text = {'Plot example particle'; 'with patch function (slow but more accurate)'};
            app.Plot_example_particle_2 = uibutton(app.GridLayout7, 'push');
            app.Plot_example_particle_2.ButtonPushedFcn = createCallbackFcn(app, @Plot_example_particle_2ButtonPushed, true);
            app.Plot_example_particle_2.Text = {'Plot example particle'; 'with volshow (fast but less accurate)'};
            app.CroppingTab = uitab(app.TabGroup);
            app.CroppingTab.Title = 'Cropping';
            app.GridLayout8 = uigridlayout(app.CroppingTab);
            app.Instructions_overlapping_3 = uilabel(app.GridLayout8);
            app.Instructions_overlapping_3.Text = 'Set cropping paramters';
            app.Cropping_Label = uilabel(app.GridLayout8);
            app.Cropping_Label.Text = 'Cropping is recommended to remove edge effect (volume fractions is typically incorrect near the domain''s edges).';
            app.Cropping_UITable = uitable(app.GridLayout8);
            app.Cropping_UITable.ColumnName = {'Direction'; 'Crop by (in voxel length)'; 'Remove truncated particles'; 'Volume truncation threshold (%)'; 'Remove particles with center not in FOV'};
            app.Cropping_UITable.RowName = {};
            app.Cropping_UITable.ColumnEditable = [false true true true true];
            app.Cropping_UITable.CellEditCallback = createCallbackFcn(app, @Cropping_UITableCellEdit, true);
            app.Image4 = uiimage(app.GridLayout8);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @Image4Clicked, true);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Cropping.png');
            app.ClickonthefiguretoenlargeLabel_2 = uilabel(app.GridLayout8);
            app.ClickonthefiguretoenlargeLabel_2.Text = 'Click on the figure to enlarge';
            app.Label2 = uilabel(app.GridLayout8);
            app.Label2.Text = {'Algorithm generates incorrect volume fractions near the edges. To remove this edge effect, it is'; 'recommended to crop the domains by a length ~half the particle diameter, in all directions.'; ''; 'In addition, truncated particles can be removed according to different criteria user can select.'; ''; 'Domain is first cropped and then particles at the edges are removed. For each direction, particle'; 'removal is performed using one or a combination of the three criteria available (remove'; 'truncated particles, volume truncation threshold, remove particles with center not in FOV).'; 'The last criterion can happen only if the domain has been cropped. Figure on the right illustrates'; 'each case.'};
            app.OverlappingTab = uitab(app.TabGroup);
            app.OverlappingTab.Tooltip = {''};
            app.OverlappingTab.Title = 'Overlapping';
            app.GridLayout9 = uigridlayout(app.OverlappingTab);
            app.Instructions_overlapping = uilabel(app.GridLayout9);
            app.Instructions_overlapping.Text = 'Set particle overlapping paramters';
            app.Label_2 = uilabel(app.GridLayout9);
            app.Label_2.Tooltip = {'With mono-size sphere randomly distributed, the theoretical density limit is 63.4% (porosity 36.6%). You can expect to reach density limit sooner with a numerical algorithm. From previous runs, you can generate 40% spheres without overlapping. For higher density, an overlapping of 0.1 (45%), 0.175 (50%), 0.25 (55%) and 0.3 (60%) was required. To increase particle density, you can also use particle size and elongation distribution instead of mono-size ideal spheres.'};
            app.Label_2.Text = {'Theoretical maximum density for unisize sphere packing is 74% [1], and for random spatial distribution of unisize spheres 63.4% [2].'; 'Overlapping enables to overcome this packing density limit.'; '[1] A. Bezdek and W. Kuperberg, Arxiv (2010).'; '[2] C. Song, P. Wang, and H. A. Makse, Nature, 453, 629–632 (2008).'};
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = 'You can control particle overlapping between particles of phase i and particle of phase j through the overlapping symmetric matrix.';
            app.Overlapping_Image = uiimage(app.GridLayout9);
            app.Overlapping_Image.Tooltip = {''};
            app.Overlapping_Image.ImageSource = 'Particle_overlapping.png';
            app.overlapping_UITable = uitable(app.GridLayout9);
            app.overlapping_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.overlapping_UITable.RowName = {};
            app.overlapping_UITable.CellEditCallback = createCallbackFcn(app, @overlapping_UITableCellEdit, true);
            app.overlapping_UITable.Enable = 'off';
            app.overlapping_UITable.Visible = 'off';
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = {'The above condition is checked for each new particle. However, cumulative overlapping may degrade too much particle morphology, even if each indidivual overlapping'; 'passes the above condition. You can specify below the (normalized) minimum volume each particle must preserve from its initial volume when generated for each phase.'; 'If an overlapping would remove particle volume so that its remaining volume would go below this value, the particle is not generated and the algorithm goes to the next'; 'iteration. Unlike for the overlapping matrix, high value here indicates less overlapping allowed.'; ''};
            app.Minimumvolume_overlapping_UITable = uitable(app.GridLayout9);
            app.Minimumvolume_overlapping_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Minimumvolume_overlapping_UITable.RowName = {};
            app.Minimumvolume_overlapping_UITable.CellEditCallback = createCallbackFcn(app, @Minimumvolume_overlapping_UITableCellEdit, true);
            app.Minimumvolume_overlapping_UITable.Enable = 'off';
            app.Minimumvolume_overlapping_UITable.Visible = 'off';
            app.DisableparticlecontiguitycheckCheckBox = uicheckbox(app.GridLayout9);
            app.DisableparticlecontiguitycheckCheckBox.Text = 'Disable particle contiguity check';
            app.Label_5 = uilabel(app.GridLayout9);
            app.Label_5.Text = {'For very elongated particles, overlapping may cut particles. By default contiguity is verified and if a particle is cut into several parts due to the newly generated particle,'; 'the latter is canceled and the algorithm goes to the next iteration. You can disable it (not recommended) if you have difficutly generating elongated particles'; 'and/or very low porosity microstructures.'};
            app.Overlapping_save = uibutton(app.GridLayout9, 'push');
            app.Overlapping_save.ButtonPushedFcn = createCallbackFcn(app, @Overlapping_saveButtonPushed, true);
            app.Overlapping_save.Enable = 'off';
            app.Overlapping_save.Text = 'Save';
            app.particlegenerationfailLabel = uilabel(app.GridLayout9);
            app.particlegenerationfailLabel.Text = 'particle generation fail';
            app.OverlappingrelaxationparametersLabel = uilabel(app.GridLayout9);
            app.OverlappingrelaxationparametersLabel.Text = 'Overlapping relaxation parameters';
            app.IncreaseoverlappingmatrixbyEditFieldLabel = uilabel(app.GridLayout9);
            app.IncreaseoverlappingmatrixbyEditFieldLabel.Text = 'Increase overlapping matrix by';
            app.IncreaseoverlappingmatrixbyEditField = uieditfield(app.GridLayout9, 'numeric');
            app.IncreaseoverlappingmatrixbyEditField.Limits = [0 1];
            app.IncreaseoverlappingmatrixbyEditField.Tooltip = {'Each phase has its own increment value. Increment is also applied (minus) on the normalized minimum volume criterion listed in the table below.'};
            app.IncreaseoverlappingmatrixbyEditField.Value = 0.05;
            app.eachtimeEditFieldLabel = uilabel(app.GridLayout9);
            app.eachtimeEditFieldLabel.Text = 'each time';
            app.eachtimeEditField = uieditfield(app.GridLayout9, 'numeric');
            app.eachtimeEditField.Limits = [0 Inf];
            app.eachtimeEditField.RoundFractionalValues = 'on';
            app.eachtimeEditField.ValueChangedFcn = createCallbackFcn(app, @eachtimeEditFieldValueChanged, true);
            app.eachtimeEditField.Tooltip = {'Counter reset each time a new particle is successfully generated, and each time algorithm moves to next pass/phase ("n0")'};
            app.eachtimeEditField.Value = 1000;
            app.SetvalueforwholematrixEditFieldLabel = uilabel(app.GridLayout9);
            app.SetvalueforwholematrixEditFieldLabel.Text = 'Set value for whole matrix';
            app.SetvalueforwholematrixEditField = uieditfield(app.GridLayout9, 'numeric');
            app.SetvalueforwholematrixEditField.Limits = [0 Inf];
            app.SetvalueforwholematrixEditField.ValueChangedFcn = createCallbackFcn(app, @SetvalueforwholematrixEditFieldValueChanged, true);
            app.SetvalueforwholematrixEditField.Value = 0.5;
            app.AlgorithmTab = uitab(app.TabGroup);
            app.AlgorithmTab.Title = 'Algorithm';
            app.GridLayout10 = uigridlayout(app.AlgorithmTab);
            app.Instructions_overlapping_2 = uilabel(app.GridLayout10);
            app.Instructions_overlapping_2.Text = 'Choose algorithm method';
            app.Image3 = uiimage(app.GridLayout10);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Stochastic_algorithm.png');
            app.SelectasolidphaseandsetthenumberofLabel_27 = uilabel(app.GridLayout10);
            app.SelectasolidphaseandsetthenumberofLabel_27.Text = 'Select method and associated parameters, if any';
            app.Algorithm_description = uilabel(app.GridLayout10);
            app.ClickonthefiguretoenlargeLabel = uilabel(app.GridLayout10);
            app.ClickonthefiguretoenlargeLabel.Text = 'Click on the figure to enlarge';
            app.Constraintn2n1n0Label = uilabel(app.GridLayout10);
            app.Constraintn2n1n0Label.Text = 'Constraint: n2 < n1 < n0';
            app.Rthreshold_CheckBox = uicheckbox(app.GridLayout10);
            app.Rthreshold_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rthreshold_CheckBoxValueChanged, true);
            app.Rthreshold_CheckBox.Tooltip = {'For spheres, and with no overlapping allowed, this option should always be checked. r is the particle volume from V=4/3*pi*r3 (thus only accurate for spheres). rt (>= 1.0) allows you to relax the trigger condition for non-spherical particles.'};
            app.Rthreshold_CheckBox.Text = 'Go directly to "select best  candidate" if max(distance map) < r x rt and max(overlapping matrix(current phase,:) = 0) and particle overlapping did not occur yet';
            app.CandidateselectionDropDownLabel = uilabel(app.GridLayout10);
            app.CandidateselectionDropDownLabel.Text = 'Candidate selection';
            app.CandidateselectionDropDown = uidropdown(app.GridLayout10);
            app.CandidateselectionDropDown.Items = {'Random', 'Accelerated'};
            app.CandidateselectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CandidateselectionDropDownValueChanged, true);
            app.CandidateselectionDropDown.Value = 'Random';
            app.n0overlappingcounterLabel = uilabel(app.GridLayout10);
            app.n0overlappingcounterLabel.Text = 'n0 (overlapping counter)';
            app.n0overlappingcounterEditField = uieditfield(app.GridLayout10, 'numeric');
            app.n0overlappingcounterEditField.Editable = 'off';
            app.n0overlappingcounterEditField.Enable = 'off';
            app.n0overlappingcounterEditField.Tooltip = {'You can change this value on the overlapping tab.'};
            app.n0overlappingcounterEditField.Value = 1000;
            app.n1switchselectionmethodEditFieldLabel = uilabel(app.GridLayout10);
            app.n1switchselectionmethodEditFieldLabel.Text = 'n1 (switch selection method)';
            app.n1switchselectionmethodEditField = uieditfield(app.GridLayout10, 'numeric');
            app.n1switchselectionmethodEditField.Limits = [1 Inf];
            app.n1switchselectionmethodEditField.RoundFractionalValues = 'on';
            app.n1switchselectionmethodEditField.ValueChangedFcn = createCallbackFcn(app, @n1switchselectionmethodEditFieldValueChanged, true);
            app.n1switchselectionmethodEditField.Value = 500;
            app.n2selectbestcandidateEditFieldLabel = uilabel(app.GridLayout10);
            app.n2selectbestcandidateEditFieldLabel.Text = 'n2 (select best candidate)';
            app.n2selectbestcandidateEditField = uieditfield(app.GridLayout10, 'numeric');
            app.n2selectbestcandidateEditField.Limits = [1 Inf];
            app.n2selectbestcandidateEditField.RoundFractionalValues = 'on';
            app.n2selectbestcandidateEditField.ValueChangedFcn = createCallbackFcn(app, @n2selectbestcandidateEditFieldValueChanged, true);
            app.n2selectbestcandidateEditField.Value = 250;
            app.SelectionmethodDropDownLabel = uilabel(app.GridLayout10);
            app.SelectionmethodDropDownLabel.Text = 'Selection method';
            app.SelectionmethodDropDown = uidropdown(app.GridLayout10);
            app.SelectionmethodDropDown.Items = {'Distance map (dmap)', 'Volume fraction', 'Both'};
            app.SelectionmethodDropDown.Value = 'Distance map (dmap)';
            app.TopselectionEditFieldLabel = uilabel(app.GridLayout10);
            app.TopselectionEditFieldLabel.Text = 'Top selection (%)';
            app.TopselectionEditField = uieditfield(app.GridLayout10, 'numeric');
            app.TopselectionEditField.Limits = [1e-06 100];
            app.TopselectionEditField.Value = 10;
            app.rtEditFieldLabel = uilabel(app.GridLayout10);
            app.rtEditFieldLabel.Text = 'rt';
            app.rtthreshold_EditField = uieditfield(app.GridLayout10, 'numeric');
            app.rtthreshold_EditField.Limits = [1 Inf];
            app.rtthreshold_EditField.Value = 1;
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Tooltip = {'Particle generation order'};
            app.OrderTab.Title = 'Order';
            app.GridLayout11 = uigridlayout(app.OrderTab);
            app.Instructions_order = uilabel(app.GridLayout11);
            app.Instructions_order.Text = 'Set particle generation order';
            app.SelectasolidphaseandsetthenumberofLabel_6 = uilabel(app.GridLayout11);
            app.SelectasolidphaseandsetthenumberofLabel_6.Text = {'Algorithm generation may stall if inputs are ill-defined. One example would be try generating a phase with two very different particle diameters: large and tiny particles.'; 'While finding place for the tiny particles is easy, it may become impossible to find place for the large ones, especially as packing density is increasing.'; 'A workaround would be to first generate all the large particles, and only after the small ones, i.e., controlling the particle generation order, as illustrated in the figure below. '};
            app.Image = uiimage(app.GridLayout11);
            app.Image.ImageSource = 'Particle_generation_order.png';
            app.SelectasolidphaseandsetthenumberofLabel_7 = uilabel(app.GridLayout11);
            app.SelectasolidphaseandsetthenumberofLabel_7.Text = {'You can specify below the number of generation pass and the target ratio matrix r for each phase and pass iteration. It reads as follow, for the value in row i, column j:'; 'For the pass iteration i, generate for the phase j, r(i,j) times the target volume fraction of the phase j.'};
            app.Label_6 = uilabel(app.GridLayout11);
            app.Label_6.Text = {'For the example mentioned above (the tiny and large particles), you would need to assign the tiny and large particles to different phases.'; 'The parameter would be: 2 phases, 1 or 2 generation pass, with the following fill ratio matrix:'; ''; '              Large particles  | Small particles'; 'Pass 1 |             1                       1         '; ''; '              Small particles  | Large particles'; 'Pass 1 |             0                       1         '; 'Pass 2 |             1                       1'; 'Note that for the second pass, the generation for the large particle is skipped as the target has been already reached at the end of the first pass.'};
            app.Order_UITable = uitable(app.GridLayout11);
            app.Order_UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Order_UITable.RowName = {};
            app.Order_UITable.CellEditCallback = createCallbackFcn(app, @Order_UITableCellEdit, true);
            app.Order_UITable.Enable = 'off';
            app.Order_UITable.Visible = 'off';
            app.Order_save = uibutton(app.GridLayout11, 'push');
            app.Order_save.ButtonPushedFcn = createCallbackFcn(app, @Order_saveButtonPushed, true);
            app.Order_save.Enable = 'off';
            app.Order_save.Text = 'Save';
            app.PseudocodeTextAreaLabel = uilabel(app.GridLayout11);
            app.PseudocodeTextAreaLabel.Text = 'Pseudo-code';
            app.PseudocodeTextArea = uitextarea(app.GridLayout11);
            app.PseudocodeTextArea.Value = {'Loop over pass'; '   Loop over phase'; '      While volume fraction(phase) ≤ target ratio (pass) x target volume fraction(phase) '; '         Generate new particle'};
            app.NumberofgenerationpassEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofgenerationpassEditFieldLabel.Text = 'Number of generation pass';
            app.NumberofgenerationpassEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumberofgenerationpassEditField.Limits = [1 Inf];
            app.NumberofgenerationpassEditField.RoundFractionalValues = 'on';
            app.NumberofgenerationpassEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofgenerationpassEditFieldValueChanged, true);
            app.NumberofgenerationpassEditField.Value = 1;
            app.StopconditionsTab = uitab(app.TabGroup);
            app.StopconditionsTab.Tooltip = {'Algorithm stop conditions (stalling)'};
            app.StopconditionsTab.Title = 'Stop conditions';
            app.GridLayout12 = uigridlayout(app.StopconditionsTab);
            app.Instructions_order_2 = uilabel(app.GridLayout12);
            app.Instructions_order_2.Text = 'Algorithm progression and stop conditions';
            app.PlotalgorithmprogressionCheckBox = uicheckbox(app.GridLayout12);
            app.PlotalgorithmprogressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotalgorithmprogressionCheckBoxValueChanged, true);
            app.PlotalgorithmprogressionCheckBox.Text = 'Plot algorithm progression';
            app.PlotalgorithmprogressionCheckBox.Value = true;
            app.SelectasolidphaseandsetthenumberofLabel_8 = uilabel(app.GridLayout12);
            app.SelectasolidphaseandsetthenumberofLabel_8.Text = {'Algorithm may stall if inputs are ill-defined or unrealistic (e.g., asking for a density higher than the packing density limit wihout particle overlapping).'; 'You can plot the volume fraction, volume fraction rate, and particle generation rate as function of wallclock time to evaluate if a long generation time is due to a too small'; '(particle volume / domain volume) ratio or due to unrealistic inputs.'};
            app.SelectasolidphaseandsetthenumberofLabel_9 = uilabel(app.GridLayout12);
            app.SelectasolidphaseandsetthenumberofLabel_9.Text = 'You can specify stop conditions in case the algorithm is stalling, to avoid losing time.';
            app.stopcondition_andor_DropDown = uidropdown(app.GridLayout12);
            app.stopcondition_andor_DropDown.Items = {'and', 'or'};
            app.stopcondition_andor_DropDown.Value = 'or';
            app.iterationsLabel = uilabel(app.GridLayout12);
            app.iterationsLabel.Text = 'iterations';
            app.thenLabel = uilabel(app.GridLayout12);
            app.thenLabel.Text = 'then';
            app.stopcondition_action_DropDown = uidropdown(app.GridLayout12);
            app.stopcondition_action_DropDown.Items = {'Ignore (no stoping condition)', 'Move to next phase or generation pass (and stop if last iteration)', 'Stop'};
            app.stopcondition_action_DropDown.Value = 'Ignore (no stoping condition)';
            app.AlgothmisfasterforLabel_11 = uilabel(app.GridLayout12);
            app.AlgothmisfasterforLabel_11.Text = 'The threshold on the volume fraction rate and particle generation rate are applied on the maximum calculated among the different phases.';
            app.Label_8 = uilabel(app.GridLayout12);
            app.Label_8.Text = 'Algorithm will always stop after reaching this generation time. Use high value if you want to ignore this last stoping condition.';
            app.PlotalsoporosityprogressionCheckBox = uicheckbox(app.GridLayout12);
            app.PlotalsoporosityprogressionCheckBox.Text = 'Plot also porosity progression';
            app.Ifvolumefractions1goesbelowEditFieldLabel = uilabel(app.GridLayout12);
            app.Ifvolumefractions1goesbelowEditFieldLabel.Text = 'If volume fraction.s-1 goes below';
            app.Ifvolumefractions1goesbelowEditField = uieditfield(app.GridLayout12, 'numeric');
            app.Ifvolumefractions1goesbelowEditField.Limits = [0 Inf];
            app.Ifvolumefractions1goesbelowEditField.Value = 0.0001;
            app.particles1goesbelowEditFieldLabel = uilabel(app.GridLayout12);
            app.particles1goesbelowEditFieldLabel.Text = 'particle.s-1 goes below';
            app.particles1goesbelowEditField = uieditfield(app.GridLayout12, 'numeric');
            app.particles1goesbelowEditField.Limits = [0 Inf];
            app.particles1goesbelowEditField.Value = 0.1;
            app.whenaveragedonthelastEditFieldLabel = uilabel(app.GridLayout12);
            app.whenaveragedonthelastEditFieldLabel.Text = 'when averaged on the last';
            app.whenaveragedonthelastEditField = uieditfield(app.GridLayout12, 'numeric');
            app.whenaveragedonthelastEditField.Limits = [0 Inf];
            app.whenaveragedonthelastEditField.RoundFractionalValues = 'on';
            app.whenaveragedonthelastEditField.Value = 50;
            app.MaximumwallclocktimesEditFieldLabel = uilabel(app.GridLayout12);
            app.MaximumwallclocktimesEditFieldLabel.Text = 'Maximum wallclock time (s)';
            app.MaximumwallclocktimesEditField = uieditfield(app.GridLayout12, 'numeric');
            app.MaximumwallclocktimesEditField.Limits = [0 Inf];
            app.MaximumwallclocktimesEditField.Value = 3600;
            app.RefreshfigureseachsEditFieldLabel = uilabel(app.GridLayout12);
            app.RefreshfigureseachsEditFieldLabel.Text = 'Refresh figures each (s)';
            app.RefreshfigureseachsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.RefreshfigureseachsEditField.Limits = [0 Inf];
            app.RefreshfigureseachsEditField.Value = 5;
            app.MaskTab = uitab(app.TabGroup);
            app.MaskTab.Title = 'Mask';
            app.GridLayout13 = uigridlayout(app.MaskTab);
            app.Instructions_order_3 = uilabel(app.GridLayout13);
            app.Instructions_order_3.Text = 'Pre-existing material';
            app.Cropping_Label_2 = uilabel(app.GridLayout13);
            app.Cropping_Label_2.Text = '1) You can import a mask (0=background, otherwise pre-existing material) to specify regions of space where particles CAN NOT be generated. ';
            app.AlgothmisfasterforLabel_17 = uilabel(app.GridLayout13);
            app.AlgothmisfasterforLabel_17.Text = 'Mask must share the same dimension (size) with the generated volume.';
            app.ImportmaskButton = uibutton(app.GridLayout13, 'push');
            app.ImportmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmaskButtonPushed, true);
            app.ImportmaskButton.Enable = 'off';
            app.ImportmaskButton.Text = 'Import mask';
            app.iClicktoimportnotwithinmaskLabel = uilabel(app.GridLayout13);
            app.iClicktoimportnotwithinmaskLabel.Text = 'i) Click to import "not within" mask';
            app.Mask_volume_name_label = uilabel(app.GridLayout13);
            app.Mask_volume_name_label.Text = 'No mask file imported.';
            app.UsenotwithinmaskCheckBox = uicheckbox(app.GridLayout13);
            app.UsenotwithinmaskCheckBox.Enable = 'off';
            app.UsenotwithinmaskCheckBox.Text = 'Use "not within" mask';
            app.iiSetadditionaloverlappingdistanceconditionsoptionalLabel = uilabel(app.GridLayout13);
            app.iiSetadditionaloverlappingdistanceconditionsoptionalLabel.Text = 'ii) Set additional overlapping/distance conditions (optional)';
            app.Visualize_mask_button = uibutton(app.GridLayout13, 'push');
            app.Visualize_mask_button.ButtonPushedFcn = createCallbackFcn(app, @Visualize_mask_buttonButtonPushed, true);
            app.Visualize_mask_button.Enable = 'off';
            app.Visualize_mask_button.Text = 'Visualize mask';
            app.mask_volumefraction_label = uilabel(app.GridLayout13);
            app.mask_volumefraction_label.Text = 'No mask file imported.';
            app.Setto0toignoretwoconditionsyoucanusebothLabel = uilabel(app.GridLayout13);
            app.Setto0toignoretwoconditionsyoucanusebothLabel.Text = 'You can use none, one, or both of the above conditions. ';
            app.Cropping_Label_3 = uilabel(app.GridLayout13);
            app.Cropping_Label_3.Text = '2) You can import a mask (0=background, otherwise pre-existing material) to specify regions of space where particles CAN be generated. ';
            app.AlgothmisfasterforLabel_18 = uilabel(app.GridLayout13);
            app.AlgothmisfasterforLabel_18.Text = 'Mask must share the same dimension (size) with the generated volume.';
            app.ImportmaskButton_2 = uibutton(app.GridLayout13, 'push');
            app.ImportmaskButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportmaskButton_2Pushed, true);
            app.ImportmaskButton_2.Enable = 'off';
            app.ImportmaskButton_2.Text = 'Import mask';
            app.iClicktoimportwithinmaskLabel = uilabel(app.GridLayout13);
            app.iClicktoimportwithinmaskLabel.Text = 'i) Click to import "within" mask';
            app.Visualize_mask_button_2 = uibutton(app.GridLayout13, 'push');
            app.Visualize_mask_button_2.ButtonPushedFcn = createCallbackFcn(app, @Visualize_mask_button_2ButtonPushed, true);
            app.Visualize_mask_button_2.Enable = 'off';
            app.Visualize_mask_button_2.Text = 'Visualize mask';
            app.Mask_volume_name_label_2 = uilabel(app.GridLayout13);
            app.Mask_volume_name_label_2.Text = 'No mask file imported.';
            app.mask_volumefraction_label_2 = uilabel(app.GridLayout13);
            app.mask_volumefraction_label_2.Text = 'No mask file imported.';
            app.UseATLEASTconditionCheckBox = uicheckbox(app.GridLayout13);
            app.UseATLEASTconditionCheckBox.Enable = 'off';
            app.UseATLEASTconditionCheckBox.Text = 'Use AT LEAST condition';
            app.UseNOTMOREconditionCheckBox = uicheckbox(app.GridLayout13);
            app.UseNOTMOREconditionCheckBox.Enable = 'off';
            app.UseNOTMOREconditionCheckBox.Text = 'Use NOT MORE  condition';
            app.UsewithinmaskCheckBox = uicheckbox(app.GridLayout13);
            app.UsewithinmaskCheckBox.Enable = 'off';
            app.UsewithinmaskCheckBox.Text = 'Use "within" mask';
            app.Mask_addexistingmaterial = uicheckbox(app.GridLayout13);
            app.Mask_addexistingmaterial.Enable = 'off';
            app.Mask_addexistingmaterial.Text = 'Add pre-existing material at the end of the particle generation (and overwritte particles if overlapping)';
            app.Image5 = uiimage(app.GridLayout13);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @Image5Clicked, true);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Mask.png');
            app.ClickonthefiguretoenlargeLabel_3 = uilabel(app.GridLayout13);
            app.ClickonthefiguretoenlargeLabel_3.Text = {'Click on the figure'; 'to enlarge'};
            app.ParticlescanNOToverlapwithmaskCheckBox = uicheckbox(app.GridLayout13);
            app.ParticlescanNOToverlapwithmaskCheckBox.Enable = 'off';
            app.ParticlescanNOToverlapwithmaskCheckBox.Text = 'Particles can NOT overlap with mask';
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel = uilabel(app.GridLayout13);
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel.Tooltip = {'Set value to half particle diameter if you want particles can touch the pre-existing materal. Lower values allow overlapping (mask will overwritte generated particles). Higher values enforce no-contact condition.'};
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel.Text = 'Particles can be generated only if their center is distant from AT LEAST x voxel(s) from the pre-existing material';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField = uieditfield(app.GridLayout13, 'numeric');
            app.ParticlecanbegeneratedonlyiftheircenterisEditField.Limits = [1 Inf];
            app.ParticlecanbegeneratedonlyiftheircenterisEditField.Enable = 'off';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField.Tooltip = {'Set value to half particle diameter if you want particles can touch the pre-existing materal. Lower values allow overlapping (mask will overwritte generated particles). Higher values enforce no-contact condition.'};
            app.ParticlecanbegeneratedonlyiftheircenterisEditField.Value = 1;
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel_2 = uilabel(app.GridLayout13);
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel_2.Tooltip = {'Set value to half particle diameter if you want particles can touch the pre-existing materal. Lower values allow overlapping (mask will overwritte generated particles). Higher values enforce no-contact condition.'};
            app.ParticlecanbegeneratedonlyiftheircenterisEditFieldLabel_2.Text = 'Particles can be generated only if their center is distant from NOT MORE than x voxel(s) from the pre-existing material';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2 = uieditfield(app.GridLayout13, 'numeric');
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Limits = [2 Inf];
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Enable = 'off';
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Tooltip = {'Set value to half particle diameter if you want particles can touch the pre-existing materal. Lower values allow overlapping (mask will overwritte generated particles). Higher values enforce no-contact condition.'};
            app.ParticlecanbegeneratedonlyiftheircenterisEditField_2.Value = 2;
            app.UpscaleoptionalTab = uitab(app.TabGroup);
            app.UpscaleoptionalTab.Tooltip = {'Upscale (optional)'};
            app.UpscaleoptionalTab.Title = 'Upscale (optional)';
            app.GridLayout = uigridlayout(app.UpscaleoptionalTab);
            app.Instructions_upscaling = uilabel(app.GridLayout);
            app.Instructions_upscaling.Text = ' Microstructure up(down) scaling';
            app.SelectasolidphaseandsetthenumberofLabel_12 = uilabel(app.GridLayout);
            app.SelectasolidphaseandsetthenumberofLabel_12.Text = {'Algorithm generation may take some time, especially if you are targeting a high density and/or a low (particle volume / domain volume) ratio.'; 'One way to speed up the process is to generate with a coarse representation and then to upscale.'};
            app.SelectasolidphaseandsetthenumberofLabel_13 = uilabel(app.GridLayout);
            app.SelectasolidphaseandsetthenumberofLabel_13.Text = 'Particles are redrawn in a different grid, knowing their centroid, diameters, and rotation, with centroid and diameters scaled. Provides exact ellipsoid geometry';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Scaling_regen.png');
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = 'Number of voxel (see "volume fraction tab") is multiplied by the scaling factor.';
            app.ApplyscalingtophaselabelCheckBox = uicheckbox(app.GridLayout);
            app.ApplyscalingtophaselabelCheckBox.Enable = 'off';
            app.ApplyscalingtophaselabelCheckBox.Text = 'Apply scaling to phase label';
            app.ApplyscalingtophaselabelCheckBox.Value = true;
            app.ApplyscalingalsotoparticlelabelCheckBox = uicheckbox(app.GridLayout);
            app.ApplyscalingalsotoparticlelabelCheckBox.Enable = 'off';
            app.ApplyscalingalsotoparticlelabelCheckBox.Text = 'Apply scaling also to particle label';
            app.ApplyscalingalsotoparticlelabelCheckBox.Value = true;
            app.ForceseparationCheckBox = uicheckbox(app.GridLayout);
            app.ForceseparationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceseparationCheckBoxValueChanged, true);
            app.ForceseparationCheckBox.Text = 'Force separation';
            app.Upscale_ForceodddiametersCheckBox = uicheckbox(app.GridLayout);
            app.Upscale_ForceodddiametersCheckBox.Text = 'Force odd diameters';
            app.TargetnewporosityCheckBox = uicheckbox(app.GridLayout);
            app.TargetnewporosityCheckBox.ValueChangedFcn = createCallbackFcn(app, @TargetnewporosityCheckBoxValueChanged, true);
            app.TargetnewporosityCheckBox.Tooltip = {'Upscaling change particle volume slighly. You can stop the re-generation once you reach a particular porosity. Therefore, you may set lower volume fractions for the solid to save generation time.'};
            app.TargetnewporosityCheckBox.Text = 'Target new porosity';
            app.ShuffleparticlesCheckBox = uicheckbox(app.GridLayout);
            app.ShuffleparticlesCheckBox.Tooltip = {'Particle order is shuffled.'};
            app.ShuffleparticlesCheckBox.Enable = 'off';
            app.ShuffleparticlesCheckBox.Text = 'Shuffle particles';
            app.Scalingfactor1upscaling1downscaling1noscalingEditFieldLabel = uilabel(app.GridLayout);
            app.Scalingfactor1upscaling1downscaling1noscalingEditFieldLabel.Text = 'Scaling factor (>1: upscaling, <1: downscaling, =1 no scaling)';
            app.Scalingfactor1upscaling1downscaling1noscalingEditField = uieditfield(app.GridLayout, 'numeric');
            app.Scalingfactor1upscaling1downscaling1noscalingEditField.Value = 1;
            app.HowtoDropDownLabel = uilabel(app.GridLayout);
            app.HowtoDropDownLabel.Text = 'How to';
            app.HowtoDropDown = uidropdown(app.GridLayout);
            app.HowtoDropDown.Items = {'Re-create from partice information (recommended)', 'Up(down) scale'};
            app.HowtoDropDown.ValueChangedFcn = createCallbackFcn(app, @HowtoDropDownValueChanged, true);
            app.HowtoDropDown.Value = 'Re-create from partice information (recommended)';
            app.DiameterscalingratioEditFieldLabel = uilabel(app.GridLayout);
            app.DiameterscalingratioEditFieldLabel.Text = 'Diameter scaling ratio';
            app.DiameterscalingratioEditField = uieditfield(app.GridLayout, 'numeric');
            app.DiameterscalingratioEditField.Limits = [0 Inf];
            app.DiameterscalingratioEditField.Tooltip = {'Recommended value: 1, except for case discussed below.'; 'If you start with a very coarse grid and apply a high scaling factor,  the scale up particles will use significanly more volume than the coarse versions. Scaling the diameter (e.g. 0.75) will correct it.'};
            app.DiameterscalingratioEditField.Value = 1;
            app.DistanceseparationvoxellengthEditFieldLabel = uilabel(app.GridLayout);
            app.DistanceseparationvoxellengthEditFieldLabel.Enable = 'off';
            app.DistanceseparationvoxellengthEditFieldLabel.Text = 'Distance separation (voxel length)';
            app.DistanceseparationvoxellengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.DistanceseparationvoxellengthEditField.Limits = [1 Inf];
            app.DistanceseparationvoxellengthEditField.RoundFractionalValues = 'on';
            app.DistanceseparationvoxellengthEditField.Enable = 'off';
            app.DistanceseparationvoxellengthEditField.Tooltip = {'You can also perform it later, with more parameters, in the segmentation module.'};
            app.DistanceseparationvoxellengthEditField.Value = 1;
            app.StopregenerationatporosityEditFieldLabel = uilabel(app.GridLayout);
            app.StopregenerationatporosityEditFieldLabel.Enable = 'off';
            app.StopregenerationatporosityEditFieldLabel.Tooltip = {'Upscaling change particle volume slighly. You can stop the re-generation once you reach a particular porosity. Therefore, you may set lower volume fractions for the solid to save generation time.'};
            app.StopregenerationatporosityEditFieldLabel.Text = 'Stop re-generation at porosity ';
            app.StopregenerationatporosityEditField = uieditfield(app.GridLayout, 'numeric');
            app.StopregenerationatporosityEditField.Limits = [0 1];
            app.StopregenerationatporosityEditField.Enable = 'off';
            app.StopregenerationatporosityEditField.Tooltip = {'Upscaling change particle volume slighly. You can stop the re-generation once you reach a particular porosity. Therefore, you may set lower volume fractions for the solid to save generation time.'};
            app.SolifVf_upscaling_DropDown = uidropdown(app.GridLayout);
            app.SolifVf_upscaling_DropDown.Items = {'Keep solid phase volume fractions', 'Keep solid phase relative volume fractions'};
            app.SolifVf_upscaling_DropDown.Value = 'Keep solid phase relative volume fractions';
            app.VerificationandCharacterizationTab = uitab(app.TabGroup);
            app.VerificationandCharacterizationTab.Tooltip = {'Verification and Characterization'};
            app.VerificationandCharacterizationTab.Title = 'Verification and Characterization';
            app.GridLayout14 = uigridlayout(app.VerificationandCharacterizationTab);
            app.Instructions_characterization = uilabel(app.GridLayout14);
            app.Instructions_characterization.Text = 'Verification and basic microstructure characterization';
            app.SelectasolidphaseandsetthenumberofLabel_21 = uilabel(app.GridLayout14);
            app.SelectasolidphaseandsetthenumberofLabel_21.Text = {'You can perform simple characterization to quickly check the generated volume.'; 'Note that a more in-depth characterization can be perfomed using the MATBOX characterization module.'};
            app.VolumefractionsCheckBox = uicheckbox(app.GridLayout14);
            app.VolumefractionsCheckBox.Enable = 'off';
            app.VolumefractionsCheckBox.Text = 'Volume fractions';
            app.VolumefractionsCheckBox.Value = true;
            app.ConnectivityslowforlargevolumesCheckBox = uicheckbox(app.GridLayout14);
            app.ConnectivityslowforlargevolumesCheckBox.Text = 'Connectivity (slow for large volumes)';
            app.PoretortuosityfactorslowforlargevolumesCheckBox = uicheckbox(app.GridLayout14);
            app.PoretortuosityfactorslowforlargevolumesCheckBox.Text = 'Pore tortuosity factor (normalized effective diffusion coefficient = porosity / tortuosity factor)';
            app.SelectasolidphaseandsetthenumberofLabel_22 = uilabel(app.GridLayout14);
            app.SelectasolidphaseandsetthenumberofLabel_22.Text = {'Inputs (volume fractions, particle diameter and rotation) can be compared with the obtained geometries. Note that no post-processing or characterization is performed:'; 'we simply use the properties assigned to each phase during the generation process.'};
            app.CompareoutputsversusinputsCheckBox = uicheckbox(app.GridLayout14);
            app.CompareoutputsversusinputsCheckBox.Text = 'Compare outputs versus inputs';
            app.CompareoutputsversusinputsCheckBox.Value = true;
            app.CharacterizeDropDownLabel = uilabel(app.GridLayout14);
            app.CharacterizeDropDownLabel.Text = 'Characterize ';
            app.CharacterizeDropDown = uidropdown(app.GridLayout14);
            app.CharacterizeDropDown.Items = {'volume before upscaling', 'volume after upscaling'};
            app.CharacterizeDropDown.Value = 'volume before upscaling';
            app.GenerationTab = uitab(app.TabGroup);
            app.GenerationTab.Title = 'Generation';
            app.GridLayout15 = uigridlayout(app.GenerationTab);
            app.Generate_Button = uibutton(app.GridLayout15, 'push');
            app.Generate_Button.ButtonPushedFcn = createCallbackFcn(app, @Generate_ButtonPushed, true);
            app.Generate_Button.Enable = 'off';
            app.Generate_Button.Text = 'Run generation algorithm';
            app.Instructions_generation = uilabel(app.GridLayout15);
            app.Instructions_generation.Text = 'Once all parameters have been set, run the generation algorithm';
            app.SelectasolidphaseandsetthenumberofLabel_10 = uilabel(app.GridLayout15);
            app.SelectasolidphaseandsetthenumberofLabel_10.Text = '1) Set number of runs. Algorithm will be run sequentially. Valuable to check reproducibility';
            app.SelectasolidphaseandsetthenumberofLabel_11 = uilabel(app.GridLayout15);
            app.SelectasolidphaseandsetthenumberofLabel_11.Text = '2) Click to run the algorithms. Tables below will be updated each time a run is finished.';
            app.Connectivity_UITable = uitable(app.GridLayout15);
            app.Connectivity_UITable.ColumnName = {'Run#'; 'Pores'; 'Union of solid phases'};
            app.Connectivity_UITable.RowName = {};
            app.AlgothmisfasterforLabel_12 = uilabel(app.GridLayout15);
            app.AlgothmisfasterforLabel_12.Text = 'Volume fractions';
            app.AlgothmisfasterforLabel_13 = uilabel(app.GridLayout15);
            app.AlgothmisfasterforLabel_13.Text = {'Connectivity (largest cluster vol%. For more in-depth connectivity analysis,'; 'please use the characterization module.'};
            app.Volume_fraction_UITable = uitable(app.GridLayout15);
            app.Volume_fraction_UITable.ColumnName = {'Run#'; 'Pores'; 'Phase 1'};
            app.Volume_fraction_UITable.RowName = {};
            app.AlgothmisfasterforLabel_14 = uilabel(app.GridLayout15);
            app.AlgothmisfasterforLabel_14.Text = 'Pore tortuosity factor';
            app.Tortuosity_UITable = uitable(app.GridLayout15);
            app.Tortuosity_UITable.ColumnName = {'Run#'; 'Direction 1'; 'Direction 2'; 'Direction 3 (thickness)'};
            app.Tortuosity_UITable.RowName = {};
            app.AlgothmisfasterforLabel_15 = uilabel(app.GridLayout15);
            app.AlgothmisfasterforLabel_15.Text = 'For more in-depth characterization, please use the characterization module.';
            app.AlgothmisfasterforLabel_16 = uilabel(app.GridLayout15);
            app.AlgothmisfasterforLabel_16.Text = 'Outcome and wallclock time (s)';
            app.outcometime_UITable = uitable(app.GridLayout15);
            app.outcometime_UITable.ColumnName = {'Outcome'; 'Generation'; 'Scaling'; 'Characterization'};
            app.outcometime_UITable.RowName = {};
            app.WarningdonotresizefigureswhilegenerationisongoingLabel = uilabel(app.GridLayout15);
            app.WarningdonotresizefigureswhilegenerationisongoingLabel.Enable = 'off';
            app.WarningdonotresizefigureswhilegenerationisongoingLabel.Text = {'Warning: do not resize figures while generation is on-going'; '(MATLAB expects each frame of the video to have the same size)'};
            app.MakevideoCheckBox = uicheckbox(app.GridLayout15);
            app.MakevideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakevideoCheckBoxValueChanged, true);
            app.MakevideoCheckBox.Text = 'Make video';
            app.NumberofrunsEditFieldLabel = uilabel(app.GridLayout15);
            app.NumberofrunsEditFieldLabel.Text = 'Number of runs';
            app.NumberofrunsEditField = uieditfield(app.GridLayout15, 'numeric');
            app.NumberofrunsEditField.Limits = [1 Inf];
            app.NumberofrunsEditField.RoundFractionalValues = 'on';
            app.NumberofrunsEditField.Value = 1;
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayout16 = uigridlayout(app.VisualizationTab);
            app.Instructions_generation_3 = uilabel(app.GridLayout16);
            app.Instructions_generation_3.Text = 'Visualize as-generated microstructures';
            app.Visualization_UITable = uitable(app.GridLayout16);
            app.Visualization_UITable.ColumnName = {'Run#'; 'Scaled'; 'Visualize'};
            app.Visualization_UITable.RowName = {};
            app.Visualization_UITable.ColumnEditable = [false false true];
            app.Visualization_UITable.CellEditCallback = createCallbackFcn(app, @Visualization_UITableCellEdit, true);
            app.SelectasolidphaseandsetthenumberofLabel_23 = uilabel(app.GridLayout16);
            app.SelectasolidphaseandsetthenumberofLabel_23.Text = '1) Select what volumes you would like to visualize (one or several at once).';
            app.Plot_phase_label = uibutton(app.GridLayout16, 'push');
            app.Plot_phase_label.ButtonPushedFcn = createCallbackFcn(app, @Plot_phase_labelButtonPushed, true);
            app.Plot_phase_label.Enable = 'off';
            app.Plot_phase_label.Text = 'Plot phase label';
            app.Plot_particle_label = uibutton(app.GridLayout16, 'push');
            app.Plot_particle_label.ButtonPushedFcn = createCallbackFcn(app, @Plot_particle_labelButtonPushed, true);
            app.Plot_particle_label.Enable = 'off';
            app.Plot_particle_label.Text = 'Plot particle label';
            app.SelectasolidphaseandsetthenumberofLabel_24 = uilabel(app.GridLayout16);
            app.SelectasolidphaseandsetthenumberofLabel_24.Text = '2) Then choose to plot either phase label or particle label';
            app.SelectasolidphaseandsetthenumberofLabel_25 = uilabel(app.GridLayout16);
            app.SelectasolidphaseandsetthenumberofLabel_25.Text = 'Note: functions from the visualization module are called.';
            app.DslicesLabel = uilabel(app.GridLayout16);
            app.DslicesLabel.Text = '2D slices:';
            app.DviewLabel = uilabel(app.GridLayout16);
            app.DviewLabel.Text = '3D view:';
            app.Plot_phase_label_3D = uibutton(app.GridLayout16, 'push');
            app.Plot_phase_label_3D.ButtonPushedFcn = createCallbackFcn(app, @Plot_phase_label_3DButtonPushed, true);
            app.Plot_phase_label_3D.Enable = 'off';
            app.Plot_phase_label_3D.Text = 'Plot phase label';
            app.Plot_particle_label_3D = uibutton(app.GridLayout16, 'push');
            app.Plot_particle_label_3D.ButtonPushedFcn = createCallbackFcn(app, @Plot_particle_label_3DButtonPushed, true);
            app.Plot_particle_label_3D.Enable = 'off';
            app.Plot_particle_label_3D.Text = 'Plot particle label';
            app.SelectasolidphaseandsetthenumberofLabel_26 = uilabel(app.GridLayout16);
            app.SelectasolidphaseandsetthenumberofLabel_26.Text = 'Note: 3D view is available only when one volume is selected';
            app.ParticlecolormapDropDownLabel = uilabel(app.GridLayout16);
            app.ParticlecolormapDropDownLabel.Text = 'Particle colormap';
            app.ParticlecolormapDropDown = uidropdown(app.GridLayout16);
            app.ParticlecolormapDropDown.Items = {'parula', 'turbo', 'jet', 'gray', 'bone', 'copper'};
            app.ParticlecolormapDropDown.Value = 'parula';
            app.ForfurthercontrolintherenderingortocreatevideospleaseusethevisualizationmoduleLabel = uilabel(app.GridLayout16);
            app.ForfurthercontrolintherenderingortocreatevideospleaseusethevisualizationmoduleLabel.Text = 'For further control in  the rendering, or to create videos, please use the visualization module.';
            app.HowtoquoteTab = uitab(app.TabGroup);
            app.HowtoquoteTab.Title = 'How to quote';
            app.GridLayout17 = uigridlayout(app.HowtoquoteTab);
            app.Instructions_generation_2 = uilabel(app.GridLayout17);
            app.Instructions_generation_2.Text = 'How to quote';
            app.SelectasolidphaseandsetthenumberofLabel_20 = uilabel(app.GridLayout17);
            app.SelectasolidphaseandsetthenumberofLabel_20.Text = 'If you use this algorithm or report results produced thanks to this algorithm, please quote it as follow:';
            app.Label_10 = uilabel(app.GridLayout17);
            app.Label_10.Text = {'F. L. E. Usseglio-Viretta, P. Patel, E. Bernhardt, A Mistry, P. P. Mukherjee, J. Allen, S. J. Cooper, J. Laurencin, and K. Smith'; 'MATBOX: An Open-source Microstructure Analysis Toolbox for microstructure generation, segmentation, characterization, visualization, correlation, and meshing'; 'SoftwareX, 17, 2022'};
            app.Hyperlink_2 = uihyperlink(app.GridLayout17);
            app.Hyperlink_2.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_2.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout18 = uigridlayout(app.HelpTab);
            app.Instructions_title_4 = uilabel(app.GridLayout18);
            app.Instructions_title_4.Text = 'Help';
            app.OpendocumentationButton = uibutton(app.GridLayout18, 'push');
            app.OpendocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpendocumentationButtonPushed, true);
            app.OpendocumentationButton.Text = 'Open documentation';
            app.Label_11 = uilabel(app.GridLayout18);
            app.Label_11.Text = '- Please read the documentation for detailed instructions.';
            app.Label_12 = uilabel(app.GridLayout18);
            app.Label_12.Text = '- You can also read the MATBOX journal article.';
            app.Label_13 = uilabel(app.GridLayout18);
            app.Label_13.Text = 'In addition, you can find a selection of journal articles that are using MATBOX in the "References" tab.';
            app.Label_14 = uilabel(app.GridLayout18);
            app.Label_14.Text = '- If you run into problems, please read the open issues listed in the GitHub webpage. If your problem is not listed, please open a new issue.';
            app.Hyperlink_3 = uihyperlink(app.GridLayout18);
            app.Hyperlink_3.URL = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_3.Text = 'https://doi.org/10.1016/j.softx.2021.100915';
            app.Hyperlink_4 = uihyperlink(app.GridLayout18);
            app.Hyperlink_4.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Hyperlink_4.Text = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox/issues';
            app.Label_16 = uilabel(app.GridLayout18);
            app.Label_16.Text = '- Click on the "See examples" located in the top right corner of the window in some tabs to see some parameter examples.';
            app.SeeexamplesButton_2 = uibutton(app.GridLayout18, 'push');
            app.SeeexamplesButton_2.Text = {'See'; 'examples'};
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Instructions_generation_4 = uilabel(app.AboutTab);
            app.Instructions_generation_4.Text = 'About';
            app.Label_15 = uilabel(app.AboutTab);
            app.Label_15.Text = {'National Renewable Energy Laboratory (NREL) developed the Microstructure Analysis Toolbox (MATBOX) to analyze electrode microstructures for lithium-ion'; '(Li-ion) batteries and other 3D heterogeneous materials.'};
            app.NREL_logo = uiimage(app.AboutTab);
            app.NREL_logo.ImageClickedFcn = createCallbackFcn(app, @NREL_logoClicked, true);
            app.NREL_logo.ImageSource = 'logo_NREL.png';
            app.Hyperlink_5 = uihyperlink(app.AboutTab);
            app.Hyperlink_5.URL = 'https://www.nrel.gov/transportation/matbox.html';
            app.Hyperlink_5.Text = 'MATBOX official webpage';
            app.Hyperlink_6 = uihyperlink(app.AboutTab);
            app.Hyperlink_6.URL = 'https://github.com/NREL/MATBOX_Microstructure_analysis_toolbox';
            app.Hyperlink_6.Text = 'MATBOX GitHub repository';
            % Show the figure after all components are created
            app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_stochastic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EllipsoidbasedstochasticgenerationsubmoduleUIFigure)
        end
    end
end